classdef Edit_segmentationmacro < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MacrosegmenationeditorUIFigure  matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        GridLayout2                     matlab.ui.container.GridLayout
        WarningLabel                    matlab.ui.control.Label
        ForadvancedusersonlyLabel       matlab.ui.control.Label
        Parameter_UITable               matlab.ui.control.Table
        VerboseLabel                    matlab.ui.control.Label
        Actions_UITable                 matlab.ui.control.Table
        SavefolderButton                matlab.ui.control.Button
        SavefolderText                  matlab.ui.control.Label
        NewmacronameEditField           matlab.ui.control.EditField
        NewmacronameEditFieldLabel      matlab.ui.control.Label
        SavemacroButton                 matlab.ui.control.Button
        InsertfilenameincustomenameCheckBox  matlab.ui.control.CheckBox
        Macro_imported_name             matlab.ui.control.Label
        ImportmacroButton               matlab.ui.control.Button
    end

    
    properties (Access = private)
        importpath = [];
        savefolder = [];
        macro_act = [];
        macro_cal = [];
        selected_step = [];
        formatchange = [];
        matbox_version = '2.0 beta August 2024';
    end
    
    methods (Access = private)
        
        function [] = Initialize_data(app)
            

            [~,name,~] = fileparts(app.Macro_imported_name.Text);
            app.NewmacronameEditField.Value = [name '_modified'];

            % Activate UI
            app.ActivateUI('on');

            % Unpack
            app.InsertfilenameincustomenameCheckBox.Value = app.macro_act(1).insertfilename;
            n = length(app.macro_act);
            app.Actions_UITable.Data = cell(n,5);
            for k=1:1:n
                app.Actions_UITable.Data(k,1) = {k+1};
                app.Actions_UITable.Data(k,2) = {app.macro_act(k).action};
                app.Actions_UITable.Data(k,3) = {app.macro_act(k).markedforsave};
                app.Actions_UITable.Data(k,4) = {app.macro_act(k).customname};
                app.Actions_UITable.Data(k,5) = {app.macro_act(k).par_verbose};
                s = uistyle('BackgroundColor',[0.75    0.75    0.75]);
                addStyle(app.Actions_UITable,s,'row',k);
            end

            if ~strcmp(app.macro_act(1).matbox_version, app.matbox_version)
                app.WarningLabel.Visible = 'on';
                app.WarningLabel.Text = ['Warning: macro has been crated with an older version of MATBOX (' app.macro_act(1).matbox_version ') and may not work on current version!'];
            end

        end
        
        function [] = ActivateUI(app,stat)
            app.InsertfilenameincustomenameCheckBox.Enable = stat;
            app.SavemacroButton.Enable = stat;    
            app.SavefolderButton.Enable = stat;   
            app.Actions_UITable.Enable = stat;
            app.Parameter_UITable.Enable = stat;
            app.VerboseLabel.Enable = stat;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            app.ActivateUI('off');
            if ~isempty(varargin) % Call by segmentation module
                set(app.Macro_imported_name,'FontColor','k','Text',varargin{1,1});
                app.macro_act = varargin{1,2};
                app.macro_cal = varargin{1,3};
                app.Initialize_data
            end
            app.savefolder = pwd;
            app.SavefolderText.Text = app.savefolder;           
            app.SavefolderText.Tooltip = app.SavefolderText.Text;
        end

        % Button pushed function: ImportmacroButton
        function ImportmacroButtonPushed(app, event)
            % Open dialog box to choose file path
            str_dialogbox = 'Select a macro previsouly created with the segmentation module';
            app.NewmacronameEditField.Value = '';
            app.ActivateUI('off');
            app.Actions_UITable.Data = [];
            app.Parameter_UITable.Data = [];
            if isempty(app.importpath)
                [FileName,PathName,~] = uigetfile({'*.mat','Mat file (*.mat)'},str_dialogbox);
            else
                [FileName,PathName,~] = uigetfile({'*.mat','Mat file (*.mat)'},str_dialogbox,app.importpath);
            end
            app.WarningLabel.Visible = 'off';
            
            if FileName==0
                % User clicked cancel button or closed the dialog box
                set(app.Macro_imported_name,'FontColor','k','Text','No macro loaded');
                app.macro_act = [];
                app.macro_cal = [];
            else
                tmp = load([PathName FileName]);
                if isstruct(tmp) && isfield(tmp,'macro_actions') && isfield(tmp,'macro_calculations')
                    set(app.Macro_imported_name,'FontColor','k','Text',FileName);
                    app.macro_act = tmp.macro_actions;
                    app.macro_cal = tmp.macro_calculations;
                    app.importpath = PathName;                   
                    app.Initialize_data;                    
                else
                    set(app.Macro_imported_name,'FontColor','r','Text','Not a macro!');
                    app.macro_act = [];
                    app.macro_cal = [];
                end
            end
        end

        % Button pushed function: SavemacroButton
        function SavemacroButtonPushed(app, event)
            app.macro_act(1).insertfilename = app.InsertfilenameincustomenameCheckBox.Value;
            [n,~] = size(app.Actions_UITable.Data);
            for k=1:1:n
                app.macro_act(k).markedforsave = cell2mat(app.Actions_UITable.Data(k,3));
                app.macro_act(k).customname = char(app.Actions_UITable.Data(k,4));
                app.macro_act(k).par_verbose = char(app.Actions_UITable.Data(k,5));
            end  
            f=fullfile(app.savefolder,[app.NewmacronameEditField.Value '.mat']);
            macro_actions = app.macro_act;
            macro_calculations = app.macro_cal;
            save(f,'macro_actions','macro_calculations');
        end

        % Button pushed function: SavefolderButton
        function SavefolderButtonPushed(app, event)
            selpath = uigetdir(app.savefolder,'Select save folder');
            if selpath~=0
                app.savefolder=selpath;
                app.SavefolderText.Text = app.savefolder;             
                app.SavefolderText.Tooltip = app.SavefolderText.Text;
            end
        end

        % Clicked callback: Actions_UITable
        function Actions_UITableClicked(app, event)
            DisplayRow = event.InteractionInformation.DisplayRow;
            if ~isempty(DisplayRow)
                app.selected_step = DisplayRow;
                [n,~] = size(app.Actions_UITable.Data);
                for k=1:1:n
                    if k==app.selected_step
                        s = uistyle('BackgroundColor',[0.3290    0.7130    1.0000]);
                        pars = app.macro_act(k).p;
                        if isstruct(pars)
                            f = fields(pars);
                            nn = length(f);
                            app.Parameter_UITable.Data = cell(nn,2);
                            for kk=1:1:nn
                                app.Parameter_UITable.Data(kk,1) = f(kk);       
                                val = pars.(char(f(kk)));
                                if isscalar(val)
                                    app.Parameter_UITable.Data(kk,2) = {val};
                                else
                                    if isstring(val)
                                        app.Parameter_UITable.Data(kk,2) = {val};
                                    else
                                        val = reshape(val,[1 numel(val)]);
                                        app.Parameter_UITable.Data(kk,2) = {num2str(val)};
                                        app.formatchange(k,kk) = true;
                                    end
                                end
                            end                          
                        else

                        end
                    else
                        s = uistyle('BackgroundColor',[0.75    0.75    0.75]);
                    end
                    addStyle(app.Actions_UITable,s,'row',k);
                end
            end

        end

        % Cell edit callback: Parameter_UITable
        function Parameter_UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            if indices(2)==2
                val = app.Parameter_UITable.Data(indices(1),indices(2));
                f = fieldnames(app.macro_act(app.selected_step).p);

                val = cell2mat(val);
                if ischar(val)
                    if isempty(str2num(val))
                        app.macro_act(app.selected_step).p.(f{indices(1)}) = val;
                    else
                        app.macro_act(app.selected_step).p.(f{indices(1)}) = str2num(val);
                    end
                else
                    app.macro_act(app.selected_step).p.(f{indices(1)}) = val;
                end

                % if ischar(cell2mat(val))
                %     app.macro_act(app.selected_step).p.(f{indices(1)}) = cell2mat(val);
                % else
                %     app.macro_act(app.selected_step).p.(f{indices(1)}) = str2num(cell2mat(val));
                % end
                % if app.formatchange(app.selected_step,indices(1))
                %    app.macro_act(app.selected_step).p.(f{indices(1)}) = str2num(cell2mat(val));
                % else
                %    app.macro_act(app.selected_step).p.(f{indices(1)}) = cell2mat(val);
                % end
                %app.macro_act(app.selected_step).p.(f{indices(1)}) = cell2mat(val);
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MacrosegmenationeditorUIFigure = uifigure('Visible', 'off');
            app.MacrosegmenationeditorUIFigure.Name = 'Macro segmenation editor';
            app.GridLayout = uigridlayout(app.MacrosegmenationeditorUIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.ImportmacroButton = uibutton(app.GridLayout2, 'push');
            app.ImportmacroButton.ButtonPushedFcn = createCallbackFcn(app, @ImportmacroButtonPushed, true);
            app.ImportmacroButton.Text = 'Import macro';
            app.Macro_imported_name = uilabel(app.GridLayout2);
            app.Macro_imported_name.Text = 'No macro loaded';
            app.InsertfilenameincustomenameCheckBox = uicheckbox(app.GridLayout2);
            app.InsertfilenameincustomenameCheckBox.Text = 'Insert file name in custome name';
            app.SavemacroButton = uibutton(app.GridLayout2, 'push');
            app.SavemacroButton.ButtonPushedFcn = createCallbackFcn(app, @SavemacroButtonPushed, true);
            app.SavemacroButton.Text = 'Save macro';
            app.NewmacronameEditFieldLabel = uilabel(app.GridLayout2);
            app.NewmacronameEditFieldLabel.Text = 'New macro name';
            app.NewmacronameEditField = uieditfield(app.GridLayout2, 'text');
            app.SavefolderText = uilabel(app.GridLayout2);
            app.SavefolderText.Text = 'No save folder';
            app.SavefolderButton = uibutton(app.GridLayout2, 'push');
            app.SavefolderButton.ButtonPushedFcn = createCallbackFcn(app, @SavefolderButtonPushed, true);
            app.SavefolderButton.Text = 'Save folder';
            app.Actions_UITable = uitable(app.GridLayout2);
            app.Actions_UITable.ColumnName = {'Step#'; 'Action'; 'Marked for save'; 'Custom name'; 'Verbose'};
            app.Actions_UITable.RowName = {};
            app.Actions_UITable.ColumnEditable = [false false true true true];
            app.Actions_UITable.ClickedFcn = createCallbackFcn(app, @Actions_UITableClicked, true);
            app.VerboseLabel = uilabel(app.GridLayout2);
            app.VerboseLabel.Text = 'You can only modify parameters, ''marked for save'', and ''custom name''. ''Verbose'' has no impact but should be modifed accordingly if you change parameter values.';
            app.Parameter_UITable = uitable(app.GridLayout2);
            app.Parameter_UITable.ColumnName = {'Parameter'; 'Value'};
            app.Parameter_UITable.RowName = {};
            app.Parameter_UITable.ColumnEditable = [false true];
            app.Parameter_UITable.CellEditCallback = createCallbackFcn(app, @Parameter_UITableCellEdit, true);
            app.ForadvancedusersonlyLabel = uilabel(app.GridLayout2);
            app.ForadvancedusersonlyLabel.Text = 'For advanced users only. Incorrect parameters will result in a broken macro.';
            app.WarningLabel = uilabel(app.GridLayout2);
            app.WarningLabel.Text = 'Warning';
            % Show the figure after all components are created
            app.MacrosegmenationeditorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Edit_segmentationmacro(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MacrosegmenationeditorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MacrosegmenationeditorUIFigure)
        end
    end
end