classdef Manual_segmentation_sliders < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ManualsegmentationUIFigure  matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        P6_plus_button              matlab.ui.control.Button
        P5_plus_button              matlab.ui.control.Button
        P4_plus_button              matlab.ui.control.Button
        P3_plus_button              matlab.ui.control.Button
        P2_plus_button              matlab.ui.control.Button
        P6_minus_button             matlab.ui.control.Button
        P5_minus_button             matlab.ui.control.Button
        P4_minus_button             matlab.ui.control.Button
        P3_minus_button             matlab.ui.control.Button
        P2_minus_button             matlab.ui.control.Button
        P6_EditField                matlab.ui.control.NumericEditField
        P5_EditField                matlab.ui.control.NumericEditField
        P4_EditField                matlab.ui.control.NumericEditField
        P3_EditField                matlab.ui.control.NumericEditField
        P2_EditField                matlab.ui.control.NumericEditField
        Label                       matlab.ui.control.Label
        Phase6Slider                matlab.ui.control.RangeSlider
        Phase6SliderLabel           matlab.ui.control.Label
        Phase5Slider                matlab.ui.control.RangeSlider
        Phase5SliderLabel           matlab.ui.control.Label
        Phase4Slider                matlab.ui.control.RangeSlider
        Phase4Slider_2Label         matlab.ui.control.Label
        Phase3Slider                matlab.ui.control.RangeSlider
        Phase3SliderLabel           matlab.ui.control.Label
        Phase2Slider                matlab.ui.control.RangeSlider
        Phase2SliderLabel           matlab.ui.control.Label
        P0_plus_button              matlab.ui.control.Button
        P0_minus_button             matlab.ui.control.Button
        P0_EditField                matlab.ui.control.NumericEditField
        Phase1Slider                matlab.ui.control.RangeSlider
        Phase1SliderLabel           matlab.ui.control.Label
        UpdatewhilechangingvaluesCheckBox  matlab.ui.control.CheckBox
        P1_EditField                matlab.ui.control.NumericEditField
        P1_minus_button             matlab.ui.control.Button
        P1_plus_button              matlab.ui.control.Button
        Phase0Slider                matlab.ui.control.RangeSlider
        Phase0SliderLabel           matlab.ui.control.Label
        NumberofphasesincludingbackgroundEditField  matlab.ui.control.NumericEditField
        NumberofphasesincludingbackgroundEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp; % Main app
        extremums = [];
        nphase = [];
    end
    
    methods (Access = private)
        
        function [] = Check_all_bounds(app,new_threshold)
            if sum(diff(new_threshold)<1)
                % Ensure all phases can have at least one grey level
                max_thresholds = app.extremums(2)-app.nphase+1:1:app.extremums(2);
                idx = find(new_threshold>max_thresholds);
                new_threshold(idx) = max_thresholds(idx);
                % Propagate
                for k=2:1:app.nphase-1
                    if new_threshold(k)<=new_threshold(k-1)
                        new_threshold(k) = new_threshold(k-1)+1;
                    end
                end             
            end            

            % Update thresholds
            app.P0_EditField.Value = new_threshold(1);
            app.P1_EditField.Value = new_threshold(2);
            app.Phase0Slider.Value= [app.extremums(1) new_threshold(1)];
            app.Phase1Slider.Value= [new_threshold(1) new_threshold(2)];
            if app.nphase==3
                app.P2_EditField.Value = new_threshold(3);
                app.Phase2Slider.Value= [new_threshold(2) new_threshold(3)];
            elseif app.nphase==4
                app.P2_EditField.Value = new_threshold(3);
                app.P3_EditField.Value = new_threshold(4);
                app.Phase2Slider.Value= [new_threshold(2) new_threshold(3)];
                app.Phase3Slider.Value= [new_threshold(3) new_threshold(4)];
            elseif app.nphase==5
                app.P2_EditField.Value = new_threshold(3);
                app.P3_EditField.Value = new_threshold(4);
                app.P4_EditField.Value = new_threshold(5);
                app.Phase2Slider.Value= [new_threshold(2) new_threshold(3)];
                app.Phase3Slider.Value= [new_threshold(3) new_threshold(4)];
                app.Phase4Slider.Value= [new_threshold(4) new_threshold(5)];
            elseif app.nphase==6
                app.P2_EditField.Value = new_threshold(3);
                app.P3_EditField.Value = new_threshold(4);
                app.P4_EditField.Value = new_threshold(5);
                app.P5_EditField.Value = new_threshold(6);
                app.Phase2Slider.Value= [new_threshold(2) new_threshold(3)];
                app.Phase3Slider.Value= [new_threshold(3) new_threshold(4)];
                app.Phase4Slider.Value= [new_threshold(4) new_threshold(5)];
                app.Phase5Slider.Value= [new_threshold(5) new_threshold(6)];
            elseif app.nphase==7
                app.P2_EditField.Value = new_threshold(3);
                app.P3_EditField.Value = new_threshold(4);
                app.P4_EditField.Value = new_threshold(5);
                app.P5_EditField.Value = new_threshold(6);
                app.P6_EditField.Value = new_threshold(7);
                app.Phase2Slider.Value= [new_threshold(2) new_threshold(3)];
                app.Phase3Slider.Value= [new_threshold(3) new_threshold(4)];
                app.Phase4Slider.Value= [new_threshold(4) new_threshold(5)];
                app.Phase5Slider.Value= [new_threshold(5) new_threshold(6)];
                app.Phase6Slider.Value= [new_threshold(6) new_threshold(7)];
            end


            % Call main app's public function
            Update_from_sliders(app.CallingApp,app.nphase,new_threshold); 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, extremums)
            % Store main app object
            app.CallingApp = caller;
            app.extremums = double(extremums);
            % Initialize sliders
            app.NumberofphasesincludingbackgroundEditFieldValueChanged;


        end

        % Value changed function: 
        % NumberofphasesincludingbackgroundEditField
        function NumberofphasesincludingbackgroundEditFieldValueChanged(app, event)
            app.nphase = app.NumberofphasesincludingbackgroundEditField.Value;
            bounds = round(linspace(app.extremums(1)-1,app.extremums(2),app.nphase+1));

            % Limits
            app.Phase0Slider.Limits = [-1 app.extremums(2)];
            app.Phase1Slider.Limits = [0 app.extremums(2)];
            app.Phase2Slider.Limits = [0 app.extremums(2)];
            app.Phase3Slider.Limits = [0 app.extremums(2)];
            app.Phase4Slider.Limits = [0 app.extremums(2)];
            app.Phase5Slider.Limits = [0 app.extremums(2)];
            app.Phase6Slider.Limits = [0 app.extremums(2)];

            app.P0_EditField.Limits = [0 app.extremums(2)];
            app.P1_EditField.Limits = [0 app.extremums(2)];
            app.P2_EditField.Limits = [0 app.extremums(2)];
            app.P3_EditField.Limits = [0 app.extremums(2)];
            app.P4_EditField.Limits = [0 app.extremums(2)];
            app.P5_EditField.Limits = [0 app.extremums(2)];
            app.P6_EditField.Limits = [0 app.extremums(2)];            

            % Value
            app.Phase0Slider.Value = [bounds(1) bounds(2)]; app.P0_EditField.Value = app.Phase0Slider.Value(2);
            app.Phase1Slider.Value = [bounds(2) bounds(3)]; app.P1_EditField.Value = app.Phase1Slider.Value(2);


            if app.nphase==2
                app.Phase1Slider.Enable='off'; app.P1_EditField.Enable = 'off'; app.P1_minus_button.Enable='off'; app.P1_plus_button.Enable = 'off';

                app.Phase2SliderLabel.Visible='off'; app.Phase2Slider.Visible='off'; app.P2_EditField.Visible = 'off'; app.P2_minus_button.Visible='off'; app.P2_plus_button.Visible = 'off';
                app.Phase2Slider.Enable='off'; app.P2_EditField.Enable = 'off'; app.P2_minus_button.Enable='off'; app.P2_plus_button.Enable = 'off';
                app.Phase3SliderLabel.Visible='off'; app.Phase3Slider.Visible='off'; app.P3_EditField.Visible = 'off'; app.P3_minus_button.Visible='off'; app.P3_plus_button.Visible = 'off';
                app.Phase3Slider.Enable='off'; app.P3_EditField.Enable = 'off'; app.P3_minus_button.Enable='off'; app.P3_plus_button.Enable = 'off';                
                app.Phase4Slider_2Label.Visible='off'; app.Phase4Slider.Visible='off'; app.P4_EditField.Visible = 'off'; app.P4_minus_button.Visible='off'; app.P4_plus_button.Visible = 'off';
                app.Phase4Slider.Enable='off'; app.P4_EditField.Enable = 'off'; app.P4_minus_button.Enable='off'; app.P4_plus_button.Enable = 'off';                
                app.Phase5SliderLabel.Visible='off'; app.Phase5Slider.Visible='off'; app.P5_EditField.Visible = 'off'; app.P5_minus_button.Visible='off'; app.P5_plus_button.Visible = 'off';
                app.Phase5Slider.Enable='off'; app.P5_EditField.Enable = 'off'; app.P5_minus_button.Enable='off'; app.P5_plus_button.Enable = 'off';                
                app.Phase6SliderLabel.Visible='off'; app.Phase6Slider.Visible='off'; app.P6_EditField.Visible = 'off'; app.P6_minus_button.Visible='off'; app.P6_plus_button.Visible = 'off';
                app.Phase6Slider.Enable='off'; app.P6_EditField.Enable = 'off'; app.P6_minus_button.Enable='off'; app.P6_plus_button.Enable = 'off';                
            elseif app.nphase==3
                app.Phase1Slider.Enable='on'; app.P1_EditField.Enable = 'on'; app.P1_minus_button.Enable='on'; app.P1_plus_button.Enable = 'on';

                app.Phase2SliderLabel.Visible='on'; app.Phase2Slider.Visible='on'; app.P2_EditField.Visible = 'on'; app.P2_minus_button.Visible='on'; app.P2_plus_button.Visible = 'on';
                app.Phase2Slider.Enable='off'; app.P2_EditField.Enable = 'off'; app.P2_minus_button.Enable='off'; app.P2_plus_button.Enable = 'off';

                app.Phase3SliderLabel.Visible='off'; app.Phase3Slider.Visible='off'; app.P3_EditField.Visible = 'off'; app.P3_minus_button.Visible='off'; app.P3_plus_button.Visible = 'off';
                app.Phase3Slider.Enable='off'; app.P3_EditField.Enable = 'off'; app.P3_minus_button.Enable='off'; app.P3_plus_button.Enable = 'off';                
                app.Phase4Slider_2Label.Visible='off'; app.Phase4Slider.Visible='off'; app.P4_EditField.Visible = 'off'; app.P4_minus_button.Visible='off'; app.P4_plus_button.Visible = 'off';
                app.Phase4Slider.Enable='off'; app.P4_EditField.Enable = 'off'; app.P4_minus_button.Enable='off'; app.P4_plus_button.Enable = 'off';                
                app.Phase5SliderLabel.Visible='off'; app.Phase5Slider.Visible='off'; app.P5_EditField.Visible = 'off'; app.P5_minus_button.Visible='off'; app.P5_plus_button.Visible = 'off';
                app.Phase5Slider.Enable='off'; app.P5_EditField.Enable = 'off'; app.P5_minus_button.Enable='off'; app.P5_plus_button.Enable = 'off';                
                app.Phase6SliderLabel.Visible='off'; app.Phase6Slider.Visible='off'; app.P6_EditField.Visible = 'off'; app.P6_minus_button.Visible='off'; app.P6_plus_button.Visible = 'off';
                app.Phase6Slider.Enable='off'; app.P6_EditField.Enable = 'off'; app.P6_minus_button.Enable='off'; app.P6_plus_button.Enable = 'off';  

                app.Phase2Slider.Value = [bounds(3) bounds(4)]; app.P2_EditField.Value = app.Phase2Slider.Value(2);                
            elseif app.nphase==4
                app.Phase1Slider.Enable='on'; app.P1_EditField.Enable = 'on'; app.P1_minus_button.Enable='on'; app.P1_plus_button.Enable = 'on';

                app.Phase2SliderLabel.Visible='on'; app.Phase2Slider.Visible='on'; app.P2_EditField.Visible = 'on'; app.P2_minus_button.Visible='on'; app.P2_plus_button.Visible = 'on';
                app.Phase2Slider.Enable='on'; app.P2_EditField.Enable = 'on'; app.P2_minus_button.Enable='on'; app.P2_plus_button.Enable = 'on';

                app.Phase3SliderLabel.Visible='on'; app.Phase3Slider.Visible='on'; app.P3_EditField.Visible = 'on'; app.P3_minus_button.Visible='on'; app.P3_plus_button.Visible = 'on';
                app.Phase3Slider.Enable='off'; app.P3_EditField.Enable = 'off'; app.P3_minus_button.Enable='off'; app.P3_plus_button.Enable = 'off';  

                app.Phase4Slider_2Label.Visible='off'; app.Phase4Slider.Visible='off'; app.P4_EditField.Visible = 'off'; app.P4_minus_button.Visible='off'; app.P4_plus_button.Visible = 'off';
                app.Phase4Slider.Enable='off'; app.P4_EditField.Enable = 'off'; app.P4_minus_button.Enable='off'; app.P4_plus_button.Enable = 'off';                
                app.Phase5SliderLabel.Visible='off'; app.Phase5Slider.Visible='off'; app.P5_EditField.Visible = 'off'; app.P5_minus_button.Visible='off'; app.P5_plus_button.Visible = 'off';
                app.Phase5Slider.Enable='off'; app.P5_EditField.Enable = 'off'; app.P5_minus_button.Enable='off'; app.P5_plus_button.Enable = 'off';                
                app.Phase6SliderLabel.Visible='off'; app.Phase6Slider.Visible='off'; app.P6_EditField.Visible = 'off'; app.P6_minus_button.Visible='off'; app.P6_plus_button.Visible = 'off';
                app.Phase6Slider.Enable='off'; app.P6_EditField.Enable = 'off'; app.P6_minus_button.Enable='off'; app.P6_plus_button.Enable = 'off';  

                app.Phase2Slider.Value = [bounds(3) bounds(4)]; app.P2_EditField.Value = app.Phase2Slider.Value(2);
                app.Phase3Slider.Value = [bounds(4) bounds(5)]; app.P3_EditField.Value = app.Phase3Slider.Value(2);                
            elseif app.nphase==5
                app.Phase1Slider.Enable='on'; app.P1_EditField.Enable = 'on'; app.P1_minus_button.Enable='on'; app.P1_plus_button.Enable = 'on';

                app.Phase2SliderLabel.Visible='on'; app.Phase2Slider.Visible='on'; app.P2_EditField.Visible = 'on'; app.P2_minus_button.Visible='on'; app.P2_plus_button.Visible = 'on';
                app.Phase2Slider.Enable='on'; app.P2_EditField.Enable = 'on'; app.P2_minus_button.Enable='on'; app.P2_plus_button.Enable = 'on';
                app.Phase3SliderLabel.Visible='on'; app.Phase3Slider.Visible='on'; app.P3_EditField.Visible = 'on'; app.P3_minus_button.Visible='on'; app.P3_plus_button.Visible = 'on';
                app.Phase3Slider.Enable='on'; app.P3_EditField.Enable = 'on'; app.P3_minus_button.Enable='on'; app.P3_plus_button.Enable = 'on';  

                app.Phase4Slider_2Label.Visible='on'; app.Phase4Slider.Visible='on'; app.P4_EditField.Visible = 'on'; app.P4_minus_button.Visible='on'; app.P4_plus_button.Visible = 'on';
                app.Phase4Slider.Enable='off'; app.P4_EditField.Enable = 'off'; app.P4_minus_button.Enable='off'; app.P4_plus_button.Enable = 'off'; 

                app.Phase5SliderLabel.Visible='off'; app.Phase5Slider.Visible='off'; app.P5_EditField.Visible = 'off'; app.P5_minus_button.Visible='off'; app.P5_plus_button.Visible = 'off';
                app.Phase5Slider.Enable='off'; app.P5_EditField.Enable = 'off'; app.P5_minus_button.Enable='off'; app.P5_plus_button.Enable = 'off';                
                app.Phase6SliderLabel.Visible='off'; app.Phase6Slider.Visible='off'; app.P6_EditField.Visible = 'off'; app.P6_minus_button.Visible='off'; app.P6_plus_button.Visible = 'off';
                app.Phase6Slider.Enable='off'; app.P6_EditField.Enable = 'off'; app.P6_minus_button.Enable='off'; app.P6_plus_button.Enable = 'off'; 

                app.Phase2Slider.Value = [bounds(3) bounds(4)]; app.P2_EditField.Value = app.Phase2Slider.Value(2);
                app.Phase3Slider.Value = [bounds(4) bounds(5)]; app.P3_EditField.Value = app.Phase3Slider.Value(2);
                app.Phase4Slider.Value = [bounds(5) bounds(6)]; app.P4_EditField.Value = app.Phase4Slider.Value(2);                
            elseif app.nphase==6
                app.Phase1Slider.Enable='on'; app.P1_EditField.Enable = 'on'; app.P1_minus_button.Enable='on'; app.P1_plus_button.Enable = 'on';

                app.Phase2SliderLabel.Visible='on'; app.Phase2Slider.Visible='on'; app.P2_EditField.Visible = 'on'; app.P2_minus_button.Visible='on'; app.P2_plus_button.Visible = 'on';
                app.Phase2Slider.Enable='on'; app.P2_EditField.Enable = 'on'; app.P2_minus_button.Enable='on'; app.P2_plus_button.Enable = 'on';
                app.Phase3SliderLabel.Visible='on'; app.Phase3Slider.Visible='on'; app.P3_EditField.Visible = 'on'; app.P3_minus_button.Visible='on'; app.P3_plus_button.Visible = 'on';
                app.Phase3Slider.Enable='on'; app.P3_EditField.Enable = 'on'; app.P3_minus_button.Enable='on'; app.P3_plus_button.Enable = 'on';  
                app.Phase4Slider_2Label.Visible='on'; app.Phase4Slider.Visible='on'; app.P4_EditField.Visible = 'on'; app.P4_minus_button.Visible='on'; app.P4_plus_button.Visible = 'on';
                app.Phase4Slider.Enable='on'; app.P4_EditField.Enable = 'on'; app.P4_minus_button.Enable='on'; app.P4_plus_button.Enable = 'on'; 

                app.Phase5SliderLabel.Visible='on'; app.Phase5Slider.Visible='on'; app.P5_EditField.Visible = 'on'; app.P5_minus_button.Visible='on'; app.P5_plus_button.Visible = 'on';
                app.Phase5Slider.Enable='off'; app.P5_EditField.Enable = 'off'; app.P5_minus_button.Enable='off'; app.P5_plus_button.Enable = 'off';    

                app.Phase6SliderLabel.Visible='off'; app.Phase6Slider.Visible='off'; app.P6_EditField.Visible = 'off'; app.P6_minus_button.Visible='off'; app.P6_plus_button.Visible = 'off';
                app.Phase6Slider.Enable='off'; app.P6_EditField.Enable = 'off'; app.P6_minus_button.Enable='off'; app.P6_plus_button.Enable = 'off'; 

                app.Phase2Slider.Value = [bounds(3) bounds(4)]; app.P2_EditField.Value = app.Phase2Slider.Value(2);
                app.Phase3Slider.Value = [bounds(4) bounds(5)]; app.P3_EditField.Value = app.Phase3Slider.Value(2);
                app.Phase4Slider.Value = [bounds(5) bounds(6)]; app.P4_EditField.Value = app.Phase4Slider.Value(2);
                app.Phase5Slider.Value = [bounds(6) bounds(7)]; app.P5_EditField.Value = app.Phase5Slider.Value(2);                
            elseif app.nphase==7
                app.Phase1Slider.Enable='on'; app.P1_EditField.Enable = 'on'; app.P1_minus_button.Enable='on'; app.P1_plus_button.Enable = 'on';

                app.Phase2SliderLabel.Visible='on'; app.Phase2Slider.Visible='on'; app.P2_EditField.Visible = 'on'; app.P2_minus_button.Visible='on'; app.P2_plus_button.Visible = 'on';
                app.Phase2Slider.Enable='on'; app.P2_EditField.Enable = 'on'; app.P2_minus_button.Enable='on'; app.P2_plus_button.Enable = 'on';
                app.Phase3SliderLabel.Visible='on'; app.Phase3Slider.Visible='on'; app.P3_EditField.Visible = 'on'; app.P3_minus_button.Visible='on'; app.P3_plus_button.Visible = 'on';
                app.Phase3Slider.Enable='on'; app.P3_EditField.Enable = 'on'; app.P3_minus_button.Enable='on'; app.P3_plus_button.Enable = 'on';  
                app.Phase4Slider_2Label.Visible='on'; app.Phase4Slider.Visible='on'; app.P4_EditField.Visible = 'on'; app.P4_minus_button.Visible='on'; app.P4_plus_button.Visible = 'on';
                app.Phase4Slider.Enable='on'; app.P4_EditField.Enable = 'on'; app.P4_minus_button.Enable='on'; app.P4_plus_button.Enable = 'on'; 
                app.Phase5SliderLabel.Visible='on'; app.Phase5Slider.Visible='on'; app.P5_EditField.Visible = 'on'; app.P5_minus_button.Visible='on'; app.P5_plus_button.Visible = 'on';
                app.Phase5Slider.Enable='on'; app.P5_EditField.Enable = 'on'; app.P5_minus_button.Enable='on'; app.P5_plus_button.Enable = 'on';    

                app.Phase6SliderLabel.Visible='on'; app.Phase6Slider.Visible='on'; app.P6_EditField.Visible = 'on'; app.P6_minus_button.Visible='on'; app.P6_plus_button.Visible = 'on';
                app.Phase6Slider.Enable='off'; app.P6_EditField.Enable = 'off'; app.P6_minus_button.Enable='off'; app.P6_plus_button.Enable = 'off';   

                app.Phase2Slider.Value = [bounds(3) bounds(4)]; app.P2_EditField.Value = app.Phase2Slider.Value(2);
                app.Phase3Slider.Value = [bounds(4) bounds(5)]; app.P3_EditField.Value = app.Phase3Slider.Value(2);
                app.Phase4Slider.Value = [bounds(5) bounds(6)]; app.P4_EditField.Value = app.Phase4Slider.Value(2);
                app.Phase5Slider.Value = [bounds(6) bounds(7)]; app.P5_EditField.Value = app.Phase5Slider.Value(2);
                app.Phase6Slider.Value = [bounds(7) bounds(8)]; app.P6_EditField.Value = app.Phase6Slider.Value(2);
            end  

            % Update figure
            old_thresholds = [app.P0_EditField.Value app.P1_EditField.Value app.P2_EditField.Value app.P3_EditField.Value app.P4_EditField.Value app.P5_EditField.Value app.P6_EditField.Value];
            old_thresholds = old_thresholds(1:app.nphase);
            new_threshold = old_thresholds;
            app.Check_all_bounds(new_threshold); 
        end

        % Button pushed function: P1_minus_button
        function P1_minus_buttonButtonPushed(app, event)
            app.P1_EditField.Value = app.P1_EditField.Value-1;
            app.P1_EditFieldValueChanged;
        end

        % Button pushed function: P1_plus_button
        function P1_plus_buttonButtonPushed(app, event)
            app.P1_EditField.Value = app.P1_EditField.Value+1;
            app.P1_EditFieldValueChanged;    
        end

        % Value changed function: P1_EditField
        function P1_EditFieldValueChanged(app, event)
            value = app.P1_EditField.Value;
            value = max([app.Phase1Slider.Value(1)+1 value]);
            old_thresholds = [app.P0_EditField.Value app.P1_EditField.Value app.P2_EditField.Value app.P3_EditField.Value app.P4_EditField.Value app.P5_EditField.Value app.P6_EditField.Value];
            old_thresholds = old_thresholds(1:app.nphase);
            new_threshold = old_thresholds;
            new_threshold(2) = value;
            app.Check_all_bounds(new_threshold);            
        end

        % Value changed function: Phase1Slider
        function Phase1SliderValueChanged(app, event)
            x0 = app.extremums(1)-1;
            if app.Phase1Slider.Value(2) == app.Phase1Slider.Value(1)
                if app.Phase1Slider.Value(1)<x0
                    app.Phase1Slider.Value=[x0 x0+1];
                else
                    x1=app.Phase1Slider.Value(1);
                    app.Phase1Slider.Value=[x1-1 x1];
                end
            end
            old_thresholds = [app.P0_EditField.Value app.P1_EditField.Value app.P2_EditField.Value app.P3_EditField.Value app.P4_EditField.Value app.P5_EditField.Value app.P6_EditField.Value];
            old_thresholds = old_thresholds(1:app.nphase);
            new_threshold = old_thresholds;
            new_threshold(2) = round(app.Phase1Slider.Value(2));
            app.Check_all_bounds(new_threshold);   
        end

        % Value changed function: Phase0Slider
        function Phase0SliderValueChanged(app, event)
            x0 = app.extremums(1)-1;
            if app.Phase0Slider.Value(2) == app.Phase0Slider.Value(1)
                if app.Phase0Slider.Value(1)<x0
                    app.Phase0Slider.Value=[x0 x0+1];
                else
                    x1=app.Phase0Slider.Value(1);
                    app.Phase0Slider.Value=[x1-1 x1];
                end
            end
            old_thresholds = [app.P0_EditField.Value app.P1_EditField.Value app.P2_EditField.Value app.P3_EditField.Value app.P4_EditField.Value app.P5_EditField.Value app.P6_EditField.Value];
            old_thresholds = old_thresholds(1:app.nphase);
            new_threshold = old_thresholds;
            new_threshold(1) = round(app.Phase0Slider.Value(2));
            app.Check_all_bounds(new_threshold);            
        end

        % Value changed function: P0_EditField
        function P0_EditFieldValueChanged(app, event)
            value = app.P0_EditField.Value;
            value = max([app.extremums(1) value]);
            old_thresholds = [app.P0_EditField.Value app.P1_EditField.Value app.P2_EditField.Value app.P3_EditField.Value app.P4_EditField.Value app.P5_EditField.Value app.P6_EditField.Value];
            old_thresholds = old_thresholds(1:app.nphase);
            new_threshold = old_thresholds;
            new_threshold(1) = value;
            app.Check_all_bounds(new_threshold);   
        end

        % Button pushed function: P0_minus_button
        function P0_minus_buttonButtonPushed(app, event)
            app.P0_EditField.Value = app.P0_EditField.Value-1;
            app.P0_EditFieldValueChanged;
        end

        % Button pushed function: P0_plus_button
        function P0_plus_buttonButtonPushed(app, event)
            app.P0_EditField.Value = app.P0_EditField.Value+1;
            app.P0_EditFieldValueChanged;            
        end

        % Value changing function: Phase0Slider
        function Phase0SliderValueChanging(app, event)
            changingValue = event.Value;
            if app.UpdatewhilechangingvaluesCheckBox.Value
                x0 = app.extremums(1)-1;
                if changingValue(2) == changingValue(1)
                    if changingValue(1)<x0
                        app.Phase0Slider.Value=[x0 x0+1];
                    else
                        x1=changingValue(1);
                        app.Phase0Slider.Value=[x1-1 x1];
                    end
                    changingValue = app.Phase0Slider.Value;
                end
                old_thresholds = [app.P0_EditField.Value app.P1_EditField.Value app.P2_EditField.Value app.P3_EditField.Value app.P4_EditField.Value app.P5_EditField.Value app.P6_EditField.Value];
                old_thresholds = old_thresholds(1:app.nphase);
                new_threshold = old_thresholds;
                new_threshold(1) = round(changingValue(2));
                app.Check_all_bounds(new_threshold);
            end            
        end

        % Button pushed function: P2_minus_button
        function P2_minus_buttonButtonPushed(app, event)
            app.P2_EditField.Value = app.P2_EditField.Value-1;
            app.P2_EditFieldValueChanged;
        end

        % Button pushed function: P3_minus_button
        function P3_minus_buttonButtonPushed(app, event)
            app.P3_EditField.Value = app.P3_EditField.Value-1;
            app.P3_EditFieldValueChanged;
        end

        % Button pushed function: P4_minus_button
        function P4_minus_buttonButtonPushed(app, event)
            app.P4_EditField.Value = app.P4_EditField.Value-1;
            app.P4_EditFieldValueChanged;
        end

        % Button pushed function: P5_minus_button
        function P5_minus_buttonButtonPushed(app, event)
            app.P5_EditField.Value = app.P5_EditField.Value-1;
            app.P5_EditFieldValueChanged;
        end

        % Button pushed function: P2_plus_button
        function P2_plus_buttonButtonPushed(app, event)
            app.P2_EditField.Value = app.P2_EditField.Value+1;
            app.P2_EditFieldValueChanged;    
        end

        % Button pushed function: P3_plus_button
        function P3_plus_buttonButtonPushed(app, event)
            app.P3_EditField.Value = app.P3_EditField.Value+1;
            app.P3_EditFieldValueChanged;    
        end

        % Button pushed function: P4_plus_button
        function P4_plus_buttonButtonPushed(app, event)
            app.P4_EditField.Value = app.P4_EditField.Value+1;
            app.P4_EditFieldValueChanged;    
        end

        % Button pushed function: P5_plus_button
        function P5_plus_buttonButtonPushed(app, event)
            app.P5_EditField.Value = app.P5_EditField.Value+1;
            app.P5_EditFieldValueChanged;    
        end

        % Value changed function: P2_EditField
        function P2_EditFieldValueChanged(app, event)
            value = app.P2_EditField.Value;
            value = max([app.Phase2Slider.Value(1)+1 value]);
            old_thresholds = [app.P0_EditField.Value app.P1_EditField.Value app.P2_EditField.Value app.P3_EditField.Value app.P4_EditField.Value app.P5_EditField.Value app.P6_EditField.Value];
            old_thresholds = old_thresholds(1:app.nphase);
            new_threshold = old_thresholds;
            new_threshold(3) = value;
            app.Check_all_bounds(new_threshold);  
        end

        % Value changed function: P3_EditField
        function P3_EditFieldValueChanged(app, event)
            value = app.P3_EditField.Value;
            value = max([app.Phase3Slider.Value(1)+1 value]);
            old_thresholds = [app.P0_EditField.Value app.P1_EditField.Value app.P2_EditField.Value app.P3_EditField.Value app.P4_EditField.Value app.P5_EditField.Value app.P6_EditField.Value];
            old_thresholds = old_thresholds(1:app.nphase);
            new_threshold = old_thresholds;
            new_threshold(4) = value;
            app.Check_all_bounds(new_threshold);  
        end

        % Value changed function: P4_EditField
        function P4_EditFieldValueChanged(app, event)
            value = app.P4_EditField.Value;
            value = max([app.Phase4Slider.Value(1)+1 value]);
            old_thresholds = [app.P0_EditField.Value app.P1_EditField.Value app.P2_EditField.Value app.P3_EditField.Value app.P4_EditField.Value app.P5_EditField.Value app.P6_EditField.Value];
            old_thresholds = old_thresholds(1:app.nphase);
            new_threshold = old_thresholds;
            new_threshold(5) = value;
            app.Check_all_bounds(new_threshold); 
        end

        % Value changed function: P5_EditField
        function P5_EditFieldValueChanged(app, event)
            value = app.P5_EditField.Value;
            value = max([app.Phase5Slider.Value(1)+1 value]);
            old_thresholds = [app.P0_EditField.Value app.P1_EditField.Value app.P2_EditField.Value app.P3_EditField.Value app.P4_EditField.Value app.P5_EditField.Value app.P6_EditField.Value];
            old_thresholds = old_thresholds(1:app.nphase);
            new_threshold = old_thresholds;
            new_threshold(6) = value;
            app.Check_all_bounds(new_threshold); 
        end

        % Value changing function: Phase1Slider
        function Phase1SliderValueChanging(app, event)
            changingValue = event.Value;
            if app.UpdatewhilechangingvaluesCheckBox.Value
                x0 = app.extremums(1)-1;
                if changingValue(2) == changingValue(1)
                    if changingValue(1)<x0
                        app.Phase1Slider.Value=[x0 x0+1];
                    else
                        x1=changingValue(1);
                        app.Phase1Slider.Value=[x1-1 x1];
                    end
                    changingValue = app.Phase1Slider.Value;
                end
                old_thresholds = [app.P0_EditField.Value app.P1_EditField.Value app.P2_EditField.Value app.P3_EditField.Value app.P4_EditField.Value app.P5_EditField.Value app.P6_EditField.Value];
                old_thresholds = old_thresholds(1:app.nphase);
                new_threshold = old_thresholds;
                new_threshold(2) = round(changingValue(2));
                app.Check_all_bounds(new_threshold);
            end    
        end

        % Value changed function: Phase2Slider
        function Phase2SliderValueChanged(app, event)
            x0 = app.extremums(1)-1;
            if app.Phase2Slider.Value(2) == app.Phase2Slider.Value(1)
                if app.Phase2Slider.Value(1)<x0
                    app.Phase2Slider.Value=[x0 x0+1];
                else
                    x1=app.Phase2Slider.Value(1);
                    app.Phase2Slider.Value=[x1-1 x1];
                end
            end
            old_thresholds = [app.P0_EditField.Value app.P1_EditField.Value app.P2_EditField.Value app.P3_EditField.Value app.P4_EditField.Value app.P5_EditField.Value app.P6_EditField.Value];
            old_thresholds = old_thresholds(1:app.nphase);
            new_threshold = old_thresholds;
            new_threshold(3) = round(app.Phase2Slider.Value(2));
            app.Check_all_bounds(new_threshold); 
        end

        % Value changed function: Phase3Slider
        function Phase3SliderValueChanged(app, event)
            x0 = app.extremums(1)-1;
            if app.Phase3Slider.Value(2) == app.Phase3Slider.Value(1)
                if app.Phase3Slider.Value(1)<x0
                    app.Phase3Slider.Value=[x0 x0+1];
                else
                    x1=app.Phase3Slider.Value(1);
                    app.Phase3Slider.Value=[x1-1 x1];
                end
            end
            old_thresholds = [app.P0_EditField.Value app.P1_EditField.Value app.P2_EditField.Value app.P3_EditField.Value app.P4_EditField.Value app.P5_EditField.Value app.P6_EditField.Value];
            old_thresholds = old_thresholds(1:app.nphase);
            new_threshold = old_thresholds;
            new_threshold(4) = round(app.Phase3Slider.Value(2));
            app.Check_all_bounds(new_threshold);             
        end

        % Value changed function: Phase4Slider
        function Phase4SliderValueChanged(app, event)
            x0 = app.extremums(1)-1;
            if app.Phase4Slider.Value(2) == app.Phase4Slider.Value(1)
                if app.Phase4Slider.Value(1)<x0
                    app.Phase4Slider.Value=[x0 x0+1];
                else
                    x1=app.Phase4Slider.Value(1);
                    app.Phase4Slider.Value=[x1-1 x1];
                end
            end
            old_thresholds = [app.P0_EditField.Value app.P1_EditField.Value app.P2_EditField.Value app.P3_EditField.Value app.P4_EditField.Value app.P5_EditField.Value app.P6_EditField.Value];
            old_thresholds = old_thresholds(1:app.nphase);
            new_threshold = old_thresholds;
            new_threshold(5) = round(app.Phase4Slider.Value(2));
            app.Check_all_bounds(new_threshold);              
        end

        % Value changed function: Phase5Slider
        function Phase5SliderValueChanged(app, event)
            x0 = app.extremums(1)-1;
            if app.Phase5Slider.Value(2) == app.Phase5Slider.Value(1)
                if app.Phase5Slider.Value(1)<x0
                    app.Phase5Slider.Value=[x0 x0+1];
                else
                    x1=app.Phase5Slider.Value(1);
                    app.Phase5Slider.Value=[x1-1 x1];
                end
            end
            old_thresholds = [app.P0_EditField.Value app.P1_EditField.Value app.P2_EditField.Value app.P3_EditField.Value app.P4_EditField.Value app.P5_EditField.Value app.P6_EditField.Value];
            old_thresholds = old_thresholds(1:app.nphase);
            new_threshold = old_thresholds;
            new_threshold(6) = round(app.Phase5Slider.Value(2));
            app.Check_all_bounds(new_threshold);               
        end

        % Value changing function: Phase2Slider
        function Phase2SliderValueChanging(app, event)
            changingValue = event.Value;
            if app.UpdatewhilechangingvaluesCheckBox.Value
                x0 = app.extremums(1)-1;
                if changingValue(2) == changingValue(1)
                    if changingValue(1)<x0
                        app.Phase2Slider.Value=[x0 x0+1];
                    else
                        x1=changingValue(1);
                        app.Phase2Slider.Value=[x1-1 x1];
                    end
                    changingValue = app.Phase2Slider.Value;
                end
                old_thresholds = [app.P0_EditField.Value app.P1_EditField.Value app.P2_EditField.Value app.P3_EditField.Value app.P4_EditField.Value app.P5_EditField.Value app.P6_EditField.Value];
                old_thresholds = old_thresholds(1:app.nphase);
                new_threshold = old_thresholds;
                new_threshold(3) = round(changingValue(2));
                app.Check_all_bounds(new_threshold);
            end    
        end

        % Value changing function: Phase3Slider
        function Phase3SliderValueChanging(app, event)
            changingValue = event.Value;
            if app.UpdatewhilechangingvaluesCheckBox.Value
                x0 = app.extremums(1)-1;
                if changingValue(2) == changingValue(1)
                    if changingValue(1)<x0
                        app.Phase3Slider.Value=[x0 x0+1];
                    else
                        x1=changingValue(1);
                        app.Phase3Slider.Value=[x1-1 x1];
                    end
                    changingValue = app.Phase3Slider.Value;
                end
                old_thresholds = [app.P0_EditField.Value app.P1_EditField.Value app.P2_EditField.Value app.P3_EditField.Value app.P4_EditField.Value app.P5_EditField.Value app.P6_EditField.Value];
                old_thresholds = old_thresholds(1:app.nphase);
                new_threshold = old_thresholds;
                new_threshold(4) = round(changingValue(2));
                app.Check_all_bounds(new_threshold);
            end                
        end

        % Value changing function: Phase4Slider
        function Phase4SliderValueChanging(app, event)
            changingValue = event.Value;
            if app.UpdatewhilechangingvaluesCheckBox.Value
                x0 = app.extremums(1)-1;
                if changingValue(2) == changingValue(1)
                    if changingValue(1)<x0
                        app.Phase4Slider.Value=[x0 x0+1];
                    else
                        x1=changingValue(1);
                        app.Phase4Slider.Value=[x1-1 x1];
                    end
                    changingValue = app.Phase4Slider.Value;
                end
                old_thresholds = [app.P0_EditField.Value app.P1_EditField.Value app.P2_EditField.Value app.P3_EditField.Value app.P4_EditField.Value app.P5_EditField.Value app.P6_EditField.Value];
                old_thresholds = old_thresholds(1:app.nphase);
                new_threshold = old_thresholds;
                new_threshold(5) = round(changingValue(2));
                app.Check_all_bounds(new_threshold);
            end              
        end

        % Value changing function: Phase5Slider
        function Phase5SliderValueChanging(app, event)
            changingValue = event.Value;
            if app.UpdatewhilechangingvaluesCheckBox.Value
                x0 = app.extremums(1)-1;
                if changingValue(2) == changingValue(1)
                    if changingValue(1)<x0
                        app.Phase5Slider.Value=[x0 x0+1];
                    else
                        x1=changingValue(1);
                        app.Phase5Slider.Value=[x1-1 x1];
                    end
                    changingValue = app.Phase5Slider.Value;
                end
                old_thresholds = [app.P0_EditField.Value app.P1_EditField.Value app.P2_EditField.Value app.P3_EditField.Value app.P4_EditField.Value app.P5_EditField.Value app.P6_EditField.Value];
                old_thresholds = old_thresholds(1:app.nphase);
                new_threshold = old_thresholds;
                new_threshold(6) = round(changingValue(2));
                app.Check_all_bounds(new_threshold);
            end               
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ManualsegmentationUIFigure = uifigure('Visible', 'off');
            app.ManualsegmentationUIFigure.Name = 'Manual segmentation';
            app.GridLayout = uigridlayout(app.ManualsegmentationUIFigure);
            app.NumberofphasesincludingbackgroundEditFieldLabel = uilabel(app.GridLayout);
            app.NumberofphasesincludingbackgroundEditFieldLabel.Text = 'Number of phases (including background)';
            app.NumberofphasesincludingbackgroundEditField = uieditfield(app.GridLayout, 'numeric');
            app.NumberofphasesincludingbackgroundEditField.Limits = [2 7];
            app.NumberofphasesincludingbackgroundEditField.RoundFractionalValues = 'on';
            app.NumberofphasesincludingbackgroundEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofphasesincludingbackgroundEditFieldValueChanged, true);
            app.NumberofphasesincludingbackgroundEditField.Value = 2;
            app.Phase0SliderLabel = uilabel(app.GridLayout);
            app.Phase0SliderLabel.Text = 'Phase 0';
            app.Phase0Slider = uislider(app.GridLayout, 'range');
            app.Phase0Slider.ValueChangedFcn = createCallbackFcn(app, @Phase0SliderValueChanged, true);
            app.Phase0Slider.ValueChangingFcn = createCallbackFcn(app, @Phase0SliderValueChanging, true);
            app.P1_plus_button = uibutton(app.GridLayout, 'push');
            app.P1_plus_button.ButtonPushedFcn = createCallbackFcn(app, @P1_plus_buttonButtonPushed, true);
            app.P1_plus_button.Text = '+';
            app.P1_minus_button = uibutton(app.GridLayout, 'push');
            app.P1_minus_button.ButtonPushedFcn = createCallbackFcn(app, @P1_minus_buttonButtonPushed, true);
            app.P1_minus_button.Text = '-';
            app.P1_EditField = uieditfield(app.GridLayout, 'numeric');
            app.P1_EditField.Limits = [1 Inf];
            app.P1_EditField.RoundFractionalValues = 'on';
            app.P1_EditField.ValueChangedFcn = createCallbackFcn(app, @P1_EditFieldValueChanged, true);
            app.P1_EditField.Value = 1;
            app.UpdatewhilechangingvaluesCheckBox = uicheckbox(app.GridLayout);
            app.UpdatewhilechangingvaluesCheckBox.Text = 'Update while changing values';
            app.Phase1SliderLabel = uilabel(app.GridLayout);
            app.Phase1SliderLabel.Text = 'Phase 1';
            app.Phase1Slider = uislider(app.GridLayout, 'range');
            app.Phase1Slider.ValueChangedFcn = createCallbackFcn(app, @Phase1SliderValueChanged, true);
            app.Phase1Slider.ValueChangingFcn = createCallbackFcn(app, @Phase1SliderValueChanging, true);
            app.P0_EditField = uieditfield(app.GridLayout, 'numeric');
            app.P0_EditField.Limits = [1 Inf];
            app.P0_EditField.RoundFractionalValues = 'on';
            app.P0_EditField.ValueChangedFcn = createCallbackFcn(app, @P0_EditFieldValueChanged, true);
            app.P0_EditField.Value = 1;
            app.P0_minus_button = uibutton(app.GridLayout, 'push');
            app.P0_minus_button.ButtonPushedFcn = createCallbackFcn(app, @P0_minus_buttonButtonPushed, true);
            app.P0_minus_button.Text = '-';
            app.P0_plus_button = uibutton(app.GridLayout, 'push');
            app.P0_plus_button.ButtonPushedFcn = createCallbackFcn(app, @P0_plus_buttonButtonPushed, true);
            app.P0_plus_button.Text = '+';
            app.Phase2SliderLabel = uilabel(app.GridLayout);
            app.Phase2SliderLabel.Text = 'Phase 2';
            app.Phase2Slider = uislider(app.GridLayout, 'range');
            app.Phase2Slider.ValueChangedFcn = createCallbackFcn(app, @Phase2SliderValueChanged, true);
            app.Phase2Slider.ValueChangingFcn = createCallbackFcn(app, @Phase2SliderValueChanging, true);
            app.Phase3SliderLabel = uilabel(app.GridLayout);
            app.Phase3SliderLabel.Text = 'Phase 3';
            app.Phase3Slider = uislider(app.GridLayout, 'range');
            app.Phase3Slider.ValueChangedFcn = createCallbackFcn(app, @Phase3SliderValueChanged, true);
            app.Phase3Slider.ValueChangingFcn = createCallbackFcn(app, @Phase3SliderValueChanging, true);
            app.Phase4Slider_2Label = uilabel(app.GridLayout);
            app.Phase4Slider_2Label.Text = 'Phase 4';
            app.Phase4Slider = uislider(app.GridLayout, 'range');
            app.Phase4Slider.ValueChangedFcn = createCallbackFcn(app, @Phase4SliderValueChanged, true);
            app.Phase4Slider.ValueChangingFcn = createCallbackFcn(app, @Phase4SliderValueChanging, true);
            app.Phase5SliderLabel = uilabel(app.GridLayout);
            app.Phase5SliderLabel.Text = 'Phase 5';
            app.Phase5Slider = uislider(app.GridLayout, 'range');
            app.Phase5Slider.ValueChangedFcn = createCallbackFcn(app, @Phase5SliderValueChanged, true);
            app.Phase5Slider.ValueChangingFcn = createCallbackFcn(app, @Phase5SliderValueChanging, true);
            app.Phase6SliderLabel = uilabel(app.GridLayout);
            app.Phase6SliderLabel.Text = 'Phase 6';
            app.Phase6Slider = uislider(app.GridLayout, 'range');
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = 'Choose the number of phases and then set the upper limit (<=) threshold for each phase';
            app.P2_EditField = uieditfield(app.GridLayout, 'numeric');
            app.P2_EditField.Limits = [1 Inf];
            app.P2_EditField.RoundFractionalValues = 'on';
            app.P2_EditField.ValueChangedFcn = createCallbackFcn(app, @P2_EditFieldValueChanged, true);
            app.P2_EditField.Value = 1;
            app.P3_EditField = uieditfield(app.GridLayout, 'numeric');
            app.P3_EditField.Limits = [1 Inf];
            app.P3_EditField.RoundFractionalValues = 'on';
            app.P3_EditField.ValueChangedFcn = createCallbackFcn(app, @P3_EditFieldValueChanged, true);
            app.P3_EditField.Value = 1;
            app.P4_EditField = uieditfield(app.GridLayout, 'numeric');
            app.P4_EditField.Limits = [1 Inf];
            app.P4_EditField.RoundFractionalValues = 'on';
            app.P4_EditField.ValueChangedFcn = createCallbackFcn(app, @P4_EditFieldValueChanged, true);
            app.P4_EditField.Value = 1;
            app.P5_EditField = uieditfield(app.GridLayout, 'numeric');
            app.P5_EditField.Limits = [1 Inf];
            app.P5_EditField.RoundFractionalValues = 'on';
            app.P5_EditField.ValueChangedFcn = createCallbackFcn(app, @P5_EditFieldValueChanged, true);
            app.P5_EditField.Value = 1;
            app.P6_EditField = uieditfield(app.GridLayout, 'numeric');
            app.P6_EditField.Limits = [1 Inf];
            app.P6_EditField.RoundFractionalValues = 'on';
            app.P6_EditField.Value = 1;
            app.P2_minus_button = uibutton(app.GridLayout, 'push');
            app.P2_minus_button.ButtonPushedFcn = createCallbackFcn(app, @P2_minus_buttonButtonPushed, true);
            app.P2_minus_button.Text = '-';
            app.P3_minus_button = uibutton(app.GridLayout, 'push');
            app.P3_minus_button.ButtonPushedFcn = createCallbackFcn(app, @P3_minus_buttonButtonPushed, true);
            app.P3_minus_button.Text = '-';
            app.P4_minus_button = uibutton(app.GridLayout, 'push');
            app.P4_minus_button.ButtonPushedFcn = createCallbackFcn(app, @P4_minus_buttonButtonPushed, true);
            app.P4_minus_button.Text = '-';
            app.P5_minus_button = uibutton(app.GridLayout, 'push');
            app.P5_minus_button.ButtonPushedFcn = createCallbackFcn(app, @P5_minus_buttonButtonPushed, true);
            app.P5_minus_button.Text = '-';
            app.P6_minus_button = uibutton(app.GridLayout, 'push');
            app.P6_minus_button.Text = '-';
            app.P2_plus_button = uibutton(app.GridLayout, 'push');
            app.P2_plus_button.ButtonPushedFcn = createCallbackFcn(app, @P2_plus_buttonButtonPushed, true);
            app.P2_plus_button.Text = '+';
            app.P3_plus_button = uibutton(app.GridLayout, 'push');
            app.P3_plus_button.ButtonPushedFcn = createCallbackFcn(app, @P3_plus_buttonButtonPushed, true);
            app.P3_plus_button.Text = '+';
            app.P4_plus_button = uibutton(app.GridLayout, 'push');
            app.P4_plus_button.ButtonPushedFcn = createCallbackFcn(app, @P4_plus_buttonButtonPushed, true);
            app.P4_plus_button.Text = '+';
            app.P5_plus_button = uibutton(app.GridLayout, 'push');
            app.P5_plus_button.ButtonPushedFcn = createCallbackFcn(app, @P5_plus_buttonButtonPushed, true);
            app.P5_plus_button.Text = '+';
            app.P6_plus_button = uibutton(app.GridLayout, 'push');
            app.P6_plus_button.Text = '+';
            % Show the figure after all components are created
            app.ManualsegmentationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Manual_segmentation_sliders(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ManualsegmentationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ManualsegmentationUIFigure)
        end
    end
end