classdef VideoSequence < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VideosequencesUIFigure    matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        RemovelastsequenceButton  matlab.ui.control.Button
        RemoveallsequencesButton  matlab.ui.control.Button
        sequence_UITable          matlab.ui.control.Table
    end

    
    properties (Access = private)
        mainapp % mainapp
    end
    
    methods (Access = public)
        
        function UpdateGUI_from_main(app,seqdata)
            seqnums = cell2mat(app.sequence_UITable.Data(:,1));
            seqtimes = cell2mat(app.sequence_UITable.Data(:,end));
            new_seq = cell2mat(seqdata(end,1));
            tmp = ismember(seqnums,new_seq);
            if sum(tmp)
                idx = find(tmp);
                seqdata(end,end) = app.sequence_UITable.Data(idx(1),end);
            end
            [n,~] = size(app.sequence_UITable.Data);
            app.sequence_UITable.Data(n+1,:) = seqdata(end,:);            
        end
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, seqdata)
            % Store main app object
            app.mainapp = mainapp;                 
            app.sequence_UITable.Data = seqdata;
        end

        % Button pushed function: RemoveallsequencesButton
        function RemoveallsequencesButtonPushed(app, event)
            FromSequence_RemoveallsequencesButton(app.mainapp);
            delete(app)   
        end

        % Button pushed function: RemovelastsequenceButton
        function RemovelastsequenceButtonPushed(app, event)
            tmp = app.sequence_UITable.Data;
            if ~isempty(tmp)
                tmp(end,:)=[];
                app.sequence_UITable.Data = tmp;
                [n,~]=size(tmp);
                FromSequence_RemovelastsequenceButton(app.mainapp,tmp);
            else
                FromSequence_RemoveallsequencesButton(app.mainapp);
                delete(app)   
            end
        end

        % Double-clicked callback: sequence_UITable
        function sequence_UITableDoubleClicked(app, event)
            SelectedRow = event.InteractionInformation.DisplayRow;
            SelectedSeq = cell2mat(app.sequence_UITable.Data(SelectedRow,1));
            % Call main app's public function
            UpdateGUI_fromVideoSequence(app.mainapp,SelectedSeq);
        end

        % Cell edit callback: sequence_UITable
        function sequence_UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            if ~isempty(app.sequence_UITable.Data)
                sz = size(app.sequence_UITable.Data);
                if indices(2)==sz(2) % Time
                    if isnan(newData) || newData<=0
                        app.sequence_UITable.Data(indices(1),indices(2)) = {event.PreviousData};
                    else
                        all_sequences = cell2mat(app.sequence_UITable.Data(:,1));
                        sequence = cell2mat(app.sequence_UITable.Data(indices(1),1));
                        time = app.sequence_UITable.Data(indices(1),end);
                        for k=1:sz(1)
                            if all_sequences(k) == sequence
                                app.sequence_UITable.Data(k,end) = time;
                            end
                        end
                    end                    
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.VideosequencesUIFigure = uifigure('Visible', 'off');
            app.VideosequencesUIFigure.Name = 'Video sequences';
            app.VideosequencesUIFigure.Icon = fullfile(pathToMLAPP, 'Icon_sequence.png');
            app.GridLayout = uigridlayout(app.VideosequencesUIFigure);
            app.sequence_UITable = uitable(app.GridLayout);
            app.sequence_UITable.ColumnName = {'Sequence#'; 'Camera'; 'Light'; 'Transformations'; 'Clipping (FOV)'; 'Clipping (labels)'; 'Opacity'; 'Time (s)'};
            app.sequence_UITable.RowName = {};
            app.sequence_UITable.ColumnEditable = [false false false false false false false true];
            app.sequence_UITable.CellEditCallback = createCallbackFcn(app, @sequence_UITableCellEdit, true);
            app.sequence_UITable.DoubleClickedFcn = createCallbackFcn(app, @sequence_UITableDoubleClicked, true);
            app.RemoveallsequencesButton = uibutton(app.GridLayout, 'push');
            app.RemoveallsequencesButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveallsequencesButtonPushed, true);
            app.RemoveallsequencesButton.Text = 'Remove all sequences';
            app.RemovelastsequenceButton = uibutton(app.GridLayout, 'push');
            app.RemovelastsequenceButton.ButtonPushedFcn = createCallbackFcn(app, @RemovelastsequenceButtonPushed, true);
            app.RemovelastsequenceButton.Text = 'Remove last sequence';
            % Show the figure after all components are created
            app.VideosequencesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VideoSequence(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VideosequencesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VideosequencesUIFigure)
        end
    end
end