classdef microstructure_visualization < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Visualizationmodule_UIFigure    matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        InstructionsTab                 matlab.ui.container.Tab
        instructions_4                  matlab.ui.control.Label
        instructions_3                  matlab.ui.control.Label
        instructions_2                  matlab.ui.control.Label
        instructions_1                  matlab.ui.control.Label
        Main_instructions_title         matlab.ui.control.Label
        ViewvolumesTab                  matlab.ui.container.Tab
        View_UnitEditField              matlab.ui.control.EditField
        UnitEditFieldLabel_2            matlab.ui.control.Label
        View_VoxelsizeEditField         matlab.ui.control.NumericEditField
        VoxelsizeEditFieldLabel_2       matlab.ui.control.Label
        IdleLabel                       matlab.ui.control.Label
        View_3Dfigure_button            matlab.ui.control.Button
        View_createfigure_button        matlab.ui.control.Button
        View_Labelloaded                matlab.ui.control.Label
        View_UITable                    matlab.ui.control.Table
        Checkseg_instructions_2         matlab.ui.control.Label
        Viewvolume_Selecttif_Button     matlab.ui.control.Button
        Checkseg_title_2                matlab.ui.control.Label
        ChecksegmentationTab            matlab.ui.container.Tab
        Checkseg_UnitEditField          matlab.ui.control.EditField
        UnitEditFieldLabel              matlab.ui.control.Label
        Checkseg_VoxelsizeEditField     matlab.ui.control.NumericEditField
        VoxelsizeEditFieldLabel         matlab.ui.control.Label
        Checkseg_Segmentedvolumename    matlab.ui.control.Label
        Checkseg_Greyvolumename         matlab.ui.control.Label
        Checkseg_createfigure_button    matlab.ui.control.Button
        Checkseg_instructions           matlab.ui.control.Label
        Checkseg_Importtif_segmented_Button  matlab.ui.control.Button
        Checkseg_Importtif_grey_Button  matlab.ui.control.Button
        Checkseg_title                  matlab.ui.control.Label
        ViewcharacterizationresultsTab  matlab.ui.container.Tab
        Res_ResUnitEditField            matlab.ui.control.EditField
        PropertyunitLabel               matlab.ui.control.Label
        Res_ErrormessageLabel           matlab.ui.control.Label
        ForthephaseDropDown             matlab.ui.control.DropDown
        ForthephaseDropDownLabel        matlab.ui.control.Label
        PropertytoplotDropDown          matlab.ui.control.DropDown
        PropertytoplotDropDownLabel     matlab.ui.control.Label
        Res_UnitEditField               matlab.ui.control.EditField
        UnitEditFieldLabel_3            matlab.ui.control.Label
        Res_VoxelsizeEditField          matlab.ui.control.NumericEditField
        VoxelsizeEditFieldLabel_3       matlab.ui.control.Label
        Res_3Dfigure_button             matlab.ui.control.Button
        Res_createfigure_button         matlab.ui.control.Button
        Res_Labelloaded                 matlab.ui.control.Label
        Res_instructions                matlab.ui.control.Label
        Res_Selectfolder_Button         matlab.ui.control.Button
        Res_title                       matlab.ui.control.Label
        VideoTab                        matlab.ui.container.Tab
        Video_OrientationaxesCheckBox   matlab.ui.control.CheckBox
        RemovelastsequenceButton        matlab.ui.control.Button
        AddsequenceButton               matlab.ui.control.Button
        FramepersecondfpsEditField      matlab.ui.control.NumericEditField
        FramepersecondfpsEditFieldLabel  matlab.ui.control.Label
        SetvideosequencesLabel          matlab.ui.control.Label
        SetinitialviewparametersLabel   matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        InitialelevationEditField       matlab.ui.control.NumericEditField
        InitialelevationEditFieldLabel  matlab.ui.control.Label
        InitialrotationEditField        matlab.ui.control.NumericEditField
        InitialrotationEditFieldLabel   matlab.ui.control.Label
        Video_GradientCheckBox          matlab.ui.control.CheckBox
        Video_GradientcolorButton       matlab.ui.control.Button
        Video_BackgroundcolorButton     matlab.ui.control.Button
        Video_ScalebarCheckBox          matlab.ui.control.CheckBox
        Video_BoxCheckBox               matlab.ui.control.CheckBox
        ScalingfactorEditField          matlab.ui.control.NumericEditField
        ScalingfactorLabel              matlab.ui.control.Label
        DimensionLabel                  matlab.ui.control.Label
        createview_button               matlab.ui.control.Button
        Phasecolor_UITable              matlab.ui.control.Table
        Videosequence_UITable           matlab.ui.control.Table
        createvideo_button              matlab.ui.control.Button
        Res_Labelloaded_2               matlab.ui.control.Label
        Res_Selectfolder_Button_2       matlab.ui.control.Button
        VideofilenamewoextensionEditField  matlab.ui.control.EditField
        VideofilenamewoextensionEditFieldLabel  matlab.ui.control.Label
        Checkseg_instructions_3         matlab.ui.control.Label
        Checkseg_Segmentedvolumename_2  matlab.ui.control.Label
        Checkseg_Importtif_segmented_Button_2  matlab.ui.control.Button
        Res_title_2                     matlab.ui.control.Label
    end

    
    properties (Access = private)
        % View volumes
        allpath = {};
        volumes = [];
        
        % Check segmentation
        is_loaded_segmentedvolume = false;
        is_loaded_greyvolume = false;
        is_domainsize_coherent = false;
        segmentedvolume = [];
        greyvolume = [];
        path_seg = [];

        
        % Characterization result
        results_filenames = [];
        current_result_folder = [];
        arrayresult = [];
        resultname = [];
        result_id_mat =[];

        % Video
        path_video = [];
        segmentedvolume_video = [];
        colorphase_video = [];
        video_savefolder = [];
        Video_Backgroundcolor = [1 1 1]
        Video_Gradientcolor = [0, 0.5610, 1];
    end
    
    methods (Access = private)


        function [] = check_condition_videotab(app)
            if ~isempty(app.segmentedvolume_video) && ~isempty(app.VideofilenamewoextensionEditField.Value) && ~isempty(app.video_savefolder)
                app.createvideo_button.Enable = "on";
                app.createview_button.Enable = "on";
            else
                app.createvideo_button.Enable = "off";
                app.createview_button.Enable = "off";
            end            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.View_UITable.Visible = 'off'; app.View_UITable.Enable = 'off';
            app.Res_ErrormessageLabel.Visible = 'off';
            app.Video_BackgroundcolorButton.BackgroundColor = app.Video_Backgroundcolor;
            app.Video_GradientcolorButton.BackgroundColor = app.Video_Gradientcolor;     
            app.Videosequence_UITable.ColumnFormat = {'numeric' 'numeric' 'numeric' 'char' 'char'};
            app.Videosequence_UITable.Data = [{5;5} {0;1} {2;0} {'1';'0'} {'0';'0'}];
        end

        % Button pushed function: Checkseg_Importtif_grey_Button
        function Checkseg_Importtif_grey_ButtonPushed(app, event)
            % Open dialog box to choose file path
            str_dialogbox = 'Select grey-level volume';
            if isempty(app.path_seg)
                [FileName,PathName,~] = uigetfile({'*.tif;*.tiff','Tif image (*.tif, *.tiff)'},str_dialogbox);
            else
                [FileName,PathName,~] = uigetfile({'*.tif;*.tiff','Tif image (*.tif, *.tiff)'},str_dialogbox,app.path_seg);
            end
            if FileName==0
                % User clicked cancel button or closed the dialog box
                set(app.Checkseg_Greyvolumename,'FontColor','k','Text','No grey-level volume loaded');
                app.is_loaded_greyvolume = false;
            else
                [app.greyvolume, outcome] = function_load_tif([PathName FileName], 'uint16' ); % Load new volume
                if outcome % Success to import
                    app.is_loaded_greyvolume = true;
                    app.path_seg = PathName;
                    % Check dimension
                    if app.is_loaded_greyvolume && app.is_loaded_segmentedvolume
                        size_segmented = size(app.segmentedvolume);
                        dim_segmented = length(size_segmented);
                        size_grey = size(app.greyvolume);
                        dim_grey = length(size_grey);
                        if dim_segmented==dim_grey && sum(size_segmented==size_grey)==dim_segmented
                            app.is_domainsize_coherent=true;
                            set(app.Checkseg_Greyvolumename,'FontColor','k','Text',FileName);
                        else
                            app.is_domainsize_coherent=false;
                            set(app.Checkseg_Greyvolumename,'FontColor','r','Text','Volumes have different fields of view!');
                        end
                    else
                        set(app.Checkseg_Greyvolumename,'FontColor','k','Text',FileName);
                    end
                else
                    set(app.Checkseg_Greyvolumename,'FontColor','r','Text','Failed to load the volume!');
                    app.is_loaded_greyvolume = false;
                end
            end
            if app.is_loaded_greyvolume && app.is_loaded_segmentedvolume && app.is_domainsize_coherent
                set(app.Checkseg_createfigure_button,'enable','on');
            else
                set(app.Checkseg_createfigure_button,'enable','off');
            end
        end

        % Button pushed function: Checkseg_Importtif_segmented_Button
        function Checkseg_Importtif_segmented_ButtonPushed(app, event)
            % Open dialog box to choose file path
            str_dialogbox = 'Select segmented volume';
            if isempty(app.path_seg)
                [FileName,PathName,~] = uigetfile({'*.tif;*.tiff','Tif image (*.tif, *.tiff)'},str_dialogbox);
            else
                [FileName,PathName,~] = uigetfile({'*.tif;*.tiff','Tif image (*.tif, *.tiff)'},str_dialogbox,app.path_seg);
            end
            if FileName==0
                % User clicked cancel button or closed the dialog box
                set(app.Checkseg_Segmentedvolumename,'FontColor','k','Text','No segmented volume loaded');
                app.is_loaded_segmentedvolume = false;
            else
                [app.segmentedvolume, outcome] = function_load_tif([PathName FileName], 'uint8' ); % Load new volume
                if outcome % Success to import
                    app.is_loaded_segmentedvolume = true;
                    app.path_seg = PathName;
                    % Check dimension
                    if app.is_loaded_greyvolume && app.is_loaded_segmentedvolume
                        size_segmented = size(app.segmentedvolume);
                        dim_segmented = length(size_segmented);
                        size_grey = size(app.greyvolume);
                        dim_grey = length(size_grey);    
                        if dim_segmented==dim_grey && sum(size_segmented==size_grey)==dim_segmented
                            app.is_domainsize_coherent=true;
                            set(app.Checkseg_Segmentedvolumename,'FontColor','k','Text',FileName);
                        else
                            app.is_domainsize_coherent=false;
                            set(app.Checkseg_Segmentedvolumename,'FontColor','r','Text','Volumes have different fields of view!');
                        end
                    else
                        set(app.Checkseg_Segmentedvolumename,'FontColor','k','Text',FileName);
                    end
                else
                    set(app.Checkseg_Segmentedvolumename,'FontColor','r','Text','Failed to load the volume!');
                    app.is_loaded_segmentedvolume = false;
                end
            end
            if app.is_loaded_greyvolume && app.is_loaded_segmentedvolume && app.is_domainsize_coherent
                set(app.Checkseg_createfigure_button,'enable','on');
            else
                set(app.Checkseg_createfigure_button,'enable','off');
            end
        end

        % Button pushed function: Checkseg_createfigure_button
        function Checkseg_createfigure_buttonButtonPushed(app, event)
            microstructure_visualization_segmentation(app.greyvolume, app.segmentedvolume, app.Checkseg_Greyvolumename.Text, app.Checkseg_Segmentedvolumename.Text, app.Checkseg_VoxelsizeEditField.Value, app.Checkseg_UnitEditField.Value);
        end

        % Button pushed function: Viewvolume_Selecttif_Button
        function Viewvolume_Selecttif_ButtonPushed(app, event)
            flg=true;   % set the logic variable to start the loop
            nfile=0; files={};
            app.allpath = {}; % reset
            while flg
                str_dialogbox = 'Select One or More Files. Click cancel to stop importing more files';
                [FileName,PathName,~] = uigetfile({'*.tif;*.tiff','Tif image (*.tif, *.tiff)'},str_dialogbox,'MultiSelect', 'on');
                if isequal(FileName,0), flg=0; break; end   %  no file selected; quit
                if iscell(FileName) % More than 1 file selected
                    [~,n]=size(FileName);
                    for k=1:1:n
                        nfile=nfile+1;
                        files{nfile,1} = [char(FileName(k))];
                        app.allpath{nfile,1} = [char(PathName) char(FileName(k))];
                    end
                else
                    nfile=nfile+1;
                    files{nfile,1} = [char(FileName)];
                    app.allpath{nfile,1} = [char(PathName) char(FileName)];
                end
            end

            if ~isempty(app.allpath)
                if nfile>1
                    % Import
                    app.IdleLabel.Text = 'Importing...'; pause(0.01);                    
                    allimport = true;
                    for k=1:1:nfile
                        [app.volumes(k).array, outcome] = function_load_tif(app.allpath{k,1}, 'uint16' ); % Load new volume
                        if outcome % Success to import
                            set(app.View_Labelloaded,'FontColor','k','Text',[files{k,:} ' imported.']);  pause(0.01);
                        else
                            set(app.View_Labelloaded,'FontColor','r','Text',[files{k,:} ' import failed!']);
                            allimport = false;
                            break                            
                        end
                    end
                    app.IdleLabel.Text = 'Idle'; pause(0.01);
                    if allimport
                        set(app.View_Labelloaded,'FontColor','k','Text',[num2str(nfile) ' volumes imported.']);
                        app.View_UITable.Data = [files num2cell(ones(nfile,1))];
                        app.View_UITable.ColumnFormat = {'char' 'logical'};
                        app.View_UITable.Visible = 'on'; app.View_UITable.Enable = 'on';
                        app.View_createfigure_button.Enable = 'on';
                    else
                        app.View_UITable.Visible = 'off'; app.View_UITable.Enable = 'off';
                        app.View_createfigure_button.Enable = 'off';
                    end
                    app.View_3Dfigure_button.Enable = 'off';
                else
                    app.View_UITable.Visible = 'off'; app.View_UITable.Enable = 'off';
                    % Import
                    app.IdleLabel.Text = 'Importing...'; pause(0.01);
                    [app.volumes(1).array, outcome] = function_load_tif(app.allpath{nfile,1}, 'uint16' ); % Load new volume
                    if outcome % Success to import
                        set(app.View_Labelloaded,'FontColor','k','Text',files{1,:});
                        app.View_createfigure_button.Enable = 'on';
                        app.View_3Dfigure_button.Enable = 'on';
                    else
                        set(app.View_Labelloaded,'FontColor','r','Text','Failed to load the volume!');
                        app.View_createfigure_button.Enable = 'off';
                        app.View_3Dfigure_button.Enable = 'off';
                    end
                    app.IdleLabel.Text = 'Idle'; pause(0.01);
                end
            else
                app.View_Labelloaded.Text = 'No volume selected';
                app.View_createfigure_button.Enable = 'off';
                app.View_3Dfigure_button.Enable = 'off';
                app.View_UITable.Visible = 'off'; app.View_UITable.Enable = 'off';
            end


        end

        % Button pushed function: View_createfigure_button
        function View_createfigure_buttonButtonPushed(app, event)
            [nfile,~]=size(app.allpath);
            if nfile==1 % 1 volume visualization
                voxelsize = app.View_VoxelsizeEditField.Value;
                voxelsize_unitname = app.View_UnitEditField.Value;
                arrayunit = '';
                microstructure_visualization_slices(app.volumes(1).array, app.View_Labelloaded.Text, voxelsize, voxelsize_unitname, arrayunit);               
            else 
                choice = cell2mat(app.View_UITable.Data(:,2));
                if sum(choice)>1 % n volume visualization
                    % Build the string require to call the visualization function
                    str = 'Microstructure_comparison_visualization_interface(';
                    first_volume = true;
                    for k=1:1:length(choice)
                        if choice(k)
                            if first_volume
                                str = [str 'app.volumes(' num2str(k) ').array'];
                                first_volume = false;
                            else
                                str = [str ',app.volumes(' num2str(k) ').array'];
                            end
                        end
                    end
                    str = [str ');'];
                    eval(str);
                else % 1 volume visualization
                    voxelsize = app.View_VoxelsizeEditField.Value;
                    voxelsize_unitname = app.View_UnitEditField.Value;
                    arrayunit = '';
                    idx = find(choice==1);
                    microstructure_visualization_slices(app.volumes(idx).array, app.View_UITable.Data(idx,1), voxelsize, voxelsize_unitname, arrayunit);
                end
            end            
        end

        % Button pushed function: View_3Dfigure_button
        function View_3Dfigure_buttonButtonPushed(app, event)
            if ~isempty(app.View_UITable.Data)
                plotid = cell2mat(app.View_UITable.Data(:,2));
                id=find(plotid);
                vol = volshow(app.volumes(id).array);
            else
                vol = volshow(app.volumes(1).array);


            end
            %Fig_3D=figure;
            %col_ = bone;  
            %volshow(app.volumes(id).array,'Parent', Fig_3D,'BackgroundColor','w','Colormap',col_,'Renderer','VolumeRendering');           
        end

        % Button pushed function: Res_Selectfolder_Button
        function Res_Selectfolder_ButtonPushed(app, event)
            str_dialogbox = 'Select the main folder of the volume you want to investigate'; % Set string of the dialog box
            volume_main_folder = uigetdir(pwd,str_dialogbox); % Open dialog box to choose file path
            if volume_main_folder==0
                % User clicked cancel button or closed the dialog box
                app.Res_Labelloaded.Text = 'No folder selected';
                app.PropertytoplotDropDown.Enable = 'off'; app.PropertytoplotDropDownLabel.Enable = 'off';
                app.ForthephaseDropDown.Enable = 'off'; app.ForthephaseDropDownLabel.Enable = 'off';
                app.Res_ErrormessageLabel.Visible = 'off';
                app.Res_createfigure_button.Enable = 'off';
                app.Res_3Dfigure_button.Enable = 'off';
            else
                if ispc
                    app.current_result_folder = [volume_main_folder '\' 'Visualization' '\'];
                    tmp_ = find(volume_main_folder=='\');
                else
                    app.current_result_folder = [volume_main_folder '/' 'Visualization' '/'];
                    tmp_ = find(volume_main_folder=='/');
                end                
                volume_name = volume_main_folder(tmp_(end)+1:length(volume_main_folder));
                % Find all mat files that start with 'Visualization'
                MyFolderInfo=dir(app.current_result_folder);
                n_files=length(MyFolderInfo);
                detected_files=0;
                app.results_filenames={};
                for k_file=1:1:n_files
                    [~,filename,ext] = fileparts(MyFolderInfo(k_file).name);
                    if strcmp(ext,'.mat')
                        sub_name = filename(1:13);
                        if strcmp(sub_name,'Visualization')
                            detected_files=detected_files+1;
                            app.results_filenames(detected_files)={[filename '.mat']};
                        end
                    end
                end
                if detected_files
                    app.Res_ErrormessageLabel.Visible = 'off'; app.Res_ErrormessageLabel.Text = 'Error message';
                    app.Res_Labelloaded.Text = volume_name; % Set name
                    number_results = length(app.results_filenames); % Number of results to summarize
                    fields=[];
                    for current_matfile=1:1:number_results % Loop over all saved results
                        current_result=char(app.results_filenames(current_matfile)); % Load result
                        pathname = [app.current_result_folder current_result];
                        datamat = load(pathname);
                        if isfield(datamat,'results_visualization')
                            data = datamat.results_visualization;
                            fields = [fields; fieldnames(data)];
                        end
                    end
                    fields = unique(fields);
                    field_results={};
                    kk=0;
                    for k=1:1:length(fields)
                        if ~strcmp(char(fields(k)),'name')
                            kk=kk+1;
                            field_results(kk,1)=fields(k);
                        end
                    end
                    app.PropertytoplotDropDownLabel.Enable = 'on';
                    set(app.PropertytoplotDropDown,'Items', field_results,'enable','on');
                    app.PropertytoplotDropDownValueChanged;
                else
                    app.Res_ErrormessageLabel.Visible = 'on'; app.Res_ErrormessageLabel.Text = 'Wrong folder! ...\Main_folder_to_select\Visualization\Visualization*.mat';
                    app.Res_Labelloaded.Text = 'No folder selected';
                    app.PropertytoplotDropDown.Enable = 'off'; app.PropertytoplotDropDownLabel.Enable = 'off';
                    app.ForthephaseDropDown.Enable = 'off'; app.ForthephaseDropDownLabel.Enable = 'off';
                    app.Res_ErrormessageLabel.Visible = 'off';
                    app.Res_createfigure_button.Enable = 'off';
                    app.Res_3Dfigure_button.Enable = 'off';
                end
            end
        end

        % Button pushed function: Res_createfigure_button
        function Res_createfigure_buttonButtonPushed(app, event)
            voxelsize = app.Res_VoxelsizeEditField.Value;
            voxelsize_unitname = app.Res_UnitEditField.Value;
            arrayunit = app.Res_ResUnitEditField.Value;
            microstructure_visualization_slices(app.arrayresult, app.resultname, voxelsize, voxelsize_unitname, arrayunit);
        end

        % Button pushed function: Res_3Dfigure_button
        function Res_3Dfigure_buttonButtonPushed(app, event)
            %Fig_3D=figure;
            col_ = turbo;
            %volshow(app.arrayresult,'Parent', Fig_3D,'BackgroundColor','w','Colormap',col_,'Renderer','VolumeRendering');
            %volshow(app.arrayresult,'Parent', Fig_3D,'BackgroundColor','w','Colormap',col_,'Renderer','MaximumIntensityProjection');
            vol = volshow(app.arrayresult,'Colormap',col_);
        end

        % Value changed function: PropertytoplotDropDown
        function PropertytoplotDropDownValueChanged(app, event)
            property_string = app.PropertytoplotDropDown.Value;
            number_results = length(app.results_filenames); % Number of results to summarize
            app.result_id_mat=[];
            for current_matfile=1:1:number_results % Loop over all saved results
                current_result=char(app.results_filenames(current_matfile)); % Load result
                pathname = [app.current_result_folder current_result];
                datamat = load(pathname);
                if isfield(datamat,'results_visualization')
                    data = datamat.results_visualization;
                    fields = fieldnames(data);
                    for k=1:1:length(fields)
                        if strcmp( char(fields(k)),property_string)
                            app.result_id_mat=current_matfile;
                            [~, number_phase] = size(data);
                            phase_results={};
                            for kk=1:1:number_phase
                                phase_results(kk,1)={char(data(kk).name)};
                            end
                            set(app.ForthephaseDropDown,'Items', phase_results,'enable','on');
                            app.ForthephaseDropDownLabel.Enable = 'on';
                            app.ForthephaseDropDownValueChanged;
                        end
                    end
                end
            end
            app.ForthephaseDropDown;
        end

        % Value changed function: ForthephaseDropDown
        function ForthephaseDropDownValueChanged(app, event)
            phase_string = app.ForthephaseDropDown.Value;
            volume_name = app.Res_Labelloaded.Text;
            property_string = app.PropertytoplotDropDown.Value;
            app.resultname = [volume_name ', ' property_string ', ' phase_string];     
            current_result=char(app.results_filenames(app.result_id_mat)); % Load result
            pathname = [app.current_result_folder current_result];
            datamat = load(pathname);
            data = datamat.results_visualization;
            % Select phase
            [~, number_phase] = size(data);
            for k=1:1:number_phase
                if strcmp(data(k).name, phase_string)
                    k_phase=k;
                end
            end
            % Select array
            app.arrayresult = data(k_phase).(property_string);
            app.Res_createfigure_button.Enable = 'on';
            if length(size(app.arrayresult))==3
                app.Res_3Dfigure_button.Enable = 'on';
            else
                app.Res_3Dfigure_button.Enable = 'off';
            end
            
        end

        % Cell edit callback: View_UITable
        function View_UITableCellEdit(app, event)
            nplot = sum(cell2mat(app.View_UITable.Data(:,2)));
            if nplot==0
                app.View_createfigure_button.Enable = 'off';
                app.View_3Dfigure_button.Enable = 'off';
            elseif nplot==1
                app.View_createfigure_button.Enable = 'on';
                app.View_3Dfigure_button.Enable = 'on';
            else
                app.View_createfigure_button.Enable = 'on';
                app.View_3Dfigure_button.Enable = 'off';
            end  
        end

        % Button pushed function: Checkseg_Importtif_segmented_Button_2
        function Checkseg_Importtif_segmented_Button_2Pushed(app, event)
            % Open dialog box to choose file path
            str_dialogbox = 'Select segmented volume';
            if isempty(app.path_video)
                [FileName,PathName,~] = uigetfile({'*.tif;*.tiff','Tif image (*.tif, *.tiff)'},str_dialogbox);
            else
                [FileName,PathName,~] = uigetfile({'*.tif;*.tiff','Tif image (*.tif, *.tiff)'},str_dialogbox,app.path_video);
            end
            if FileName==0
                % User clicked cancel button or closed the dialog box
                set(app.Checkseg_Segmentedvolumename_2,'FontColor','k','Text','No segmented volume loaded');
                app.segmentedvolume_video = [];
                app.DimensionLabel.Visible = "off";
                app.Phasecolor_UITable.Data = [];
            else
                [app.segmentedvolume_video, outcome] = function_load_tif([PathName FileName], 'uint8' ); % Load new volume
                if outcome % Success to import
                    app.path_video = PathName;
                    set(app.Checkseg_Segmentedvolumename_2,'FontColor','k','Text',FileName);
                    sz = size(app.segmentedvolume_video);
                    app.DimensionLabel.Text = ['Dimensions: ' num2str(sz(1),'%i') ', ' num2str(sz(2),'%i') ', ' num2str(sz(3),'%i')];
                    app.DimensionLabel.Visible = "on";

                    % Fill volume fraction table
                    col_ = [colororder; rand(1e6,3)];
                    phase_label = unique(app.segmentedvolume_video);
                    number_phase = length(phase_label);
                    labels = cell(number_phase,1);
                    opacity = cell(number_phase,1);
                    volume_fractions = zeros(number_phase,1);
                    % Re-initialize
                    app.colorphase_video = [];

                    for k=1:1:number_phase
                        labels(k,1)={num2str(phase_label(k))};
                        volume_fractions(k,1)=sum(sum(sum( app.segmentedvolume_video==phase_label(k) )))/numel(app.segmentedvolume_video);
                        app.colorphase_video(k,:) = col_(k,:);
                        s = uistyle('BackgroundColor',app.colorphase_video(k,:));
                        addStyle(app.Phasecolor_UITable,s,'cell',[k,3]);
                        if k==1
                            opacity(k) = {0};
                        else
                            opacity(k) = {1};
                        end
                    end
                    app.Phasecolor_UITable.Data = [labels num2cell(volume_fractions) cell(number_phase,1) opacity];


                else
                    set(app.Checkseg_Segmentedvolumename_2,'FontColor','r','Text','Failed to load the volume!');
                    app.segmentedvolume_video = [];
                    app.DimensionLabel.Visible = "off";
                    app.Phasecolor_UITable.Data = [];
                end
            end           

            % Check condition
            app.check_condition_videotab;


        end

        % Button pushed function: Res_Selectfolder_Button_2
        function Res_Selectfolder_Button_2Pushed(app, event)
            str_dialogbox = 'Select the folder where the video file will be saved'; % Set string of the dialog box
            if isempty(app.path_video) 
                videosavefolder = uigetdir(pwd,str_dialogbox); % Open dialog box to choose file path
            else
                videosavefolder = uigetdir(app.path_video,str_dialogbox); % Open dialog box to choose file path
            end
            if videosavefolder==0
                % User clicked cancel button or closed the dialog box
                app.video_savefolder = [];
                app.Res_Labelloaded_2.Text = 'No folder selected';
            else                           
                app.video_savefolder = videosavefolder;
                app.Res_Labelloaded_2.Text = app.video_savefolder; % Set name
            end
            app.check_condition_videotab;
        end

        % Value changed function: VideofilenamewoextensionEditField
        function VideofilenamewoextensionEditFieldValueChanged(app, event)
            app.check_condition_videotab;            
        end

        % Button pushed function: createvideo_button
        function createvideo_buttonButtonPushed(app, event)
            alphamap = cell2mat(app.Phasecolor_UITable.Data(:,4));
            if length(alphamap)>10
                tmp1 = str2num(cell2mat(app.Phasecolor_UITable.Data(1:10,1)));
                tmp2 = str2num(cell2mat(app.Phasecolor_UITable.Data(11:end,1)));
                labels = [tmp1; tmp2];
            else
                labels = str2num(cell2mat(app.Phasecolor_UITable.Data(:,1)));
            end
            p.rotation = app.InitialrotationEditField.Value;
            p.elevation = app.InitialelevationEditField.Value;
            p.box = app.Video_BoxCheckBox.Value;
            p.scalebar = app.Video_ScalebarCheckBox.Value;
            p.backgroundcolor = app.Video_Backgroundcolor;
            p.gradientcolor = app.Video_Gradientcolor;
            p.gradient = app.Video_GradientCheckBox.Value;
            p.orientationaxes = app.Video_OrientationaxesCheckBox.Value;

            % Save parameter
            if ispc
                sav.folder = [app.video_savefolder '\'];
            else
                sav.folder = [app.video_savefolder '/'];
            end
            sav.filename = app.VideofilenamewoextensionEditField.Value;

            % Sequence parameters
            seq.fps = app.FramepersecondfpsEditField.Value;
            tmp = app.Videosequence_UITable.Data;
            seq.nseq = length(tmp(:,1));
            seq.time = cell2mat(tmp(:,1));
            seq.nturn = cell2mat(tmp(:,2));
            seq.clipdir = cell2mat(tmp(:,3));
            for k=1:1:seq.nseq
                seq.clipphases(k).vals = str2num(char(app.Videosequence_UITable.Data(k,4)));
                seq.notvisibleohases(k).vals = str2num(char(app.Videosequence_UITable.Data(k,5)));
            end
            
            if app.ScalingfactorEditField.Value~=1
                parameters_scaling.scaling_factor = app.ScalingfactorEditField.Value;
                parameters_scaling.label_or_greylevel = 'Label';
                parameters_scaling.background = 0;
                % Scale
                scaled = function_scaling(app.segmentedvolume_video,parameters_scaling);
                Video3D(scaled,labels,app.colorphase_video,alphamap,p,seq,sav)
            else
                Video3D(app.segmentedvolume_video,labels,app.colorphase_video,alphamap,p,seq,sav)
            end            
        end

        % Cell edit callback: Phasecolor_UITable
        function Phasecolor_UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            if indices(2)==4 % opacity
                if isnan(newData) || newData<0 || newData>1
                    app.Phasecolor_UITable.Data(indices(1),indices(2)) = {event.PreviousData};
                end
            end
        end

        % Cell selection callback: Phasecolor_UITable
        function Phasecolor_UITableCellSelection(app, event)
            indices = event.Indices;
            if ~isempty(indices)
                if indices(2)==3 % color
                    c = uisetcolor;
                    if length(c)==3 % RGB
                        app.colorphase_video(indices(1),:) = c;
                        s = uistyle('BackgroundColor',app.colorphase_video(indices(1),:));
                        addStyle(app.Phasecolor_UITable,s,'cell',[indices(1),3]);
                    end
                end
            end
        end

        % Button pushed function: createview_button
        function createview_buttonButtonPushed(app, event)
            alphamap = cell2mat(app.Phasecolor_UITable.Data(:,4));
            p.rotation = app.InitialrotationEditField.Value;
            p.elevation = app.InitialelevationEditField.Value;
            p.box = app.Video_BoxCheckBox.Value;
            p.scalebar = app.Video_ScalebarCheckBox.Value;
            p.backgroundcolor = app.Video_Backgroundcolor;
            p.gradientcolor = app.Video_Gradientcolor;
            p.gradient = app.Video_GradientCheckBox.Value;
            p.orientationaxes = app.Video_OrientationaxesCheckBox.Value;
            if app.ScalingfactorEditField.Value~=1
                parameters_scaling.scaling_factor = app.ScalingfactorEditField.Value;
                parameters_scaling.label_or_greylevel = 'Label';
                parameters_scaling.background = 0;
                % Scale
                scaled = function_scaling(app.segmentedvolume_video,parameters_scaling);
                View3D(scaled,app.colorphase_video,alphamap,p)
            else
                View3D(app.segmentedvolume_video,app.colorphase_video,alphamap,p)
            end
        end

        % Button pushed function: Video_BackgroundcolorButton
        function Video_BackgroundcolorButtonPushed(app, event)
            c = uisetcolor;
            if length(c)==3 % RGB
                app.Video_Backgroundcolor = c;
                app.Video_BackgroundcolorButton.BackgroundColor = c;
            end
        end

        % Value changed function: Video_GradientCheckBox
        function Video_GradientCheckBoxValueChanged(app, event)
            value = app.Video_GradientCheckBox.Value;
            if value
                app.Video_GradientcolorButton.Enable = 'on';
            else
                app.Video_GradientcolorButton.Enable = 'off';
            end
        end

        % Button pushed function: Video_GradientcolorButton
        function Video_GradientcolorButtonPushed(app, event)
            c = uisetcolor;
            if length(c)==3 % RGB
                app.Video_Gradientcolor = c;
                app.Video_GradientcolorButton.BackgroundColor = c;
            end
        end

        % Button pushed function: AddsequenceButton
        function AddsequenceButtonPushed(app, event)
            app.Videosequence_UITable.Data = [app.Videosequence_UITable.Data; [{5} {1} {0} {'0'} {'0'}]];
        end

        % Button pushed function: RemovelastsequenceButton
        function RemovelastsequenceButtonPushed(app, event)
            tmp =  app.Videosequence_UITable.Data;
            [n,~] = size(tmp);
            if n>1
                app.Videosequence_UITable.Data = app.Videosequence_UITable.Data(1:end-1,:);
            end
        end

        % Cell edit callback: Videosequence_UITable
        function Videosequence_UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            if indices(2)==1 % Time
                if isnan(newData) || newData<0 
                    app.Videosequence_UITable.Data(indices(1),indices(2)) = {event.PreviousData};
                end
            elseif indices(2)==2 % Number of turn
                if isnan(newData) || newData<0 
                    app.Videosequence_UITable.Data(indices(1),indices(2)) = {event.PreviousData};
                end
            elseif indices(2)==3 % Clipping directions
                if isnan(newData) || newData<-3 ||  newData>3
                    app.Videosequence_UITable.Data(indices(1),indices(2)) = {event.PreviousData};
                end     
            elseif indices(2)==4 || indices(2)==5
                labels = unique(app.segmentedvolume_video);
                tmp = str2num(newData);
                success=false;
                if ~isempty(tmp) && isnumeric(tmp)
                    tmp = sort(tmp,2);
                    sz = size(tmp);
                    if sz(1)==1 && sz(2)>=1 && sum(sum(ismember(tmp,labels)))==numel(tmp) && numel(tmp)==numel(unique(tmp))
                        success = true;                        
                    end
                end
                if ~success
                    app.Videosequence_UITable.Data(indices(1),indices(2)) = {[' ']};
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Visualizationmodule_UIFigure = uifigure('Visible', 'off');
            app.Visualizationmodule_UIFigure.Name = 'Visualization module';
            app.Visualizationmodule_UIFigure.Icon = 'Icon_visualization.png';
            app.TabGroup = uitabgroup(app.Visualizationmodule_UIFigure);
            app.InstructionsTab = uitab(app.TabGroup);
            app.InstructionsTab.Title = 'Instructions';
            app.Main_instructions_title = uilabel(app.InstructionsTab);
            app.Main_instructions_title.Text = 'Main instructions';
            app.instructions_1 = uilabel(app.InstructionsTab);
            app.instructions_1.Text = '- If you want to visualize one or several tif files, go to the "View volume(s)" tab.';
            app.instructions_2 = uilabel(app.InstructionsTab);
            app.instructions_2.Text = '- If you want to evaluate visually the relevance of a segmentation, go to the "Check segmentation" tab.';
            app.instructions_3 = uilabel(app.InstructionsTab);
            app.instructions_3.Text = {'- If you want to visualize a voxel-wise property (such as particle diameter, particle label, cluster connectivity etc.)'; 'go to the the "View characterization results" tab. Note that you need to run the characterization module first to generate'; 'the results to visualize in this tab. If you do not find the property you want to visualize you can easily add it (see'; 'the documentation for details).'};
            app.instructions_4 = uilabel(app.InstructionsTab);
            app.instructions_4.Text = '- If you want to create animations of 3D segmented microstructures, go to the "Video" tab.';
            app.ViewvolumesTab = uitab(app.TabGroup);
            app.ViewvolumesTab.Title = 'View volume(s)';
            app.Checkseg_title_2 = uilabel(app.ViewvolumesTab);
            app.Checkseg_title_2.Text = 'Select one or several volume files';
            app.Viewvolume_Selecttif_Button = uibutton(app.ViewvolumesTab, 'push');
            app.Viewvolume_Selecttif_Button.ButtonPushedFcn = createCallbackFcn(app, @Viewvolume_Selecttif_ButtonPushed, true);
            app.Viewvolume_Selecttif_Button.Text = 'Click to select one or several files';
            app.Checkseg_instructions_2 = uilabel(app.ViewvolumesTab);
            app.Checkseg_instructions_2.Text = 'Select file(s), enter a voxel size and then create figure. Volumes can have different dimension.';
            app.View_UITable = uitable(app.ViewvolumesTab);
            app.View_UITable.ColumnName = {'Files'; 'Select volumes to plot'};
            app.View_UITable.RowName = {};
            app.View_UITable.ColumnEditable = [false true];
            app.View_UITable.CellEditCallback = createCallbackFcn(app, @View_UITableCellEdit, true);
            app.View_Labelloaded = uilabel(app.ViewvolumesTab);
            app.View_Labelloaded.Text = 'No volume selected';
            app.View_createfigure_button = uibutton(app.ViewvolumesTab, 'push');
            app.View_createfigure_button.ButtonPushedFcn = createCallbackFcn(app, @View_createfigure_buttonButtonPushed, true);
            app.View_createfigure_button.Enable = 'off';
            app.View_createfigure_button.Text = '2D slices visualization';
            app.View_3Dfigure_button = uibutton(app.ViewvolumesTab, 'push');
            app.View_3Dfigure_button.ButtonPushedFcn = createCallbackFcn(app, @View_3Dfigure_buttonButtonPushed, true);
            app.View_3Dfigure_button.Enable = 'off';
            app.View_3Dfigure_button.Text = '3D visualization';
            app.IdleLabel = uilabel(app.ViewvolumesTab);
            app.IdleLabel.Text = 'Idle';
            app.VoxelsizeEditFieldLabel_2 = uilabel(app.ViewvolumesTab);
            app.VoxelsizeEditFieldLabel_2.Text = 'Voxel size';
            app.View_VoxelsizeEditField = uieditfield(app.ViewvolumesTab, 'numeric');
            app.View_VoxelsizeEditField.Limits = [1e-05 Inf];
            app.View_VoxelsizeEditField.Value = 1;
            app.UnitEditFieldLabel_2 = uilabel(app.ViewvolumesTab);
            app.UnitEditFieldLabel_2.Text = 'Unit';
            app.View_UnitEditField = uieditfield(app.ViewvolumesTab, 'text');
            app.View_UnitEditField.Value = 'um';
            app.ChecksegmentationTab = uitab(app.TabGroup);
            app.ChecksegmentationTab.Title = 'Check segmentation';
            app.Checkseg_title = uilabel(app.ChecksegmentationTab);
            app.Checkseg_title.Text = 'Select grey level and segemented volume files to compare them';
            app.Checkseg_Importtif_grey_Button = uibutton(app.ChecksegmentationTab, 'push');
            app.Checkseg_Importtif_grey_Button.ButtonPushedFcn = createCallbackFcn(app, @Checkseg_Importtif_grey_ButtonPushed, true);
            app.Checkseg_Importtif_grey_Button.Text = 'Click to import grey-level image';
            app.Checkseg_Importtif_segmented_Button = uibutton(app.ChecksegmentationTab, 'push');
            app.Checkseg_Importtif_segmented_Button.ButtonPushedFcn = createCallbackFcn(app, @Checkseg_Importtif_segmented_ButtonPushed, true);
            app.Checkseg_Importtif_segmented_Button.Text = 'Click to import segmented image';
            app.Checkseg_instructions = uilabel(app.ChecksegmentationTab);
            app.Checkseg_instructions.Text = 'Select both files, enter a voxel size and then create figure. Volumes must share same dimension.';
            app.Checkseg_createfigure_button = uibutton(app.ChecksegmentationTab, 'push');
            app.Checkseg_createfigure_button.ButtonPushedFcn = createCallbackFcn(app, @Checkseg_createfigure_buttonButtonPushed, true);
            app.Checkseg_createfigure_button.Enable = 'off';
            app.Checkseg_createfigure_button.Text = 'Create figure';
            app.Checkseg_Greyvolumename = uilabel(app.ChecksegmentationTab);
            app.Checkseg_Greyvolumename.Text = 'No volume loaded';
            app.Checkseg_Segmentedvolumename = uilabel(app.ChecksegmentationTab);
            app.Checkseg_Segmentedvolumename.Text = 'No volume loaded';
            app.VoxelsizeEditFieldLabel = uilabel(app.ChecksegmentationTab);
            app.VoxelsizeEditFieldLabel.Text = 'Voxel size';
            app.Checkseg_VoxelsizeEditField = uieditfield(app.ChecksegmentationTab, 'numeric');
            app.Checkseg_VoxelsizeEditField.Limits = [1e-05 Inf];
            app.Checkseg_VoxelsizeEditField.Value = 1;
            app.UnitEditFieldLabel = uilabel(app.ChecksegmentationTab);
            app.UnitEditFieldLabel.Text = 'Unit';
            app.Checkseg_UnitEditField = uieditfield(app.ChecksegmentationTab, 'text');
            app.Checkseg_UnitEditField.Value = 'um';
            app.ViewcharacterizationresultsTab = uitab(app.TabGroup);
            app.ViewcharacterizationresultsTab.Title = 'View characterization results';
            app.Res_title = uilabel(app.ViewcharacterizationresultsTab);
            app.Res_title.Text = 'Select one folder. You need to run the microstructure characterization module first before';
            app.Res_Selectfolder_Button = uibutton(app.ViewcharacterizationresultsTab, 'push');
            app.Res_Selectfolder_Button.ButtonPushedFcn = createCallbackFcn(app, @Res_Selectfolder_ButtonPushed, true);
            app.Res_Selectfolder_Button.Text = 'Click to select a  folder';
            app.Res_instructions = uilabel(app.ViewcharacterizationresultsTab);
            app.Res_instructions.Text = {'Select the main folder generated by the microstructure characteriation module, then choose the property to plot and for'; 'which phase. Enter a voxel size and then create the figure.'};
            app.Res_Labelloaded = uilabel(app.ViewcharacterizationresultsTab);
            app.Res_Labelloaded.Text = 'No folder selected';
            app.Res_createfigure_button = uibutton(app.ViewcharacterizationresultsTab, 'push');
            app.Res_createfigure_button.ButtonPushedFcn = createCallbackFcn(app, @Res_createfigure_buttonButtonPushed, true);
            app.Res_createfigure_button.Enable = 'off';
            app.Res_createfigure_button.Text = '2D slices visualization';
            app.Res_3Dfigure_button = uibutton(app.ViewcharacterizationresultsTab, 'push');
            app.Res_3Dfigure_button.ButtonPushedFcn = createCallbackFcn(app, @Res_3Dfigure_buttonButtonPushed, true);
            app.Res_3Dfigure_button.Enable = 'off';
            app.Res_3Dfigure_button.Text = '3D visualization';
            app.VoxelsizeEditFieldLabel_3 = uilabel(app.ViewcharacterizationresultsTab);
            app.VoxelsizeEditFieldLabel_3.Text = 'Voxel size';
            app.Res_VoxelsizeEditField = uieditfield(app.ViewcharacterizationresultsTab, 'numeric');
            app.Res_VoxelsizeEditField.Limits = [1e-05 Inf];
            app.Res_VoxelsizeEditField.Value = 1;
            app.UnitEditFieldLabel_3 = uilabel(app.ViewcharacterizationresultsTab);
            app.UnitEditFieldLabel_3.Text = 'Unit';
            app.Res_UnitEditField = uieditfield(app.ViewcharacterizationresultsTab, 'text');
            app.Res_UnitEditField.Value = 'um';
            app.PropertytoplotDropDownLabel = uilabel(app.ViewcharacterizationresultsTab);
            app.PropertytoplotDropDownLabel.Enable = 'off';
            app.PropertytoplotDropDownLabel.Text = 'Property to plot';
            app.PropertytoplotDropDown = uidropdown(app.ViewcharacterizationresultsTab);
            app.PropertytoplotDropDown.Items = {};
            app.PropertytoplotDropDown.ValueChangedFcn = createCallbackFcn(app, @PropertytoplotDropDownValueChanged, true);
            app.PropertytoplotDropDown.Enable = 'off';
            app.PropertytoplotDropDown.Value = {};
            app.ForthephaseDropDownLabel = uilabel(app.ViewcharacterizationresultsTab);
            app.ForthephaseDropDownLabel.Enable = 'off';
            app.ForthephaseDropDownLabel.Text = 'For the phase';
            app.ForthephaseDropDown = uidropdown(app.ViewcharacterizationresultsTab);
            app.ForthephaseDropDown.Items = {};
            app.ForthephaseDropDown.ValueChangedFcn = createCallbackFcn(app, @ForthephaseDropDownValueChanged, true);
            app.ForthephaseDropDown.Enable = 'off';
            app.ForthephaseDropDown.Value = {};
            app.Res_ErrormessageLabel = uilabel(app.ViewcharacterizationresultsTab);
            app.Res_ErrormessageLabel.Text = 'Error message';
            app.PropertyunitLabel = uilabel(app.ViewcharacterizationresultsTab);
            app.PropertyunitLabel.Text = 'Property unit';
            app.Res_ResUnitEditField = uieditfield(app.ViewcharacterizationresultsTab, 'text');
            app.VideoTab = uitab(app.TabGroup);
            app.VideoTab.Title = 'Video';
            app.Res_title_2 = uilabel(app.VideoTab);
            app.Res_title_2.Text = 'Select one volume and setup a video sequence';
            app.Checkseg_Importtif_segmented_Button_2 = uibutton(app.VideoTab, 'push');
            app.Checkseg_Importtif_segmented_Button_2.ButtonPushedFcn = createCallbackFcn(app, @Checkseg_Importtif_segmented_Button_2Pushed, true);
            app.Checkseg_Importtif_segmented_Button_2.Text = 'Click to import segmented image';
            app.Checkseg_Segmentedvolumename_2 = uilabel(app.VideoTab);
            app.Checkseg_Segmentedvolumename_2.Text = 'No volume loaded';
            app.Checkseg_instructions_3 = uilabel(app.VideoTab);
            app.Checkseg_instructions_3.Text = '1) Select file to import, write video file name and select save folder';
            app.VideofilenamewoextensionEditFieldLabel = uilabel(app.VideoTab);
            app.VideofilenamewoextensionEditFieldLabel.Text = 'Video file name (w/o extension)';
            app.VideofilenamewoextensionEditField = uieditfield(app.VideoTab, 'text');
            app.VideofilenamewoextensionEditField.ValueChangedFcn = createCallbackFcn(app, @VideofilenamewoextensionEditFieldValueChanged, true);
            app.Res_Selectfolder_Button_2 = uibutton(app.VideoTab, 'push');
            app.Res_Selectfolder_Button_2.ButtonPushedFcn = createCallbackFcn(app, @Res_Selectfolder_Button_2Pushed, true);
            app.Res_Selectfolder_Button_2.Text = 'Click to select a save folder';
            app.Res_Labelloaded_2 = uilabel(app.VideoTab);
            app.Res_Labelloaded_2.Text = 'No folder selected';
            app.createvideo_button = uibutton(app.VideoTab, 'push');
            app.createvideo_button.ButtonPushedFcn = createCallbackFcn(app, @createvideo_buttonButtonPushed, true);
            app.createvideo_button.Enable = 'off';
            app.createvideo_button.Text = 'Create video';
            app.Videosequence_UITable = uitable(app.VideoTab);
            app.Videosequence_UITable.ColumnName = {'Time (s)'; 'Number of turns (e.g.: 1 or 2)'; 'Clipping direction (e.g.: 1 or -2)'; 'Clipped phases (e.g.: 1 or 1,2)'; 'Not visible phases (e.g.: 0 or 0,1)'};
            app.Videosequence_UITable.RowName = {};
            app.Videosequence_UITable.ColumnEditable = true;
            app.Videosequence_UITable.CellEditCallback = createCallbackFcn(app, @Videosequence_UITableCellEdit, true);
            app.Phasecolor_UITable = uitable(app.VideoTab);
            app.Phasecolor_UITable.ColumnName = {'Label'; 'Volume fraction'; 'Color'; 'Opacity'};
            app.Phasecolor_UITable.RowName = {};
            app.Phasecolor_UITable.ColumnEditable = [false false true true];
            app.Phasecolor_UITable.CellEditCallback = createCallbackFcn(app, @Phasecolor_UITableCellEdit, true);
            app.Phasecolor_UITable.CellSelectionCallback = createCallbackFcn(app, @Phasecolor_UITableCellSelection, true);
            app.createview_button = uibutton(app.VideoTab, 'push');
            app.createview_button.ButtonPushedFcn = createCallbackFcn(app, @createview_buttonButtonPushed, true);
            app.createview_button.Enable = 'off';
            app.createview_button.Text = 'Initial view';
            app.DimensionLabel = uilabel(app.VideoTab);
            app.DimensionLabel.Visible = 'off';
            app.DimensionLabel.Text = 'Dimension';
            app.ScalingfactorLabel = uilabel(app.VideoTab);
            app.ScalingfactorLabel.Text = 'Scaling factor';
            app.ScalingfactorEditField = uieditfield(app.VideoTab, 'numeric');
            app.ScalingfactorEditField.Tooltip = {'>1: downscaling'; '<1: upscaling'};
            app.ScalingfactorEditField.Value = 1;
            app.Video_BoxCheckBox = uicheckbox(app.VideoTab);
            app.Video_BoxCheckBox.Text = 'Box';
            app.Video_ScalebarCheckBox = uicheckbox(app.VideoTab);
            app.Video_ScalebarCheckBox.Text = 'Scale bar';
            app.Video_BackgroundcolorButton = uibutton(app.VideoTab, 'push');
            app.Video_BackgroundcolorButton.ButtonPushedFcn = createCallbackFcn(app, @Video_BackgroundcolorButtonPushed, true);
            app.Video_BackgroundcolorButton.Text = 'Background color';
            app.Video_GradientcolorButton = uibutton(app.VideoTab, 'push');
            app.Video_GradientcolorButton.ButtonPushedFcn = createCallbackFcn(app, @Video_GradientcolorButtonPushed, true);
            app.Video_GradientcolorButton.Text = 'Gradient color';
            app.Video_GradientCheckBox = uicheckbox(app.VideoTab);
            app.Video_GradientCheckBox.ValueChangedFcn = createCallbackFcn(app, @Video_GradientCheckBoxValueChanged, true);
            app.Video_GradientCheckBox.Text = 'Gradient';
            app.Video_GradientCheckBox.Value = true;
            app.InitialrotationEditFieldLabel = uilabel(app.VideoTab);
            app.InitialrotationEditFieldLabel.Text = 'Initial rotation';
            app.InitialrotationEditField = uieditfield(app.VideoTab, 'numeric');
            app.InitialrotationEditField.Value = 4;
            app.InitialelevationEditFieldLabel = uilabel(app.VideoTab);
            app.InitialelevationEditFieldLabel.Text = 'Initial elevation';
            app.InitialelevationEditField = uieditfield(app.VideoTab, 'numeric');
            app.InitialelevationEditField.Value = 2;
            app.Label = uilabel(app.VideoTab);
            app.Label.Text = 'Use initial view to setup your initial rotation and elevation. Use high scaling factor and low fps to test your sequences';
            app.SetinitialviewparametersLabel = uilabel(app.VideoTab);
            app.SetinitialviewparametersLabel.Text = '2) Set initial view parameters';
            app.SetvideosequencesLabel = uilabel(app.VideoTab);
            app.SetvideosequencesLabel.Text = '3) Set video sequences';
            app.FramepersecondfpsEditFieldLabel = uilabel(app.VideoTab);
            app.FramepersecondfpsEditFieldLabel.Text = 'Frame per second (fps)';
            app.FramepersecondfpsEditField = uieditfield(app.VideoTab, 'numeric');
            app.FramepersecondfpsEditField.Limits = [1 Inf];
            app.FramepersecondfpsEditField.RoundFractionalValues = 'on';
            app.FramepersecondfpsEditField.Value = 30;
            app.AddsequenceButton = uibutton(app.VideoTab, 'push');
            app.AddsequenceButton.ButtonPushedFcn = createCallbackFcn(app, @AddsequenceButtonPushed, true);
            app.AddsequenceButton.Text = 'Add sequence';
            app.RemovelastsequenceButton = uibutton(app.VideoTab, 'push');
            app.RemovelastsequenceButton.ButtonPushedFcn = createCallbackFcn(app, @RemovelastsequenceButtonPushed, true);
            app.RemovelastsequenceButton.Text = 'Remove last sequence';
            app.Video_OrientationaxesCheckBox = uicheckbox(app.VideoTab);
            app.Video_OrientationaxesCheckBox.Text = 'Orientation axes';
            app.Video_OrientationaxesCheckBox.Value = true;
            % Show the figure after all components are created
            app.Visualizationmodule_UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = microstructure_visualization

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Visualizationmodule_UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Visualizationmodule_UIFigure)
        end
    end
end