classdef microstructure_visualization_segmentation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SegmentationvisualizationUIFigure  matlab.ui.Figure
        Sep_HorizontalButton            matlab.ui.control.Button
        SegmentationchannelsPanel       matlab.ui.container.Panel
        ThresholdEditField              matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel         matlab.ui.control.Label
        PleasewaitLabel                 matlab.ui.control.Label
        ExportProbButton                matlab.ui.control.Button
        ShowlineCompositeCheckBox       matlab.ui.control.CheckBox
        PlotStatsButton                 matlab.ui.control.Button
        Channel_ViewDropDown            matlab.ui.control.DropDown
        ViewDropDownLabel               matlab.ui.control.Label
        Channel_minus_Button            matlab.ui.control.Button
        Channel_plus_Button             matlab.ui.control.Button
        ChannelSlider                   matlab.ui.control.Slider
        ChannelSliderLabel              matlab.ui.control.Label
        ShowlineCheckBox                matlab.ui.control.CheckBox
        SeparatorSlider                 matlab.ui.control.Slider
        SeparatorLabel                  matlab.ui.control.Label
        FOV                             matlab.ui.control.Label
        VideoPanel                      matlab.ui.container.Panel
        WchannelCheckBox                matlab.ui.control.CheckBox
        WSepCheckBox                    matlab.ui.control.CheckBox
        SavevideoButton                 matlab.ui.control.Button
        sliceLabel                      matlab.ui.control.Label
        SaveeveryEditField              matlab.ui.control.NumericEditField
        SaveeveryEditFieldLabel         matlab.ui.control.Label
        toEditField                     matlab.ui.control.NumericEditField
        toEditFieldLabel                matlab.ui.control.Label
        FromEditField                   matlab.ui.control.NumericEditField
        FromEditFieldLabel              matlab.ui.control.Label
        FramepersEditField              matlab.ui.control.NumericEditField
        FramepersLabel                  matlab.ui.control.Label
        VideoformatDropDown             matlab.ui.control.DropDown
        VideoformatDropDownLabel        matlab.ui.control.Label
        FilenameEditField               matlab.ui.control.EditField
        FilenameEditFieldLabel          matlab.ui.control.Label
        NosavefolderselectedLabel       matlab.ui.control.Label
        SelectfolderButton              matlab.ui.control.Button
        ColorsPanel                     matlab.ui.container.Panel
        Prob_ColorbarCheckBox           matlab.ui.control.CheckBox
        Grey_ColorbarCheckBox           matlab.ui.control.CheckBox
        Grey_GlobalCheckBox             matlab.ui.control.CheckBox
        ProbabilitycolormapDropDown     matlab.ui.control.DropDown
        ProbabilitycolormapDropDownLabel  matlab.ui.control.Label
        GreycolormapDropDown            matlab.ui.control.DropDown
        GreycolormapDropDownLabel       matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        BackgroundDropDown              matlab.ui.control.DropDown
        BackgroundDropDownLabel         matlab.ui.control.Label
        ViewnormaltoaxisSlicexyumLabel  matlab.ui.control.Label
        ViewPanel                       matlab.ui.container.Panel
        ROI_y_b_EditField               matlab.ui.control.NumericEditField
        ROI_y_a_EditField               matlab.ui.control.NumericEditField
        ROIverticalLabel                matlab.ui.control.Label
        ResetButton                     matlab.ui.control.Button
        ApplyButton                     matlab.ui.control.Button
        ROI_x_b_EditField               matlab.ui.control.NumericEditField
        ROI_x_a_EditField               matlab.ui.control.NumericEditField
        ROIxLabel                       matlab.ui.control.Label
        AdjustviewasmovingsliderCheckBox  matlab.ui.control.CheckBox
        Button_pp                       matlab.ui.control.Button
        Button_p                        matlab.ui.control.Button
        Button_mm                       matlab.ui.control.Button
        ViewnormaltoDropDown            matlab.ui.control.DropDown
        ViewnormaltoDropDownLabel       matlab.ui.control.Label
        Button_m                        matlab.ui.control.Button
        Slider                          matlab.ui.control.Slider
        DisplayPanel                    matlab.ui.container.Panel
        PlotStatsDifferenceButton       matlab.ui.control.Button
        FlipYaxisCheckBox               matlab.ui.control.CheckBox
        RotateButton                    matlab.ui.control.StateButton
        LayoutDropDown                  matlab.ui.control.DropDown
        LayoutDropDownLabel             matlab.ui.control.Label
        GridnumberEditField             matlab.ui.control.NumericEditField
        GridnumberEditFieldLabel        matlab.ui.control.Label
        OverlayEditField                matlab.ui.control.NumericEditField
        OverlayEditField_2Label         matlab.ui.control.Label
        ComparisonDropDown              matlab.ui.control.DropDown
        ComparisonDropDownLabel         matlab.ui.control.Label
        DisplayDropDown                 matlab.ui.control.DropDown
        DisplayDropDownLabel            matlab.ui.control.Label
        ThirdplotDropDown               matlab.ui.control.DropDown
        ThirdplotDropDownLabel          matlab.ui.control.Label
        SecondplotDropDown              matlab.ui.control.DropDown
        SecondplotDropDownLabel         matlab.ui.control.Label
        FirstplotDropDown               matlab.ui.control.DropDown
        FirstplotDropDownLabel          matlab.ui.control.Label
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes1                         matlab.ui.control.UIAxes
    end


    properties (Access = private)
        str_grey = [];
        isgroundtruth = [];
        greyvolume = [];
        segmentedvolume = [];
        differencevolume = [];
        imds = [];
        direction = [];
        position_slice = 100;
        greyname = [];
        segmentedname = [];
        slice_grey = [];
        slice_segmented  =[];
        slice_probability_global = [];
        slice_PhaseMaxprobability = [];
        slice_PhaseHighprobability = [];
        slice_PhaseHighprobabilityReassigned = [];
        slice_probability_perhase = [];
        slice_differencevolume = [];
        comp = []
        domain_size = [];
        major_tick = [];
        number_phase = [];
        phases_id = [];
        RGB_phase = [];

        channel_ids = [];
        probability_perphase = [];
        probability_global = [];
        PhaseMaxprobability = [];
        PhaseHighprobability = [];
        PhaseHighprobabilityReassigned = [];

        probmap_global = [];
        probmap_local = [];

        groundtruth_volumefraction = [];
        volumefraction = [];
        Phase = [];
        groundtruth_Phase = []

        max_grey = [];
        min_grey = [];
        voxel_size = [];
        voxel_unit = [];
        color_phase = [];

        x1_start = []; x1_end=[];
        x2_start = []; x2_end=[];

        ROI = false;
        valuesep = [];

        savefolder = [];


        manual_segmentation = false;
        DialogApp  % Dialog box app
    end

    methods (Access = private)

        function [] = func_update_grey_figure(app,haxis)
            [t,s] = title(haxis,[app.str_grey ' volume'],app.greyname,'Color','k');
            t.FontSize = 16; t.FontName = 'Helvetica'; s.FontAngle = 'italic'; s.FontSize = 14;
            if app.Grey_GlobalCheckBox.Value
                clim(haxis,[app.min_grey app.max_grey]);
            else
                clim(haxis,'auto');
            end
            if app.ROI
                xa = app.ROI_x_a_EditField.Value; xb = app.ROI_x_b_EditField.Value;
                ya = app.ROI_y_a_EditField.Value; yb = app.ROI_y_b_EditField.Value;
                image(app.slice_grey(ya:yb,xa:xb,1),'CDataMapping','scaled','Parent', haxis);
            else
                image(app.slice_grey(:,:,1),'CDataMapping','scaled','Parent', haxis);
            end
            colormap(haxis,char(app.GreycolormapDropDown.Value));
            if app.Grey_ColorbarCheckBox.Value
                colorbar(haxis);
            else
                colorbar(haxis,'off');
            end
            % Get axis position
            axe_position =haxis.Position;
            set(haxis ,'Position', axe_position);
            % Remove tick and label
            set(haxis,'xtick',[],'ytick',[]);
            set(haxis,'xticklabel',[],'yticklabel',[]);
            set(haxis,'Xlabel',[],'Ylabel',[]);
            box(haxis,'on');
            %pixelgrid(haxis);
            % Fit the axes box
            axis(haxis,'tight');
            % Aspect ratio is 1:1
            axis(haxis,'equal');
            if app.FlipYaxisCheckBox.Value
                haxis.YDir='normal';
            end
        end

        function [] = func_update_probability_figure(app,haxis,str)
            [t,s] = title(haxis,str,' ','Color','k');
            t.FontSize = 16; t.FontName = 'Helvetica'; s.FontAngle = 'italic'; s.FontSize = 14;
            if strcmp(str,'Phase with higher probability') ||  strcmp(str,'Phase with high probability')
                colorbar(haxis,'off');
            else
                clim(haxis,[0 1]);
                colormap(haxis,char(app.ProbabilitycolormapDropDown.Value));
                if app.Prob_ColorbarCheckBox.Value
                    colorbar(haxis);
                else
                    colorbar(haxis,'off');
                end
            end

            if strcmp(str,'Probability map')
                current_slice = app.slice_probability_global(:,:,1);
            elseif strcmp(str,'Phase with higher probability')
                current_slice = app.slice_PhaseMaxprobability(:,:,:);
            elseif strcmp(str,'Phase with high probability')
                current_slice = app.slice_PhaseHighprobability(:,:,:);   
            elseif strcmp(str,'Phase with low confidence voxels assigned to nearest high confidence phase')
                current_slice = app.slice_PhaseHighprobabilityReassigned(:,:,:);  
            else
                current_phase = str2num(str(22:end));
                current_slice = app.slice_probability_perhase(current_phase+1).img;
            end

            if app.ROI
                xa = app.ROI_x_a_EditField.Value; xb = app.ROI_x_b_EditField.Value;
                ya = app.ROI_y_a_EditField.Value; yb = app.ROI_y_b_EditField.Value;
                if strcmp(str,'Phase with higher probability') ||  strcmp(str,'Phase with high probability') || strcmp(str,'Phase with low confidence voxels assigned to nearest high confidence phase')
                    image(current_slice(ya:yb,xa:xb,:),'Parent', haxis);
                else
                    image(current_slice(ya:yb,xa:xb,1),'CDataMapping','scaled','Parent', haxis);
                end
            else
                if strcmp(str,'Phase with higher probability') ||  strcmp(str,'Phase with high probability') || strcmp(str,'Phase with low confidence voxels assigned to nearest high confidence phase')
                    image(current_slice,'Parent', haxis);
                else
                    image(current_slice(:,:,1),'CDataMapping','scaled','Parent', haxis);
                end
            end
                     
            % Get axis position
            axe_position =haxis.Position;
            set(haxis ,'Position', axe_position);
            % Remove tick and label
            set(haxis,'xtick',[],'ytick',[]);
            set(haxis,'xticklabel',[],'yticklabel',[]);
            set(haxis,'Xlabel',[],'Ylabel',[]);
            box(haxis,'on');
            %pixelgrid(haxis);
            % Fit the axes box
            axis(haxis,'tight');
            % Aspect ratio is 1:1
            axis(haxis,'equal');
            if app.FlipYaxisCheckBox.Value
                haxis.YDir='normal';
            end
        end

        
        function [] = func_update_segmented_figure(app, haxis)          
            % Display the slice
            colorbar(haxis,'off');
            if app.ROI
                xa = app.ROI_x_a_EditField.Value; xb = app.ROI_x_b_EditField.Value;
                ya = app.ROI_y_a_EditField.Value; yb = app.ROI_y_b_EditField.Value;
                image(app.slice_segmented(ya:yb,xa:xb,:),'Parent', haxis);
            else
                image(app.slice_segmented,'parent',haxis);
            end
            [t,s] = title(haxis,'Segmented volume',app.segmentedname,'Color','k');
            t.FontSize = 16; t.FontName = 'Helvetica'; s.FontAngle = 'italic'; s.FontSize = 14;
            % Get axis position
            axe_position =haxis.Position;
            set(haxis ,'Position', axe_position);
            % Remove tick and label
            set(haxis,'xtick',[],'ytick',[]);
            set(haxis,'xticklabel',[],'yticklabel',[]);
            set(haxis,'Xlabel',[],'Ylabel',[]);
            box(haxis,'on');
            %pixelgrid(haxis);
            % Fit the axes box
            axis(haxis,'tight');
            % Aspect ratio is 1:1
            axis(haxis,'equal');
            if app.FlipYaxisCheckBox.Value
                haxis.YDir='normal';
            end
        end

        function [] = func_update_difference(app,haxis)
            [t,s] = title(haxis,'Difference',' ','Color','k');
            t.FontSize = 16; t.FontName = 'Helvetica'; s.FontAngle = 'italic'; s.FontSize = 14;
            clim(haxis,'auto');
            if app.ROI
                xa = app.ROI_x_a_EditField.Value; xb = app.ROI_x_b_EditField.Value;
                ya = app.ROI_y_a_EditField.Value; yb = app.ROI_y_b_EditField.Value;
                image(app.slice_differencevolume(ya:yb,xa:xb,1),'CDataMapping','scaled','Parent', haxis);
            else
                image(app.slice_differencevolume(:,:,1),'CDataMapping','scaled','Parent', haxis);
            end
            colormap(haxis,'gray');
            % Get axis position
            axe_position =haxis.Position;
            set(haxis ,'Position', axe_position);
            % Remove tick and label
            set(haxis,'xtick',[],'ytick',[]);
            set(haxis,'xticklabel',[],'yticklabel',[]);
            set(haxis,'Xlabel',[],'Ylabel',[]);
            box(haxis,'on');
            %pixelgrid(haxis);
            % Fit the axes box
            axis(haxis,'tight');
            % Aspect ratio is 1:1
            axis(haxis,'equal');
            if app.FlipYaxisCheckBox.Value
                haxis.YDir='normal';
            end
        end

        function [] = func_update_overlay(app,haxis)
            clim(haxis,'auto');
            colorbar(haxis,'off');            
            % Convert grey image in RGB
            grey_in_RGB = cat(3, app.slice_grey, app.slice_grey, app.slice_grey);
            grey_in_RGB=double(grey_in_RGB)/app.max_grey; % Scale
            % Linear combination
            C = app.OverlayEditField.Value*app.comp + (1-app.OverlayEditField.Value)*grey_in_RGB;
            % C = app.OverlayEditField.Value*app.slice_segmented + (1-app.OverlayEditField.Value)*grey_in_RGB;
            % Display
            if app.ROI
                xa = app.ROI_x_a_EditField.Value; xb = app.ROI_x_b_EditField.Value;
                ya = app.ROI_y_a_EditField.Value; yb = app.ROI_y_b_EditField.Value;
                image(C(ya:yb,xa:xb,:),'Parent', haxis);
            else
                image(C,'parent',haxis);
            end
            [t,s] = title(haxis,'Comparison','Overlay','Color','k');
            t.FontSize = 16; t.FontName = 'Helvetica'; s.FontAngle = 'italic'; s.FontSize = 14;
            %s = subtitle(haxis,'fff');
            %cla(haxis,'reset')
            % Get axis position
            axe_position =haxis.Position;
            set(haxis ,'Position', axe_position);
            % Remove tick and label
            set(haxis,'xtick',[],'ytick',[]);
            set(haxis,'xticklabel',[],'yticklabel',[]);
            set(haxis,'Xlabel',[],'Ylabel',[]);
            box(haxis,'on');
            %pixelgrid(haxis);
            % Fit the axes box
            axis(haxis,'tight');
            % Aspect ratio is 1:1
            axis(haxis,'equal');
            if app.FlipYaxisCheckBox.Value
                haxis.YDir='normal';
            end
        end
        
        function [] = func_update_checkerboard(app,haxis)
            clim(haxis,'auto');
            colorbar(haxis,'off');                
            % Convert grey image in RGB                     
            grey_in_RGB = cat(3, app.slice_grey, app.slice_grey, app.slice_grey);
            grey_in_RGB=double(grey_in_RGB)/app.max_grey; % Scale
            % Create grid
            %checkerboard_slice = app.slice_segmented;
            checkerboard_slice = app.comp;
            check=1;
            for k1=1:1:length(app.x1_start)
                check=abs(check-1);
                for k2=1+check:2:length(app.x2_start)
                    checkerboard_slice(app.x1_start(k1):app.x1_end(k1),app.x2_start(k2):app.x2_end(k2),1) = grey_in_RGB(app.x1_start(k1):app.x1_end(k1),app.x2_start(k2):app.x2_end(k2),1);
                    checkerboard_slice(app.x1_start(k1):app.x1_end(k1),app.x2_start(k2):app.x2_end(k2),2) = grey_in_RGB(app.x1_start(k1):app.x1_end(k1),app.x2_start(k2):app.x2_end(k2),2);
                    checkerboard_slice(app.x1_start(k1):app.x1_end(k1),app.x2_start(k2):app.x2_end(k2),3) = grey_in_RGB(app.x1_start(k1):app.x1_end(k1),app.x2_start(k2):app.x2_end(k2),3);
                end
            end
            % Display
            if app.ROI
                xa = app.ROI_x_a_EditField.Value; xb = app.ROI_x_b_EditField.Value;
                ya = app.ROI_y_a_EditField.Value; yb = app.ROI_y_b_EditField.Value;
                image(checkerboard_slice(ya:yb,xa:xb,:),'Parent', haxis);
            else
                image(checkerboard_slice,'parent',haxis);
            end

            %image(checkerboard_slice,'Parent', haxis);
            [t,s] = title(haxis,'Comparison','Checkerboard','Color','k');
            t.FontSize = 16; t.FontName = 'Helvetica'; s.FontAngle = 'italic'; s.FontSize = 14;
            % Get axis position
            axe_position =haxis.Position;
            set(haxis ,'Position', axe_position);
            % Remove tick and label
            set(haxis,'xtick',[],'ytick',[]);
            set(haxis,'xticklabel',[],'yticklabel',[]);
            set(haxis,'Xlabel',[],'Ylabel',[]);
            box(haxis,'on');
            % Fit the axes box
            axis(haxis,'tight');
            % Aspect ratio is 1:1
            axis(haxis,'equal');
            if app.FlipYaxisCheckBox.Value
                haxis.YDir='normal';
            end
        end

        function [] = func_update_separator(app,haxis)
            clim(haxis,'auto');
            colorbar(haxis,'off');                
            % Convert grey image in RGB                     
            grey_in_RGB = cat(3, app.slice_grey, app.slice_grey, app.slice_grey);
            grey_in_RGB=double(grey_in_RGB)/app.max_grey; % Scale
            % Create grid
            %separator_slice = app.slice_segmented;
            separator_slice = app.comp;

            %sep = round(app.SeparatorSlider.Value);
            sep = round(app.valuesep);
            sz = size(grey_in_RGB) ;

            if strcmp(app.Sep_HorizontalButton.Text ,'Horizontal')
                if sep>sz(1)
                    sep = round(sz(1)/2);
                    app.SeparatorSlider.Value = sep;
                end 
                separator_slice(1:sep,:,1) = grey_in_RGB(1:sep,:,1);
                separator_slice(1:sep,:,2) = grey_in_RGB(1:sep,:,2);
                separator_slice(1:sep,:,3) = grey_in_RGB(1:sep,:,3);
                if app.ShowlineCheckBox.Value
                    separator_slice(sep,:,1) = 0;
                    separator_slice(sep,:,2) = 0;
                    separator_slice(sep,:,3) = 0;
                end
            elseif strcmp(app.Sep_HorizontalButton.Text ,'Vertical')
                if sep>sz(2)
                    sep = round(sz(2)/2);
                    app.SeparatorSlider.Value = sep;
                end
                separator_slice(:,1:sep,1) = grey_in_RGB(:,1:sep,1);
                separator_slice(:,1:sep,2) = grey_in_RGB(:,1:sep,2);
                separator_slice(:,1:sep,3) = grey_in_RGB(:,1:sep,3);
                if app.ShowlineCheckBox.Value
                    separator_slice(:,sep,1) = 0;
                    separator_slice(:,sep,2) = 0;
                    separator_slice(:,sep,3) = 0;
                end
            end

            % Display
            if app.ROI
                xa = app.ROI_x_a_EditField.Value; xb = app.ROI_x_b_EditField.Value;
                ya = app.ROI_y_a_EditField.Value; yb = app.ROI_y_b_EditField.Value;
                image(separator_slice(ya:yb,xa:xb,:),'Parent', haxis);
            else
                image(separator_slice,'parent',haxis);
            end

            %image(checkerboard_slice,'Parent', haxis);
            [t,s] = title(haxis,'Comparison','Separator','Color','k');
            t.FontSize = 16; t.FontName = 'Helvetica'; s.FontAngle = 'italic'; s.FontSize = 14;
            % Get axis position
            axe_position =haxis.Position;
            set(haxis ,'Position', axe_position);
            % Remove tick and label
            set(haxis,'xtick',[],'ytick',[]);
            set(haxis,'xticklabel',[],'yticklabel',[]);
            set(haxis,'Xlabel',[],'Ylabel',[]);
            box(haxis,'on');
            % Fit the axes box
            axis(haxis,'tight');
            % Aspect ratio is 1:1
            axis(haxis,'equal');
            if app.FlipYaxisCheckBox.Value
                haxis.YDir='normal';
            end
        end


        function [] = func_update_juxtaposed(app,haxis)
            clim(haxis,'auto');
            colorbar(haxis,'off');                
            firstplot = app.FirstplotDropDown.Value;
            secondplot = app.SecondplotDropDown.Value;
            thirdplot = app.ThirdplotDropDown.Value;
            comparison = app.ComparisonDropDown.Value;
            layout = app.LayoutDropDown.Value;

            % Grey level
            grey_in_RGB = cat(3, app.slice_grey, app.slice_grey, app.slice_grey);
            grey_in_RGB=double(grey_in_RGB)/app.max_grey; % Scale
            % Segmented
            slice_segmened_r = app.slice_segmented (:,:,1);
            slice_segmened_g = app.slice_segmented (:,:,2);
            slice_segmened_b = app.slice_segmented (:,:,3);
            % Comparison
            if strcmp(firstplot,'Comparison') || strcmp(secondplot,'Comparison') || strcmp(thirdplot,'Comparison')
                if strcmp(comparison,'Overlay')
                    C = app.OverlayEditField.Value*app.slice_segmented + (1-app.OverlayEditField.Value)*grey_in_RGB;
                elseif strcmp(comparison,'Checkerboard')
                    C = app.slice_segmented;
                    check=1;
                    for k1=1:1:length(app.x1_start)
                        check=abs(check-1);
                        for k2=1+check:2:length(app.x2_start)
                            C(app.x1_start(k1):app.x1_end(k1),app.x2_start(k2):app.x2_end(k2),1) = grey_in_RGB(app.x1_start(k1):app.x1_end(k1),app.x2_start(k2):app.x2_end(k2),1);
                            C(app.x1_start(k1):app.x1_end(k1),app.x2_start(k2):app.x2_end(k2),2) = grey_in_RGB(app.x1_start(k1):app.x1_end(k1),app.x2_start(k2):app.x2_end(k2),2);
                            C(app.x1_start(k1):app.x1_end(k1),app.x2_start(k2):app.x2_end(k2),3) = grey_in_RGB(app.x1_start(k1):app.x1_end(k1),app.x2_start(k2):app.x2_end(k2),3);
                        end
                    end
                elseif strcmp(comparison,'Difference')
                    C = app.slice_differencevolume;
                    C = cat(3, C, C, C);
                end
            end

            sz=size(app.slice_grey);
            n=1; k=2;
            if ~strcmp(secondplot,'None')
                n=2; k=1;
                if ~strcmp(thirdplot,'None')
                    n=3; k=0;
                end
            end
            if strcmp(layout,'Horizontal')
                d = linspace(1,n*sz(2)+1,n+1);
                slice_juxtaposed = zeros(sz(1), n*sz(2),3);
                if strcmp(firstplot,app.str_grey)
                    slice_juxtaposed(:,1:d(2)-1,1) = grey_in_RGB(:,:,1); slice_juxtaposed(:,1:d(2)-1,2) = grey_in_RGB(:,:,2); slice_juxtaposed(:,1:d(2)-1,3) = grey_in_RGB(:,:,3);
                    t1 = [app.str_grey ' volume']; t2 = app.greyname;
                elseif strcmp(firstplot,'Segmented')
                    slice_juxtaposed(:,1:d(2)-1,1) = slice_segmened_r; slice_juxtaposed(:,1:d(2)-1,2) = slice_segmened_g; slice_juxtaposed(:,1:d(2)-1,3) = slice_segmened_b;
                    t1 = 'Segmented volume'; t2 = app.segmentedname;
                elseif strcmp(firstplot,'Comparison')
                    slice_juxtaposed(:,1:d(2)-1,1) = C(:,:,1); slice_juxtaposed(:,1:d(2)-1,2) = C(:,:,2); slice_juxtaposed(:,1:d(2)-1,3) = C(:,:,3);
                    t1 = 'Comparison'; t2 = comparison;
                end

                if strcmp(secondplot,app.str_grey)
                    slice_juxtaposed(:,d(2):d(3)-1,1) = grey_in_RGB(:,:,1); slice_juxtaposed(:,d(2):d(3)-1,2) = grey_in_RGB(:,:,2); slice_juxtaposed(:,d(2):d(3)-1,3) = grey_in_RGB(:,:,3);
                    t1 = [t1 ', ' app.str_grey ' volume']; t2 = [t2 ' ' app.greyname];
                elseif strcmp(secondplot,'Segmented')
                    slice_juxtaposed(:,d(2):d(3)-1,1) = slice_segmened_r; slice_juxtaposed(:,d(2):d(3)-1,2) = slice_segmened_g; slice_juxtaposed(:,d(2):d(3)-1,3) = slice_segmened_b;
                    t1 = [t1 ', Segmented volume']; t2 = [t2 ' ' app.segmentedname];
                elseif strcmp(secondplot,'Comparison')
                    slice_juxtaposed(:,d(2):d(3)-1,1) = C(:,:,1); slice_juxtaposed(:,d(2):d(3)-1,2) = C(:,:,2); slice_juxtaposed(:,d(2):d(3)-1,3) = C(:,:,3);
                    t1 = [t1 ', Comparison']; t2 = [t2 ' ' comparison];
                end       

                if strcmp(thirdplot,app.str_grey)
                    slice_juxtaposed(:,d(3):d(4)-1,1) = grey_in_RGB(:,:,1); slice_juxtaposed(:,d(3):d(4)-1,2) = grey_in_RGB(:,:,2); slice_juxtaposed(:,d(3):d(4)-1,3) = grey_in_RGB(:,:,3);
                    t1 = [t1 ', ' app.str_grey ' volume']; t2 = [t2 ' ' app.greyname];
                elseif strcmp(thirdplot,'Segmented')
                    slice_juxtaposed(:,d(3):d(4)-1,1) = slice_segmened_r; slice_juxtaposed(:,d(3):d(4)-1,2) = slice_segmened_g; slice_juxtaposed(:,d(3):d(4)-1,3) = slice_segmened_b;
                    t1 = [t1 ', Segmented volume']; t2 = [t2 ' ' app.segmentedname];
                elseif strcmp(thirdplot,'Comparison')
                    slice_juxtaposed(:,d(3):d(4)-1,1) = C(:,:,1); slice_juxtaposed(:,d(3):d(4)-1,2) = C(:,:,2); slice_juxtaposed(:,d(3):d(4)-1,3) = C(:,:,3);
                    t1 = [t1 ', Comparison']; t2 = [t2 ' ' comparison];                    
                end   

            else
                d = linspace(1,n*sz(1)+1,n+1);
                slice_juxtaposed = zeros(n*sz(1), sz(2),3);
                if strcmp(thirdplot,app.str_grey)
                    slice_juxtaposed(1:d(2)-1,:,1) = grey_in_RGB(:,:,1); slice_juxtaposed(1:d(2)-1,:,2) = grey_in_RGB(:,:,2); slice_juxtaposed(1:d(2)-1,:,3) = grey_in_RGB(:,:,3);
                    t1 = [app.str_grey ' volume,']; t2 = app.greyname;                   
                elseif strcmp(thirdplot,'Segmented')
                    slice_juxtaposed(1:d(2)-1,:,1) = slice_segmened_r; slice_juxtaposed(1:d(2)-1,:,2) = slice_segmened_g; slice_juxtaposed(1:d(2)-1,:,3) = slice_segmened_b;
                    t1 = 'Segmented volume,'; t2 = app.segmentedname;                    
                elseif strcmp(thirdplot,'Comparison')
                    slice_juxtaposed(1:d(2)-1,:,1) = C(:,:,1); slice_juxtaposed(1:d(2)-1,:,2) = C(:,:,2); slice_juxtaposed(1:d(2)-1,:,3) = C(:,:,3);
                    t1 = 'Comparison,'; t2 = comparison;      
                else
                    t1 = []; t2 = [];
                end   

                if strcmp(secondplot,app.str_grey)
                    slice_juxtaposed(d(2-k):d(3-k)-1,:,1) = grey_in_RGB(:,:,1); slice_juxtaposed(d(2-k):d(3-k)-1,:,2) = grey_in_RGB(:,:,2); slice_juxtaposed(d(2-k):d(3-k)-1,:,3) = grey_in_RGB(:,:,3);
                    t1 = [app.str_grey ' volume, ' t1]; t2 = [app.greyname ' ' t2];
                elseif strcmp(secondplot,'Segmented')
                    slice_juxtaposed(d(2-k):d(3-k)-1,:,1) = slice_segmened_r; slice_juxtaposed(d(2-k):d(3-k)-1,:,2) = slice_segmened_g; slice_juxtaposed(d(2-k):d(3-k)-1,:,3) = slice_segmened_b;
                    t1 = ['Segmented volume, ' t1]; t2 = [app.segmentedname ' ' t2];
                elseif strcmp(secondplot,'Comparison')
                    slice_juxtaposed(d(2-k):d(3-k)-1,:,1) = C(:,:,1); slice_juxtaposed(d(2-k):d(3-k)-1,:,2) = C(:,:,2); slice_juxtaposed(d(2-k):d(3-k)-1,:,3) = C(:,:,3);
                    t1 = ['Comparison, ' t1]; t2 = [comparison ' ' t2];           
                end  

                if strcmp(firstplot,app.str_grey)
                    slice_juxtaposed(d(3-k):d(4-k)-1,:,1) = grey_in_RGB(:,:,1); slice_juxtaposed(d(3-k):d(4-k)-1,:,2) = grey_in_RGB(:,:,2); slice_juxtaposed(d(3-k):d(4-k)-1,:,3) = grey_in_RGB(:,:,3);
                    t1 = [app.str_grey ' volume, ' t1]; t2 = [app.greyname ' ' t2];
                elseif strcmp(firstplot,'Segmented')
                    slice_juxtaposed(d(3-k):d(4-k)-1,:,1) = slice_segmened_r; slice_juxtaposed(d(3-k):d(4-k)-1,:,2) = slice_segmened_g; slice_juxtaposed(d(3-k):d(4-k)-1,:,3) = slice_segmened_b;
                    t1 = ['Segmented volume, ' t1]; t2 = [app.segmentedname ' ' t2];
                elseif strcmp(firstplot,'Comparison')
                    slice_juxtaposed(d(3-k):d(4-k)-1,:,1) = C(:,:,1); slice_juxtaposed(d(3-k):d(4-k)-1,:,2) = C(:,:,2); slice_juxtaposed(d(3-k):d(4-k)-1,:,3) = C(:,:,3);
                    t1 = ['Comparison, ' t1]; t2 = [comparison ' ' t2];   
                end  
                id=find(t1==',');
                t1(max(id):end)=[];
            end              

            % Display the slice
            image(slice_juxtaposed,'parent',haxis);
            [t,s] = title(haxis,t1,t2,'Color','k');
            t.FontSize = 16; t.FontName = 'Helvetica'; s.FontAngle = 'italic'; s.FontSize = 14;            
            % Get axis position
            axe_position =haxis.Position;
            set(haxis ,'Position', axe_position);
            % Remove tick and label
            set(haxis,'xtick',[],'ytick',[]);
            set(haxis,'xticklabel',[],'yticklabel',[]);
            set(haxis,'Xlabel',[],'Ylabel',[]);
            box(haxis,'on');
            % Fit the axes box
            axis(haxis,'tight');
            % Aspect ratio is 1:1
            axis(haxis,'equal');
            if app.FlipYaxisCheckBox.Value
                haxis.YDir='normal';
            end      

        end
        
        function [] = func_update_layout(app)
            secondplot = app.SecondplotDropDown.Value;
            thirdplot = app.ThirdplotDropDown.Value;
            if strcmp(secondplot,'None')
                colorbar(app.UIAxes2,'off');
                colorbar(app.UIAxes3,'off');
                thirdplot = 'None';
                app.ThirdplotDropDown.Value = thirdplot;
            end
            display = app.DisplayDropDown.Value;
            
            % text
            if length(app.domain_size)>2
                app.ViewnormaltoaxisSlicexyumLabel.Text = {['View normal to axis ' num2str(app.direction) '. Slice: ' num2str(app.position_slice) ' / ' num2str(app.domain_size(app.direction)) ', ' num2str(app.position_slice*app.voxel_size,'%1.1f') ' / ' num2str(app.domain_size(app.direction)*app.voxel_size,'%1.1f') ' ' app.voxel_unit]};
            else
                app.ViewnormaltoaxisSlicexyumLabel.Text = {['View normal to axis ' num2str(app.direction)]};
            end

            % Checkerboard grid
            g1 = app.GridnumberEditField.Value;
            sz = size(app.slice_segmented);
            tmp = round(linspace(1,sz(1),g1+1));
            app.x1_start = tmp(1:end-1);
            app.x1_end = [tmp(2:end-1)-1 sz(1)];
            app.x2_start = 1; app.x2_end = [];
            endnotreach = true;
            while endnotreach
                app.x2_end = [app.x2_end min(app.x2_start(end)+app.x1_end(1)-1,sz(2))];
                if app.x2_end(end)==sz(2)
                    endnotreach=false;
                else
                    app.x2_start=[app.x2_start app.x2_end(end)+1];
                end
            end

            % Layout
            tmp=app.VideoPanel.Position;
            Lx = tmp(1);
            tmp=app.DisplayPanel.Position;
            Ly = tmp(2)+tmp(4);
            if strcmp(display,'Separated plots')
                if strcmp(secondplot,'None')
                    app.UIAxes2.Visible = 'off'; cla(app.UIAxes2);
                else
                    app.UIAxes2.Visible = 'on';
                end
                if strcmp(thirdplot,'None')
                    app.UIAxes3.Visible = 'off'; cla(app.UIAxes3);
                else
                    app.UIAxes3.Visible = 'on';
                end
            else
                app.UIAxes2.Visible = 'off'; cla(app.UIAxes2);
                app.UIAxes3.Visible = 'off'; cla(app.UIAxes3);
            end
            app.UITable.Position(3) = tmp(3);

            % ROI
            app.ROI_x_a_EditField.Limits = [1 sz(2)-1];
            app.ROI_x_b_EditField.Limits = [2 sz(2)]; app.ROI_x_b_EditField.Value = sz(2);
            app.ROI_y_a_EditField.Limits = [1 sz(1)-1];
            app.ROI_y_b_EditField.Limits = [2 sz(1)]; app.ROI_y_b_EditField.Value = sz(1);
            app.ROI = false;
            if length(app.domain_size)>2
                app.FOV.Text = ['Domain''s size: ' num2str(app.domain_size(1)*app.voxel_size,'%1.1f') ' x ' num2str(app.domain_size(2)*app.voxel_size,'%1.1f') ' x ' num2str(app.domain_size(3)*app.voxel_size,'%1.1f') ' ' app.voxel_unit '3, voxel size:' num2str(app.voxel_size,'%1.3f') ' ' app.voxel_unit];
            else
                app.FOV.Text = ['Domain''s size: ' num2str(app.domain_size(1)*app.voxel_size,'%1.1f') ' x ' num2str(app.domain_size(2)*app.voxel_size,'%1.1f') ' ' app.voxel_unit '2, voxel size:' num2str(app.voxel_size,'%1.3f') ' ' app.voxel_unit];
            end

            % One plot
            offset = 50;
            if (strcmp(secondplot,'None') && strcmp(thirdplot,'None')) || strcmp(display,'Juxtaposed plots')
                app.UIAxes1.Position = [1 1+offset Lx Ly-offset]; 
            else % Multiple plot
                if strcmp(thirdplot,'None') % Two plot
                    if strcmp(app.LayoutDropDown.Value, 'Horizontal')
                        app.UIAxes1.Position = [1 1+offset Lx/2 Ly-offset];
                        app.UIAxes2.Position = [Lx/2 1+offset Lx/2 Ly-offset];
                    else
                        app.UIAxes1.Position = [1 (Ly+offset)/2 Lx (Ly-offset)/2];
                        app.UIAxes2.Position = [1 1+offset Lx (Ly-offset)/2];
                    end
                else % Three plot
                    if strcmp(app.LayoutDropDown.Value, 'Horizontal')
                        app.UIAxes1.Position = [1 1+offset Lx/3 Ly-offset];
                        app.UIAxes2.Position = [Lx/3 1+offset Lx/3 Ly-offset];
                        app.UIAxes3.Position = [2*Lx/3 1+offset Lx/3 Ly-offset];
                    elseif strcmp(app.LayoutDropDown.Value, 'Vertical')
                        app.UIAxes1.Position = [1 1+offset+2*(Ly-offset)/3 Lx (Ly-offset)/3];
                        app.UIAxes2.Position = [1 1+offset+(Ly-offset)/3 Lx (Ly-offset)/3];
                        app.UIAxes3.Position = [1 1+offset Lx (Ly-offset)/3];
                    else
                        app.UIAxes1.Position = [1 (Ly+offset)/2 Lx/2 (Ly-offset)/2];
                        app.UIAxes2.Position = [Lx/2 (Ly+offset)/2 Lx/2 (Ly-offset)/2];
                        app.UIAxes3.Position = [1*Lx/4 1+offset Lx/2 (Ly-offset)/2]; 
                    end                    
                end
            end           
            
        end
        
        function [] = func_update_axis(app)

            firstplot = app.FirstplotDropDown.Value;
            secondplot = app.SecondplotDropDown.Value;
            thirdplot = app.ThirdplotDropDown.Value;
            if strcmp(secondplot,'None')
                thirdplot = 'None';
                app.ThirdplotDropDown.Value = thirdplot;
            end
            display = app.DisplayDropDown.Value;
            comparison = app.ComparisonDropDown.Value;
            app.comp = app.slice_segmented;

            if strcmp(display,'Separated plots')
                if strcmp(firstplot,app.str_grey)
                    app.func_update_grey_figure(app.UIAxes1);
                elseif strcmp(firstplot,'Segmented')
                    app.func_update_segmented_figure(app.UIAxes1);
                elseif strcmp(firstplot,'Probability map') || strcmp(firstplot,'Phase with higher probability') || strcmp(firstplot,'Phase with high probability') || strcmp(firstplot,'Phase with low confidence voxels assigned to nearest high confidence phase') || (length(firstplot)>20 && strcmp(firstplot(1:20),'Probability of phase'))
                    app.func_update_probability_figure(app.UIAxes1,firstplot);
                elseif strcmp(firstplot,'Comparison')
                    if strcmp(comparison,'Overlay')
                        app.func_update_overlay(app.UIAxes1);
                    elseif strcmp(comparison,'Checkerboard')
                        app.func_update_checkerboard(app.UIAxes1);
                    elseif strcmp(comparison,'Separator')
                        app.func_update_separator(app.UIAxes1);      
                    elseif strcmp(comparison,'Difference')
                        app.func_update_difference(app.UIAxes1);                         
                    end
                end

                if ~strcmp(secondplot,'None')
                    if strcmp(secondplot,app.str_grey)
                        app.func_update_grey_figure(app.UIAxes2);
                    elseif strcmp(secondplot,'Segmented')
                        app.func_update_segmented_figure(app.UIAxes2);
                    elseif strcmp(secondplot,'Probability map') || strcmp(secondplot,'Phase with higher probability') || strcmp(secondplot,'Phase with high probability') || strcmp(secondplot,'Phase with low confidence voxels assigned to nearest high confidence phase') || (length(secondplot)>20 && strcmp(secondplot(1:20),'Probability of phase'))
                        app.func_update_probability_figure(app.UIAxes2,secondplot);
                    elseif strcmp(secondplot,'Comparison')
                        if strcmp(comparison,'Overlay')
                            app.func_update_overlay(app.UIAxes2);
                        elseif strcmp(comparison,'Checkerboard')
                            app.func_update_checkerboard(app.UIAxes2);
                        elseif strcmp(comparison,'Separator')
                            app.func_update_separator(app.UIAxes2);
                        elseif strcmp(comparison,'Difference')
                            app.func_update_difference(app.UIAxes2);
                        end
                    end
                end

                if ~strcmp(thirdplot,'None')
                    if strcmp(thirdplot,app.str_grey)
                        app.func_update_grey_figure(app.UIAxes3);
                    elseif strcmp(thirdplot,'Segmented')
                        app.func_update_segmented_figure(app.UIAxes3);
                    elseif strcmp(thirdplot,'Probability map') || strcmp(thirdplot,'Phase with higher probability') || strcmp(thirdplot,'Phase with high probability') || strcmp(thirdplot,'Phase with low confidence voxels assigned to nearest high confidence phase') || (length(thirdplot)>20 && strcmp(thirdplot(1:20),'Probability of phase'))
                        app.func_update_probability_figure(app.UIAxes3,thirdplot);                        
                    elseif strcmp(thirdplot,'Comparison')
                        if strcmp(app.Channel_ViewDropDown.Value,'Probability')
                            if strcmp(app.FirstplotDropDown.Value,'Probability map') || strcmp(app.SecondplotDropDown.Value,'Probability map')
                                app.comp = app.slice_probability_global;
                                app.comp = cat(3, app.comp, app.comp, app.comp);
                            elseif strcmp(app.FirstplotDropDown.Value,'Phase with higher probability') || strcmp(app.SecondplotDropDown.Value,'Phase with higher probability')
                                app.comp = app.slice_PhaseMaxprobability;
                            elseif strcmp(app.FirstplotDropDown.Value,'Phase with high probability') || strcmp(app.SecondplotDropDown.Value,'Phase with high probability')
                                app.comp = app.slice_PhaseHighprobability;   
                            elseif strcmp(app.FirstplotDropDown.Value,'Phase with low confidence voxels assigned to nearest high confidence phase') || strcmp(app.SecondplotDropDown.Value,'Phase with low confidence voxels assigned to nearest high confidence phase')
                                app.comp = app.slice_PhaseHighprobabilityReassigned;                                 
                            else
                                for kid=1:1:length(app.phases_id)-1
                                    str = ['Probability of phase ' num2str(kid-1)];
                                    if strcmp(app.FirstplotDropDown.Value,str) || strcmp(app.SecondplotDropDown.Value,str)
                                        app.comp = app.slice_probability_perhase(kid).img;
                                    end
                                end
                            end
                        end
                        if strcmp(comparison,'Overlay')
                            app.func_update_overlay(app.UIAxes3);
                        elseif strcmp(comparison,'Checkerboard')
                            app.func_update_checkerboard(app.UIAxes3);
                        elseif strcmp(comparison,'Separator')
                            app.func_update_separator(app.UIAxes3);
                        elseif strcmp(comparison,'Difference')
                            app.func_update_difference(app.UIAxes3);                            
                        end                      
                    end
                end

            elseif strcmp(display,'Juxtaposed plots')
                app.func_update_juxtaposed(app.UIAxes1);
            end   

            % text
            if length(app.domain_size)>2
                app.ViewnormaltoaxisSlicexyumLabel.Text = {['View normal to axis ' num2str(app.direction) '. Slice: ' num2str(app.position_slice) ' / ' num2str(app.domain_size(app.direction)) ', ' num2str(app.position_slice*app.voxel_size,'%1.1f') ' / ' num2str(app.domain_size(app.direction)*app.voxel_size,'%1.1f') ' ' app.voxel_unit]};
            else
                app.ViewnormaltoaxisSlicexyumLabel.Text = {['View normal to axis ' num2str(app.direction)]};
            end

        end
        
        function [] = func_update_slices(app)
            % Grey volume
            if app.direction==1
                app.slice_grey = squeeze(app.greyvolume(app.position_slice,:,:));
            elseif app.direction==2
                app.slice_grey = squeeze(app.greyvolume(:,app.position_slice,:));
            elseif app.direction==3
                app.slice_grey = app.greyvolume(:,:,app.position_slice);
            end
            if app.RotateButton.Value
                app.slice_grey = app.slice_grey';
            end

            if strcmp(app.ComparisonDropDown.Value,'Difference') && (strcmp(app.FirstplotDropDown.Value,'Comparison') || strcmp(app.SecondplotDropDown.Value,'Comparison') || strcmp(app.ThirdplotDropDown.Value,'Comparison'))
                if app.direction==1
                    app.slice_differencevolume = squeeze(app.differencevolume(app.position_slice,:,:));
                elseif app.direction==2
                    app.slice_differencevolume = squeeze(app.differencevolume(:,app.position_slice,:));
                elseif app.direction==3
                    app.slice_differencevolume = app.differencevolume(:,:,app.position_slice);
                end
                if app.RotateButton.Value
                    app.slice_differencevolume = app.slice_differencevolume';
                end               
            end

            if strcmp(app.Channel_ViewDropDown.Value,'Probability')
                if app.direction==1
                    app.slice_probability_global = squeeze(app.probability_global(app.position_slice,:,:));
                elseif app.direction==2
                    app.slice_probability_global = squeeze(app.probability_global(:,app.position_slice,:));
                elseif app.direction==3
                    app.slice_probability_global = app.probability_global(:,:,app.position_slice);
                end
                if app.RotateButton.Value
                    app.slice_probability_global = app.slice_probability_global';
                end

                for kid=1:1:length(app.phases_id)-1
                    tmp = app.probability_perphase(:,:,:,kid);
                    if app.direction==1
                        app.slice_probability_perhase(kid).img = squeeze(tmp(app.position_slice,:,:));
                    elseif app.direction==2
                        app.slice_probability_perhase(kid).img = squeeze(tmp(:,app.position_slice,:));
                    elseif app.direction==3
                        app.slice_probability_perhase(kid).img = tmp(:,:,app.position_slice);
                    end
                    if app.RotateButton.Value
                        app.slice_probability_perhase(kid).img = app.slice_probability_perhase(kid).img';
                    end
                end
            end

            sz = size(app.slice_grey);
            if strcmp(app.Sep_HorizontalButton.Text,'Horizontal')
                app.SeparatorSlider.Limits = [1 sz(1)];
            elseif strcmp(app.Sep_HorizontalButton.Text,'Vertical')
                app.SeparatorSlider.Limits = [1 sz(2)];
            end               
            %app.SeparatorSlider.Value = round(sz(2)/2);

            % Segmented
            if app.direction==1
                % Initializaion
                app.slice_segmented = zeros(app.domain_size(2),app.domain_size(3),3); % RGB color map
                slice_r = zeros(app.domain_size(2),app.domain_size(3)); % Red color map
                slice_g = zeros(app.domain_size(2),app.domain_size(3)); % Green color map
                slice_b = zeros(app.domain_size(2),app.domain_size(3)); % Blue color map
                % Attribute RGB colors for each voxel
                for current_phase=1:1:app.number_phase
                    id = app.phases_id(current_phase); % Current phase code
                    slice_r(app.segmentedvolume(app.position_slice,:,:)==id)= app.RGB_phase(current_phase,1); % Attribute red color
                    slice_g(app.segmentedvolume(app.position_slice,:,:)==id)= app.RGB_phase(current_phase,2); % Attribute green color
                    slice_b(app.segmentedvolume(app.position_slice,:,:)==id)= app.RGB_phase(current_phase,3); % Attribute blue color
                end
            elseif app.direction==2
                % Initializaion
                app.slice_segmented = zeros(app.domain_size(1),app.domain_size(3),3); % RGB color map
                slice_r = zeros(app.domain_size(1),app.domain_size(3)); % Red color map
                slice_g = zeros(app.domain_size(1),app.domain_size(3)); % Green color map
                slice_b = zeros(app.domain_size(1),app.domain_size(3)); % Blue color map
                % Attribute RGB colors for each voxel
                for current_phase=1:1:app.number_phase
                    id = app.phases_id(current_phase); % Current phase code
                    slice_r(app.segmentedvolume(:,app.position_slice,:)==id)= app.RGB_phase(current_phase,1); % Attribute red color
                    slice_g(app.segmentedvolume(:,app.position_slice,:)==id)= app.RGB_phase(current_phase,2); % Attribute green color
                    slice_b(app.segmentedvolume(:,app.position_slice,:)==id)= app.RGB_phase(current_phase,3); % Attribute blue color
                end
            elseif app.direction==3
                % Initializaion
                app.slice_segmented = zeros(app.domain_size(1),app.domain_size(2),3); % RGB color map
                slice_r = zeros(app.domain_size(1),app.domain_size(2)); % Red color map
                slice_g = zeros(app.domain_size(1),app.domain_size(2)); % Green color map
                slice_b = zeros(app.domain_size(1),app.domain_size(2)); % Blue color map
                % Attribute RGB colors for each voxel
                for current_phase=1:1:app.number_phase
                    id =app.phases_id(current_phase); % Current phase code
                    slice_r(app.segmentedvolume(:,:,app.position_slice)==id)= app.RGB_phase(current_phase,1); % Attribute red color
                    slice_g(app.segmentedvolume(:,:,app.position_slice)==id)= app.RGB_phase(current_phase,2); % Attribute green color
                    slice_b(app.segmentedvolume(:,:,app.position_slice)==id)= app.RGB_phase(current_phase,3); % Attribute blue color
                end
            end
            app.slice_segmented(:,:,1)=slice_r; % Attribute RGB color
            app.slice_segmented(:,:,2)=slice_g;
            app.slice_segmented(:,:,3)=slice_b;
            if app.RotateButton.Value
                sz=size(slice_r);
                RGB = zeros(sz(2),sz(1),3);
                RGB(:,:,1) = app.slice_segmented(:,:,1)';
                RGB(:,:,2) = app.slice_segmented(:,:,2)';
                RGB(:,:,3) = app.slice_segmented(:,:,3)';
                app.slice_segmented = RGB;
            end

            % Phase with higher probability
            if strcmp(app.Channel_ViewDropDown.Value,'Probability')
                if app.direction==1
                    % Initializaion
                    app.slice_PhaseMaxprobability = zeros(app.domain_size(2),app.domain_size(3),3); % RGB color map
                    slice_r = zeros(app.domain_size(2),app.domain_size(3)); % Red color map
                    slice_g = zeros(app.domain_size(2),app.domain_size(3)); % Green color map
                    slice_b = zeros(app.domain_size(2),app.domain_size(3)); % Blue color map
                    % Attribute RGB colors for each voxel
                    for current_phase=1:1:app.number_phase
                        id = app.phases_id(current_phase); % Current phase code
                        slice_r(app.PhaseMaxprobability(app.position_slice,:,:)==id)= app.RGB_phase(current_phase,1); % Attribute red color
                        slice_g(app.PhaseMaxprobability(app.position_slice,:,:)==id)= app.RGB_phase(current_phase,2); % Attribute green color
                        slice_b(app.PhaseMaxprobability(app.position_slice,:,:)==id)= app.RGB_phase(current_phase,3); % Attribute blue color
                    end
                elseif app.direction==2
                    % Initializaion
                    app.slice_PhaseMaxprobability = zeros(app.domain_size(1),app.domain_size(3),3); % RGB color map
                    slice_r = zeros(app.domain_size(1),app.domain_size(3)); % Red color map
                    slice_g = zeros(app.domain_size(1),app.domain_size(3)); % Green color map
                    slice_b = zeros(app.domain_size(1),app.domain_size(3)); % Blue color map
                    % Attribute RGB colors for each voxel
                    for current_phase=1:1:app.number_phase
                        id = app.phases_id(current_phase); % Current phase code
                        slice_r(app.PhaseMaxprobability(:,app.position_slice,:)==id)= app.RGB_phase(current_phase,1); % Attribute red color
                        slice_g(app.PhaseMaxprobability(:,app.position_slice,:)==id)= app.RGB_phase(current_phase,2); % Attribute green color
                        slice_b(app.PhaseMaxprobability(:,app.position_slice,:)==id)= app.RGB_phase(current_phase,3); % Attribute blue color
                    end
                elseif app.direction==3
                    % Initializaion
                    app.slice_PhaseMaxprobability = zeros(app.domain_size(1),app.domain_size(2),3); % RGB color map
                    slice_r = zeros(app.domain_size(1),app.domain_size(2)); % Red color map
                    slice_g = zeros(app.domain_size(1),app.domain_size(2)); % Green color map
                    slice_b = zeros(app.domain_size(1),app.domain_size(2)); % Blue color map
                    % Attribute RGB colors for each voxel
                    for current_phase=1:1:app.number_phase
                        id =app.phases_id(current_phase); % Current phase code
                        slice_r(app.PhaseMaxprobability(:,:,app.position_slice)==id)= app.RGB_phase(current_phase,1); % Attribute red color
                        slice_g(app.PhaseMaxprobability(:,:,app.position_slice)==id)= app.RGB_phase(current_phase,2); % Attribute green color
                        slice_b(app.PhaseMaxprobability(:,:,app.position_slice)==id)= app.RGB_phase(current_phase,3); % Attribute blue color
                    end
                end
                app.slice_PhaseMaxprobability(:,:,1)=slice_r; % Attribute RGB color
                app.slice_PhaseMaxprobability(:,:,2)=slice_g;
                app.slice_PhaseMaxprobability(:,:,3)=slice_b;
                if app.RotateButton.Value
                    sz=size(slice_r);
                    RGB = zeros(sz(2),sz(1),3);
                    RGB(:,:,1) = app.slice_PhaseMaxprobability(:,:,1)';
                    RGB(:,:,2) = app.slice_PhaseMaxprobability(:,:,2)';
                    RGB(:,:,3) = app.slice_PhaseMaxprobability(:,:,3)';
                    app.slice_PhaseMaxprobability = RGB;
                end


                if app.direction==1
                    % Initializaion
                    app.slice_PhaseHighprobability = zeros(app.domain_size(2),app.domain_size(3),3); % RGB color map
                    slice_r = zeros(app.domain_size(2),app.domain_size(3)); % Red color map
                    slice_g = zeros(app.domain_size(2),app.domain_size(3)); % Green color map
                    slice_b = zeros(app.domain_size(2),app.domain_size(3)); % Blue color map
                    % Attribute RGB colors for each voxel
                    for current_phase=1:1:app.number_phase
                        id = app.phases_id(current_phase); % Current phase code
                        slice_r(app.PhaseHighprobability(app.position_slice,:,:)==id)= app.RGB_phase(current_phase,1); % Attribute red color
                        slice_g(app.PhaseHighprobability(app.position_slice,:,:)==id)= app.RGB_phase(current_phase,2); % Attribute green color
                        slice_b(app.PhaseHighprobability(app.position_slice,:,:)==id)= app.RGB_phase(current_phase,3); % Attribute blue color
                    end
                elseif app.direction==2
                    % Initializaion
                    app.slice_PhaseHighprobability = zeros(app.domain_size(1),app.domain_size(3),3); % RGB color map
                    slice_r = zeros(app.domain_size(1),app.domain_size(3)); % Red color map
                    slice_g = zeros(app.domain_size(1),app.domain_size(3)); % Green color map
                    slice_b = zeros(app.domain_size(1),app.domain_size(3)); % Blue color map
                    % Attribute RGB colors for each voxel
                    for current_phase=1:1:app.number_phase
                        id = app.phases_id(current_phase); % Current phase code
                        slice_r(app.PhaseHighprobability(:,app.position_slice,:)==id)= app.RGB_phase(current_phase,1); % Attribute red color
                        slice_g(app.PhaseHighprobability(:,app.position_slice,:)==id)= app.RGB_phase(current_phase,2); % Attribute green color
                        slice_b(app.PhaseHighprobability(:,app.position_slice,:)==id)= app.RGB_phase(current_phase,3); % Attribute blue color
                    end
                elseif app.direction==3
                    % Initializaion
                    app.slice_PhaseHighprobability = zeros(app.domain_size(1),app.domain_size(2),3); % RGB color map
                    slice_r = zeros(app.domain_size(1),app.domain_size(2)); % Red color map
                    slice_g = zeros(app.domain_size(1),app.domain_size(2)); % Green color map
                    slice_b = zeros(app.domain_size(1),app.domain_size(2)); % Blue color map
                    % Attribute RGB colors for each voxel
                    for current_phase=1:1:app.number_phase
                        id =app.phases_id(current_phase); % Current phase code
                        slice_r(app.PhaseHighprobability(:,:,app.position_slice)==id)= app.RGB_phase(current_phase,1); % Attribute red color
                        slice_g(app.PhaseHighprobability(:,:,app.position_slice)==id)= app.RGB_phase(current_phase,2); % Attribute green color
                        slice_b(app.PhaseHighprobability(:,:,app.position_slice)==id)= app.RGB_phase(current_phase,3); % Attribute blue color
                    end
                end
                app.slice_PhaseHighprobability(:,:,1)=slice_r; % Attribute RGB color
                app.slice_PhaseHighprobability(:,:,2)=slice_g;
                app.slice_PhaseHighprobability(:,:,3)=slice_b;
                if app.RotateButton.Value
                    sz=size(slice_r);
                    RGB = zeros(sz(2),sz(1),3);
                    RGB(:,:,1) = app.slice_PhaseHighprobability(:,:,1)';
                    RGB(:,:,2) = app.slice_PhaseHighprobability(:,:,2)';
                    RGB(:,:,3) = app.slice_PhaseHighprobability(:,:,3)';
                    app.slice_PhaseHighprobability = RGB;
                end


                if app.direction==1
                    % Initializaion
                    app.slice_PhaseHighprobabilityReassigned = zeros(app.domain_size(2),app.domain_size(3),3); % RGB color map
                    slice_r = zeros(app.domain_size(2),app.domain_size(3)); % Red color map
                    slice_g = zeros(app.domain_size(2),app.domain_size(3)); % Green color map
                    slice_b = zeros(app.domain_size(2),app.domain_size(3)); % Blue color map
                    % Attribute RGB colors for each voxel
                    for current_phase=1:1:app.number_phase
                        id = app.phases_id(current_phase); % Current phase code
                        slice_r(app.PhaseHighprobabilityReassigned(app.position_slice,:,:)==id)= app.RGB_phase(current_phase,1); % Attribute red color
                        slice_g(app.PhaseHighprobabilityReassigned(app.position_slice,:,:)==id)= app.RGB_phase(current_phase,2); % Attribute green color
                        slice_b(app.PhaseHighprobabilityReassigned(app.position_slice,:,:)==id)= app.RGB_phase(current_phase,3); % Attribute blue color
                    end
                elseif app.direction==2
                    % Initializaion
                    app.slice_PhaseHighprobabilityReassigned = zeros(app.domain_size(1),app.domain_size(3),3); % RGB color map
                    slice_r = zeros(app.domain_size(1),app.domain_size(3)); % Red color map
                    slice_g = zeros(app.domain_size(1),app.domain_size(3)); % Green color map
                    slice_b = zeros(app.domain_size(1),app.domain_size(3)); % Blue color map
                    % Attribute RGB colors for each voxel
                    for current_phase=1:1:app.number_phase
                        id = app.phases_id(current_phase); % Current phase code
                        slice_r(app.PhaseHighprobabilityReassigned(:,app.position_slice,:)==id)= app.RGB_phase(current_phase,1); % Attribute red color
                        slice_g(app.PhaseHighprobabilityReassigned(:,app.position_slice,:)==id)= app.RGB_phase(current_phase,2); % Attribute green color
                        slice_b(app.PhaseHighprobabilityReassigned(:,app.position_slice,:)==id)= app.RGB_phase(current_phase,3); % Attribute blue color
                    end
                elseif app.direction==3
                    % Initializaion
                    app.slice_PhaseHighprobabilityReassigned = zeros(app.domain_size(1),app.domain_size(2),3); % RGB color map
                    slice_r = zeros(app.domain_size(1),app.domain_size(2)); % Red color map
                    slice_g = zeros(app.domain_size(1),app.domain_size(2)); % Green color map
                    slice_b = zeros(app.domain_size(1),app.domain_size(2)); % Blue color map
                    % Attribute RGB colors for each voxel
                    for current_phase=1:1:app.number_phase
                        id =app.phases_id(current_phase); % Current phase code
                        slice_r(app.PhaseHighprobabilityReassigned(:,:,app.position_slice)==id)= app.RGB_phase(current_phase,1); % Attribute red color
                        slice_g(app.PhaseHighprobabilityReassigned(:,:,app.position_slice)==id)= app.RGB_phase(current_phase,2); % Attribute green color
                        slice_b(app.PhaseHighprobabilityReassigned(:,:,app.position_slice)==id)= app.RGB_phase(current_phase,3); % Attribute blue color
                    end
                end
                app.slice_PhaseHighprobabilityReassigned(:,:,1)=slice_r; % Attribute RGB color
                app.slice_PhaseHighprobabilityReassigned(:,:,2)=slice_g;
                app.slice_PhaseHighprobabilityReassigned(:,:,3)=slice_b;
                if app.RotateButton.Value
                    sz=size(slice_r);
                    RGB = zeros(sz(2),sz(1),3);
                    RGB(:,:,1) = app.slice_PhaseHighprobabilityReassigned(:,:,1)';
                    RGB(:,:,2) = app.slice_PhaseHighprobabilityReassigned(:,:,2)';
                    RGB(:,:,3) = app.slice_PhaseHighprobabilityReassigned(:,:,3)';
                    app.slice_PhaseHighprobabilityReassigned = RGB;
                end

            end

        end
    end
    
    methods (Access = public)
        
        function [] = Update_from_sliders(app,number_phases,thresholds)
            app.number_phase = number_phases;

            % Assign voxels to phase based on threshold
            sz = size(app.greyvolume);
            app.segmentedvolume = zeros(sz);
            thresholds=[-1e9 thresholds];
            for k=1:1:app.number_phase
                condition_1 = app.greyvolume > thresholds(k);
                condition_2 = app.greyvolume <= thresholds(k+1);
                app.segmentedvolume( condition_1+condition_2==2 ) = k-1;
            end

            app.channel_ids = [0:1:app.number_phase-1]';
            app.phases_id = app.channel_ids;

            % Color
            app.color_phase=[colororder;rand(1000,3)];
            app.RGB_phase = app.color_phase(1:app.number_phase,:);
            % Table
            app.volumefraction=zeros(app.number_phase,1);
            app.Phase = [];
            for k=1:1:app.number_phase
                app.volumefraction(k,1)=sum(sum(sum(app.segmentedvolume==app.phases_id(k))))/numel(app.segmentedvolume);
                app.Phase(k).name=['Phase ' num2str(app.phases_id(k))];
                s = uistyle('BackgroundColor',app.color_phase(k,:));
                addStyle(app.UITable,s,'cell',[k,3]);
            end

            app.UITable.Data = [num2cell(app.phases_id) num2cell(app.volumefraction) cell(app.number_phase,1) {app.Phase.name}'];

            % Update
            app.func_update_slices;
            app.func_update_axis;

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, greyvolume, isgroundtruth, segmentedvolume, greyname, segmentedname, voxel_size, voxel_unit, manual_segmentation)
            if isgroundtruth
                app.str_grey = 'Ground truth';
                app.GreycolormapDropDownLabel.Text = 'Ground truth colormap';
                app.ComparisonDropDown.Items = {'Overlay','Checkerboard','Separator','Difference'};
                app.differencevolume = zeros(size(greyvolume));
                if ~isempty(segmentedname)
                    app.differencevolume(greyvolume~=segmentedvolume)=1;
                end
            else
                app.str_grey = 'Grey level';
            end
            app.FirstplotDropDown.Items = {app.str_grey, 'Segmented', 'Comparison'};
            app.SecondplotDropDown.Items = {app.str_grey, 'Segmented', 'Comparison', 'None'};
            app.ThirdplotDropDown.Items = {app.str_grey, 'Segmented', 'Comparison', 'None'};
            app.FirstplotDropDown.Value = app.FirstplotDropDown.Items(1);
            app.SecondplotDropDown.Value = app.SecondplotDropDown.Items(2);
            app.ThirdplotDropDown.Value = app.ThirdplotDropDown.Items(3);

            app.domain_size = size(greyvolume);

            app.ChannelSliderLabel.Visible = false;
            if isempty(segmentedname) % Image datastore case
                app.imds = segmentedvolume;
                if app.imds.no_import_needed
                    app.segmentedvolume = app.imds.Data.Files(1).M;
                else
                    [app.segmentedvolume, ~] = function_load_tif(char(app.imds.Files(1)), 'uint16' ); % Load new volume
                    if app.imds.loadallatonce
                        app.imds.Data.Files(1).M = app.segmentedvolume;
                    end
                end
                app.channel_ids = uint16(unique(app.segmentedvolume));
                if isgroundtruth
                    app.differencevolume(greyvolume~=app.segmentedvolume)=1;
                end
                app.ChannelSlider.Limits = [1 length(app.imds.Files)];
                app.ChannelSlider.Value = 1;
                app.ChannelSliderLabel.Visible = true;
                [~,segmentedname,~] = fileparts(char(app.imds.Files(1)));
                app.ChannelSlider.Visible = true; app.ChannelSlider.Enable = true;
                app.Channel_minus_Button.Enable = true; app.Channel_plus_Button.Enable = true;
                app.Channel_ViewDropDown.Visible = true; app.Channel_ViewDropDown.Enable = true; app.ViewDropDownLabel.Visible = true;
                app.WchannelCheckBox.Enable = true;
                % Check other images
                for k=2:1:length(app.imds.Files)
                    if app.imds.no_import_needed
                        tmp_segmented = app.imds.Data.Files(k).M;
                    else
                        [tmp_segmented, ~] = function_load_tif(char(app.imds.Files(k)), 'uint16' );
                        length(unique(tmp_segmented))
                        if app.imds.loadallatonce
                            app.imds.Data.Files(k).M = tmp_segmented;
                        end
                    end
                    sz_seg = size(tmp_segmented);
                    if length(sz_seg)==length(app.domain_size) && sum(sz_seg==app.domain_size)==length(sz_seg)
                        ids = uint16(unique(tmp_segmented));
                        app.channel_ids = [app.channel_ids; ids];
                    else
                        error('Some volume from the image data store do not have the same dimension with the grey level image');
                    end
                end
                app.channel_ids = unique(app.channel_ids);
                app.channel_ids = [app.channel_ids; max(app.channel_ids)+1];
                app.phases_id = app.channel_ids;
                app.number_phase = length(app.phases_id);
            else
                app.SegmentationchannelsPanel.Title = 'Segmentation channels (no data store loaded)';
                app.SegmentationchannelsPanel.Enable = false;
                app.segmentedvolume = segmentedvolume;
                app.phases_id = unique(app.segmentedvolume);
                app.number_phase = length(app.phases_id);
            end

            app.isgroundtruth = isgroundtruth;
            app.greyvolume = greyvolume;
            
            app.voxel_size = voxel_size;
            app.voxel_unit = voxel_unit;
            app.greyname = strrep(greyname,'_',' ');
            app.segmentedname = strrep(segmentedname,'_',' ');     
            
            if strcmp(app.voxel_unit,'um') || strcmp(app.voxel_unit,'micrometer') || strcmp(app.voxel_unit,'micrometers')
                app.voxel_unit = [char(956) 'm'];
            end        

            app.major_tick = app.domain_size/10;
  
            app.max_grey = double(max(max(max(app.greyvolume)))); 
            app.min_grey = double(min(min(min(app.greyvolume)))); 

            app.direction = 3;
            if length(app.domain_size)==2
                app.ViewnormaltoDropDown.Enable = 'off';
                app.ViewnormaltoDropDown.Items = {'axis 3'};
                app.Slider.Visible = 'off'; app.Slider.Enable = 'off';
                app.Button_p.Visible = 'off'; app.Button_p.Enable = 'off';
                app.Button_m.Visible = 'off'; app.Button_m.Enable = 'off';
                app.Button_pp.Visible = 'off'; app.Button_pp.Enable = 'off';
                app.Button_mm.Visible = 'off'; app.Button_mm.Enable = 'off';                
                app.ViewnormaltoaxisSlicexyumLabel.Visible = 'off'; app.ViewnormaltoaxisSlicexyumLabel.Enable = 'off';
                app.position_slice = 1;
                app.SelectfolderButton.Enable = 'off';
                app.AdjustviewasmovingsliderCheckBox.Visible = 'off'; app.AdjustviewasmovingsliderCheckBox.Enable = 'off';
            else
                app.Slider.Limits = [1 app.domain_size(app.direction)];
                app.Slider.Value = round(app.domain_size(app.direction)/2);
                app.position_slice = round(app.domain_size(app.direction)/2);
                app.FOV.Text = ['Domain''s size: ' num2str(app.domain_size(1)*app.voxel_size,'%1.1f') ' x ' num2str(app.domain_size(2)*app.voxel_size,'%1.1f') ' x ' num2str(app.domain_size(3)*app.voxel_size,'%1.1f') ' ' app.voxel_unit '3, voxel size:' num2str(app.voxel_size,'%1.3f') ' ' app.voxel_unit];
                app.ViewnormaltoaxisSlicexyumLabel.Text = ['View normal to axis ' num2str(app.direction) '. Slice: ' num2str(app.position_slice) ' / ' num2str(app.domain_size(app.direction)) ', ' num2str(app.position_slice*app.voxel_size,'%1.1f') ' / ' num2str(app.domain_size(app.direction)*app.voxel_size,'%1.1f') ' ' app.voxel_unit];
                app.toEditField.Value = app.domain_size(3);
            end


            % ROI
            app.ROI_x_a_EditField.Limits = [1 app.domain_size(2)-1];
            app.ROI_x_b_EditField.Limits = [2 app.domain_size(2)]; app.ROI_x_b_EditField.Value = app.domain_size(2);
            app.ROI_y_a_EditField.Limits = [1 app.domain_size(1)-1];
            app.ROI_y_b_EditField.Limits = [2 app.domain_size(1)]; app.ROI_y_b_EditField.Value = app.domain_size(1);

            % Color
            app.color_phase=[colororder;rand(1e6,3)];

            % app.color_phase(1,:)=[0.7 0.7 0.7];
            % app.color_phase(2,:)=[0.0 0.0 0.0];
            % app.color_phase(3,:)=[0.0 0.0 1.0];
            % app.color_phase(4,:)=[91 254 8]/255;
            % app.color_phase(5,:)=[35 251 236]/255;
            % app.color_phase(6,:)=[255 208 1.0]/255;
            % app.color_phase(7,:)=[1.0 0.0 0.0];
          

            app.RGB_phase = app.color_phase(1:app.number_phase,:);
            % Table
            app.volumefraction=zeros(app.number_phase,1);
            for k=1:1:app.number_phase
                app.volumefraction(k,1)=sum(sum(sum(app.segmentedvolume==app.phases_id(k))))/numel(app.segmentedvolume);
                if k==app.number_phase && ~isempty(app.imds)
                    app.Phase(k).name='Region with low confidence';
                else
                    app.Phase(k).name=['Phase ' num2str(app.phases_id(k))];
                end
                s = uistyle('BackgroundColor',app.color_phase(k,:));
                addStyle(app.UITable,s,'cell',[k,3]);
            end
            app.UITable.Data = [num2cell(app.phases_id) num2cell(app.volumefraction) cell(app.number_phase,1) {app.Phase.name}'];

            % if isgroundtruth
            %     app.GroundtruthCheckBox.Enable = true;
            %     app.groundtruth_phases_id = unique(app.greyvolume);
            %     app.groundtruth_number_phase = length(app.groundtruth_phases_id);
            %     app.groundtruth_volumefraction=zeros(app.number_phase,1);
            %     for k=1:1:app.groundtruth_number_phase
            %         app.groundtruth_volumefraction(k,1)=sum(sum(sum(app.greyvolume==app.groundtruth_phases_id(k))))/numel(app.greyvolume);
            %         app.groundtruth_Phase(k).name=['Phase ' num2str(app.groundtruth_phases_id(k))];
            %     end
            % end

            app.SeparatorLabel.Visible = false;

            % Update figure
            app.ViewnormaltoDropDownValueChanged    

            app.manual_segmentation = manual_segmentation;
            if app.manual_segmentation
                extremums = [min(min(min(greyvolume))) max(max(max(greyvolume)))];
                app.DialogApp = Manual_segmentation_sliders(app,extremums);
            end


        end

        % Value changed function: FirstplotDropDown, FlipYaxisCheckBox, 
        % ...and 6 other components
        function FirstplotDropDownValueChanged(app, event)
            if strcmp(app.DisplayDropDown.Value,'Juxtaposed plots') && (strcmp(app.ComparisonDropDown.Value,'Separator') || strcmp(app.Channel_ViewDropDown.Value,'Probability'))
                app.DisplayDropDown.Value = 'Separated plots';
            end
            
            if strcmp(app.ComparisonDropDown.Value,'Overlay')
                app.OverlayEditField.Enable = true;
                app.GridnumberEditField.Enable = false;
                app.SeparatorSlider.Enable = false;
                app.SeparatorSlider.Visible = false;
                app.SeparatorLabel.Visible = false;
                app.ShowlineCheckBox.Enable = false;
                app.ShowlineCheckBox.Visible = false;
                app.Sep_HorizontalButton.Enable = false;
                app.Sep_HorizontalButton.Visible = false;                       
                app.WSepCheckBox.Enable = false;
                app.WSepCheckBox.Value = false;
                app.PlotStatsDifferenceButton.Enable = false; app.PlotStatsDifferenceButton.Visible = false;
            elseif strcmp(app.ComparisonDropDown.Value,'Checkerboard')
                app.OverlayEditField.Enable = false;
                app.GridnumberEditField.Enable = true;
                app.SeparatorSlider.Enable = false;
                app.SeparatorSlider.Visible = false;
                app.SeparatorLabel.Visible = false;
                app.ShowlineCheckBox.Enable = false;
                app.ShowlineCheckBox.Visible = false;
                app.Sep_HorizontalButton.Enable = false;
                app.Sep_HorizontalButton.Visible = false;                       
                app.WSepCheckBox.Enable = false;
                app.WSepCheckBox.Value = false;
                app.PlotStatsDifferenceButton.Enable = false; app.PlotStatsDifferenceButton.Visible = false;
            elseif strcmp(app.ComparisonDropDown.Value,'Separator')
                app.OverlayEditField.Enable = false;
                app.GridnumberEditField.Enable = false;
                app.SeparatorSlider.Enable = true;
                app.SeparatorSlider.Visible = true;
                app.SeparatorLabel.Visible = true;
                app.ShowlineCheckBox.Enable = true;
                app.ShowlineCheckBox.Visible = true;
                app.Sep_HorizontalButton.Enable = true;
                app.Sep_HorizontalButton.Visible = true;
                app.WSepCheckBox.Enable = true;
                app.PlotStatsDifferenceButton.Enable = false; app.PlotStatsDifferenceButton.Visible = false;
            elseif strcmp(app.ComparisonDropDown.Value,'Difference')
                app.OverlayEditField.Enable = false;
                app.GridnumberEditField.Enable = false;
                app.SeparatorSlider.Enable = false;
                app.SeparatorSlider.Visible = false;
                app.SeparatorLabel.Visible = false;
                app.ShowlineCheckBox.Enable = false;
                app.ShowlineCheckBox.Visible = false;
                app.Sep_HorizontalButton.Enable = false;
                app.Sep_HorizontalButton.Visible = false;                
                app.WSepCheckBox.Enable = false;
                app.WSepCheckBox.Value = false;
                app.PlotStatsDifferenceButton.Enable = true; app.PlotStatsDifferenceButton.Visible = true;
            end

            app.func_update_slices;
            app.func_update_layout;
            app.func_update_axis;
        end

        % Button pushed function: Button_p
        function Button_pPushed(app, event)
            value = round(app.Slider.Value);
            new_value = min(value+1, app.domain_size(app.direction));
            app.position_slice = new_value;
            app.Slider.Value = new_value;
            if value~=new_value
                app.func_update_slices;
                app.func_update_axis;
            end
        end

        % Button pushed function: Button_pp
        function Button_ppPushed(app, event)
            value = round(app.Slider.Value);
            new_value = min(round(value+app.major_tick(app.direction)), app.domain_size(app.direction));
            app.position_slice = new_value;
            app.Slider.Value = new_value;
            if value~=new_value
                app.func_update_slices;
                app.func_update_axis;
            end
        end

        % Button pushed function: Button_m
        function Button_mPushed(app, event)
            value = round(app.Slider.Value);
            new_value = max(value-1, 1);
            app.position_slice = new_value;
            app.Slider.Value = new_value;
            if value~=new_value
                app.func_update_slices;
                app.func_update_axis;
            end
        end

        % Button pushed function: Button_mm
        function Button_mmPushed(app, event)
            value = round(app.Slider.Value);
            new_value = max(round(value-app.major_tick(app.direction)), 1);
            app.position_slice = new_value;
            app.Slider.Value = new_value;
            if value~=new_value
                app.func_update_slices;
                app.func_update_axis;
            end               
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = round(app.Slider.Value);
            app.position_slice = value;
            app.Slider.Value = value;
            app.func_update_slices;
            app.func_update_axis;
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            if app.AdjustviewasmovingsliderCheckBox.Value
                value = round(event.Value);
                app.position_slice = value;
                app.Slider.Value = value;
                app.func_update_slices;
                app.func_update_axis;
            end
        end

        % Value changed function: BackgroundDropDown
        function BackgroundDropDownValueChanged(app, event)
            value = app.BackgroundDropDown.Value;
            if strcmp(value,'Light grey')
                app.SegmentationvisualizationUIFigure.Color = [0.94 0.94 0.94];
            elseif strcmp(value,'White')
                app.SegmentationvisualizationUIFigure.Color = [1.0 1.0 1.0];
            end

            
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            if ~isempty(indices)
                if indices(2)==3 % color
                    c = uisetcolor;
                    if length(c)==3 % RGB
                        app.color_phase(indices(1),:) = c;
                        s = uistyle('BackgroundColor',app.color_phase(indices(1),:));
                        addStyle(app.UITable,s,'cell',[indices(1),3]);
                        app.RGB_phase = app.color_phase(1:app.number_phase,:);
                        app.func_update_slices;
                        app.func_update_axis;
                    end
                end
            end
        end

        % Value changed function: ViewnormaltoDropDown
        function ViewnormaltoDropDownValueChanged(app, event)
            value = app.ViewnormaltoDropDown.Value;
            app.FromEditField.Value = 1;
            if strcmp(value,'axis 1')
                app.direction = 1;
            elseif strcmp(value,'axis 2')
                app.direction = 2;
            elseif strcmp(value,'axis 3')
                app.direction = 3;
            end
            if length(app.domain_size)>2
                app.toEditField.Value = app.domain_size(app.direction);
                app.Slider.Limits = [1 app.domain_size(app.direction)];
                app.Slider.Value = round(app.domain_size(app.direction)/2);
                app.position_slice = round(app.domain_size(app.direction)/2);
            end

            value = app.Channel_ViewDropDown.Value;
            if strcmp(value,'Composite')
                app.segmentedvolume = zeros(app.domain_size);
                x = round(linspace(1,app.domain_size(1),length(app.imds.Files)+1));
                x0 = x(1:end-1); x1 = x(2:end)-1; x1(end)=x1(end)+1;
                y = round(linspace(1,app.domain_size(2),length(app.imds.Files)+1));
                y0 = y(1:end-1); y1 = y(2:end)-1; y1(end)=y1(end)+1;
                z = round(linspace(1,app.domain_size(3),length(app.imds.Files)+1));
                z0 = z(1:end-1); z1 = z(2:end)-1; z1(end)=z1(end)+1;
                app.PleasewaitLabel.Visible = 'on'; pause (0.01);
                for k=1:1:length(app.imds.Files)
                    app.ChannelSlider.Value = k; pause(0.01);
                    if app.imds.loadallatonce
                        tmp_segmented = app.imds.Data.Files(k).M;
                    else
                        [tmp_segmented, ~] = function_load_tif(char(app.imds.Files(k)), 'uint16' ); % Load new volume
                    end

                    if app.direction==1
                        if app.domain_size(2)>=app.domain_size(3)
                            app.segmentedvolume(:,y0(k):y1(k),:) = tmp_segmented(:,y0(k):y1(k),:);
                        else
                            app.segmentedvolume(:,:,z0(k):z1(k)) = tmp_segmented(:,:,z0(k):z1(k));
                        end
                    elseif app.direction==2
                        if app.domain_size(1)>=app.domain_size(3)
                            app.segmentedvolume(x0(k):x1(k),:,:) = tmp_segmented(x0(k):x1(k),:,:);
                        else
                            app.segmentedvolume(:,:,z0(k):z1(k)) = tmp_segmented(:,:,z0(k):z1(k));
                        end
                    elseif app.direction==3
                        if app.domain_size(1)>=app.domain_size(2)
                            app.segmentedvolume(x0(k):x1(k),:,:) = tmp_segmented(x0(k):x1(k),:,:);
                        else
                            app.segmentedvolume(:,y0(k):y1(k),:) = tmp_segmented(:,y0(k):y1(k),:);
                        end
                    end
                    if app.ShowlineCompositeCheckBox.Value && k<length(app.imds.Files)
                        if app.direction==1
                            if app.domain_size(2)>=app.domain_size(3)
                                app.segmentedvolume(:,y1(k),:) = max(app.channel_ids)+1;
                            else
                                app.segmentedvolume(:,:,z1(k)) = max(app.channel_ids)+1;
                            end
                        elseif app.direction==2
                            if app.domain_size(1)>=app.domain_size(3)
                                app.segmentedvolume(x1(k),:,:) = max(app.channel_ids)+1;
                            else
                                app.segmentedvolume(:,:,z1(k)) = max(app.channel_ids)+1;
                            end
                        elseif app.direction==3
                            if app.domain_size(1)>=app.domain_size(2)
                                app.segmentedvolume(x1(k),:,:) = max(app.channel_ids)+1;
                            else
                                app.segmentedvolume(:,y1(k),:) = max(app.channel_ids)+1;
                            end
                        end
                    end
                end

                if app.isgroundtruth
                    app.differencevolume = zeros(app.domain_size);
                    app.differencevolume(app.greyvolume~=app.segmentedvolume)=1;
                end

                app.PleasewaitLabel.Visible = 'off';
            end  
            app.FirstplotDropDownValueChanged;
        end

        % Value changed function: ROI_x_a_EditField, ROI_x_b_EditField, 
        % ...and 2 other components
        function ROIxEditFieldValueChanged(app, event)
            xa = app.ROI_x_a_EditField.Value;
            xb = app.ROI_x_b_EditField.Value;
            ya = app.ROI_y_a_EditField.Value;
            yb = app.ROI_y_b_EditField.Value;
            sz = size(app.slice_segmented);
            % ROI
            if xb<=xa || xb>sz(2) || yb<=ya || yb>sz(1)
                app.ROI_x_a_EditField.Value = 1;
                app.ROI_x_b_EditField.Value = sz(2);
                app.ROI_y_a_EditField.Value = 1;
                app.ROI_y_b_EditField.Value = sz(1);
            else
                app.func_update_axis;
                hold(app.UIAxes1,'on');
                plot(app.UIAxes1,[xa xb],[ya ya],'Color','r','LineStyle','--','LineWidth',2)
                plot(app.UIAxes1,[xb xb],[ya yb],'Color','r','LineStyle','--','LineWidth',2)
                plot(app.UIAxes1,[xb xa],[yb yb],'Color','r','LineStyle','--','LineWidth',2)
                plot(app.UIAxes1,[xa xa],[yb ya],'Color','r','LineStyle','--','LineWidth',2)
                hold(app.UIAxes1,'off');
                if ~strcmp(app.SecondplotDropDown.Value,'None') && strcmp(app.DisplayDropDown.Value,'Separated plots')
                    hold(app.UIAxes2,'on');
                    plot(app.UIAxes2,[xa xb],[ya ya],'Color','r','LineStyle','--','LineWidth',2)
                    plot(app.UIAxes2,[xb xb],[ya yb],'Color','r','LineStyle','--','LineWidth',2)
                    plot(app.UIAxes2,[xb xa],[yb yb],'Color','r','LineStyle','--','LineWidth',2)
                    plot(app.UIAxes2,[xa xa],[yb ya],'Color','r','LineStyle','--','LineWidth',2)
                    hold(app.UIAxes2,'off');
                    if ~strcmp(app.ThirdplotDropDown.Value,'None')
                        hold(app.UIAxes3,'on');
                        plot(app.UIAxes3,[xa xb],[ya ya],'Color','r','LineStyle','--','LineWidth',2)
                        plot(app.UIAxes3,[xb xb],[ya yb],'Color','r','LineStyle','--','LineWidth',2)
                        plot(app.UIAxes3,[xb xa],[yb yb],'Color','r','LineStyle','--','LineWidth',2)
                        plot(app.UIAxes3,[xa xa],[yb ya],'Color','r','LineStyle','--','LineWidth',2)
                        hold(app.UIAxes3,'off');
                    end
                end
            end
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            if ~app.ROI
                app.ROI = true;
            end       
            xa = app.ROI_x_a_EditField.Value; xb = app.ROI_x_b_EditField.Value;
            ya = app.ROI_y_a_EditField.Value; yb = app.ROI_y_b_EditField.Value;
            Lx = xb-xa+1;
            Ly = yb-ya+1;
            app.FOV.Text = ['Cropped field of view: ' num2str(Ly*app.voxel_size,'%1.1f') ' x ' num2str(Lx*app.voxel_size,'%1.1f') ' ' app.voxel_unit '2, voxel size:' num2str(app.voxel_size,'%1.3f') ' ' app.voxel_unit];
            app.func_update_slices;
            app.func_update_axis;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.ROI = false;
            sz = size(app.slice_segmented);
            app.ROI_x_a_EditField.Value = 1;
            app.ROI_x_b_EditField.Value = sz(2);
            app.ROI_y_a_EditField.Value = 1;
            app.ROI_y_b_EditField.Value = sz(1);
            app.FOV.Text = ['Domain''s size: ' num2str(app.domain_size(1)*app.voxel_size,'%1.1f') ' x ' num2str(app.domain_size(2)*app.voxel_size,'%1.1f') ' x ' num2str(app.domain_size(3)*app.voxel_size,'%1.1f') ' ' app.voxel_unit '3, voxel size:' num2str(app.voxel_size,'%1.3f') ' ' app.voxel_unit];
            app.func_update_slices;
            app.func_update_axis;
        end

        % Button pushed function: SelectfolderButton
        function SelectfolderButtonPushed(app, event)
            str_dialogbox = 'Select save folder';
            res = uigetdir(matlabroot,str_dialogbox); % Open dialog box
            if res==0
                % User clicked cancel button or closed the dialog box
                app.NosavefolderselectedLabel.Text = 'No save folder selected';
                app.SavevideoButton.Enable = 'off';
            else
                if ispc
                    app.savefolder = [res '\'];
                else
                    app.savefolder = [res '/'];
                end
                app.NosavefolderselectedLabel.Text = app.savefolder;
                app.SavevideoButton.Enable = 'on';
            end                
        end

        % Value changed function: FromEditField, SaveeveryEditField, 
        % ...and 1 other component
        function FromEditFieldValueChanged(app, event)
            za = app.FromEditField.Value;
            zb = app.toEditField.Value;
            dz = app.SaveeveryEditField.Value;
            z = app.domain_size(app.direction);
            if za>=zb || zb>z || dz>=(zb-za+1)
                app.FromEditField.Value = 1;
                app.toEditField.Value = z;
            end            
        end

        % Button pushed function: SavevideoButton
        function SavevideoButtonPushed(app, event)
            video_format = app.VideoformatDropDown.Value;
            video_handle = VideoWriter([app.savefolder app.FilenameEditField.Value],video_format);
            if strcmp(video_format,'mpeg-4')
                set(video_handle,'Quality',100); % Set video quality
            end
            % Set video framerate
            set(video_handle,'FrameRate',app.FramepersEditField.Value);
            % Open video
            open(video_handle)
            
            % Video ROI
            pos = app.DisplayPanel.Position;
            rect = [0 0 pos(1) app.SegmentationvisualizationUIFigure.Position(4)];

            app.SeparatorSlider.Visible = false;
            app.SeparatorLabel.Visible = false;
            app.ShowlineCheckBox.Visible = false;

            if app.WSepCheckBox.Value
                sepa = 1;
                sepb = app.SeparatorSlider.Limits(2);
                dsep = app.SaveeveryEditField.Value;
                seps = unique(round(linspace(sepa,sepb, (sepb-sepa)/dsep+1 )));   
                seps = flip(seps);
                frame_number = 0;

                pause(0.1);
                for ksep=1:1:length(seps)
                    frame_number = frame_number+1;
                    % Update figure
                    app.valuesep = seps(ksep);
                    app.func_update_slices;
                    app.func_update_axis;
                    % Save frame
                    stored_frame(frame_number) = getframe(app.SegmentationvisualizationUIFigure,rect);
                    writeVideo(video_handle,stored_frame(frame_number))
                    pause(0.01);
                end
                app.SeparatorSlider.Value = app.valuesep;
                app.SeparatorSlider.Visible = true; 
                app.SeparatorLabel.Visible = true;
                app.ShowlineCheckBox.Visible = true;      
            elseif app.WchannelCheckBox.Value
                cha = 1;
                chb = app.ChannelSlider.Limits(2);
                dcg = app.SaveeveryEditField.Value;
                chs = unique(round(linspace(cha,chb, (chb-cha)/dcg+1 )));
                frame_number = 0;

                pause(0.1);
                for kch=1:1:length(chs)
                    frame_number = frame_number+1;
                    % Update figure
                    app.ChannelSlider.Value = chs(kch);
                    app.ChannelSliderValueChanged;
                    % Save frame
                    stored_frame(frame_number) = getframe(app.SegmentationvisualizationUIFigure,rect);
                    writeVideo(video_handle,stored_frame(frame_number))
                    pause(0.01);
                end
                if strcmp(app.ComparisonDropDown.Value,'Separator')
                    app.SeparatorSlider.Visible = true;
                    app.SeparatorLabel.Visible = true;
                    app.ShowlineCheckBox.Visible = true;
                end
            else
                za = app.FromEditField.Value;
                zb = app.toEditField.Value;
                dz = app.SaveeveryEditField.Value;
                zs = unique(round(linspace(za,zb, (zb-za)/dz+1 ))); 
                frame_number = 0;

                pause(0.1);
                for kz=1:1:length(zs)
                    frame_number = frame_number+1;
                    % Update figure
                    app.position_slice = zs(kz);
                    app.Slider.Value = zs(kz);
                    app.func_update_slices;
                    app.func_update_axis;
                    % Save frame
                    stored_frame(frame_number) = getframe(app.SegmentationvisualizationUIFigure,rect);
                    writeVideo(video_handle,stored_frame(frame_number))
                    pause(0.01);
                end
                if strcmp(app.ComparisonDropDown.Value,'Separator')
                    app.SeparatorSlider.Visible = true;
                    app.SeparatorLabel.Visible = true;
                    app.ShowlineCheckBox.Visible = true;
                end
            end

            % Close video
            close(video_handle)
        end

        % Value changing function: SeparatorSlider
        function SeparatorSliderValueChanged(app, event)
            %value = app.SeparatorSlider.Value;
            app.valuesep = round(event.Value);
            app.func_update_slices;
            app.func_update_axis;
        end

        % Value changed function: SeparatorSlider
        function SeparatorSliderValueChanged2(app, event)
            app.valuesep = app.SeparatorSlider.Value;
            app.func_update_slices;
            app.func_update_axis;            
        end

        % Value changed function: WSepCheckBox
        function WSepCheckBoxValueChanged(app, event)
            value = app.WSepCheckBox.Value;
            if value
                app.FromEditField.Enable = false;
                app.toEditField.Enable = false;
                app.sliceLabel.Text = 'line'; 
                app.WchannelCheckBox.Value = false;
                app.SelectfolderButton.Enable = 'on';
                app.SavevideoButton.Enable = 'on';
            else
                app.FromEditField.Enable = true;
                app.toEditField.Enable = true;                
                app.sliceLabel.Text = 'slice';
            end 
        end

        % Value changed function: ChannelSlider
        function ChannelSliderValueChanged(app, event)
            value = round(app.ChannelSlider.Value);
            app.ChannelSlider.Value = value;
            if app.imds.loadallatonce
                app.segmentedvolume = app.imds.Data.Files(value).M;
            else
                if app.imds.loadallatonce
                    app.segmentedvolume = app.imds.Data.Files(value).M;
                else
                    [app.segmentedvolume, ~] = function_load_tif(char(app.imds.Files(value)), 'uint16' ); % Load new volume
                end
            end

            [~,app.segmentedname,~] = fileparts(char(app.imds.Files(value)));
            app.segmentedname = strrep(app.segmentedname,'_',' ');
            if app.isgroundtruth
                app.differencevolume = zeros(app.domain_size);
                app.differencevolume(app.greyvolume~=app.segmentedvolume)=1;
            end
            % Table
            app.volumefraction=zeros(app.number_phase,1);
            for k=1:1:app.number_phase
                app.volumefraction(k,1)=sum(sum(sum(app.segmentedvolume==app.phases_id(k))))/numel(app.segmentedvolume);
                if k==app.number_phase && ~isempty(app.imds)
                    app.Phase(k).name='Region with low confidence';
                else
                    app.Phase(k).name=['Phase ' num2str(app.phases_id(k))];
                end
                s = uistyle('BackgroundColor',app.color_phase(k,:));
                addStyle(app.UITable,s,'cell',[k,3]);                
            end
            app.UITable.Data = [num2cell(app.phases_id) num2cell(app.volumefraction) cell(app.number_phase,1) {app.Phase.name}'];

            app.func_update_slices;
            app.func_update_axis;
        end

        % Button pushed function: Channel_plus_Button
        function Channel_plus_ButtonPushed(app, event)
            value = round(app.ChannelSlider.Value);
            value_new = min([value+1 app.ChannelSlider.Limits(2)]);
            if value_new~=value
                app.ChannelSlider.Value = value_new;
                app.ChannelSliderValueChanged;
            end
        end

        % Button pushed function: Channel_minus_Button
        function Channel_minus_ButtonPushed(app, event)
            value = round(app.ChannelSlider.Value);
            value_new = max([value-1 app.ChannelSlider.Limits(1)]);
            if value_new~=value
                app.ChannelSlider.Value = value_new;
                app.ChannelSliderValueChanged;
            end
        end

        % Value changed function: Channel_ViewDropDown
        function Channel_ViewDropDownValueChanged(app, event)
            value = app.Channel_ViewDropDown.Value;

            if strcmp(app.ComparisonDropDown.Value,'Separator') || strcmp(app.Channel_ViewDropDown.Value,'Probability')
                app.DisplayDropDown.Items = {'Separated plots'};
                app.DisplayDropDown.Value = app.DisplayDropDown.Items(1);
            else
                app.DisplayDropDown.Items = {'Separated plots','Juxtaposed plots'};
            end

            if strcmp(value,'Per channel')
                app.UITable.Tooltip = 'Volume fractions for current channel';
                app.UITable.Enable = 'on';
                app.Channel_minus_Button.Enable = true;
                app.Channel_plus_Button.Enable = true;                
                app.ChannelSlider.Enable = true;
                app.ChannelSliderValueChanged;
                app.PlotStatsButton.Enable = false;
                app.ShowlineCompositeCheckBox.Enable = false;
                app.ExportProbButton.Enable = false;
                app.ProbabilitycolormapDropDown.Enable = false; app.Prob_ColorbarCheckBox.Enable = false;       
                app.ProbabilitycolormapDropDownLabel.Enable = false;
                app.ThresholdEditField.Enable = false; app.ThresholdEditFieldLabel.Enable = false;

                app.FirstplotDropDown.Items = {app.str_grey, 'Segmented',  'Comparison'};
                app.SecondplotDropDown.Items = {app.str_grey, 'Segmented', 'Comparison', 'None'};
                app.ThirdplotDropDown.Items = {app.str_grey, 'Segmented', 'Comparison', 'None'};
                app.FirstplotDropDown.Value = app.FirstplotDropDown.Items(1);
                app.SecondplotDropDown.Value = app.SecondplotDropDown.Items(2);
                app.ThirdplotDropDown.Value = app.ThirdplotDropDown.Items(3);
                app.FirstplotDropDownValueChanged;
                app.WchannelCheckBox.Enable = true;

            elseif strcmp(value,'Composite')
                app.UITable.Tooltip = 'Not applicable';
                app.UITable.Enable = 'off';
                app.PleasewaitLabel.Visible = 'on'; pause (0.01);
                app.Channel_minus_Button.Enable = false;
                app.Channel_plus_Button.Enable = false;
                app.ChannelSlider.Enable = false;
                app.PlotStatsButton.Enable = false;
                app.ShowlineCompositeCheckBox.Enable = true;
                app.ExportProbButton.Enable = false;
                app.ProbabilitycolormapDropDown.Enable = false; app.Prob_ColorbarCheckBox.Enable = false; 
                app.ProbabilitycolormapDropDownLabel.Enable = false;
                app.ThresholdEditField.Enable = false; app.ThresholdEditFieldLabel.Enable = false;
                app.WchannelCheckBox.Enable = false;
                app.segmentedname ='Composite';
                app.segmentedvolume = zeros(app.domain_size);

                app.FirstplotDropDown.Items = {app.str_grey, 'Segmented',  'Comparison'};
                app.SecondplotDropDown.Items = {app.str_grey, 'Segmented', 'Comparison', 'None'};
                app.ThirdplotDropDown.Items = {app.str_grey, 'Segmented', 'Comparison', 'None'};
                app.FirstplotDropDown.Value = app.FirstplotDropDown.Items(1);
                app.SecondplotDropDown.Value = app.SecondplotDropDown.Items(2);
                app.ThirdplotDropDown.Value = app.ThirdplotDropDown.Items(3);

                if length(app.domain_size)>2
                    app.ViewnormaltoDropDownValueChanged;
                else
                    app.segmentedvolume = zeros(app.domain_size);
                    x = round(linspace(1,app.domain_size(1),length(app.imds.Files)+1));
                    x0 = x(1:end-1); x1 = x(2:end)-1; x1(end)=x1(end)+1;
                    y = round(linspace(1,app.domain_size(2),length(app.imds.Files)+1));
                    y0 = y(1:end-1); y1 = y(2:end)-1; y1(end)=y1(end)+1;

                    for k=1:1:length(app.imds.Files)
                        app.ChannelSlider.Value = k; pause(0.01);
                        if app.imds.loadallatonce
                            tmp_segmented = app.imds.Data.Files(k).M;
                        else
                            [tmp_segmented, ~] = function_load_tif(char(app.imds.Files(k)), 'uint16' );
                        end

                        if app.domain_size(1)>=app.domain_size(2)
                            app.segmentedvolume(x0(k):x1(k),:) = tmp_segmented(x0(k):x1(k),:);
                        else
                            app.segmentedvolume(:,y0(k):y1(k)) = tmp_segmented(:,y0(k):y1(k));
                        end
                        if app.ShowlineCompositeCheckBox.Value && k<length(app.imds.Files)
                            if app.domain_size(1)>=app.domain_size(2)
                                app.segmentedvolume(x1(k),:,:) = max(app.channel_ids)+1;
                            else
                                app.segmentedvolume(:,y1(k),:) = max(app.channel_ids)+1;
                            end
                        end
                    end

                    if app.isgroundtruth
                        app.differencevolume = zeros(app.domain_size);
                        app.differencevolume(app.greyvolume~=app.segmentedvolume)=1;
                    end

                end
                app.PleasewaitLabel.Visible = 'off'; pause (0.01);
                app.FirstplotDropDownValueChanged;

            elseif strcmp(value,'Probability')
                app.Channel_minus_Button.Enable = false;
                app.Channel_plus_Button.Enable = false;
                app.ChannelSlider.Enable = false;
                app.ShowlineCompositeCheckBox.Enable = false;
                app.segmentedvolume = zeros(app.domain_size);       
                app.PlotStatsButton.Enable = true;
                app.ExportProbButton.Enable = true;
                app.ProbabilitycolormapDropDown.Enable = true; app.Prob_ColorbarCheckBox.Enable = true;
                app.ProbabilitycolormapDropDownLabel.Enable = true;
                app.ThresholdEditField.Enable = true; app.ThresholdEditFieldLabel.Enable = true;
                app.WchannelCheckBox.Enable = false;
                app.PleasewaitLabel.Visible = 'on'; pause (0.01);

                if isempty(app.probability_perphase) % Only calculate this one time
                    
                    sz = app.domain_size;
                    n_ids = length(app.channel_ids);
                    sz = [sz n_ids];
                    app.probability_perphase = zeros(sz);
                    for k=1:1:length(app.imds.Files)
                        app.ChannelSlider.Value = k; pause(0.01);
                        if app.imds.loadallatonce
                            tmp_segmented = app.imds.Data.Files(k).M;
                        else
                            [tmp_segmented, ~] = function_load_tif(char(app.imds.Files(k)), 'uint16' );
                        end   

                        for kid = 1:1:n_ids
                            idx = tmp_segmented == app.channel_ids(kid);
                            tmp = zeros(app.domain_size);
                            tmp(idx)=1;
                            app.probability_perphase(:,:,:,kid) = app.probability_perphase(:,:,:,kid) + tmp;
                        end
                    end
                    % Normalized
                    app.probability_perphase = app.probability_perphase/length(app.imds.Files);
                    % Global probability
                    [app.probability_global, M] = max(app.probability_perphase,[],4);
                    % Phases with higher probability
                    app.PhaseMaxprobability = zeros(app.domain_size);
                    for kid = 1:1:n_ids
                        app.PhaseMaxprobability(M==kid) = app.channel_ids(kid);
                    end

                    % Phases with high probability
                    app.ThresholdEditFieldValueChanged
                end

                % Table
                app.UITable.Tooltip = 'Volume fractions for phases with: higher probability / high probability (> threshold) and labelled with low confidence otherwise / low probability phase (<= threshold) re-assigned to nearest high probability (> threshold) phase';
                app.volumefraction=zeros(app.number_phase,1);
                vol = cell(app.number_phase,1);
                for k=1:1:app.number_phase
                    v1=sum(sum(sum(app.PhaseMaxprobability==app.phases_id(k))))/numel(app.segmentedvolume);
                    v2=sum(sum(sum(app.PhaseHighprobability==app.phases_id(k))))/numel(app.segmentedvolume);
                    v3=sum(sum(sum(app.PhaseHighprobabilityReassigned==app.phases_id(k))))/numel(app.segmentedvolume);
                    vol(k) = {[num2str(v1,'%1.4f') ' / ' num2str(v2,'%1.4f') ' / ' num2str(v3,'%1.4f')]};
                    if k==app.number_phase && ~isempty(app.imds)
                        app.Phase(k).name='Region with low confidence';
                    else
                        app.Phase(k).name=['Phase ' num2str(app.phases_id(k))];
                    end
                    s = uistyle('BackgroundColor',app.color_phase(k,:));
                    addStyle(app.UITable,s,'cell',[k,3]);
                end
                app.UITable.Data = [num2cell(app.phases_id) vol cell(app.number_phase,1) {app.Phase.name}'];
                app.UITable.Enable = 'on';

                app.PleasewaitLabel.Visible = 'off'; pause (0.01);
                app.FirstplotDropDown.Items = {app.str_grey, 'Probability map', 'Phase with higher probability' 'Phase with high probability' 'Phase with low confidence voxels assigned to nearest high confidence phase'};
                app.SecondplotDropDown.Items = {app.str_grey, 'Probability map', 'Phase with higher probability' 'Phase with high probability' 'Phase with low confidence voxels assigned to nearest high confidence phase'};
                app.ThirdplotDropDown.Items = {'Probability map', 'Phase with higher probability' 'Phase with high probability' 'Phase with low confidence voxels assigned to nearest high confidence phase'};                
                for kid = 1:1:length(app.channel_ids)-1
                    c={['Probability of phase ' num2str(app.channel_ids(kid),'%i')]};
                    app.FirstplotDropDown.Items = [app.FirstplotDropDown.Items c];
                    app.SecondplotDropDown.Items = [app.SecondplotDropDown.Items c];
                    app.ThirdplotDropDown.Items = [app.ThirdplotDropDown.Items c];
                end
                app.SecondplotDropDown.Items = [app.SecondplotDropDown.Items {'None'}];
                app.ThirdplotDropDown.Items = [app.ThirdplotDropDown.Items {'Comparison'} {'None'}];    
                app.FirstplotDropDown.Value = app.FirstplotDropDown.Items(1);
                app.SecondplotDropDown.Value = app.SecondplotDropDown.Items(2);
                app.ThirdplotDropDown.Value = app.ThirdplotDropDown.Items(3);          
                app.FirstplotDropDownValueChanged;
            end


        end

        % Button pushed function: PlotStatsButton
        function PlotStatsButtonPushed(app, event)
            % Probability distribution per phase
            nbins = length(app.imds.Files)+1;
            y = zeros(nbins, length(app.channel_ids)-1);
            for kid = 1:1:length(app.channel_ids)-1
                tmp = round(app.probability_perphase(:,:,:,kid)*length(app.imds.Files));
                for kbin=1:1:nbins
                    y(kbin,kid) = sum(sum(sum( tmp==kbin-1  )));
                end             
            end
            y = y/numel(app.greyvolume); % Nornalized
            x = (0:1:length(app.imds.Files))/length(app.imds.Files);
            Fig = figure;
            Fig.Name= 'Phase occurence';
            Fig.Color = 'w';
            ax_ = axes('Parent',Fig);
            hold(ax_,'on');
            b=bar(x,y,'BarWidth',1.0);
            for kid = 1:1:length(app.channel_ids)-1
                set(b(kid),'DisplayName',['Phase ' num2str(app.phases_id(kid))]);
            end
            set(ax_,'XTick',x,'FontName','Times new roman','FontSize',14);
            ylabel('Occurence (normalized)')
            xlabel('Probability')
            grid(ax_,'on');
            legend(ax_,'show');

            % Probability along direction
            number_dimension = length(app.domain_size);
            for d=1:1:number_dimension
                for k=1:1:app.domain_size(d)
                    if d==1
                        tmp = reshape(app.probability_global(k,:,:),[1 numel(app.probability_global(k,:,:))]);
                    elseif d==2
                        tmp = reshape(app.probability_global(:,k,:),[1 numel(app.probability_global(:,k,:))]);
                    else
                        tmp = reshape(app.probability_global(:,k,:),[1 numel(app.probability_global(:,k,:))]);
                    end
                    res.dir(d).global_mean(k) = mean(tmp);
                    %res.dir(d).global_std(k) = std(tmp);

                    % for kid = 1:1:length(app.channel_ids)-1
                    %     if d==1
                    %         tmp = reshape(app.probability_perphase(k,:,:,kid),[1 numel(app.probability_perphase(k,:,:,kid))]);
                    %     elseif d==2
                    %         tmp = reshape(app.probability_perphase(:,k,:,kid),[1 numel(app.probability_perphase(:,k,:,kid))]);
                    %     else
                    %         tmp = reshape(app.probability_perphase(:,k,:,kid),[1 numel(app.probability_perphase(:,k,:,kid))]);
                    %     end
                    %     res.dir(d).phase(kid).mean(k) = mean(tmp);
                    %     %res.dir(d).phase(kid).std(k) = std(tmp);
                    % end
                end
            end
            
            scrsz = get(0,'ScreenSize'); % Screen resolution
            Fig = figure; % Create figure
            Fig.Name= 'Probability'; % Figure name
            Fig.Color='white'; % Background colour
            set(Fig,'position',[scrsz(1) scrsz(2) scrsz(3)*number_dimension/3 scrsz(4)*1/2]);
            id_axe = 0;
            for kprob=1:1:1
                for current_direction=1:1:number_dimension % Iterate over axe
                    x_ = (1:1:app.domain_size(current_direction))*app.voxel_size;
                    id_axe = id_axe+1;
                    sub_axes=subplot(1,number_dimension,id_axe,'Parent',Fig);
                    hold(sub_axes,'on'); % Active subplot
                    if kprob==1
                        h_title=title(['Higher probability along axe ' num2str(current_direction,'%i')]);
                        plot(x_,res.dir(current_direction).global_mean,'Color','k','LineWidth',2)
                        %std_min = (res.dir(current_direction).global_mean - res.dir(current_direction).global_std);
                        %std_max = (res.dir(current_direction).global_mean + res.dir(current_direction).global_std);
                        %x2 = [x_, fliplr(x_)];
                        %inBetween = [std_min, fliplr(std_max)];
                        %h_=fill(x2, inBetween, 'k');
                        %set(h_,'LineStyle','none','FaceAlpha',0.25);
                    else
                        %h_title=title(['Probability per phase along axe ' num2str(current_direction,'%i')]);
                        %for kid = 1:1:length(app.channel_ids)-1
                        %    plot(x_,res.dir(current_direction).phase(kid).mean,'Color','k','LineWidth',2)
                        %end
                    end
                    xlabel(['Position along axe ' num2str(current_direction,'%i') ' (' app.voxel_unit ')']);
                    ylabel('Probability (average)');
                    
                    % - Grid
                    grid(sub_axes,'on'); % Display grid
                    set(sub_axes,'FontName','Times new roman','FontSize',12); % Fontname and fontsize
                    h_title.FontSize = 14; % Set title fontsize
                    hold(sub_axes,'off'); % Relase figure
                end
            end
            sgtitle(Fig,'Higher probability among phases averaged per slice','FontWeight','bold','FontSize',16,'FontName','Times new roman');

            % Grey level - probability map
            unique_grey = unique(app.greyvolume);
            unique_prob = (0:1:length(app.imds.Files))/length(app.imds.Files);
            unique_prob = round(unique_prob,3);
            if isempty(app.probmap_global)
                h_waitbar = waitbar(0,'Please wait');
                n_grey = length(unique_grey);
                n_prob = length(unique_prob);
                app.probmap_global = zeros(n_prob,n_grey);
                app.probmap_local = zeros(n_prob,n_grey);
                n_global = numel(app.greyvolume);
                for kgrey=1:1:n_grey
                    waitbar(kgrey/n_grey,h_waitbar); pause(.001);
                    idg = find(app.greyvolume==unique_grey(kgrey));
                    probvals = round(app.probability_global(idg),3);
                    n_local = length(idg);
                    for kprob = 1:1:n_prob
                        s = sum( probvals==unique_prob(kprob) );
                        app.probmap_global(kprob,kgrey) = s/n_global;
                        app.probmap_local(kprob,kgrey) = s/n_local;
                    end
                end
                delete(h_waitbar);
            end

            Fig = figure;
            Fig.Name= 'Grey level - probability map (global)'; % Figure name
            Fig.Color='white'; % Background colour
            imagesc(unique_grey,unique_prob,app.probmap_global)
            [t,s] = title('Grey level - probability map (global)','Probability sum is 1','Color','k');
            t.FontSize = 16; t.FontName = 'Helvetica'; s.FontAngle = 'italic'; s.FontSize = 14;   
            xlabel('Grey level','FontSize',12,'FontName','Helvetica');
            ylabel('Proabability','FontSize',12,'FontName','Helvetica');
            colormap(char(app.ProbabilitycolormapDropDown.Value));
            colorbar;

            Fig = figure;
            Fig.Name= 'Grey level - probability map (local)'; % Figure name
            Fig.Color='white'; % Background colour
            imagesc(unique_grey,unique_prob,app.probmap_local)
            [t,s] = title('Grey level - probability map (local)','Probability sum per grey level is 1','Color','k');
            t.FontSize = 16; t.FontName = 'Helvetica'; s.FontAngle = 'italic'; s.FontSize = 14;            
            xlabel('Grey level','FontSize',12,'FontName','Helvetica');
            ylabel('Proabability','FontSize',12,'FontName','Helvetica');
            colormap(char(app.ProbabilitycolormapDropDown.Value));
            colorbar;            
        end

        % Value changed function: WchannelCheckBox
        function WchannelCheckBoxValueChanged(app, event)
            value = app.WchannelCheckBox.Value;
            if value
                app.FromEditField.Enable = false;
                app.toEditField.Enable = false;
                app.sliceLabel.Text = 'channel';   
                app.WSepCheckBox.Value = false;
            else
                app.FromEditField.Enable = true;
                app.toEditField.Enable = true;                
                app.sliceLabel.Text = 'slice';
            end 
        end

        % Value changed function: ShowlineCompositeCheckBox
        function ShowlineCompositeCheckBoxValueChanged(app, event)
            app.Channel_ViewDropDownValueChanged;            
        end

        % Value changed function: Grey_ColorbarCheckBox, 
        % ...and 4 other components
        function GreycolormapDropDownValueChanged(app, event)
            app.func_update_axis;
        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            threshold = app.ThresholdEditField.Value;
            % Keep phases with high probability
            app.PhaseHighprobability = app.PhaseMaxprobability;
            idx = find(app.probability_global<=threshold);
            app.PhaseHighprobability(idx)= max(app.channel_ids);

            % Reassigned;
            BW = ones(app.domain_size);
            BW(idx) = 0;
            [~,idx2] = bwdist(BW);
            app.PhaseHighprobabilityReassigned = app.PhaseHighprobability;
            app.PhaseHighprobabilityReassigned(idx) = app.PhaseHighprobability(idx2(idx));

           app.FirstplotDropDownValueChanged;
        end

        % Value changed function: ComparisonDropDown
        function ComparisonDropDownValueChanged(app, event)
            if strcmp(app.ComparisonDropDown.Value,'Separator') || strcmp(app.Channel_ViewDropDown.Value,'Probability')
                app.DisplayDropDown.Items = {'Separated plots'};
                app.DisplayDropDown.Value = app.DisplayDropDown.Items(1);
            else
                app.DisplayDropDown.Items = {'Separated plots','Juxtaposed plots'};
            end
            app.FirstplotDropDownValueChanged
        end

        % Value changed function: DisplayDropDown
        function DisplayDropDownValueChanged(app, event)
            value = app.DisplayDropDown.Value;
            if strcmp(value,'Juxtaposed plots')
                if app.isgroundtruth
                    app.ComparisonDropDown.Items = {'Overlay','Checkerboard','Difference'};
                else
                    app.ComparisonDropDown.Items = {'Overlay','Checkerboard'};
                end
            else
                if app.isgroundtruth
                    app.ComparisonDropDown.Items = {'Overlay','Checkerboard','Separator','Difference'};
                else
                    app.ComparisonDropDown.Items = {'Overlay','Checkerboard','Separator'};
                end
            end
            app.FirstplotDropDownValueChanged
        end

        % Button pushed function: ExportProbButton
        function ExportProbButtonPushed(app, event)
            if isempty(app.savefolder)
                if ispc
                    mainfolder=winqueryreg('HKEY_CURRENT_USER', 'Software\Microsoft\Windows\CurrentVersion\Explorer\Shell Folders', 'Desktop'); % Find desktop folder of windows user
                    separator = '\';
                else
                    mainfolder = pwd;
                    separator = '/';
                end
                mainfolder = [mainfolder separator];
            else
                mainfolder = app.savefolder;
            end
            fprintf('Probabilities exported in %s\n',mainfolder)
            function_save_tif(uint8(app.probability_global*length(app.imds.Files)),[mainfolder 'Probability.tiff']);
            function_save_tif(uint8(app.PhaseMaxprobability),[mainfolder 'Phase_with_higherProbability.tiff']);
            function_save_tif(uint8(app.PhaseHighprobability),[mainfolder 'Phase_with_Probability_higherthanthreshold.tiff']);
            function_save_tif(uint8(app.PhaseHighprobabilityReassigned),[mainfolder 'Phase_with_low_confidence_voxels_assigned_to_nearest_high_confidence_phase.tiff']);
            for kid = 1:1:length(app.channel_ids)-1
                function_save_tif(uint8(app.probability_perphase(:,:,:,kid)*length(app.imds.Files)),[mainfolder 'Probability_phase' num2str(app.channel_ids(kid),'%i') '.tiff']);                
            end
            if ~isempty(app.probmap_global)
                probmap_global = app.probmap_global;
                probmap_local = app.probmap_local;
                save([mainfolder 'Probability map.mat'],'probmap_global','probmap_local');
            end 
        end

        % Button pushed function: PlotStatsDifferenceButton
        function PlotStatsDifferenceButtonPushed(app, event)
            phases = unique([unique(app.greyvolume); unique(app.segmentedvolume)]);
            n_phases = length(phases);
            diff_matrix = zeros(n_phases,n_phases);
            for kg=1:1:n_phases
                kg_array = app.greyvolume==phases(kg);
                n_ref = sum(sum(sum( kg_array )));
                for ks=1:1:n_phases
                    ks_array = app.segmentedvolume==phases(ks);
                    diff_matrix(kg,ks) = sum(sum(sum( kg_array.*ks_array )))/n_ref;
                end
            end
            Fig = figure;
            Fig.Name= 'Phase assignment'; % Figure name
            Fig.Color='white'; % Background colour
            h = heatmap(phases,phases,diff_matrix);
            h.Title = {'Phase assignment','i=j: ratio of i correctly assigned, i≠j: ratio of i incorreclty assigned to j'};
            h.YLabel = 'label i (ground truth)';
            h.XLabel = 'label j (segmented)';            
            colorbar;    
        end

        % Close request function: SegmentationvisualizationUIFigure
        function SegmentationvisualizationUIFigureCloseRequest(app, event)
            if app.manual_segmentation
                delete(app.DialogApp)
            end
            delete(app)            
        end

        % Button pushed function: Sep_HorizontalButton
        function Sep_HorizontalButtonPushed(app, event)
            if strcmp(app.Sep_HorizontalButton.Text,'Horizontal')
                app.Sep_HorizontalButton.Text = 'Vertical';
            elseif strcmp(app.Sep_HorizontalButton.Text,'Vertical')
                app.Sep_HorizontalButton.Text = 'Horizontal';
            end     
            app.func_update_axis;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SegmentationvisualizationUIFigure = uifigure('Visible', 'off');
            app.SegmentationvisualizationUIFigure.Name = 'Segmentation visualization';
            app.SegmentationvisualizationUIFigure.CloseRequestFcn = createCallbackFcn(app, @SegmentationvisualizationUIFigureCloseRequest, true);
            app.UIAxes1 = uiaxes(app.SegmentationvisualizationUIFigure);
            title(app.UIAxes1, 'Title')
            xlabel(app.UIAxes1, 'X')
            ylabel(app.UIAxes1, 'Y')
            zlabel(app.UIAxes1, 'Z')
            app.UIAxes2 = uiaxes(app.SegmentationvisualizationUIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.SegmentationvisualizationUIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.DisplayPanel = uipanel(app.SegmentationvisualizationUIFigure);
            app.DisplayPanel.Title = 'Display';
            app.FirstplotDropDownLabel = uilabel(app.DisplayPanel);
            app.FirstplotDropDownLabel.Text = 'First plot';
            app.FirstplotDropDown = uidropdown(app.DisplayPanel);
            app.FirstplotDropDown.Items = {'Grey level or ground truth', 'Segmented', 'Comparison'};
            app.FirstplotDropDown.ValueChangedFcn = createCallbackFcn(app, @FirstplotDropDownValueChanged, true);
            app.FirstplotDropDown.Value = 'Grey level or ground truth';
            app.SecondplotDropDownLabel = uilabel(app.DisplayPanel);
            app.SecondplotDropDownLabel.Text = 'Second plot';
            app.SecondplotDropDown = uidropdown(app.DisplayPanel);
            app.SecondplotDropDown.Items = {'Grey level or ground truth', 'Segmented', 'Comparison', 'None'};
            app.SecondplotDropDown.ValueChangedFcn = createCallbackFcn(app, @FirstplotDropDownValueChanged, true);
            app.SecondplotDropDown.Value = 'Grey level or ground truth';
            app.ThirdplotDropDownLabel = uilabel(app.DisplayPanel);
            app.ThirdplotDropDownLabel.Text = 'Third plot';
            app.ThirdplotDropDown = uidropdown(app.DisplayPanel);
            app.ThirdplotDropDown.Items = {'Grey level or ground truth', 'Segmented', 'Comparison', 'None'};
            app.ThirdplotDropDown.ValueChangedFcn = createCallbackFcn(app, @FirstplotDropDownValueChanged, true);
            app.ThirdplotDropDown.Value = 'Grey level or ground truth';
            app.DisplayDropDownLabel = uilabel(app.DisplayPanel);
            app.DisplayDropDownLabel.Text = 'Display';
            app.DisplayDropDown = uidropdown(app.DisplayPanel);
            app.DisplayDropDown.Items = {'Separated plots', 'Juxtaposed plots'};
            app.DisplayDropDown.ValueChangedFcn = createCallbackFcn(app, @DisplayDropDownValueChanged, true);
            app.DisplayDropDown.Value = 'Separated plots';
            app.ComparisonDropDownLabel = uilabel(app.DisplayPanel);
            app.ComparisonDropDownLabel.Text = 'Comparison';
            app.ComparisonDropDown = uidropdown(app.DisplayPanel);
            app.ComparisonDropDown.Items = {'Overlay', 'Checkerboard', 'Separator'};
            app.ComparisonDropDown.ValueChangedFcn = createCallbackFcn(app, @ComparisonDropDownValueChanged, true);
            app.ComparisonDropDown.Value = 'Overlay';
            app.OverlayEditField_2Label = uilabel(app.DisplayPanel);
            app.OverlayEditField_2Label.Text = 'Overlay';
            app.OverlayEditField = uieditfield(app.DisplayPanel, 'numeric');
            app.OverlayEditField.Limits = [0 1];
            app.OverlayEditField.ValueChangedFcn = createCallbackFcn(app, @FirstplotDropDownValueChanged, true);
            app.OverlayEditField.Value = 0.25;
            app.GridnumberEditFieldLabel = uilabel(app.DisplayPanel);
            app.GridnumberEditFieldLabel.Text = 'Grid number';
            app.GridnumberEditField = uieditfield(app.DisplayPanel, 'numeric');
            app.GridnumberEditField.Limits = [2 Inf];
            app.GridnumberEditField.RoundFractionalValues = 'on';
            app.GridnumberEditField.ValueChangedFcn = createCallbackFcn(app, @FirstplotDropDownValueChanged, true);
            app.GridnumberEditField.Enable = 'off';
            app.GridnumberEditField.Value = 8;
            app.LayoutDropDownLabel = uilabel(app.DisplayPanel);
            app.LayoutDropDownLabel.Text = 'Layout';
            app.LayoutDropDown = uidropdown(app.DisplayPanel);
            app.LayoutDropDown.Items = {'Horizontal', 'Vertical', 'T'};
            app.LayoutDropDown.ValueChangedFcn = createCallbackFcn(app, @FirstplotDropDownValueChanged, true);
            app.LayoutDropDown.Value = 'Horizontal';
            app.RotateButton = uibutton(app.DisplayPanel, 'state');
            app.RotateButton.ValueChangedFcn = createCallbackFcn(app, @FirstplotDropDownValueChanged, true);
            app.RotateButton.Text = 'Rotate';
            app.FlipYaxisCheckBox = uicheckbox(app.DisplayPanel);
            app.FlipYaxisCheckBox.ValueChangedFcn = createCallbackFcn(app, @FirstplotDropDownValueChanged, true);
            app.FlipYaxisCheckBox.Text = 'Flip Y-axis';
            app.PlotStatsDifferenceButton = uibutton(app.DisplayPanel, 'push');
            app.PlotStatsDifferenceButton.ButtonPushedFcn = createCallbackFcn(app, @PlotStatsDifferenceButtonPushed, true);
            app.PlotStatsDifferenceButton.Enable = 'off';
            app.PlotStatsDifferenceButton.Visible = 'off';
            app.PlotStatsDifferenceButton.Text = 'Plot Stats';
            app.ViewPanel = uipanel(app.SegmentationvisualizationUIFigure);
            app.ViewPanel.Title = 'View';
            app.Slider = uislider(app.ViewPanel);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Button_m = uibutton(app.ViewPanel, 'push');
            app.Button_m.ButtonPushedFcn = createCallbackFcn(app, @Button_mPushed, true);
            app.Button_m.Text = '-';
            app.ViewnormaltoDropDownLabel = uilabel(app.ViewPanel);
            app.ViewnormaltoDropDownLabel.Text = 'View normal to ';
            app.ViewnormaltoDropDown = uidropdown(app.ViewPanel);
            app.ViewnormaltoDropDown.Items = {'axis 1', 'axis 2', 'axis 3'};
            app.ViewnormaltoDropDown.ValueChangedFcn = createCallbackFcn(app, @ViewnormaltoDropDownValueChanged, true);
            app.ViewnormaltoDropDown.Value = 'axis 1';
            app.Button_mm = uibutton(app.ViewPanel, 'push');
            app.Button_mm.ButtonPushedFcn = createCallbackFcn(app, @Button_mmPushed, true);
            app.Button_mm.Text = '--';
            app.Button_p = uibutton(app.ViewPanel, 'push');
            app.Button_p.ButtonPushedFcn = createCallbackFcn(app, @Button_pPushed, true);
            app.Button_p.Text = '+';
            app.Button_pp = uibutton(app.ViewPanel, 'push');
            app.Button_pp.ButtonPushedFcn = createCallbackFcn(app, @Button_ppPushed, true);
            app.Button_pp.Text = '++';
            app.AdjustviewasmovingsliderCheckBox = uicheckbox(app.ViewPanel);
            app.AdjustviewasmovingsliderCheckBox.Text = 'Adjust view as moving slider';
            app.ROIxLabel = uilabel(app.ViewPanel);
            app.ROIxLabel.Text = 'ROI horizontal';
            app.ROI_x_a_EditField = uieditfield(app.ViewPanel, 'numeric');
            app.ROI_x_a_EditField.Limits = [1 Inf];
            app.ROI_x_a_EditField.RoundFractionalValues = 'on';
            app.ROI_x_a_EditField.ValueChangedFcn = createCallbackFcn(app, @ROIxEditFieldValueChanged, true);
            app.ROI_x_a_EditField.Value = 1;
            app.ROI_x_b_EditField = uieditfield(app.ViewPanel, 'numeric');
            app.ROI_x_b_EditField.Limits = [2 Inf];
            app.ROI_x_b_EditField.RoundFractionalValues = 'on';
            app.ROI_x_b_EditField.ValueChangedFcn = createCallbackFcn(app, @ROIxEditFieldValueChanged, true);
            app.ROI_x_b_EditField.Value = 2;
            app.ApplyButton = uibutton(app.ViewPanel, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.ResetButton = uibutton(app.ViewPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ROIverticalLabel = uilabel(app.ViewPanel);
            app.ROIverticalLabel.Text = 'ROI vertical';
            app.ROI_y_a_EditField = uieditfield(app.ViewPanel, 'numeric');
            app.ROI_y_a_EditField.Limits = [1 Inf];
            app.ROI_y_a_EditField.RoundFractionalValues = 'on';
            app.ROI_y_a_EditField.ValueChangedFcn = createCallbackFcn(app, @ROIxEditFieldValueChanged, true);
            app.ROI_y_a_EditField.Value = 1;
            app.ROI_y_b_EditField = uieditfield(app.ViewPanel, 'numeric');
            app.ROI_y_b_EditField.Limits = [2 Inf];
            app.ROI_y_b_EditField.RoundFractionalValues = 'on';
            app.ROI_y_b_EditField.ValueChangedFcn = createCallbackFcn(app, @ROIxEditFieldValueChanged, true);
            app.ROI_y_b_EditField.Value = 2;
            app.ViewnormaltoaxisSlicexyumLabel = uilabel(app.SegmentationvisualizationUIFigure);
            app.ViewnormaltoaxisSlicexyumLabel.Text = 'View normal to axis *. Slice x/y, */* um';
            app.ColorsPanel = uipanel(app.SegmentationvisualizationUIFigure);
            app.ColorsPanel.Title = 'Colors';
            app.BackgroundDropDownLabel = uilabel(app.ColorsPanel);
            app.BackgroundDropDownLabel.Text = 'Background';
            app.BackgroundDropDown = uidropdown(app.ColorsPanel);
            app.BackgroundDropDown.Items = {'Light grey', 'White'};
            app.BackgroundDropDown.ValueChangedFcn = createCallbackFcn(app, @BackgroundDropDownValueChanged, true);
            app.BackgroundDropDown.Value = 'Light grey';
            app.UITable = uitable(app.ColorsPanel);
            app.UITable.ColumnName = {'Label'; 'Ratio'; 'Color'; 'Name'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false false true true];
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.GreycolormapDropDownLabel = uilabel(app.ColorsPanel);
            app.GreycolormapDropDownLabel.Text = 'Grey colormap';
            app.GreycolormapDropDown = uidropdown(app.ColorsPanel);
            app.GreycolormapDropDown.Items = {'gray', 'bone', 'copper'};
            app.GreycolormapDropDown.ValueChangedFcn = createCallbackFcn(app, @GreycolormapDropDownValueChanged, true);
            app.GreycolormapDropDown.Value = 'gray';
            app.ProbabilitycolormapDropDownLabel = uilabel(app.ColorsPanel);
            app.ProbabilitycolormapDropDownLabel.Enable = 'off';
            app.ProbabilitycolormapDropDownLabel.Text = 'Probability colormap';
            app.ProbabilitycolormapDropDown = uidropdown(app.ColorsPanel);
            app.ProbabilitycolormapDropDown.Items = {'gray', 'turbo', 'sky'};
            app.ProbabilitycolormapDropDown.ValueChangedFcn = createCallbackFcn(app, @GreycolormapDropDownValueChanged, true);
            app.ProbabilitycolormapDropDown.Enable = 'off';
            app.ProbabilitycolormapDropDown.Value = 'gray';
            app.Grey_GlobalCheckBox = uicheckbox(app.ColorsPanel);
            app.Grey_GlobalCheckBox.ValueChangedFcn = createCallbackFcn(app, @GreycolormapDropDownValueChanged, true);
            app.Grey_GlobalCheckBox.Text = 'Global';
            app.Grey_ColorbarCheckBox = uicheckbox(app.ColorsPanel);
            app.Grey_ColorbarCheckBox.ValueChangedFcn = createCallbackFcn(app, @GreycolormapDropDownValueChanged, true);
            app.Grey_ColorbarCheckBox.Text = 'Colorbar';
            app.Prob_ColorbarCheckBox = uicheckbox(app.ColorsPanel);
            app.Prob_ColorbarCheckBox.ValueChangedFcn = createCallbackFcn(app, @GreycolormapDropDownValueChanged, true);
            app.Prob_ColorbarCheckBox.Enable = 'off';
            app.Prob_ColorbarCheckBox.Text = 'Colorbar';
            app.VideoPanel = uipanel(app.SegmentationvisualizationUIFigure);
            app.VideoPanel.Title = 'Video';
            app.SelectfolderButton = uibutton(app.VideoPanel, 'push');
            app.SelectfolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfolderButtonPushed, true);
            app.SelectfolderButton.Text = 'Select folder';
            app.NosavefolderselectedLabel = uilabel(app.VideoPanel);
            app.NosavefolderselectedLabel.Text = 'No save folder selected';
            app.FilenameEditFieldLabel = uilabel(app.VideoPanel);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.FilenameEditField = uieditfield(app.VideoPanel, 'text');
            app.FilenameEditField.Value = 'segmentation_video';
            app.VideoformatDropDownLabel = uilabel(app.VideoPanel);
            app.VideoformatDropDownLabel.Text = 'Video format';
            app.VideoformatDropDown = uidropdown(app.VideoPanel);
            app.VideoformatDropDown.Items = {'mpeg-4', 'Uncompressed AVI'};
            app.VideoformatDropDown.Value = 'mpeg-4';
            app.FramepersLabel = uilabel(app.VideoPanel);
            app.FramepersLabel.Text = 'Frame per s.';
            app.FramepersEditField = uieditfield(app.VideoPanel, 'numeric');
            app.FramepersEditField.Limits = [1 Inf];
            app.FramepersEditField.RoundFractionalValues = 'on';
            app.FramepersEditField.Value = 25;
            app.FromEditFieldLabel = uilabel(app.VideoPanel);
            app.FromEditFieldLabel.Text = 'From';
            app.FromEditField = uieditfield(app.VideoPanel, 'numeric');
            app.FromEditField.Limits = [1 Inf];
            app.FromEditField.RoundFractionalValues = 'on';
            app.FromEditField.ValueChangedFcn = createCallbackFcn(app, @FromEditFieldValueChanged, true);
            app.FromEditField.Value = 1;
            app.toEditFieldLabel = uilabel(app.VideoPanel);
            app.toEditFieldLabel.Text = 'to';
            app.toEditField = uieditfield(app.VideoPanel, 'numeric');
            app.toEditField.Limits = [2 Inf];
            app.toEditField.RoundFractionalValues = 'on';
            app.toEditField.ValueChangedFcn = createCallbackFcn(app, @FromEditFieldValueChanged, true);
            app.toEditField.Value = 2;
            app.SaveeveryEditFieldLabel = uilabel(app.VideoPanel);
            app.SaveeveryEditFieldLabel.Text = 'Save every';
            app.SaveeveryEditField = uieditfield(app.VideoPanel, 'numeric');
            app.SaveeveryEditField.Limits = [1 Inf];
            app.SaveeveryEditField.RoundFractionalValues = 'on';
            app.SaveeveryEditField.ValueChangedFcn = createCallbackFcn(app, @FromEditFieldValueChanged, true);
            app.SaveeveryEditField.Value = 1;
            app.sliceLabel = uilabel(app.VideoPanel);
            app.sliceLabel.Text = 'slice';
            app.SavevideoButton = uibutton(app.VideoPanel, 'push');
            app.SavevideoButton.ButtonPushedFcn = createCallbackFcn(app, @SavevideoButtonPushed, true);
            app.SavevideoButton.Enable = 'off';
            app.SavevideoButton.Text = 'Save video';
            app.WSepCheckBox = uicheckbox(app.VideoPanel);
            app.WSepCheckBox.ValueChangedFcn = createCallbackFcn(app, @WSepCheckBoxValueChanged, true);
            app.WSepCheckBox.Enable = 'off';
            app.WSepCheckBox.Tooltip = {'If selected, video will iterate over the separator value, and not through slides.'};
            app.WSepCheckBox.Text = 'W/ Sep';
            app.WchannelCheckBox = uicheckbox(app.VideoPanel);
            app.WchannelCheckBox.ValueChangedFcn = createCallbackFcn(app, @WchannelCheckBoxValueChanged, true);
            app.WchannelCheckBox.Enable = 'off';
            app.WchannelCheckBox.Tooltip = {'If selected, video will iterate over the separator value, and not through slides.'};
            app.WchannelCheckBox.Text = 'W/ channel';
            app.FOV = uilabel(app.SegmentationvisualizationUIFigure);
            app.FOV.Text = 'Domain''s size: a x b x c um3, voxel size: um';
            app.SeparatorLabel = uilabel(app.SegmentationvisualizationUIFigure);
            app.SeparatorLabel.Text = 'Separator';
            app.SeparatorSlider = uislider(app.SegmentationvisualizationUIFigure);
            app.SeparatorSlider.ValueChangedFcn = createCallbackFcn(app, @SeparatorSliderValueChanged2, true);
            app.SeparatorSlider.ValueChangingFcn = createCallbackFcn(app, @SeparatorSliderValueChanged, true);
            app.SeparatorSlider.Enable = 'off';
            app.SeparatorSlider.Visible = 'off';
            app.ShowlineCheckBox = uicheckbox(app.SegmentationvisualizationUIFigure);
            app.ShowlineCheckBox.Enable = 'off';
            app.ShowlineCheckBox.Visible = 'off';
            app.ShowlineCheckBox.Text = 'Show line';
            app.SegmentationchannelsPanel = uipanel(app.SegmentationvisualizationUIFigure);
            app.SegmentationchannelsPanel.Title = 'Segmentation channels';
            app.ChannelSliderLabel = uilabel(app.SegmentationchannelsPanel);
            app.ChannelSliderLabel.Text = 'Channel';
            app.ChannelSlider = uislider(app.SegmentationchannelsPanel);
            app.ChannelSlider.ValueChangedFcn = createCallbackFcn(app, @ChannelSliderValueChanged, true);
            app.ChannelSlider.Enable = 'off';
            app.Channel_plus_Button = uibutton(app.SegmentationchannelsPanel, 'push');
            app.Channel_plus_Button.ButtonPushedFcn = createCallbackFcn(app, @Channel_plus_ButtonPushed, true);
            app.Channel_plus_Button.Enable = 'off';
            app.Channel_plus_Button.Text = '+';
            app.Channel_minus_Button = uibutton(app.SegmentationchannelsPanel, 'push');
            app.Channel_minus_Button.ButtonPushedFcn = createCallbackFcn(app, @Channel_minus_ButtonPushed, true);
            app.Channel_minus_Button.Enable = 'off';
            app.Channel_minus_Button.Text = '-';
            app.ViewDropDownLabel = uilabel(app.SegmentationchannelsPanel);
            app.ViewDropDownLabel.Text = 'View';
            app.Channel_ViewDropDown = uidropdown(app.SegmentationchannelsPanel);
            app.Channel_ViewDropDown.Items = {'Per channel', 'Composite', 'Probability'};
            app.Channel_ViewDropDown.ValueChangedFcn = createCallbackFcn(app, @Channel_ViewDropDownValueChanged, true);
            app.Channel_ViewDropDown.Enable = 'off';
            app.Channel_ViewDropDown.Value = 'Per channel';
            app.PlotStatsButton = uibutton(app.SegmentationchannelsPanel, 'push');
            app.PlotStatsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotStatsButtonPushed, true);
            app.PlotStatsButton.Enable = 'off';
            app.PlotStatsButton.Text = 'Plot Stats';
            app.ShowlineCompositeCheckBox = uicheckbox(app.SegmentationchannelsPanel);
            app.ShowlineCompositeCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowlineCompositeCheckBoxValueChanged, true);
            app.ShowlineCompositeCheckBox.Enable = 'off';
            app.ShowlineCompositeCheckBox.Text = 'Show line';
            app.ExportProbButton = uibutton(app.SegmentationchannelsPanel, 'push');
            app.ExportProbButton.ButtonPushedFcn = createCallbackFcn(app, @ExportProbButtonPushed, true);
            app.ExportProbButton.Enable = 'off';
            app.ExportProbButton.Text = 'Export Prob.';
            app.PleasewaitLabel = uilabel(app.SegmentationchannelsPanel);
            app.PleasewaitLabel.Visible = 'off';
            app.PleasewaitLabel.Text = 'Please wait...';
            app.ThresholdEditFieldLabel = uilabel(app.SegmentationchannelsPanel);
            app.ThresholdEditFieldLabel.Enable = 'off';
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.SegmentationchannelsPanel, 'numeric');
            app.ThresholdEditField.Limits = [0 1];
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.ThresholdEditField.Enable = 'off';
            app.ThresholdEditField.Tooltip = {'Voxels with confidence (i.e., maximum probability) < this threshold are first identified and then assigned to the nearest high confidence phase.'};
            app.ThresholdEditField.Value = 0.95;
            app.Sep_HorizontalButton = uibutton(app.SegmentationvisualizationUIFigure, 'push');
            app.Sep_HorizontalButton.ButtonPushedFcn = createCallbackFcn(app, @Sep_HorizontalButtonPushed, true);
            app.Sep_HorizontalButton.Enable = 'off';
            app.Sep_HorizontalButton.Visible = 'off';
            app.Sep_HorizontalButton.Text = 'Horizontal';
            % Show the figure after all components are created
            app.SegmentationvisualizationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = microstructure_visualization_segmentation(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SegmentationvisualizationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SegmentationvisualizationUIFigure)
        end
    end
end