classdef Microstructure_characterization < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MicrostructurecharacterizationUIFigure  matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        InstructionsTab                 matlab.ui.container.Tab
        DiameterbackgroundcolormapDropDown  matlab.ui.control.DropDown
        DiametercolormapDropDown_2Label_2  matlab.ui.control.Label
        DiametercolormapDropDown        matlab.ui.control.DropDown
        DiametercolormapDropDown_2Label  matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Imageprocessing_Label           matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        ChooseyourformatiedisplayoptionsLabel  matlab.ui.control.Label
        saveoptions_UITable             matlab.ui.control.Table
        ChooseyoursaveoptionsLabel      matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        ClosefigureoncedisplayedsavedCheckBox  matlab.ui.control.CheckBox
        SubplotmaintitleiesgtitlefontsizeEditField  matlab.ui.control.NumericEditField
        SubplotmaintitleiesgtitlefontsizeEditFieldLabel  matlab.ui.control.Label
        TitlefontsizeEditField          matlab.ui.control.NumericEditField
        TitlefontsizeEditFieldLabel     matlab.ui.control.Label
        LegendfontsizeEditField         matlab.ui.control.NumericEditField
        LegendfontsizeEditFieldLabel    matlab.ui.control.Label
        AxeFontsizeEditField            matlab.ui.control.NumericEditField
        AxeFontsizeEditFieldLabel       matlab.ui.control.Label
        GridDropDown                    matlab.ui.control.DropDown
        GridDropDownLabel               matlab.ui.control.Label
        LinewidthEditField              matlab.ui.control.NumericEditField
        LinewidthEditFieldLabel         matlab.ui.control.Label
        FontnameDropDown                matlab.ui.control.DropDown
        FontnameDropDownLabel           matlab.ui.control.Label
        Instructions_title              matlab.ui.control.Label
        ImportvolumeTab                 matlab.ui.container.Tab
        DropDown                        matlab.ui.control.DropDown
        DropDownLabel                   matlab.ui.control.Label
        Save_volume_button              matlab.ui.control.Button
        SavevolumeLabel                 matlab.ui.control.Label
        VolumefolderEditField           matlab.ui.control.EditField
        VolumefolderEditFieldLabel      matlab.ui.control.Label
        ResultswillbesavedinmainfoldervolumefolderLabel  matlab.ui.control.Label
        Main_folder_Label               matlab.ui.control.Label
        SelectsavefoldersLabel          matlab.ui.control.Label
        SelectmainfolderButton          matlab.ui.control.Button
        Info_UITable                    matlab.ui.control.Table
        OptionalSavedinanexcelfileforyourrecordLabel  matlab.ui.control.Label
        WritevolumeinformationLabel     matlab.ui.control.Label
        TableisupdatedautomaticallyLabel  matlab.ui.control.Label
        voxelinfo_UITable               matlab.ui.control.Table
        Visualize_ROI_vf_button         matlab.ui.control.Button
        Visualize_vf_button             matlab.ui.control.Button
        NewvoxelsizeEditField           matlab.ui.control.NumericEditField
        NewvoxelsizeEditFieldLabel      matlab.ui.control.Label
        Scalingfactor_Label             matlab.ui.control.Label
        SetvoxelsizeandscalingfactorLabel  matlab.ui.control.Label
        Visualize_ROI_button            matlab.ui.control.Button
        Phasecolor_Label                matlab.ui.control.Label
        SetphasecolorandnameLabel       matlab.ui.control.Label
        ROI_Label                       matlab.ui.control.Label
        SetRegionOfInterestROILabel     matlab.ui.control.Label
        ScalingfactorEditField          matlab.ui.control.NumericEditField
        ScalingfactorEditFieldLabel     matlab.ui.control.Label
        VoxelsizeUnitEditField          matlab.ui.control.EditField
        UnitEditFieldLabel              matlab.ui.control.Label
        VoxelsizeEditField              matlab.ui.control.NumericEditField
        VoxelsizeEditFieldLabel         matlab.ui.control.Label
        Direction_UITable               matlab.ui.control.Table
        Vf_UITable                      matlab.ui.control.Table
        Visualize_volume_button         matlab.ui.control.Button
        Volumenumber1Label              matlab.ui.control.Label
        Imported_volume_name_label      matlab.ui.control.Label
        ClicktoimportvolumeLabel        matlab.ui.control.Label
        ImportvolumeButton              matlab.ui.control.Button
        Instructions_title_2            matlab.ui.control.Label
        ImportedvolumesTab              matlab.ui.container.Tab
        Label_5                         matlab.ui.control.Label
        Save_importedvolume_button      matlab.ui.control.Button
        ClicktoimportvolumeLabel_5      matlab.ui.control.Label
        Order_UITable                   matlab.ui.control.Table
        ClicktoimportvolumeLabel_2      matlab.ui.control.Label
        Instructions_title_3            matlab.ui.control.Label
        VolumefractionsTab              matlab.ui.container.Tab
        vf_Fractal_Bertei_CheckBox      matlab.ui.control.CheckBox
        vf_SelectallButton              matlab.ui.control.Button
        Setto1e9togothoughallpossibleboxlengthLabel  matlab.ui.control.Label
        ClicktoimportvolumeLabel_20     matlab.ui.control.Label
        vf_fractal_voxelsizeCheckBox    matlab.ui.control.CheckBox
        vf_Boxlengthmax_EditField       matlab.ui.control.NumericEditField
        BoxlengthmaximumvalueinvoxellenghEditFieldLabel  matlab.ui.control.Label
        Volume_Fractal_CheckBox         matlab.ui.control.CheckBox
        vf_phaseselection_UITable       matlab.ui.control.Table
        ClicktoimportvolumeLabel_3      matlab.ui.control.Label
        Instructions_title_4            matlab.ui.control.Label
        ConnectivityTab                 matlab.ui.container.Tab
        Connectivity_plottype_DropDown  matlab.ui.control.DropDown
        ForLabel                        matlab.ui.control.Label
        Connectivity_plotdirection_DropDown  matlab.ui.control.DropDown
        PlotLabel                       matlab.ui.control.Label
        ForcoupledphaseonlyLabel_2      matlab.ui.control.Label
        Connectivity_OrientationDropDown  matlab.ui.control.DropDown
        OrientationDropDownLabel        matlab.ui.control.Label
        ForcoupledphaseonlyLabel        matlab.ui.control.Label
        Connectivity_DirectionofinterestDropDown  matlab.ui.control.DropDown
        DirectionofinterestDropDownLabel  matlab.ui.control.Label
        Conn_SelectallButton            matlab.ui.control.Button
        ClicktoimportvolumeLabel_25     matlab.ui.control.Label
        Connectivity_phaseselection_UITable  matlab.ui.control.Table
        Instructions_title_14           matlab.ui.control.Label
        TortuosityfactorTab             matlab.ui.container.Tab
        tau_Alld3Button                 matlab.ui.control.Button
        tau_Alld2Button                 matlab.ui.control.Button
        tau_Alld1Button                 matlab.ui.control.Button
        tau_SelectallButton             matlab.ui.control.Button
        Image2                          matlab.ui.control.Image
        CalculationmethodDirectLabel_4  matlab.ui.control.Label
        Tau_phaseselection_UITable      matlab.ui.control.Table
        ClicktoimportvolumeLabel_24     matlab.ui.control.Label
        Instructions_title_13           matlab.ui.control.Label
        SpecificsurfaceareaTab          matlab.ui.container.Tab
        Sp_donotconsidercomvol          matlab.ui.control.CheckBox
        Sp_Fractal_Bertei_CheckBox      matlab.ui.control.CheckBox
        Sp_SelectallButton              matlab.ui.control.Button
        Sp_Boxlengthmax_EditField       matlab.ui.control.NumericEditField
        BoxlengthmaximumvalueinvoxellenghEditFieldLabel_3  matlab.ui.control.Label
        Setto1e9togothoughallpossibleboxlengthLabel_3  matlab.ui.control.Label
        ClicktoimportvolumeLabel_22     matlab.ui.control.Label
        Sp_fractal_voxelsizeCheckBox    matlab.ui.control.CheckBox
        Sp_Fractal_CheckBox             matlab.ui.control.CheckBox
        CalculationmethodDirectLabel    matlab.ui.control.Label
        CorrectivefactorEditField       matlab.ui.control.NumericEditField
        CorrectivefactorEditFieldLabel  matlab.ui.control.Label
        Sp_DefinitionDropDown           matlab.ui.control.DropDown
        DefinitionDropDownLabel         matlab.ui.control.Label
        ClicktoimportvolumeLabel_13     matlab.ui.control.Label
        Spdirect_phaseselection_UITable  matlab.ui.control.Table
        ClicktoimportvolumeLabel_12     matlab.ui.control.Label
        Instructions_title_8            matlab.ui.control.Label
        Sp_correctivefactor_UIAxes      matlab.ui.control.UIAxes
        SpecificinterfaceareaTab        matlab.ui.container.Tab
        Int_Fractal_Bertei_CheckBox     matlab.ui.control.CheckBox
        Int_SelectallButton             matlab.ui.control.Button
        Int_Boxlengthmax_EditField      matlab.ui.control.NumericEditField
        BoxlengthmaximumvalueinvoxellenghEditFieldLabel_4  matlab.ui.control.Label
        Setto1e9togothoughallpossibleboxlengthLabel_4  matlab.ui.control.Label
        ClicktoimportvolumeLabel_23     matlab.ui.control.Label
        Int_fractal_voxelsizeCheckBox   matlab.ui.control.CheckBox
        Int_Fractal_CheckBox            matlab.ui.control.CheckBox
        Correctivefactor_interface_EditField  matlab.ui.control.NumericEditField
        CorrectivefactorEditField_2Label  matlab.ui.control.Label
        ClicktoimportvolumeLabel_18     matlab.ui.control.Label
        CalculationmethodDirectLabel_2  matlab.ui.control.Label
        Exampleforavolumewithphaselabels012and40104Label  matlab.ui.control.Label
        Interface_phaseselection_UITable  matlab.ui.control.Table
        ClicktoimportvolumeLabel_17     matlab.ui.control.Label
        Instructions_title_12           matlab.ui.control.Label
        TPBLTab                         matlab.ui.container.Tab
        TPBL_Fractal_Bertei_CheckBox    matlab.ui.control.CheckBox
        TPBL_SelectallButton            matlab.ui.control.Button
        TPBL_Boxlengthmax_EditField     matlab.ui.control.NumericEditField
        BoxlengthmaximumvalueinvoxellenghEditFieldLabel_2  matlab.ui.control.Label
        Setto1e9togothoughallpossibleboxlengthLabel_2  matlab.ui.control.Label
        ClicktoimportvolumeLabel_21     matlab.ui.control.Label
        TPBL_fractal_voxelsizeCheckBox  matlab.ui.control.CheckBox
        TPBL_Fractal_Boxcounting_CheckBox  matlab.ui.control.CheckBox
        CalculationmethodDirectLabel_3  matlab.ui.control.Label
        Correctivefactor_TPBL_EditField  matlab.ui.control.NumericEditField
        CorrectivefactorEditField_2Label_2  matlab.ui.control.Label
        ClicktoimportvolumeLabel_19     matlab.ui.control.Label
        Exampleforavolumewithphaselabels012and4012024Label  matlab.ui.control.Label
        TPBL_phaseselection_UITable     matlab.ui.control.Table
        ClicktoimportvolumeLabel_16     matlab.ui.control.Label
        Instructions_title_11           matlab.ui.control.Label
        DcPSDTab                        matlab.ui.container.Tab
        SphereapproachLabel             matlab.ui.control.Label
        Dcpsd_SelectallButton           matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        CalculationmethodCpsdtLabel     matlab.ui.control.Label
        Dcpsd_phaseselection_UITable    matlab.ui.control.Table
        ClicktoimportvolumeLabel_14     matlab.ui.control.Label
        Instructions_title_9            matlab.ui.control.Label
        DEDMFTab                        matlab.ui.container.Tab
        DistanceapproachLabel           matlab.ui.control.Label
        Image4                          matlab.ui.control.Image
        MethodsparameterexplanationLabel  matlab.ui.control.Label
        MethodexplanationLabel          matlab.ui.control.Label
        ClickfigurebelowtoenlargeLabel_2  matlab.ui.control.Label
        setto1toignoreLabel             matlab.ui.control.Label
        ClicktoimportvolumeLabel_26     matlab.ui.control.Label
        EDMF_RemoveinclusionCheckBox    matlab.ui.control.CheckBox
        EDMF_DistancetowardlabelEditField  matlab.ui.control.NumericEditField
        DistancetowardlabelEditFieldLabel  matlab.ui.control.Label
        ClickfigurebelowtoenlargeLabel  matlab.ui.control.Label
        Image3                          matlab.ui.control.Image
        Dedmf_SelectallButton           matlab.ui.control.Button
        CalculationmethodEDMFLabel      matlab.ui.control.Label
        Dedmf_phaseselection_UITable    matlab.ui.control.Table
        ClicktoimportvolumeLabel_15     matlab.ui.control.Label
        Instructions_title_10           matlab.ui.control.Label
        DCLMFTab                        matlab.ui.container.Tab
        Chord_SizefilterEditField       matlab.ui.control.NumericEditField
        SizefilterEditFieldLabel        matlab.ui.control.Label
        ClicktoimportvolumeLabel_28     matlab.ui.control.Label
        CalculationmethodEDMFLabel_2    matlab.ui.control.Label
        ClickfigurestoenlargeLabel      matlab.ui.control.Label
        IllustratedonellipsoidLabel     matlab.ui.control.Label
        IllustratedonsphereLabel        matlab.ui.control.Label
        MethodexplanationLabel_2        matlab.ui.control.Label
        Image7                          matlab.ui.control.Image
        Image6                          matlab.ui.control.Image
        Image5                          matlab.ui.control.Image
        Dchord_SelectallButton          matlab.ui.control.Button
        Dchord_phaseselection_UITable   matlab.ui.control.Table
        ClicktoimportvolumeLabel_27     matlab.ui.control.Label
        Instructions_title_20           matlab.ui.control.Label
        LenghtapproachLabel             matlab.ui.control.Label
        DwatershedTab                   matlab.ui.container.Tab
        CorrectoversegmentationoptionsLabel  matlab.ui.control.Label
        Watershed_SizefilterEditField   matlab.ui.control.NumericEditField
        SizefilternumberofvoxelwithinaparticleEditFieldLabel  matlab.ui.control.Label
        Watershed_ReassignCheckBox      matlab.ui.control.CheckBox
        Watershed_Detail_CheckBox       matlab.ui.control.CheckBox
        ClicktoimportvolumeLabel_30     matlab.ui.control.Label
        Dwatershed_SelectallButton      matlab.ui.control.Button
        Dwatershed_phaseselection_UITable  matlab.ui.control.Table
        ClicktoimportvolumeLabel_29     matlab.ui.control.Label
        ParticleidentificationvolumebasedapproachLabel  matlab.ui.control.Label
        Instructions_title_15           matlab.ui.control.Label
        DPCRFTab                        matlab.ui.container.Tab
        ParticleidentificationvolumebasedapproachLabel_2  matlab.ui.control.Label
        Instructions_title_16           matlab.ui.control.Label
        DlabelTab                       matlab.ui.container.Tab
        ParticleidentificationvolumebasedapproachLabel_3  matlab.ui.control.Label
        Instructions_title_18           matlab.ui.control.Label
        DcovarianceTab                  matlab.ui.container.Tab
        StatisticalapproachLabel        matlab.ui.control.Label
        Instructions_title_17           matlab.ui.control.Label
        VoxelsizeTab                    matlab.ui.container.Tab
        Deff_ExtrapolatetozerovoxelsizeDropDown  matlab.ui.control.DropDown
        ParticlelevelofdetailExtrapolatetozerovoxelsizeLabel_3  matlab.ui.control.Label
        Bruggeman_ExtrapolatetozerovoxelsizeDropDown  matlab.ui.control.DropDown
        ParticlelevelofdetailExtrapolatetozerovoxelsizeLabel_2  matlab.ui.control.Label
        LvlDetail_ExtrapolatetozerovoxelsizeDropDown  matlab.ui.control.DropDown
        ParticlelevelofdetailExtrapolatetozerovoxelsizeLabel  matlab.ui.control.Label
        Voxel_ExtrapolatetozerovoxelsizeDropDown  matlab.ui.control.DropDown
        ExtrapolatetozerovoxelsizeDropDownLabel_2  matlab.ui.control.Label
        ClicktoimportvolumeLabel_11     matlab.ui.control.Label
        Voxelsize_PropertyselectionDropDown  matlab.ui.control.DropDown
        PropertyselectionDropDownLabel_2  matlab.ui.control.Label
        Voxel_label                     matlab.ui.control.Label
        voxelsizeUITable                matlab.ui.control.Table
        ClicktoimportvolumeLabel_10     matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        Instructions_title_7            matlab.ui.control.Label
        RVEconvergenceTab               matlab.ui.container.Tab
        HelpresultinterpretationButton  matlab.ui.control.Button
        HelpRVEandconvergencecasesButton  matlab.ui.control.Button
        HelpRVEdefinitionButton         matlab.ui.control.Button
        ClickonthebuttonsbelowforhelpLabel  matlab.ui.control.Label
        RVE_UITable                     matlab.ui.control.Table
        RVEandconvergenceoptionsPanel   matlab.ui.container.Panel
        CaseEFGHconvergenceanalysisPanel  matlab.ui.container.Panel
        Mustbealistofreal0Label_2       matlab.ui.control.Label
        RelativedifferencethresholdsEditField  matlab.ui.control.EditField
        RelativedifferencethresholdsEditFieldLabel  matlab.ui.control.Label
        RVEgrowthrelativeto_InDropDown  matlab.ui.control.DropDown
        InDropDownLabel                 matlab.ui.control.Label
        VolumegrowthEditField           matlab.ui.control.NumericEditField
        VolumegrowthEditFieldLabel      matlab.ui.control.Label
        InofthefullvolumeLabel          matlab.ui.control.Label
        SizeofthefirstsubvolumeEditField  matlab.ui.control.NumericEditField
        SizeofthefirstsubvolumeEditFieldLabel  matlab.ui.control.Label
        SubvolumeaspectratioEditField_2  matlab.ui.control.EditField
        SubvolumeaspectratioEditField_2Label  matlab.ui.control.Label
        GrowthdirectionDropDown         matlab.ui.control.DropDown
        GrowthdirectionDropDownLabel    matlab.ui.control.Label
        CaseABCDRVEanalysisPanel        matlab.ui.container.Panel
        StandarddeviationthresholdsEditField  matlab.ui.control.EditField
        StandarddeviationthresholdsEditFieldLabel  matlab.ui.control.Label
        Mustbealistofreal0Label         matlab.ui.control.Label
        RVE_IterateuntilDropDown        matlab.ui.control.DropDown
        IterateuntilDropDownLabel       matlab.ui.control.Label
        ofinitialvolumeLabel            matlab.ui.control.Label
        MinimumsubvvolumesizeEditField  matlab.ui.control.NumericEditField
        MinimumsubvvolumesizeEditFieldLabel  matlab.ui.control.Label
        ForatleastnsubvolumesEditField  matlab.ui.control.NumericEditField
        ForatleastnsubvolumesEditFieldLabel  matlab.ui.control.Label
        ConstantdirectionslengthDropDown  matlab.ui.control.DropDown
        ConstantdirectionslengthDropDownLabel  matlab.ui.control.Label
        Formatabcwithabcrealnumber0Label  matlab.ui.control.Label
        SubvolumeaspectratioEditField   matlab.ui.control.EditField
        SubvolumeaspectratioEditFieldLabel  matlab.ui.control.Label
        subvolumes_sub4_CheckBox        matlab.ui.control.CheckBox
        subvolumes_sub2_CheckBox        matlab.ui.control.CheckBox
        Mustbealistofincreasinginteger2Label  matlab.ui.control.Label
        DividerEditField                matlab.ui.control.EditField
        DividerEditFieldLabel           matlab.ui.control.Label
        NestedanalysisPanel             matlab.ui.container.Panel
        RVE_ofinitialvolumeLabel        matlab.ui.control.Label
        RVE_MinimumvolumetoapplyRVEanalysisEditField  matlab.ui.control.NumericEditField
        MinimumvolumetoapplyanalysisLabel  matlab.ui.control.Label
        RVE_xEditField                  matlab.ui.control.NumericEditField
        xEditFieldLabel                 matlab.ui.control.Label
        RVE_AteachstepDropDown          matlab.ui.control.DropDown
        AteachstepremoveLabel           matlab.ui.control.Label
        RVE_thatisDropDown              matlab.ui.control.DropDown
        thatisDropDownLabel             matlab.ui.control.Label
        RVE_ShrinkDropDown              matlab.ui.control.DropDown
        ShrinkDropDownLabel             matlab.ui.control.Label
        DonestedanalysisCheckBox        matlab.ui.control.CheckBox
        RVE_canceloptionButton          matlab.ui.control.Button
        RVE_saveoptionButton            matlab.ui.control.Button
        SelectRVEorconvergenceanalysisDropDown  matlab.ui.control.DropDown
        SelectRVEorconvergenceanalysisDropDownLabel  matlab.ui.control.Label
        OrLabel                         matlab.ui.control.Label
        CopyoptionssetintheabovetableusingonthethreeoptionsbelowLabel  matlab.ui.control.Label
        RVE_apply_THISproperty_ALLvolumes_button  matlab.ui.control.Button
        RVE_apply_PERproperties_ALLvolumes_button  matlab.ui.control.Button
        RVE_apply_ALLproperties_ALLvolumes_button  matlab.ui.control.Button
        ClicktoimportvolumeLabel_9      matlab.ui.control.Label
        RVE_remove_button               matlab.ui.control.Button
        RVE_add_button                  matlab.ui.control.Button
        RVE_apply_ALLproperties_THISvolume_button  matlab.ui.control.Button
        RVE_PropertyselectionDropDown   matlab.ui.control.DropDown
        PropertyselectionDropDownLabel  matlab.ui.control.Label
        ClicktoimportvolumeLabel_8      matlab.ui.control.Label
        RVE_VolumeselectionDropDown     matlab.ui.control.DropDown
        VolumeselectionDropDownLabel    matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Instructions_title_6            matlab.ui.control.Label
        RunTab                          matlab.ui.container.Tab
        RunStatut_UITable               matlab.ui.control.Table
        Run_button                      matlab.ui.control.Button
        ClicktoimportvolumeLabel_7      matlab.ui.control.Label
        Instructions_title_5            matlab.ui.control.Label
        ConvergenceTab                  matlab.ui.container.Tab
        DistancefromcentertocenterindiameterEditField  matlab.ui.control.NumericEditField
        DistancefromcentertocenterindiameterEditFieldLabel  matlab.ui.control.Label
        Image8_4                        matlab.ui.control.Image
        Image8_3                        matlab.ui.control.Image
        Image8_2                        matlab.ui.control.Image
        Label_30                        matlab.ui.control.Label
        UptoxnumberofvoxelperdiametertwospheresEditField  matlab.ui.control.NumericEditField
        UptoxnumberofvoxelperdiametertwospheresEditFieldLabel  matlab.ui.control.Label
        RecalculateConvergenceButton_3  matlab.ui.control.Button
        RecalculateConvergenceButton_2  matlab.ui.control.Button
        RandomlydistributedisolatedsphereLabel  matlab.ui.control.Label
        Label_29                        matlab.ui.control.Label
        Label_28                        matlab.ui.control.Label
        TwospheresLabel                 matlab.ui.control.Label
        SinglesphereLabel               matlab.ui.control.Label
        UptoxnumberofvoxelperdiametermultiplesphereEditField  matlab.ui.control.NumericEditField
        UptoxnumberofvoxelperdiametermultiplesphereEditFieldLabel  matlab.ui.control.Label
        Image8                          matlab.ui.control.Image
        Label_27                        matlab.ui.control.Label
        UptoxnumberofvoxelperdiametersinglesphereEditField  matlab.ui.control.NumericEditField
        UptoxnumberofvoxelperdiametersinglesphereEditFieldLabel  matlab.ui.control.Label
        Label_20                        matlab.ui.control.Label
        RecalculateConvergenceButton    matlab.ui.control.Button
        Image_DiameterConvergence       matlab.ui.control.Image
        Label_19                        matlab.ui.control.Label
        Image_SpConvergence             matlab.ui.control.Image
        Label_18                        matlab.ui.control.Label
        Instructions_title_21           matlab.ui.control.Label
        ReferencesTab                   matlab.ui.container.Tab
        Hyperlink_8                     matlab.ui.control.Hyperlink
        DCRobertsonLFloLabel_2          matlab.ui.control.Label
        Hyperlink_7                     matlab.ui.control.Hyperlink
        DCRobertsonLFloLabel            matlab.ui.control.Label
        Hyperlink_6                     matlab.ui.control.Hyperlink
        FLEUsseglioVirettaandKSmithLabel  matlab.ui.control.Label
        Hyperlink_5                     matlab.ui.control.Hyperlink
        Label_17                        matlab.ui.control.Label
        Hyperlink_4                     matlab.ui.control.Hyperlink
        Label_15                        matlab.ui.control.Label
        Hyperlink_2                     matlab.ui.control.Hyperlink
        Label_11                        matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        Instructions_title_19           matlab.ui.control.Label
        HowtoquoteTab                   matlab.ui.container.Tab
        Hyperlink_3                     matlab.ui.control.Hyperlink
        Label_13                        matlab.ui.control.Label
        Label_12                        matlab.ui.control.Label
        Hyperlink                       matlab.ui.control.Hyperlink
        Label_10                        matlab.ui.control.Label
        Label_9                         matlab.ui.control.Label
        Instructions_generation         matlab.ui.control.Label
        HelpTab                         matlab.ui.container.Tab
        Hyperlink_10                    matlab.ui.control.Hyperlink
        Hyperlink_9                     matlab.ui.control.Hyperlink
        Label_24                        matlab.ui.control.Label
        Label_23                        matlab.ui.control.Label
        Label_22                        matlab.ui.control.Label
        Label_21                        matlab.ui.control.Label
        OpendocumentationButton         matlab.ui.control.Button
        Instructions_title_22           matlab.ui.control.Label
        AboutTab                        matlab.ui.container.Tab
        Localversion                    matlab.ui.control.Label
        OnlineversioncheckLabel         matlab.ui.control.Label
        MATBOXinstalledversionLabel     matlab.ui.control.Label
        Hyperlink_12                    matlab.ui.control.Hyperlink
        Hyperlink_11                    matlab.ui.control.Hyperlink
        NREL_logo                       matlab.ui.control.Image
        Label_26                        matlab.ui.control.Label
        Instructions_title_23           matlab.ui.control.Label
    end

    
    properties (Access = private)
        FileName = [];
        PathName = [];
        number_volume = 1; % Number of segmented volume imported
        current_microstructure = []; % Current imported microstructure
        voxel_size = 1;
        color_phase = [];
        phase_name_sav = [];
        direction_name_sav = [];
        inputvolume = []; % Structure for all volumes to characterize
        volumeinfo_sav = [];
        mainfolder_sav = [];
        workingpath = [];
        cubicrootsize = [];
        order_calculation = [];
        row2nputvolumevolumek = [];
        property_list = {'Volume fractions','Connectivity','Tortuosity factor','Specific surface area (direct)','Specific interface area (direct)','Triple phase boundary length (direct)','Diameter (c-PSD)','Diameter (EDMF)','Diameter (Chord)','Diameter (watershed)','Diameter (PCRF)','Diameter (label)','Diameter (covariance)'};
        RVE_list = [];
        voxelsizeanalysis = [];
        colormap_diameter_list = {'turbo','jet','parula','hot',};
    end
    
    methods (Access = private)
        
        function [] = function_vf_alongthickness(app,M,figtitle)
            % Volume fractions
            Domain_size = size(M);
            number_direction = length(Domain_size);
            if number_direction==2 % Case 2D
                Domain_size=[Domain_size(1) Domain_size(2) 1];
            end
            phase_label = cell2mat(app.Vf_UITable.Data(:,6));
            number_phase = length(phase_label);
            ROI = cell2mat(app.Direction_UITable.Data(:,3:4)); % Extract array
            direction_name = app.Direction_UITable.Data(:,5);
            phase_color =  app.color_phase;
            phase_name = app.Vf_UITable.Data(:,5);

            strunit = app.VoxelsizeUnitEditField.Value;
            if strcmp(strunit,'um') || strcmp(strunit,'micrometer') || strcmp(strunit,'Micrometer') || strcmp(strunit,'micrometers') || strcmp(strunit,'Micrometers')
                axisunit = '(\mum)';
            else
                axisunit = ['(' strunit ')'];
            end

            % Initialize
            for current_direction=1:1:number_direction % Loop over all directions
                res(current_direction).volumefraction = zeros(Domain_size(current_direction),number_phase+1); % Initialization
                res(current_direction).volumefraction(:,1) = (1:1:Domain_size(current_direction))*app.VoxelsizeEditField.Value; % Axis (position along direction)
            end
            for current_phase = 1:1:number_phase % Loop over all phases
                % Direction 1
                for current_position=1:1:Domain_size(1) % Loop over postion
                    Numbervoxel= sum(sum( M(current_position,:,:)==phase_label(current_phase) ));
                    res(1).volumefraction(current_position,current_phase+1) = Numbervoxel / (Domain_size(2)*Domain_size(3));
                end
                % Direction 2
                for current_position=1:1:Domain_size(2) % Loop over postion
                    Numbervoxel= sum(sum(M(:,current_position,:)==phase_label(current_phase)));
                    res(2).volumefraction(current_position,current_phase+1) = Numbervoxel / (Domain_size(1)*Domain_size(3));
                end
                if number_direction==3 % 3D case
                    % Direction 3
                    for current_position=1:1:Domain_size(3) % Loop over postion
                        Numbervoxel= sum(sum(M(:,:,current_position)==phase_label(current_phase)));
                        res(3).volumefraction(current_position,current_phase+1) = Numbervoxel / (Domain_size(1)*Domain_size(2));
                    end
                end
            end

            scrsz = get(0,'ScreenSize'); % Screen resolution
            Fig = figure; % Create figure
            Fig.Name= 'Volume fractions'; % Figure name
            Fig.Color='white'; % Background colour
            set(Fig,'position',[scrsz(1) scrsz(2) scrsz(3)*number_direction/3 scrsz(4)*1/2]); % Full screen figure
            for current_direction=1:1:number_direction % Iterate over axe
                sub_axes=subplot(1,number_direction,current_direction,'Parent',Fig);
                hold(sub_axes,'on'); % Active subplot
                % Plot graphs
                for current_phase=1:1:number_phase % Loop over phases
                    x_ = res(current_direction).volumefraction(:,1);
                    y_ = res(current_direction).volumefraction(:,current_phase+1);
                    plot(x_,y_,'Color', phase_color(current_phase,:),'LineWidth',app.LinewidthEditField.Value,'DisplayName',char(phase_name(current_phase)));
                end
                % Axis label
                t_ = xlabel(' ');
                t_1 = sprintf('Position along %s ',char(direction_name(current_direction)));
                t_2 = axisunit;
                t_.String= [t_1 t_2]; % Sprintf does not accept greek characters
                ylabel('Volume fractions');
                % Legend
                h_legend = legend(sub_axes,'Location','best');
                % - Grid
                if strcmp(app.GridDropDown.Value,'Grid')
                    grid(sub_axes,'on'); % Display grid
                elseif strcmp(app.GridDropDown.Value,'Grid and minor grid')
                    grid(sub_axes,'on'); % Display grid
                    set(sub_axes,'XMinorGrid','on','YMinorGrid','on'); % Display grid for minor thicks
                end
                set(sub_axes,'FontName',app.FontnameDropDown.Value,'FontSize',app.AxeFontsizeEditField.Value); % Fontname and fontsize
                h_legend.FontSize = app.LegendfontsizeEditField.Value; % Set title fontsize
                hold(sub_axes,'off'); % Relase figure
            end
            sgtitle(Fig,[figtitle ': volume fractions along directions'],'FontWeight','bold','FontSize',app.SubplotmaintitleiesgtitlefontsizeEditField.Value,'FontName',app.FontnameDropDown.Value);
        end
        
        function [] = function_update_voxeltable(app)
            c1 = {'Number of voxels';'Domain size';'Domain size cubic root'};

            Domain_size = size(app.current_microstructure);
            number_direction = length(Domain_size);
            if number_direction==2 % Case 2D
                Domain_size=[Domain_size(1) Domain_size(2) 1];
            end

            size_direction_1 = Domain_size(1)*app.VoxelsizeEditField.Value;
            size_direction_2 = Domain_size(2)*app.VoxelsizeEditField.Value;
            size_direction_3 = Domain_size(3)*app.VoxelsizeEditField.Value;
            size_cubeequivalent = (size_direction_1*size_direction_2*size_direction_3)^(1/3);
            str_1 = sprintf('%0.1f',size_direction_1);
            str_2 = sprintf('%0.1f',size_direction_2);
            str_3 = sprintf('%0.1f',size_direction_3);
            str_4 = sprintf('%0.1f',size_cubeequivalent);
            c2 = {num2str(prod(Domain_size)); [str_1 ' x ' str_2 ' x ' str_3 ' ' app.VoxelsizeUnitEditField.Value '3']; [str_4 ' x ' str_4 ' x ' str_4 ' ' app.VoxelsizeUnitEditField.Value '3']};

            ROI = cell2mat(app.Direction_UITable.Data(:,3:4)); % Extract array
            [number_direction,~] = size(ROI);
            if number_direction==2 % Case 2D
                ROI = [ROI; 1 1];
                Domain_size = size(app.current_microstructure(ROI(1,1):ROI(1,2),ROI(2,1):ROI(2,2),1:1));
                Domain_size = [Domain_size 1];
            else           
                Domain_size = size(app.current_microstructure(ROI(1,1):ROI(1,2),ROI(2,1):ROI(2,2),ROI(3,1):ROI(3,2)));
            end
        
            size_direction_1 = Domain_size(1)*app.VoxelsizeEditField.Value;
            size_direction_2 = Domain_size(2)*app.VoxelsizeEditField.Value;
            size_direction_3 = Domain_size(3)*app.VoxelsizeEditField.Value;
            size_cubeequivalent = (size_direction_1*size_direction_2*size_direction_3)^(1/3);
            str_1 = sprintf('%0.1f',size_direction_1);
            str_2 = sprintf('%0.1f',size_direction_2);
            str_3 = sprintf('%0.1f',size_direction_3);
            str_4 = sprintf('%0.1f',size_cubeequivalent);
            app.cubicrootsize = [str_4 ' ' app.VoxelsizeUnitEditField.Value];

            c3 = {num2str(prod(Domain_size) / (app.ScalingfactorEditField.Value^3)); [str_1 ' x ' str_2 ' x ' str_3 ' ' app.VoxelsizeUnitEditField.Value '3']; [str_4 ' x ' str_4 ' x ' str_4 ' ' app.VoxelsizeUnitEditField.Value '3']};
            app.voxelinfo_UITable.Data = [c1 c2 c3];
            
            % Saving folder
            app.function_defaultfoldername;
        end
        
        function [] = function_defaultfoldername(app)
            [~,name,~] = fileparts(app.FileName);

            ROI = cell2mat(app.Direction_UITable.Data(:,3:4)); % Extract array
            [number_direction,~] = size(ROI);
            if number_direction==2 % Case 2D
                ROI = [ROI; 1 1];
                Domain_size = size(app.current_microstructure(ROI(1,1):ROI(1,2),ROI(2,1):ROI(2,2),1:1));
                Domain_size = [Domain_size 1];
            else
                Domain_size = size(app.current_microstructure(ROI(1,1):ROI(1,2),ROI(2,1):ROI(2,2),ROI(3,1):ROI(3,2)));
            end

            size_direction_1 = Domain_size(1)*app.VoxelsizeEditField.Value;
            size_direction_2 = Domain_size(2)*app.VoxelsizeEditField.Value;
            size_direction_3 = Domain_size(3)*app.VoxelsizeEditField.Value;
            size_cubeequivalent = (size_direction_1*size_direction_2*size_direction_3)^(1/3);

            strunit = app.VoxelsizeUnitEditField.Value;
            if strcmp(strunit,'um') || strcmp(strunit,'micrometer') || strcmp(strunit,'Micrometer') || strcmp(strunit,'micrometers') || strcmp(strunit,'Micrometers')
                str_vol = [sprintf('%1.0f',size_cubeequivalent) 'um'];
                str_vox = [sprintf('%1.0f',app.NewvoxelsizeEditField.Value*1000) 'nm'];
            elseif strcmp(strunit,'nm') || strcmp(strunit,'nanometer') || strcmp(strunit,'Nanometer') || strcmp(strunit,'nanometers') || strcmp(strunit,'Nanometers')
                str_vol = [sprintf('%1.0f',size_cubeequivalent/1000) 'um'];
                str_vox = [sprintf('%1.0f',app.NewvoxelsizeEditField.Value) 'nm'];
            else
                str_vol = [sprintf('%1.0f',size_cubeequivalent) strunit];
                str_vox = [sprintf('%1.0f',app.NewvoxelsizeEditField.Value*1000) strunit];
            end

            app.VolumefolderEditField.Value = [name '_Vol_' str_vol '_Voxel_' str_vox];
        end
             
        function [] = Check_RVEtable(app)
            % Update table
            kvol = find(ismember(app.RVE_VolumeselectionDropDown.Items, app.RVE_VolumeselectionDropDown.Value));
            kpro = find(ismember(app.property_list, app.RVE_PropertyselectionDropDown.Value));
            nRVE = app.RVE_list.vol(kvol).pro(kpro).number_RVE;

            if nRVE > 0
                cname = cell(1,nRVE+1);
                cname(1) = {'Parameter'};
                for kRVE=1:1:nRVE
                    cname(kRVE+1) = {['Analysis #' num2str(kRVE)]};
                end
                app.RVE_UITable.ColumnName = cname;
                datacell =cell(23,nRVE+1);
                datacell(:,1) = {'Name';'Type';'Divider';'Minimum subvolume size in% of initial volume';'Iterate until';'Do 2 subvolume';'Do 4 subvolumes';'Subvolume aspect ratio';'Constant direction';'RVE std threshold (%)';'...for at least x subvolumes';'Growth along';'First subvolume size in% of initial volume';'Growth per step';'... relative to';'Relative difference threshold (%)';'Do also nested analysis';'(nested) Number of shrinking directions';'(nested) ... with';'(nested) At each step';'(nested) ... with x=';'(nested) Minimum volume size in% of initial volume';'Folder name'};

                for kRVE=1:1:nRVE
                    tmp = struct2cell(app.RVE_list.vol(kvol).pro(kpro).RVE(kRVE));
                    tmpsav=tmp;
                    for k=1:1:16
                        if length(cell2mat(tmp(k)))>1
                            tmp(k) = {num2str(cell2mat(tmp(k)))};
                        end
                    end
                    % manual reordering
                    tmp(17) = tmpsav(18);
                    tmp(18) = {num2str(cell2mat(tmpsav(19)))};
                    tmp(19) = {num2str(cell2mat(tmpsav(20)))};
                    tmp(20) = {num2str(cell2mat(tmpsav(21)))};
                    tmp(21) = {num2str(cell2mat(tmpsav(22)))};
                    tmp(22) = {num2str(cell2mat(tmpsav(23)))};
                    tmp(23) = tmpsav(17);
                    datacell(:,kRVE+1) = tmp;
                end              
                app.RVE_UITable.ColumnName = cname;
                app.RVE_UITable.Data = datacell;

                app.RVE_remove_button.Enable = 'on';
                app.RVE_apply_ALLproperties_THISvolume_button.Enable = 'on';
                app.RVE_apply_THISproperty_ALLvolumes_button.Enable = 'on';
                app.RVE_apply_ALLproperties_ALLvolumes_button.Enable = 'on';
                app.RVE_apply_PERproperties_ALLvolumes_button.Enable = 'on';

            else
                app.RVE_remove_button.Enable = 'off';
                app.RVE_apply_ALLproperties_THISvolume_button.Enable = 'off';
                app.RVE_apply_THISproperty_ALLvolumes_button.Enable = 'off';
                app.RVE_apply_ALLproperties_ALLvolumes_button.Enable = 'off';
                app.RVE_apply_PERproperties_ALLvolumes_button.Enable = 'off';                
                app.RVE_UITable.ColumnName = {'Parameter','Analysis #1'};
                app.RVE_UITable.Data = [{'Name';'Type';'Divider';'Minimum subvolume size in% of initial volume';'Iterate until';'Do 2 subvolume';'Do 4 subvolumes';'Subvolume aspect ratio';'Constant direction';'RVE std threshold (%)';'...for at least x subvolumes';'Growth along';'First subvolume size in% of initial volume';'Growth per step';'... relative to';'Relative difference threshold (%)';'Folder name';'Do also nested analysis';'(nested) Number of shrinking directions';'(nested) ... with';'(nested) At each step';'(nested) ... with x=';'(nested) Minimum volume size in% of initial volume'} {' ';' ';' ';' ';' ';' ';' ';' ';' ';' ';' ';' ';' ';' ';' ';' ';' ';' ';' ';' ';' ';' ';' '}];
            end
            app.RVE_UITable.Visible = 'on'; app.RVE_UITable.Enable = 'on';
        end
        
        function t = func_selectall(app,t,foo)
            if nargin==2
                [rn,cn]=size(t);
            else
                [rn,~]=size(t);
                cn=foo;
            end

            tot_=0; sum_=0;
            for r=1:1:rn
                for c=3:2:cn
                    val = cell2mat(t(r,c));
                    if ~isempty(val)
                        tot_ = tot_+1;
                        sum_ = sum_ + double(val);
                    end
                end
            end
            if sum_>=tot_/2
                new=0;
            else
                new=1;
            end
            for r=1:1:rn
                for c=3:2:cn
                    val = cell2mat(t(r,c));
                    if ~isempty(val)
                        t(r,c) = {logical(new)};
                    end
                end
            end
        end

        
        function t = func_selectall_dir(app,t,c)
            [rn,~]=size(t);
            tot_=0; sum_=0;
            for r=1:1:rn
                val = cell2mat(t(r,c));
                if ~isempty(val)
                    tot_ = tot_+1;
                    sum_ = sum_ + double(val);
                end
            end
            if sum_>=tot_/2
                new=0;
            else
                new=1;
            end
            for r=1:1:rn
                t(r,c) = {logical(new)};
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.NewvoxelsizeEditFieldLabel.Enable = 'on';
            app.FontnameDropDown.Items = listfonts;
            app.FontnameDropDown.Value = 'Times New Roman';
            app.saveoptions_UITable.Data =[{'Save results in .mat';'Save results in .xlsx';'Save figure in .fig';'Save figuer in .png'}  {true;true;true;true}];
            app.voxelinfo_UITable.Data = [{'Number of voxels';'Domain size';'Domain size cubic root'} {' ';' ';' '}  {' ';' ';' '}];
            app.Info_UITable.Data = [];
            app.DiametercolormapDropDown.Items = app.colormap_diameter_list;
            app.DiametercolormapDropDown.Value = app.colormap_diameter_list(1);

            is_installed_imagetoolbox = license('test','Image_Toolbox');
            if ~is_installed_imagetoolbox
                app.Imageprocessing_Label.Visible = 'on';
            end

            % Check online version
            localversion = app.Localversion.Text;
            try
                githubversionfile=webread('https://raw.githubusercontent.com/NREL/MATBOX_Microstructure_analysis_toolbox/master/License.txt');
                if ~strcmp(localversion,githubversionfile)
                    app.OnlineversioncheckLabel.Text = ['There is a new version (' localversion ') available online. Please go to the Github repositorty to update your MATBOX installation'];
                    app.OnlineversioncheckLabel.FontColor = 'r';
                else
                    app.OnlineversioncheckLabel.Text = 'You are using the up-to-date version of MATBOX';
                    app.OnlineversioncheckLabel.FontColor = 'k';
                end
            catch ME
                if strcmp(ME.identifier,'MATLAB:webservices:Timeout')
                    app.OnlineversioncheckLabel.Text = 'Could not check current version online.';
                end
            end

            % Specific surface area corrective factor
            % Surface of an oblate spheroid: https://en.wikipedia.org/wiki/Ellipsoid#Surface_area
            % Then you can deduce the analytical relationship below knowing the calculated, overestimated surface is a sum of 6 ellipoid area
            r=0.001:0.001:0.999; % Ratio short diameter / long diameters (oblate a:a:b, r=b/a, a>b)
            corrective_factor = (1 + (r.^2 ./ ((1-r.^2).^0.5)).*atanh((1-r.^2).^0.5)) ./ (1+2*r);
            hold(app.Sp_correctivefactor_UIAxes,'on'); % Active subplot
            h_title=title(app.Sp_correctivefactor_UIAxes,'Corrective factor for an oblate spheroid of diameters: L:L:S, L>S'); % Set title font
            % Plot graphs
            plot(app.Sp_correctivefactor_UIAxes,r,corrective_factor,'Linestyle','-','Linewidth',2);
            % Axis label
            xlabel(app.Sp_correctivefactor_UIAxes,'Short diameter S / long diameters L');
            ylabel(app.Sp_correctivefactor_UIAxes,'Theoritical surface / voxel-based surface');
            % - Grid
            grid(app.Sp_correctivefactor_UIAxes,'on'); % Display grid
            set(app.Sp_correctivefactor_UIAxes,'XMinorGrid','off','YMinorGrid','off'); % Display grid for minor thicks
            set(app.Sp_correctivefactor_UIAxes,'FontName','times new roman','FontSize',14); % Fontname and fontsize
            h_title.FontSize = 14; % Set title fontsize
            hold(app.Sp_correctivefactor_UIAxes,'off'); % Relase figure

            Box_counting_activate = false; % Algorithm needs more work
            if ~Box_counting_activate
                app.Volume_Fractal_CheckBox.Visible = 'off'; app.Volume_Fractal_CheckBox.Enable = 'off';
                app.vf_fractal_voxelsizeCheckBox.Visible= 'off'; app.vf_fractal_voxelsizeCheckBox.Enable  = 'off';
                app.vf_Boxlengthmax_EditField.Visible = 'off'; app.vf_Boxlengthmax_EditField.Enable = 'off';  
                app.BoxlengthmaximumvalueinvoxellenghEditFieldLabel.Visible = 'off';
                app.Setto1e9togothoughallpossibleboxlengthLabel.Visible = 'off';

                app.Sp_Fractal_CheckBox.Visible = 'off'; app.Sp_Fractal_CheckBox.Enable = 'off';
                app.Sp_fractal_voxelsizeCheckBox.Visible= 'off'; app.Sp_fractal_voxelsizeCheckBox.Enable  = 'off';
                app.Sp_Boxlengthmax_EditField.Visible = 'off'; app.Sp_Boxlengthmax_EditField.Enable = 'off';  
                app.BoxlengthmaximumvalueinvoxellenghEditFieldLabel_3.Visible = 'off';
                app.Setto1e9togothoughallpossibleboxlengthLabel_3.Visible = 'off';     

                app.Int_Fractal_CheckBox.Visible = 'off'; app.Int_Fractal_CheckBox.Enable = 'off';
                app.Int_fractal_voxelsizeCheckBox.Visible= 'off'; app.Int_fractal_voxelsizeCheckBox.Enable  = 'off';
                app.Int_Boxlengthmax_EditField.Visible = 'off'; app.Int_Boxlengthmax_EditField.Enable = 'off';  
                app.BoxlengthmaximumvalueinvoxellenghEditFieldLabel_4.Visible = 'off';
                app.Setto1e9togothoughallpossibleboxlengthLabel_4.Visible = 'off';     

                app.TPBL_Fractal_Boxcounting_CheckBox.Visible = 'off'; app.TPBL_Fractal_Boxcounting_CheckBox.Enable = 'off';
                app.TPBL_fractal_voxelsizeCheckBox.Visible= 'off'; app.TPBL_fractal_voxelsizeCheckBox.Enable  = 'off';
                app.TPBL_Boxlengthmax_EditField.Visible = 'off'; app.TPBL_Boxlengthmax_EditField.Enable = 'off';  
                app.BoxlengthmaximumvalueinvoxellenghEditFieldLabel_2.Visible = 'off';
                app.Setto1e9togothoughallpossibleboxlengthLabel_2.Visible = 'off';  
            end

        end

        % Button pushed function: ImportvolumeButton
        function ImportvolumeButtonPushed(app, event)
            % Open dialog box to choose file path
            if isempty(app.workingpath)
                app.workingpath = pwd;
            end
            [app.FileName,app.PathName,~] = uigetfile({'*.tif;*.tiff','Tif image (*.tif, *.tiff)'},'Select a .tif file',app.workingpath);
            if app.FileName==0
                % User clicked cancel button or closed the dialog box
                app.Imported_volume_name_label.Text ='No file imported.';
                app.current_microstructure=[]; % Reset Microstructure
                app.Volumenumber1Label.Visible = 'off';
                app.Visualize_volume_button.Enable = 'off'; app.Visualize_vf_button.Enable='off'; app.Visualize_ROI_button.Enable = 'off'; app.Visualize_ROI_vf_button.Enable = 'off';
                app.VoxelsizeEditField.Enable = 'off'; app.VoxelsizeUnitEditField.Enable = 'off'; app.ScalingfactorEditField.Enable = 'off';
                app.VoxelsizeEditFieldLabel.Enable = 'off'; app.UnitEditFieldLabel.Enable = 'off'; app.ScalingfactorEditFieldLabel.Enable = 'off';
                app.Direction_UITable.Data = []; app.Vf_UITable.Data = []; app.color_phase = [];
                app.voxelinfo_UITable.Data = [{'Number of voxels';'Domain size';'Domain size cubic root'} {' ';' ';' '}  {' ';' ';' '}];
                app.Main_folder_Label.Text = 'Not defined';
                app.VolumefolderEditField.Value = 'Not defined';
                app.Save_volume_button.Enable = 'off';
                app.Info_UITable.Data = [{'Material source';'Material name';'Material group name';'Volume description';'Raw data source';'Raw data technique';'Segmentation source';'Segmentation technique';'Analysis decription'} {'Not defined';'Not defined';'Not defined';'Not defined';'Not defined';'Not defined';'Not defined';'Not defined';'Not defined'}];
            else
                app.Imported_volume_name_label.Text = 'Loading file... please wait'; pause(0.1);
                [tmp,outcome] = function_load_tif([app.PathName app.FileName]);
                if outcome % Success to import
                    app.current_microstructure=tmp;
                    app.Imported_volume_name_label.Text = [app.PathName app.FileName];
                    app.Volumenumber1Label.Text = ['Volume number ' num2str(app.number_volume,'%i')];
                    app.Volumenumber1Label.Visible = 'on';
                    app.Visualize_volume_button.Enable = 'on'; app.Visualize_vf_button.Enable='on'; app.Visualize_ROI_button.Enable = 'on'; app.Visualize_ROI_vf_button.Enable = 'on';

                    % Fill volume fraction table
                    col_ = [colororder; rand(1e6,3)];
                    phase_label = unique(app.current_microstructure);
                    number_phase = length(phase_label);
                    phase_number = cell(number_phase,1);
                    volume_fractions = zeros(number_phase,2);
                    phase_name = cell(number_phase,1);
                    for k=1:1:number_phase
                        phase_number(k,1)={num2str(k)};
                        volume_fractions(k,1)=sum(sum(sum( app.current_microstructure==phase_label(k) )))/numel(app.current_microstructure);
                        app.color_phase(k,:) = col_(k,:);
                        if ~isempty(app.phase_name_sav) && length(app.phase_name_sav)>=k
                            phase_name(k,1) = app.phase_name_sav(k,1); % Avoid re-writting each time if volumes are same materials
                        else
                            phase_name(k,1) = {['Phase ' num2str(k)]};
                        end
                        %phase_label(k,1) = k;
                        s = uistyle('BackgroundColor',app.color_phase(k,:));
                        addStyle(app.Vf_UITable,s,'cell',[k,4]);
                    end
                    app.Vf_UITable.Data = [phase_number num2cell(volume_fractions(:,1)) num2cell(volume_fractions(:,1)) cell(number_phase,1) phase_name num2cell(phase_label) num2cell(phase_label)];

                    % Fill direction table
                    number_direction = length(size(app.current_microstructure));
                    direction_number = cell(number_direction,1);
                    direction_voxel = size(app.current_microstructure)';
                    ROI_start = ones(number_direction,1);
                    ROI_end = size(app.current_microstructure)';
                    direction_name = cell(number_direction,1);
                    for k=1:1:number_direction
                        direction_number(k,1)={num2str(k)};
                        if ~isempty(app.direction_name_sav) && length(app.direction_name_sav)>=k
                            direction_name(k,1) = app.direction_name_sav(k,1); % Avoid re-writting each time if volumes are same materials
                        else
                            direction_name(k,1) = {['Direction ' num2str(k)]};
                        end
                    end
                    app.Direction_UITable.Data = [direction_number num2cell(direction_voxel) num2cell(ROI_start) num2cell(ROI_end) direction_name];

                    app.VoxelsizeEditField.Enable = 'on'; app.VoxelsizeUnitEditField.Enable = 'on'; app.ScalingfactorEditField.Enable = 'on';
                    app.VoxelsizeEditFieldLabel.Enable = 'on'; app.UnitEditFieldLabel.Enable = 'on'; app.ScalingfactorEditFieldLabel.Enable = 'on';
                    
                    % Fill voxel info table
                    app.function_update_voxeltable;

                    % Volume info table
                    if ~isempty(app.volumeinfo_sav)
                        app.Info_UITable.Data = app.volumeinfo_sav;
                    else
                        app.Info_UITable.Data = [{'Material source';'Material name';'Material group name';'Volume description';'Raw data source';'Raw data technique';'Segmentation source';'Segmentation technique';'Analysis decription'} {'Not defined';'Not defined';'Not defined';'Not defined';'Not defined';'Not defined';'Not defined';'Not defined';'Not defined'}];
                    end

                    % Saving folder
                    if ~isempty(app.mainfolder_sav)
                        app.Main_folder_Label.Text = app.mainfolder_sav;
                    else
                        app.Main_folder_Label.Text = app.PathName;
                    end
                    app.Save_volume_button.Enable = 'on';

                else
                    app.Imported_volume_name_label.Text ='Error: volume cannot be loaded! File is not a .tif file';
                    app.current_microstructure=[]; % Reset Microstructure
                    app.Volumenumber1Label.Visible = 'off';
                    app.Visualize_volume_button.Enable = 'off'; app.Visualize_vf_button.Enable='off'; app.Visualize_ROI_button.Enable = 'off'; app.Visualize_ROI_vf_button.Enable = 'off';
                    app.VoxelsizeEditField.Enable = 'off'; app.VoxelsizeUnitEditField.Enable = 'off'; app.ScalingfactorEditField.Enable = 'off';
                    app.VoxelsizeEditFieldLabel.Enable = 'off'; app.UnitEditFieldLabel.Enable = 'off'; app.ScalingfactorEditFieldLabel.Enable = 'off';
                    app.Direction_UITable.Data = []; app.Vf_UITable.Data = []; app.color_phase = [];
                    app.voxelinfo_UITable.Data = [{'Number of voxels';'Domain size';'Domain size cubic root'} {' ';' ';' '}  {' ';' ';' '}];
                    app.Main_folder_Label.Text = 'Not defined';
                    app.VolumefolderEditField.Value = 'Not defined';
                    app.Save_volume_button.Enable = 'off';
                    app.Info_UITable.Data = [{'Material source';'Material name';'Material group name';'Volume description';'Raw data source';'Raw data technique';'Segmentation source';'Segmentation technique';'Analysis decription'} {'Not defined';'Not defined';'Not defined';'Not defined';'Not defined';'Not defined';'Not defined';'Not defined';'Not defined'}];
                end

            end
        end

        % Button pushed function: Visualize_volume_button
        function Visualize_volume_buttonButtonPushed(app, event)
            p.direction_name = app.Direction_UITable.Data(:,5);
            p.custom_colormap = app.color_phase;
            Microstructure_basic_visualization_interface(app.current_microstructure,p);
        end

        % Cell selection callback: Vf_UITable
        function Vf_UITableCellSelection(app, event)
            indices = event.Indices;
            if ~isempty(indices)
                if indices(2)==4 % color
                    c = uisetcolor;
                    if length(c)==3 % RGB
                        app.color_phase(indices(1),:) = c;
                        s = uistyle('BackgroundColor',app.color_phase(indices(1),:));
                        addStyle(app.Vf_UITable,s,'cell',[indices(1),4]);
                    end
                end
            end
        end

        % Button pushed function: Visualize_ROI_button
        function Visualize_ROI_buttonButtonPushed(app, event)
            % Visualize
            p.direction_name = app.Direction_UITable.Data(:,5);
            p.custom_colormap = app.color_phase;
            ROI = cell2mat(app.Direction_UITable.Data(:,3:4)); % Extract array
            [number_direction,~] = size(ROI);
            if number_direction==2
                ROI = [ROI; 1 1];
            end
            Microstructure_basic_visualization_interface(app.current_microstructure(ROI(1,1):ROI(1,2),ROI(2,1):ROI(2,2),ROI(3,1):ROI(3,2)),p);
        end

        % Cell edit callback: Direction_UITable
        function Direction_UITableCellEdit(app, event)
            indices = event.Indices;
            if indices(2)==3 || indices(2)==4 % ROI
                number_direction = length(size(app.current_microstructure));
                ROI = cell2mat(app.Direction_UITable.Data(:,3:4)); % Extract array
                ROI(isnan(ROI))=-1e9; % NaN -> -1e9
                ROI(:,1) = max( ones(number_direction,1),ROI(:,1) ); % > 1
                ROI(:,2) = max( ones(number_direction,1),ROI(:,2) );
                ROI(:,1) = min( size(app.current_microstructure)',ROI(:,1) ); % < Domain size
                ROI(:,2) = min( size(app.current_microstructure)',ROI(:,2) );
                ROI(:,1:2) = sort(ROI(:,1:2),2); % Start < End
                app.Direction_UITable.Data(:,3:4)= num2cell(ROI); % Insert table

                % Update volume fraction
                phase_label = cell2mat(app.Vf_UITable.Data(:,6));
                number_phase = length(phase_label);
                volume_fractions = zeros(number_phase,1);
                if number_direction==2
                    ROI = [ROI; 1 1];
                end
                for k=1:1:number_phase
                    volume_fractions(k,1)=sum(sum(sum( app.current_microstructure(ROI(1,1):ROI(1,2),ROI(2,1):ROI(2,2),ROI(3,1):ROI(3,2))==phase_label(k) )))/numel(app.current_microstructure(ROI(1,1):ROI(1,2),ROI(2,1):ROI(2,2),ROI(3,1):ROI(3,2)));
                end
                app.Vf_UITable.Data(:,3) = num2cell(volume_fractions(:,1));

                % Update voxel info table
                app.function_update_voxeltable;
            end
        end

        % Button pushed function: Visualize_vf_button
        function Visualize_vf_buttonButtonPushed(app, event)
            app.function_vf_alongthickness(app.current_microstructure,'Full volume');
        end

        % Button pushed function: Visualize_ROI_vf_button
        function Visualize_ROI_vf_buttonButtonPushed(app, event)
            ROI = cell2mat(app.Direction_UITable.Data(:,3:4)); % Extract array
            [number_direction,~] = size(ROI);
            if number_direction==2
                ROI = [ROI; 1 1];
            end            
            app.function_vf_alongthickness(app.current_microstructure(ROI(1,1):ROI(1,2),ROI(2,1):ROI(2,2),ROI(3,1):ROI(3,2)),'ROI');
        end

        % Value changed function: ScalingfactorEditField, 
        % ...and 2 other components
        function ScalingfactorEditFieldValueChanged(app, event)
            app.NewvoxelsizeEditField.Value = app.VoxelsizeEditField.Value * app.ScalingfactorEditField.Value;
            % Update voxel info table
            app.function_update_voxeltable;
        end

        % Button pushed function: SelectmainfolderButton
        function SelectmainfolderButtonPushed(app, event)
            % Set string of the dialog box
            str_dialogbox = 'Select location where the result folder will be created';
            % Open dialog box to choose folder path
            resultfolder_location = uigetdir(app.Main_folder_Label.Text,str_dialogbox);
            if resultfolder_location
                app.Main_folder_Label.Text = resultfolder_location;
            end
        end

        % Button pushed function: Save_volume_button
        function Save_volume_buttonButtonPushed(app, event)
            % % Save

            % Order of calculation
            app.inputvolume.volume(app.number_volume).orderofcalculation=app.number_volume;
            % loading path
            app.inputvolume.volume(app.number_volume).loadingpath=app.Imported_volume_name_label.Text;
            % Input file name
            [~, name_, ~] = fileparts(app.Imported_volume_name_label.Text);
            app.inputvolume.volume(app.number_volume).filename_input=name_;

            % ROI
            app.inputvolume.volume(app.number_volume).ROI = cell2mat(app.Direction_UITable.Data(:,3:4));
            % Direction name
            app.inputvolume.volume(app.number_volume).directionname = app.Direction_UITable.Data(:,5);
            for k=1:1:length(app.Direction_UITable.Data(:,5))
                app.inputvolume.volume(app.number_volume).direction_name(k).compatible = function_remove_emptyandspecialcharacter_string( char(app.inputvolume.volume(app.number_volume).directionname(k)) );
            end
            app.direction_name_sav = app.Direction_UITable.Data(:,5); % For next volume

            % Phase name
            app.inputvolume.volume(app.number_volume).initial_phasename = app.Vf_UITable.Data(:,5);
            app.phase_name_sav = app.Vf_UITable.Data(:,5); % For next volume
            % Phase color
            app.inputvolume.volume(app.number_volume).phasecolor = app.color_phase;
            % Label
            app.inputvolume.volume(app.number_volume).initial_phaselabel = cell2mat(app.Vf_UITable.Data(:,6:7));

            % Voxel size
            app.inputvolume.volume(app.number_volume).initial_voxelsize =  app.VoxelsizeEditField.Value;
            app.inputvolume.volume(app.number_volume).scaling_factor =  app.ScalingfactorEditField.Value;
            app.inputvolume.volume(app.number_volume).voxelsize =  app.NewvoxelsizeEditField.Value;
            % Unit
            app.inputvolume.volume(app.number_volume).unit = app.VoxelsizeUnitEditField.Value;

            % Volume information
            app.inputvolume.volume(app.number_volume).volumeinformation = app.Info_UITable.Data;            
            app.volumeinfo_sav = app.Info_UITable.Data;

            % Saving path
            tmp = app.Main_folder_Label.Text;
            if ispc
                separator = '\';
            else
                separator = '/';
            end
            if strcmp(tmp(end),separator)
                tmp=tmp(1:end-1);
            end
            app.inputvolume.volume(app.number_volume).mainfolder = tmp;
            app.inputvolume.volume(app.number_volume).volumesubfolder = app.VolumefolderEditField.Value;
            if ispc
                app.inputvolume.volume(app.number_volume).volpath = [tmp '\' app.VolumefolderEditField.Value '\'];
            else
                app.inputvolume.volume(app.number_volume).volpath = [tmp '/' app.VolumefolderEditField.Value '/'];
            end
            app.mainfolder_sav = app.Main_folder_Label.Text;
            app.workingpath = app.PathName;

            % % Imported volume tab
            if isempty(app.Order_UITable.Data)
                newrow = [{name_} {app.cubicrootsize} {[num2str(app.NewvoxelsizeEditField.Value) ' ' app.VoxelsizeUnitEditField.Value]} {app.number_volume}];
                app.Order_UITable.Data = newrow;
            else
                orders = max(cell2mat(app.Order_UITable.Data(:,4)));
                newrow = [{name_} {app.cubicrootsize} {[num2str(app.NewvoxelsizeEditField.Value) ' ' app.VoxelsizeUnitEditField.Value]} {orders+1}];
                app.Order_UITable.Data = [app.Order_UITable.Data; newrow];
            end
            app.Save_importedvolume_button.Enable = 'on';
            
            % % Increment number
            app.number_volume=app.number_volume+1;

            % % Reset
            app.Imported_volume_name_label.Text ='Volume saved. You can import a new volume.';
            app.current_microstructure=[]; % Reset Microstructure
            app.Volumenumber1Label.Visible = 'off';
            app.Visualize_volume_button.Enable = 'off'; app.Visualize_vf_button.Enable='off'; app.Visualize_ROI_button.Enable = 'off'; app.Visualize_ROI_vf_button.Enable = 'off';
            app.VoxelsizeEditField.Enable = 'off'; app.VoxelsizeUnitEditField.Enable = 'off'; app.ScalingfactorEditField.Enable = 'off';
            app.Direction_UITable.Data = []; app.Vf_UITable.Data = []; app.color_phase = [];
            app.Info_UITable.Data =[];
            app.voxelinfo_UITable.Data = [{'Number of voxels';'Domain size';'Domain size cubic root'} {' ';' ';' '}  {' ';' ';' '}];
            app.Main_folder_Label.Text = 'Not defined';
            app.VolumefolderEditField.Value = 'Not defined';
            app.Save_volume_button.Enable = 'off';
        end

        % Cell edit callback: Order_UITable
        function Order_UITableCellEdit(app, event)
            newData = event.NewData;
            % Prevent <0 or string
            orders = cell2mat(app.Order_UITable.Data(:,4));
            n = length(orders);
            if isnan(newData) || newData<0
                app.Order_UITable.Data(:,4) = num2cell([1:1:n]');
            end
            % Prevent duplicate
            orders = cell2mat(app.Order_UITable.Data(:,4));
            orders(orders==0)=[];
            if length(orders)~=length(unique(orders))
                app.Order_UITable.Data(:,4) = num2cell([1:1:n]');
            end
            % Prevent only zeros
            orders = cell2mat(app.Order_UITable.Data(:,4));
            if length(orders)==sum(orders==0)
                app.Order_UITable.Data(:,4) = num2cell([1:1:n]');
            end
            % Prevent non integer
            orders = cell2mat(app.Order_UITable.Data(:,4));
            if sum(round(orders)-orders) ~= 0
                app.Order_UITable.Data(:,4) = num2cell([1:1:n]');
            end
        end

        % Button pushed function: Save_importedvolume_button
        function Save_importedvolume_buttonButtonPushed(app, event)
            
            app.Save_importedvolume_button.Text = 'Please wait...';
            pause(0.01);            
            
            % Re-order with continous numeration
            orders = cell2mat(app.Order_UITable.Data(:,4));
            sort_orders = sort(orders);
            sort_orders(sort_orders==0) = [];
            [~,neworders] = ismember(orders,sort_orders);
            app.Order_UITable.Data(:,4) = num2cell(neworders);

            % Create table for phase selection
            numbervolume = sum(neworders~=0);
            number_phasemax = 0;
            for kvol = 1:1:app.number_volume-1
                if neworders(kvol)~=0
                    number_phasemax = max(number_phasemax, length(unique(app.inputvolume.volume(kvol).initial_phaselabel(:,2))));
                end
            end
            table_phaseselection = cell(numbervolume,2*number_phasemax+1);
            table_phase_direction_selection_section = cell(numbervolume,2*number_phasemax+1+6);
            table_phase_direction_selection_allphase = cell(numbervolume,2*number_phasemax+1+3);
            table_2phaseselection = cell(numbervolume,4);
            table_3phaseselection = cell(numbervolume,4);
            table_coupledphaseselection = cell(numbervolume,6);
            number_volume_to_characterize = sum(neworders~=0);
            for k=1:1:number_volume_to_characterize
                kvol = find(neworders==k);
                table_phaseselection(k,1) = {[app.inputvolume.volume(kvol).filename_input ' (#' num2str(k) ')']};
                phaselabel = app.inputvolume.volume(kvol).initial_phaselabel(:,2);
                phasename = app.inputvolume.volume(kvol).initial_phasename;
                ulabel = unique(phaselabel);
                nphase = length(ulabel);
                app.inputvolume.volume(kvol).phasename=cell(nphase,1);
                for kphase=1:1:nphase
                    idx = find(phaselabel==ulabel(kphase));
                    str = char(app.inputvolume.volume(kvol).initial_phasename(idx(1)));
                    if length(idx)==1
                        table_phaseselection(k,2*kphase)= app.inputvolume.volume(kvol).initial_phasename(idx);
                    else
                        for kstr=2:1:length(idx)
                            str = [str ' and ' char(app.inputvolume.volume(kvol).initial_phasename(idx(kstr)))];
                        end
                        table_phaseselection(k,2*kphase)={str};
                    end
                    % 2 and 3 phase property
                    tmp = [num2str(ulabel(kphase)) ': ' char(table_phaseselection(k,2*kphase))];
                    if kphase==1
                        strphases=tmp;
                    else
                        strphases = [strphases ', ' tmp];
                    end
                    table_phaseselection(k,2*kphase+1)={true};
                    app.inputvolume.volume(kvol).phasename(kphase) = {str};
                end
                app.inputvolume.volume(kvol).phaselabel = ulabel;
                app.inputvolume.volume(kvol).phase_name(kphase).compatible = function_remove_emptyandspecialcharacter_string( char(app.inputvolume.volume(kvol).phasename(kphase)) );

                % For phase i - phase j properties (interface)
                table_2phaseselection(k,1) = table_phaseselection(k,1);
                table_2phaseselection(k,2) = {strphases};
                if nphase==2
                    table_2phaseselection(k,3)={false};
                    table_2phaseselection(k,4) = {'Do specific surface area instead'};
                elseif nphase>=3
                    table_2phaseselection(k,3)={true};
                    table_2phaseselection(k,4) = {[num2str(ulabel(1)) ',' num2str(ulabel(2)) ';' num2str(ulabel(1)) ',' num2str(ulabel(3)) ';' num2str(ulabel(2)) ',' num2str(ulabel(3))  ]};
                end

                % For phase i - phase j - phase l properties (TPBL)
                table_3phaseselection(k,1) = table_phaseselection(k,1);
                table_3phaseselection(k,2) = {strphases};
                if nphase<3
                    table_3phaseselection(k,3)={false};
                    table_3phaseselection(k,4) = {'n/a'};
                elseif nphase>=3
                    table_3phaseselection(k,3)={true};
                    table_3phaseselection(k,4) = {[num2str(ulabel(1)) ',' num2str(ulabel(2)) ',' num2str(ulabel(3)) ]};
                end     

                % For couple-phase (connectivity)
                table_coupledphaseselection(k,1) = table_phaseselection(k,1);
                table_coupledphaseselection(k,2) = {strphases};
                table_coupledphaseselection(k,3)={true};
                str = num2str(ulabel(1)); 
                for kphase=2:1:nphase-1
                    str = [str ',' num2str(ulabel(kphase))];
                end
                str = [str ',' num2str(ulabel(nphase))];
                table_coupledphaseselection(k,4)={str};
                if nphase==2
                    table_coupledphaseselection(k,5)={'n/a'};
                elseif nphase==3
                    table_coupledphaseselection(k,5) = {[num2str(ulabel(2)) ',' num2str(ulabel(3))]};
                else
                    table_coupledphaseselection(k,5) = {[num2str(ulabel(1)) ',' num2str(ulabel(2)) ';' num2str(ulabel(1)) ',' num2str(ulabel(3)) ';' num2str(ulabel(2)) ',' num2str(ulabel(3))  ]};
                end
                table_coupledphaseselection(k,6)=table_coupledphaseselection(k,5);                

                % For 1-phase-property with directions
                table_phase_direction_selection_allphase(k,1) = table_phaseselection(k,1);
                table_phase_direction_selection_allphase(k,2) = {true};
                table_phase_direction_selection_allphase(k,3) = {true};
                table_phase_direction_selection_allphase(k,4) = {true};
                table_phase_direction_selection_allphase(k,5:end) = table_phaseselection(k,2:end);

                % For 1-phase-property with directions and section cut (Tortuosity)
                table_phase_direction_selection_section(k,1) = table_phaseselection(k,1);
                table_phase_direction_selection_section(k,2) = {true};
                table_phase_direction_selection_section(k,3) = {1};
                table_phase_direction_selection_section(k,4) = {true};
                table_phase_direction_selection_section(k,5) = {1};
                table_phase_direction_selection_section(k,6) = {true};
                table_phase_direction_selection_section(k,7) = {1};                
                table_phase_direction_selection_section(k,8:end) = table_phaseselection(k,2:end);
                for kk=11:2:2*number_phasemax+1+6
                    if ~isempty(cell2mat(table_phase_direction_selection_section(k,kk)))
                        table_phase_direction_selection_section(k,kk) = {false}; % By default, only pore domain
                    end
                end                

            end

            % Name and edit for 1-phase property
            cname_phaseselection = cell(1,2*number_phasemax+1);
            cname_phaseselection(1) = {'Volume'};
            for k=2:2:2*number_phasemax
                cname_phaseselection(k)={['Phase #' num2str(k/2)]};
            end
            cedit_phaseselection = [false];
            for kphase=1:1:number_phasemax
                cedit_phaseselection(2*kphase)=false;
                cedit_phaseselection(2*kphase+1)=true;
            end

            % Name and edit for 2-phase property
            cname_2phaseselection = {'Volume','Phase labels','To do','Label doublets'};
            cedit_2phaseselection = [false false true true];

            % Name and edit for 3-phase property
            cname_3phaseselection = {'Volume','Phase labels','To do','Label triplets'};      
            cedit_3phaseselection = [false false true true];

            % Name and edit for coupledphase property
            cname_coupledphaseselection = {'Volume','Phase labels','To do','Individuals','Union','Coupled'};      
            cedit_coupledphaseselection = [false false  true true true true];

            % Name and edit for 1-phase property with directions
            cname_phase_direction_selection = cell(1,2*number_phasemax+1+3);
            cname_phase_direction_selection(1) = {'Volume'};
            cname_phase_direction_selection(2) = {'Direction 1'};
            cname_phase_direction_selection(3) = {'Direction 2'};
            cname_phase_direction_selection(4) = {'Direction 3'};
            cname_phase_direction_selection(5:end) = cname_phaseselection(2:end);
            cedit_phase_direction_selection = [false];
            cedit_phase_direction_selection(1) = false;
            cedit_phase_direction_selection(2) = true;
            cedit_phase_direction_selection(3) = true;
            cedit_phase_direction_selection(4) = true;
            for k=6:2:2*number_phasemax+1+3
                cedit_phase_direction_selection(k-1) = false;
                cedit_phase_direction_selection(k) = true;
            end

            % Name and edit for 1-phase property with directions and sectoin
            cname_phase_direction_selection_section = cell(1,2*number_phasemax+1+6);
            cname_phase_direction_selection_section(1) = {'Volume'};
            cname_phase_direction_selection_section(2) = {'Direction 1'};
            cname_phase_direction_selection_section(3) = {'#Section'};
            cname_phase_direction_selection_section(4) = {'Direction 2'};
            cname_phase_direction_selection_section(5) = {'#Section'};
            cname_phase_direction_selection_section(6) = {'Direction 3'};
            cname_phase_direction_selection_section(7) = {'#Section'};
            cname_phase_direction_selection_section(8:end) = cname_phaseselection(2:end);
            cedit_phase_direction_selection_section = [false];
            cedit_phase_direction_selection_section(1) = false;
            cedit_phase_direction_selection_section(2) = true;
            cedit_phase_direction_selection_section(3) = true;
            cedit_phase_direction_selection_section(4) = true;
            cedit_phase_direction_selection_section(5) = true;
            cedit_phase_direction_selection_section(6) = true;
            cedit_phase_direction_selection_section(7) = true;            
            for k=9:2:2*number_phasemax+1+6
                cedit_phase_direction_selection_section(k-1) = false;
                cedit_phase_direction_selection_section(k) = true;
            end            


            % Create table for voxel size dependence analysis
            default_voxelsizeanalysis = cell(numbervolume,4);
            default_voxelsizeanalysis(:,1) = table_phaseselection(:,1);
            for k=1:1:number_volume_to_characterize
                kvol = find(neworders==k);
                default_voxelsizeanalysis(k,2) = {[num2str(app.inputvolume.volume(kvol).voxelsize) ' ' app.inputvolume.volume(kvol).unit]};
                default_voxelsizeanalysis(k,3) = {'1'};
                default_voxelsizeanalysis(k,4) = default_voxelsizeanalysis(k,2);
            end

            app.order_calculation = cell2mat(app.Order_UITable.Data(:,4));

            orders = app.order_calculation;
            ks = [1:1:length(orders)]';
            app.row2nputvolumevolumek = [orders ks];
            app.row2nputvolumevolumek = sortrows(app.row2nputvolumevolumek,1);
            id0 = find(app.row2nputvolumevolumek(:,1)==0);
            if ~isempty(id0)
                app.row2nputvolumevolumek(id0,:)=[];
            end

            % Create table for statut run
            table_statutrun = cell(numbervolume,4);
            table_statutrun(:,1) = table_phaseselection(:,1);
      
            % % Update tables (1phase-property)
            % Volume fraction
            app.vf_phaseselection_UITable.Data = table_phaseselection;
            app.vf_phaseselection_UITable.ColumnName =cname_phaseselection;
            app.vf_phaseselection_UITable.ColumnEditable =cedit_phaseselection;
            app.vf_phaseselection_UITable.Enable = 'on';

            % Specific surface area (direct)
            app.Spdirect_phaseselection_UITable.Data = table_phaseselection;
            app.Spdirect_phaseselection_UITable.ColumnName =cname_phaseselection;
            app.Spdirect_phaseselection_UITable.ColumnEditable =cedit_phaseselection;
            app.Spdirect_phaseselection_UITable.Enable = 'on';

            % Diameter (c-PSD)
            app.Dcpsd_phaseselection_UITable.Data = table_phaseselection;
            app.Dcpsd_phaseselection_UITable.ColumnName =cname_phaseselection;
            app.Dcpsd_phaseselection_UITable.ColumnEditable =cedit_phaseselection;
            app.Dcpsd_phaseselection_UITable.Enable = 'on';

            % Diameter (EDMF)
            %app.Dedmf_phaseselection_UITable.Data = table_phaseselection;
            %app.Dedmf_phaseselection_UITable.ColumnName =cname_phaseselection;
            %app.Dedmf_phaseselection_UITable.ColumnEditable =cedit_phaseselection;
            %app.Dedmf_phaseselection_UITable.Enable = 'on';   

            % Diameter (watershed)
            app.Dwatershed_phaseselection_UITable.Data = table_phaseselection;
            app.Dwatershed_phaseselection_UITable.ColumnName =cname_phaseselection;
            app.Dwatershed_phaseselection_UITable.ColumnEditable =cedit_phaseselection;
            app.Dwatershed_phaseselection_UITable.Enable = 'on';            
           
            % % Update tables (1-phase-property, directional)
            % Tortuosity
            app.Tau_phaseselection_UITable.Data = table_phase_direction_selection_section;
            app.Tau_phaseselection_UITable.ColumnName =cname_phase_direction_selection_section;
            app.Tau_phaseselection_UITable.ColumnEditable =cedit_phase_direction_selection_section;
            app.Tau_phaseselection_UITable.Enable = 'on';            

            % Diameter (EDMF)
            app.Dedmf_phaseselection_UITable.Data = table_phase_direction_selection_allphase;
            app.Dedmf_phaseselection_UITable.ColumnName =cname_phase_direction_selection;
            app.Dedmf_phaseselection_UITable.ColumnEditable =cedit_phase_direction_selection;
            app.Dedmf_phaseselection_UITable.Enable = 'on';   

            % Diameter (Chord)
            app.Dchord_phaseselection_UITable.Data = table_phase_direction_selection_allphase;
            app.Dchord_phaseselection_UITable.ColumnName =cname_phase_direction_selection;
            app.Dchord_phaseselection_UITable.ColumnEditable =cedit_phase_direction_selection;
            app.Dchord_phaseselection_UITable.Enable = 'on';   

            % % Update tables (2-phase-property)
            % Interface
            app.Interface_phaseselection_UITable.Data = table_2phaseselection;
            app.Interface_phaseselection_UITable.ColumnName =cname_2phaseselection;
            app.Interface_phaseselection_UITable.ColumnEditable =cedit_2phaseselection;
            app.Interface_phaseselection_UITable.Enable = 'on';

            % % Update tables (3-phase-property)
            % TPBL
            app.TPBL_phaseselection_UITable.Data = table_3phaseselection;
            app.TPBL_phaseselection_UITable.ColumnName =cname_3phaseselection;
            app.TPBL_phaseselection_UITable.ColumnEditable =cedit_3phaseselection;
            app.TPBL_phaseselection_UITable.Enable = 'on';            

            % % Update tables (coupled-phase-property)
            % Connectivity
            app.Connectivity_phaseselection_UITable.Data = table_coupledphaseselection;
            app.Connectivity_phaseselection_UITable.ColumnName =cname_coupledphaseselection;
            app.Connectivity_phaseselection_UITable.ColumnEditable =cedit_coupledphaseselection;
            app.Connectivity_phaseselection_UITable.Enable = 'on'; 

            % Voxel size dependence analysis 
            app.voxelsizeUITable.Data = default_voxelsizeanalysis;
            app.voxelsizeUITable.Enable = 'on';
            app.Voxel_ExtrapolatetozerovoxelsizeDropDown.Enable = 'on';
            app.ExtrapolatetozerovoxelsizeDropDownLabel_2.Enable = 'on';
            app.Voxelsize_PropertyselectionDropDown.Enable = 'on';
            app.PropertyselectionDropDownLabel_2.Enable = 'on';  
            app.Voxelsize_PropertyselectionDropDown.Items = app.property_list;
            % Default values
            app.voxelsizeanalysis.volumefraction.scaling = default_voxelsizeanalysis;
            app.voxelsizeanalysis.volumefraction.scaling_extrapolation = app.Voxel_ExtrapolatetozerovoxelsizeDropDown.Value;
            app.voxelsizeanalysis.connectivity.scaling = default_voxelsizeanalysis;
            app.voxelsizeanalysis.connectivity.scaling_extrapolation = app.Voxel_ExtrapolatetozerovoxelsizeDropDown.Value;
            app.voxelsizeanalysis.tortuosity.scaling = default_voxelsizeanalysis;
            app.voxelsizeanalysis.tortuosity.scaling_extrapolation = app.Voxel_ExtrapolatetozerovoxelsizeDropDown.Value;            
            app.voxelsizeanalysis.Bruggeman.scaling_extrapolation = app.Voxel_ExtrapolatetozerovoxelsizeDropDown.Value; 
            app.voxelsizeanalysis.Deff.scaling_extrapolation = app.Voxel_ExtrapolatetozerovoxelsizeDropDown.Value; 
            app.voxelsizeanalysis.Sp_direct.scaling = default_voxelsizeanalysis;
            app.voxelsizeanalysis.Sp_direct.scaling_extrapolation = app.Voxel_ExtrapolatetozerovoxelsizeDropDown.Value; 
            app.voxelsizeanalysis.Int_direct.scaling = default_voxelsizeanalysis;
            app.voxelsizeanalysis.Int_direct.scaling_extrapolation = app.Voxel_ExtrapolatetozerovoxelsizeDropDown.Value;    
            app.voxelsizeanalysis.TPBL_direct.scaling = default_voxelsizeanalysis;
            app.voxelsizeanalysis.TPBL_direct.scaling_extrapolation = app.Voxel_ExtrapolatetozerovoxelsizeDropDown.Value;                
            app.voxelsizeanalysis.D_cpsd.scaling = default_voxelsizeanalysis;
            app.voxelsizeanalysis.D_cpsd.scaling_extrapolation = app.Voxel_ExtrapolatetozerovoxelsizeDropDown.Value;       
            app.voxelsizeanalysis.D_cpsd_lvldetail.scaling_extrapolation = app.Voxel_ExtrapolatetozerovoxelsizeDropDown.Value; 
            app.voxelsizeanalysis.D_edmf.scaling = default_voxelsizeanalysis;
            app.voxelsizeanalysis.D_edmf.scaling_extrapolation = app.Voxel_ExtrapolatetozerovoxelsizeDropDown.Value;   
            app.voxelsizeanalysis.D_chord.scaling = default_voxelsizeanalysis;
            app.voxelsizeanalysis.D_chord.scaling_extrapolation = app.Voxel_ExtrapolatetozerovoxelsizeDropDown.Value;              
            app.voxelsizeanalysis.D_watershed.scaling = default_voxelsizeanalysis;
            app.voxelsizeanalysis.D_watershed.scaling_extrapolation = app.Voxel_ExtrapolatetozerovoxelsizeDropDown.Value;    
            app.voxelsizeanalysis.D_pcrf.scaling = default_voxelsizeanalysis;
            app.voxelsizeanalysis.D_pcrf.scaling_extrapolation = app.Voxel_ExtrapolatetozerovoxelsizeDropDown.Value;    
            app.voxelsizeanalysis.D_label.scaling = default_voxelsizeanalysis;
            app.voxelsizeanalysis.D_label.scaling_extrapolation = app.Voxel_ExtrapolatetozerovoxelsizeDropDown.Value;                
            app.voxelsizeanalysis.D_covariance.scaling = default_voxelsizeanalysis;
            app.voxelsizeanalysis.D_covariance.scaling_extrapolation = app.Voxel_ExtrapolatetozerovoxelsizeDropDown.Value; 

            % RVE analysis
            app.RVE_VolumeselectionDropDown.Enable = 'on'; app.VolumeselectionDropDownLabel.Enable = 'on';
            app.RVE_VolumeselectionDropDown.Items =table_phaseselection(:,1);
            app.RVE_VolumeselectionDropDown.Value = app.RVE_VolumeselectionDropDown.Items(1);
            app.RVE_PropertyselectionDropDown.Items = app.property_list;
            app.RVE_PropertyselectionDropDown.Value = app.RVE_PropertyselectionDropDown.Items(1);
            app.RVE_PropertyselectionDropDown.Enable = 'on'; app.PropertyselectionDropDownLabel.Enable = 'on';

            % Reset RVE
            app.RVE_UITable.ColumnName = {'Parameter','Analysis #1'};
            app.RVE_UITable.Data = [{'Name';'Type';'Divider';'Minimum subvolume size in% of initial volume';'Iterate until';'Do 2 subvolume';'Do 4 subvolumes';'Subvolume aspect ratio';'Constant direction';'RVE std threshold (%)';'...for at least x subvolumes';'Growth along';'First subvolume size in% of initial volume';'Growth per step';'... relative to';'Relative difference threshold (%)';'Folder name';'Do also nested analysis';'(nested) Number of shrinking directions';'(nested) ... with';'(nested) At each step';'(nested) ... with x=';'(nested) Minimum volume size in% of initial volume'} {' ';' ';' ';' ';' ';' ';' ';' ';' ';' ';' ';' ';' ';' ';' ';' ';' ';' ';' ';' ';' ';' ';' '}];
            for kvol=1:1:1000
                for kpro=1:1:1000
                    app.RVE_list.vol(kvol).pro(kpro).number_RVE = 0;
                    app.RVE_list.vol(kvol).pro(kpro).RVE = [];
                end
            end
            app.RVE_PropertyselectionDropDownValueChanged;

            % % Enable run calculation
            app.RunStatut_UITable.Data=table_statutrun;
            app.Run_button.Enable = 'on'; 

            % Move to next tab
            app.Save_importedvolume_button.Text = 'Save your settings';
            app.TabGroup.SelectedTab = app.VolumefractionsTab;
        end

        % Button pushed function: Run_button
        function Run_buttonButtonPushed(app, event)
            % optc: options common to all volumes
            % opts: options volume specific 
            
            % Toolbox info (hardcoded)
            optc.toolbox.toolboxname = 'Microstructure analysis toolbox (MATBOX)';
            optc.toolbox.version = '1.1, March 2022';
            optc.toolbox.githubrepo = 'https://github.com/NREL/MATBOX_Microstructure_analysis_toolbox';
            optc.toolbox.officialwebpage = 'https://github.com/NREL/MATBOX_Microstructure_analysis_toolbox';            
            optc.toolbox.article = 'F.L.E.Usseglio-Viretta et al.,MATBOX: An Open-source Microstructure Analysis Toolbox for microstructure generation, segmentation, characterization, visualization, correlation, and meshing, SoftwareX, Volume 17, January 2022, 100915 https://doi.org/10.1016/j.softx.2021.100915';
            optc.toolbox.authorlist = {'Francois L. E. Usseglio-Viretta','National Renewable Energy Laboratory';
                                      'Prehit Patel','National Renewable Energy Laboratory';
                                       'Elizabeth Bernhardt','National Renewable Energy Laboratory';
                                       'Aashutosh Mistry', 'Argonne National Laboratory';
                                       'Partha P. Mukherjee', 'Purdue University';
                                       'Jeffery Allen', 'National Renewable Energy Laboratory';
                                       'Samuel J. Cooper','Imperial College London';
                                       'Jerome Laurencin','CEA-Liten';
                                       'Kandler Smith','National Renewable Energy Laboratory'};

            % % PRINT IMFORMATION ABOUT TOOLBOX AND USER
            Table_toolbox = table({'Toolbox';'Version';'GitHub repository';'Journal article'},{optc.toolbox.toolboxname;optc.toolbox.version;optc.toolbox.githubrepo;optc.toolbox.article},...
                'VariableNames',{'Item','Value'});            
            Table_author = table(optc.toolbox.authorlist(:,1),optc.toolbox.authorlist(:,2),...
                'VariableNames',{'Author','Affiliation'});
            username = getenv('USERNAME');
            computername = getenv('COMPUTERNAME');
            os = getenv('OS');
            Table_User = table({username},{computername},{os},...
                'VariableNames',{'User_name','Computer_name','Operating_system'});

            clc % Clear command window
            disp '***************************************';
            disp '>>> MICROSTRUCTURE ANALYSIS TOOLBOX <<<';
            disp '    Characterization module';
            disp '***************************************';
            disp ' ';
            disp(Table_toolbox);disp ' ';
            disp(Table_author);disp ' ';
            disp 'Run by:';            
            disp(Table_User);disp ' ';

            % Save options
            tmp = logical(cell2mat(app.saveoptions_UITable.Data(:,2)));
            optc.save.mat = tmp(1);
            optc.save.xls = tmp(2);
            optc.save.fig_infig = tmp(3);
            if tmp(4)
                optc.save.fig_format = {'png'};
            else
                optc.save.fig_format = [];
            end
            optc.save.savefig = tmp(4) || tmp(3);

            % Format options
            optc.format.fontname = app.FontnameDropDown.Value;
            optc.format.axefontsize = app.AxeFontsizeEditField.Value;
            optc.format.legendfontsize = app.LegendfontsizeEditField.Value;
            optc.format.titlefontsize = app.TitlefontsizeEditField.Value;
            optc.format.sgtitlefontsize = app.SubplotmaintitleiesgtitlefontsizeEditField.Value;
            optc.format.linewidth = app.LinewidthEditField.Value;
            if strcmp(app.GridDropDown.Value,'No grid')
                optc.format.grid = 'off';
                optc.format.minorgrid = 'off';
            elseif strcmp(app.GridDropDown.Value,'Grid')
                optc.format.grid = 'on';
                optc.format.minorgrid = 'off';
            elseif strcmp(app.GridDropDown.Value,'Grid and minor grid')
                optc.format.grid = 'on';
                optc.format.minorgrid = 'on';
            end
            optc.format.autoclosefig = app.ClosefigureoncedisplayedsavedCheckBox.Value;

            % Loop over all files
            % app.inputvolume(kvol): import order -> need to be reorder: unde kvol index
            % app.RVE_list.vol(k): already re-ordered: use k index
            % app.voxelsizeanalysis.*.scaling: already re-ordered: use k index
            orders = app.order_calculation;
            number_volume_to_characterize = sum(orders~=0);
            for k=1:1:number_volume_to_characterize
                kvol = find(orders==k);
                % % Set volume specific options  
                number_phase = length(app.inputvolume.volume(kvol).phaselabel);

                % % Volume fractions
                opts.volumefraction.todo = zeros(number_phase,1);
                for kphase=1:1:number_phase
                    opts.volumefraction.todo(kphase,1) = cell2mat(app.vf_phaseselection_UITable.Data(k,2*kphase+1));
                end  
                % Voxel size
                tmp = app.voxelsizeanalysis.volumefraction.scaling;
                opts.volumefraction.scaling = str2num(cell2mat(tmp(k,3)));
                opts.volumefraction.scaling_extrapolation = app.voxelsizeanalysis.volumefraction.scaling_extrapolation;
                % RVE
                opts.volumefraction.RVE = app.RVE_list.vol(k).pro(1); % 1 for volume fraction
                % Fractal analysis
                opts.volumefraction.fractal_bertei.todo = app.vf_Fractal_Bertei_CheckBox.Value;     
                opts.volumefraction.fractal_boxcounting.todo = app.Volume_Fractal_CheckBox.Value;     
                opts.volumefraction.fractal_boxcounting.voxelsize = app.vf_fractal_voxelsizeCheckBox.Value;                  
                opts.volumefraction.fractal_boxcounting.boxmaxlength = app.vf_Boxlengthmax_EditField.Value; 

                % % Connectivity
                opts.connectivity.todo = cell2mat(app.Connectivity_phaseselection_UITable.Data(k,3));
                opts.connectivity.ind = str2num(cell2mat(app.Connectivity_phaseselection_UITable.Data(k,4)));
                opts.connectivity.union = char(app.Connectivity_phaseselection_UITable.Data(k,5));
                opts.connectivity.plotdir = app.Connectivity_plotdirection_DropDown.Value;
                opts.connectivity.plottype = app.Connectivity_plottype_DropDown.Value;                
                opts.connectivity.coupled = str2num(cell2mat(app.Connectivity_phaseselection_UITable.Data(k,6)));
                opts.connectivity.coupled_direction = app.Connectivity_DirectionofinterestDropDown.Value;
                opts.connectivity.coupled_orientation = app.Connectivity_OrientationDropDown.Value;
                % Voxel size
                tmp = app.voxelsizeanalysis.connectivity.scaling;
                opts.connectivity.scaling = str2num(cell2mat(tmp(k,3)));
                opts.connectivity.scaling_extrapolation = app.voxelsizeanalysis.connectivity.scaling_extrapolation;
                % RVE
                opts.connectivity.RVE = app.RVE_list.vol(k).pro(2); % 2 for connectivity

                % % Tortuosity factor
                opts.tortuosity.todo = zeros(number_phase,1);
                for kphase=1:1:number_phase
                    opts.tortuosity.todo(kphase,1) = cell2mat(app.Tau_phaseselection_UITable.Data(k,2*kphase+1+6));
                    opts.tortuosity.direction_todo(1) = cell2mat(app.Tau_phaseselection_UITable.Data(k,2));
                    opts.tortuosity.direction_todo(2) = cell2mat(app.Tau_phaseselection_UITable.Data(k,4));
                    opts.tortuosity.direction_todo(3) = cell2mat(app.Tau_phaseselection_UITable.Data(k,6));
                    opts.tortuosity.direction_section(1) = cell2mat(app.Tau_phaseselection_UITable.Data(k,3));
                    opts.tortuosity.direction_section(2) = cell2mat(app.Tau_phaseselection_UITable.Data(k,5));
                    opts.tortuosity.direction_section(3) = cell2mat(app.Tau_phaseselection_UITable.Data(k,7));                    
                end  
                % Voxel size
                tmp = app.voxelsizeanalysis.tortuosity.scaling;
                opts.tortuosity.scaling = str2num(cell2mat(tmp(k,3)));
                opts.tortuosity.scaling_extrapolation = app.voxelsizeanalysis.tortuosity.scaling_extrapolation;
                opts.tortuosity.Bruggeman_scaling_extrapolation = app.voxelsizeanalysis.Bruggeman.scaling_extrapolation;
                opts.tortuosity.Deff_scaling_extrapolation = app.voxelsizeanalysis.Deff.scaling_extrapolation;                
                % RVE
                opts.tortuosity.RVE = app.RVE_list.vol(k).pro(3); % 3 for tortuosity factor

                % % Specific surface area (direct)
                opts.Sp_direct.todo = zeros(number_phase,1);
                for kphase=1:1:number_phase
                    opts.Sp_direct.todo(kphase,1) = cell2mat(app.Spdirect_phaseselection_UITable.Data(k,2*kphase+1));
                end
                opts.Sp_direct.definition = app.Sp_DefinitionDropDown.Value;
                opts.Sp_direct.correctivefactor = app.CorrectivefactorEditField.Value;      
                opts.Sp_direct.donotconsiderisolatedcompvol = app.Sp_donotconsidercomvol.Value;  
                % Voxel size
                tmp = app.voxelsizeanalysis.Sp_direct.scaling;
                opts.Sp_direct.scaling = str2num(cell2mat(tmp(k,3)));
                opts.Sp_direct.scaling_extrapolation = app.voxelsizeanalysis.Sp_direct.scaling_extrapolation;
                % RVE
                opts.Sp_direct.RVE = app.RVE_list.vol(k).pro(4); % 4 for specific surface area direct          
                % Fractal analysis
                opts.Sp_direct.fractal_bertei.todo = app.Sp_Fractal_Bertei_CheckBox.Value;     
                opts.Sp_direct.fractal_boxcounting.todo = app.Sp_Fractal_CheckBox.Value;     
                opts.Sp_direct.fractal_boxcounting.voxelsize = app.Sp_fractal_voxelsizeCheckBox.Value;                  
                opts.Sp_direct.fractal_boxcounting.boxmaxlength = app.Sp_Boxlengthmax_EditField.Value;                 

                % % Specific interface area (direct)
                opts.Int_direct.todo = cell2mat(app.Interface_phaseselection_UITable.Data(k,3));
                opts.Int_direct.doublets = str2num(cell2mat(app.Interface_phaseselection_UITable.Data(k,4)));
                opts.Int_direct.correctivefactor = app.Correctivefactor_interface_EditField.Value;      
                % Voxel size
                tmp = app.voxelsizeanalysis.Int_direct.scaling;
                opts.Int_direct.scaling = str2num(cell2mat(tmp(k,3)));
                opts.Int_direct.scaling_extrapolation = app.voxelsizeanalysis.Int_direct.scaling_extrapolation;
                % RVE
                opts.Int_direct.RVE = app.RVE_list.vol(k).pro(5); % 5 for specific interface area direct  
                % Fractal analysis
                opts.Int_direct.fractal_bertei.todo = app.Int_Fractal_Bertei_CheckBox.Value;     
                opts.Int_direct.fractal_boxcounting.todo = app.Int_Fractal_CheckBox.Value;     
                opts.Int_direct.fractal_boxcounting.voxelsize = app.Int_fractal_voxelsizeCheckBox.Value;                  
                opts.Int_direct.fractal_boxcounting.boxmaxlength = app.Int_Boxlengthmax_EditField.Value;                       

                % % Triple phase boundary length (direct)
                opts.TPBL_direct.todo = cell2mat(app.TPBL_phaseselection_UITable.Data(k,3));
                opts.TPBL_direct.triplets = str2num(cell2mat(app.TPBL_phaseselection_UITable.Data(k,4)));
                opts.TPBL_direct.correctivefactor = app.Correctivefactor_TPBL_EditField.Value;      
                % Voxel size
                tmp = app.voxelsizeanalysis.TPBL_direct.scaling;
                opts.TPBL_direct.scaling = str2num(cell2mat(tmp(k,3)));
                opts.TPBL_direct.scaling_extrapolation = app.voxelsizeanalysis.TPBL_direct.scaling_extrapolation;
                % RVE
                opts.TPBL_direct.RVE = app.RVE_list.vol(k).pro(6); % 6 for specific interface area direct      
                % Fractal analysis
                opts.TPBL_direct.fractal_bertei.todo = app.TPBL_Fractal_Bertei_CheckBox.Value;     
                opts.TPBL_direct.fractal_boxcounting.todo = app.TPBL_Fractal_Boxcounting_CheckBox.Value;     
                opts.TPBL_direct.fractal_boxcounting.voxelsize = app.TPBL_fractal_voxelsizeCheckBox.Value;                  
                opts.TPBL_direct.fractal_boxcounting.boxmaxlength = app.TPBL_Boxlengthmax_EditField.Value;     

                % % Diameter (c-PSD)
                opts.D_cpsd.todo = zeros(number_phase,1);
                for kphase=1:1:number_phase
                    opts.D_cpsd.todo(kphase,1) = cell2mat(app.Dcpsd_phaseselection_UITable.Data(k,2*kphase+1));
                end
                % Voxel size
                tmp = app.voxelsizeanalysis.D_cpsd.scaling;
                opts.D_cpsd.scaling = str2num(cell2mat(tmp(k,3)));
                opts.D_cpsd.scaling_extrapolation = app.voxelsizeanalysis.D_cpsd.scaling_extrapolation;
                opts.D_cpsd.lvldetail_scaling_extrapolation = app.voxelsizeanalysis.D_cpsd_lvldetail.scaling_extrapolation;
                % RVE
                opts.D_cpsd.RVE = app.RVE_list.vol(k).pro(7); % 7 for diameter c-PSD  
                % Colormap
                opts.D_cpsd.colormap = app.DiametercolormapDropDown.Value;
                opts.D_cpsd.colormap_background = app.DiameterbackgroundcolormapDropDown.Value;

                % % Diameter (EDMF)
                opts.D_edmf.todo = zeros(number_phase,1);
                for kphase=1:1:number_phase
                    %opts.D_edmf.todo(kphase,1) = cell2mat(app.Dedmf_phaseselection_UITable.Data(k,2*kphase+1));
                    opts.D_edmf.todo(kphase,1) = cell2mat(app.Dedmf_phaseselection_UITable.Data(k,2*kphase+1+3));
                    opts.D_edmf.direction_todo(1:3) = cell2mat(app.Dedmf_phaseselection_UITable.Data(k,2:4));
                end
                opts.D_edmf.removeinclusion = app.EDMF_RemoveinclusionCheckBox.Value;
                opts.D_edmf.distancelabel = app.EDMF_DistancetowardlabelEditField.Value;
                % Voxel size
                tmp = app.voxelsizeanalysis.D_edmf.scaling;
                opts.D_edmf.scaling = str2num(cell2mat(tmp(k,3)));
                opts.D_edmf.scaling_extrapolation = app.voxelsizeanalysis.D_edmf.scaling_extrapolation;
                % RVE
                opts.D_edmf.RVE = app.RVE_list.vol(k).pro(8); % 8 for diameter edmf  
                % Colormap
                opts.D_edmf.colormap = app.DiametercolormapDropDown.Value;
                opts.D_edmf.colormap_background = app.DiameterbackgroundcolormapDropDown.Value;                

                % % Diameter (Chord)
                opts.D_chord.todo = zeros(number_phase,1);
                for kphase=1:1:number_phase
                    opts.D_chord.todo(kphase,1) = cell2mat(app.Dchord_phaseselection_UITable.Data(k,2*kphase+1+3));
                    opts.D_chord.direction_todo(1:3) = cell2mat(app.Dchord_phaseselection_UITable.Data(k,2:4));
                end  
                opts.D_chord.size_filter = app.Chord_SizefilterEditField.Value;
                % Voxel size
                tmp = app.voxelsizeanalysis.D_chord.scaling;
                opts.D_chord.scaling = str2num(cell2mat(tmp(k,3)));
                opts.D_chord.scaling_extrapolation = app.voxelsizeanalysis.tortuosity.scaling_extrapolation;
                % RVE
                opts.D_chord.RVE = app.RVE_list.vol(k).pro(9); % 9 for diameter chord
                % Colormap
                opts.D_chord.colormap = app.DiametercolormapDropDown.Value;
                opts.D_chord.colormap_background = app.DiameterbackgroundcolormapDropDown.Value;  

                % % Diameter (watershed)
                opts.D_watershed.todo = zeros(number_phase,1);
                for kphase=1:1:number_phase
                    opts.D_watershed.todo(kphase,1) = cell2mat(app.Dwatershed_phaseselection_UITable.Data(k,2*kphase+1));
                end
                opts.D_watershed.correctoversegmentation_cpsd = app.Watershed_ReassignCheckBox.Value;
                opts.D_watershed.correctoversegmentation_sizethreshold = app.Watershed_SizefilterEditField.Value;
                opts.D_watershed.verbose = app.Watershed_Detail_CheckBox.Value;                
                % Colormap
                opts.D_watershed.colormap = app.DiametercolormapDropDown.Value;
                opts.D_watershed.colormap_background = app.DiameterbackgroundcolormapDropDown.Value;

                % % Start
                date_start = datetime('now','TimeZone','local','Format','eeee, HH:mm:ss Z, MMMM d, y');
                Table_date = table({char(date_start)},...
                    'VariableNames',{'Date'});
                disp '>>>';
                disp(['>>> VOLUME #' num2str(k) ' <<<']);
                disp '>>>';
                disp ' ';
                disp(Table_date);disp ' ';
                app.RunStatut_UITable.Data(k,2) = {'On-going'};
                app.RunStatut_UITable.Data(k,3) = {char(date_start)};
                app.RunStatut_UITable.Data(k,4) = {' '};
                sPass = uistyle("Icon","none","IconAlignment","rightmargin");
                addStyle(app.RunStatut_UITable,sPass,"cell",[k 2]);                
                pause(0.01);
                tic
                function_microstructure_characterization_segmentedvolume(app.inputvolume.volume(kvol),optc,opts); % Call characterization function
                app.RunStatut_UITable.Data(k,4) = {[num2str(toc,'%1.1f') ' s']};
                app.RunStatut_UITable.Data(k,2) = {'Done'};
                sPass = uistyle("Icon","success","IconAlignment","rightmargin");
                addStyle(app.RunStatut_UITable,sPass,"cell",[k 2]);
                pause(0.01);
            end
            disp ' ';
            disp '**************************************************************************************';
            disp '>>> ALL CALCULATIONS FOR ALL VOLUMES HAVE BEEN PERFORMED <<<';
            disp '    Results (figures and tables) are located within the ''volume folder'' of each volume';
            disp '**************************************************************************************';

        end

        % Button pushed function: RVE_add_button
        function RVE_add_buttonButtonPushed(app, event)
            app.RVE_UITable.Visible = 'off';
            app.RVE_add_button.Enable = 'off'; app.RVE_remove_button.Enable = 'off';
            app.DonestedanalysisCheckBox.Value = false;
            app.DonestedanalysisCheckBoxValueChanged;
            app.SelectRVEorconvergenceanalysisDropDownValueChanged;
            app.RVEandconvergenceoptionsPanel.Visible = 'on';
            app.RVEandconvergenceoptionsPanel.Enable = 'on';            
        end

        % Button pushed function: RVE_remove_button
        function RVE_remove_buttonButtonPushed(app, event)
            kvol = find(ismember(app.RVE_VolumeselectionDropDown.Items, app.RVE_VolumeselectionDropDown.Value));
            kpro = find(ismember(app.property_list, app.RVE_PropertyselectionDropDown.Value));
            app.RVE_list.vol(kvol).pro(kpro).number_RVE = 0;
            app.RVE_UITable.ColumnName = {'Parameter','Analysis #1'};
            app.RVE_UITable.Data = [{'Name';'Type';'Divider';'Minimum subvolume size in% of initial volume';'Iterate until';'Do 2 subvolume';'Do 4 subvolumes';'Subvolume aspect ratio';'Constant direction';'RVE std threshold (%)';'...for at least x subvolumes';'Growth along';'First subvolume size in% of initial volume';'Growth per step';'... relative to';'Relative difference threshold (%)';'Folder name';'Do also nested analysis';'(nested) Number of shrinking directions';'(nested) ... with';'(nested) At each step';'(nested) ... with x=';'(nested) Minimum volume size in% of initial volume'} {' ';' ';' ';' ';' ';' ';' ';' ';' ';' ';' ';' ';' ';' ';' ';' ';' ';' ';' ';' ';' ';' ';' '}];
            app.RVE_remove_button.Enable = 'off';
            app.RVE_apply_ALLproperties_THISvolume_button.Enable = 'off';
            app.RVE_apply_THISproperty_ALLvolumes_button.Enable = 'off';
            app.RVE_apply_ALLproperties_ALLvolumes_button.Enable = 'off';
            app.RVE_apply_PERproperties_ALLvolumes_button.Enable = 'off';
        end

        % Button pushed function: RVE_apply_ALLproperties_THISvolume_button
        function RVE_apply_ALLproperties_THISvolume_buttonButtonPushed(app, event)
            kvol = find(ismember(app.RVE_VolumeselectionDropDown.Items, app.RVE_VolumeselectionDropDown.Value));
            kpro = find(ismember(app.property_list, app.RVE_PropertyselectionDropDown.Value));
            n_property = length(app.property_list);
            for kkpro=1:1:n_property
                app.RVE_list.vol(kvol).pro(kkpro) = app.RVE_list.vol(kvol).pro(kpro);
            end
        end

        % Button pushed function: RVE_apply_THISproperty_ALLvolumes_button
        function RVE_apply_THISproperty_ALLvolumes_buttonButtonPushed(app, event)
            kvol = find(ismember(app.RVE_VolumeselectionDropDown.Items, app.RVE_VolumeselectionDropDown.Value));
            kpro = find(ismember(app.property_list, app.RVE_PropertyselectionDropDown.Value));
            n_volume = length(app.RVE_VolumeselectionDropDown.Items);
            for kkvol=1:1:n_volume
                app.RVE_list.vol(kkvol).pro(kpro) = app.RVE_list.vol(kvol).pro(kpro);
            end            
        end

        % Button pushed function: RVE_apply_ALLproperties_ALLvolumes_button
        function RVE_apply_ALLproperties_ALLvolumes_buttonButtonPushed(app, event)
            kvol = find(ismember(app.RVE_VolumeselectionDropDown.Items, app.RVE_VolumeselectionDropDown.Value));
            kpro = find(ismember(app.property_list, app.RVE_PropertyselectionDropDown.Value));
            n_property = length(app.property_list);
            n_volume = length(app.RVE_VolumeselectionDropDown.Items);
            for kkvol=1:1:n_volume
                for kkpro=1:1:n_property
                    app.RVE_list.vol(kkvol).pro(kkpro) = app.RVE_list.vol(kvol).pro(kpro);
                end
            end
        end

        % Button pushed function: RVE_apply_PERproperties_ALLvolumes_button
        function RVE_apply_PERproperties_ALLvolumes_buttonButtonPushed(app, event)
            kvol = find(ismember(app.RVE_VolumeselectionDropDown.Items, app.RVE_VolumeselectionDropDown.Value));
            kpro = find(ismember(app.property_list, app.RVE_PropertyselectionDropDown.Value));            
            n_property = length(app.property_list);
            n_volume = length(app.RVE_VolumeselectionDropDown.Items);
            for kkvol=1:1:n_volume
                for kkpro=1:1:n_property
                    app.RVE_list.vol(kkvol).pro(kkpro) = app.RVE_list.vol(kvol).pro(kkpro);
                end
            end            
        end

        % Value changed function: SelectRVEorconvergenceanalysisDropDown
        function SelectRVEorconvergenceanalysisDropDownValueChanged(app, event)
            value = app.SelectRVEorconvergenceanalysisDropDown.Value;
            if strcmp(value,'Independant subvolumes of same size + keep initial aspect ratio (A)') || strcmp(value,'Independant subvolumes of same size + user-defined aspect ratio (B)') || strcmp(value,'Independant subvolumes of same size + one constant length (C)') || strcmp(value,'Independant subvolumes of same size + two constant lengths (D)')
                app.CaseABCDRVEanalysisPanel.Enable = 'on';
                app.CaseEFGHconvergenceanalysisPanel.Enable = 'off';
                if strcmp(value,'Independant subvolumes of same size + keep initial aspect ratio (A)')
                    app.subvolumes_sub2_CheckBox.Enable = 'on';
                    app.subvolumes_sub4_CheckBox.Enable = 'on';     
                    app.SubvolumeaspectratioEditField.Enable = 'off'; app.SubvolumeaspectratioEditFieldLabel.Enable = 'off';
                    app.Formatabcwithabcrealnumber0Label.Enable = 'off';
                    app.ConstantdirectionslengthDropDown.Enable = 'off'; app.ConstantdirectionslengthDropDownLabel.Enable = 'off';
                elseif strcmp(value,'Independant subvolumes of same size + user-defined aspect ratio (B)')
                    app.subvolumes_sub2_CheckBox.Enable = 'off'; app.subvolumes_sub2_CheckBox.Value = 0;
                    app.subvolumes_sub4_CheckBox.Enable = 'off'; app.subvolumes_sub4_CheckBox.Value = 0;
                    app.SubvolumeaspectratioEditField.Enable = 'on'; app.SubvolumeaspectratioEditFieldLabel.Enable = 'on';
                    app.Formatabcwithabcrealnumber0Label.Enable = 'on';
                    app.ConstantdirectionslengthDropDown.Enable = 'off'; app.ConstantdirectionslengthDropDownLabel.Enable = 'off';
                elseif strcmp(value,'Independant subvolumes of same size + one constant length (C)')
                    app.subvolumes_sub2_CheckBox.Enable = 'on';
                    app.subvolumes_sub4_CheckBox.Enable = 'off'; app.subvolumes_sub4_CheckBox.Value = 0;
                    app.SubvolumeaspectratioEditField.Enable = 'off'; app.SubvolumeaspectratioEditFieldLabel.Enable = 'off';
                    app.Formatabcwithabcrealnumber0Label.Enable = 'off';
                    app.ConstantdirectionslengthDropDown.Enable = 'on'; app.ConstantdirectionslengthDropDownLabel.Enable = 'on';
                    app.ConstantdirectionslengthDropDown.Items = {'Direction 1', 'Direction 2', 'Direction 3'};
                    app.ConstantdirectionslengthDropDown.Value = 'Direction 1';
                elseif strcmp(value,'Independant subvolumes of same size + two constant lengths (D)')
                    app.subvolumes_sub2_CheckBox.Enable = 'off'; app.subvolumes_sub2_CheckBox.Value = 0;
                    app.subvolumes_sub4_CheckBox.Enable = 'off'; app.subvolumes_sub4_CheckBox.Value = 0;
                    app.SubvolumeaspectratioEditField.Enable = 'off'; app.SubvolumeaspectratioEditFieldLabel.Enable = 'off';
                    app.Formatabcwithabcrealnumber0Label.Enable = 'off';
                    app.ConstantdirectionslengthDropDown.Enable = 'on'; app.ConstantdirectionslengthDropDownLabel.Enable = 'on'; 
                    app.ConstantdirectionslengthDropDown.Items = {'Direction 1 and 2', 'Direction 1 and 3', 'Direction 2 and 3'};
                    app.ConstantdirectionslengthDropDown.Value = 'Direction 1 and 2';
                end
            elseif strcmp(value,'One subvolume + growing from volume center + keep initial aspect ratio (E)') || strcmp(value,'One subvolume + growing from volume center + user-defined aspect ratio (F)') || strcmp(value,'One subvolume + growing from volume edge (G)') || strcmp(value,'One subvolume + one constant length (H)')
                app.CaseABCDRVEanalysisPanel.Enable = 'off';
                app.CaseEFGHconvergenceanalysisPanel.Enable = 'on';
                if strcmp(value,'One subvolume + growing from volume center + keep initial aspect ratio (E)')
                    app.GrowthdirectionDropDown.Enable = 'off'; app.GrowthdirectionDropDownLabel.Enable = 'off';
                    app.SubvolumeaspectratioEditField_2.Enable = 'off'; app.SubvolumeaspectratioEditField_2Label.Enable = 'off';

                elseif  strcmp(value,'One subvolume + growing from volume center + user-defined aspect ratio (F)')
                    app.GrowthdirectionDropDown.Enable = 'off'; app.GrowthdirectionDropDownLabel.Enable = 'off';
                    app.SubvolumeaspectratioEditField_2.Enable = 'on'; app.SubvolumeaspectratioEditField_2Label.Enable = 'on';

                elseif  strcmp(value,'One subvolume + growing from volume edge (G)')
                    app.GrowthdirectionDropDown.Enable = 'on'; app.GrowthdirectionDropDownLabel.Enable = 'on';
                    app.SubvolumeaspectratioEditField_2.Enable = 'off'; app.SubvolumeaspectratioEditField_2Label.Enable = 'off';
                    app.GrowthdirectionDropDownLabel.Text = 'Growth direction';
                    app.GrowthdirectionDropDown.Items = {'Direction 1, from x min to x max','Direction 1, from x max to x min','Direction 2, from y min to y max','Direction 2, from y max to y min','Direction 3, from z min to z max','Direction 3, from z max to z min'};
                    app.GrowthdirectionDropDown.Value = 'Direction 1, from x min to x max';                    

                elseif  strcmp(value,'One subvolume + one constant length (H)')
                    app.GrowthdirectionDropDown.Enable = 'on'; app.GrowthdirectionDropDownLabel.Enable = 'on';
                    app.SubvolumeaspectratioEditField_2.Enable = 'off'; app.SubvolumeaspectratioEditField_2Label.Enable = 'off';
                    app.GrowthdirectionDropDownLabel.Text = 'Constant direction';
                    app.GrowthdirectionDropDown.Items = {'Direction 1', 'Direction 2', 'Direction 3'};
                    app.GrowthdirectionDropDown.Value = 'Direction 1';
                end
            end            
        end

        % Button pushed function: RVE_saveoptionButton
        function RVE_saveoptionButtonPushed(app, event)
            app.RVEandconvergenceoptionsPanel.Visible = 'off';
            app.RVEandconvergenceoptionsPanel.Enable = 'off';
            app.RVE_add_button.Enable = 'on';

            kvol = find(ismember(app.RVE_VolumeselectionDropDown.Items, app.RVE_VolumeselectionDropDown.Value));
            kpro = find(ismember(app.property_list, app.RVE_PropertyselectionDropDown.Value));

            nRVE = app.RVE_list.vol(kvol).pro(kpro).number_RVE + 1;
            app.RVE_list.vol(kvol).pro(kpro).number_RVE = nRVE;

            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).name = app.SelectRVEorconvergenceanalysisDropDown.Value;
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).type = ' ';

            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).divisions = 'n/a';
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).minsubsize = 'n/a';
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).iterateuntil = 'n/a';
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).subs2 = 'n/a';
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).subs4 = 'n/a';
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).Aspectratio = 'n/a';
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).Constantdirection = 'n/a';
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).threshold_std = 'n/a';
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).threshold_numbersubvolumes = 'n/a';
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).Growthdirection = 'n/a';
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).firstuniquevolume_size = 'n/a';
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).Growthperstep = 'n/a';
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).Growthrelativeto = 'n/a';
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).threshold_reldiff = 'n/a';
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).savename = 'n/a';

            orders = app.order_calculation;
            k = find(orders==kvol);
            ROI = app.inputvolume.volume(k).ROI; 
       
            AR_initial = [(ROI(1,2)-ROI(1,1)+1)/(ROI(3,2)-ROI(3,1)+1) (ROI(2,2)-ROI(2,1)+1)/(ROI(3,2)-ROI(3,1)+1) 1.0];

            if strcmp(app.SelectRVEorconvergenceanalysisDropDown.Value,'Independant subvolumes of same size + keep initial aspect ratio (A)')
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).type = 'A';
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).Aspectratio = AR_initial;
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).savename = ['SubInitialAR_' num2str(AR_initial(1),'%1.3f\t') ' ' num2str(AR_initial(2),'%1.3f\t') ' ' ' ' num2str(AR_initial(3),'%1.3f\t')];
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).divisions = str2num(app.DividerEditField.Value);
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).minsubsize = app.MinimumsubvvolumesizeEditField.Value;
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).iterateuntil = app.RVE_IterateuntilDropDown.Value;
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).subs2 = app.subvolumes_sub2_CheckBox.Value;
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).subs4 = app.subvolumes_sub4_CheckBox.Value;
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).threshold_std = str2num(app.StandarddeviationthresholdsEditField.Value);
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).threshold_numbersubvolumes = app.ForatleastnsubvolumesEditField.Value;

            elseif strcmp(app.SelectRVEorconvergenceanalysisDropDown.Value,'Independant subvolumes of same size + user-defined aspect ratio (B)')
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).type = 'B';
                AR = str2num( app.SubvolumeaspectratioEditField.Value ); AR=AR/AR(3);
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).Aspectratio = AR;
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).savename = ['SubCustomAR_' num2str(AR(1),'%1.3f\t') ' ' num2str(AR(2),'%1.3f\t') ' ' ' ' num2str(AR(3),'%1.3f\t')];
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).divisions = str2num(app.DividerEditField.Value);
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).minsubsize = app.MinimumsubvvolumesizeEditField.Value;
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).iterateuntil = app.RVE_IterateuntilDropDown.Value;
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).threshold_std = str2num(app.StandarddeviationthresholdsEditField.Value);
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).threshold_numbersubvolumes = app.ForatleastnsubvolumesEditField.Value;

            elseif strcmp(app.SelectRVEorconvergenceanalysisDropDown.Value,'Independant subvolumes of same size + one constant length (C)')
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).type = 'C';
                dir = app.ConstantdirectionslengthDropDown.Value;
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).Constantdirection = dir;
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).savename = ['SubConstL_' dir(end)];
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).divisions = str2num(app.DividerEditField.Value);
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).minsubsize = app.MinimumsubvvolumesizeEditField.Value;
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).iterateuntil = app.RVE_IterateuntilDropDown.Value;
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).subs2 = app.subvolumes_sub2_CheckBox.Value;
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).threshold_std = str2num(app.StandarddeviationthresholdsEditField.Value);
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).threshold_numbersubvolumes = app.ForatleastnsubvolumesEditField.Value;

            elseif strcmp(app.SelectRVEorconvergenceanalysisDropDown.Value,'Independant subvolumes of same size + two constant lengths (D)')
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).type = 'D';
                dir = app.ConstantdirectionslengthDropDown.Value;
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).Constantdirection = dir;
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).savename = ['SubConstL_' dir(end-6) '_' dir(end)];
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).divisions = str2num(app.DividerEditField.Value);
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).minsubsize = app.MinimumsubvvolumesizeEditField.Value;
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).iterateuntil = app.RVE_IterateuntilDropDown.Value;
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).threshold_std = str2num(app.StandarddeviationthresholdsEditField.Value);
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).threshold_numbersubvolumes = app.ForatleastnsubvolumesEditField.Value;

            elseif strcmp(app.SelectRVEorconvergenceanalysisDropDown.Value,'One subvolume + growing from volume center + keep initial aspect ratio (E)')
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).type = 'E';
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).Aspectratio = AR_initial;
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).firstuniquevolume_size = app.SizeofthefirstsubvolumeEditField.Value;
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).Growthperstep = app.VolumegrowthEditField.Value;
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).Growthrelativeto = app.RVEgrowthrelativeto_InDropDown.Value;
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).savename = ['GrowthCenter_InitialAR_' num2str(AR_initial(1),'%1.3f\t') ' ' num2str(AR_initial(2),'%1.3f\t') ' ' ' ' num2str(AR_initial(3),'%1.3f\t')];
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).threshold_reldiff = str2num(app.RelativedifferencethresholdsEditField.Value);

            elseif strcmp(app.SelectRVEorconvergenceanalysisDropDown.Value,'One subvolume + growing from volume center + user-defined aspect ratio (F)')
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).type = 'F';
                AR = str2num( app.SubvolumeaspectratioEditField_2.Value ); AR=AR/AR(3);
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).Aspectratio = AR;
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).firstuniquevolume_size = app.SizeofthefirstsubvolumeEditField.Value;
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).Growthperstep = app.VolumegrowthEditField.Value;
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).Growthrelativeto = app.RVEgrowthrelativeto_InDropDown.Value;
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).savename = ['GrowthCenter_CustomAR_' num2str(AR(1),'%1.3f\t') ' ' num2str(AR(2),'%1.3f\t') ' ' ' ' num2str(AR(3),'%1.3f\t')];
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).threshold_reldiff = str2num(app.RelativedifferencethresholdsEditField.Value);

            elseif strcmp(app.SelectRVEorconvergenceanalysisDropDown.Value,'One subvolume + growing from volume edge (G)')
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).type = 'G';
                dir = app.GrowthdirectionDropDown.Value;
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).Growthdirection = dir;
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).firstuniquevolume_size = app.SizeofthefirstsubvolumeEditField.Value;
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).Growthperstep = app.VolumegrowthEditField.Value;
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).Growthrelativeto = app.RVEgrowthrelativeto_InDropDown.Value;
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).threshold_reldiff = str2num(app.RelativedifferencethresholdsEditField.Value);
                if strcmp(dir, 'Direction 1, from x min to x max')
                    app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).savename = 'GrowthEdge_D1_min2max';
                elseif strcmp(dir,'Direction 1, from x max to x min')
                    app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).savename = 'GrowthEdge_D1_max2min';
                elseif strcmp(dir,'Direction 2, from y min to y max')
                    app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).savename = 'GrowthEdge_D2_min2max';
                elseif strcmp(dir,'Direction 2, from y max to y min')
                    app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).savename = 'GrowthEdge_D2_max2min';
                elseif strcmp(dir,'Direction 3, from z min to z max')
                    app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).savename = 'GrowthEdge_D3_min2max';
                elseif strcmp(dir,'Direction 3, from z max to z min')
                    app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).savename = 'GrowthEdge_D3_max2min';
                end

            elseif  strcmp(app.SelectRVEorconvergenceanalysisDropDown.Value,'One subvolume + one constant length (H)')
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).type = 'H';
                dir = app.GrowthdirectionDropDown.Value;
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).Constantdirection = dir;
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).firstuniquevolume_size = app.SizeofthefirstsubvolumeEditField.Value;
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).Growthperstep = app.VolumegrowthEditField.Value;
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).Growthrelativeto = app.RVEgrowthrelativeto_InDropDown.Value;
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).threshold_reldiff = str2num(app.RelativedifferencethresholdsEditField.Value);
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).savename = ['Growth_ConstL_' dir(end)];
            end

            % Nested RVE
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).donested = app.DonestedanalysisCheckBox.Value;
            if app.DonestedanalysisCheckBox.Value
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).nested_ndirection = app.RVE_ShrinkDropDown.Value;
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).nested_shrinksetup = app.RVE_thatisDropDown.Value;
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).nested_croppingstep = app.RVE_AteachstepDropDown.Value;
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).nested_croppingvalue = app.RVE_xEditField.Value;
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).nested_minsize = app.RVE_MinimumvolumetoapplyRVEanalysisEditField.Value;
            else
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).nested_ndirection = 'n/a';
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).nested_shrinksetup = 'n/a';
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).nested_croppingstep = 'n/a';
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).nested_croppingvalue = 'n/a';
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).nested_minsize = 'n/a';
            end

            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).savename = function_remove_emptyandspecialcharacter_string(app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).savename);

            % Update table
            app.Check_RVEtable;
        end

        % Button pushed function: RVE_canceloptionButton
        function RVE_canceloptionButtonPushed(app, event)
            app.RVEandconvergenceoptionsPanel.Visible = 'off';
            app.RVEandconvergenceoptionsPanel.Enable = 'off';
            app.RVE_add_button.Enable = 'on'; app.Check_RVEtable;  
        end

        % Value changed function: DonestedanalysisCheckBox
        function DonestedanalysisCheckBoxValueChanged(app, event)
            value = app.DonestedanalysisCheckBox.Value;
            if value
                statut = 'on';
                app.RVE_ShrinkDropDownValueChanged;
            else
                statut = 'off';
            end
            app.RVE_ShrinkDropDown.Enable = statut;
            app.ShrinkDropDownLabel.Enable = statut;
            app.RVE_thatisDropDown.Enable = statut;
            app.thatisDropDownLabel.Enable = statut;
            app.RVE_AteachstepDropDown.Enable = statut;
            app.AteachstepremoveLabel.Enable = statut;
            app.RVE_xEditField.Enable = statut;
            app.xEditFieldLabel.Enable = statut;
            app.RVE_MinimumvolumetoapplyRVEanalysisEditField.Enable = statut;
            app.MinimumvolumetoapplyanalysisLabel.Enable = statut;
            app.RVE_ofinitialvolumeLabel.Enable = statut;
        end

        % Value changed function: RVE_ShrinkDropDown
        function RVE_ShrinkDropDownValueChanged(app, event)
            value = app.RVE_ShrinkDropDown.Value;
            if strcmp(value,'1 direction')
                app.RVE_thatisDropDown.Enable = 'on'; app.thatisDropDownLabel.Enable = 'on';
                app.RVE_thatisDropDown.Items = {'Shrink along direction 1, from x min to x max','Shrink along direction 1, from x max to x min','Shrink along direction 1, from both ends','Shrink along direction 2, from y min to y max','Shrink along direction 2, from y max to y min','Shrink along direction 2, from both ends','Shrink along direction 3, from z min to z max','Shrink along direction 3, from z max to z min','Shrink along direction 3, from both ends'};
                app.RVE_thatisDropDown.Value = app.RVE_thatisDropDown.Items(1);
            elseif strcmp(value,'2 directions')
                app.RVE_thatisDropDown.Enable = 'on'; app.thatisDropDownLabel.Enable = 'on';
                app.RVE_thatisDropDown.Items = {'And keep direction 1 uncropped','And keep direction 2 uncropped','And keep direction 3 uncropped'};
                app.RVE_thatisDropDown.Value = app.RVE_thatisDropDown.Items(1);
            elseif strcmp(value,'3 directions')
                app.RVE_thatisDropDown.Items = {' '};
                app.RVE_thatisDropDown.Value = {' '};
                app.RVE_thatisDropDown.Enable = 'off'; app.thatisDropDownLabel.Enable = 'off';
            end            
        end

        % Value changed function: DividerEditField
        function DividerEditFieldValueChanged(app, event)
            value = app.DividerEditField.Value;
            divider = str2num(value);
            if isempty(divider) % Incorrect input
                app.DividerEditField.Value = ['2,3,4,5'];
            else
                if length(divider)==length(unique(divider)) && length(divider)==sum(divider>=2) && sum(divider==round(divider))==length(divider) && sum(divider==sort(divider))==length(divider) % No duplicate, >=2, integer, increasing.
                    foo=1;
                else
                    app.DividerEditField.Value = ['2,3,4,5'];
                end
            end
        end

        % Value changed function: SubvolumeaspectratioEditField
        function SubvolumeaspectratioEditFieldValueChanged(app, event)
            value = app.SubvolumeaspectratioEditField.Value;
            AR = str2num(value);
            if isempty(AR) % Incorrect input
                app.SubvolumeaspectratioEditField.Value = ['1,1,1'];
            else
                if length(AR)==3 && length(AR)==sum(AR>0) % 3 number, >0.
                    foo=1;
                else
                    app.SubvolumeaspectratioEditField.Value = ['1,1,1'];
                end
            end            
        end

        % Value changed function: SubvolumeaspectratioEditField_2
        function SubvolumeaspectratioEditField_2ValueChanged(app, event)
            value = app.SubvolumeaspectratioEditField_2.Value;
            AR = str2num(value);
            if isempty(AR) % Incorrect input
                app.SubvolumeaspectratioEditField_2.Value = ['1,1,1'];
            else
                if length(AR)==3 && length(AR)==sum(AR>0) % 3 number, >0.
                    foo=1;
                else
                    app.SubvolumeaspectratioEditField_2.Value = ['1,1,1'];
                end
            end      
        end

        % Value changed function: RVE_PropertyselectionDropDown, 
        % ...and 1 other component
        function RVE_PropertyselectionDropDownValueChanged(app, event)
            app.RVE_add_button.Enable = 'on';
            app.Check_RVEtable;
            app.RVEandconvergenceoptionsPanel.Visible = 'off'; app.RVEandconvergenceoptionsPanel.Enable = 'off'; 

        end

        % Value changed function: Voxelsize_PropertyselectionDropDown
        function Voxelsize_PropertyselectionDropDownValueChanged(app, event)
            value = app.Voxelsize_PropertyselectionDropDown.Value;
            app.LvlDetail_ExtrapolatetozerovoxelsizeDropDown.Enable = 'off'; app.LvlDetail_ExtrapolatetozerovoxelsizeDropDown.Visible = 'off'; app.ParticlelevelofdetailExtrapolatetozerovoxelsizeLabel.Visible = 'off';
            app.Bruggeman_ExtrapolatetozerovoxelsizeDropDown.Enable = 'off'; app.Bruggeman_ExtrapolatetozerovoxelsizeDropDown.Visible = 'off'; app.ParticlelevelofdetailExtrapolatetozerovoxelsizeLabel_2.Visible = 'off';
            app.Deff_ExtrapolatetozerovoxelsizeDropDown.Enable = 'off'; app.Deff_ExtrapolatetozerovoxelsizeDropDown.Visible = 'off'; app.ParticlelevelofdetailExtrapolatetozerovoxelsizeLabel_3.Visible = 'off';

            if strcmp(value,'Volume fractions')
                app.voxelsizeUITable.Data = app.voxelsizeanalysis.volumefraction.scaling;
                app.Voxel_ExtrapolatetozerovoxelsizeDropDown.Value = app.voxelsizeanalysis.volumefraction.scaling_extrapolation;
            elseif strcmp(value,'Tortuosity factor')
                app.voxelsizeUITable.Data = app.voxelsizeanalysis.tortuosity.scaling;
                app.Voxel_ExtrapolatetozerovoxelsizeDropDown.Value = app.voxelsizeanalysis.tortuosity.scaling_extrapolation;
                app.Bruggeman_ExtrapolatetozerovoxelsizeDropDown.Enable = 'on'; app.Bruggeman_ExtrapolatetozerovoxelsizeDropDown.Visible = 'on'; app.ParticlelevelofdetailExtrapolatetozerovoxelsizeLabel_2.Visible = 'on';
                app.Deff_ExtrapolatetozerovoxelsizeDropDown.Enable = 'on'; app.Deff_ExtrapolatetozerovoxelsizeDropDown.Visible = 'on'; app.ParticlelevelofdetailExtrapolatetozerovoxelsizeLabel_3.Visible = 'on';
                app.Bruggeman_ExtrapolatetozerovoxelsizeDropDown.Value = app.voxelsizeanalysis.Bruggeman.scaling_extrapolation;
                app.Deff_ExtrapolatetozerovoxelsizeDropDown.Value = app.voxelsizeanalysis.Deff.scaling_extrapolation;
            elseif strcmp(value,'Connectivity')
                app.voxelsizeUITable.Data = app.voxelsizeanalysis.connectivity.scaling;
                app.Voxel_ExtrapolatetozerovoxelsizeDropDown.Value = app.voxelsizeanalysis.connectivity.scaling_extrapolation; 
            elseif strcmp(value,'Specific surface area (direct)')
                app.voxelsizeUITable.Data = app.voxelsizeanalysis.Sp_direct.scaling;
                app.Voxel_ExtrapolatetozerovoxelsizeDropDown.Value = app.voxelsizeanalysis.Sp_direct.scaling_extrapolation;
            elseif strcmp(value,'Specific interface area (direct)')
                app.voxelsizeUITable.Data = app.voxelsizeanalysis.Int_direct.scaling;
                app.Voxel_ExtrapolatetozerovoxelsizeDropDown.Value = app.voxelsizeanalysis.Int_direct.scaling_extrapolation;
            elseif strcmp(value,'Triple phase boundary length (direct)')
                app.voxelsizeUITable.Data = app.voxelsizeanalysis.TPBL_direct.scaling;
                app.Voxel_ExtrapolatetozerovoxelsizeDropDown.Value = app.voxelsizeanalysis.TPBL_direct.scaling_extrapolation;
            elseif strcmp(value,'Diameter (c-PSD)')
                app.voxelsizeUITable.Data = app.voxelsizeanalysis.D_cpsd.scaling;
                app.Voxel_ExtrapolatetozerovoxelsizeDropDown.Value = app.voxelsizeanalysis.D_cpsd.scaling_extrapolation;
                app.LvlDetail_ExtrapolatetozerovoxelsizeDropDown.Enable = 'on'; app.LvlDetail_ExtrapolatetozerovoxelsizeDropDown.Visible = 'on'; app.ParticlelevelofdetailExtrapolatetozerovoxelsizeLabel.Visible = 'on';
                app.LvlDetail_ExtrapolatetozerovoxelsizeDropDown.Value = app.voxelsizeanalysis.D_cpsd_lvldetail.scaling_extrapolation;
            elseif strcmp(value,'Diameter (EDMF)')
                app.voxelsizeUITable.Data = app.voxelsizeanalysis.D_edmf.scaling;
                app.Voxel_ExtrapolatetozerovoxelsizeDropDown.Value = app.voxelsizeanalysis.D_edmf.scaling_extrapolation;
            elseif strcmp(value,'Diameter (Chord)')
                app.voxelsizeUITable.Data = app.voxelsizeanalysis.D_chord.scaling;
                app.Voxel_ExtrapolatetozerovoxelsizeDropDown.Value = app.voxelsizeanalysis.D_chord.scaling_extrapolation;
            elseif strcmp(value,'Diameter (watershed)')
                app.voxelsizeUITable.Data = app.voxelsizeanalysis.D_watershed.scaling;
                app.Voxel_ExtrapolatetozerovoxelsizeDropDown.Value = app.voxelsizeanalysis.D_watershed.scaling_extrapolation;
            elseif strcmp(value,'Diameter (PCRF)')
                app.voxelsizeUITable.Data = app.voxelsizeanalysis.D_pcrf.scaling;
                app.Voxel_ExtrapolatetozerovoxelsizeDropDown.Value = app.voxelsizeanalysis.D_pcrf.scaling_extrapolation;
            elseif strcmp(value,'Diameter (label)')
                app.voxelsizeUITable.Data = app.voxelsizeanalysis.D_label.scaling;
                app.Voxel_ExtrapolatetozerovoxelsizeDropDown.Value = app.voxelsizeanalysis.D_label.scaling_extrapolation;                  
            elseif strcmp(value,'Diameter (covariance)')
                app.voxelsizeUITable.Data = app.voxelsizeanalysis.D_covariance.scaling;
                app.Voxel_ExtrapolatetozerovoxelsizeDropDown.Value = app.voxelsizeanalysis.D_covariance.scaling_extrapolation;                
            end
        end

        % Cell edit callback: voxelsizeUITable
        function voxelsizeUITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            multiplier = str2num(newData);
            if isempty(multiplier) % Incorrect input
                app.voxelsizeUITable.Data(indices(1),indices(2)) = {'1'};
            else
                if length(multiplier)==length(unique(multiplier)) && length(multiplier)==sum(multiplier>0) % No duplicate, no negative.
                    foo=1;
                else
                    app.voxelsizeUITable.Data(indices(1),indices(2)) = {'1'};
                end
            end

            v = char(app.voxelsizeUITable.Data(indices(1),2));
            vsize = [char(v(1)) char(extractBetween(v,v(1)," "))];
            vunit = [char(extractBetween(v," ",v(end))) char(v(end))];
            if length(multiplier)==1
                str = [num2str(multiplier*str2num(vsize)) ' ' vunit];
            else
                str = num2str(multiplier(1)*str2num(vsize));
                for k=2:1:length(multiplier)
                    str = [str ', ' num2str(multiplier(k)*str2num(vsize))];
                end
                str = [str ' ' vunit];
            end
            app.voxelsizeUITable.Data(indices(1),4) = {str}; 
            
            if strcmp(app.Voxelsize_PropertyselectionDropDown.Value,'Volume fractions')
                app.voxelsizeanalysis.volumefraction.scaling = app.voxelsizeUITable.Data;
            elseif strcmp(app.Voxelsize_PropertyselectionDropDown.Value,'Connectivity')
                app.voxelsizeanalysis.connectivity.scaling= app.voxelsizeUITable.Data;
            elseif strcmp(app.Voxelsize_PropertyselectionDropDown.Value,'Tortuosity factor')
                app.voxelsizeanalysis.tortuosity.scaling= app.voxelsizeUITable.Data;                
            elseif strcmp(app.Voxelsize_PropertyselectionDropDown.Value,'Specific surface area (direct)')
                app.voxelsizeanalysis.Sp_direct.scaling= app.voxelsizeUITable.Data;
            elseif strcmp(app.Voxelsize_PropertyselectionDropDown.Value,'Specific interface area (direct)')
                app.voxelsizeanalysis.Int_direct.scaling= app.voxelsizeUITable.Data;
            elseif strcmp(app.Voxelsize_PropertyselectionDropDown.Value,'Triple phase boundary length (direct)')
                app.voxelsizeanalysis.TPBL_direct.scaling= app.voxelsizeUITable.Data;                
            elseif strcmp(app.Voxelsize_PropertyselectionDropDown.Value,'Diameter (c-PSD)')
                app.voxelsizeanalysis.D_cpsd.scaling= app.voxelsizeUITable.Data;  
            elseif strcmp(app.Voxelsize_PropertyselectionDropDown.Value,'Diameter (EDMF)')
                app.voxelsizeanalysis.D_edmf.scaling= app.voxelsizeUITable.Data;   
            elseif strcmp(app.Voxelsize_PropertyselectionDropDown.Value,'Diameter (Chord)')
                app.voxelsizeanalysis.D_chord.scaling= app.voxelsizeUITable.Data;  
            elseif strcmp(app.Voxelsize_PropertyselectionDropDown.Value,'Diameter (watershed)')
                app.voxelsizeanalysis.D_watershed.scaling= app.voxelsizeUITable.Data;           
            elseif strcmp(app.Voxelsize_PropertyselectionDropDown.Value,'Diameter (PCRF)')
                app.voxelsizeanalysis.D_pcrf.scaling= app.voxelsizeUITable.Data;         
            elseif strcmp(app.Voxelsize_PropertyselectionDropDown.Value,'Diameter (label)')
                app.voxelsizeanalysis.D_label.scaling= app.voxelsizeUITable.Data;                     
            elseif strcmp(app.Voxelsize_PropertyselectionDropDown.Value,'Diameter (covariance)')
                app.voxelsizeanalysis.D_covariance.scaling= app.voxelsizeUITable.Data;                           
            end

        end

        % Value changed function: 
        % Bruggeman_ExtrapolatetozerovoxelsizeDropDown, 
        % ...and 3 other components
        function Voxel_ExtrapolatetozerovoxelsizeDropDownValueChanged(app, event)
            if strcmp(app.Voxelsize_PropertyselectionDropDown.Value,'Volume fractions')
                app.voxelsizeanalysis.volumefraction.scaling_extrapolation = app.Voxel_ExtrapolatetozerovoxelsizeDropDown.Value;
            elseif strcmp(app.Voxelsize_PropertyselectionDropDown.Value,'Connectivity')
                app.voxelsizeanalysis.connectivity.scaling_extrapolation = app.Voxel_ExtrapolatetozerovoxelsizeDropDown.Value;
            elseif strcmp(app.Voxelsize_PropertyselectionDropDown.Value,'Tortuosity factor')
                app.voxelsizeanalysis.tortuosity.scaling_extrapolation = app.Voxel_ExtrapolatetozerovoxelsizeDropDown.Value;   
                app.voxelsizeanalysis.Bruggeman.scaling_extrapolation = app.Bruggeman_ExtrapolatetozerovoxelsizeDropDown.Value;   
                app.voxelsizeanalysis.Deff.scaling_extrapolation = app.Deff_ExtrapolatetozerovoxelsizeDropDown.Value;   
            elseif strcmp(app.Voxelsize_PropertyselectionDropDown.Value,'Specific surface area (direct)')
                app.voxelsizeanalysis.Sp_direct.scaling_extrapolation = app.Voxel_ExtrapolatetozerovoxelsizeDropDown.Value;
            elseif strcmp(app.Voxelsize_PropertyselectionDropDown.Value,'Specific interface area (direct)')
                app.voxelsizeanalysis.Int_direct.scaling_extrapolation = app.Voxel_ExtrapolatetozerovoxelsizeDropDown.Value;
            elseif strcmp(app.Voxelsize_PropertyselectionDropDown.Value,'Triple phase boundary length (direct)')
                app.voxelsizeanalysis.TPBL_direct.scaling_extrapolation = app.Voxel_ExtrapolatetozerovoxelsizeDropDown.Value;   
            elseif strcmp(app.Voxelsize_PropertyselectionDropDown.Value,'Diameter (c-PSD)')
                app.voxelsizeanalysis.D_cpsd.scaling_extrapolation = app.Voxel_ExtrapolatetozerovoxelsizeDropDown.Value;   
                app.voxelsizeanalysis.D_cpsd_lvldetail.scaling_extrapolation = app.LvlDetail_ExtrapolatetozerovoxelsizeDropDown.Value;                  
            elseif strcmp(app.Voxelsize_PropertyselectionDropDown.Value,'Diameter (EDMF)')
                app.voxelsizeanalysis.D_edmf.scaling_extrapolation = app.Voxel_ExtrapolatetozerovoxelsizeDropDown.Value;   
            elseif strcmp(app.Voxelsize_PropertyselectionDropDown.Value,'Diameter (Chord)')
                app.voxelsizeanalysis.D_chord.scaling_extrapolation = app.Voxel_ExtrapolatetozerovoxelsizeDropDown.Value;                 
            elseif strcmp(app.Voxelsize_PropertyselectionDropDown.Value,'Diameter (watershed)')
                app.voxelsizeanalysis.D_watershed.scaling_extrapolation = app.Voxel_ExtrapolatetozerovoxelsizeDropDown.Value;   
            elseif strcmp(app.Voxelsize_PropertyselectionDropDown.Value,'Diameter (PCRF)')
                app.voxelsizeanalysis.D_pcrf.scaling_extrapolation = app.Voxel_ExtrapolatetozerovoxelsizeDropDown.Value;   
            elseif strcmp(app.Voxelsize_PropertyselectionDropDown.Value,'Diameter (label)')
                app.voxelsizeanalysis.D_label.scaling_extrapolation = app.Voxel_ExtrapolatetozerovoxelsizeDropDown.Value;                   
            elseif strcmp(app.Voxelsize_PropertyselectionDropDown.Value,'Diameter (covariance)')
                app.voxelsizeanalysis.D_covariance.scaling_extrapolation = app.Voxel_ExtrapolatetozerovoxelsizeDropDown.Value;                   
            end                

        end

        % Value changed function: StandarddeviationthresholdsEditField
        function StandarddeviationthresholdsEditFieldValueChanged(app, event)
            value = app.StandarddeviationthresholdsEditField.Value;
            thresholds = str2num(value);
            if isempty(thresholds) % Incorrect input
                app.StandarddeviationthresholdsEditField.Value = ['1,2.5,5,10'];
            else
                if length(thresholds)==length(unique(thresholds)) && length(thresholds)==sum(thresholds>0) && sum(thresholds==sort(thresholds))==length(thresholds) % No duplicate, >=2, increasing.
                    foo=1;
                else
                    app.StandarddeviationthresholdsEditField.Value = ['1,2.5,5,10'];
                end
            end
        end

        % Value changed function: RelativedifferencethresholdsEditField
        function RelativedifferencethresholdsEditFieldValueChanged(app, event)
            value = app.RelativedifferencethresholdsEditField.Value;
            thresholds = str2num(value);
            if isempty(thresholds) % Incorrect input
                app.RelativedifferencethresholdsEditField.Value = ['1,2.5,5,10'];
            else
                if length(thresholds)==length(unique(thresholds)) && length(thresholds)==sum(thresholds>0) && sum(thresholds==sort(thresholds))==length(thresholds) % No duplicate, >=2, increasing.
                    foo=1;
                else
                    app.RelativedifferencethresholdsEditField.Value = ['1,2.5,5,10'];
                end
            end            
        end

        % Cell edit callback: Interface_phaseselection_UITable
        function Interface_phaseselection_UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            if indices(2)==3
                orders = app.order_calculation; orders(orders==0)=[];
                kvol = orders(indices(1));
                labels = app.inputvolume.volume(kvol).phaselabel;                
                if length(labels)==2
                    app.Interface_phaseselection_UITable.Data(indices(1),indices(2)) = {false};
                end               

            elseif indices(2)==4
                orders = app.order_calculation; orders(orders==0)=[];
                kvol = orders(indices(1));
                labels = app.inputvolume.volume(kvol).phaselabel;
                if length(labels)==2
                    app.Interface_phaseselection_UITable.Data(indices(1),indices(2)) = {'Do specific surface area instead'};
                else
                    tmp = str2num(newData);
                    success=false;
                    if ~isempty(tmp) && isnumeric(tmp)
                        tmp = sort(tmp,2);
                        tmp = unique(tmp,"rows");
                        sz = size(tmp);
                        if sz(1)>=1 && sz(2)==2 && sum(sum(ismember(tmp,labels)))==numel(tmp)
                            success = true;
                            str2 = [num2str(tmp(1,1)) ',' num2str(tmp(1,2))]; 
                            if length(unique(tmp(1,:)))==1; success=false; end
                            if sz(1)>1
                                for k=2:1:sz(1)
                                    str2 = [str2 ';' num2str(tmp(k,1)) ',' num2str(tmp(k,2))]; 
                                    if length(unique(tmp(k,:)))==1; success=false; end
                                end
                            end 
                        end
                    end
                    if success
                        app.Interface_phaseselection_UITable.Data(indices(1),indices(2)) = {str2};
                    else
                        app.Interface_phaseselection_UITable.Data(indices(1),indices(2)) = {[num2str(labels(1)) ',' num2str(labels(2))]};
                    end
                end
            end

        end

        % Cell edit callback: TPBL_phaseselection_UITable
        function TPBL_phaseselection_UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            if indices(2)==3
                orders = app.order_calculation; orders(orders==0)=[];
                kvol = orders(indices(1));
                labels = app.inputvolume.volume(kvol).phaselabel;
                if length(labels)<3
                    app.TPBL_phaseselection_UITable.Data(indices(1),indices(2)) = {false};
                end

            elseif indices(2)==4
                orders = app.order_calculation; orders(orders==0)=[];
                kvol = orders(indices(1));
                labels = app.inputvolume.volume(kvol).phaselabel;
                if length(labels)<3
                    app.TPBL_phaseselection_UITable.Data(indices(1),indices(2)) = {'n/a'};
                else
                    tmp = str2num(newData);
                    success=false;
                    if ~isempty(tmp) && isnumeric(tmp)
                        tmp = sort(tmp,2);
                        tmp = unique(tmp,"rows");
                        sz = size(tmp);
                        if sz(1)>=1 && sz(2)==3 && sum(sum(ismember(tmp,labels)))==numel(tmp)
                            success = true;
                            str2 = [num2str(tmp(1,1)) ',' num2str(tmp(1,2)) ',' num2str(tmp(1,3))];
                            if length(unique(tmp(1,:)))<3; success=false; end
                            if sz(1)>1
                                for k=2:1:sz(1)
                                    str2 = [str2 ';' num2str(tmp(k,1)) ',' num2str(tmp(k,2)) ',' num2str(tmp(k,3))];
                                    if length(unique(tmp(k,:)))<3; success=false; end
                                end
                            end
                        end
                    end
                    if success
                        app.TPBL_phaseselection_UITable.Data(indices(1),indices(2)) = {str2};
                    else
                        app.TPBL_phaseselection_UITable.Data(indices(1),indices(2)) = {[num2str(labels(1)) ',' num2str(labels(2)) ',' num2str(labels(3))]};
                    end
                end
            end
        end

        % Value changed function: Volume_Fractal_CheckBox
        function Volume_Fractal_CheckBoxValueChanged(app, event)
            value = app.Volume_Fractal_CheckBox.Value;
            app.vf_fractal_voxelsizeCheckBox.Enable = value;
        end

        % Value changed function: TPBL_Fractal_Boxcounting_CheckBox
        function TPBL_Fractal_Boxcounting_CheckBoxValueChanged(app, event)
            value = app.TPBL_Fractal_Boxcounting_CheckBox.Value;
            app.TPBL_fractal_voxelsizeCheckBox.Enable = value;
        end

        % Value changed function: Sp_Fractal_CheckBox
        function Sp_Fractal_CheckBoxValueChanged(app, event)
            value = app.Sp_Fractal_CheckBox.Value;
            app.Sp_fractal_voxelsizeCheckBox.Enable = value;
        end

        % Value changed function: Int_Fractal_CheckBox
        function Int_Fractal_CheckBoxValueChanged(app, event)
            value = app.Int_Fractal_CheckBox.Value;
            app.Int_fractal_voxelsizeCheckBox.Enable = value;
        end

        % Button pushed function: vf_SelectallButton
        function vf_SelectallButtonPushed(app, event)
           t = app.vf_phaseselection_UITable.Data;
           t = app.func_selectall(t);
           app.vf_phaseselection_UITable.Data = t;
        end

        % Button pushed function: tau_SelectallButton
        function tau_SelectallButtonPushed(app, event)
           t = app.Tau_phaseselection_UITable.Data;
           [rn,cn]=size(t);
           tot_=0; sum_=0;
           cs = [[2 4 6] 9:2:cn];
           for r=1:1:rn
               for kc=1:1:length(cs)
                   c = cs(kc);
                   val = cell2mat(t(r,c));
                   if ~isempty(val)
                       tot_ = tot_+1;
                       sum_ = sum_ + double(val);
                   end                       
               end
           end
           if sum_>=tot_/2
               new=0;
           else
               new=1;
           end
           for r=1:1:rn
               for kc=1:1:length(cs)
                   c = cs(kc);
                   val = cell2mat(t(r,c));
                   if ~isempty(val)
                       t(r,c) = {logical(new)};
                   end
               end
           end    
           app.Tau_phaseselection_UITable.Data = t;
        end

        % Button pushed function: tau_Alld1Button
        function tau_Alld1ButtonPushed(app, event)
            t = app.Tau_phaseselection_UITable.Data;
            t = app.func_selectall_dir(t,2);
            app.Tau_phaseselection_UITable.Data = t;  
        end

        % Button pushed function: tau_Alld2Button
        function tau_Alld2ButtonPushed(app, event)
            t = app.Tau_phaseselection_UITable.Data;
            t = app.func_selectall_dir(t,4);
            app.Tau_phaseselection_UITable.Data = t; 
        end

        % Button pushed function: tau_Alld3Button
        function tau_Alld3ButtonPushed(app, event)
            t = app.Tau_phaseselection_UITable.Data;
            t = app.func_selectall_dir(t,6);
            app.Tau_phaseselection_UITable.Data = t;   
        end

        % Button pushed function: Sp_SelectallButton
        function Sp_SelectallButtonPushed(app, event)
           t = app.Spdirect_phaseselection_UITable.Data;
           t = app.func_selectall(t);
           app.Spdirect_phaseselection_UITable.Data = t;            
        end

        % Button pushed function: Int_SelectallButton
        function Int_SelectallButtonPushed(app, event)
           t = app.Interface_phaseselection_UITable.Data;
           t = app.func_selectall(t);
           app.Interface_phaseselection_UITable.Data = t;               
        end

        % Button pushed function: TPBL_SelectallButton
        function TPBL_SelectallButtonPushed(app, event)
           t = app.TPBL_phaseselection_UITable.Data;
           t = app.func_selectall(t);
           app.TPBL_phaseselection_UITable.Data = t;               
        end

        % Button pushed function: Dcpsd_SelectallButton
        function Dcpsd_SelectallButtonPushed(app, event)
           t = app.Dcpsd_phaseselection_UITable.Data;
           t = app.func_selectall(t);
           app.Dcpsd_phaseselection_UITable.Data = t;                   
        end

        % Button pushed function: Dedmf_SelectallButton
        function Dedmf_SelectallButtonPushed(app, event)
           t = app.Dedmf_phaseselection_UITable.Data;
           [rn,cn]=size(t);
           tot_=0; sum_=0;
           cs = [[2 3 4] 6:2:cn];
           for r=1:1:rn
               for kc=1:1:length(cs)
                   c = cs(kc);
                   val = cell2mat(t(r,c));
                   if ~isempty(val)
                       tot_ = tot_+1;
                       sum_ = sum_ + double(val);
                   end                       
               end
           end
           if sum_>=tot_/2
               new=0;
           else
               new=1;
           end
           for r=1:1:rn
               for kc=1:1:length(cs)
                   c = cs(kc);
                   val = cell2mat(t(r,c));
                   if ~isempty(val)
                       t(r,c) = {logical(new)};
                   end
               end
           end    
           app.Dedmf_phaseselection_UITable.Data = t;                   
        end

        % Button pushed function: Conn_SelectallButton
        function Conn_SelectallButtonPushed(app, event)
           t = app.Connectivity_phaseselection_UITable.Data;
           t = app.func_selectall(t,3);
           app.Connectivity_phaseselection_UITable.Data = t;               
        end

        % Cell edit callback: Connectivity_phaseselection_UITable
        function Connectivity_phaseselection_UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            kvol = app.row2nputvolumevolumek(indices(1),2);
            labels = app.inputvolume.volume(kvol).phaselabel;   
            if indices(2)==4
                tmp = str2num(newData);
                success=false;
                if isempty(tmp)
                    success = true;
                    str2 = 'None';
                elseif isnumeric(tmp)
                    tmp = sort(tmp);
                    tmp = unique(tmp);
                    sz = size(tmp);
                    if sz(1)==1 && sum(ismember(tmp,labels))==numel(tmp)
                        success = true;
                        str2 = num2str(tmp(1));
                        if sz(2)>1
                            for k=2:1:sz(2)
                                str2 = [str2 ',' num2str(tmp(k))];
                            end
                        end
                    end
                end
                if success
                    app.Connectivity_phaseselection_UITable.Data(indices(1),indices(2)) = {str2};
                else
                    app.Connectivity_phaseselection_UITable.Data(indices(1),indices(2)) = {[num2str(labels(1)) ',' num2str(labels(2))]};
                end

            elseif indices(2)==5
                if length(labels)==2
                    app.Connectivity_phaseselection_UITable.Data(indices(1),indices(2)) = {'n/a'};
                else
                    if isempty(newData)
                        success = true;
                        str2 = 'None';
                    else
                        success=true;
                        str2 = newData;
                        current_union = [];
                        for k=1:1:length(newData)
                            if strcmp(newData(k),';')
                                if isempty(current_union) || ~(length(current_union)==length(unique(current_union)) && sum(ismember(current_union,labels))==numel(current_union))
                                    success = false;
                                end
                                current_union = [];
                            elseif strcmp(newData(k),',') 
                                foo=1;
                            else
                                current_union = [current_union str2num(newData(k))];
                            end
                        end
                        if isempty(current_union) || ~(length(current_union)==length(unique(current_union)) && sum(ismember(current_union,labels))==numel(current_union))
                            success = false;
                        end
                    end
                    if success
                        app.Connectivity_phaseselection_UITable.Data(indices(1),indices(2)) = {str2};
                    else
                        app.Connectivity_phaseselection_UITable.Data(indices(1),indices(2)) = {[num2str(labels(2)) ',' num2str(labels(3))]};
                    end
                end         

            elseif indices(2)==6
                if length(labels)==2
                    app.Connectivity_phaseselection_UITable.Data(indices(1),indices(2)) = {'n/a'};
                else
                    if isempty(newData)
                        success = true;
                        str2 = 'None';
                    else
                        tmp = str2num(newData);
                        success=false;
                        if ~isempty(tmp) && isnumeric(tmp)
                            % tmp = sort(tmp,2);
                            tmp = unique(tmp,"rows");
                            sz = size(tmp);
                            if sz(1)>=1 && sz(2)==2 && sum(sum(ismember(tmp,labels)))==numel(tmp)
                                success = true;
                                str2 = [num2str(tmp(1,1)) ',' num2str(tmp(1,2))];
                                if length(unique(tmp(1,:)))==1; success=false; end
                                if sz(1)>1
                                    for k=2:1:sz(1)
                                        str2 = [str2 ';' num2str(tmp(k,1)) ',' num2str(tmp(k,2))];
                                        if length(unique(tmp(k,:)))==1; success=false; end
                                    end
                                end
                            end
                        end
                    end
                    if success
                        app.Connectivity_phaseselection_UITable.Data(indices(1),indices(2)) = {str2};
                    else
                        app.Connectivity_phaseselection_UITable.Data(indices(1),indices(2)) = {[num2str(labels(1)) ',' num2str(labels(2))]};
                    end
                end
            end
            tmp = app.Connectivity_phaseselection_UITable.Data;
            if strcmp(tmp(indices(1),4),'None') && (strcmp(tmp(indices(1),5),'None') || strcmp(tmp(indices(1),5),'n/a')) && (strcmp(tmp(indices(1),6),'None') || strcmp(tmp(indices(1),6),'n/a'))
                app.Connectivity_phaseselection_UITable.Data(indices(1),3) = {false};
            else
                app.Connectivity_phaseselection_UITable.Data(indices(1),3) = {true};
            end

            % if all none, then to do =0
            
        end

        % Image clicked function: Image3
        function Image3Clicked(app, event)
            winopen('EDMF_illustration.png');
        end

        % Image clicked function: Image4
        function Image4Clicked(app, event)
            winopen('EDMF_illustration_b.png'); 
        end

        % Button pushed function: Dchord_SelectallButton
        function Dchord_SelectallButtonPushed(app, event)
           t = app.Dchord_phaseselection_UITable.Data;
           [rn,cn]=size(t);
           tot_=0; sum_=0;
           cs = [[2 3 4] 6:2:cn];
           for r=1:1:rn
               for kc=1:1:length(cs)
                   c = cs(kc);
                   val = cell2mat(t(r,c));
                   if ~isempty(val)
                       tot_ = tot_+1;
                       sum_ = sum_ + double(val);
                   end                       
               end
           end
           if sum_>=tot_/2
               new=0;
           else
               new=1;
           end
           for r=1:1:rn
               for kc=1:1:length(cs)
                   c = cs(kc);
                   val = cell2mat(t(r,c));
                   if ~isempty(val)
                       t(r,c) = {logical(new)};
                   end
               end
           end    
           app.Dchord_phaseselection_UITable.Data = t;          
        end

        % Image clicked function: Image5
        function Image5Clicked(app, event)
            winopen('Chord_illustration_method.png');
        end

        % Image clicked function: Image6
        function Image6Clicked(app, event)
            winopen('Chord_illustration_sphere.png');
        end

        % Image clicked function: Image7
        function Image7Clicked(app, event)
            winopen('Chord_illustration_ellipsoid.png');
        end

        % Cell edit callback: Dchord_phaseselection_UITable
        function Dchord_phaseselection_UITableCellEdit(app, event)
            tmp = app.Dchord_phaseselection_UITable.Data;
            [r,c]=size(tmp);
            for kr=1:1:r
                val=[];
                for kc=6:2:c
                    if ~isempty(cell2mat(tmp(kr,kc)))
                        val=[val tmp(kr,kc)];
                    end
                end
                val = cell2mat(val);
                if sum(val)==0
                    app.Dchord_phaseselection_UITable.Data(kr,2)={false};
                    app.Dchord_phaseselection_UITable.Data(kr,3)={false};
                    app.Dchord_phaseselection_UITable.Data(kr,4)={false};
                end
            end            
        end

        % Cell edit callback: Tau_phaseselection_UITable
        function Tau_phaseselection_UITableCellEdit(app, event)
            tmp = app.Tau_phaseselection_UITable.Data;
            [r,c]=size(tmp);
            for kr=1:1:r
                val=[];
                for kc=9:2:c
                    if ~isempty(cell2mat(tmp(kr,kc)))
                        val=[val tmp(kr,kc)];
                    end
                end
                val = cell2mat(val);
                if sum(val)==0
                    app.Tau_phaseselection_UITable.Data(kr,2)={false};
                    app.Tau_phaseselection_UITable.Data(kr,3)={1};
                    app.Tau_phaseselection_UITable.Data(kr,4)={false};
                    app.Tau_phaseselection_UITable.Data(kr,5)={1};
                    app.Tau_phaseselection_UITable.Data(kr,6)={false};
                    app.Tau_phaseselection_UITable.Data(kr,7)={1};                    
                end
            end

            indices = event.Indices;
            newData = event.NewData;

            if indices(2) >= 9
                if newData
                    if cell2mat(app.Tau_phaseselection_UITable.Data(indices(1),2))==0 && cell2mat(app.Tau_phaseselection_UITable.Data(indices(1),4))==0 && cell2mat(app.Tau_phaseselection_UITable.Data(indices(1),6))==0
                    app.Tau_phaseselection_UITable.Data(indices(1),2)={true};
                    app.Tau_phaseselection_UITable.Data(indices(1),3)={1};
                    app.Tau_phaseselection_UITable.Data(indices(1),4)={true};
                    app.Tau_phaseselection_UITable.Data(indices(1),5)={1};
                    app.Tau_phaseselection_UITable.Data(indices(1),6)={true};
                    app.Tau_phaseselection_UITable.Data(indices(1),7)={1};
                    end
                end
            else
                if cell2mat(app.Tau_phaseselection_UITable.Data(indices(1),2))==0 && cell2mat(app.Tau_phaseselection_UITable.Data(indices(1),4))==0 && cell2mat(app.Tau_phaseselection_UITable.Data(indices(1),6))==0
                    for kc=9:2:c
                        app.Tau_phaseselection_UITable.Data(indices(1),kc)={false};
                    end
                end
            end

            if indices(2)==3 || indices(2)==5 || indices(2)==7 
                if isnumeric(newData)
                    if newData<1 || round(newData)~=newData
                        app.Tau_phaseselection_UITable.Data(indices(1),indices(2))={1};
                    end
                else
                    app.Tau_phaseselection_UITable.Data(indices(1),indices(2))={1};
                end
            end

        end

        % Cell edit callback: Dedmf_phaseselection_UITable
        function Dedmf_phaseselection_UITableCellEdit(app, event)
            tmp = app.Dedmf_phaseselection_UITable.Data;
            [r,c]=size(tmp);
            for kr=1:1:r
                val=[];
                for kc=6:2:c
                    if ~isempty(cell2mat(tmp(kr,kc)))
                        val=[val tmp(kr,kc)];
                    end
                end
                val = cell2mat(val);
                if sum(val)==0
                    app.Dedmf_phaseselection_UITable.Data(kr,2)={false};
                    app.Dedmf_phaseselection_UITable.Data(kr,3)={false};
                    app.Dedmf_phaseselection_UITable.Data(kr,4)={false};
                end
            end            
        end

        % Button pushed function: Dwatershed_SelectallButton
        function Dwatershed_SelectallButtonPushed(app, event)
           t = app.Dwatershed_phaseselection_UITable.Data;
           t = app.func_selectall(t);
           app.Dwatershed_phaseselection_UITable.Data = t;     
        end

        % Image clicked function: Image_SpConvergence
        function Image_SpConvergenceClicked(app, event)
            winopen('Specificsurfacearea_convergence_illustration.png');
        end

        % Image clicked function: Image_DiameterConvergence
        function Image_DiameterConvergenceClicked(app, event)
           winopen('Diameter_convergence_illustration.png'); 
        end

        % Button pushed function: RecalculateConvergenceButton
        function RecalculateConvergenceButtonPushed(app, event)
            maxdiameter = app.UptoxnumberofvoxelperdiametersinglesphereEditField.Value;
            SphereConvergence_withvoxel(maxdiameter);
        end

        % Button pushed function: OpendocumentationButton
        function OpendocumentationButtonPushed(app, event)
            Find_file('NREL_MATBOX_Microstructure_analysis_toolbox_documentation.pdf','MATBOX_Microstructure_analysis_toolbox','Default location is \MATBOX_Microstructure_analysis_toolbox\Documentation\');
        end

        % Image clicked function: NREL_logo
        function NREL_logoImageClicked(app, event)
            url = 'https://www.nrel.gov/';
            web(url)   
        end

        % Button pushed function: RecalculateConvergenceButton_2
        function RecalculateConvergenceButton_2Pushed(app, event)
             maxdiameter = app.UptoxnumberofvoxelperdiametermultiplesphereEditField.Value;
             TwoSphereConvergence_withvoxel(maxdiameter,overlapping)
        end

        % Button pushed function: RecalculateConvergenceButton_3
        function RecalculateConvergenceButton_3Pushed(app, event)
            maxdiameter = app.UptoxnumberofvoxelperdiametermultiplesphereEditField.Value;
            RandomSphereConvergence_withvoxel(maxdiameter)
        end

        % Button pushed function: HelpRVEdefinitionButton
        function HelpRVEdefinitionButtonPushed(app, event)
            winopen('RVE_definition_methodology.png');
        end

        % Button pushed function: HelpRVEandconvergencecasesButton
        function HelpRVEandconvergencecasesButtonPushed(app, event)
            winopen('RVE_cases.png');
        end

        % Button pushed function: HelpresultinterpretationButton
        function HelpresultinterpretationButtonPushed(app, event)
            winopen('RVE_interpretation.png');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MicrostructurecharacterizationUIFigure = uifigure('Visible', 'off');
            app.MicrostructurecharacterizationUIFigure.Name = 'Microstructure characterization';
            app.MicrostructurecharacterizationUIFigure.Icon = 'Icon_Characterization.png';
            app.TabGroup = uitabgroup(app.MicrostructurecharacterizationUIFigure);
            app.InstructionsTab = uitab(app.TabGroup);
            app.InstructionsTab.Title = 'Instructions';
            app.Instructions_title = uilabel(app.InstructionsTab);
            app.Instructions_title.Text = 'Main instructions and display/save options';
            app.FontnameDropDownLabel = uilabel(app.InstructionsTab);
            app.FontnameDropDownLabel.Text = 'Font name';
            app.FontnameDropDown = uidropdown(app.InstructionsTab);
            app.LinewidthEditFieldLabel = uilabel(app.InstructionsTab);
            app.LinewidthEditFieldLabel.Text = 'Linewidth';
            app.LinewidthEditField = uieditfield(app.InstructionsTab, 'numeric');
            app.LinewidthEditField.Limits = [1 Inf];
            app.LinewidthEditField.RoundFractionalValues = 'on';
            app.LinewidthEditField.Value = 2;
            app.GridDropDownLabel = uilabel(app.InstructionsTab);
            app.GridDropDownLabel.Text = 'Grid';
            app.GridDropDown = uidropdown(app.InstructionsTab);
            app.GridDropDown.Items = {'No grid', 'Grid', 'Grid and minor grid'};
            app.GridDropDown.Value = 'Grid';
            app.AxeFontsizeEditFieldLabel = uilabel(app.InstructionsTab);
            app.AxeFontsizeEditFieldLabel.Text = 'Axe Font size';
            app.AxeFontsizeEditField = uieditfield(app.InstructionsTab, 'numeric');
            app.AxeFontsizeEditField.Limits = [1 Inf];
            app.AxeFontsizeEditField.RoundFractionalValues = 'on';
            app.AxeFontsizeEditField.Value = 12;
            app.LegendfontsizeEditFieldLabel = uilabel(app.InstructionsTab);
            app.LegendfontsizeEditFieldLabel.Text = 'Legend font size';
            app.LegendfontsizeEditField = uieditfield(app.InstructionsTab, 'numeric');
            app.LegendfontsizeEditField.Limits = [1 Inf];
            app.LegendfontsizeEditField.RoundFractionalValues = 'on';
            app.LegendfontsizeEditField.Value = 10;
            app.TitlefontsizeEditFieldLabel = uilabel(app.InstructionsTab);
            app.TitlefontsizeEditFieldLabel.Text = 'Title font size';
            app.TitlefontsizeEditField = uieditfield(app.InstructionsTab, 'numeric');
            app.TitlefontsizeEditField.Limits = [1 Inf];
            app.TitlefontsizeEditField.RoundFractionalValues = 'on';
            app.TitlefontsizeEditField.Value = 14;
            app.SubplotmaintitleiesgtitlefontsizeEditFieldLabel = uilabel(app.InstructionsTab);
            app.SubplotmaintitleiesgtitlefontsizeEditFieldLabel.Text = 'Subplot main title (i.e., sgtitle) font size';
            app.SubplotmaintitleiesgtitlefontsizeEditField = uieditfield(app.InstructionsTab, 'numeric');
            app.SubplotmaintitleiesgtitlefontsizeEditField.Limits = [1 Inf];
            app.SubplotmaintitleiesgtitlefontsizeEditField.RoundFractionalValues = 'on';
            app.SubplotmaintitleiesgtitlefontsizeEditField.Value = 16;
            app.ClosefigureoncedisplayedsavedCheckBox = uicheckbox(app.InstructionsTab);
            app.ClosefigureoncedisplayedsavedCheckBox.Text = 'Close figure once displayed/saved';
            app.ClosefigureoncedisplayedsavedCheckBox.Value = true;
            app.Label = uilabel(app.InstructionsTab);
            app.Label.Text = {'The characterization module produces a lot of figures. You may want to close them as they are generated, otherwise you could have dozens of open figures'; 'which is not very user-friendly'};
            app.ChooseyoursaveoptionsLabel = uilabel(app.InstructionsTab);
            app.ChooseyoursaveoptionsLabel.Text = '1) Choose your save options';
            app.saveoptions_UITable = uitable(app.InstructionsTab);
            app.saveoptions_UITable.ColumnName = {'Parameters'; 'Choice'};
            app.saveoptions_UITable.RowName = {};
            app.saveoptions_UITable.ColumnEditable = [false true];
            app.ChooseyourformatiedisplayoptionsLabel = uilabel(app.InstructionsTab);
            app.ChooseyourformatiedisplayoptionsLabel.Text = '2) Choose your format (i.e., display) options';
            app.Label_2 = uilabel(app.InstructionsTab);
            app.Label_2.Text = 'As many figures can be generated, it is time-saving to setup the format beforehand';
            app.Imageprocessing_Label = uilabel(app.InstructionsTab);
            app.Imageprocessing_Label.Visible = 'off';
            app.Imageprocessing_Label.Text = 'Image processing toolbox is missing. Some algorithms will not work!';
            app.Label_3 = uilabel(app.InstructionsTab);
            app.Label_3.Text = {'- Choose your save and format options in this tab. Then import one or several volume(s) in the next tab. Volumes will be characterized sequentially (batch).'; '- You can re-organize the order of calculation (if you have imported more than one volume) in the third tab.'; '- Each property (e.g., volume fraction) has a dedicated tab for which you can setup parameters. For each property, you can choose which phase(s) to'; 'characterize along with property-specific parameters.'; '- You can set parameters for the voxel size dependence analysis and of the representativiy/convergence analysis in their respective tabs.'; '- Once done, go to the "Run" tab and click to start all the calculations. Figures and result table (xls) will be created automatically.'};
            app.DiametercolormapDropDown_2Label = uilabel(app.InstructionsTab);
            app.DiametercolormapDropDown_2Label.Text = 'Diameter color map';
            app.DiametercolormapDropDown = uidropdown(app.InstructionsTab);
            app.DiametercolormapDropDown_2Label_2 = uilabel(app.InstructionsTab);
            app.DiametercolormapDropDown_2Label_2.Text = 'Diameter background color map';
            app.DiameterbackgroundcolormapDropDown = uidropdown(app.InstructionsTab);
            app.DiameterbackgroundcolormapDropDown.Items = {'white', 'black', 'grey'};
            app.DiameterbackgroundcolormapDropDown.Value = 'white';
            app.ImportvolumeTab = uitab(app.TabGroup);
            app.ImportvolumeTab.Tooltip = {'Import segmented volume'};
            app.ImportvolumeTab.Title = 'Import volume';
            app.Instructions_title_2 = uilabel(app.ImportvolumeTab);
            app.Instructions_title_2.Text = 'Import segmented volume';
            app.ImportvolumeButton = uibutton(app.ImportvolumeTab, 'push');
            app.ImportvolumeButton.ButtonPushedFcn = createCallbackFcn(app, @ImportvolumeButtonPushed, true);
            app.ImportvolumeButton.Text = 'Import volume';
            app.ClicktoimportvolumeLabel = uilabel(app.ImportvolumeTab);
            app.ClicktoimportvolumeLabel.Text = '1) Click to import volume';
            app.Imported_volume_name_label = uilabel(app.ImportvolumeTab);
            app.Imported_volume_name_label.Text = 'No file imported';
            app.Volumenumber1Label = uilabel(app.ImportvolumeTab);
            app.Volumenumber1Label.Visible = 'off';
            app.Volumenumber1Label.Text = 'Volume number 1';
            app.Visualize_volume_button = uibutton(app.ImportvolumeTab, 'push');
            app.Visualize_volume_button.ButtonPushedFcn = createCallbackFcn(app, @Visualize_volume_buttonButtonPushed, true);
            app.Visualize_volume_button.Enable = 'off';
            app.Visualize_volume_button.Text = 'Visualize volume';
            app.Vf_UITable = uitable(app.ImportvolumeTab);
            app.Vf_UITable.ColumnName = {'Phase #'; 'Volume fraction (loaded)'; 'Volume fraction (ROI)'; 'Color'; 'Name'; 'Label'; 'Re-assign label'};
            app.Vf_UITable.RowName = {};
            app.Vf_UITable.ColumnEditable = [false false false false true false true];
            app.Vf_UITable.CellSelectionCallback = createCallbackFcn(app, @Vf_UITableCellSelection, true);
            app.Direction_UITable = uitable(app.ImportvolumeTab);
            app.Direction_UITable.ColumnName = {'Direction #'; 'Number of voxel'; 'ROI (start)'; 'ROI (end)'; 'Name'};
            app.Direction_UITable.RowName = {};
            app.Direction_UITable.ColumnEditable = [false false true true true];
            app.Direction_UITable.CellEditCallback = createCallbackFcn(app, @Direction_UITableCellEdit, true);
            app.VoxelsizeEditFieldLabel = uilabel(app.ImportvolumeTab);
            app.VoxelsizeEditFieldLabel.Enable = 'off';
            app.VoxelsizeEditFieldLabel.Text = 'Voxel size';
            app.VoxelsizeEditField = uieditfield(app.ImportvolumeTab, 'numeric');
            app.VoxelsizeEditField.Limits = [1e-09 Inf];
            app.VoxelsizeEditField.ValueChangedFcn = createCallbackFcn(app, @ScalingfactorEditFieldValueChanged, true);
            app.VoxelsizeEditField.Enable = 'off';
            app.VoxelsizeEditField.Value = 1;
            app.UnitEditFieldLabel = uilabel(app.ImportvolumeTab);
            app.UnitEditFieldLabel.Enable = 'off';
            app.UnitEditFieldLabel.Text = 'Unit';
            app.VoxelsizeUnitEditField = uieditfield(app.ImportvolumeTab, 'text');
            app.VoxelsizeUnitEditField.ValueChangedFcn = createCallbackFcn(app, @ScalingfactorEditFieldValueChanged, true);
            app.VoxelsizeUnitEditField.Enable = 'off';
            app.VoxelsizeUnitEditField.Value = 'um';
            app.ScalingfactorEditFieldLabel = uilabel(app.ImportvolumeTab);
            app.ScalingfactorEditFieldLabel.Enable = 'off';
            app.ScalingfactorEditFieldLabel.Text = 'Scaling factor';
            app.ScalingfactorEditField = uieditfield(app.ImportvolumeTab, 'numeric');
            app.ScalingfactorEditField.Limits = [1e-09 Inf];
            app.ScalingfactorEditField.ValueChangedFcn = createCallbackFcn(app, @ScalingfactorEditFieldValueChanged, true);
            app.ScalingfactorEditField.Enable = 'off';
            app.ScalingfactorEditField.Value = 1;
            app.SetRegionOfInterestROILabel = uilabel(app.ImportvolumeTab);
            app.SetRegionOfInterestROILabel.Text = '2) Set Region Of Interest (ROI)';
            app.ROI_Label = uilabel(app.ImportvolumeTab);
            app.ROI_Label.Text = 'For more advanced ROI (e.g., rotation), please use the segmentation module first';
            app.SetphasecolorandnameLabel = uilabel(app.ImportvolumeTab);
            app.SetphasecolorandnameLabel.Text = '3) Set phase color and name';
            app.Phasecolor_Label = uilabel(app.ImportvolumeTab);
            app.Phasecolor_Label.Text = 'Color and name (both direction and phase) will be used for figures. Assign background/pore to the smallest label (e.g., 0 or 1).';
            app.Visualize_ROI_button = uibutton(app.ImportvolumeTab, 'push');
            app.Visualize_ROI_button.ButtonPushedFcn = createCallbackFcn(app, @Visualize_ROI_buttonButtonPushed, true);
            app.Visualize_ROI_button.Enable = 'off';
            app.Visualize_ROI_button.Text = 'Visualize ROI volume';
            app.SetvoxelsizeandscalingfactorLabel = uilabel(app.ImportvolumeTab);
            app.SetvoxelsizeandscalingfactorLabel.Text = '4) Set voxel size and scaling factor';
            app.Scalingfactor_Label = uilabel(app.ImportvolumeTab);
            app.Scalingfactor_Label.Text = 'Scaling factor >1 (downscaling), <1 (upscaling), =1 (no scaling). "Unit" you choose will be used for figures.';
            app.NewvoxelsizeEditFieldLabel = uilabel(app.ImportvolumeTab);
            app.NewvoxelsizeEditFieldLabel.Enable = 'off';
            app.NewvoxelsizeEditFieldLabel.Text = 'New voxel size';
            app.NewvoxelsizeEditField = uieditfield(app.ImportvolumeTab, 'numeric');
            app.NewvoxelsizeEditField.Limits = [0 Inf];
            app.NewvoxelsizeEditField.Enable = 'off';
            app.NewvoxelsizeEditField.Value = 1;
            app.Visualize_vf_button = uibutton(app.ImportvolumeTab, 'push');
            app.Visualize_vf_button.ButtonPushedFcn = createCallbackFcn(app, @Visualize_vf_buttonButtonPushed, true);
            app.Visualize_vf_button.Enable = 'off';
            app.Visualize_vf_button.Text = 'Visualize volume fractions';
            app.Visualize_ROI_vf_button = uibutton(app.ImportvolumeTab, 'push');
            app.Visualize_ROI_vf_button.ButtonPushedFcn = createCallbackFcn(app, @Visualize_ROI_vf_buttonButtonPushed, true);
            app.Visualize_ROI_vf_button.Enable = 'off';
            app.Visualize_ROI_vf_button.Text = 'Visualize ROI volume fractions';
            app.voxelinfo_UITable = uitable(app.ImportvolumeTab);
            app.voxelinfo_UITable.ColumnName = {''; 'Initial volume and voxel size'; 'ROI with new voxel size'};
            app.voxelinfo_UITable.RowName = {};
            app.TableisupdatedautomaticallyLabel = uilabel(app.ImportvolumeTab);
            app.TableisupdatedautomaticallyLabel.Text = 'Table is updated automatically';
            app.WritevolumeinformationLabel = uilabel(app.ImportvolumeTab);
            app.WritevolumeinformationLabel.Text = '5) Write volume information';
            app.OptionalSavedinanexcelfileforyourrecordLabel = uilabel(app.ImportvolumeTab);
            app.OptionalSavedinanexcelfileforyourrecordLabel.Text = '(Optional) Saved in an excel file for your record';
            app.Info_UITable = uitable(app.ImportvolumeTab);
            app.Info_UITable.ColumnName = {'Information'; 'Text'};
            app.Info_UITable.RowName = {};
            app.Info_UITable.ColumnEditable = [false true];
            app.SelectmainfolderButton = uibutton(app.ImportvolumeTab, 'push');
            app.SelectmainfolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectmainfolderButtonPushed, true);
            app.SelectmainfolderButton.Text = 'Select main folder';
            app.SelectsavefoldersLabel = uilabel(app.ImportvolumeTab);
            app.SelectsavefoldersLabel.Text = '6) Select save folders';
            app.Main_folder_Label = uilabel(app.ImportvolumeTab);
            app.Main_folder_Label.Text = 'Not defined';
            app.ResultswillbesavedinmainfoldervolumefolderLabel = uilabel(app.ImportvolumeTab);
            app.ResultswillbesavedinmainfoldervolumefolderLabel.Text = 'Results will be saved in main folder\volume folder\. You can re-use the same main folder for different volumes.';
            app.VolumefolderEditFieldLabel = uilabel(app.ImportvolumeTab);
            app.VolumefolderEditFieldLabel.Text = 'Volume folder';
            app.VolumefolderEditField = uieditfield(app.ImportvolumeTab, 'text');
            app.VolumefolderEditField.Value = 'Not defined';
            app.SavevolumeLabel = uilabel(app.ImportvolumeTab);
            app.SavevolumeLabel.Text = '7) Save volume ';
            app.Save_volume_button = uibutton(app.ImportvolumeTab, 'push');
            app.Save_volume_button.ButtonPushedFcn = createCallbackFcn(app, @Save_volume_buttonButtonPushed, true);
            app.Save_volume_button.Enable = 'off';
            app.Save_volume_button.Tooltip = {'After saving, you can import another volume to perform batch analysis.'};
            app.Save_volume_button.Text = {'Save your'; 'settings'};
            app.DropDownLabel = uilabel(app.ImportvolumeTab);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.ImportvolumeTab);
            app.ImportedvolumesTab = uitab(app.TabGroup);
            app.ImportedvolumesTab.Tooltip = {'Imported volume(s)'};
            app.ImportedvolumesTab.Title = 'Imported volume(s)';
            app.Instructions_title_3 = uilabel(app.ImportedvolumesTab);
            app.Instructions_title_3.Text = 'Set order of calculation of imported volume(s)';
            app.ClicktoimportvolumeLabel_2 = uilabel(app.ImportedvolumesTab);
            app.ClicktoimportvolumeLabel_2.Text = '1) (Optional) You can re-arrange calculation order. If you want to remove one volume from the calculation, give it a 0 value';
            app.Order_UITable = uitable(app.ImportedvolumesTab);
            app.Order_UITable.ColumnName = {'Volume name'; 'Volume cubic root length'; 'Voxel size'; 'Calculation order'};
            app.Order_UITable.RowName = {};
            app.Order_UITable.ColumnEditable = [false false false true];
            app.Order_UITable.CellEditCallback = createCallbackFcn(app, @Order_UITableCellEdit, true);
            app.ClicktoimportvolumeLabel_5 = uilabel(app.ImportedvolumesTab);
            app.ClicktoimportvolumeLabel_5.Text = '2) (Mandatory) Confirm your choice once all volumes have been imported (and re-arranged).';
            app.Save_importedvolume_button = uibutton(app.ImportedvolumesTab, 'push');
            app.Save_importedvolume_button.ButtonPushedFcn = createCallbackFcn(app, @Save_importedvolume_buttonButtonPushed, true);
            app.Save_importedvolume_button.Enable = 'off';
            app.Save_importedvolume_button.Tooltip = {''};
            app.Save_importedvolume_button.Text = 'Save your settings';
            app.Label_5 = uilabel(app.ImportedvolumesTab);
            app.Label_5.Text = 'Warning: all options for the subsequent tabs are reset when you click on "Save your settings".';
            app.VolumefractionsTab = uitab(app.TabGroup);
            app.VolumefractionsTab.Tooltip = {''};
            app.VolumefractionsTab.Title = 'Volume fractions';
            app.Instructions_title_4 = uilabel(app.VolumefractionsTab);
            app.Instructions_title_4.Text = 'Volume fractions options';
            app.ClicktoimportvolumeLabel_3 = uilabel(app.VolumefractionsTab);
            app.ClicktoimportvolumeLabel_3.Text = '1) Check boxes for the phase you want to calculate volume fraction';
            app.vf_phaseselection_UITable = uitable(app.VolumefractionsTab);
            app.vf_phaseselection_UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.vf_phaseselection_UITable.RowName = {};
            app.vf_phaseselection_UITable.Enable = 'off';
            app.Volume_Fractal_CheckBox = uicheckbox(app.VolumefractionsTab);
            app.Volume_Fractal_CheckBox.ValueChangedFcn = createCallbackFcn(app, @Volume_Fractal_CheckBoxValueChanged, true);
            app.Volume_Fractal_CheckBox.Text = 'Calculate fractal dimension for selected phase volume (Box counting algorithm)';
            app.BoxlengthmaximumvalueinvoxellenghEditFieldLabel = uilabel(app.VolumefractionsTab);
            app.BoxlengthmaximumvalueinvoxellenghEditFieldLabel.Text = 'Box length maximum value (in voxel lengh)';
            app.vf_Boxlengthmax_EditField = uieditfield(app.VolumefractionsTab, 'numeric');
            app.vf_Boxlengthmax_EditField.Limits = [2 Inf];
            app.vf_Boxlengthmax_EditField.RoundFractionalValues = 'on';
            app.vf_Boxlengthmax_EditField.Value = 1000000000;
            app.vf_fractal_voxelsizeCheckBox = uicheckbox(app.VolumefractionsTab);
            app.vf_fractal_voxelsizeCheckBox.Enable = 'off';
            app.vf_fractal_voxelsizeCheckBox.Tooltip = {'Fractal dimension converges with voxel size. While a fractal property (e.g.: TPBL) diverges with voxel size.'};
            app.vf_fractal_voxelsizeCheckBox.Text = 'If, yes, also calculate fractal dimension as function of voxel size';
            app.ClicktoimportvolumeLabel_20 = uilabel(app.VolumefractionsTab);
            app.ClicktoimportvolumeLabel_20.Text = '2) Fractal analysis';
            app.Setto1e9togothoughallpossibleboxlengthLabel = uilabel(app.VolumefractionsTab);
            app.Setto1e9togothoughallpossibleboxlengthLabel.Text = 'Set to 1e9 to go though all possible box length';
            app.vf_SelectallButton = uibutton(app.VolumefractionsTab, 'push');
            app.vf_SelectallButton.ButtonPushedFcn = createCallbackFcn(app, @vf_SelectallButtonPushed, true);
            app.vf_SelectallButton.Text = 'Select all';
            app.vf_Fractal_Bertei_CheckBox = uicheckbox(app.VolumefractionsTab);
            app.vf_Fractal_Bertei_CheckBox.Tooltip = {'Require voxel size dependence analysis. '};
            app.vf_Fractal_Bertei_CheckBox.Text = 'Calculate fractal dimension for selected contact line using Log(property) = m + (3-fractal dimension)*log(voxel size), Richardson/Mandelbrot formula';
            app.ConnectivityTab = uitab(app.TabGroup);
            app.ConnectivityTab.Title = 'Connectivity';
            app.Instructions_title_14 = uilabel(app.ConnectivityTab);
            app.Instructions_title_14.Text = 'Connectivity/percolation options';
            app.Connectivity_phaseselection_UITable = uitable(app.ConnectivityTab);
            app.Connectivity_phaseselection_UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.Connectivity_phaseselection_UITable.RowName = {};
            app.Connectivity_phaseselection_UITable.CellEditCallback = createCallbackFcn(app, @Connectivity_phaseselection_UITableCellEdit, true);
            app.Connectivity_phaseselection_UITable.Enable = 'off';
            app.ClicktoimportvolumeLabel_25 = uilabel(app.ConnectivityTab);
            app.ClicktoimportvolumeLabel_25.Text = '1) Specify list of labels, union of labels, or coupled labels for which connectivity will be calculated';
            app.Conn_SelectallButton = uibutton(app.ConnectivityTab, 'push');
            app.Conn_SelectallButton.ButtonPushedFcn = createCallbackFcn(app, @Conn_SelectallButtonPushed, true);
            app.Conn_SelectallButton.Text = 'Select all';
            app.DirectionofinterestDropDownLabel = uilabel(app.ConnectivityTab);
            app.DirectionofinterestDropDownLabel.Text = 'Direction of interest';
            app.Connectivity_DirectionofinterestDropDown = uidropdown(app.ConnectivityTab);
            app.Connectivity_DirectionofinterestDropDown.Items = {'Direction 1', 'Direction 2', 'Direction 3'};
            app.Connectivity_DirectionofinterestDropDown.Value = 'Direction 1';
            app.ForcoupledphaseonlyLabel = uilabel(app.ConnectivityTab);
            app.ForcoupledphaseonlyLabel.Text = 'For coupled phase calculation';
            app.OrientationDropDownLabel = uilabel(app.ConnectivityTab);
            app.OrientationDropDownLabel.Text = 'Orientation';
            app.Connectivity_OrientationDropDown = uidropdown(app.ConnectivityTab);
            app.Connectivity_OrientationDropDown.Items = {'Connected to both faces', 'Connected to first face', 'Connected to last face'};
            app.Connectivity_OrientationDropDown.Value = 'Connected to both faces';
            app.ForcoupledphaseonlyLabel_2 = uilabel(app.ConnectivityTab);
            app.ForcoupledphaseonlyLabel_2.Text = 'For phase, union of phase visualization';
            app.PlotLabel = uilabel(app.ConnectivityTab);
            app.PlotLabel.Text = 'Plot along';
            app.Connectivity_plotdirection_DropDown = uidropdown(app.ConnectivityTab);
            app.Connectivity_plotdirection_DropDown.Items = {'Direction 1', 'Direction 2', 'Direction 3'};
            app.Connectivity_plotdirection_DropDown.Value = 'Direction 1';
            app.ForLabel = uilabel(app.ConnectivityTab);
            app.ForLabel.Text = 'For';
            app.Connectivity_plottype_DropDown = uidropdown(app.ConnectivityTab);
            app.Connectivity_plottype_DropDown.Items = {'Connected to both faces', 'Connected to first face', 'Connected to last face', 'Largest cluster'};
            app.Connectivity_plottype_DropDown.Value = 'Connected to both faces';
            app.TortuosityfactorTab = uitab(app.TabGroup);
            app.TortuosityfactorTab.Title = 'Tortuosity factor';
            app.Instructions_title_13 = uilabel(app.TortuosityfactorTab);
            app.Instructions_title_13.Text = 'Tortuosity factor';
            app.ClicktoimportvolumeLabel_24 = uilabel(app.TortuosityfactorTab);
            app.ClicktoimportvolumeLabel_24.Text = '1) Check boxes for the direction and phase you want to calculate tortuosity factor';
            app.Tau_phaseselection_UITable = uitable(app.TortuosityfactorTab);
            app.Tau_phaseselection_UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.Tau_phaseselection_UITable.RowName = {};
            app.Tau_phaseselection_UITable.CellEditCallback = createCallbackFcn(app, @Tau_phaseselection_UITableCellEdit, true);
            app.Tau_phaseselection_UITable.Tooltip = {'By default, the tortuosity is calculated on the whole volume (no gradation). If you want to calculate ALSO for the first and second half, then enter 2 for the column #Section. Enter higher integer if you want to refine the gradation. Keep in mind that smaller volumes have stronger edge effects.'};
            app.Tau_phaseselection_UITable.Enable = 'off';
            app.CalculationmethodDirectLabel_4 = uilabel(app.TortuosityfactorTab);
            app.CalculationmethodDirectLabel_4.Text = {'Calculation method: Diffusion homogenization. Laplace equation (heat equation with bulk diffusion = 1) is solved'; 'with fixed boundary conditions (Dirichlet) using Finite difference method.'; 'TauFactor is used (see How to quote tab).'; ''; 'Pros/cons:'; '+ Relatively fast.'; '- Dirichlet-Dirichlet provides lower (upper) bound for tortuosity factor and Bruggeman exponent (effective diffusion coefficient).'; ''; 'Recommendations:'; 'Run RVE analysis to verify convergence. If RVE is reached, the error induced by the boundary conditions is negligible.'};
            app.Image2 = uiimage(app.TortuosityfactorTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Tau_illustration.png');
            app.tau_SelectallButton = uibutton(app.TortuosityfactorTab, 'push');
            app.tau_SelectallButton.ButtonPushedFcn = createCallbackFcn(app, @tau_SelectallButtonPushed, true);
            app.tau_SelectallButton.Text = 'Select all';
            app.tau_Alld1Button = uibutton(app.TortuosityfactorTab, 'push');
            app.tau_Alld1Button.ButtonPushedFcn = createCallbackFcn(app, @tau_Alld1ButtonPushed, true);
            app.tau_Alld1Button.Text = 'All d1';
            app.tau_Alld2Button = uibutton(app.TortuosityfactorTab, 'push');
            app.tau_Alld2Button.ButtonPushedFcn = createCallbackFcn(app, @tau_Alld2ButtonPushed, true);
            app.tau_Alld2Button.Text = 'All d2';
            app.tau_Alld3Button = uibutton(app.TortuosityfactorTab, 'push');
            app.tau_Alld3Button.ButtonPushedFcn = createCallbackFcn(app, @tau_Alld3ButtonPushed, true);
            app.tau_Alld3Button.Text = 'All d3';
            app.SpecificsurfaceareaTab = uitab(app.TabGroup);
            app.SpecificsurfaceareaTab.Tooltip = {''};
            app.SpecificsurfaceareaTab.Title = 'Specific surface area';
            app.Sp_correctivefactor_UIAxes = uiaxes(app.SpecificsurfaceareaTab);
            title(app.Sp_correctivefactor_UIAxes, 'Title')
            xlabel(app.Sp_correctivefactor_UIAxes, 'X')
            ylabel(app.Sp_correctivefactor_UIAxes, 'Y')
            zlabel(app.Sp_correctivefactor_UIAxes, 'Z')
            app.Instructions_title_8 = uilabel(app.SpecificsurfaceareaTab);
            app.Instructions_title_8.Text = 'Specific surface area (direct method) options.';
            app.ClicktoimportvolumeLabel_12 = uilabel(app.SpecificsurfaceareaTab);
            app.ClicktoimportvolumeLabel_12.Text = '1) Check boxes for the phase you want to calculate specific surface area';
            app.Spdirect_phaseselection_UITable = uitable(app.SpecificsurfaceareaTab);
            app.Spdirect_phaseselection_UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.Spdirect_phaseselection_UITable.RowName = {};
            app.Spdirect_phaseselection_UITable.Enable = 'off';
            app.ClicktoimportvolumeLabel_13 = uilabel(app.SpecificsurfaceareaTab);
            app.ClicktoimportvolumeLabel_13.Text = '2) Set options specific to this method';
            app.DefinitionDropDownLabel = uilabel(app.SpecificsurfaceareaTab);
            app.DefinitionDropDownLabel.Text = 'Definition';
            app.Sp_DefinitionDropDown = uidropdown(app.SpecificsurfaceareaTab);
            app.Sp_DefinitionDropDown.Items = {'Phase surface divided by domain volume', 'Phase surface divided by phase volume'};
            app.Sp_DefinitionDropDown.Value = 'Phase surface divided by domain volume';
            app.CorrectivefactorEditFieldLabel = uilabel(app.SpecificsurfaceareaTab);
            app.CorrectivefactorEditFieldLabel.Text = 'Corrective factor';
            app.CorrectivefactorEditField = uieditfield(app.SpecificsurfaceareaTab, 'numeric');
            app.CorrectivefactorEditField.Limits = [1e-09 Inf];
            app.CorrectivefactorEditField.Tooltip = {'Surface of an ideal sphere = 4*pi*r^2'; 'Surface of a sphere discretized in voxels = 6*pi*r^2'; 'Surface is overestimated with a factor 3/2, therefore the standard approach consists in multiplying the calculated specific surface area by 2/3.'; 'The corrective factor is shape-dependent, with the graph below providing the analytical value for an oblate spheroid.'; 'Note that this corrective factor does not correct surface roughness.'};
            app.CorrectivefactorEditField.Value = 0.666666666666667;
            app.CalculationmethodDirectLabel = uilabel(app.SpecificsurfaceareaTab);
            app.CalculationmethodDirectLabel.Text = {'Calculation method: "Direct". Surface area are calculated by summing voxel area.'; 'Specific surface area is deduced by dividing the surface area with either the phase volume'; 'or the domain volume.'; ''; 'Pros/cons:'; '+ Fast.'; '+ Local specific surface area.'; '- Overestimation due to voxel discretization (see corrective factor).'; '- Voxel-size dependent (surface roughness):'; 'expected to diverge with infinitely high image resolution'; '(fractal behavior).'; ''; 'Recommendations:'; 'Compare values obtained with the same image resolution'; ' and same fractal dimension.'; 'Do not consider absolute value as true.'};
            app.Sp_Fractal_CheckBox = uicheckbox(app.SpecificsurfaceareaTab);
            app.Sp_Fractal_CheckBox.ValueChangedFcn = createCallbackFcn(app, @Sp_Fractal_CheckBoxValueChanged, true);
            app.Sp_Fractal_CheckBox.Text = 'Calculate fractal dimension for selected phase surface (Box counting algorithm)';
            app.Sp_fractal_voxelsizeCheckBox = uicheckbox(app.SpecificsurfaceareaTab);
            app.Sp_fractal_voxelsizeCheckBox.Enable = 'off';
            app.Sp_fractal_voxelsizeCheckBox.Tooltip = {'Fractal dimension converges with voxel size. While a fractal property (e.g.: TPBL) diverges with voxel size.'};
            app.Sp_fractal_voxelsizeCheckBox.Text = 'If, yes, also calculate fractal dimension as function of voxel size';
            app.ClicktoimportvolumeLabel_22 = uilabel(app.SpecificsurfaceareaTab);
            app.ClicktoimportvolumeLabel_22.Text = '3) Fractal analysis';
            app.Setto1e9togothoughallpossibleboxlengthLabel_3 = uilabel(app.SpecificsurfaceareaTab);
            app.Setto1e9togothoughallpossibleboxlengthLabel_3.Text = 'Set to 1e9 to go though all possible box length';
            app.BoxlengthmaximumvalueinvoxellenghEditFieldLabel_3 = uilabel(app.SpecificsurfaceareaTab);
            app.BoxlengthmaximumvalueinvoxellenghEditFieldLabel_3.Text = 'Box length maximum value (in voxel lengh)';
            app.Sp_Boxlengthmax_EditField = uieditfield(app.SpecificsurfaceareaTab, 'numeric');
            app.Sp_Boxlengthmax_EditField.Limits = [2 Inf];
            app.Sp_Boxlengthmax_EditField.RoundFractionalValues = 'on';
            app.Sp_Boxlengthmax_EditField.Value = 1000000000;
            app.Sp_SelectallButton = uibutton(app.SpecificsurfaceareaTab, 'push');
            app.Sp_SelectallButton.ButtonPushedFcn = createCallbackFcn(app, @Sp_SelectallButtonPushed, true);
            app.Sp_SelectallButton.Text = 'Select all';
            app.Sp_Fractal_Bertei_CheckBox = uicheckbox(app.SpecificsurfaceareaTab);
            app.Sp_Fractal_Bertei_CheckBox.Tooltip = {'Require voxel size dependence analysis. '};
            app.Sp_Fractal_Bertei_CheckBox.Text = 'Calculate fractal dimension for selected contact line using Log(property) = m + (2-fractal dimension)*log(voxel size), Richardson/Mandelbrot formula';
            app.Sp_donotconsidercomvol = uicheckbox(app.SpecificsurfaceareaTab);
            app.Sp_donotconsidercomvol.Tooltip = {'Connectivity analysis is performed on the complementary volume: isolated and unknows clusters will not be considered for the interface calculation.'};
            app.Sp_donotconsidercomvol.Text = 'Do not consider surface for which the complementary volume is isolated';
            app.SpecificinterfaceareaTab = uitab(app.TabGroup);
            app.SpecificinterfaceareaTab.Title = 'Specific interface area';
            app.Instructions_title_12 = uilabel(app.SpecificinterfaceareaTab);
            app.Instructions_title_12.Text = 'Specific interface area (direct method) options.';
            app.ClicktoimportvolumeLabel_17 = uilabel(app.SpecificinterfaceareaTab);
            app.ClicktoimportvolumeLabel_17.Text = '1) Specify list of doublets for which interfaces will be calculated';
            app.Interface_phaseselection_UITable = uitable(app.SpecificinterfaceareaTab);
            app.Interface_phaseselection_UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.Interface_phaseselection_UITable.RowName = {};
            app.Interface_phaseselection_UITable.CellEditCallback = createCallbackFcn(app, @Interface_phaseselection_UITableCellEdit, true);
            app.Interface_phaseselection_UITable.Enable = 'off';
            app.Exampleforavolumewithphaselabels012and40104Label = uilabel(app.SpecificinterfaceareaTab);
            app.Exampleforavolumewithphaselabels012and40104Label.Text = 'Example: for a volume with phase labels 0,1, 2 and 4: 0,1;0,4';
            app.CalculationmethodDirectLabel_2 = uilabel(app.SpecificinterfaceareaTab);
            app.CalculationmethodDirectLabel_2.Text = {'Calculation method: "Direct". Surface area are calculated by summing voxel area between phase i - phase j.'; 'Specific interface area is deduced by dividing the interface area with the domain volume.'; ''; 'Pros/cons:'; '+ Fast.'; '+ Local specific interface area.'; '- Overestimation due to voxel discretization (see specific surface area tab).'; '- Voxel-size dependent (surface roughness):'; 'expected to diverge with infinitely high image resolution'; '(fractal behavior).'; ''; 'Recommendations:'; 'Compare values obtained with the same image resolution'; 'and same fractal dimension.'; 'Do not consider absolute value as true.'};
            app.ClicktoimportvolumeLabel_18 = uilabel(app.SpecificinterfaceareaTab);
            app.ClicktoimportvolumeLabel_18.Text = '2) Set options specific to this method';
            app.CorrectivefactorEditField_2Label = uilabel(app.SpecificinterfaceareaTab);
            app.CorrectivefactorEditField_2Label.Text = 'Corrective factor';
            app.Correctivefactor_interface_EditField = uieditfield(app.SpecificinterfaceareaTab, 'numeric');
            app.Correctivefactor_interface_EditField.Limits = [1e-09 Inf];
            app.Correctivefactor_interface_EditField.Tooltip = {'See comment for specific surface area. We re-use same corrective factor by default. Note that this corrective factor does not correct surface roughness but only for overestimation due to voxel discretization.'};
            app.Correctivefactor_interface_EditField.Value = 0.666666666666667;
            app.Int_Fractal_CheckBox = uicheckbox(app.SpecificinterfaceareaTab);
            app.Int_Fractal_CheckBox.ValueChangedFcn = createCallbackFcn(app, @Int_Fractal_CheckBoxValueChanged, true);
            app.Int_Fractal_CheckBox.Text = 'Calculate fractal dimension for selected phase-phase surface (Box counting algorithm)';
            app.Int_fractal_voxelsizeCheckBox = uicheckbox(app.SpecificinterfaceareaTab);
            app.Int_fractal_voxelsizeCheckBox.Enable = 'off';
            app.Int_fractal_voxelsizeCheckBox.Tooltip = {'Fractal dimension converges with voxel size. While a fractal property (e.g.: TPBL) diverges with voxel size.'};
            app.Int_fractal_voxelsizeCheckBox.Text = 'If, yes, also calculate fractal dimension as function of voxel size';
            app.ClicktoimportvolumeLabel_23 = uilabel(app.SpecificinterfaceareaTab);
            app.ClicktoimportvolumeLabel_23.Text = '3) Fractal analysis';
            app.Setto1e9togothoughallpossibleboxlengthLabel_4 = uilabel(app.SpecificinterfaceareaTab);
            app.Setto1e9togothoughallpossibleboxlengthLabel_4.Text = 'Set to 1e9 to go though all possible box length';
            app.BoxlengthmaximumvalueinvoxellenghEditFieldLabel_4 = uilabel(app.SpecificinterfaceareaTab);
            app.BoxlengthmaximumvalueinvoxellenghEditFieldLabel_4.Text = 'Box length maximum value (in voxel lengh)';
            app.Int_Boxlengthmax_EditField = uieditfield(app.SpecificinterfaceareaTab, 'numeric');
            app.Int_Boxlengthmax_EditField.Limits = [2 Inf];
            app.Int_Boxlengthmax_EditField.RoundFractionalValues = 'on';
            app.Int_Boxlengthmax_EditField.Value = 1000000000;
            app.Int_SelectallButton = uibutton(app.SpecificinterfaceareaTab, 'push');
            app.Int_SelectallButton.ButtonPushedFcn = createCallbackFcn(app, @Int_SelectallButtonPushed, true);
            app.Int_SelectallButton.Text = 'Select all';
            app.Int_Fractal_Bertei_CheckBox = uicheckbox(app.SpecificinterfaceareaTab);
            app.Int_Fractal_Bertei_CheckBox.Tooltip = {'Require voxel size dependence analysis. '};
            app.Int_Fractal_Bertei_CheckBox.Text = 'Calculate fractal dimension for selected contact line using Log(property) = m + (2-fractal dimension)*log(voxel size), Richardson/Mandelbrot formula';
            app.TPBLTab = uitab(app.TabGroup);
            app.TPBLTab.Title = 'TPBL';
            app.Instructions_title_11 = uilabel(app.TPBLTab);
            app.Instructions_title_11.Text = 'Triple Phase Boundary Length (direct method) options.';
            app.ClicktoimportvolumeLabel_16 = uilabel(app.TPBLTab);
            app.ClicktoimportvolumeLabel_16.Text = '1) Specify list of triplets for which TPBLs will be calculated';
            app.TPBL_phaseselection_UITable = uitable(app.TPBLTab);
            app.TPBL_phaseselection_UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.TPBL_phaseselection_UITable.RowName = {};
            app.TPBL_phaseselection_UITable.CellEditCallback = createCallbackFcn(app, @TPBL_phaseselection_UITableCellEdit, true);
            app.TPBL_phaseselection_UITable.Enable = 'off';
            app.Exampleforavolumewithphaselabels012and4012024Label = uilabel(app.TPBLTab);
            app.Exampleforavolumewithphaselabels012and4012024Label.Text = 'Example: for a volume with phase labels: 0,1,2 and 4: 0, 1, 2;0, 2, 4';
            app.ClicktoimportvolumeLabel_19 = uilabel(app.TPBLTab);
            app.ClicktoimportvolumeLabel_19.Text = '2) Set options specific to this method';
            app.CorrectivefactorEditField_2Label_2 = uilabel(app.TPBLTab);
            app.CorrectivefactorEditField_2Label_2.Text = 'Corrective factor';
            app.Correctivefactor_TPBL_EditField = uieditfield(app.TPBLTab, 'numeric');
            app.Correctivefactor_TPBL_EditField.Limits = [1e-09 Inf];
            app.Correctivefactor_TPBL_EditField.Tooltip = {'0.6873 = 1/1.455'; 'See Wilson et al., Journal of Power Sources, 195 (2010) 1829-1840 for derivation of the corrective factor.'; 'Note that this corrective factor does not correct surface roughness.'};
            app.Correctivefactor_TPBL_EditField.Value = 0.6873;
            app.CalculationmethodDirectLabel_3 = uilabel(app.TPBLTab);
            app.CalculationmethodDirectLabel_3.Text = {'Calculation method: "Direct". Triple phase boundary lengths are calculated by summing voxel edge length'; ' between phase i - phase j - phase k.'; 'Length density is deduced by dividing the length with the domain volume.'; ''; 'Pros/cons:'; '+ Fast.'; '+ Local TPBL'; '- Overestimation due to voxel discretization. (see corrective factor)'; '- Voxel-size dependent (surface roughness):'; 'expected to diverge with infinitely high image resolution'; '(fractal behavior).'; ''; 'Recommendations:'; 'Compare values obtained with the same image resolution'; 'and same fractal dimension.'; 'Do not consider absolute value as true.'};
            app.TPBL_Fractal_Boxcounting_CheckBox = uicheckbox(app.TPBLTab);
            app.TPBL_Fractal_Boxcounting_CheckBox.ValueChangedFcn = createCallbackFcn(app, @TPBL_Fractal_Boxcounting_CheckBoxValueChanged, true);
            app.TPBL_Fractal_Boxcounting_CheckBox.Text = 'Calculate fractal dimension for selected contact line using box counting algorithm';
            app.TPBL_fractal_voxelsizeCheckBox = uicheckbox(app.TPBLTab);
            app.TPBL_fractal_voxelsizeCheckBox.Enable = 'off';
            app.TPBL_fractal_voxelsizeCheckBox.Tooltip = {'Fractal dimension converges with voxel size. While a fractal property (e.g.: TPBL) diverges with voxel size.'};
            app.TPBL_fractal_voxelsizeCheckBox.Text = 'If, yes, also calculate fractal dimension as function of voxel size';
            app.ClicktoimportvolumeLabel_21 = uilabel(app.TPBLTab);
            app.ClicktoimportvolumeLabel_21.Text = '3) Fractal analysis';
            app.Setto1e9togothoughallpossibleboxlengthLabel_2 = uilabel(app.TPBLTab);
            app.Setto1e9togothoughallpossibleboxlengthLabel_2.Text = 'Set to 1e9 to go though all possible box length';
            app.BoxlengthmaximumvalueinvoxellenghEditFieldLabel_2 = uilabel(app.TPBLTab);
            app.BoxlengthmaximumvalueinvoxellenghEditFieldLabel_2.Text = 'Box length maximum value (in voxel lengh)';
            app.TPBL_Boxlengthmax_EditField = uieditfield(app.TPBLTab, 'numeric');
            app.TPBL_Boxlengthmax_EditField.Limits = [2 Inf];
            app.TPBL_Boxlengthmax_EditField.RoundFractionalValues = 'on';
            app.TPBL_Boxlengthmax_EditField.Value = 1000000000;
            app.TPBL_SelectallButton = uibutton(app.TPBLTab, 'push');
            app.TPBL_SelectallButton.ButtonPushedFcn = createCallbackFcn(app, @TPBL_SelectallButtonPushed, true);
            app.TPBL_SelectallButton.Text = 'Select all';
            app.TPBL_Fractal_Bertei_CheckBox = uicheckbox(app.TPBLTab);
            app.TPBL_Fractal_Bertei_CheckBox.Tooltip = {'Require voxel size dependence analysis. '};
            app.TPBL_Fractal_Bertei_CheckBox.Text = 'Calculate fractal dimension for selected contact line using Log(property) = m + (1-fractal dimension)*log(voxel size), Richardson/Mandelbrot formula';
            app.DcPSDTab = uitab(app.TabGroup);
            app.DcPSDTab.Title = 'D (c-PSD)';
            app.Instructions_title_9 = uilabel(app.DcPSDTab);
            app.Instructions_title_9.Text = 'Diameter (Continuum Particle Size Distribution, C-PSD method) options';
            app.ClicktoimportvolumeLabel_14 = uilabel(app.DcPSDTab);
            app.ClicktoimportvolumeLabel_14.Text = '1) Check boxes for the phase you want to calculate diameters';
            app.Dcpsd_phaseselection_UITable = uitable(app.DcPSDTab);
            app.Dcpsd_phaseselection_UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.Dcpsd_phaseselection_UITable.RowName = {};
            app.Dcpsd_phaseselection_UITable.Enable = 'off';
            app.CalculationmethodCpsdtLabel = uilabel(app.DcPSDTab);
            app.CalculationmethodCpsdtLabel.Text = {'Calculation method: "C-PSD". Voxels are assigned to the largest sphere that contain'; 'them and that is not overlapping with the complementary volume'; ''; 'Pros/cons:'; '+ Fast (compared with particle identification).'; '+ Parameter-free.'; '+ Limited edge effect induced by truncated particles.'; '+ Local diameter.'; '- Isotropic diameter.'; '- Strong underestimation due to spherical assumption'; '(due to surface roughness, non-spherical particles, and inclusion).'; ''; 'Recommendations:'; 'Adequate for particles with high-sphericity and for channel-like domain.'; 'Otherwise expect diameter to be underestimated by a factor up to 5 for electrode battery materials.'; ''};
            app.Image = uiimage(app.DcPSDTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Cpsd_illustration.png');
            app.Dcpsd_SelectallButton = uibutton(app.DcPSDTab, 'push');
            app.Dcpsd_SelectallButton.ButtonPushedFcn = createCallbackFcn(app, @Dcpsd_SelectallButtonPushed, true);
            app.Dcpsd_SelectallButton.Text = 'Select all';
            app.SphereapproachLabel = uilabel(app.DcPSDTab);
            app.SphereapproachLabel.Text = '(Sphere approach)';
            app.DEDMFTab = uitab(app.TabGroup);
            app.DEDMFTab.Title = 'D (EDMF)';
            app.Instructions_title_10 = uilabel(app.DEDMFTab);
            app.Instructions_title_10.Text = 'Diameter (Euclidean Distance Map Fitting, EDMF method) options';
            app.ClicktoimportvolumeLabel_15 = uilabel(app.DEDMFTab);
            app.ClicktoimportvolumeLabel_15.Text = '1) Check boxes for the phase you want to calculate diameters';
            app.Dedmf_phaseselection_UITable = uitable(app.DEDMFTab);
            app.Dedmf_phaseselection_UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.Dedmf_phaseselection_UITable.RowName = {};
            app.Dedmf_phaseselection_UITable.CellEditCallback = createCallbackFcn(app, @Dedmf_phaseselection_UITableCellEdit, true);
            app.Dedmf_phaseselection_UITable.Enable = 'off';
            app.CalculationmethodEDMFLabel = uilabel(app.DEDMFTab);
            app.CalculationmethodEDMFLabel.Text = {'Calculation method: "EDMF". Cumulative function of the Eculidean distance map of a sphere with a diameter D'; 'is analytically known. Cumulative function of the Eculidean distance map of the invesigated phase is calculated'; 'and D is fitted to match it.'; 'By default, one diameter is calculated for the whole phase volume. In addition, section diameters normal'; 'to selected directions can be calculated, but calculation is slow.'; ''; 'Pros/cons:'; '+ Fast (compared with particle identification).'; '+ Parameter-free.'; '+ Limited edge effect induced by truncated particles.'; '+ Often (but not always, e.g. cracks) in similar range with more CPU expensive D-PSD methods'; '+ Its diffusion distance approach fits well electrochemical models for which "diameter" is used as a characteristic diffusion distance.'; 'As well, it is adequate to model diffusion within cracked particles with open porosity and for elongated particles.'; '- Isotropic diameter.'; '- Only average diameter.'; ''; 'Recommendations:'; 'Method of choice for battery electrochemical macro-scale models such as Pseudo-2D or Single Particle Model.'; 'Use in conjonction with another method to get information on anisoptropy and gradation.'};
            app.Dedmf_SelectallButton = uibutton(app.DEDMFTab, 'push');
            app.Dedmf_SelectallButton.ButtonPushedFcn = createCallbackFcn(app, @Dedmf_SelectallButtonPushed, true);
            app.Dedmf_SelectallButton.Text = 'Select all';
            app.Image3 = uiimage(app.DEDMFTab);
            app.Image3.ImageClickedFcn = createCallbackFcn(app, @Image3Clicked, true);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'EDMF_illustration.png');
            app.ClickfigurebelowtoenlargeLabel = uilabel(app.DEDMFTab);
            app.ClickfigurebelowtoenlargeLabel.Text = {'Click figure below to enlarge'; 'Corresponds to case with "remove inclusion" not checked'; 'and distance toward label ignored (e.g. -1)'};
            app.DistancetowardlabelEditFieldLabel = uilabel(app.DEDMFTab);
            app.DistancetowardlabelEditFieldLabel.Text = 'Distance toward label';
            app.EDMF_DistancetowardlabelEditField = uieditfield(app.DEDMFTab, 'numeric');
            app.EDMF_DistancetowardlabelEditField.Limits = [-1 Inf];
            app.EDMF_DistancetowardlabelEditField.RoundFractionalValues = 'on';
            app.EDMF_DistancetowardlabelEditField.Value = -1;
            app.EDMF_RemoveinclusionCheckBox = uicheckbox(app.DEDMFTab);
            app.EDMF_RemoveinclusionCheckBox.Text = 'Remove inclusion';
            app.ClicktoimportvolumeLabel_26 = uilabel(app.DEDMFTab);
            app.ClicktoimportvolumeLabel_26.Text = '2) Set options specific to this method';
            app.setto1toignoreLabel = uilabel(app.DEDMFTab);
            app.setto1toignoreLabel.Text = '(set to -1 to ignore)';
            app.ClickfigurebelowtoenlargeLabel_2 = uilabel(app.DEDMFTab);
            app.ClickfigurebelowtoenlargeLabel_2.Text = 'Click figure below to enlarge';
            app.MethodexplanationLabel = uilabel(app.DEDMFTab);
            app.MethodexplanationLabel.Text = 'Method explanation';
            app.MethodsparameterexplanationLabel = uilabel(app.DEDMFTab);
            app.MethodsparameterexplanationLabel.Text = 'Method''s parameter explanation';
            app.Image4 = uiimage(app.DEDMFTab);
            app.Image4.ImageClickedFcn = createCallbackFcn(app, @Image4Clicked, true);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'EDMF_illustration_b.png');
            app.DistanceapproachLabel = uilabel(app.DEDMFTab);
            app.DistanceapproachLabel.Text = '(Distance approach)';
            app.DCLMFTab = uitab(app.TabGroup);
            app.DCLMFTab.Title = 'D (CLMF)';
            app.LenghtapproachLabel = uilabel(app.DCLMFTab);
            app.LenghtapproachLabel.Text = '(Lenght approach)';
            app.Instructions_title_20 = uilabel(app.DCLMFTab);
            app.Instructions_title_20.Text = 'Diameter (Chord Lenght Map Fitting, CLMF method) options';
            app.ClicktoimportvolumeLabel_27 = uilabel(app.DCLMFTab);
            app.ClicktoimportvolumeLabel_27.Text = '1) Check boxes for the phase you want to calculate diameters';
            app.Dchord_phaseselection_UITable = uitable(app.DCLMFTab);
            app.Dchord_phaseselection_UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.Dchord_phaseselection_UITable.RowName = {};
            app.Dchord_phaseselection_UITable.CellEditCallback = createCallbackFcn(app, @Dchord_phaseselection_UITableCellEdit, true);
            app.Dchord_phaseselection_UITable.Enable = 'off';
            app.Dchord_SelectallButton = uibutton(app.DCLMFTab, 'push');
            app.Dchord_SelectallButton.ButtonPushedFcn = createCallbackFcn(app, @Dchord_SelectallButtonPushed, true);
            app.Dchord_SelectallButton.Text = 'Select all';
            app.Image5 = uiimage(app.DCLMFTab);
            app.Image5.ImageClickedFcn = createCallbackFcn(app, @Image5Clicked, true);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'Chord_illustration_method.png');
            app.Image6 = uiimage(app.DCLMFTab);
            app.Image6.ImageClickedFcn = createCallbackFcn(app, @Image6Clicked, true);
            app.Image6.ImageSource = fullfile(pathToMLAPP, 'Chord_illustration_sphere.png');
            app.Image7 = uiimage(app.DCLMFTab);
            app.Image7.ImageClickedFcn = createCallbackFcn(app, @Image7Clicked, true);
            app.Image7.ImageSource = fullfile(pathToMLAPP, 'Chord_illustration_ellipsoid.png');
            app.MethodexplanationLabel_2 = uilabel(app.DCLMFTab);
            app.MethodexplanationLabel_2.Text = 'Method explanation';
            app.IllustratedonsphereLabel = uilabel(app.DCLMFTab);
            app.IllustratedonsphereLabel.Text = 'Illustrated on sphere';
            app.IllustratedonellipsoidLabel = uilabel(app.DCLMFTab);
            app.IllustratedonellipsoidLabel.Text = 'Illustrated on ellipsoid';
            app.ClickfigurestoenlargeLabel = uilabel(app.DCLMFTab);
            app.ClickfigurestoenlargeLabel.Text = 'Click figures to enlarge';
            app.CalculationmethodEDMFLabel_2 = uilabel(app.DCLMFTab);
            app.CalculationmethodEDMFLabel_2.Text = {'Calculation method: "CLMF": Cumulative function of the chord length maps of a sphere with a diameter D'; 'is analytically known. Cumulative function of the chord length maps of the investigated phase is calculated '; 'and D is fitted to match it. One D value per spatial direction.'; 'Chords truncated at the edges are not considered to limit edge effects.'; 'By default, all three diameters are calculated for the whole phase volume. In addition, section diameters normal to selected directions'; 'can be calculated, but calculation is slow.'; ''; 'Pros/Cons:'; '+ Fast (compared with particle identification)'; '+ Parameter-free'; '+  Compared with EDMF, provides anisotropy information'; '- Some edge effects.'; '- Biased if particles main axis are not aligned with axis (rotation).'; '- Overestimation is particles share large contact area.'; '- Only averaged diameters.'; ''; 'Recommendations:'; 'Use in complement with EDMF,  to evaluate particle diameter anisotropy.'; 'Not adequate If particles are in contact (voxel-wize) with  a large contact area.'};
            app.ClicktoimportvolumeLabel_28 = uilabel(app.DCLMFTab);
            app.ClicktoimportvolumeLabel_28.Text = '2) Set options specific to this method';
            app.SizefilterEditFieldLabel = uilabel(app.DCLMFTab);
            app.SizefilterEditFieldLabel.Text = 'Size filter';
            app.Chord_SizefilterEditField = uieditfield(app.DCLMFTab, 'numeric');
            app.Chord_SizefilterEditField.Limits = [0 Inf];
            app.Chord_SizefilterEditField.RoundFractionalValues = 'on';
            app.Chord_SizefilterEditField.Tooltip = {'Chords with length inferior or equal to this size filter (expressed in voxel length) are not considered. Set to 0 to ignore.'};
            app.DwatershedTab = uitab(app.TabGroup);
            app.DwatershedTab.Title = 'D (watershed)';
            app.Instructions_title_15 = uilabel(app.DwatershedTab);
            app.Instructions_title_15.Text = 'Diameter (Watershed method) options';
            app.ParticleidentificationvolumebasedapproachLabel = uilabel(app.DwatershedTab);
            app.ParticleidentificationvolumebasedapproachLabel.Text = '(Particle identification, volume-based, approach)';
            app.ClicktoimportvolumeLabel_29 = uilabel(app.DwatershedTab);
            app.ClicktoimportvolumeLabel_29.Text = '1) Check boxes for the phase you want to calculate diameters';
            app.Dwatershed_phaseselection_UITable = uitable(app.DwatershedTab);
            app.Dwatershed_phaseselection_UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.Dwatershed_phaseselection_UITable.RowName = {};
            app.Dwatershed_phaseselection_UITable.Enable = 'off';
            app.Dwatershed_SelectallButton = uibutton(app.DwatershedTab, 'push');
            app.Dwatershed_SelectallButton.ButtonPushedFcn = createCallbackFcn(app, @Dwatershed_SelectallButtonPushed, true);
            app.Dwatershed_SelectallButton.Text = 'Select all';
            app.ClicktoimportvolumeLabel_30 = uilabel(app.DwatershedTab);
            app.ClicktoimportvolumeLabel_30.Text = '2) Set options specific to this method';
            app.Watershed_Detail_CheckBox = uicheckbox(app.DwatershedTab);
            app.Watershed_Detail_CheckBox.Text = 'Detail algorithm progression (verbose)';
            app.Watershed_Detail_CheckBox.Value = true;
            app.Watershed_ReassignCheckBox = uicheckbox(app.DwatershedTab);
            app.Watershed_ReassignCheckBox.Text = {'Reassign voxels for which the diameter is lower than the diameter of the largest sphere that contains it without overlapping the complementary phase'; 'i.e., d-PSD(x) < c-PSD(x).'};
            app.Watershed_ReassignCheckBox.Value = true;
            app.SizefilternumberofvoxelwithinaparticleEditFieldLabel = uilabel(app.DwatershedTab);
            app.SizefilternumberofvoxelwithinaparticleEditFieldLabel.Text = 'Size filter (number of voxel within a particle). Set to 0 to ignore';
            app.Watershed_SizefilterEditField = uieditfield(app.DwatershedTab, 'numeric');
            app.Watershed_SizefilterEditField.Limits = [0 Inf];
            app.Watershed_SizefilterEditField.RoundFractionalValues = 'on';
            app.CorrectoversegmentationoptionsLabel = uilabel(app.DwatershedTab);
            app.CorrectoversegmentationoptionsLabel.Text = 'Correct oversegmentation options:';
            app.DPCRFTab = uitab(app.TabGroup);
            app.DPCRFTab.Title = 'D (PCRF)';
            app.Instructions_title_16 = uilabel(app.DPCRFTab);
            app.Instructions_title_16.Text = 'Diameter (Pseudo-Coulomb Repulsive Field method) options';
            app.ParticleidentificationvolumebasedapproachLabel_2 = uilabel(app.DPCRFTab);
            app.ParticleidentificationvolumebasedapproachLabel_2.Text = '(Particle identification, volume-based, approach)';
            app.DlabelTab = uitab(app.TabGroup);
            app.DlabelTab.Title = 'D (label)';
            app.Instructions_title_18 = uilabel(app.DlabelTab);
            app.Instructions_title_18.Text = 'Diameter (label) options';
            app.ParticleidentificationvolumebasedapproachLabel_3 = uilabel(app.DlabelTab);
            app.ParticleidentificationvolumebasedapproachLabel_3.Text = '(Particle identification, volume-based, approach)';
            app.DcovarianceTab = uitab(app.TabGroup);
            app.DcovarianceTab.Title = 'D (covariance)';
            app.Instructions_title_17 = uilabel(app.DcovarianceTab);
            app.Instructions_title_17.Text = 'Diameter (Covariance/two-point correlation method) options';
            app.StatisticalapproachLabel = uilabel(app.DcovarianceTab);
            app.StatisticalapproachLabel.Text = '(Statistical approach)';
            app.VoxelsizeTab = uitab(app.TabGroup);
            app.VoxelsizeTab.Title = 'Voxel size';
            app.Instructions_title_7 = uilabel(app.VoxelsizeTab);
            app.Instructions_title_7.Text = 'Voxel size (image resolution) dependence analysis. ';
            app.Label_6 = uilabel(app.VoxelsizeTab);
            app.Label_6.Text = {'In this tab, you can setup the voxel size dependence analysis options for each volume and each property indiviudally. By default, no analysis are setup.'; 'If analysis are added, they will be performed for all phases and directions you have selected on the previous tabs, for their respective property.'};
            app.ClicktoimportvolumeLabel_10 = uilabel(app.VoxelsizeTab);
            app.ClicktoimportvolumeLabel_10.Text = '1) Select property for which you want to set voxel size dependence analysis options.';
            app.voxelsizeUITable = uitable(app.VoxelsizeTab);
            app.voxelsizeUITable.ColumnName = {'Volume'; 'Voxel size'; 'Multiplier'; 'Voxel size * multiplier'};
            app.voxelsizeUITable.RowName = {};
            app.voxelsizeUITable.ColumnEditable = [false false true false];
            app.voxelsizeUITable.CellEditCallback = createCallbackFcn(app, @voxelsizeUITableCellEdit, true);
            app.voxelsizeUITable.Enable = 'off';
            app.Voxel_label = uilabel(app.VoxelsizeTab);
            app.Voxel_label.Text = 'Enter voxel size multiplier for each volume. Examples: 1 (no analysis), 1,1.5,2,2.5 (analysis, with voxel size downscaled up to 2.5 times the initial resolution)';
            app.PropertyselectionDropDownLabel_2 = uilabel(app.VoxelsizeTab);
            app.PropertyselectionDropDownLabel_2.Enable = 'off';
            app.PropertyselectionDropDownLabel_2.Text = 'Property selection';
            app.Voxelsize_PropertyselectionDropDown = uidropdown(app.VoxelsizeTab);
            app.Voxelsize_PropertyselectionDropDown.Items = {' '};
            app.Voxelsize_PropertyselectionDropDown.ValueChangedFcn = createCallbackFcn(app, @Voxelsize_PropertyselectionDropDownValueChanged, true);
            app.Voxelsize_PropertyselectionDropDown.Enable = 'off';
            app.Voxelsize_PropertyselectionDropDown.Value = ' ';
            app.ClicktoimportvolumeLabel_11 = uilabel(app.VoxelsizeTab);
            app.ClicktoimportvolumeLabel_11.Text = '2) Write down multiplier and choose how to extrapolate to zero voxel size.';
            app.ExtrapolatetozerovoxelsizeDropDownLabel_2 = uilabel(app.VoxelsizeTab);
            app.ExtrapolatetozerovoxelsizeDropDownLabel_2.Enable = 'off';
            app.ExtrapolatetozerovoxelsizeDropDownLabel_2.Text = 'Extrapolate to zero voxel size';
            app.Voxel_ExtrapolatetozerovoxelsizeDropDown = uidropdown(app.VoxelsizeTab);
            app.Voxel_ExtrapolatetozerovoxelsizeDropDown.Items = {'Linear', 'Quadratic', 'Cubic'};
            app.Voxel_ExtrapolatetozerovoxelsizeDropDown.ValueChangedFcn = createCallbackFcn(app, @Voxel_ExtrapolatetozerovoxelsizeDropDownValueChanged, true);
            app.Voxel_ExtrapolatetozerovoxelsizeDropDown.Enable = 'off';
            app.Voxel_ExtrapolatetozerovoxelsizeDropDown.Value = 'Linear';
            app.ParticlelevelofdetailExtrapolatetozerovoxelsizeLabel = uilabel(app.VoxelsizeTab);
            app.ParticlelevelofdetailExtrapolatetozerovoxelsizeLabel.Visible = 'off';
            app.ParticlelevelofdetailExtrapolatetozerovoxelsizeLabel.Text = '(Particle level of detail) Extrapolate to zero voxel size';
            app.LvlDetail_ExtrapolatetozerovoxelsizeDropDown = uidropdown(app.VoxelsizeTab);
            app.LvlDetail_ExtrapolatetozerovoxelsizeDropDown.Items = {'Linear', 'Quadratic', 'Cubic'};
            app.LvlDetail_ExtrapolatetozerovoxelsizeDropDown.ValueChangedFcn = createCallbackFcn(app, @Voxel_ExtrapolatetozerovoxelsizeDropDownValueChanged, true);
            app.LvlDetail_ExtrapolatetozerovoxelsizeDropDown.Enable = 'off';
            app.LvlDetail_ExtrapolatetozerovoxelsizeDropDown.Visible = 'off';
            app.LvlDetail_ExtrapolatetozerovoxelsizeDropDown.Value = 'Linear';
            app.ParticlelevelofdetailExtrapolatetozerovoxelsizeLabel_2 = uilabel(app.VoxelsizeTab);
            app.ParticlelevelofdetailExtrapolatetozerovoxelsizeLabel_2.Visible = 'off';
            app.ParticlelevelofdetailExtrapolatetozerovoxelsizeLabel_2.Text = '(Bruggeman exponent) Extrapolate to zero voxel size';
            app.Bruggeman_ExtrapolatetozerovoxelsizeDropDown = uidropdown(app.VoxelsizeTab);
            app.Bruggeman_ExtrapolatetozerovoxelsizeDropDown.Items = {'Linear', 'Quadratic', 'Cubic'};
            app.Bruggeman_ExtrapolatetozerovoxelsizeDropDown.ValueChangedFcn = createCallbackFcn(app, @Voxel_ExtrapolatetozerovoxelsizeDropDownValueChanged, true);
            app.Bruggeman_ExtrapolatetozerovoxelsizeDropDown.Enable = 'off';
            app.Bruggeman_ExtrapolatetozerovoxelsizeDropDown.Visible = 'off';
            app.Bruggeman_ExtrapolatetozerovoxelsizeDropDown.Value = 'Linear';
            app.ParticlelevelofdetailExtrapolatetozerovoxelsizeLabel_3 = uilabel(app.VoxelsizeTab);
            app.ParticlelevelofdetailExtrapolatetozerovoxelsizeLabel_3.Visible = 'off';
            app.ParticlelevelofdetailExtrapolatetozerovoxelsizeLabel_3.Text = '(Normalized effective difussion coefficient) Extrapolate to zero voxel size';
            app.Deff_ExtrapolatetozerovoxelsizeDropDown = uidropdown(app.VoxelsizeTab);
            app.Deff_ExtrapolatetozerovoxelsizeDropDown.Items = {'Linear', 'Quadratic', 'Cubic'};
            app.Deff_ExtrapolatetozerovoxelsizeDropDown.ValueChangedFcn = createCallbackFcn(app, @Voxel_ExtrapolatetozerovoxelsizeDropDownValueChanged, true);
            app.Deff_ExtrapolatetozerovoxelsizeDropDown.Enable = 'off';
            app.Deff_ExtrapolatetozerovoxelsizeDropDown.Visible = 'off';
            app.Deff_ExtrapolatetozerovoxelsizeDropDown.Value = 'Linear';
            app.RVEconvergenceTab = uitab(app.TabGroup);
            app.RVEconvergenceTab.Title = 'RVE/convergence';
            app.Instructions_title_6 = uilabel(app.RVEconvergenceTab);
            app.Instructions_title_6.Text = 'Representative Volume Element (RVE) and convergence analysis options';
            app.Label_4 = uilabel(app.RVEconvergenceTab);
            app.Label_4.Text = {'In this tab, you can setup the RVE/convergence options for each volume and each property indiviudally. By default, no analysis are setup. If analysis are added,'; 'they will be performed for all phases and directions you have selected on the previous tabs, for their respective property.'};
            app.VolumeselectionDropDownLabel = uilabel(app.RVEconvergenceTab);
            app.VolumeselectionDropDownLabel.Enable = 'off';
            app.VolumeselectionDropDownLabel.Text = 'Volume selection';
            app.RVE_VolumeselectionDropDown = uidropdown(app.RVEconvergenceTab);
            app.RVE_VolumeselectionDropDown.Items = {' '};
            app.RVE_VolumeselectionDropDown.ValueChangedFcn = createCallbackFcn(app, @RVE_PropertyselectionDropDownValueChanged, true);
            app.RVE_VolumeselectionDropDown.Enable = 'off';
            app.RVE_VolumeselectionDropDown.Value = ' ';
            app.ClicktoimportvolumeLabel_8 = uilabel(app.RVEconvergenceTab);
            app.ClicktoimportvolumeLabel_8.Text = '1) Select volume and property for which you want to set RVE/convergence options, and either add one or remove all analysis for this doublet';
            app.PropertyselectionDropDownLabel = uilabel(app.RVEconvergenceTab);
            app.PropertyselectionDropDownLabel.Enable = 'off';
            app.PropertyselectionDropDownLabel.Text = 'Property selection';
            app.RVE_PropertyselectionDropDown = uidropdown(app.RVEconvergenceTab);
            app.RVE_PropertyselectionDropDown.Items = {' '};
            app.RVE_PropertyselectionDropDown.ValueChangedFcn = createCallbackFcn(app, @RVE_PropertyselectionDropDownValueChanged, true);
            app.RVE_PropertyselectionDropDown.Enable = 'off';
            app.RVE_PropertyselectionDropDown.Tooltip = {'RVE is property dependent. Volume fractions have typically the smallest RVE, so you should check for the other parameters of your model.'};
            app.RVE_PropertyselectionDropDown.Value = ' ';
            app.RVE_apply_ALLproperties_THISvolume_button = uibutton(app.RVEconvergenceTab, 'push');
            app.RVE_apply_ALLproperties_THISvolume_button.ButtonPushedFcn = createCallbackFcn(app, @RVE_apply_ALLproperties_THISvolume_buttonButtonPushed, true);
            app.RVE_apply_ALLproperties_THISvolume_button.Enable = 'off';
            app.RVE_apply_ALLproperties_THISvolume_button.Tooltip = {''};
            app.RVE_apply_ALLproperties_THISvolume_button.Text = 'Apply THE option above on ALL properties for THIS volume';
            app.RVE_add_button = uibutton(app.RVEconvergenceTab, 'push');
            app.RVE_add_button.ButtonPushedFcn = createCallbackFcn(app, @RVE_add_buttonButtonPushed, true);
            app.RVE_add_button.Enable = 'off';
            app.RVE_add_button.Text = 'Add a RVE or convergence analysis';
            app.RVE_remove_button = uibutton(app.RVEconvergenceTab, 'push');
            app.RVE_remove_button.ButtonPushedFcn = createCallbackFcn(app, @RVE_remove_buttonButtonPushed, true);
            app.RVE_remove_button.Enable = 'off';
            app.RVE_remove_button.Text = 'Remove analysis';
            app.ClicktoimportvolumeLabel_9 = uilabel(app.RVEconvergenceTab);
            app.ClicktoimportvolumeLabel_9.Text = '2) (Optional) To avoid manually setup each doublet {volume, property}, you can copy your options on others doublets.';
            app.RVE_apply_ALLproperties_ALLvolumes_button = uibutton(app.RVEconvergenceTab, 'push');
            app.RVE_apply_ALLproperties_ALLvolumes_button.ButtonPushedFcn = createCallbackFcn(app, @RVE_apply_ALLproperties_ALLvolumes_buttonButtonPushed, true);
            app.RVE_apply_ALLproperties_ALLvolumes_button.Enable = 'off';
            app.RVE_apply_ALLproperties_ALLvolumes_button.Tooltip = {''};
            app.RVE_apply_ALLproperties_ALLvolumes_button.Text = 'Apply THE option above on ALL properties for ALL volumes';
            app.RVE_apply_PERproperties_ALLvolumes_button = uibutton(app.RVEconvergenceTab, 'push');
            app.RVE_apply_PERproperties_ALLvolumes_button.ButtonPushedFcn = createCallbackFcn(app, @RVE_apply_PERproperties_ALLvolumes_buttonButtonPushed, true);
            app.RVE_apply_PERproperties_ALLvolumes_button.Enable = 'off';
            app.RVE_apply_PERproperties_ALLvolumes_button.Tooltip = {''};
            app.RVE_apply_PERproperties_ALLvolumes_button.Text = 'Apply ALL options of this volume, property PER property, for ALL volumes';
            app.RVE_apply_THISproperty_ALLvolumes_button = uibutton(app.RVEconvergenceTab, 'push');
            app.RVE_apply_THISproperty_ALLvolumes_button.ButtonPushedFcn = createCallbackFcn(app, @RVE_apply_THISproperty_ALLvolumes_buttonButtonPushed, true);
            app.RVE_apply_THISproperty_ALLvolumes_button.Enable = 'off';
            app.RVE_apply_THISproperty_ALLvolumes_button.Tooltip = {''};
            app.RVE_apply_THISproperty_ALLvolumes_button.Text = 'Apply THE option above on THIS property for ALL volumes';
            app.CopyoptionssetintheabovetableusingonthethreeoptionsbelowLabel = uilabel(app.RVEconvergenceTab);
            app.CopyoptionssetintheabovetableusingonthethreeoptionsbelowLabel.Text = 'Copy options set in the above table using on the three options below:';
            app.OrLabel = uilabel(app.RVEconvergenceTab);
            app.OrLabel.Text = 'Or:';
            app.RVEandconvergenceoptionsPanel = uipanel(app.RVEconvergenceTab);
            app.RVEandconvergenceoptionsPanel.Enable = 'off';
            app.RVEandconvergenceoptionsPanel.Title = 'RVE and convergence options';
            app.RVEandconvergenceoptionsPanel.Visible = 'off';
            app.SelectRVEorconvergenceanalysisDropDownLabel = uilabel(app.RVEandconvergenceoptionsPanel);
            app.SelectRVEorconvergenceanalysisDropDownLabel.Text = 'Select RVE or convergence analysis';
            app.SelectRVEorconvergenceanalysisDropDown = uidropdown(app.RVEandconvergenceoptionsPanel);
            app.SelectRVEorconvergenceanalysisDropDown.Items = {'Independant subvolumes of same size + keep initial aspect ratio (A)', 'Independant subvolumes of same size + user-defined aspect ratio (B)', 'Independant subvolumes of same size + one constant length (C)', 'Independant subvolumes of same size + two constant lengths (D)', 'One subvolume + growing from volume center + keep initial aspect ratio (E)', 'One subvolume + growing from volume center + user-defined aspect ratio (F)', 'One subvolume + growing from volume edge (G)', 'One subvolume + one constant length (H)'};
            app.SelectRVEorconvergenceanalysisDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectRVEorconvergenceanalysisDropDownValueChanged, true);
            app.SelectRVEorconvergenceanalysisDropDown.Tooltip = {'For battery electrode materials, you should use case C + constant direction = electrode thickness. Warning: convergence analysis are NOT representative volume element analysis and should not be interpretated as it.'};
            app.SelectRVEorconvergenceanalysisDropDown.Value = 'Independant subvolumes of same size + keep initial aspect ratio (A)';
            app.RVE_saveoptionButton = uibutton(app.RVEandconvergenceoptionsPanel, 'push');
            app.RVE_saveoptionButton.ButtonPushedFcn = createCallbackFcn(app, @RVE_saveoptionButtonPushed, true);
            app.RVE_saveoptionButton.Text = 'Save your option';
            app.RVE_canceloptionButton = uibutton(app.RVEandconvergenceoptionsPanel, 'push');
            app.RVE_canceloptionButton.ButtonPushedFcn = createCallbackFcn(app, @RVE_canceloptionButtonPushed, true);
            app.RVE_canceloptionButton.Text = 'Cancel';
            app.NestedanalysisPanel = uipanel(app.RVEandconvergenceoptionsPanel);
            app.NestedanalysisPanel.Tooltip = {'As calculated RVE sizes of doublets {volume, property} are lower bounds of the actual sizes as we have access to only a subsample of the full geometry. If the FOV is indeed large enough to identify the RVE size among subvolumes, then if you would have access to an even larger FOV, you should calculate the same RVE size. Conversely, for smaller FOV, as volume get less representative, the RVE size calculated would vary. RVE size is therefore converging with FOV. In practical, you can expect RVE to be increasing with FOV (as larger FOV can include more heterogeneity) until it reaches convergence. Once convergence is reached, the calculated lower bound RVE size is believed to be very close for the real RVE. Same reasoning applies for the convergence analysis.'; 'The Nested analysis will apply the selected RVE or convergence analysis on sequentially smaller FOV (included within each other, thus the name ‘nested’) and plot the RVE/convergence size as function of FOV to check for the convergence.'};
            app.NestedanalysisPanel.Title = 'Nested analysis';
            app.DonestedanalysisCheckBox = uicheckbox(app.NestedanalysisPanel);
            app.DonestedanalysisCheckBox.ValueChangedFcn = createCallbackFcn(app, @DonestedanalysisCheckBoxValueChanged, true);
            app.DonestedanalysisCheckBox.Text = 'Do nested analysis';
            app.ShrinkDropDownLabel = uilabel(app.NestedanalysisPanel);
            app.ShrinkDropDownLabel.Enable = 'off';
            app.ShrinkDropDownLabel.Text = 'Shrink';
            app.RVE_ShrinkDropDown = uidropdown(app.NestedanalysisPanel);
            app.RVE_ShrinkDropDown.Items = {'1 direction', '2 directions', '3 directions'};
            app.RVE_ShrinkDropDown.ValueChangedFcn = createCallbackFcn(app, @RVE_ShrinkDropDownValueChanged, true);
            app.RVE_ShrinkDropDown.Enable = 'off';
            app.RVE_ShrinkDropDown.Value = '1 direction';
            app.thatisDropDownLabel = uilabel(app.NestedanalysisPanel);
            app.thatisDropDownLabel.Enable = 'off';
            app.thatisDropDownLabel.Text = 'that is:';
            app.RVE_thatisDropDown = uidropdown(app.NestedanalysisPanel);
            app.RVE_thatisDropDown.Items = {' '};
            app.RVE_thatisDropDown.Enable = 'off';
            app.RVE_thatisDropDown.Value = ' ';
            app.AteachstepremoveLabel = uilabel(app.NestedanalysisPanel);
            app.AteachstepremoveLabel.Enable = 'off';
            app.AteachstepremoveLabel.Text = 'At each step:';
            app.RVE_AteachstepDropDown = uidropdown(app.NestedanalysisPanel);
            app.RVE_AteachstepDropDown.Items = {'remove x %vol of the initial volume ', 'crop length of shrinking direction(s) is x% of the initial full volume cubic root'};
            app.RVE_AteachstepDropDown.Enable = 'off';
            app.RVE_AteachstepDropDown.Value = 'remove x %vol of the initial volume ';
            app.xEditFieldLabel = uilabel(app.NestedanalysisPanel);
            app.xEditFieldLabel.Enable = 'off';
            app.xEditFieldLabel.Text = 'x (%)';
            app.RVE_xEditField = uieditfield(app.NestedanalysisPanel, 'numeric');
            app.RVE_xEditField.Limits = [0.01 100];
            app.RVE_xEditField.Enable = 'off';
            app.RVE_xEditField.Value = 5;
            app.MinimumvolumetoapplyanalysisLabel = uilabel(app.NestedanalysisPanel);
            app.MinimumvolumetoapplyanalysisLabel.Enable = 'off';
            app.MinimumvolumetoapplyanalysisLabel.Text = 'Minimum volume to apply analysis';
            app.RVE_MinimumvolumetoapplyRVEanalysisEditField = uieditfield(app.NestedanalysisPanel, 'numeric');
            app.RVE_MinimumvolumetoapplyRVEanalysisEditField.Limits = [0.01 100];
            app.RVE_MinimumvolumetoapplyRVEanalysisEditField.Enable = 'off';
            app.RVE_MinimumvolumetoapplyRVEanalysisEditField.Value = 10;
            app.RVE_ofinitialvolumeLabel = uilabel(app.NestedanalysisPanel);
            app.RVE_ofinitialvolumeLabel.Enable = 'off';
            app.RVE_ofinitialvolumeLabel.Text = '% of initial volume';
            app.CaseABCDRVEanalysisPanel = uipanel(app.RVEandconvergenceoptionsPanel);
            app.CaseABCDRVEanalysisPanel.Title = 'Case A, B, C, D (RVE analysis)';
            app.DividerEditFieldLabel = uilabel(app.CaseABCDRVEanalysisPanel);
            app.DividerEditFieldLabel.Text = 'Divider';
            app.DividerEditField = uieditfield(app.CaseABCDRVEanalysisPanel, 'text');
            app.DividerEditField.ValueChangedFcn = createCallbackFcn(app, @DividerEditFieldValueChanged, true);
            app.DividerEditField.Tooltip = {'Calculate properties on independent subdomains. Number of subdomains = divider^3 for cases A and B, and divider^2 for case C.'};
            app.DividerEditField.Value = '2,3,4,5';
            app.Mustbealistofincreasinginteger2Label = uilabel(app.CaseABCDRVEanalysisPanel);
            app.Mustbealistofincreasinginteger2Label.Text = 'Must be a list of increasing integer >=2';
            app.subvolumes_sub2_CheckBox = uicheckbox(app.CaseABCDRVEanalysisPanel);
            app.subvolumes_sub2_CheckBox.Text = '2 subvolumes';
            app.subvolumes_sub4_CheckBox = uicheckbox(app.CaseABCDRVEanalysisPanel);
            app.subvolumes_sub4_CheckBox.Text = '4 subvolumes';
            app.SubvolumeaspectratioEditFieldLabel = uilabel(app.CaseABCDRVEanalysisPanel);
            app.SubvolumeaspectratioEditFieldLabel.Text = 'Subvolume aspect ratio';
            app.SubvolumeaspectratioEditField = uieditfield(app.CaseABCDRVEanalysisPanel, 'text');
            app.SubvolumeaspectratioEditField.ValueChangedFcn = createCallbackFcn(app, @SubvolumeaspectratioEditFieldValueChanged, true);
            app.SubvolumeaspectratioEditField.Tooltip = {'Subvolumes 100 x 100 x 100 um3 and 1 x 1 x 1000000 um3 have same total volume, but properties calculated on them (espeically oriented or percolating-dependent properties) will be different.'};
            app.SubvolumeaspectratioEditField.Value = '1,1,1';
            app.Formatabcwithabcrealnumber0Label = uilabel(app.CaseABCDRVEanalysisPanel);
            app.Formatabcwithabcrealnumber0Label.Text = 'Format: a,b,c, with a,b,c real number >0';
            app.ConstantdirectionslengthDropDownLabel = uilabel(app.CaseABCDRVEanalysisPanel);
            app.ConstantdirectionslengthDropDownLabel.Text = 'Constant direction(s) length';
            app.ConstantdirectionslengthDropDown = uidropdown(app.CaseABCDRVEanalysisPanel);
            app.ConstantdirectionslengthDropDown.Items = {'Direction 1', 'Direction 2', 'Direction 3'};
            app.ConstantdirectionslengthDropDown.Tooltip = {'For battery electrode materials, you should use case C + constant direction = electrode thickness.'};
            app.ConstantdirectionslengthDropDown.Value = 'Direction 1';
            app.ForatleastnsubvolumesEditFieldLabel = uilabel(app.CaseABCDRVEanalysisPanel);
            app.ForatleastnsubvolumesEditFieldLabel.Text = 'For at least n subvolumes';
            app.ForatleastnsubvolumesEditField = uieditfield(app.CaseABCDRVEanalysisPanel, 'numeric');
            app.ForatleastnsubvolumesEditField.Limits = [2 Inf];
            app.ForatleastnsubvolumesEditField.RoundFractionalValues = 'on';
            app.ForatleastnsubvolumesEditField.Tooltip = {'Statistics performed on a low number of volumes are meaningless. Unfortunatelly, very often volumes are not large enough to have the luxury to only do statistics on dozens of subvolumes.'};
            app.ForatleastnsubvolumesEditField.Value = 4;
            app.MinimumsubvvolumesizeEditFieldLabel = uilabel(app.CaseABCDRVEanalysisPanel);
            app.MinimumsubvvolumesizeEditFieldLabel.Text = 'Minimum subvvolume size';
            app.MinimumsubvvolumesizeEditField = uieditfield(app.CaseABCDRVEanalysisPanel, 'numeric');
            app.MinimumsubvvolumesizeEditField.Limits = [1e-07 50];
            app.MinimumsubvvolumesizeEditField.Tooltip = {'To prevent analysing subvolumes too small to be relevant. For nested analysis, the % apply to the same initial volume so that as the cropping progress the high divider values will not be used to prevent too small subvolumes.'};
            app.MinimumsubvvolumesizeEditField.Value = 1;
            app.ofinitialvolumeLabel = uilabel(app.CaseABCDRVEanalysisPanel);
            app.ofinitialvolumeLabel.Tooltip = {'To prevent analysing subvolume too small to be relevant. For nested analysis, the % apply to the same initial volume so that as the cropping progress the high divider value will not be used to prevent too small subvolumes.'};
            app.ofinitialvolumeLabel.Text = '% of initial volume';
            app.IterateuntilDropDownLabel = uilabel(app.CaseABCDRVEanalysisPanel);
            app.IterateuntilDropDownLabel.Text = 'Iterate until';
            app.RVE_IterateuntilDropDown = uidropdown(app.CaseABCDRVEanalysisPanel);
            app.RVE_IterateuntilDropDown.Items = {'All divider have been used or minimum subvolume size has been reached', 'Minimum subvolume size has been reached (ignore divider value)'};
            app.RVE_IterateuntilDropDown.Value = 'All divider have been used or minimum subvolume size has been reached';
            app.Mustbealistofreal0Label = uilabel(app.CaseABCDRVEanalysisPanel);
            app.Mustbealistofreal0Label.Text = 'Must be a list of real >0';
            app.StandarddeviationthresholdsEditFieldLabel = uilabel(app.CaseABCDRVEanalysisPanel);
            app.StandarddeviationthresholdsEditFieldLabel.Text = 'Standard deviation threshold(s) (%)';
            app.StandarddeviationthresholdsEditField = uieditfield(app.CaseABCDRVEanalysisPanel, 'text');
            app.StandarddeviationthresholdsEditField.ValueChangedFcn = createCallbackFcn(app, @StandarddeviationthresholdsEditFieldValueChanged, true);
            app.StandarddeviationthresholdsEditField.Value = '1, 2.5, 5, 7.5, 10';
            app.CaseEFGHconvergenceanalysisPanel = uipanel(app.RVEandconvergenceoptionsPanel);
            app.CaseEFGHconvergenceanalysisPanel.Title = 'Case E, F, G, H (convergence analysis)';
            app.GrowthdirectionDropDownLabel = uilabel(app.CaseEFGHconvergenceanalysisPanel);
            app.GrowthdirectionDropDownLabel.Text = 'Growth direction';
            app.GrowthdirectionDropDown = uidropdown(app.CaseEFGHconvergenceanalysisPanel);
            app.GrowthdirectionDropDown.Items = {'Direction 1, from x min to x max', 'Direction 1, from x max to x min', 'Direction 2, from y min to y max', 'Direction 2, from y max to y min', 'Direction 3, from z min to z max', 'Direction 3, from z max to z min'};
            app.GrowthdirectionDropDown.Value = 'Direction 1, from x min to x max';
            app.SubvolumeaspectratioEditField_2Label = uilabel(app.CaseEFGHconvergenceanalysisPanel);
            app.SubvolumeaspectratioEditField_2Label.Text = 'Subvolume aspect ratio';
            app.SubvolumeaspectratioEditField_2 = uieditfield(app.CaseEFGHconvergenceanalysisPanel, 'text');
            app.SubvolumeaspectratioEditField_2.ValueChangedFcn = createCallbackFcn(app, @SubvolumeaspectratioEditField_2ValueChanged, true);
            app.SubvolumeaspectratioEditField_2.Value = '1,1,1';
            app.SizeofthefirstsubvolumeEditFieldLabel = uilabel(app.CaseEFGHconvergenceanalysisPanel);
            app.SizeofthefirstsubvolumeEditFieldLabel.Text = 'Size of the first subvolume';
            app.SizeofthefirstsubvolumeEditField = uieditfield(app.CaseEFGHconvergenceanalysisPanel, 'numeric');
            app.SizeofthefirstsubvolumeEditField.Limits = [0.01 50];
            app.SizeofthefirstsubvolumeEditField.Value = 5;
            app.InofthefullvolumeLabel = uilabel(app.CaseEFGHconvergenceanalysisPanel);
            app.InofthefullvolumeLabel.Text = 'In % of the full volume';
            app.VolumegrowthEditFieldLabel = uilabel(app.CaseEFGHconvergenceanalysisPanel);
            app.VolumegrowthEditFieldLabel.Text = 'Volume growth';
            app.VolumegrowthEditField = uieditfield(app.CaseEFGHconvergenceanalysisPanel, 'numeric');
            app.VolumegrowthEditField.Limits = [0.01 50];
            app.VolumegrowthEditField.Value = 5;
            app.InDropDownLabel = uilabel(app.CaseEFGHconvergenceanalysisPanel);
            app.InDropDownLabel.Text = 'In';
            app.RVEgrowthrelativeto_InDropDown = uidropdown(app.CaseEFGHconvergenceanalysisPanel);
            app.RVEgrowthrelativeto_InDropDown.Items = {'% of the full volume', '% of the previous subvolume'};
            app.RVEgrowthrelativeto_InDropDown.Tooltip = {'"% of the previous subvolume"enforve Dv/V0=(V1-V0)/V0 to be constant. While for "% of the full volume", this relative volume increment is decreasing.'};
            app.RVEgrowthrelativeto_InDropDown.Value = '% of the full volume';
            app.RelativedifferencethresholdsEditFieldLabel = uilabel(app.CaseEFGHconvergenceanalysisPanel);
            app.RelativedifferencethresholdsEditFieldLabel.Text = 'Relative difference threshold(s) (%)';
            app.RelativedifferencethresholdsEditField = uieditfield(app.CaseEFGHconvergenceanalysisPanel, 'text');
            app.RelativedifferencethresholdsEditField.ValueChangedFcn = createCallbackFcn(app, @RelativedifferencethresholdsEditFieldValueChanged, true);
            app.RelativedifferencethresholdsEditField.Value = '1, 2.5, 5, 10';
            app.Mustbealistofreal0Label_2 = uilabel(app.CaseEFGHconvergenceanalysisPanel);
            app.Mustbealistofreal0Label_2.Text = 'Must be a list of real >0';
            app.RVE_UITable = uitable(app.RVEconvergenceTab);
            app.RVE_UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.RVE_UITable.RowName = {};
            app.RVE_UITable.Enable = 'off';
            app.RVE_UITable.Visible = 'off';
            app.ClickonthebuttonsbelowforhelpLabel = uilabel(app.RVEconvergenceTab);
            app.ClickonthebuttonsbelowforhelpLabel.Text = {'Click on the buttons below for help.'; 'Please read reference [x] (see references tab)'; 'for more details.'};
            app.HelpRVEdefinitionButton = uibutton(app.RVEconvergenceTab, 'push');
            app.HelpRVEdefinitionButton.ButtonPushedFcn = createCallbackFcn(app, @HelpRVEdefinitionButtonPushed, true);
            app.HelpRVEdefinitionButton.Text = 'Help: RVE definition';
            app.HelpRVEandconvergencecasesButton = uibutton(app.RVEconvergenceTab, 'push');
            app.HelpRVEandconvergencecasesButton.ButtonPushedFcn = createCallbackFcn(app, @HelpRVEandconvergencecasesButtonPushed, true);
            app.HelpRVEandconvergencecasesButton.Text = 'Help: RVE and convergence cases';
            app.HelpresultinterpretationButton = uibutton(app.RVEconvergenceTab, 'push');
            app.HelpresultinterpretationButton.ButtonPushedFcn = createCallbackFcn(app, @HelpresultinterpretationButtonPushed, true);
            app.HelpresultinterpretationButton.Text = 'Help: result interpretation';
            app.RunTab = uitab(app.TabGroup);
            app.RunTab.Title = 'Run';
            app.Instructions_title_5 = uilabel(app.RunTab);
            app.Instructions_title_5.Text = 'Run all calculations';
            app.ClicktoimportvolumeLabel_7 = uilabel(app.RunTab);
            app.ClicktoimportvolumeLabel_7.Text = '1) Once you have setup all previous tabs, you can click on the button below to characterize all volumes sequentially';
            app.Run_button = uibutton(app.RunTab, 'push');
            app.Run_button.ButtonPushedFcn = createCallbackFcn(app, @Run_buttonButtonPushed, true);
            app.Run_button.Enable = 'off';
            app.Run_button.Tooltip = {''};
            app.Run_button.Text = '>>> CLICK TO START CALCULATIONS <<<';
            app.RunStatut_UITable = uitable(app.RunTab);
            app.RunStatut_UITable.ColumnName = {'Volume'; 'Statut'; 'Started'; 'Wallclock time'};
            app.RunStatut_UITable.RowName = {};
            app.ConvergenceTab = uitab(app.TabGroup);
            app.ConvergenceTab.Title = 'Convergence';
            app.Instructions_title_21 = uilabel(app.ConvergenceTab);
            app.Instructions_title_21.Text = 'Convergence with mesh';
            app.Label_18 = uilabel(app.ConvergenceTab);
            app.Label_18.Text = 'Specific surface area (direct method with corrective factor = 2/3)';
            app.Image_SpConvergence = uiimage(app.ConvergenceTab);
            app.Image_SpConvergence.ImageClickedFcn = createCallbackFcn(app, @Image_SpConvergenceClicked, true);
            app.Image_SpConvergence.ImageSource = fullfile(pathToMLAPP, 'Specificsurfacearea_convergence_illustration.png');
            app.Label_19 = uilabel(app.ConvergenceTab);
            app.Label_19.Text = 'Particle diameter (c-PSD, EDMF, CLMF, particle identification)';
            app.Image_DiameterConvergence = uiimage(app.ConvergenceTab);
            app.Image_DiameterConvergence.ImageClickedFcn = createCallbackFcn(app, @Image_DiameterConvergenceClicked, true);
            app.Image_DiameterConvergence.ImageSource = fullfile(pathToMLAPP, 'Diameter_convergence_illustration.png');
            app.RecalculateConvergenceButton = uibutton(app.ConvergenceTab, 'push');
            app.RecalculateConvergenceButton.ButtonPushedFcn = createCallbackFcn(app, @RecalculateConvergenceButtonPushed, true);
            app.RecalculateConvergenceButton.Text = 'Re-calculate (single sphere)';
            app.Label_20 = uilabel(app.ConvergenceTab);
            app.Label_20.Text = {'In this tab you can visualize the characterization error induced by mesh resolution for various algorithms. Note these numbers only stand for ideal spheres'; '(i.e., no surface rougness, no cracks), real material microstructures likely have different error with mesh resolution. Click on the figures to enlarge them.'; 'You can also recalculate the graphs.'};
            app.UptoxnumberofvoxelperdiametersinglesphereEditFieldLabel = uilabel(app.ConvergenceTab);
            app.UptoxnumberofvoxelperdiametersinglesphereEditFieldLabel.Text = 'Up to x number of voxel per diameter  (single sphere)';
            app.UptoxnumberofvoxelperdiametersinglesphereEditField = uieditfield(app.ConvergenceTab, 'numeric');
            app.UptoxnumberofvoxelperdiametersinglesphereEditField.Limits = [9 Inf];
            app.UptoxnumberofvoxelperdiametersinglesphereEditField.RoundFractionalValues = 'on';
            app.UptoxnumberofvoxelperdiametersinglesphereEditField.Tooltip = {'For diameter and specific surface area.'};
            app.UptoxnumberofvoxelperdiametersinglesphereEditField.Value = 100;
            app.Label_27 = uilabel(app.ConvergenceTab);
            app.Label_27.Text = 'Bruggeman exponent (TauFactor)';
            app.Image8 = uiimage(app.ConvergenceTab);
            app.UptoxnumberofvoxelperdiametermultiplesphereEditFieldLabel = uilabel(app.ConvergenceTab);
            app.UptoxnumberofvoxelperdiametermultiplesphereEditFieldLabel.Text = 'Up to x number of voxel per diameter  (randomly distributed isolated sphere)';
            app.UptoxnumberofvoxelperdiametermultiplesphereEditField = uieditfield(app.ConvergenceTab, 'numeric');
            app.UptoxnumberofvoxelperdiametermultiplesphereEditField.Limits = [9 Inf];
            app.UptoxnumberofvoxelperdiametermultiplesphereEditField.RoundFractionalValues = 'on';
            app.UptoxnumberofvoxelperdiametermultiplesphereEditField.Tooltip = {'For tortuosity factor. Domain size is 15 times particle diameter.'};
            app.UptoxnumberofvoxelperdiametermultiplesphereEditField.Value = 21;
            app.SinglesphereLabel = uilabel(app.ConvergenceTab);
            app.SinglesphereLabel.Text = 'Single sphere';
            app.TwospheresLabel = uilabel(app.ConvergenceTab);
            app.TwospheresLabel.Text = 'Two spheres';
            app.Label_28 = uilabel(app.ConvergenceTab);
            app.Label_28.Text = 'Specific interface area (direct method)';
            app.Label_29 = uilabel(app.ConvergenceTab);
            app.Label_29.Text = 'TPBL (direct method)';
            app.RandomlydistributedisolatedsphereLabel = uilabel(app.ConvergenceTab);
            app.RandomlydistributedisolatedsphereLabel.Text = 'Randomly distributed isolated sphere';
            app.RecalculateConvergenceButton_2 = uibutton(app.ConvergenceTab, 'push');
            app.RecalculateConvergenceButton_2.ButtonPushedFcn = createCallbackFcn(app, @RecalculateConvergenceButton_2Pushed, true);
            app.RecalculateConvergenceButton_2.Text = 'Re-calculate (two spheres)';
            app.RecalculateConvergenceButton_3 = uibutton(app.ConvergenceTab, 'push');
            app.RecalculateConvergenceButton_3.ButtonPushedFcn = createCallbackFcn(app, @RecalculateConvergenceButton_3Pushed, true);
            app.RecalculateConvergenceButton_3.Text = 'Re-calculate (randomly distributed isolated sphere)';
            app.UptoxnumberofvoxelperdiametertwospheresEditFieldLabel = uilabel(app.ConvergenceTab);
            app.UptoxnumberofvoxelperdiametertwospheresEditFieldLabel.Text = 'Up to x number of voxel per diameter  (two spheres)';
            app.UptoxnumberofvoxelperdiametertwospheresEditField = uieditfield(app.ConvergenceTab, 'numeric');
            app.UptoxnumberofvoxelperdiametertwospheresEditField.Limits = [9 Inf];
            app.UptoxnumberofvoxelperdiametertwospheresEditField.RoundFractionalValues = 'on';
            app.UptoxnumberofvoxelperdiametertwospheresEditField.Tooltip = {'For diameter and specific surface area.'};
            app.UptoxnumberofvoxelperdiametertwospheresEditField.Value = 100;
            app.Label_30 = uilabel(app.ConvergenceTab);
            app.Label_30.Text = 'Particle diameter and specific surface area (covariance)';
            app.Image8_2 = uiimage(app.ConvergenceTab);
            app.Image8_3 = uiimage(app.ConvergenceTab);
            app.Image8_4 = uiimage(app.ConvergenceTab);
            app.DistancefromcentertocenterindiameterEditFieldLabel = uilabel(app.ConvergenceTab);
            app.DistancefromcentertocenterindiameterEditFieldLabel.Text = 'Distance  from center to center (in diameter)';
            app.DistancefromcentertocenterindiameterEditField = uieditfield(app.ConvergenceTab, 'numeric');
            app.DistancefromcentertocenterindiameterEditField.Limits = [0 1];
            app.DistancefromcentertocenterindiameterEditField.Value = 0.75;
            app.ReferencesTab = uitab(app.TabGroup);
            app.ReferencesTab.Title = 'References';
            app.Instructions_title_19 = uilabel(app.ReferencesTab);
            app.Instructions_title_19.Text = 'References';
            app.Label_7 = uilabel(app.ReferencesTab);
            app.Label_7.Text = 'Below is a selection of peer-reviewed journal articles from the authors, discussing properties calculated with MATBOX for lithium-ion battery materials.';
            app.Label_8 = uilabel(app.ReferencesTab);
            app.Label_8.Text = {'- For a simple connectivity analysis (i.e., isolated, unknown, and connected clusters) see [1]. For a more in-depth connectivity analysis focused on the solid phase'; '(percolation transition, impact of additives, impact on effective solid conductivity) see [6].'; ''; '- For an introduction on particle size (covariance, sphere assumption, and watershed) see [1]. For an in-depth discussion of the different particle size methods'; 'see [3].'; ''; '- For an introduction on tortuosity factor and impact on boundary conditions see [1, 2]. For impact of additives on tortuosity factor and comparison with'; 'experimentally deternined and macroscale fitted values see [2,6]. Have a look at the supplementary material of [2] to see difference between FEniCS (Finite'; 'element method) and MATBOX/Taufactor (Finite difference method). For in-depth discussion on relationships between microstructure and tortuosity see [3].'; ''; '- For specific surface area calculations see [1]. For correlation with porosity and comparison with baseline theoritical relationship see [4].'; ''; '- For impact of voxel size on parameters see [2 supplementary material, and 3].'; ''; '- For an introduction to Representative Volume Element analysis see [1, 5].'};
            app.Label_11 = uilabel(app.ReferencesTab);
            app.Label_11.Text = {'[6] F. L. E. Usseglio-Viretta, A. M. Colclasure, A. R. Dunlop, S. E. Trask, A. Jansen, D. P. Abraham, M-T F. Rodrigues, E. J. Dufek, T. Tanim, P. Chinnam, Y. Ha'; 'and K. Smith,  Carbon-Binder Weight Loading Optimization for Improved Lithium-Ion Battery Rate Capability, Journal of the Electrochemical Society, 2022'};
            app.Hyperlink_2 = uihyperlink(app.ReferencesTab);
            app.Hyperlink_2.URL = 'https://doi.org/10.1149/1945-7111/ac7ef9';
            app.Hyperlink_2.Text = 'https://doi.org/10.1149/1945-7111/ac7ef9';
            app.Label_15 = uilabel(app.ReferencesTab);
            app.Label_15.Text = {'[3] F. L. E. Usseglio-Viretta, D. P. Finegan, A. Colclasure, T. M. M. Heenan, D. Abraham, P. Shearing, and K. Smith, Quantitative Relationships Between Pore'; 'Tortuosity, Pore Topology, and Solid Particle Morphology Using a Novel Discrete Particle Size Algorithm, Journal of the Electrochemical Society, 167, 2020'};
            app.Hyperlink_4 = uihyperlink(app.ReferencesTab);
            app.Hyperlink_4.URL = 'https://doi.org/10.1149/1945-7111/ab913b';
            app.Hyperlink_4.Text = {'https://doi.org/10.1149/1945-7111/ab913b'; ''};
            app.Label_17 = uilabel(app.ReferencesTab);
            app.Label_17.Text = {'[2] F. L. E. Usseglio-Viretta, A. Colclasure, A. N. Mistry, K. P. Y. Claver, F. Pouraghajan, D. P. Finegan, T. M. M. Heenan, D. Abraham, P. P. Mukherjee, D. Wheeler,'; 'P. Shearing, S. J. Cooper, and K. Smith, Resolving the Discrepancy in Tortuosity Factor Estimation for Li-Ion Battery Electrodes through Micro-Macro Modeling'; 'and Experiment, Journal of the electrochemical Society, 165, 2018'};
            app.Hyperlink_5 = uihyperlink(app.ReferencesTab);
            app.Hyperlink_5.URL = 'https://doi.org/10.1149/2.0731814jes';
            app.Hyperlink_5.Text = 'https://doi.org/10.1149/2.0731814jes';
            app.FLEUsseglioVirettaandKSmithLabel = uilabel(app.ReferencesTab);
            app.FLEUsseglioVirettaandKSmithLabel.Text = '[1] F. L. E. Usseglio-Viretta and K. Smith, Quantitative Microstructure Characterization of a NMC Electrode, ECS Transactions, 77, 2017';
            app.Hyperlink_6 = uihyperlink(app.ReferencesTab);
            app.Hyperlink_6.URL = 'https://doi.org/10.1149/07711.1095ecst';
            app.Hyperlink_6.Text = 'https://doi.org/10.1149/07711.1095ecst';
            app.DCRobertsonLFloLabel = uilabel(app.ReferencesTab);
            app.DCRobertsonLFloLabel.Text = {'[4] D. C. Robertson, L. Flores, A. R. Dunlop, S. E. Trask, F. L. E. Usseglio-Viretta, A. M. Colclasure, Z. Yang, and I. Bloom, Effect of Anode Porosity and'; 'Temperature on the Performance and Lithium Plating During Fast-Charging of Lithium-Ion Cells, Energy Technology, 2020'};
            app.Hyperlink_7 = uihyperlink(app.ReferencesTab);
            app.Hyperlink_7.URL = 'https://doi.org/10.1002/ente.202000666';
            app.Hyperlink_7.Text = 'https://doi.org/10.1002/ente.202000666';
            app.DCRobertsonLFloLabel_2 = uilabel(app.ReferencesTab);

            app.DCRobertsonLFloLabel_2.Text = {'[5] H. Xu, F. L.E. Usseglio-Viretta, S. Kench, S. J. Cooper, and D. P. Finegan, Microstructure reconstruction of battery polymer separators by fusing 2D and 3D'; 'image data for transport property analysis, Journal of Power Sources, 480, 2020'};
            app.Hyperlink_8 = uihyperlink(app.ReferencesTab);
            app.Hyperlink_8.URL = 'https://doi.org/10.1016/j.jpowsour.2020.229101';
            app.Hyperlink_8.Text = 'https://doi.org/10.1016/j.jpowsour.2020.229101';
            app.HowtoquoteTab = uitab(app.TabGroup);
            app.HowtoquoteTab.Title = 'How to quote';
            app.Instructions_generation = uilabel(app.HowtoquoteTab);
            app.Instructions_generation.Text = 'How to quote';
            app.Label_9 = uilabel(app.HowtoquoteTab);
            app.Label_9.Text = 'If you use this algorithm or report results produced thanks to this algorithm, please quote it as follow:';
            app.Label_10 = uilabel(app.HowtoquoteTab);
            app.Label_10.Text = {'F. L. E. Usseglio-Viretta, P. Patel, E. Bernhardt, A Mistry, P. P. Mukherjee, J. Allen, S. J. Cooper, J. Laurencin, and K. Smith'; 'MATBOX: An Open-source Microstructure Analysis Toolbox for microstructure generation, segmentation, characterization, visualization, correlation, and meshing'; 'SoftwareX, 17, 2022'};
            app.Hyperlink = uihyperlink(app.HowtoquoteTab);
            app.Hyperlink.URL = 'https://doi.org/10.1016/j.softx.2021.100915';
            app.Hyperlink.Text = 'https://doi.org/10.1016/j.softx.2021.100915';
            app.Label_12 = uilabel(app.HowtoquoteTab);
            app.Label_12.Text = 'If you calculate tortuosity factor, please ALSO quote:';
            app.Label_13 = uilabel(app.HowtoquoteTab);
            app.Label_13.Text = {'S. J. Cooper, A. Bertei, P. R. Shearing, J. A. Kilner, and N. P. Brandon, TauFactor: An open-source application for calculating tortuosity factors from tomographic'; 'data, SoftwareX, 5, 2016'};
            app.Hyperlink_3 = uihyperlink(app.HowtoquoteTab);
            app.Hyperlink_3.URL = 'https://doi.org/10.1016/j.softx.2016.09.002';
            app.Hyperlink_3.Text = 'https://doi.org/10.1016/j.softx.2016.09.002';
            app.HelpTab = uitab(app.TabGroup);
            app.HelpTab.Title = 'Help';
            app.Instructions_title_22 = uilabel(app.HelpTab);
            app.Instructions_title_22.Text = 'Help';
            app.OpendocumentationButton = uibutton(app.HelpTab, 'push');
            app.OpendocumentationButton.ButtonPushedFcn = createCallbackFcn(app, @OpendocumentationButtonPushed, true);
            app.OpendocumentationButton.Text = 'Open documentation';
            app.Label_21 = uilabel(app.HelpTab);
            app.Label_21.Text = '- Please read the documentation for detailed instructions.';
            app.Label_22 = uilabel(app.HelpTab);
            app.Label_22.Text = '- You can also read the MATBOX journal article.';
            app.Label_23 = uilabel(app.HelpTab);
            app.Label_23.Text = 'In addition, you can find a selection of journal articles that are using MATBOX in the "References" tab.';
            app.Label_24 = uilabel(app.HelpTab);
            app.Label_24.Text = '- If you run into problems, please read the open issues listed in the GitHub webpage. If your problem is not listed, please open a new issue.';
            app.Hyperlink_9 = uihyperlink(app.HelpTab);
            app.Hyperlink_9.URL = 'https://doi.org/10.1016/j.softx.2021.100915';
            app.Hyperlink_9.Text = 'https://doi.org/10.1016/j.softx.2021.100915';
            app.Hyperlink_10 = uihyperlink(app.HelpTab);
            app.Hyperlink_10.URL = 'https://github.com/NREL/MATBOX_Microstructure_analysis_toolbox/issues';
            app.Hyperlink_10.Text = 'https://github.com/NREL/MATBOX_Microstructure_analysis_toolbox/issues';
            app.AboutTab = uitab(app.TabGroup);
            app.AboutTab.Title = 'About';
            app.Instructions_title_23 = uilabel(app.AboutTab);
            app.Instructions_title_23.Text = 'About';
            app.Label_26 = uilabel(app.AboutTab);
            app.Label_26.Text = {'National Renewable Energy Laboratory (NREL) developed the Microstructure Analysis Toolbox (MATBOX) to analyze electrode microstructures for lithium-ion'; '(Li-ion) batteries and other 3D heterogeneous materials.'};
            app.NREL_logo = uiimage(app.AboutTab);
            app.NREL_logo.ImageClickedFcn = createCallbackFcn(app, @NREL_logoImageClicked, true);
            app.NREL_logo.ImageSource = 'logo_NREL.png';
            app.Hyperlink_11 = uihyperlink(app.AboutTab);
            app.Hyperlink_11.URL = 'https://www.nrel.gov/transportation/matbox.html';
            app.Hyperlink_11.Text = 'MATBOX official webpage';
            app.Hyperlink_12 = uihyperlink(app.AboutTab);
            app.Hyperlink_12.URL = 'https://github.com/NREL/MATBOX_Microstructure_analysis_toolbox';
            app.Hyperlink_12.Text = 'MATBOX GitHub repository';
            app.MATBOXinstalledversionLabel = uilabel(app.AboutTab);
            app.MATBOXinstalledversionLabel.Text = 'MATBOX installed version:';
            app.OnlineversioncheckLabel = uilabel(app.AboutTab);
            app.OnlineversioncheckLabel.Text = 'Online version check';
            app.Localversion = uilabel(app.AboutTab);
            app.Localversion.Text = '1.1 February 2023';
            % Show the figure after all components are created
            app.MicrostructurecharacterizationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Microstructure_characterization

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MicrostructurecharacterizationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MicrostructurecharacterizationUIFigure)
        end
    end
end