classdef Microstructure_characterization2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MicrostructurecharacterizationUIFigure  matlab.ui.Figure
        SurfaceareaGradientmethodseemstobeeasytoimplementlookathttpsiopscienceioporgarticle10114919457111ad9a07Label  matlab.ui.control.Label
        Sp1223labellabellssgcombinedliquidsolidgas1llabelcombinedsolidliquidexamplefortwopixelAandBsolidAliquidBliquidAsolidB1npAnpBwetBnpAwetA1npBDonotconsiderpercolationyetLabel  matlab.ui.control.Label
        NextversionindicateforeachphaseiftheyareeconductorionconductorgazconductorLabel  matlab.ui.control.Label
        VolumefractionifwettingisheterogeneousplotdistributionandpositonalongaxisLabel  matlab.ui.control.Label
        TortuosityifheterogeneousplotdistributionandpositonalongaxisLabel  matlab.ui.control.Label
        SetvolumefractionerrorsmacrotagrunthenaddotherpropertiesLabel  matlab.ui.control.Label
        Main_TabGroup                   matlab.ui.container.TabGroup
        InstructionsTab                 matlab.ui.container.Tab
        Instructions_GridLayout         matlab.ui.container.GridLayout
        OnceallparametersLabel          matlab.ui.control.Label
        RunLabel                        matlab.ui.control.Label
        MacroLabel                      matlab.ui.control.Label
        LastlytostartthecalculationsgototheRunTabLabel  matlab.ui.control.Label
        IfyouareusingamacroLabel        matlab.ui.control.Label
        HereyoucanchoosehowtoLabel      matlab.ui.control.Label
        FilesLabel                      matlab.ui.control.Label
        Label_42                        matlab.ui.control.Label
        FormatLabel                     matlab.ui.control.Label
        FieldofviewFOVLabel             matlab.ui.control.Label
        Label_41                        matlab.ui.control.Label
        RepresentativityLabel           matlab.ui.control.Label
        ImageresolutionLabel            matlab.ui.control.Label
        Label_40                        matlab.ui.control.Label
        Label_39                        matlab.ui.control.Label
        Label_38                        matlab.ui.control.Label
        InterfaceLabel                  matlab.ui.control.Label
        ParticleLabel                   matlab.ui.control.Label
        TheseareparametersthatcanonlyberelatedtoaphaseLabel  matlab.ui.control.Label
        VolumeLabel                     matlab.ui.control.Label
        MicrostructureparametersaregroupedintothreemaincategoriesLabel  matlab.ui.control.Label
        ThismoduleLabel                 matlab.ui.control.Label
        CharacterizationmoduleLabel     matlab.ui.control.Label
        Restart                         matlab.ui.control.Button
        FormatTab                       matlab.ui.container.Tab
        Format_GridLayout               matlab.ui.container.GridLayout
        FigureformatPanel               matlab.ui.container.Panel
        GridLayout9                     matlab.ui.container.GridLayout
        TilelayoutLabel                 matlab.ui.control.Label
        TilespacingDropDown             matlab.ui.control.DropDown
        TilespacingDropDownLabel        matlab.ui.control.Label
        PaddingDropDown_2               matlab.ui.control.DropDown
        PaddingDropDown_2Label          matlab.ui.control.Label
        IncludefilenameinfiguretitleCheckBox  matlab.ui.control.CheckBox
        GridDropDown                    matlab.ui.control.DropDown
        GridDropDownLabel               matlab.ui.control.Label
        LinewidthEditField              matlab.ui.control.NumericEditField
        LinewidthEditFieldLabel         matlab.ui.control.Label
        SgtitlefontsizeEditField        matlab.ui.control.NumericEditField
        SgtitlefontsizeEditFieldLabel   matlab.ui.control.Label
        TitlefontsizeEditField          matlab.ui.control.NumericEditField
        TitlefontsizeEditFieldLabel     matlab.ui.control.Label
        LegendfontsizeEditField         matlab.ui.control.NumericEditField
        LegendfontsizeEditFieldLabel    matlab.ui.control.Label
        AxeFontsizeEditField            matlab.ui.control.NumericEditField
        AxeFontsizeEditFieldLabel       matlab.ui.control.Label
        FontnameDropDown                matlab.ui.control.DropDown
        FontnameDropDownLabel           matlab.ui.control.Label
        FontoptionsLabel                matlab.ui.control.Label
        SavingoptionsPanel              matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        FiguredimensionsaremodifiedforthesavingsothatimageswillhaveaconsistentsizewhateveristhewindowsizeLabel  matlab.ui.control.Label
        PaddingDropDown                 matlab.ui.control.DropDown
        PaddingDropDownLabel            matlab.ui.control.Label
        FigureheightEditField           matlab.ui.control.NumericEditField
        FigureheightEditFieldLabel      matlab.ui.control.Label
        FigureheightDropDown            matlab.ui.control.DropDown
        FiguresizeLabel_2               matlab.ui.control.Label
        FigurefileformatLabel           matlab.ui.control.Label
        InputfilesLabel                 matlab.ui.control.Label
        ClosefigureautomaticallyoncesavedCheckBox  matlab.ui.control.CheckBox
        SavefiguresinfollowingfileformatCheckBox  matlab.ui.control.CheckBox
        AllowoverwritefigurefilesCheckBox  matlab.ui.control.CheckBox
        DPIresolutionEditField          matlab.ui.control.NumericEditField
        DPIresolutionEditFieldLabel     matlab.ui.control.Label
        svgscalablevectorgraphicsCheckBox  matlab.ui.control.CheckBox
        pngCheckBox                     matlab.ui.control.CheckBox
        figCheckBox                     matlab.ui.control.CheckBox
        SavingtiffilesintheresultfolderwillhelpyoukeepthingsorganizedasinputsandresultswillbelocatedunderthesamerootfolderbutitrequiresmorediskspaceLabel  matlab.ui.control.Label
        Savemodifiedtiffiles_CheckBox   matlab.ui.control.CheckBox
        Saveimportedtiffiles_CheckBox   matlab.ui.control.CheckBox
        ColormapoptionsLabel            matlab.ui.control.Label
        DiameterbackgroundcolormapDropDown  matlab.ui.control.DropDown
        DiameterbackgroundcolormapDropDownLabel  matlab.ui.control.Label
        DiametercolormapDropDown        matlab.ui.control.DropDown
        DiametercolormapDropDownLabel   matlab.ui.control.Label
        ThismodulecreatesalotoffiguresYoucanselectformatoptionsLabel  matlab.ui.control.Label
        FilesTab                        matlab.ui.container.Tab
        FilesTabGroup                   matlab.ui.container.TabGroup
        ImportTab                       matlab.ui.container.Tab
        GridLayout_import               matlab.ui.container.GridLayout
        BulkpropertiesLabel             matlab.ui.control.Label
        ForporeLabel                    matlab.ui.control.Label
        LowerScale_SolidTransport_DropDown  matlab.ui.control.DropDown
        forsolidtransportDropDownLabel  matlab.ui.control.Label
        BulkConductivity_file_Label     matlab.ui.control.Label
        ImportsolidconductivityButton   matlab.ui.control.Button
        SelectscaleLabel                matlab.ui.control.Label
        Nanoporosity_file_Label         matlab.ui.control.Label
        LowerScale_PoreTransport_DropDown  matlab.ui.control.DropDown
        PartialwettingCheckBox          matlab.ui.control.CheckBox
        Airsaturation_file_Label        matlab.ui.control.Label
        Airsaturation_uniform_DropDown  matlab.ui.control.DropDown
        AirSaturation_UITable           matlab.ui.control.Table
        LowerscaleTransport_UITable     matlab.ui.control.Table
        Airsaturation_terminology_DropDown  matlab.ui.control.DropDown
        ImportAirSaturation_Button      matlab.ui.control.Button
        Bulkdiffusivity_file_Label      matlab.ui.control.Label
        ImportporediffusivityButton     matlab.ui.control.Button
        ImportnanoporosityButton        matlab.ui.control.Button
        LowerScale_Nanoporosity_DropDown  matlab.ui.control.DropDown
        ThereisabackgroundCheckBox      matlab.ui.control.CheckBox
        ItwillresetallparametersLabel   matlab.ui.control.Label
        PhasecolornameInstance_UITable  matlab.ui.control.Table
        ROI_Label                       matlab.ui.control.Label
        Scaledvoxelsize_Label           matlab.ui.control.Label
        Import_indication               matlab.ui.control.Label
        Import_SavesettingsButton       matlab.ui.control.Button
        SaveimportsettingstocontinueLabel  matlab.ui.control.Label
        VolumefolderEditField           matlab.ui.control.EditField
        VolumefolderEditFieldLabel      matlab.ui.control.Label
        Mainfolder_Label                matlab.ui.control.Label
        SelectmainfolderButton          matlab.ui.control.Button
        SetsavelocationLabel            matlab.ui.control.Label
        OptionalPanel                   matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        Info_UITable                    matlab.ui.control.Table
        VolumeinformationLabel          matlab.ui.control.Label
        visualize_resolution_withDropDown  matlab.ui.control.DropDown
        visualized_ROI_withDropDown     matlab.ui.control.DropDown
        withDropDown_3Label             matlab.ui.control.Label
        PlotvolumefractionsButton       matlab.ui.control.Button
        VisualizevolumeButton           matlab.ui.control.Button
        TohelpselectingROIandscalingparametersLabel  matlab.ui.control.Label
        FilesmustsharesamesizeLabel     matlab.ui.control.Label
        PhasecolornameSemantic_UITable  matlab.ui.control.Table
        Phasenamecolor_DropDown         matlab.ui.control.DropDown
        AssignlabelcolorandnameLabel    matlab.ui.control.Label
        ScalingfactorEditField          matlab.ui.control.NumericEditField
        ScalingfactorEditFieldLabel     matlab.ui.control.Label
        VoxelsizeUnit_EditField         matlab.ui.control.EditField
        VoxelsizeEditField              matlab.ui.control.NumericEditField
        VoxelsizeEditFieldLabel         matlab.ui.control.Label
        SetvoxelsizeandscalingfactorLabel  matlab.ui.control.Label
        ROI_UITable                     matlab.ui.control.Table
        SetRegionofInterestROILabel     matlab.ui.control.Label
        Volumenumber_Label              matlab.ui.control.Label
        Instance_NofileimportedLabel    matlab.ui.control.Label
        Semantic_NofileimportedLabel    matlab.ui.control.Label
        Label_instancedescription       matlab.ui.control.Label
        Label_semanticdescription       matlab.ui.control.Label
        ClicktoimportinstancesegmentationButton  matlab.ui.control.Button
        ClicktoimportsemanticsegmentationButton  matlab.ui.control.Button
        ImportfilesLabel                matlab.ui.control.Label
        NomacroloadedLabel              matlab.ui.control.Label
        ClicktoimportmacroButton        matlab.ui.control.Button
        ModeDropDown                    matlab.ui.control.DropDown
        SelectanalyismodeLabel          matlab.ui.control.Label
        SegmentationtypeDropDown        matlab.ui.control.DropDown
        SelectsegmentationtypeofvolumestoanalyseLabel  matlab.ui.control.Label
        ImportedTab                     matlab.ui.container.Tab
        GridLayout_imported             matlab.ui.container.GridLayout
        PhaseandInstancecorrespondtotheimportednotnecessarlytheROILabel  matlab.ui.control.Label
        SearchtagButton                 matlab.ui.control.Button
        Label_6                         matlab.ui.control.Label
        itwillinitializeorresetthesubsequenttabsLabel  matlab.ui.control.Label
        Imported_SavesettingsButton     matlab.ui.control.Button
        Label_5                         matlab.ui.control.Label
        Setcalculationorderto0nottoanalyseafileLabel  matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        TagLabel                        matlab.ui.control.Label
        VisualizationLabel              matlab.ui.control.Label
        TagunitEditField                matlab.ui.control.EditField
        TagunitEditFieldLabel           matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Imported_UITable                matlab.ui.control.Table
        VisualizeROI_DropDown           matlab.ui.control.DropDown
        VisualizeallfilesButton         matlab.ui.control.Button
        VolumeTab                       matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        VolumefractionTab               matlab.ui.container.Tab
        Volumefraction_GridLayout       matlab.ui.container.GridLayout
        vf_Direction3_CheckBox          matlab.ui.control.CheckBox
        vf_Direction2_CheckBox          matlab.ui.control.CheckBox
        vf_Direction1_CheckBox          matlab.ui.control.CheckBox
        PlotresultsalongLabel           matlab.ui.control.Label
        Applyfirstvolumeselections_volfraction_Button  matlab.ui.control.Button
        SelectUnselectall_volfraction_Button  matlab.ui.control.Button
        BackgrounddescriptionLabel      matlab.ui.control.Label
        Volumefraction_Instance_UITable  matlab.ui.control.Table
        Volumefraction_Semantic_UITable  matlab.ui.control.Table
        VolumefractionInstructions_Label  matlab.ui.control.Label
        TortuosityfactorTab             matlab.ui.container.Tab
        TortuosityFactor_GridLayout     matlab.ui.container.GridLayout
        Direction3EditField             matlab.ui.control.NumericEditField
        Direction3EditFieldLabel        matlab.ui.control.Label
        Direction2EditField             matlab.ui.control.NumericEditField
        Direction2EditFieldLabel        matlab.ui.control.Label
        Direction1EditField             matlab.ui.control.NumericEditField
        Direction1EditFieldLabel        matlab.ui.control.Label
        tau_3Label                      matlab.ui.control.Label
        tau_2Label                      matlab.ui.control.Label
        tau_1Label                      matlab.ui.control.Label
        alongLabel                      matlab.ui.control.Label
        Tau_d3_CheckBox                 matlab.ui.control.CheckBox
        Tau_d2_CheckBox                 matlab.ui.control.CheckBox
        Tau_d1_CheckBox                 matlab.ui.control.CheckBox
        CalculateLabel                  matlab.ui.control.Label
        Tau_SelectUnselectall_volfraction_Button  matlab.ui.control.Button
        Tau_Applyfirstvolumeselections_volfraction_Button  matlab.ui.control.Button
        BackgroundoptionifanyPanel      matlab.ui.container.Panel
        TauBackground_GridLayout        matlab.ui.container.GridLayout
        MaxiterEditField                matlab.ui.control.NumericEditField
        MaxiterEditFieldLabel           matlab.ui.control.Label
        SelectmethodDropDown            matlab.ui.control.DropDown
        SelectmethodLabel               matlab.ui.control.Label
        ToleranceEditField              matlab.ui.control.NumericEditField
        ToleranceEditFieldLabel         matlab.ui.control.Label
        Hyperlink5                      matlab.ui.control.Hyperlink
        TauFactor2isusedPleasequoteLabel  matlab.ui.control.Label
        Tau_Instance_UITable            matlab.ui.control.Table
        Tau_Semantic_UITable            matlab.ui.control.Table
        TauInstructions_Label           matlab.ui.control.Label
        ConnectivityTab                 matlab.ui.container.Tab
        ItwillbereintroducedwithnextupdateInthemeantimethesegmentationmodulecancalculateconnectivityLabel  matlab.ui.control.Label
        FractaldimensionTab             matlab.ui.container.Tab
        ItwillbeintroducedwithnextupdateBoxingmethodLabel  matlab.ui.control.Label
        Volume_Numerical_error_Tab      matlab.ui.container.Tab
        BoundaryconditionTauFactoruseDirichletDiricheltwhichmeansyouhavetheboundforFinitedifferencebasedcalculationsoorder1ErrorcanbesignificantwithimageresolutionseeUsseglioarticleTobeinsensitivewithimageresoltuionFEwithhighorderelementworkswellSmallfieldofviewedgeeffectsbiasthecalculationlesspathavailabletortuositywillincreaseLabel  matlab.ui.control.Label
        ParticleTab                     matlab.ui.container.Tab
        TabGroup4                       matlab.ui.container.TabGroup
        DiameterTab                     matlab.ui.container.Tab
        TabGroup7                       matlab.ui.container.TabGroup
        ContinuousparticlesizedistributionTab  matlab.ui.container.Tab
        Cpsd_GridLayout                 matlab.ui.container.GridLayout
        Cpsd_Usesymmetry_CheckBox       matlab.ui.control.CheckBox
        MethodprosandconsLabel          matlab.ui.control.Label
        MethoddescriptionLabel          matlab.ui.control.Label
        Cpsd_limitations_Image          matlab.ui.control.Image
        Cpsd_method_Image               matlab.ui.control.Image
        Cpsd_AllphasesCheckBox          matlab.ui.control.CheckBox
        Cpsd_ExcludetruncatedparticlesCheckBox  matlab.ui.control.CheckBox
        Cpsd_Instance_UITable           matlab.ui.control.Table
        Cpsd_Semantic_UITable           matlab.ui.control.Table
        SelectphasesforwhichyouwanttocalculatesizedistributionLabel  matlab.ui.control.Label
        VolumebasedTab                  matlab.ui.container.Tab
        MetricsperparticleTab           matlab.ui.container.Tab
        MetricsPerParticle_GridLayout   matlab.ui.container.GridLayout
        WeightMetrics_withvolume_CheckBox  matlab.ui.control.CheckBox
        requiresinstanceorsemanticandinstancesegmentationLabel  matlab.ui.control.Label
        ExcludetruncatedparticlesCheckBox  matlab.ui.control.CheckBox
        MetricsPerParticle_UITable      matlab.ui.control.Table
        VolumefractionInstructions_Label_2  matlab.ui.control.Label
        InterfaceTab                    matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        SpecificsurfaceareaTab          matlab.ui.container.Tab
        Volumefraction_GridLayout_2     matlab.ui.container.GridLayout
        CorrectivefactorEditField_2     matlab.ui.control.NumericEditField
        CorrectivefactorEditField_2Label  matlab.ui.control.Label
        IsgradientmethodsamewithcovarianceLabel  matlab.ui.control.Label
        GradientmethodCheckBox          matlab.ui.control.CheckBox
        SumCheckBox                     matlab.ui.control.CheckBox
        CroftonformulaCheckBox          matlab.ui.control.CheckBox
        CovariancedoonlyfirststepCheckBox  matlab.ui.control.CheckBox
        Volumefraction_Instance_UITable_2  matlab.ui.control.Table
        Volumefraction_Semantic_UITable_2  matlab.ui.control.Table
        Volumefractions_AllphasesCheckBox_2  matlab.ui.control.CheckBox
        VolumefractionInstructions_Label_3  matlab.ui.control.Label
        SpecificinterfaceareaTab        matlab.ui.container.Tab
        SpInterface_GridLayout          matlab.ui.container.GridLayout
        BackgrounddescriptionLabel_2    matlab.ui.control.Label
        CorrectivefactorEditField       matlab.ui.control.NumericEditField
        CorrectivefactorEditFieldLabel  matlab.ui.control.Label
        Interface_Instructions_Label    matlab.ui.control.Label
        TriplephaseboundarylengthdensityTab  matlab.ui.container.Tab
        Sp_Numerical_error              matlab.ui.container.Tab
        TabGroup8                       matlab.ui.container.TabGroup
        VoxelrepresentationTab          matlab.ui.container.Tab
        GridLayout10                    matlab.ui.container.GridLayout
        Hyperlink9                      matlab.ui.control.Hyperlink
        ForTPBLacorrectivefactorof11455hasbeenproposedthereLabel  matlab.ui.control.Label
        In2Dtheperimetercalculatednumericallyfromapixelizedrepresentationmustbemultipliedbypi4forforanidealdisc11Label  matlab.ui.control.Label
        Hyperlink8                      matlab.ui.control.Hyperlink
        Thedemonstrationofthe23correctivefactorcanbefoundthereLabel  matlab.ui.control.Label
        LeftAreaoverestimationillustratedonavoxelizedspherethesphereareais6timestheareaofadiscprojectedalonganaxisRightthecorrectivefactoranalyticallycalculatedasafunctionoftheparticleelongationLabel  matlab.ui.control.Label
        Image4                          matlab.ui.control.Image
        Onesourceofnumericalerrorforinterfacecalculationsboth2Dand1DistheoverestimationinducedbythemicrostructurevoxelrepresentationThisoverestimationisintrinsictothevoxelrepresentationandpersistsevenwithaninfiniteimageresolutionLabel  matlab.ui.control.Label
        Sp_correctivefactor_UIAxes      matlab.ui.control.UIAxes
        ImageresolutionTab_2            matlab.ui.container.Tab
        Asecondsourceoferrorforinterfacecalculationsboth2Dand1DisthelimitedimageresolutionTwocasesmustbeconsideredLabel  matlab.ui.control.Label
        GridLayout10_2                  matlab.ui.container.GridLayout
          matlab.ui.control.Label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
        Label_43                        matlab.ui.control.Label
        SurfaceandlinearpropertiescanexhibitafractalbehaviorasmoredetailsarerevealedwithfinerimageresolutionsuntilacutoffresolutionisreachedabovewhichthefractalbehaviorcollapsesforrealmaterialsSeeErrorsImageresolutionforadditionalreferencesandtoseehowtocalculatethefractaldimensionLabel  matlab.ui.control.Label
        RealgeometrieswithsurfaceroughnessLabel  matlab.ui.control.Label
        Image5                          matlab.ui.control.Image
        InthiscasetheactualvaluecanbeobtainedbyincreasingtheimageresolutionYoujustneedtobecarefulforthedefinitionofthediameterforanoddorevennumberofpixelsalongtheparticlediameterLabel  matlab.ui.control.Label
        IdealgeometrieswithoutsurfaceroughnessLabel  matlab.ui.control.Label
        ErrorsTab                       matlab.ui.container.Tab
        TabGroup5                       matlab.ui.container.TabGroup
        ImageresolutionTab              matlab.ui.container.Tab
        Imageresolution_GridLayout      matlab.ui.container.GridLayout
        LogpmTFlogvLabel                matlab.ui.control.Label
        Scalingfactorexample1152255writeasitiswiththecommasLabel  matlab.ui.control.Label
        ExtrapolationchoicenonelinearquadraticcubicLabel  matlab.ui.control.Label
        TocompareafractalpropertyF0twoormorevolumesLabel  matlab.ui.control.Label
        Hyperlink3_2                    matlab.ui.control.Hyperlink
        Hyperlink4_2                    matlab.ui.control.Hyperlink
        Label_29                        matlab.ui.control.Label
        andrevisitedformicrostructureLabel_2  matlab.ui.control.Label
        TheoriginalconceptLabel_2       matlab.ui.control.Label
        Label_28                        matlab.ui.control.Label
        Voxelsize_Applytoallvolumes_Button  matlab.ui.control.Button
        Voxelsize_UITable               matlab.ui.control.Table
        Voxelsize_VolumeselectionDropDown  matlab.ui.control.DropDown
        VolumeselectionDropDownLabel_2  matlab.ui.control.Label
        SelectedmicrostructurepropertiesarecalculatedattheLabel  matlab.ui.control.Label
        RepresentativityTab             matlab.ui.container.Tab
        TabGroup6                       matlab.ui.container.TabGroup
        RVEanalysis_Tab                 matlab.ui.container.Tab
        Set_RRVE_GridLayout             matlab.ui.container.GridLayout
        ThresholdDropDown               matlab.ui.control.DropDown
        ThresholdDropDownLabel          matlab.ui.control.Label
        OncedoneclicktoaddthisrepresentativityanalysisLabel  matlab.ui.control.Label
        RVE_minsize_EditField           matlab.ui.control.NumericEditField
        DonotcutsubvolumesmallerthanCheckBox  matlab.ui.control.CheckBox
        SetyouroptionsbelowandsavethemLabel  matlab.ui.control.Label
        RVE_AddrepresentativityanalysisButton  matlab.ui.control.Button
        RVE_ForpropertyDropDown         matlab.ui.control.DropDown
        ForpropertyDropDownLabel        matlab.ui.control.Label
        RVE_ForvolumeDropDown           matlab.ui.control.DropDown
        ForvolumeDropDownLabel          matlab.ui.control.Label
        RVEname_label                   matlab.ui.control.Label
        RVE_SubvolumeaspectratioEditField  matlab.ui.control.EditField
        SubvolumeaspectratioEditFieldLabel  matlab.ui.control.Label
        RVE_2subs_CheckBox              matlab.ui.control.CheckBox
        RelativestandarddeviationthresholdsEditField  matlab.ui.control.EditField
        RVE_ConstantlengthsDropDown     matlab.ui.control.DropDown
        ConstantlengthsDropDownLabel    matlab.ui.control.Label
        RVEdivider_Label                matlab.ui.control.Label
        RVE_DividerEditField            matlab.ui.control.EditField
        DividersLabel                   matlab.ui.control.Label
        RVE_ChoiceDropDown              matlab.ui.control.DropDown
        ChoiceDropDownLabel             matlab.ui.control.Label
        Hyperlink3_3                    matlab.ui.control.Hyperlink
        Label_35                        matlab.ui.control.Label
        Label_34                        matlab.ui.control.Label
        Image3                          matlab.ui.control.Image
        HowtointerpretateRVEresultsButton  matlab.ui.control.Button
        Hyperlink3                      matlab.ui.control.Hyperlink
        Label_33                        matlab.ui.control.Label
        ConvergenceanalysisPanel        matlab.ui.container.Panel
        RVEconvergence_GridLayout       matlab.ui.container.GridLayout
        oftheinitialFOVLabel            matlab.ui.control.Label
        RVEconvCropuntilnewFOVisEditField  matlab.ui.control.NumericEditField
        CropuntilnewFOVisEditFieldLabel  matlab.ui.control.Label
        RVEconvergence_xEditField       matlab.ui.control.NumericEditField
        xLabel                          matlab.ui.control.Label
        andhowmuchLabel                 matlab.ui.control.Label
        AteachstepDropDown              matlab.ui.control.DropDown
        AteachstepDropDownLabel         matlab.ui.control.Label
        HowtocroptheinitialFOVLabel     matlab.ui.control.Label
        RVE_thatisDropDown              matlab.ui.control.DropDown
        thatisDropDownLabel             matlab.ui.control.Label
        RVEconvergence_CropDropDown     matlab.ui.control.DropDown
        CropDropDownLabel               matlab.ui.control.Label
        RVEconvergencewithfieldofviewCheckBox  matlab.ui.control.CheckBox
        ConvergenceAnalysis_Tab         matlab.ui.container.Tab
        Set_Rconvergence_GridLayout     matlab.ui.container.GridLayout
        ThresholdDropDown_2             matlab.ui.control.DropDown
        ThresholdDropDown_2Label        matlab.ui.control.Label
        RelativedifferencethresholdsEditField  matlab.ui.control.EditField
        OneSub_DropDown                 matlab.ui.control.DropDown
        DropDown_2Label                 matlab.ui.control.Label
        SubvolumegrowthEditField        matlab.ui.control.NumericEditField
        SubvolumegrowthEditFieldLabel   matlab.ui.control.Label
        OneSub_Growthrealtiveto_DropDown  matlab.ui.control.DropDown
        oftheinitialvolumeLabel         matlab.ui.control.Label
        SizeofthefirstsubvolumeEditField  matlab.ui.control.NumericEditField
        SizeofthefirstsubvolumeEditFieldLabel  matlab.ui.control.Label
        OneSub_SubvolumeaspectratioEditField  matlab.ui.control.EditField
        SubvolumeaspectratioEditFieldLabel_2  matlab.ui.control.Label
        OneSub_ChoiceDropDown           matlab.ui.control.DropDown
        ChoiceDropDownLabel_2           matlab.ui.control.Label
        OneSub_AddrepresentativityanalysisButton  matlab.ui.control.Button
        OncedoneclicktoaddthisrepresentativityanalysisLabel_2  matlab.ui.control.Label
        OneSub_ForpropertyDropDown      matlab.ui.control.DropDown
        ForvolumeDropDown_2Label_2      matlab.ui.control.Label
        OneSub_ForvolumeDropDown        matlab.ui.control.DropDown
        ForvolumeDropDown_2Label        matlab.ui.control.Label
        SetyouroptionsbelowLabel        matlab.ui.control.Label
        Label_31                        matlab.ui.control.Label
        SeeallanalysesplannedTab        matlab.ui.container.Tab
        Set_Rplanned_GridLayout         matlab.ui.container.GridLayout
        CopyanalysesoptionsPanel        matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        ApplyPERpropertyforALLvolumesButton  matlab.ui.control.Button
        ApplyonALLpropertiesforALLvolumesButton  matlab.ui.control.Button
        ApplyonthispropertyforALLvolumesButton  matlab.ui.control.Button
        ApplyonALLpropertiesforthisvolumeButton  matlab.ui.control.Button
        RemoveallanalysesforthisvolumeButton  matlab.ui.control.Button
        ListAnalysis_UITable            matlab.ui.control.Table
        Allanalyses_ForpropertyDropDown  matlab.ui.control.DropDown
        ForpropertyDropDownLabel_2      matlab.ui.control.Label
        Allanalyses_ForvolumeDropDown   matlab.ui.control.DropDown
        ForvolumeDropDownLabel_2        matlab.ui.control.Label
        MacroTab                        matlab.ui.container.Tab
        Macro_GridLayout                matlab.ui.container.GridLayout
        MacrodescriptionTextArea        matlab.ui.control.TextArea
        MacrodescriptionTextAreaLabel   matlab.ui.control.Label
        SavemacroButton                 matlab.ui.control.Button
        Macrosavedolfer_Label           matlab.ui.control.Label
        SavefolderButton                matlab.ui.control.Button
        MacronameEditField              matlab.ui.control.EditField
        MacronameEditFieldLabel         matlab.ui.control.Label
        YoucansaveoptionsinamacroandthenapplyittoothersvolumesLabel  matlab.ui.control.Label
        RunTab                          matlab.ui.container.Tab
        Run_GridLayout                  matlab.ui.container.GridLayout
        Run_SearchtagCheckBox           matlab.ui.control.CheckBox
        RunStatut_UITable               matlab.ui.control.Table
        Run_button                      matlab.ui.control.Button
        Run_Label                       matlab.ui.control.Label
        Run_Title                       matlab.ui.control.Label
        HelpTab                         matlab.ui.container.Tab
        GridLayout5_3                   matlab.ui.container.GridLayout
        TextArea                        matlab.ui.control.TextArea
        Hyperlink6                      matlab.ui.control.Hyperlink
        Label_37                        matlab.ui.control.Label
        TauFactor2installationinstructionsLabel  matlab.ui.control.Label
        FAQimageexampleslinktotutorialsLabel  matlab.ui.control.Label
        Hyperlink_8                     matlab.ui.control.Hyperlink
        Label_19                        matlab.ui.control.Label
        ReferencesTab                   matlab.ui.container.Tab
        GridLayout5_4                   matlab.ui.container.GridLayout
        Hyperlink7                      matlab.ui.control.Hyperlink
        Hyperlink_14                    matlab.ui.control.Hyperlink
        Hyperlink_13                    matlab.ui.control.Hyperlink
        Hyperlink_12                    matlab.ui.control.Hyperlink
        Hyperlink_11                    matlab.ui.control.Hyperlink
        Hyperlink_10                    matlab.ui.control.Hyperlink
        Hyperlink_9                     matlab.ui.control.Hyperlink
        Label_21                        matlab.ui.control.Label
        Label_20                        matlab.ui.control.Label
        HowtoquoteTab                   matlab.ui.container.Tab
        GridLayout5_2                   matlab.ui.container.GridLayout
        Label_36                        matlab.ui.control.Label
        Hyperlink5_2                    matlab.ui.control.Hyperlink
        Hyperlink_7                     matlab.ui.control.Hyperlink
        Label_18                        matlab.ui.control.Label
        Label_17                        matlab.ui.control.Label
        Hyperlink_6                     matlab.ui.control.Hyperlink
        Label_16                        matlab.ui.control.Label
        Label_15                        matlab.ui.control.Label
        AboutTab                        matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        BSDLicenceLabel                 matlab.ui.control.Label
        NRELSoftwareRecordofInventionSWR2076Label  matlab.ui.control.Label
        ClicktoseethelicenceButton      matlab.ui.control.Button
        Hyperlink2                      matlab.ui.control.Hyperlink
        Label_9                         matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        Onlineversion_Label             matlab.ui.control.Label
        Hyperlink_2                     matlab.ui.control.Hyperlink
        Hyperlink                       matlab.ui.control.Hyperlink
        Image                           matlab.ui.control.Image
        Label_7                         matlab.ui.control.Label
    end

    
    properties (Access = private)
        matboxversion = '2.0b Beta July 2025';

        % Import
        number_volume = [];
        inputvolume = [];

        PathImport = [];
        mainfolder = [];

        FileName = [];

        current_semantic_microstructure = [];
        current_middleslice_semantic = [];
        semantic_fileinfo = [];
        phase_name_semantic_sav = [];
        color_phase_semantic = NaN(1e6,3);
        pathnames_semantic = [];

        current_instance_microstructure = [];
        current_instanceBW_microstructure = [];
        current_middleslice_instance = [];
        instance_fileinfo = [];
        phase_name_instance_sav = [];
        color_phase_instance = NaN(1e6,3);
        pathnames_instance = [];

        nanoporosity_array = [];
        nanoporosity_path = [];
        bulkdiffusivity_array = [];        
        bulkdiffusivity_path = [];
        bulkconductivity_array = [];        
        bulkconductivity_path = [];        
        airsaturation_array = [];        
        airsaturation_path = [];        

        volumeinfo_sav = [];
        direction_name_sav = [];
        ROI = [];

        tags = [];
        order_calculation = [];

        default_voxelsizeanalysis = [];
        voxelidx = [];

        RVE_list = [];
        RVEidx = [];

        table_sem_phaseselection_tau_onescale = [];
        table_sem_phaseselection_tau_dualscale_dbulk = [];
        table_sem_phaseselection_tau_dualscale_epsp = [];
        cname_sem_tau_onescale = [];
        cedit_sem_tau_onescale = [];
        cname_sem_tau_dualscale = [];
        cedit_sem_tau_dualscale = [];   

        table_ins_phaseselection_tau_onescale = [];
        table_ins_phaseselection_tau_dualscale_dbulk = [];
        table_ins_phaseselection_tau_dualscale_epsp = [];
        cname_ins_tau_onescale = [];
        cedit_ins_tau_onescale = [];
        cname_ins_tau_dualscale = [];
        cedit_ins_tau_dualscale = [];           

        savemacro = [];
        macro_pars = [];
        use_macro = [];

        property_resolution_list = [];
        toplogy_dimension = [];
        
        property_RVE_list = [];
    end
    
    methods (Access = private)
                        
        function [] = check_import(app)
            if isempty(app.current_semantic_microstructure) && isempty(app.current_instance_microstructure)
                app.VisualizevolumeButton.Enable = 'off'; app.PlotvolumefractionsButton.Enable='off';
            else
                app.VisualizevolumeButton.Enable = 'on'; app.PlotvolumefractionsButton.Enable='on';
            end
            if (strcmp(app.SegmentationtypeDropDown.Value,'semantic') && ~isempty(app.current_semantic_microstructure)) || ...
               (strcmp(app.SegmentationtypeDropDown.Value,'instance') && ~isempty(app.current_instance_microstructure)) || ...
               (strcmp(app.SegmentationtypeDropDown.Value,'semantic and instance') && ~isempty(app.current_semantic_microstructure) && ~isempty(app.current_instance_microstructure)) ...
               && ~strcmp(app.FilesmustsharesamesizeLabel.Text,'Incompatible dimension!') && ~strcmp(app.FilesmustsharesamesizeLabel.Text,'Incompatible size!')
                app.Import_SavesettingsButton.Enable = 'on';
            else
                app.Import_SavesettingsButton.Enable = 'off';
            end

            if (strcmp(app.SegmentationtypeDropDown.Value,'semantic') || strcmp(app.SegmentationtypeDropDown.Value,'semantic and instance'))
                if strcmp(app.LowerScale_Nanoporosity_DropDown.Value,'Mixed (heterogeneous)')
                    if isempty(app.nanoporosity_array)
                        app.Import_SavesettingsButton.Enable = 'off';
                    end
                end
                if strcmp(app.LowerScale_PoreTransport_DropDown.Value,'Diffusivity D (heterogeneous)')
                    if isempty(app.bulkdiffusivity_array)
                        app.Import_SavesettingsButton.Enable = 'off';
                    end
                end
                if strcmp(app.LowerScale_SolidTransport_DropDown.Value,'Conductivity K (heterogeneous)')
                    if isempty(app.bulkconductivity_array)
                        app.Import_SavesettingsButton.Enable = 'off';
                    end
                end                
                if app.PartialwettingCheckBox.Value && strcmp(app.Airsaturation_uniform_DropDown.Value,'Heterogeneous')
                    if isempty(app.airsaturation_array)
                        app.Import_SavesettingsButton.Enable = 'off';
                    end
                end
            end
        end
        
        function [] = check_dimensions(app)
            if strcmp(app.SegmentationtypeDropDown.Value,'semantic and instance')
                if ~isempty(app.current_semantic_microstructure) && ~isempty(app.current_instance_microstructure)
                    sz_sem = size(app.current_semantic_microstructure);
                    sz_ins = size(app.current_instance_microstructure);
                    dim_sem = length(sz_sem);
                    dim_ins = length(sz_ins);
                    if dim_sem == dim_ins
                        if sum(sz_sem==sz_ins)==dim_sem
                            app.FilesmustsharesamesizeLabel.Text = 'Files must share same size';
                            app.FilesmustsharesamesizeLabel.FontColor = 'k';
                            app.Volumenumber_Label.Text = ['Volume number ' num2str(app.number_volume,'%i')];
                            app.Volumenumber_Label.Visible = 'on';
                        else
                            app.Volumenumber_Label.Visible = 'off';
                            app.FilesmustsharesamesizeLabel.Text = 'Incompatible size!';
                            app.FilesmustsharesamesizeLabel.FontColor = 'r';
                        end
                    else
                        app.Volumenumber_Label.Visible = 'off';
                        app.FilesmustsharesamesizeLabel.Text = 'Incompatible dimension!';
                        app.FilesmustsharesamesizeLabel.FontColor = 'r';
                    end
                else
                    app.Volumenumber_Label.Visible = 'off';
                    app.FilesmustsharesamesizeLabel.Text = 'Files must share same size';
                    app.FilesmustsharesamesizeLabel.FontColor = 'k';
                end
            else
                app.Volumenumber_Label.Visible = 'on';
                app.Volumenumber_Label.Text = ['Volume number ' num2str(app.number_volume,'%i')];
            end
        end        
        
        function [] = fill_ROI_UITable(app,M)
            number_direction = length(size(M));
            direction_number = cell(number_direction,1);
            direction_voxel = size(M)';
            ROI_start = ones(number_direction,1);
            if strcmp(app.ModeDropDown.Value,'Set parameters for each volume independently')
                ROI_end = size(M)';
            else
                ROI_end = ROI_start+1e9;
            end
            ROI_length = ROI_end-ROI_start+1;
            direction_name = cell(number_direction,1);
            for k=1:1:number_direction
                direction_number(k,1)={num2str(k)};
                if ~isempty(app.direction_name_sav) && length(app.direction_name_sav)>=k
                    direction_name(k,1) = app.direction_name_sav(k,1); % Avoid re-writting each time if volumes are same materials
                else
                    direction_name(k,1) = {['Direction ' num2str(k)]};
                end
            end
            app.ROI_UITable.Enable = 'on';
            app.ROI_UITable.Data = [direction_number num2cell(direction_voxel) num2cell(ROI_start) num2cell(ROI_end) num2cell(ROI_length) direction_name];
        end

        function [] = update_volumefractions(app)
            if ~isempty(app.PhasecolornameSemantic_UITable.Data) || ~isempty(app.PhasecolornameInstance_UITable.Data)

                if strcmp(app.ModeDropDown.Value,'Set parameters for each volume independently')
                    ROI = cell2mat(app.ROI_UITable.Data(:,3:4));
                else
                    tmp = app.semantic_fileinfo(1).sz;
                    dimension = length(tmp);
                    ROI = ones(dimension,2);
                    ROI(:,2) = tmp';
                end

                if strcmp(app.Phasenamecolor_DropDown.Value,'semantic segmentation')
                    phase_label = cell2mat(app.PhasecolornameSemantic_UITable.Data(:,5));
                elseif strcmp(app.Phasenamecolor_DropDown.Value,'instance segmentation')
                    phase_label = cell2mat(app.PhasecolornameInstance_UITable.Data(:,5));
                end
                [dimension,~] = size(ROI);
                M = [];
                if ~isempty(app.current_semantic_microstructure) && strcmp(app.Phasenamecolor_DropDown.Value,'semantic segmentation')
                    M = app.current_semantic_microstructure;
                elseif ~isempty(app.current_instanceBW_microstructure) && strcmp(app.Phasenamecolor_DropDown.Value,'instance segmentation')
                    M = app.current_instanceBW_microstructure;
                end

                if dimension == 2
                    M = M(ROI(1,1):ROI(1,2), ROI(2,1):ROI(2,2));
                else
                    M = M(ROI(1,1):ROI(1,2), ROI(2,1):ROI(2,2), ROI(3,1):ROI(3,2));
                end

                p.scaling_factor = app.ScalingfactorEditField.Value;
                if p.scaling_factor ~=1
                    p.label_or_greylevel = 'Label';
                    p.background = 0;
                    [M] = function_scaling(M,p);
                end

                for k=1:1:length(phase_label)
                    if strcmp(app.Phasenamecolor_DropDown.Value,'semantic segmentation')
                        app.PhasecolornameSemantic_UITable.Data(k,2) ={sum(sum(sum( M==phase_label(k) )))/numel(M)};
                    elseif strcmp(app.Phasenamecolor_DropDown.Value,'instance segmentation')
                        app.PhasecolornameInstance_UITable.Data(k,2) ={sum(sum(sum( M==phase_label(k) )))/numel(M)};
                    end
                end

            end
        end
        
        function M = Prepare_visualization(app,M)
            % ROI
            if strcmp(app.visualized_ROI_withDropDown.Value,'region of interest')
                ROI = cell2mat(app.ROI_UITable.Data(:,3:4));
                dimension = length(size(M));
                if strcmp(app.ModeDropDown.Value,'Set parameters for each volume independently')
                    if dimension == 2
                        M = M(ROI(1,1):ROI(1,2), ROI(2,1):ROI(2,2));
                    else
                        M = M(ROI(1,1):ROI(1,2), ROI(2,1):ROI(2,2), ROI(3,1):ROI(3,2));
                    end
                end
            end
            if strcmp(app.visualize_resolution_withDropDown.Value,'after scaling')
                p.scaling_factor = app.ScalingfactorEditField.Value;
                p.label_or_greylevel = 'Label';
                p.background = 0;
                [M] = function_scaling(M,p);
            end
        end
        

        function [] = function_vf_alongthickness(app,M,isbackground,voxel_size,figtitle)
            sz = size(M);
            dimension = length(sz);
            if dimension==2 % Case 2D
                sz=[sz(1) sz(2) 1];
            end
            if strcmp(app.Phasenamecolor_DropDown.Value,'semantic segmentation')
                phase_label = cell2mat(app.PhasecolornameSemantic_UITable.Data(:,5));
                phase_color =  app.color_phase_semantic;
                phase_name = app.PhasecolornameSemantic_UITable.Data(:,4);
            elseif strcmp(app.Phasenamecolor_DropDown.Value,'instance segmentation')
                phase_label = cell2mat(app.PhasecolornameInstance_UITable.Data(:,5));
                phase_color =  app.color_phase_instance;
                phase_name = app.PhasecolornameInstance_UITable.Data(:,4);
            end
            number_phase = length(phase_label);
            direction_name = app.ROI_UITable.Data(:,6); 
            strunit = app.VoxelsizeUnit_EditField.Value;
            if strcmp(strunit,'um') || strcmp(strunit,'micrometer') || strcmp(strunit,'Micrometer') || strcmp(strunit,'micrometers') || strcmp(strunit,'Micrometers')
                axisunit = '\mum';
            else
                axisunit = strunit;
            end

            % Initialize
            for current_direction=1:1:dimension % Loop over all directions
                res(current_direction).volumefraction = zeros(sz(current_direction),number_phase+1); % Initialization
                res(current_direction).volumefraction(:,1) = (1:1:sz(current_direction))*voxel_size; % Axis (position along direction)
            end
            for current_phase = 1:1:number_phase % Loop over all phases
                % Direction 1
                for current_position=1:1:sz(1) % Loop over postion
                    Numbervoxel= sum(sum( M(current_position,:,:)==phase_label(current_phase) ));
                    res(1).volumefraction(current_position,current_phase+1) = Numbervoxel / (sz(2)*sz(3));
                end
                % Direction 2
                for current_position=1:1:sz(2) % Loop over postion
                    Numbervoxel= sum(sum(M(:,current_position,:)==phase_label(current_phase)));
                    res(2).volumefraction(current_position,current_phase+1) = Numbervoxel / (sz(1)*sz(3));
                end
                if dimension==3 % 3D case
                    % Direction 3
                    for current_position=1:1:sz(3) % Loop over postion
                        Numbervoxel= sum(sum(M(:,:,current_position)==phase_label(current_phase)));
                        res(3).volumefraction(current_position,current_phase+1) = Numbervoxel / (sz(1)*sz(2));
                    end
                end
            end

            if isbackground
                for current_direction=1:1:dimension % Loop over all directions
                    res_background(current_direction).volumefraction = zeros(sz(current_direction),number_phase-1); % Initialization
                end
                for current_phase = 2:1:number_phase % Loop over all phases
                    % Direction 1
                    for current_position=1:1:sz(1) % Loop over postion
                        background_vf = res(1).volumefraction(current_position,1+1);
                        phase_vf = res(1).volumefraction(current_position,current_phase+1);
                        res_background(1).volumefraction(current_position,current_phase-1) = phase_vf/(1-background_vf);
                    end
                    % Direction 2
                    for current_position=1:1:sz(2) % Loop over postion
                        background_vf = res(2).volumefraction(current_position,1+1);
                        phase_vf = res(2).volumefraction(current_position,current_phase+1);
                        res_background(2).volumefraction(current_position,current_phase-1) = phase_vf/(1-background_vf);
                    end
                    if dimension==3 % 3D case
                        % Direction 3
                        for current_position=1:1:sz(3) % Loop over postion
                            background_vf = res(3).volumefraction(current_position,1+1);
                            phase_vf = res(3).volumefraction(current_position,current_phase+1);
                            res_background(3).volumefraction(current_position,current_phase-1) = phase_vf/(1-background_vf);
                        end
                    end
                end
            end

            scrsz = get(0,'ScreenSize'); % Screen resolution
            Fig = figure; % Create figure
            Fig.Name= 'Volume fractions'; % Figure name
            Fig.Color='white'; % Background colour
            set(Fig,'position',[scrsz(1) scrsz(2) scrsz(3)*dimension/3 scrsz(4)*1/2]); % Full screen figure
            for current_direction=1:1:dimension % Iterate over axe
                sub_axes=subplot(1,dimension,current_direction,'Parent',Fig);
                hold(sub_axes,'on'); % Active subplot
                % Plot graphs
                x_ = res(current_direction).volumefraction(:,1);
                for current_phase=1:1:number_phase % Loop over phases
                    y_ = res(current_direction).volumefraction(:,current_phase+1);
                    plot(x_,y_,'Color', phase_color(current_phase,:),'LineWidth',2,'DisplayName',char(phase_name(current_phase)));
                end
                if isbackground
                    for current_phase=2:1:number_phase % Loop over phases
                        y_ = res_background(current_direction).volumefraction(:,current_phase-1);
                        plot(x_,y_,'Color', phase_color(current_phase,:),'LineWidth',2,'LineStyle','--','DisplayName',[char(phase_name(current_phase)) ', w/o background']);
                    end
                end
                % Axis label
                xlabel(['Position along ',char(direction_name(current_direction))]);
                xsecondarylabel(axisunit);               
                ylabel('Volume fractions');
                % Legend
                h_legend = legend(sub_axes,'Location','best');
                % - Grid
                grid(sub_axes,'on'); % Display grid
                set(sub_axes,'XMinorGrid','on','YMinorGrid','on'); % Display grid for minor thicks
                set(sub_axes,'FontName','Times new roman','FontSize',14); % Fontname and fontsize
                h_legend.FontSize = 14; % Set title fontsize
                hold(sub_axes,'off'); % Relase figure
            end
            figtitle = strrep(figtitle,'_',' ');
            sgtitle(Fig,figtitle,'FontWeight','bold','FontSize',16,'FontName','Times new roman');
        end

        
        function [] = Initialize_Phasecolorname_UITable(app)
            if strcmp(app.Phasenamecolor_DropDown.Value,'semantic segmentation')
                app.PhasecolornameSemantic_UITable.Visible = 'on';
                app.PhasecolornameSemantic_UITable.Enable = 'on';
                app.PhasecolornameInstance_UITable.Visible = 'off';
                app.PhasecolornameInstance_UITable.Enable = 'off';           
                if ~isempty(app.current_semantic_microstructure)
                    col_ = [colororder; rand(1e6,3)];
                    phase_label = unique(app.current_semantic_microstructure);
                    number_phase = length(phase_label);
                    phase_number = cell(number_phase,1);
                    volume_fractions = zeros(number_phase,2);
                    phase_name = cell(number_phase,1);

                    nanoporosity = zeros(number_phase,1);
                    for k=1:1:number_phase
                        phase_number(k,1)={num2str(k)};
                        volume_fractions(k,1)=sum(sum(sum( app.current_semantic_microstructure==phase_label(k) )))/numel(app.current_semantic_microstructure);
                        if isnan(app.color_phase_semantic(k,1))
                            app.color_phase_semantic(k,:) = col_(k,:);
                        end
                            
                        if ~isempty(app.phase_name_semantic_sav) && length(app.phase_name_semantic_sav)>=k
                            phase_name(k,1) = app.phase_name_semantic_sav(k,1); % Avoid re-writting each time if volumes are same materials
                        else
                            if strcmp(app.SegmentationtypeDropDown.Value,'semantic')
                                if k==1 && app.ThereisabackgroundCheckBox.Value
                                    phase_name(k,1) = {['Background. Label ' num2str(phase_label(k))]};
                                else
                                    phase_name(k,1) = {['Label ' num2str(phase_label(k))]};
                                end
                             else
                                if app.ThereisabackgroundCheckBox.Value
                                    if k==1
                                        phase_name(k,1) = {'Background'};                                    
                                    elseif k==2
                                        phase_name(k,1) = {'Pore'};
                                    elseif k==3
                                        phase_name(k,1) = {'Cracks'};
                                    elseif k==4
                                        phase_name(k,1) = {'Particles'};
                                    else
                                        phase_name(k,1) = {['Label ' num2str(phase_label(k))]};
                                    end
                                else
                                    if k==1
                                        phase_name(k,1) = {'Pore'};
                                    elseif k==2
                                        phase_name(k,1) = {'Cracks'};
                                    elseif k==3
                                        phase_name(k,1) = {'Particles'};
                                    else
                                        phase_name(k,1) = {['Label ' num2str(phase_label(k))]};
                                    end
                                end
                            end
                        end                        
                        
                        s = uistyle('BackgroundColor',app.color_phase_semantic(k,:));
                        addStyle(app.PhasecolornameSemantic_UITable,s,'cell',[k,3]);
                    end
                    app.PhasecolornameSemantic_UITable.Data = [phase_number num2cell(volume_fractions(:,1)) cell(number_phase,1) phase_name num2cell(phase_label) num2cell(nanoporosity)];
                    app.LowerscaleTransport_UITable.Enable = 'on'; 
                  
                 else
                    app.PhasecolornameSemantic_UITable.Data = [];
                    app.AirSaturation_UITable.Data = [];
                    app.LowerscaleTransport_UITable.Data = [];
                end

            elseif strcmp(app.Phasenamecolor_DropDown.Value,'instance segmentation')
                app.PhasecolornameSemantic_UITable.Visible = 'off';
                app.PhasecolornameSemantic_UITable.Enable = 'off';
                app.PhasecolornameInstance_UITable.Visible = 'on';
                app.PhasecolornameInstance_UITable.Enable = 'on';
                if ~isempty(app.current_instanceBW_microstructure)
                    col_ = colororder;
                    phase_label = unique(app.current_instanceBW_microstructure); % always 0 and 1 (or 0,1, 2 if there is a background)
                    number_phase = length(phase_label);
                    phase_number = cell(number_phase,1);
                    volume_fractions = zeros(number_phase,2);
                    phase_name = cell(number_phase,1);

                    for k=1:1:number_phase
                        phase_number(k,1)={num2str(k)};
                        volume_fractions(k,1)=sum(sum(sum( app.current_instanceBW_microstructure==phase_label(k) )))/numel(app.current_instanceBW_microstructure);
                        if isnan(app.color_phase_instance(k,1))
                            app.color_phase_instance(k,:) = col_(k,:);
                        end

                        if ~isempty(app.phase_name_instance_sav) && length(app.phase_name_instance_sav)>=k
                            phase_name(k,1) = app.phase_name_instance_sav(k,1); % Avoid re-writting each time if volumes are same materials
                        else
                            if app.ThereisabackgroundCheckBox.Value
                                if k==1
                                    phase_name(k,1) = {'Background'};
                                elseif k==2
                                    phase_name(k,1) = {'Complementary volume'};
                                else
                                    phase_name(k,1) = {'Instances union'};
                                end
                            else
                                if k==1
                                    phase_name(k,1) = {'Complementary volume'};
                                else
                                    phase_name(k,1) = {'Instances union'};
                                end
                            end
                        end

                        s = uistyle('BackgroundColor',app.color_phase_instance(k,:));
                        addStyle(app.PhasecolornameInstance_UITable,s,'cell',[k,3]);
                    end

                    app.PhasecolornameInstance_UITable.Data = [phase_number num2cell(volume_fractions(:,1)) cell(number_phase,1) phase_name num2cell(phase_label)];
                else
                    app.PhasecolornameInstance_UITable.Data = [];
                end
            end            
        end
        
        function [] = GreyOut(app,statut)
            % Import
            children = get(app.GridLayout_imported,'Children');
            set(children,'Enable',statut);

            % Properties
            children = get(app.Volumefraction_GridLayout,'Children');
            set(children,'Enable',statut);   
            children = get(app.MetricsPerParticle_GridLayout,'Children');
            set(children,'Enable',statut);
            if strcmp(statut,'on') && (strcmp(app.inputvolume.segmentation_type,'instance') || strcmp(app.inputvolume.segmentation_type,'semantic and instance'))
                children = get(app.MetricsPerParticle_GridLayout,'Children');
                set(children,'Enable',statut);
            end
            children = get(app.Cpsd_GridLayout,'Children');
            set(children,'Enable',statut);     
            children = get(app.TortuosityFactor_GridLayout,'Children');
            set(children,'Enable',statut);      
            if strcmp(statut,'on')
                app.BackgroundoptionifanyPanel.Enable = app.ThereisabackgroundCheckBox.Value;
            end
            
            % Error
            children = get(app.Imageresolution_GridLayout,'Children');
            set(children,'Enable',statut);     
            children = get(app.Set_Rconvergence_GridLayout,'Children');
            set(children,'Enable',statut);       
            children = get(app.Set_RRVE_GridLayout,'Children');
            set(children,'Enable',statut);    
            children = get(app.Set_Rplanned_GridLayout,'Children');
            set(children,'Enable',statut);                

            % Macro
            children = get(app.Macro_GridLayout,'Children');
            set(children,'Enable',statut); 

            % Run
            children = get(app.Run_GridLayout,'Children');
            set(children,'Enable',statut);  
        end
        
        function t = func_selectall(app,t,val)
            [rn,cn]=size(t);
            for r=1:1:rn
                for c=3:2:cn
                    if ~isempty(cell2mat(t(r,c-1)))
                        t(r,c) = {logical(val)};
                    end
                end
            end
        end
        
        function [] = Check_RVEtable(app)
            kvol = app.Allanalyses_ForvolumeDropDown.ValueIndex;
            kpro = app.Allanalyses_ForpropertyDropDown.ValueIndex;
            nRVE = app.RVE_list.vol(kvol).pro(kpro).number_RVE;

            if nRVE > 0
                cname = cell(1,nRVE+1);
                cname(1) = {'Parameter'};
                for kRVE=1:1:nRVE
                    cname(kRVE+1) = {['Analysis #' num2str(kRVE)]};
                end
                app.ListAnalysis_UITable.ColumnName = cname;
                [n,~] = size(app.ListAnalysis_UITable.Data);
                datacell =cell(n,nRVE+1);
                datacell(:,1) = app.ListAnalysis_UITable.Data(:,1);

                for kRVE=1:1:nRVE
                    tmp = struct2cell(app.RVE_list.vol(kvol).pro(kpro).RVE(kRVE));
                    tmp=tmp(1:n);
                    for k=1:1:n
                        if length(cell2mat(tmp(k)))>1
                            tmp(k) = {num2str(cell2mat(tmp(k)))};
                        end
                    end        
                    datacell(:,kRVE+1) = tmp;
                end
                app.ListAnalysis_UITable.ColumnName = cname;
                app.ListAnalysis_UITable.Data = datacell;

                app.RemoveallanalysesforthisvolumeButton.Enable = 'on';
                app.ApplyonALLpropertiesforthisvolumeButton.Enable = 'on';
                app.ApplyonthispropertyforALLvolumesButton.Enable = 'on';
                app.ApplyonALLpropertiesforALLvolumesButton.Enable = 'on';
                app.ApplyPERpropertyforALLvolumesButton.Enable = 'on';

            else
                app.RemoveallanalysesforthisvolumeButton.Enable = 'off';
                app.ApplyonALLpropertiesforthisvolumeButton.Enable = 'off';
                app.ApplyonthispropertyforALLvolumesButton.Enable = 'off';
                app.ApplyonALLpropertiesforALLvolumesButton.Enable = 'off';
                app.ApplyPERpropertyforALLvolumesButton.Enable = 'off';
                app.ListAnalysis_UITable.ColumnName = {'Parameter','Analysis #1'};
                app.ListAnalysis_UITable.Data = [{'Type';'Choice (independent subvolumes)';'Constant length(s)';'Also calculate the 2 subvolumes case';'Dividers';'Do not cut volume smaller than';'Do not cut volume smaller than (value)';'Subvolume aspect ratio';'Threshold choice';'Threshold value(s)';'RVE convergence with field of view';'Crop';'that is';'At each step';'x(%)';'Crop until new FOV is % of the initial FOV';'Choice (one subvolume)';'That is';'Size of the first subvolume % of the initial volume';'Subvolume growth';'Subvolume growth (value)';'Subvolume aspect ratio';'Threshold choice';'Threshold value(s)'} {' ';' ';' ';' ';' ';' ';' ';' ';' ';' ';' ';' ';' ';' ';' ';' ';' ';' ';' ';' ';' ';' ';' ';' '}];
            end

        end
        
        function [] = Update_TortuosityTable_direction(app,dir,value)
            if app.Tau_Semantic_UITable.Enable
                [r,c] = size(app.Tau_Semantic_UITable.Data);
                if strcmp(app.ScaleDropDown.Value,'One scale')
                    for row = 1:1:r
                        for col = 3:3:c
                            val = app.Tau_Semantic_UITable.Data(row,col);
                            if ~isempty(cell2mat(val))
                                val = str2num(cell2mat(val));
                                if value
                                    val(dir) = 1;
                                else
                                    val(dir) = 0;
                                end
                                app.Tau_Semantic_UITable.Data(row,col) = {num2str(val)};
                            end
                        end
                    end
                    app.table_sem_phaseselection_tau_onescale = app.Tau_Semantic_UITable.Data;
                elseif strcmp(app.ScaleDropDown.Value,'Dual scale')
                    for row = 1:1:r
                        val = app.Tau_Semantic_UITable.Data(row,2);
                        val = str2num(cell2mat(val));
                        if value
                            val(dir) = 1;
                        else
                            val(dir) = 0;
                        end
                        app.Tau_Semantic_UITable.Data(row,2) = {num2str(val)};
                    end
                    if strcmp(app.DualScale_InputsDropDown.Value,'Lower scale diffusivity')
                        app.table_sem_phaseselection_tau_dualscale_dbulk = app.Tau_Semantic_UITable.Data;
                    elseif strcmp(app.DualScale_InputsDropDown.Value,'Lower scale porosity and Bruggeman exponent')
                        app.table_sem_phaseselection_tau_dualscale_epsp = app.Tau_Semantic_UITable.Data;
                    end
                end                
            end
            if app.Tau_Instance_UITable.Enable
                [r,c] = size(app.Tau_Instance_UITable.Data);
                if strcmp(app.ScaleDropDown.Value,'One scale')
                    for row = 1:1:r
                        for col = 3:3:c
                            val = app.Tau_Instance_UITable.Data(row,col);
                            val = str2num(cell2mat(val));
                            if value
                                val(dir) = 1;
                            else
                                val(dir) = 0;
                            end
                            app.Tau_Instance_UITable.Data(row,col) = {num2str(val)};
                        end
                    end
                    app.table_ins_phaseselection_tau_onescale = app.Tau_Instance_UITable.Data;
                elseif strcmp(app.ScaleDropDown.Value,'Dual scale')
                    for row = 1:1:r
                        val = app.Tau_Instance_UITable.Data(row,2);
                        val = str2num(cell2mat(val));
                        if value
                            val(dir) = 1;
                        else
                            val(dir) = 0;
                        end
                        app.Tau_Instance_UITable.Data(row,2) = {num2str(val)};
                    end
                    if strcmp(app.DualScale_InputsDropDown.Value,'Lower scale diffusivity')
                        app.table_ins_phaseselection_tau_dualscale_dbulk = app.Tau_Instance_UITable.Data;
                    elseif strcmp(app.DualScale_InputsDropDown.Value,'Lower scale porosity and Bruggeman exponent')
                        app.table_ins_phaseselection_tau_dualscale_epsp = app.Tau_Instance_UITable.Data;
                    end
                end
            end
        end
        
        
        function [] = Create_pars_for_run(app)
            % optc: options common to all volumes
            % opts: options volume specific 
            
            % Toolbox info (hardcoded)
            optc.toolbox.toolboxname = 'Microstructure analysis toolbox (MATBOX)';
            optc.toolbox.version = app.matboxversion;
            optc.toolbox.githubrepo = 'https://github.com/NREL/MATBOX_Microstructure_analysis_toolbox';
            optc.toolbox.officialwebpage = 'https://www.nrel.gov/transportation/matbox.html';            
            optc.toolbox.article = 'F.L.E.Usseglio-Viretta et al.,MATBOX: An Open-source Microstructure Analysis Toolbox for microstructure generation, segmentation, characterization, visualization, correlation, and meshing, SoftwareX, Volume 17, January 2022, 100915 https://doi.org/10.1016/j.softx.2021.100915';
            optc.toolbox.authorlist = {'Francois L. E. Usseglio-Viretta','National Renewable Energy Laboratory';
                                      'Prehit Patel','National Renewable Energy Laboratory';
                                       'Elizabeth Bernhardt','National Renewable Energy Laboratory';
                                       'Aashutosh Mistry', 'Argonne National Laboratory';
                                       'Partha P. Mukherjee', 'Purdue University';
                                       'Jeffery Allen', 'National Renewable Energy Laboratory';
                                       'Samuel J. Cooper','Imperial College London';
                                       'Jerome Laurencin','CEA-Liten';
                                       'Kandler Smith','National Renewable Energy Laboratory'};

            % % PRINT IMFORMATION ABOUT TOOLBOX AND USER
            Table_toolbox = table({'Toolbox';'Version';'GitHub repository';'Official webpage';'Journal article'},{optc.toolbox.toolboxname;optc.toolbox.version;optc.toolbox.githubrepo;optc.toolbox.officialwebpage;optc.toolbox.article},...
                'VariableNames',{'Item','Value'});            
            Table_author = table(optc.toolbox.authorlist(:,1),optc.toolbox.authorlist(:,2),...
                'VariableNames',{'Author','Affiliation'});
            username = getenv('USERNAME');
            computername = getenv('COMPUTERNAME');
            os = getenv('OS');
            Table_User = table({username},{computername},{os},...
                'VariableNames',{'User name','Computer name','Operating system'});

            clc % Clear command window
            if ~app.savemacro
                disp '***************************************';
                disp '>>> MICROSTRUCTURE ANALYSIS TOOLBOX <<<';
                disp '    Characterization module';
                disp '***************************************';
                disp ' ';
                disp(Table_toolbox);disp ' ';
                disp(Table_author);disp ' ';
                disp 'Run by:';
                disp(Table_User);disp ' ';

                if strcmp(app.inputvolume.segmentation_type,'semantic')
                    disp('Characterization of semantic segmentation file(s)');disp ' ';
                elseif strcmp(app.inputvolume.segmentation_type,'instance')
                    disp('Characterization of instance segmentation file(s)');disp ' ';
                elseif strcmp(app.inputvolume.segmentation_type,'semantic and instance')
                    disp('Characterization of semantic and instance segmentation files');disp ' ';
                end
            end

            % Save options
            optc.save.mat = true;
            optc.save.xls = true;
            optc.save.savefig = app.SavefiguresinfollowingfileformatCheckBox.Value;
            optc.save.fig_infig = app.figCheckBox.Value;
            optc.save.fig_format = {};
            if app.pngCheckBox.Value
               optc.save.fig_format = [optc.save.fig_format {'png'}];
               optc.save.png_DPI = app.DPIresolutionEditField.Value;
            end
            if app.svgscalablevectorgraphicsCheckBox.Value
               optc.save.fig_format = [optc.save.fig_format {'svg'}];
            end            
            optc.save.allowoverwrite = app.AllowoverwritefigurefilesCheckBox.Value;
            optc.save.autoclosefig = app.ClosefigureautomaticallyoncesavedCheckBox.Value;
            optc.save.inputfiles = app.Saveimportedtiffiles_CheckBox.Value;
            optc.save.modified_inputfiles = app.Savemodifiedtiffiles_CheckBox.Value;    
            optc.save.Height_foroneplot = app.FigureheightEditField.Value;
            optc.save.Height_unit = app.FigureheightDropDown.Value;
            optc.save.Padding = app.PaddingDropDown.Value;            

            % Format options
            optc.format.fontname = app.FontnameDropDown.Value;
            optc.format.axefontsize = app.AxeFontsizeEditField.Value;
            optc.format.legendfontsize = app.LegendfontsizeEditField.Value;
            optc.format.titlefontsize = app.TitlefontsizeEditField.Value;
            optc.format.sgtitlefontsize = app.SgtitlefontsizeEditField.Value;
            optc.format.linewidth = app.LinewidthEditField.Value;
            optc.format.includefilenameintitle = app.IncludefilenameinfiguretitleCheckBox.Value;
            if strcmp(app.GridDropDown.Value,'No grid')
                optc.format.grid = 'off';
                optc.format.minorgrid = 'off';
            elseif strcmp(app.GridDropDown.Value,'Major grid')
                optc.format.grid = 'on';
                optc.format.minorgrid = 'off';
            elseif strcmp(app.GridDropDown.Value,'Major and minor grid')
                optc.format.grid = 'on';
                optc.format.minorgrid = 'on';
            end
            optc.format.layout_padding = app.PaddingDropDown_2.Value;
            optc.format.tile_spacing = app.TilespacingDropDown.Value;            
           
            % Loop over all files
            orders = app.order_calculation;
            number_volume_to_characterize = sum(orders~=0);

            if app.savemacro
                number_volume_to_characterize = 1;
            end

            for k=1:1:number_volume_to_characterize
                kvol = find(orders==k);
                tag = app.tags(kvol);
                tag_unit = app.TagunitEditField.Value;

                if app.use_macro
                    opts_sem = app.macro_pars.opts_sem;
                    opts_ins = app.macro_pars.opts_ins;
                else
                    if strcmp(app.inputvolume.mode,'Set parameters only once and apply to all volumes') && k>1
                        opts_sem = opts_sem1;
                        opts_ins = opts_ins1;
                    else
                        % Initialize semantic and instance options
                        opts_sem = [];
                        opts_ins = [];

                        % % Semantic options
                        if strcmp(app.inputvolume.segmentation_type,'semantic') || strcmp(app.inputvolume.segmentation_type,'semantic and instance')
                            number_phase = app.inputvolume.volume(kvol).nphase;
                            if app.inputvolume.volume(kvol).isbackground
                                number_phase = number_phase - 1;
                            end

                            % % Volume fractions
                            opts_sem.volumefraction.todo = zeros(number_phase,1);
                            for kphase=1:1:number_phase
                                opts_sem.volumefraction.todo(kphase,1) = cell2mat(app.Volumefraction_Semantic_UITable.Data(k,(kphase+1)*2));
                            end
                            opts_sem.volumefraction.combined_todo = cell2mat(app.Volumefraction_Semantic_UITable.Data(k,2));
                            % Direction plot
                            opts_sem.volumefraction.plotdirections = [app.vf_Direction1_CheckBox.Value, app.vf_Direction2_CheckBox.Value, app.vf_Direction3_CheckBox.Value];

                            % Voxel size
                            scalingfactors = app.inputvolume.volume(kvol).voxel_resize(app.voxelidx.volumefraction, 2);
                            opts_sem.volumefraction.scaling = str2num(char(strrep(scalingfactors,',',' ')));
                            opts_sem.volumefraction.scaling_extrapolation = char(app.inputvolume.volume(kvol).voxel_resize(app.voxelidx.volumefraction, 4));
                            % Fractal analysis
                            opts_sem.volumefraction.fractal_bertei.todo = cell2mat(app.inputvolume.volume(kvol).voxel_resize(app.voxelidx.volumefraction, 6));
                            % RVE
                            opts_sem.volumefraction.RVE = app.RVE_list.vol(k).pro(app.RVEidx.volumefraction); % 1 for volume fraction

                            % % Tortuosity factor
                            opts_sem.tortuosity.todo = zeros(number_phase,1);
                            for kphase=1:1:number_phase
                                opts_sem.tortuosity.todo(kphase,1) = cell2mat(app.Tau_Semantic_UITable.Data(k,(kphase+1)*2+1));
                            end
                            opts_sem.tortuosity.pore_combined_todo = cell2mat(app.Tau_Semantic_UITable.Data(k,2));
                            opts_sem.tortuosity.solid_combined_todo = cell2mat(app.Tau_Semantic_UITable.Data(k,3));
                            % Orientation
                            opts_sem.tortuosity.tau_xyz = [app.Tau_d1_CheckBox.Value, app.Tau_d2_CheckBox.Value, app.Tau_d3_CheckBox.Value];
                            % Along direction
                            opts_sem.tortuosity.calculate_along = [app.Direction1EditField.Value, app.Direction2EditField.Value, app.Direction3EditField.Value];
                            
                            % Voxel size
                            scalingfactors = app.inputvolume.volume(kvol).voxel_resize(app.voxelidx.tortuosityfactor, 2);
                            opts_sem.tortuosity.scaling = str2num(char(strrep(scalingfactors,',',' ')));
                            opts_sem.tortuosity.scaling_extrapolation.tau = char(app.inputvolume.volume(kvol).voxel_resize(app.voxelidx.tortuosityfactor, 4));
                            opts_sem.tortuosity.scaling_extrapolation.p = char(app.inputvolume.volume(kvol).voxel_resize(app.voxelidx.Bruggeman, 4));
                            opts_sem.tortuosity.scaling_extrapolation.Deff = char(app.inputvolume.volume(kvol).voxel_resize(app.voxelidx.Deff, 4));
                            
                            % Fractal analysis
                            opts_sem.tortuosity.fractal_bertei.todo = cell2mat(app.inputvolume.volume(kvol).voxel_resize(app.voxelidx.tortuosityfactor, 6));
                            % RVE
                            opts_sem.tortuosity.RVE = app.RVE_list.vol(k).pro(app.RVEidx.tortuosityfactor);

                            % Background
                            opts_sem.tortuosity.background_method = app.SelectmethodDropDown.Value;
                            opts_sem.tortuosity.iterate_tol = app.ToleranceEditField.Value;
                            opts_sem.tortuosity.iterate_maxiter = app.MaxiterEditField.Value;


                            % % CPSD
                            % opts_sem.cpsd.todo = zeros(number_phase,1);
                            % opts_sem.cpsd.colormap = app.DiametercolormapDropDown.Value;
                            % opts_sem.cpsd.colormap_background = app.DiameterbackgroundcolormapDropDown.Value;
                            % for kphase=1:1:number_phase
                            %     opts_sem.cpsd.todo(kphase,1) = cell2mat(app.Cpsd_Semantic_UITable.Data(k,2*kphase+1));
                            % end
                            % % Edge effects
                            % opts_sem.cpsd.symmetry = app.Cpsd_Usesymmetry_CheckBox.Value;
                            % % Voxel size
                            % scalingfactors = app.inputvolume.volume(kvol).voxel_resize(app.voxelidx.cpsd, 2);
                            % opts_sem.cpsd.scaling = str2num(char(strrep(scalingfactors,',',' ')));
                            % opts_sem.cpsd.scaling_extrapolation = char(app.inputvolume.volume(kvol).voxel_resize(app.voxelidx.cpsd, 4));
                            % % Fractal analysis
                            % opts_sem.cpsd.fractal_bertei.todo = cell2mat(app.inputvolume.volume(kvol).voxel_resize(app.voxelidx.cpsd, 6));
                            % % RVE
                            % opts_sem.cpsd.RVE = app.RVE_list.vol(k).pro(2); % 2 for cpsd
                            % 
                            % % Tortuosity
                            % opts_sem.tortuosity.scale = app.ScaleDropDown.Value;
                            % opts_sem.tortuosity.dualscale_input = app.DualScale_InputsDropDown.Value;
                            % opts_sem.tortuosity.background_method = app.SelectmethodDropDown.Value;
                            % opts_sem.tortuosity.iterate_tol = app.ToleranceEditField.Value;
                            % opts_sem.tortuosity.iterate_maxiter = app.MaxiterEditField.Value;
                            % opts_sem.tortuosity.tolerance = app.ToleranceEditField_2.Value;
                            % if strcmp(opts_sem.tortuosity.scale,'One scale')
                            %     for kphase=1:1:number_phase
                            %         dirs = str2num(cell2mat(app.Tau_Semantic_UITable.Data(k,3*kphase)));
                            %         sect = str2num(cell2mat(app.Tau_Semantic_UITable.Data(k,3*kphase+1)));
                            %         if sum(dirs)>0
                            %             opts_sem.tortuosity.todo(kphase,1) = 1;
                            %             opts_sem.tortuosity.directions(kphase,1).vals = dirs;
                            %             opts_sem.tortuosity.sections(kphase,1).vals = sect;
                            %         else
                            %             opts_sem.tortuosity.todo(kphase,1) = 0;
                            %         end
                            %     end
                            % elseif strcmp(opts_sem.tortuosity.scale,'Dual scale')
                            %     dirs = str2num(cell2mat(app.Tau_Semantic_UITable.Data(k,2)));
                            %     sect = str2num(cell2mat(app.Tau_Semantic_UITable.Data(k,3)));
                            %     if sum(dirs)>0
                            %         opts_sem.tortuosity.todo = 1;
                            %         opts_sem.tortuosity.directions = dirs;
                            %         opts_sem.tortuosity.sections = sect;
                            %         for kphase=1:1:number_phase
                            %             if strcmp(opts_sem.tortuosity.dualscale_input,'Lower scale diffusivity')
                            %                 opts_sem.tortuosity.lowerscale_diffusivity(kphase,1) = cell2mat(app.Tau_Semantic_UITable.Data(k,2*kphase+3));
                            %             elseif strcmp(opts_sem.tortuosity.dualscale_input,'Lower scale porosity and Bruggeman exponent')
                            %                 vals = str2num(cell2mat(app.Tau_Semantic_UITable.Data(k,2*kphase+3)));
                            %                 opts_sem.tortuosity.lowerscale_porosity(kphase,1) = vals(1);
                            %                 opts_sem.tortuosity.lowerscale_Bruggeman(kphase,1) = vals(2);
                            %             end
                            %         end
                            %     else
                            %         opts_sem.tortuosity.todo = 0;
                            %     end
                            % end


                        end

                        % % Instance options
                        if strcmp(app.inputvolume.segmentation_type,'instance') || strcmp(app.inputvolume.segmentation_type,'semantic and instance')
                            number_phase = 2;

                            % % Volume fractions
                            opts_ins.volumefraction.todo = zeros(number_phase,1);
                            for kphase=1:1:number_phase
                                opts_ins.volumefraction.todo(kphase,1) = cell2mat(app.Volumefraction_Instance_UITable.Data(k,2*kphase+1));
                            end
                            % Direction plot
                            opts_ins.volumefraction.plotdirections = [app.vf_Direction1_CheckBox.Value, app.vf_Direction2_CheckBox.Value, app.vf_Direction3_CheckBox.Value];

                            % Voxel size
                            scalingfactors = app.inputvolume.volume(kvol).voxel_resize(app.voxelidx.volumefraction, 2);
                            opts_ins.volumefraction.scaling = str2num(char(strrep(scalingfactors,',',' ')));
                            opts_ins.volumefraction.scaling_extrapolation = char(app.inputvolume.volume(kvol).voxel_resize(app.voxelidx.volumefraction, 4));
                            % Fractal analysis
                            opts_ins.volumefraction.fractal_bertei.todo = cell2mat(app.inputvolume.volume(kvol).voxel_resize(app.voxelidx.volumefraction, 6));
                            % RVE
                            opts_ins.volumefraction.RVE = app.RVE_list.vol(k).pro(1); % 1 for volume fraction


                            % % Volume fractions
                            % opts_ins.volumefraction.todo = zeros(number_phase,1);
                            % for kphase=1:1:number_phase
                            %     opts_ins.volumefraction.todo(kphase,1) = cell2mat(app.Volumefraction_Instance_UITable.Data(k,2*kphase+1));
                            % end
                            % % Voxel size
                            % scalingfactors = app.inputvolume.volume(kvol).voxel_resize(app.voxelidx.volumefraction, 2);
                            % opts_ins.volumefraction.scaling = str2num(char(strrep(scalingfactors,',',' ')));
                            % opts_ins.volumefraction.scaling_extrapolation = char(app.inputvolume.volume(kvol).voxel_resize(app.voxelidx.volumefraction, 4));
                            % % Fractal analysis
                            % opts_ins.volumefraction.fractal_bertei.todo = cell2mat(app.inputvolume.volume(kvol).voxel_resize(app.voxelidx.volumefraction, 6));
                            % % RVE
                            % opts_ins.volumefraction.RVE = app.RVE_list.vol(k).pro(1); % 1 for volume fraction
                            % 
                            % opts_sem.volumefraction.fractal_boxcounting.todo = 0;
                            % 
                            % 
                            % % Metrics per particle
                            % opts_ins.metricsperparticle.todo = cell2mat(app.MetricsPerParticle_UITable.Data(k,2));
                            % opts_ins.metricsperparticle.weightvolume = app.WeightMetrics_withvolume_CheckBox.Value;
                            % opts_ins.metricsperparticle.removetruncatedparticles = app.ExcludetruncatedparticlesCheckBox.Value;
                            % 
                            % 
                            % % CPSD
                            % opts_ins.cpsd.todo = zeros(number_phase,1);
                            % opts_ins.cpsd.colormap = app.DiametercolormapDropDown.Value;
                            % opts_ins.cpsd.colormap_background = app.DiameterbackgroundcolormapDropDown.Value;
                            % opts_ins.cpsd.removetruncatedparticles = app.Cpsd_ExcludetruncatedparticlesCheckBox.Value;
                            % for kphase=1:1:number_phase
                            %     opts_ins.cpsd.todo(kphase,1) = cell2mat(app.Cpsd_Instance_UITable.Data(k,2*kphase+1));
                            % end
                            % % Edge effects
                            % opts_ins.cpsd.symmetry = app.Cpsd_Usesymmetry_CheckBox.Value;
                            % % Voxel size
                            % scalingfactors = app.inputvolume.volume(kvol).voxel_resize(app.voxelidx.cpsd, 2);
                            % opts_ins.cpsd.scaling = str2num(char(strrep(scalingfactors,',',' ')));
                            % opts_ins.cpsd.scaling_extrapolation = char(app.inputvolume.volume(kvol).voxel_resize(app.voxelidx.cpsd, 4));
                            % % Fractal analysis
                            % opts_ins.cpsd.fractal_bertei.todo = cell2mat(app.inputvolume.volume(kvol).voxel_resize(app.voxelidx.cpsd, 6));
                            % % RVE
                            % opts_ins.cpsd.RVE = app.RVE_list.vol(k).pro(2); % 2 for cpsd
                            % 
                            % % Tortuosity
                            % opts_ins.tortuosity.scale = app.ScaleDropDown.Value;
                            % opts_ins.tortuosity.dualscale_input = app.DualScale_InputsDropDown.Value;
                            % opts_ins.tortuosity.background_method = app.SelectmethodDropDown.Value;
                            % opts_ins.tortuosity.iterate_tol = app.ToleranceEditField.Value;
                            % opts_ins.tortuosity.iterate_maxiter = app.MaxiterEditField.Value;
                            % opts_ins.tortuosity.tolerance = app.ToleranceEditField_2.Value;
                            % if strcmp(opts_ins.tortuosity.scale,'One scale')
                            %     for kphase=1:1:number_phase
                            %         dirs = str2num(cell2mat(app.Tau_Instance_UITable.Data(k,3*kphase)));
                            %         sect = str2num(cell2mat(app.Tau_Instance_UITable.Data(k,3*kphase+1)));
                            %         if sum(dirs)>0
                            %             opts_ins.tortuosity.todo(kphase,1) = 1;
                            %             opts_ins.tortuosity.directions(kphase,1).vals = dirs;
                            %             opts_ins.tortuosity.sections(kphase,1).vals = sect;
                            %         else
                            %             opts_ins.tortuosity.todo(kphase,1) = 0;
                            %         end
                            %     end
                            % elseif strcmp(opts_ins.tortuosity.scale,'Dual scale')
                            %     dirs = str2num(cell2mat(app.Tau_Instance_UITable.Data(k,2)));
                            %     sect = str2num(cell2mat(app.Tau_Instance_UITable.Data(k,3)));
                            %     if sum(dirs)>0
                            %         opts_ins.tortuosity.todo = 1;
                            %         opts_ins.tortuosity.directions = dirs;
                            %         opts_ins.tortuosity.sections = sect;
                            %         for kphase=1:1:number_phase
                            %             if strcmp(opts_ins.tortuosity.dualscale_input,'Lower scale diffusivity')
                            %                 opts_ins.tortuosity.lowerscale_diffusivity(kphase,1) = cell2mat(app.Tau_Instance_UITable.Data(k,2*kphase+3));
                            %             elseif strcmp(opts_ins.tortuosity.dualscale_input,'Lower scale porosity and Bruggeman exponent')
                            %                 vals = str2num(cell2mat(app.Tau_Instance_UITable.Data(k,2*kphase+3)));
                            %                 opts_ins.tortuosity.lowerscale_porosity(kphase,1) = vals(1);
                            %                 opts_ins.tortuosity.lowerscale_Bruggeman(kphase,1) = vals(2);
                            %             end
                            %         end
                            %     else
                            %         opts_ins.tortuosity.todo = 0;
                            %     end
                            % end


                        end


                    end
                end

                if ~app.savemacro
                    % % Start
                    date_start = datetime('now','TimeZone','local','Format','eeee, HH:mm:ss Z, MMMM d, y');
                    Table_date = table({char(date_start)},...
                        'VariableNames',{'Date'});
                    disp '>>>';
                    disp(['>>> VOLUME #' num2str(k) ' <<<']);
                    disp '>>>';
                    disp ' ';
                    disp(Table_date);disp ' ';
                    app.RunStatut_UITable.Data(k,2) = {'On-going'};
                    app.RunStatut_UITable.Data(k,3) = {char(date_start)};
                    app.RunStatut_UITable.Data(k,4) = {' '};
                    sPass = uistyle("Icon","none","IconAlignment","rightmargin");
                    addStyle(app.RunStatut_UITable,sPass,"cell",[k 2]);
                    pause(0.01);
                    tic
                    function_microstructure_characterization_segmentedvolume2(app.inputvolume.mode, app.inputvolume.segmentation_type, app.inputvolume.volume(kvol),optc,opts_sem,opts_ins,tag,tag_unit); % Call main characterization function
                    app.RunStatut_UITable.Data(k,4) = {[num2str(toc,'%1.1f') ' s']};
                    app.RunStatut_UITable.Data(k,2) = {'Done'};
                    sPass = uistyle("Icon","success","IconAlignment","rightmargin");
                    addStyle(app.RunStatut_UITable,sPass,"cell",[k 2]);
                    pause(0.01);

                    if k==1
                        opts_sem1 = opts_sem;
                        opts_ins1 = opts_ins;
                    end
                else
                    filename = fullfile(app.Macrosavedolfer_Label.Text, [app.MacronameEditField.Value '.mat']);
                    pars.segmentation_type = app.inputvolume.segmentation_type;
                    pars.optc = optc;
                    pars.opts_sem = opts_sem;
                    pars.opts_ins = opts_ins;
                    description = app.MacrodescriptionTextArea.Value;
                    save(filename,"pars","description")
                    disp('Macro has been saved.');
                end
            end
            if ~app.savemacro
                disp ' ';
                disp '****************************************************************************************************';
                disp '>>> ALL CALCULATIONS FOR ALL VOLUMES HAVE BEEN PERFORMED <<<';
                disp '    Results (figures and tables) are located within the ''main folder\volume folder'' of each volume';
                disp '****************************************************************************************************';
            end
        end       
        
        function [res] = get_average(app,array)
            labels = cell2mat(app.PhasecolornameSemantic_UITable.Data(:,5));
            ROI = cell2mat(app.ROI_UITable.Data(:,3:4));
            nphase = length(labels);
            res = zeros(nphase,1);
            sz = size(array);
            if length(sz)==2
                phases = app.current_semantic_microstructure(ROI(1,1):ROI(1,2),ROI(2,1):ROI(2,2));
                np = array(ROI(1,1):ROI(1,2),ROI(2,1):ROI(2,2));
            else
                phases = app.current_semantic_microstructure(ROI(1,1):ROI(1,2),ROI(2,1):ROI(2,2),ROI(3,1):ROI(3,2));
                np = array(ROI(1,1):ROI(1,2),ROI(2,1):ROI(2,2),ROI(3,1):ROI(3,2));
            end
            for k=1:nphase
                idx = find(phases == labels(k));
                res(k) = sum(sum(sum(np(idx))))/length(idx);
            end
        end
        
        
        function [diffusivity, Bruggeman_pore, conductivity, Bruggeman_solid, wettability, airsaturation] = Porosity_modified(app)
           
            % Get nanoporosity values
            [nphase,~] = size(app.PhasecolornameSemantic_UITable.Data);
            nanoporosity = zeros(nphase,1);
            if app.ThereisabackgroundCheckBox.Value
                nanoporosity(1) = -1;
                if ~ischar(cell2mat(app.PhasecolornameSemantic_UITable.Data(2,6)))
                    nanoporosity(2:end) = cell2mat(app.PhasecolornameSemantic_UITable.Data(2:end,6));
                else
                    nanoporosity(2:end) = -2;
                end
            else
                if ~ischar(cell2mat(app.PhasecolornameSemantic_UITable.Data(1,6)))
                    nanoporosity = cell2mat(app.PhasecolornameSemantic_UITable.Data(:,6));
                else
                    nanoporosity(1:end) = -2;
                end
            end
            id_na = find(nanoporosity==-1.0);
            id_notimported = find(nanoporosity==-2.0);
            id_pore = find(nanoporosity==1.0);
            id_solid = find(nanoporosity==0.0);
            id_mixed = find(double(nanoporosity~=1.0) .* double(nanoporosity~=0.0));            

            diffusivity = num2cell(zeros(nphase,1));
            diffusivity(id_na) = {'n/a'};
            diffusivity(id_notimported) = {1.0};
            diffusivity(id_pore) = {1.0};
            diffusivity(id_solid) = {'n/a'};
            diffusivity(id_mixed) = {0.5};

            Bruggeman_pore = num2cell(zeros(nphase,1));
            Bruggeman_pore(id_na) = {'n/a'};
            Bruggeman_pore(id_notimported) = {1.5};
            Bruggeman_pore(id_pore) = {1.0};
            Bruggeman_pore(id_solid) = {'n/a'};
            Bruggeman_pore(id_mixed) = {1.5};

            conductivity = num2cell(zeros(nphase,1));
            conductivity(id_na) = {'n/a'};
            conductivity(id_notimported) = {1.0};
            conductivity(id_pore) = {'n/a'};
            conductivity(id_solid) = {1.0};
            conductivity(id_mixed) = {0.5};

            Bruggeman_solid = num2cell(zeros(nphase,1));
            Bruggeman_solid(id_na) = {'n/a'};
            Bruggeman_solid(id_notimported) = {1.5};
            Bruggeman_solid(id_pore) = {'n/a'};
            Bruggeman_solid(id_solid) = {1.0};
            Bruggeman_solid(id_mixed) = {1.5};

            wettability = zeros(nphase,1);
            wettability(id_pore)=1;
            wettability(id_solid)=0.0;
            wettability(id_mixed)=0.5;            
            airsaturation = 1-wettability;
            airsaturation = num2cell(airsaturation);
            wettability = num2cell(wettability);
            airsaturation(id_na) = {'n/a'};
            wettability(id_na) = {'n/a'};
        end
        
        function [data] = Swicthselections(app,data)
            [rtot,ctot] = size(data);
            plus_counter = 0;
            minus_counter = 0;
            for r=1:rtot
                for c=1:ctot
                    val = cell2mat(data(r,c));
                    if islogical(val)
                        if val
                            plus_counter = plus_counter + 1;
                        else
                            minus_counter = minus_counter + 1;
                        end
                    end
                end
            end

            for r=1:rtot
                for c=1:ctot
                    val = cell2mat(data(r,c));
                    if islogical(val)
                        if plus_counter >= minus_counter
                            data(r,c) = {logical(0)};
                        else
                            data(r,c) = {logical(1)};
                        end
                    end
                end
            end


        end
        
        function [data] = ApplyFirstSelection_to_all(app,data)
            [rtot,ctot] = size(data);
            for r=2:rtot
                for c=1:ctot
                    val = cell2mat(data(r,c));
                    if islogical(val)
                        data(r,c) = data(1,c);
                    end
                end
            end  
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Format tab
            app.FontnameDropDown.Items = listfonts;
            app.FontnameDropDown.Value = 'Times New Roman'; % Default

            app.DiametercolormapDropDown.Items = colormaplist;
            app.DiametercolormapDropDown.Value = 'turbo';

            % Initialize import tab
            app.ModeDropDownValueChanged;
            app.PhasecolornameInstance_UITable.Visible = 'off';
            app.PhasecolornameInstance_UITable.Enable = 'off'; 

            % Voxel size index
            app.voxelidx.volumefraction = 1;
            app.voxelidx.cpsd = 2;
            app.voxelidx.tortuosityfactor = 3;
            app.voxelidx.Bruggeman = 4;
            app.voxelidx.Deff = 5;
            app.property_resolution_list = {'Volume fraction','Continuous particle size distribution','Tortuosity factor','Bruggeman exponent','Effective diffusivity'};
            app.toplogy_dimension = num2cell([3;3;3;3;3]);            
          
            % RVE index
            app.RVEidx.volumefraction = 1;
            app.RVEidx.cpsd = 2;
            app.RVEidx.tortuosityfactor = 3;
            app.property_RVE_list = {'Volume fraction','Continuous particle size distribution','Tortuosity factor'};

            % Macro
            app.Macrosavedolfer_Label.Text = pwd;

            % RVE
            app.RVE_ChoiceDropDownValueChanged;
            app.RVE_ForpropertyDropDown.Items = app.property_RVE_list;
            app.OneSub_ForpropertyDropDown.Items = app.RVE_ForpropertyDropDown.Items;
            app.Allanalyses_ForpropertyDropDown.Items = app.RVE_ForpropertyDropDown.Items;
            app.RVEconvergence_CropDropDownValueChanged;
            app.OneSub_ChoiceDropDownValueChanged;

            % Specific surface area corrective factor
            % Surface of an oblate spheroid: https://en.wikipedia.org/wiki/Ellipsoid#Surface_area
            % Then you can deduce the analytical relationship below knowing the calculated, overestimated surface is a sum of 6 ellipoid area
            r=0.001:0.001:0.999; % Ratio short diameter / long diameters (oblate a:a:b, r=b/a, a>b)
            corrective_factor_3D = (1 + (r.^2 ./ ((1-r.^2).^0.5)).*atanh((1-r.^2).^0.5)) ./ (1+2*r);
            % For the perimeter of an ellispod, only approximation exist
            % Villarino, Mark B. (20 June 2005). "Ramanujan's Perimeter of an Ellipse". arXiv:math/0506384.
            a=r./r; % Does not matter as it is later normalized
            b=a.*r;
            e= (1 - (b.^2)./(a.^2)).^0.5;
            eps = (3.*a.*e.^20)./(2^36);
            Ramamujan = pi*((a+b) + (3*(a-b).^2)./(10.*(a+b) + (a.^2+14.*a.*b+b.^2).^0.5) + eps);
            Num = 4*a+4*b;
            corrective_factor_2D = Ramamujan./Num;
            % Perimeter of an ellipsoid based on Ramanujan's second method
            hold(app.Sp_correctivefactor_UIAxes,'on'); % Active subplot
            h_title=title(app.Sp_correctivefactor_UIAxes,'Corrective factor'); % Set title font
            % Plot graphs
            plot(app.Sp_correctivefactor_UIAxes,r,corrective_factor_3D,'Linestyle','-','Linewidth',2,'DisplayName','3D: a:a:b, a>b');
            plot(app.Sp_correctivefactor_UIAxes,r,corrective_factor_2D,'Linestyle','-','Linewidth',2,'DisplayName','2D: a:b, a>b');
            % legend
            hl = legend(app.Sp_correctivefactor_UIAxes,'Location','best');
            hl.FontSize = 14;
            hl.Location = 'best';
            hl.IconColumnWidth = 20;
            hl.LineWidth = 1;
            % Axis label
            xlabel(app.Sp_correctivefactor_UIAxes,'Short diameter b / long diameter(s) a');
            ylabel(app.Sp_correctivefactor_UIAxes,'Theoritical / voxel-based ratio');
            app.Sp_correctivefactor_UIAxes.XLabel.FontWeight = "bold";
            app.Sp_correctivefactor_UIAxes.YLabel.FontWeight = "bold";            
            % - Grid
            grid(app.Sp_correctivefactor_UIAxes,'on'); % Display grid
            set(app.Sp_correctivefactor_UIAxes,'XMinorGrid','off','YMinorGrid','off'); % Display grid for minor thicks
            set(app.Sp_correctivefactor_UIAxes,'FontName','times new roman','FontSize',14); % Fontname and fontsize
            h_title.FontSize = 14; % Set title fontsize
            hold(app.Sp_correctivefactor_UIAxes,'off'); % Relase figure            

            % Check python
            tmp = randi(5,5,5);
            try
                tmp = py.numpy.array(tmp);
            catch ME
                if (strcmp(ME.identifier,'MATLAB:Python:PyException'))
                    warning('Python was not loaded correctly, please restart MATLAB if you plan to calculate tortuosities.')
                end
            end           

        end

        % Value changed function: ModeDropDown
        function ModeDropDownValueChanged(app, event)
            app.SegmentationtypeDropDown.Items = {'semantic', 'instance', 'semantic and instance'};
            value = app.ModeDropDown.Value;
            app.macro_pars = [];
            if strcmp(value,'Set parameters for each volume independently')
                app.ClicktoimportmacroButton.Enable = 'off';
                app.NomacroloadedLabel.Enable = 'off';
                app.NomacroloadedLabel.Text = 'No macro loaded';
                app.ModeDropDown.Tooltip = 'Import file one by one, then set parameters for each volume in the other tabs.';
                app.VolumefolderEditField.Enable = 'on';
                app.ROI_UITable.ColumnEditable = [false,false,true,true,false,true];
                app.LowerScale_Nanoporosity_DropDown.Items = {'Binary','Mixed (uniform)','Mixed (heterogeneous)'};
                app.LowerScale_PoreTransport_DropDown.Items = {'Diffusivity D (uniform)','Diffusivity D (heterogeneous)','Bruggeman exponent p (uniform)'};
                app.LowerScale_SolidTransport_DropDown.Items = {'Conductivity K (uniform)','Conductivity K (heterogeneous)','Bruggeman exponent p (uniform)'};
                app.Airsaturation_uniform_DropDown.Items = {'Uniform','Heterogeneous'};                
            elseif strcmp(value,'Set parameters only once and apply to all volumes') 
                app.ClicktoimportmacroButton.Enable = 'off';
                app.NomacroloadedLabel.Enable = 'off';
                app.NomacroloadedLabel.Text = 'No macro loaded';
                app.ModeDropDown.Tooltip = 'Select all the files to analyse, then set parameter for only the first volume in the other tabs. The first volume should have the larger number of phases among all the volumes.';
                app.VolumefolderEditField.Enable = 'off';
                app.ROI_UITable.ColumnEditable = [false,false,false,false,false,true];
                app.LowerScale_Nanoporosity_DropDown.Items = {'Binary','Mixed (uniform)'};
                app.LowerScale_PoreTransport_DropDown.Items = {'Diffusivity D (uniform)','Bruggeman exponent p (uniform)'};
                app.LowerScale_SolidTransport_DropDown.Items = {'Conductivity K (uniform)','Bruggeman exponent p (uniform)'};
                app.Airsaturation_uniform_DropDown.Items = {'Uniform'};                
            elseif strcmp(value,'Apply macro''s parameters to all volumes') 
                app.ClicktoimportmacroButton.Enable = 'on';
                app.NomacroloadedLabel.Enable = 'on';
                app.NomacroloadedLabel.Text = 'No macro loaded';
                app.ModeDropDown.Tooltip = 'Import a macro, select all the files to analyse, then direcly go to the run tab without looking at the other tabs.';       
                app.VolumefolderEditField.Enable = 'off';
                app.ROI_UITable.ColumnEditable = [false,false,false,false,false,true];
                app.LowerScale_Nanoporosity_DropDown.Items = {'Binary','Mixed (uniform)'};
                app.LowerScale_PoreTransport_DropDown.Items = {'Diffusivity D (uniform)','Bruggeman exponent p (uniform)'};
                app.LowerScale_SolidTransport_DropDown.Items = {'Conductivity K (uniform)','Bruggeman exponent p (uniform)'};
                app.Airsaturation_uniform_DropDown.Items = {'Uniform'};                
            end
            app.SegmentationtypeDropDownValueChanged;
        end

        % Button pushed function: ClicktoimportmacroButton
        function ClicktoimportmacroButtonPushed(app, event)
            [macro_fileName,tmp_PathImport,~] = uigetfile({'*.mat','mat file (*.mat)'},'Select a macro .mat file previosuly saved with this module',pwd);
            if ~macro_fileName==0
                app.NomacroloadedLabel.Text = [tmp_PathImport macro_fileName];
                app.NomacroloadedLabel.Tooltip = app.NomacroloadedLabel.Text;
                app.PathImport = tmp_PathImport;

                app.macro_pars = [];
                is_a_macro = false;
                [~,~,ext] = fileparts(macro_fileName);
                if strcmp(ext,'.mat')
                    tmp = load(fullfile(tmp_PathImport,macro_fileName));
                    if isfield(tmp,'pars') && isfield(tmp,'description')
                        pars = tmp.pars;
                        if isfield(pars,'segmentation_type') && isfield(pars,'optc') && isfield(pars,'opts_sem') && isfield(pars,'opts_ins')
                            is_a_macro = true;
                        end
                    end
                end

                if is_a_macro
                    if ischar(tmp.description)
                        app.NomacroloadedLabel.Tooltip = [macro_fileName '. ' tmp.description];
                    else
                        var = [macro_fileName '. '];
                        des = char(tmp.description);
                        [n,~] = size(des);
                        if n>1
                            for k=1:n
                                var = [var des(k,:)];
                            end
                        else
                            var = [var des];
                        end
                        app.NomacroloadedLabel.Tooltip = var;
                    end
                    app.SegmentationtypeDropDown.Items = {pars.segmentation_type};
                    app.SegmentationtypeDropDownValueChanged;
                    app.macro_pars = pars;
                else
                    app.NomacroloadedLabel.Text = 'Error: not a macro!';
                    app.SegmentationtypeDropDown.Items = {'semantic', 'instance', 'semantic and instance'};
                end              
            else
                app.SegmentationtypeDropDown.Items = {'semantic', 'instance', 'semantic and instance'};
            end
        end

        % Value changed function: SegmentationtypeDropDown, 
        % ...and 1 other component
        function SegmentationtypeDropDownValueChanged(app, event)
            % Disable UI for all elements except for the Import tab
            app.GreyOut('off');        
            
            % Reset parameters
            app.number_volume = 1;
            app.inputvolume = [];
            app.FileName = [];

            app.current_semantic_microstructure = [];
            app.current_middleslice_semantic = [];
            app.semantic_fileinfo = [];
            app.pathnames_semantic = [];

            app.current_instance_microstructure = [];
            app.current_instanceBW_microstructure = [];
            app.current_middleslice_instance = [];
            app.instance_fileinfo = [];
            app.pathnames_instance = [];

            app.ROI = [];

            app.BulkpropertiesLabel.Enable = 'off';

            app.SelectscaleLabel.Enable = 'off';
            app.LowerScale_Nanoporosity_DropDown.Enable = 'off';
            app.Nanoporosity_file_Label.Text = 'No file imported';
            app.Nanoporosity_file_Label.Tooltip = 'No file imported';
            app.Nanoporosity_file_Label.Enable = 'off';
            app.ImportnanoporosityButton.Enable = 'off';
            app.nanoporosity_array = [];

            app.Bulkdiffusivity_file_Label.Text = 'No file imported';
            app.Bulkdiffusivity_file_Label.Tooltip = 'No file imported';
            app.Bulkdiffusivity_file_Label.Enable = 'off';   
            app.ImportporediffusivityButton.Enable = 'off';
            app.bulkdiffusivity_array = [];

            app.BulkConductivity_file_Label.Text = 'No file imported';
            app.BulkConductivity_file_Label.Tooltip = 'No file imported';
            app.BulkConductivity_file_Label.Enable = 'off';   
            app.ImportsolidconductivityButton.Enable = 'off';
            app.bulkconductivity_array = [];

            app.PartialwettingCheckBox.Enable = 'off';
            app.Airsaturation_file_Label.Text = 'No file imported';
            app.Airsaturation_file_Label.Tooltip = 'No file imported';            
            app.Airsaturation_file_Label.Enable = 'off';               
            app.Airsaturation_terminology_DropDown.Enable = 'off'; 
            app.Airsaturation_uniform_DropDown.Enable = 'off'; 
            app.ImportAirSaturation_Button.Enable = 'off';
            app.airsaturation_array = [];

            % Reset Import UI
            app.Semantic_NofileimportedLabel.Text = 'No file imported';
            app.Instance_NofileimportedLabel.Text = 'No file imported';
            app.Volumenumber_Label.Visible = 'off';
            app.Volumenumber_Label.Text = ['Volume number: ' num2str(app.number_volume,'%i')]; 

            app.VoxelsizeEditField.Enable = 'off'; app.VoxelsizeUnit_EditField.Enable = 'off'; app.ScalingfactorEditField.Enable = 'off';
            app.Scaledvoxelsize_Label.Text = 'Scaled voxel size: n/a';
            app.ROI_Label.Text = 'ROI: n/a';
            app.VolumefolderEditField.Value = 'Not defined';
            app.Mainfolder_Label.Text = 'Not defined';
            app.Import_indication.Text = [' '];   

            app.Import_SavesettingsButton.Enable = 'off';
            app.VisualizevolumeButton.Enable = 'off';app.PlotvolumefractionsButton.Enable = 'off';            

            % Tables
            app.Info_UITable.Data = [{'Material source';'Material name';'Raw data source';'Raw data technique';'Segmentation source';'Segmentation technique';'Volume description';'Analysis decription'} {'Not defined';'Not defined';'Not defined';'Not defined';'Not defined';'Not defined';'Not defined';'Not defined'}];
            app.ROI_UITable.Data = [];
            app.PhasecolornameSemantic_UITable.Data = [];
            app.PhasecolornameInstance_UITable.Data = [];
            app.Imported_UITable.Data = [];
            app.PhasecolornameInstance_UITable.Data = [];
            app.PhasecolornameSemantic_UITable.Data = [];
            app.AirSaturation_UITable.Data = []; app.LowerscaleTransport_UITable.Data = [];            
                        
            app.ROI_UITable.Enable = 'off';
            app.PhasecolornameInstance_UITable.Enable = 'off';
            app.PhasecolornameSemantic_UITable.Enable = 'off';
            app.AirSaturation_UITable.Enable = 'off';
            app.LowerScale_Nanoporosity_DropDown.Enable = 'off';
            app.LowerScale_PoreTransport_DropDown.Enable = 'off';
            app.LowerScale_SolidTransport_DropDown.Enable = 'off';         
            app.SelectscaleLabel.Enable = 'off';
            app.ForporeLabel.Enable = 'off';
            app.forsolidtransportDropDownLabel.Enable = 'off';
            app.LowerscaleTransport_UITable.Enable = 'off';
            app.Phasenamecolor_DropDown.Enable = 'off';

            value = app.SegmentationtypeDropDown.Value;
            if strcmp(value,'semantic')
                app.ClicktoimportsemanticsegmentationButton.Enable = 'on';
                app.ClicktoimportinstancesegmentationButton.Enable = 'off';
                app.Label_instancedescription.Text = 'n/a';
                app.Label_instancedescription.Enable = 'off';
                if app.ThereisabackgroundCheckBox.Value
                    app.ClicktoimportsemanticsegmentationButton.Tooltip = 'Label requirement: label 0 must be background, at least two other different labels';
                    app.Label_semanticdescription.Text = '1st label = background, 2nd label = 1st domain, 3rd label = 2nd domain, 4th label = 3rd domain, etc.';                    
                else
                    app.ClicktoimportsemanticsegmentationButton.Tooltip = 'Label requirement: at least two different labels';
                    app.Label_semanticdescription.Text = '1st label = 1st domain, 2nd label = 2nd domain, 3rd label = 3rd domain, etc.';
                end
                app.Label_semanticdescription.Enable = 'on';
                app.ClicktoimportinstancesegmentationButton.Tooltip = 'Label requirement: n/a';
                app.Semantic_NofileimportedLabel.Enable = 'on';
                app.Instance_NofileimportedLabel.Enable = 'off';
                app.FilesmustsharesamesizeLabel.Visible = 'off';
                app.Phasenamecolor_DropDown.Items = {'semantic segmentation'};      

            elseif strcmp(value,'instance')
                app.ClicktoimportsemanticsegmentationButton.Enable = 'off';
                app.ClicktoimportinstancesegmentationButton.Enable = 'on';
                app.ClicktoimportsemanticsegmentationButton.Tooltip = 'Label requirement: n/a';
                app.Label_semanticdescription.Text = 'n/a';
                app.Label_semanticdescription.Enable = 'off';
                if app.ThereisabackgroundCheckBox.Value
                    app.ClicktoimportinstancesegmentationButton.Tooltip = 'Label requirements: label 0 must be background, label 1 must be complementary volume of particles, label i (i>1) = instance i of union of cracks and particle from semantic segementation (i.e. particle shell instance).';
                     app.Label_instancedescription.Text = 'Label 0 = background, Label 1 = complementary volume, label 2 = 1st instance, label 3 = 2nd instance,..., label n = n-1 th instance';
                else
                    app.ClicktoimportinstancesegmentationButton.Tooltip = 'Label requirements: label 0 must be complementary volume of particles, label i (i>0) = instance i of union of cracks and particle from semantic segementation (i.e. particle shell instance).';
                     app.Label_instancedescription.Text = 'Label 0 = complementary volume, label 1 = 1st instance, label 2 = 2nd instance,..., label n = nth instance';
                end
                 app.Label_instancedescription.Enable = 'on';
                app.Semantic_NofileimportedLabel.Enable = 'off';
                app.Instance_NofileimportedLabel.Enable = 'on';   
                app.FilesmustsharesamesizeLabel.Visible = 'off';
                app.Phasenamecolor_DropDown.Items = {'instance segmentation'};
                                                
            elseif strcmp(value,'semantic and instance')
                app.ClicktoimportsemanticsegmentationButton.Enable = 'on'; app.Label_semanticdescription.Enable = 'on';
                app.ClicktoimportinstancesegmentationButton.Enable = 'on'; app.Label_instancedescription.Enable = 'on';  
                if app.ThereisabackgroundCheckBox.Value
                    app.ClicktoimportsemanticsegmentationButton.Tooltip = 'Label requirements: label 0 must be background, label 1  must be pore, label 2 must be cracks within particle shells, label 3 must be particles, and subsequent labels can be any other domains (e.g., additives)';
                    app.ClicktoimportinstancesegmentationButton.Tooltip = 'Label requirements: label 0 must be background, label 1 must be complementary volume of particles, label i (i>1) = instance i of union of cracks and particle from semantic segementation (i.e. particle shell instance).';
                    app.Label_semanticdescription.Text = '1st label = background, 2nd label = 1st domain, 3rd label = 2nd domain, 4th label = 3rd domain, etc.';                    
                    app.Label_instancedescription.Text = 'Label 0 = background, Label 1 = complementary volume, label 2 = 1st instance, label 3 = 2nd instance,..., label n = n-1 th instance';
                else
                    app.ClicktoimportsemanticsegmentationButton.Tooltip = 'Label requirements: label 0  must be pore, label 1 must be cracks within particle shells, label 2 must be particles, and subsequent labels can be any other domains (e.g., additives)';
                    app.ClicktoimportinstancesegmentationButton.Tooltip = 'Label requirements: label 0 must be complementary volume of particles, label i (i>0) = instance i of union of cracks and particle from semantic segementation (i.e. particle shell instance).';
                    app.Label_semanticdescription.Text = '1st label = 1st domain, 2nd label = 2nd domain, 3rd label = 3rd domain, etc.';
                    app.Label_instancedescription.Text = 'Label 0 = complementary volume, label 1 = 1st instance, label 2 = 2nd instance,..., label n = nth instance';
                end
                app.Semantic_NofileimportedLabel.Enable = 'on';
                app.Instance_NofileimportedLabel.Enable = 'on'; 
                if strcmp(app.ModeDropDown.Value,'Set parameters for each volume independently')
                    app.FilesmustsharesamesizeLabel.Text = 'Files must share same size';
                else
                    app.FilesmustsharesamesizeLabel.Text = 'Each pair of files must share same size. Each pair of file must share the same substring before the last "_" of their name (e.g. if file_1.tif is the semantic file for the first pair of file, the instance filename is either file_1.tif (located in another folder) or file_1_<whatever string without underscore>.tif).';
                end
                app.FilesmustsharesamesizeLabel.FontColor = 'k';
                app.FilesmustsharesamesizeLabel.Visible = 'on';
                app.Phasenamecolor_DropDown.Items = {'semantic segmentation' 'instance segmentation'};
                app.FilesmustsharesamesizeLabel.Tooltip = app.FilesmustsharesamesizeLabel.Text;
            end       
        end

        % Button pushed function: ClicktoimportsemanticsegmentationButton
        function ClicktoimportsemanticsegmentationButtonPushed(app, event)
            outcome = false;

            % Reset
            app.current_semantic_microstructure=[];
            app.current_middleslice_semantic = [];
            app.semantic_fileinfo = [];
            app.FileName = [];

            % Wetting
            app.PartialwettingCheckBox.Enable = 'off';
            app.Airsaturation_file_Label.Text = 'No file imported';
            app.Airsaturation_file_Label.Tooltip = 'No file imported';            
            app.Airsaturation_file_Label.Enable = 'off';               
            app.Airsaturation_terminology_DropDown.Enable = 'off'; 
            app.Airsaturation_uniform_DropDown.Enable = 'off'; 
            app.ImportAirSaturation_Button.Enable = 'off';
            app.airsaturation_array = [];         

            % % Scales
            app.BulkpropertiesLabel.Enable = 'off';
            % Nanoporosity
            app.SelectscaleLabel.Enable = 'off';
            app.LowerScale_Nanoporosity_DropDown.Enable = 'off';
            app.Nanoporosity_file_Label.Text = 'No file imported';
            app.Nanoporosity_file_Label.Tooltip = 'No file imported';
            app.Nanoporosity_file_Label.Enable = 'off';
            app.ImportnanoporosityButton.Enable = 'off';
            app.nanoporosity_array = [];

            % Pore transport
            app.ForporeLabel.Enable = 'off';
            app.LowerScale_PoreTransport_DropDown.Enable = 'off';
            app.Bulkdiffusivity_file_Label.Text = 'No file imported';
            app.Bulkdiffusivity_file_Label.Tooltip = 'No file imported';
            app.Bulkdiffusivity_file_Label.Enable = 'off';   
            app.ImportporediffusivityButton.Enable = 'off';
            app.bulkdiffusivity_array = [];

            % Solid transport
            app.forsolidtransportDropDownLabel.Enable = 'off';
            app.LowerScale_SolidTransport_DropDown.Enable = 'off';
            app.BulkConductivity_file_Label.Text = 'No file imported';
            app.BulkConductivity_file_Label.Tooltip = 'No file imported';
            app.BulkConductivity_file_Label.Enable = 'off';   
            app.ImportsolidconductivityButton.Enable = 'off';
            app.bulkconductivity_array = [];            
           
            % Open dialog box to choose file path
            if strcmp(app.ModeDropDown.Value,'Set parameters for each volume independently') % Load one file
                if isempty(app.PathImport)
                    [app.FileName,tmp_PathImport,~] = uigetfile({'*.tif;*.tiff','Tif image (*.tif, *.tiff)'},'Select a .tif file (semantic segmentation)',pwd);
                else
                    [app.FileName,tmp_PathImport,~] = uigetfile({'*.tif;*.tiff','Tif image (*.tif, *.tiff)'},'Select a .tif file (semantic segmentation)',app.PathImport);
                end
                if ~app.FileName==0
                    app.Semantic_NofileimportedLabel.Text = 'Loading file... please wait'; pause(0.1);
                    [tmp,outcome] = function_load_tif([tmp_PathImport app.FileName]);
                    if outcome
                        sz = size(tmp);
                        app.semantic_fileinfo.sz = sz;
                        if length(sz)==3
                            app.current_middleslice_semantic = tmp(:,:,round(sz(3)/2));
                        else
                            app.current_middleslice_semantic = tmp;
                        end
                        app.semantic_fileinfo.nphase = length(unique(tmp));
                    end
                end

            else % Select multiple files
                % Douglas Schwarz (2022). uipickfiles: uigetfile on steroids (https://www.mathworks.com/matlabcentral/fileexchange/10867-uipickfiles-uigetfile-on-steroids), MATLAB Central File Exchange. Retrieved September 29, 2022.
                % Lisence in 'Third-party licenses' folder
                str_dialogbox = 'Select all .tif files (semantic segmentation). You will be able to set parameters only on the first file.';
                if isempty(app.PathImport)
                    app.pathnames_semantic = uipickfiles('FilterSpec',pwd,'REFilter','\.tif$|\.tiff$','Prompt',str_dialogbox);
                else
                    app.pathnames_semantic = uipickfiles('FilterSpec',app.PathImport,'REFilter','\.tif$|\.tiff$','Prompt',str_dialogbox);
                end
                if iscell(app.pathnames_semantic)
                    app.Semantic_NofileimportedLabel.Text = 'Checking files size...'; pause(0.01);
                    for k = length(app.pathnames_semantic):-1:1
                        [sz,outcome] = function_checksize_tif(char(app.pathnames_semantic(k)));
                        app.semantic_fileinfo(k).sz = sz;
                        if ~outcome
                            app.Semantic_NofileimportedLabel.Text = ['File ' char(app.pathnames_semantic(k)) 'can not be read'];
                            break
                        end
                    end
                    app.Semantic_NofileimportedLabel.Text = 'Loading only the first file... please wait'; pause(0.1);
                    [tmp,outcome] = function_load_tif(char(app.pathnames_semantic(1)));
                    [tmp_PathImport,app.FileName,~] = fileparts( char(app.pathnames_semantic(1)) );   
                    app.semantic_fileinfo(1).nphase = length(unique(tmp));
                    if ispc
                        tmp_PathImport = [tmp_PathImport '\'];
                    else
                        tmp_PathImport = [tmp_PathImport '/'];
                    end
                end
            end

            if outcome % Success to import
                app.PathImport = tmp_PathImport;
                app.current_semantic_microstructure = tmp;
                app.Semantic_NofileimportedLabel.Text = [app.PathImport app.FileName];
                app.check_dimensions;

                % Fill direction table
                app.fill_ROI_UITable(app.current_semantic_microstructure);

                % Voxel size info
                app.VoxelsizeEditField.Enable = 'on'; app.VoxelsizeUnit_EditField.Enable = 'on'; app.ScalingfactorEditField.Enable = 'on';
                app.VoxelsizeEditFieldValueChanged;

                % Fill volume fraction table
                app.Phasenamecolor_DropDown.Value = 'semantic segmentation';
                app.Initialize_Phasecolorname_UITable;

                % Lowerscale
                [nphase,~] = size(app.PhasecolornameSemantic_UITable.Data);
                app.LowerscaleTransport_UITable.Data = cell(nphase,2);
                app.BulkpropertiesLabel.Enable = 'on';
                app.SelectscaleLabel.Enable = 'on';
                app.LowerScale_Nanoporosity_DropDown.Enable = 'on';
                app.LowerScale_Nanoporosity_DropDownValueChanged;
                app.ForporeLabel.Enable = 'on';
                app.LowerScale_PoreTransport_DropDown.Enable = 'on';
                app.LowerScale_PoreTransport_DropDownValueChanged;
                app.forsolidtransportDropDownLabel.Enable = 'on';
                app.LowerScale_SolidTransport_DropDown.Enable = 'on';
                app.LowerScale_SolidTransport_DropDownValueChanged;

                % Air saturation
                app.PartialwettingCheckBox.Enable = 'on';
                app.PartialwettingCheckBoxValueChanged;               

                % Volume info table
                if ~isempty(app.volumeinfo_sav)
                    app.Info_UITable.Data = app.volumeinfo_sav;
                else
                    app.Info_UITable.Data = [{'Material source';'Material name';'Volume description';'Raw data source';'Raw data technique';'Segmentation source';'Segmentation technique';'Analysis decription'} {'Not defined';'Not defined';'Not defined';'Not defined';'Not defined';'Not defined';'Not defined';'Not defined'}];
                end

                % Saving folder
                if ~isempty(app.mainfolder)
                    app.Mainfolder_Label.Text = app.mainfolder;
                else
                    app.Mainfolder_Label.Text = app.PathImport;
                end
                app.Mainfolder_Label.Tooltip = app.Mainfolder_Label.Text;
                app.mainfolder = app.Mainfolder_Label.Text;
            end
            if ~outcome
                % User clicked cancel button or closed the dialog box or could not import file
                app.current_semantic_microstructure=[];
                app.Semantic_NofileimportedLabel.Text ='No file imported.';
                app.Volumenumber_Label.Visible = 'off';
                if isempty(app.current_instance_microstructure)
                    app.VoxelsizeEditField.Enable = 'off'; app.VoxelsizeUnit_EditField.Enable = 'off'; app.ScalingfactorEditField.Enable = 'off';
                    app.Scaledvoxelsize_Label.Text = 'Scaled voxel size: n/a';
                    app.ROI_Label.Text = 'ROI: n/a';                              
                    app.ROI_UITable.Data = []; app.ROI_UITable.Enable = 'off';
                end
                app.PhasecolornameSemantic_UITable.Data = []; app.PhasecolornameSemantic_UITable.Enable = 'off';
                app.AirSaturation_UITable.Data = []; app.AirSaturation_UITable.Enable = 'off';
                app.LowerscaleTransport_UITable.Data = []; app.LowerscaleTransport_UITable.Enable = 'off';
                app.Mainfolder_Label.Text = 'Not defined';
                app.VolumefolderEditField.Value = 'Not defined';
                app.Import_SavesettingsButton.Enable = 'off';
                app.Info_UITable.Data = [{'Material source';'Material name';'Volume description';'Raw data source';'Raw data technique';'Segmentation source';'Segmentation technique';'Analysis decription'} {'Not defined';'Not defined';'Not defined';'Not defined';'Not defined';'Not defined';'Not defined';'Not defined'}];
            end
            app.check_import;
            app.Semantic_NofileimportedLabel.Tooltip = app.Semantic_NofileimportedLabel.Text;
        end

        % Button pushed function: ClicktoimportinstancesegmentationButton
        function ClicktoimportinstancesegmentationButtonPushed(app, event)
            outcome = false;
            % Reset
            app.current_instance_microstructure=[];
            app.current_instanceBW_microstructure=[];
            app.current_middleslice_instance = [];
            app.instance_fileinfo = [];
            app.FileName = [];

            % Open dialog box to choose file path
            if strcmp(app.ModeDropDown.Value,'Set parameters for each volume independently') % Load one file
                if isempty(app.PathImport)
                    [app.FileName,tmp_PathImport,~] = uigetfile({'*.tif;*.tiff','Tif image (*.tif, *.tiff)'},'Select a .tif file (instance segmentation)',pwd);
                else
                    [app.FileName,tmp_PathImport,~] = uigetfile({'*.tif;*.tiff','Tif image (*.tif, *.tiff)'},'Select a .tif file (instance segmentation)',app.PathImport);
                end
                if ~app.FileName==0
                    app.Instance_NofileimportedLabel.Text = 'Loading file... please wait'; pause(0.1);
                    [tmp,outcome] = function_load_tif([tmp_PathImport app.FileName],'uint32');
                    if outcome
                        tmp = fct_intconvert(tmp);
                        sz = size(tmp);
                        app.instance_fileinfo.sz = sz;
                        if length(sz)==3
                            app.current_middleslice_instance = tmp(:,:,round(sz(3)/2));
                        else
                            app.current_middleslice_instance = tmp;
                        end
                        app.instance_fileinfo.ninstance = length(unique(tmp));
                    end
                end

            else % Select multiple files
                % Douglas Schwarz (2022). uipickfiles: uigetfile on steroids (https://www.mathworks.com/matlabcentral/fileexchange/10867-uipickfiles-uigetfile-on-steroids), MATLAB Central File Exchange. Retrieved September 29, 2022.
                % Lisence in 'Third-party licenses' folder
                str_dialogbox = 'Select all .tif files (instance segmentation). You will be able to set parameters only on the first file.';
                if isempty(app.PathImport)
                    app.pathnames_instance = uipickfiles('FilterSpec',pwd,'REFilter','\.tif$|\.tiff$','Prompt',str_dialogbox);
                else
                    app.pathnames_instance = uipickfiles('FilterSpec',app.PathImport,'REFilter','\.tif$|\.tiff$','Prompt',str_dialogbox);
                end
                if iscell(app.pathnames_instance)
                    app.Instance_NofileimportedLabel.Text = 'Checking files size...'; pause(0.01);
                    for k = length(app.pathnames_instance):-1:1
                        [sz,outcome] = function_checksize_tif(char(app.pathnames_instance(k)));
                        app.instance_fileinfo(k).sz = sz;
                        if ~outcome
                            app.Instance_NofileimportedLabel.Text = ['File ' char(app.pathnames_instance(k)) 'can not be read'];
                            break
                        end
                    end
                    app.Instance_NofileimportedLabel.Text = 'Loading only the first file... please wait'; pause(0.1);
                    [tmp,outcome] = function_load_tif(char(app.pathnames_instance(1)));
                    [tmp_PathImport,app.FileName,~] = fileparts( char(app.pathnames_instance(1)) );   
                    app.instance_fileinfo(1).ninstance = length(unique(tmp));
                    if ispc
                        tmp_PathImport = [tmp_PathImport '\'];
                    else
                        tmp_PathImport = [tmp_PathImport '/'];
                    end
                end
            end

            if outcome
                app.PathImport = tmp_PathImport;
                app.current_instance_microstructure = tmp;
                if app.ThereisabackgroundCheckBox.Value
                    app.current_instanceBW_microstructure = app.current_instance_microstructure;
                    app.current_instanceBW_microstructure(app.current_instance_microstructure>1)=2;
                else
                    app.current_instanceBW_microstructure = zeros(size(app.current_instance_microstructure));
                    app.current_instanceBW_microstructure(app.current_instance_microstructure~=0)=1;
                end
                app.Instance_NofileimportedLabel.Text = [app.PathImport app.FileName];
                app.check_dimensions;

                % Fill direction table
                app.fill_ROI_UITable(app.current_instance_microstructure);

                % Voxel size info
                app.VoxelsizeEditField.Enable = 'on'; app.VoxelsizeUnit_EditField.Enable = 'on'; app.ScalingfactorEditField.Enable = 'on';
                app.VoxelsizeEditFieldValueChanged;

                % Fill volume fraction table
                app.Phasenamecolor_DropDown.Value = 'instance segmentation';
                app.Initialize_Phasecolorname_UITable;

                % Volume info table
                if ~isempty(app.volumeinfo_sav)
                    app.Info_UITable.Data = app.volumeinfo_sav;
                else
                    app.Info_UITable.Data = [{'Material source';'Material name';'Volume description';'Raw data source';'Raw data technique';'Segmentation source';'Segmentation technique';'Analysis decription'} {'Not defined';'Not defined';'Not defined';'Not defined';'Not defined';'Not defined';'Not defined';'Not defined'}];
                end

                % Saving folder
                if ~isempty(app.mainfolder)
                    app.Mainfolder_Label.Text = app.mainfolder;
                else
                    app.Mainfolder_Label.Text = app.PathImport;
                end
                app.Mainfolder_Label.Tooltip = app.Mainfolder_Label.Text;
                app.mainfolder = app.Mainfolder_Label.Text;
            end
            if ~outcome
                % User clicked cancel button or closed the dialog box or could not import file
                app.Instance_NofileimportedLabel.Text ='No file imported.';
                app.current_instance_microstructure=[];
                app.current_instanceBW_microstructure=[];
                app.Volumenumber_Label.Visible = 'off';
                if isempty(app.current_semantic_microstructure)
                    app.VoxelsizeEditField.Enable = 'off'; app.VoxelsizeUnit_EditField.Enable = 'off'; app.ScalingfactorEditField.Enable = 'off';
                    app.Scaledvoxelsize_Label.Text = 'Scaled voxel size: n/a';
                    app.ROI_Label.Text = 'ROI: n/a';                              
                    app.ROI_UITable.Data = [];
                end
                app.PhasecolornameInstance_UITable.Data = [];
                app.Mainfolder_Label.Text = 'Not defined';
                app.VolumefolderEditField.Value = 'Not defined';
                app.Import_SavesettingsButton.Enable = 'off';
                app.Info_UITable.Data = [{'Material source';'Material name';'Volume description';'Raw data source';'Raw data technique';'Segmentation source';'Segmentation technique';'Analysis decription'} {'Not defined';'Not defined';'Not defined';'Not defined';'Not defined';'Not defined';'Not defined';'Not defined'}];
            end

            app.check_import;
            app.Instance_NofileimportedLabel.Tooltip = app.Instance_NofileimportedLabel.Text;            
        end

        % Value changed function: ScalingfactorEditField, 
        % ...and 2 other components
        function VoxelsizeEditFieldValueChanged(app, event)
            voxel_size = app.VoxelsizeEditField.Value;
            scaling_factor = app.ScalingfactorEditField.Value;
            voxel_sizeunit = app.VoxelsizeUnit_EditField.Value;
            if strcmp(app.ModeDropDown.Value,'Set parameters for each volume independently')
                ROI_length = cell2mat(app.ROI_UITable.Data(:,5));
            else
                if strcmp(app.SegmentationtypeDropDown.Value,'semantic')
                    ROI_length = app.semantic_fileinfo(1).sz;
                else
                    ROI_length = app.instance_fileinfo(1).sz;
                end
            end
            dimension = length(ROI_length);
            nvoxels = floor(ROI_length./scaling_factor);
            new_voxel_size = voxel_size*scaling_factor;
            app.Scaledvoxelsize_Label.Text = ['Scaled voxel size: ' num2str(new_voxel_size,'%1.3f') voxel_sizeunit];
            length_root = (prod(nvoxels*new_voxel_size)).^(1/dimension);
            if dimension == 2
                str_ROI = ['ROI: ' num2str(nvoxels(1),'%i') ' x ' num2str(nvoxels(2),'%i') ' voxels, ' num2str(nvoxels(1)*new_voxel_size,'%1.1f') ' x ' num2str(nvoxels(2)*new_voxel_size,'%1.1f') ' ' voxel_sizeunit num2str(dimension) ', square root = ' num2str(length_root,'%1.1f') voxel_sizeunit];
                str_ROI_short = [num2str(nvoxels(1),'%i') ' x ' num2str(nvoxels(2),'%i') ' voxels, ' num2str(nvoxels(1)*new_voxel_size,'%1.1f') ' x ' num2str(nvoxels(2)*new_voxel_size,'%1.1f') ' ' voxel_sizeunit num2str(dimension)];
            else
                str_ROI = ['ROI: ' num2str(nvoxels(1),'%i') ' x ' num2str(nvoxels(2),'%i') ' x ' num2str(nvoxels(3),'%i') ' voxels, ' num2str(nvoxels(1)*new_voxel_size,'%1.1f') ' x ' num2str(nvoxels(2)*new_voxel_size,'%1.1f') ' x ' num2str(nvoxels(3)*new_voxel_size,'%1.1f') ' ' voxel_sizeunit num2str(dimension) ', cubic root = ' num2str(length_root,'%1.1f') voxel_sizeunit];
                str_ROI_short = [num2str(nvoxels(1),'%i') ' x ' num2str(nvoxels(2),'%i') ' x ' num2str(nvoxels(3),'%i') ' voxels, ' num2str(nvoxels(1)*new_voxel_size,'%1.1f') ' x ' num2str(nvoxels(2)*new_voxel_size,'%1.1f') ' x ' num2str(nvoxels(3)*new_voxel_size,'%1.1f') ' ' voxel_sizeunit num2str(dimension)];
            end
            app.ROI_Label.Text = str_ROI;
            
            app.ROI.lengthroot = [num2str(length_root) ' ' voxel_sizeunit];
            app.ROI.nvoxels = prod(nvoxels);
            app.ROI.str = str_ROI_short;

            [~,name,~] = fileparts(app.FileName);
            new_voxel_size = round(new_voxel_size,3);
            new_voxel_size = num2str(new_voxel_size);
            new_voxel_size = strrep(new_voxel_size,'.','p');
            length_root = round(length_root,1);
            length_root = num2str(length_root);
            length_root = strrep(length_root,'.','p');    

            str_volumefolder = [name '_voxel_' new_voxel_size voxel_sizeunit '_ROI_' length_root voxel_sizeunit];
            app.VolumefolderEditField.Value = str_volumefolder;  

            app.update_volumefractions; 
        end

        % Cell edit callback: ROI_UITable
        function ROI_UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            oldData = event.PreviousData;
            if ~isempty(app.current_semantic_microstructure)
                sz = size(app.current_semantic_microstructure);
            elseif ~isempty(app.current_instance_microstructure)
                sz = size(app.current_instance_microstructure);
            end

            if indices(2)==3 || indices(2)==4 % ROI
                if isnan(newData) || newData~=round(newData) || newData<1 || newData>sz(indices(1))
                    app.ROI_UITable.Data(indices(1),indices(2)) = {oldData};
                end
                ROI = cell2mat(app.ROI_UITable.Data(:,3:5)); % Extract array
                ROI(:,1:2) = sort(ROI(:,1:2),2); % Start < End
                ROI(:,3) = ROI(:,2) - ROI(:,1) + 1;
                app.ROI_UITable.Data(:,3:5)= num2cell(ROI); % Insert table
                app.VoxelsizeEditFieldValueChanged;
                if strcmp(app.LowerScale_Nanoporosity_DropDown.Value,'Mixed (heterogeneous)')
                    if ~isempty(app.nanoporosity_array)
                        res = num2cell(app.get_average(app.nanoporosity_array));
                        if app.ThereisabackgroundCheckBox.Value
                            res(1) = {'n/a'};
                        end
                        app.PhasecolornameSemantic_UITable.Data(:,6) = res;
                    end
                end
                if strcmp(app.LowerScale_PoreTransport_DropDown.Value,'Diffusivity D (heterogeneous)')
                    if ~isempty(app.bulkdiffusivity_array)
                        res = num2cell(app.get_average(app.bulkdiffusivity_array));
                        if app.ThereisabackgroundCheckBox.Value
                            res(1) = {'n/a'};
                        end
                        app.LowerscaleTransport_UITable.Data(:,1) = res;
                    end
                end
                if strcmp(app.LowerScale_SolidTransport_DropDown.Value,'Conductivity K (heterogeneous)')
                    if ~isempty(app.bulkconductivity_array)
                        res = num2cell(app.get_average(app.bulkconductivity_array));
                        if app.ThereisabackgroundCheckBox.Value
                            res(1) = {'n/a'};
                        end
                        app.LowerscaleTransport_UITable.Data(:,2) = res;
                    end
                end
                if ~isempty(app.airsaturation_array)
                    res = num2cell(app.get_average(app.airsaturation_array));
                    if app.ThereisabackgroundCheckBox.Value
                        res(1) = {'n/a'};
                    end
                    app.AirSaturation_UITable.Data = res;
                end
            end
            
        end

        % Cell edit callback: PhasecolornameSemantic_UITable
        function PhasecolornameSemantic_UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            oldData = event.PreviousData;
            if indices(2)==6 % Nanoporosity
                if strcmp(app.LowerScale_Nanoporosity_DropDown.Value,'Binary') % 0 or 1
                    if isnan(newData) || (newData~=0.0 && newData~=1.0)
                        app.PhasecolornameSemantic_UITable.Data(indices(1),indices(2)) = {oldData};
                    end
                else % [0,1]
                    if isnan(newData) || newData<0.0 || newData>1.0
                        app.PhasecolornameSemantic_UITable.Data(indices(1),indices(2)) = {oldData};
                    end
                end
                app.PartialwettingCheckBoxValueChanged;
                app.LowerScale_PoreTransport_DropDownValueChanged;
                app.LowerScale_SolidTransport_DropDownValueChanged;
            end            
        end

        % Cell selection callback: PhasecolornameSemantic_UITable
        function PhasecolornameSemantic_UITableCellSelection(app, event)
            indices = event.Indices;
            if ~isempty(indices)
                if indices(2)==3 % color
                    c = uisetcolor;
                    if length(c)==3 % RGB
                        app.color_phase_semantic(indices(1),:) = c;
                        s = uistyle('BackgroundColor',app.color_phase_semantic(indices(1),:));
                        addStyle(app.PhasecolornameSemantic_UITable,s,'cell',[indices(1),3]);
                    end
                end
            end
        end

        % Value changed function: Phasenamecolor_DropDown
        function Phasenamecolor_DropDownValueChanged(app, event)
            if strcmp(app.Phasenamecolor_DropDown.Value,'semantic segmentation')
                app.PhasecolornameSemantic_UITable.Visible = 'on';
                app.PhasecolornameSemantic_UITable.Enable = 'on';
                app.PhasecolornameInstance_UITable.Visible = 'off';
                app.PhasecolornameInstance_UITable.Enable = 'off';
                app.AirSaturation_UITable.Visible = 'on';     
                app.LowerScale_PoreTransport_DropDown.Visible = 'on';
                app.LowerScale_PoreTransport_DropDownValueChanged;
                app.LowerscaleTransport_UITable.Visible = 'on';
            elseif strcmp(app.Phasenamecolor_DropDown.Value,'instance segmentation')
                app.PhasecolornameSemantic_UITable.Visible = 'off';
                app.PhasecolornameSemantic_UITable.Enable = 'off';
                app.PhasecolornameInstance_UITable.Visible = 'on';
                app.PhasecolornameInstance_UITable.Enable = 'on';
                app.LowerScale_PoreTransport_DropDown.Visible = 'off';
                app.AirSaturation_UITable.Visible = 'off'; app.LowerscaleTransport_UITable.Visible = 'off';
            end            
        end

        % Button pushed function: VisualizevolumeButton
        function VisualizevolumeButtonPushed(app, event)
            voxel_size = app.VoxelsizeEditField.Value;
            scaling_factor = app.ScalingfactorEditField.Value;
            new_voxel_size = voxel_size*scaling_factor;
            voxelsize_unitname = app.VoxelsizeUnit_EditField.Value;
            arrayunit = '';  

            if ~isempty(app.current_semantic_microstructure)
                % ROI and scaling
                Msem = Prepare_visualization(app,app.current_semantic_microstructure);
            end
            if ~isempty(app.current_instance_microstructure)
                % ROI and scaling
                Mins = Prepare_visualization(app,app.current_instance_microstructure);
                if app.ThereisabackgroundCheckBox.Value
                    MinsBW = Mins;
                    MinsBW(Mins>1)=2;
                else
                    MinsBW = zeros(size(Mins));
                    MinsBW(Mins~=0)=1;
                end
            end            

            if strcmp(app.visualize_resolution_withDropDown.Value,'after scaling')
                voxel_size = new_voxel_size;
            end

            if ~isempty(app.current_semantic_microstructure) && isempty(app.current_instance_microstructure)
                [~,filename,~] = fileparts(app.Semantic_NofileimportedLabel.Text);
                microstructure_visualization_slices(Msem, filename, voxel_size, voxelsize_unitname, arrayunit);
            elseif isempty(app.current_semantic_microstructure) && ~isempty(app.current_instance_microstructure)
                [~,filename,~] = fileparts(app.Instance_NofileimportedLabel.Text);
                p(1).name = filename;
                p(2).name = [p(1).name ', binarized'];                
                Visualization_multiple_volumes(p,Mins,MinsBW);
            elseif ~isempty(app.current_semantic_microstructure) && ~isempty(app.current_instance_microstructure)
                [~,filename,~] = fileparts(app.Semantic_NofileimportedLabel.Text);
                p(1).name = filename;
                [~,filename,~] = fileparts(app.Instance_NofileimportedLabel.Text);
                p(2).name = filename;    
                p(3).name = [p(2).name ', binarized'];  
                Visualization_multiple_volumes(p,Msem,Mins,MinsBW);
            end
        end

        % Button pushed function: PlotvolumefractionsButton
        function PlotvolumefractionsButtonPushed(app, event)
            app.PlotvolumefractionsButton.Text = 'Please wait...'; pause(0.1);
            voxel_size = app.VoxelsizeEditField.Value;
            scaling_factor = app.ScalingfactorEditField.Value;
            new_voxel_size = voxel_size*scaling_factor;

            if ~isempty(app.current_semantic_microstructure)
                % ROI and scaling
                Msem = Prepare_visualization(app,app.current_semantic_microstructure);
            end
            if ~isempty(app.current_instance_microstructure)
                % ROI and scaling
                Mins = Prepare_visualization(app,app.current_instance_microstructure);
                if app.ThereisabackgroundCheckBox.Value
                    MinsBW = Mins;
                    MinsBW(Mins>1)=2;
                else
                    MinsBW = zeros(size(Mins));
                    MinsBW(Mins~=0)=1;
                end
            end            

            if strcmp(app.visualize_resolution_withDropDown.Value,'after scaling')
                voxel_size = new_voxel_size;
            end

            if ~isempty(app.current_semantic_microstructure) && isempty(app.current_instance_microstructure)
                [~,filename,~] = fileparts(app.Semantic_NofileimportedLabel.Text);
                app.function_vf_alongthickness(Msem,app.ThereisabackgroundCheckBox.Value,voxel_size,filename);
            elseif isempty(app.current_semantic_microstructure) && ~isempty(app.current_instance_microstructure)
                [~,filename,~] = fileparts(app.Instance_NofileimportedLabel.Text);
                app.function_vf_alongthickness(MinsBW,app.ThereisabackgroundCheckBox.Value,voxel_size,filename);
            elseif ~isempty(app.current_semantic_microstructure) && ~isempty(app.current_instance_microstructure)
                app.Phasenamecolor_DropDown.Value = 'semantic segmentation';
                app.Phasenamecolor_DropDownValueChanged;                
                [~,filename,~] = fileparts(app.Semantic_NofileimportedLabel.Text);
                app.function_vf_alongthickness(Msem,app.ThereisabackgroundCheckBox.Value,voxel_size,filename);       

                app.Phasenamecolor_DropDown.Value = 'instance segmentation';
                app.Phasenamecolor_DropDownValueChanged;    
                [~,filename,~] = fileparts(app.Instance_NofileimportedLabel.Text);
                filename = [filename ', binarized'];
                app.function_vf_alongthickness(MinsBW,app.ThereisabackgroundCheckBox.Value,voxel_size,filename);
            end  
            app.PlotvolumefractionsButton.Text = 'Plot volume fractions';
        end

        % Button pushed function: Import_SavesettingsButton
        function Import_SavesettingsButtonPushed(app, event)
            if strcmp(app.ModeDropDown.Value,'Set parameters for each volume independently')
                n0 = app.number_volume;
                n1 = app.number_volume;
            else
                app.inputvolume = [];
                n0 = 1;
                if strcmp(app.SegmentationtypeDropDown.Value,'semantic')
                    n1 = length(app.pathnames_semantic);
                elseif strcmp(app.SegmentationtypeDropDown.Value,'instance')
                    n1 = length(app.pathnames_instance);
                elseif strcmp(app.SegmentationtypeDropDown.Value,'semantic and instance')
                    n1_sem = length(app.pathnames_semantic);
                    n1_ins = length(app.pathnames_instance);
                    if n1_sem ~= n1_ins
                        app.Import_indication.Text = 'Number of semantic and instance files is different!';
                        app.Import_indication.FontColor = 'r';
                        app.inputvolume = [];
                        return;
                    else
                        n1 = n1_sem;
                    end
                end 
            end

            app.inputvolume.mode = app.ModeDropDown.Value;
            app.inputvolume.segmentation_type = app.SegmentationtypeDropDown.Value;            

            for n = n0:1:n1
                app.inputvolume.volume(n).orderofcalculation=app.number_volume;
                app.inputvolume.volume(n).isbackground = app.ThereisabackgroundCheckBox.Value;

                if strcmp(app.ModeDropDown.Value,'Set parameters for each volume independently')
                    if strcmp(app.SegmentationtypeDropDown.Value,'semantic') || strcmp(app.SegmentationtypeDropDown.Value,'semantic and instance')
                        app.inputvolume.volume(n).loadingpath_semantic = app.Semantic_NofileimportedLabel.Text;
                        app.inputvolume.volume(n).nphase = app.semantic_fileinfo.nphase;     

                        % Nanoporosity
                        app.inputvolume.volume(n).nanoporosity_representation = app.LowerScale_Nanoporosity_DropDown.Value;
                        app.inputvolume.volume(n).nanoporosity = app.PhasecolornameSemantic_UITable.Data(:,6);
                        app.inputvolume.volume(n).loadingpath_nanoporosity = [];
                        if strcmp(app.LowerScale_Nanoporosity_DropDown.Value,'Mixed (heterogeneous)')
                            app.inputvolume.volume(n).loadingpath_nanoporosity = app.nanoporosity_path;
                        end

                        % Pore transport
                        app.inputvolume.volume(n).poretransport_representation = app.LowerScale_PoreTransport_DropDown.Value;
                        app.inputvolume.volume(n).poretransport = app.LowerscaleTransport_UITable.Data(:,1);
                        app.inputvolume.volume(n).loadingpath_diffusivity = [];
                        if strcmp(app.LowerScale_PoreTransport_DropDown.Value,'Diffusivity D (heterogeneous)')
                            app.inputvolume.volume(n).loadingpath_diffusivity = app.bulkdiffusivity_path;
                        end

                        % Solid transport
                        app.inputvolume.volume(n).solidtransport_representation = app.LowerScale_SolidTransport_DropDown.Value;
                        app.inputvolume.volume(n).solidtransport = app.LowerscaleTransport_UITable.Data(:,2);
                        app.inputvolume.volume(n).loadingpath_conductivity = [];
                        if strcmp(app.LowerScale_SolidTransport_DropDown.Value,'Conductivity K (heterogeneous)')
                            app.inputvolume.volume(n).loadingpath_conductivity = app.bulkconductivity_path;
                        end

                        % Wettability
                        app.inputvolume.volume(n).partial_wetting = app.PartialwettingCheckBox.Value;
                        if app.inputvolume.volume(n).partial_wetting
                            app.inputvolume.volume(n).partial_wetting_value_is = app.Airsaturation_terminology_DropDown.Value;
                            app.inputvolume.volume(n).partial_wetting_representation = app.Airsaturation_uniform_DropDown.Value;
                            app.inputvolume.volume(n).loadingpath_wetting = [];
                            if strcmp(app.Airsaturation_uniform_DropDown.Value,'Heterogeneous')
                                app.inputvolume.volume(n).loadingpath_wetting = app.airsaturation_path;
                            elseif strcmp(app.Airsaturation_uniform_DropDown.Value,'Uniform')
                                if strcmp(app.inputvolume.volume(n).partial_wetting_value_is,'Air saturation')
                                    app.inputvolume.volume(n).air_saturation = app.AirSaturation_UITable.Data;
                                else
                                    app.inputvolume.volume(n).wetting = app.AirSaturation_UITable.Data;
                                end
                            end
                        else
                            app.inputvolume.volume(n).partial_wetting_representation = 'Ideal';
                        end

                    end
                    if strcmp(app.SegmentationtypeDropDown.Value,'instance') || strcmp(app.SegmentationtypeDropDown.Value,'semantic and instance')
                        app.inputvolume.volume(n).loadingpath_instance = app.Instance_NofileimportedLabel.Text;
                        app.inputvolume.volume(n).ninstance = app.instance_fileinfo.ninstance;
                    end                   
                else
                    if strcmp(app.SegmentationtypeDropDown.Value,'semantic') || strcmp(app.SegmentationtypeDropDown.Value,'semantic and instance')
                        app.inputvolume.volume(n).loadingpath_semantic = char(app.pathnames_semantic(n));
                        if n==1
                            app.inputvolume.volume(n).nphase = app.semantic_fileinfo.nphase;

                            % Nanoporosity
                            app.inputvolume.volume(n).nanoporosity_representation = app.LowerScale_Nanoporosity_DropDown.Value;
                            app.inputvolume.volume(n).nanoporosity = app.PhasecolornameSemantic_UITable.Data(:,6);
                            app.inputvolume.volume(n).loadingpath_nanoporosity = [];
                            if strcmp(app.LowerScale_Nanoporosity_DropDown.Value,'Mixed (heterogeneous)')
                                app.inputvolume.volume(n).loadingpath_nanoporosity = app.nanoporosity_path;
                            end

                            % Pore transport
                            app.inputvolume.volume(n).poretransport_representation = app.LowerScale_PoreTransport_DropDown.Value;
                            app.inputvolume.volume(n).poretransport = app.LowerscaleTransport_UITable.Data(:,1);
                            app.inputvolume.volume(n).loadingpath_diffusivity = [];
                            if strcmp(app.LowerScale_PoreTransport_DropDown.Value,'Diffusivity D (heterogeneous)')
                                app.inputvolume.volume(n).loadingpath_diffusivity = app.bulkdiffusivity_path;
                            end

                            % Solid transport
                            app.inputvolume.volume(n).solidtransport_representation = app.LowerScale_SolidTransport_DropDown.Value;
                            app.inputvolume.volume(n).solidtransport = app.LowerscaleTransport_UITable.Data(:,2);
                            app.inputvolume.volume(n).loadingpath_conductivity = [];
                            if strcmp(app.LowerScale_SolidTransport_DropDown.Value,'Conductivity K (heterogeneous)')
                                app.inputvolume.volume(n).loadingpath_conductivity = app.bulkconductivity_path;
                            end

                            % Wettability
                            app.inputvolume.volume(n).partial_wetting = app.PartialwettingCheckBox.Value;
                            if app.inputvolume.volume(n).partial_wetting
                                app.inputvolume.volume(n).partial_wetting_value_is = app.Airsaturation_terminology_DropDown.Value;
                                app.inputvolume.volume(n).partial_wetting_represnetation = app.Airsaturation_uniform_DropDown.Value;
                                app.inputvolume.volume(n).loadingpath_wetting = [];
                                if strcmp(app.Airsaturation_uniform_DropDown.Value,'Heterogeneous')
                                    app.inputvolume.volume(n).loadingpath_wetting = app.airsaturation_path;
                                end
                            else
                                app.inputvolume.volume(n).partial_wetting_representation = 'Ideal';
                            end

                        else
                            app.inputvolume.volume(n).nphase = []; % We only imported 1st file to save time
                        end

                    end
                    if strcmp(app.SegmentationtypeDropDown.Value,'instance') || strcmp(app.SegmentationtypeDropDown.Value,'semantic and instance')
                       app.inputvolume.volume(n).loadingpath_instance = char(app.pathnames_instance(n));
                        if n==1
                            app.inputvolume.volume(n).ninstance = app.instance_fileinfo.ninstance;
                        else
                            app.inputvolume.volume(n).ninstance = []; % We only imported 1st file to save time
                        end
                    end
                end

                if strcmp(app.SegmentationtypeDropDown.Value,'semantic') || strcmp(app.SegmentationtypeDropDown.Value,'semantic and instance')
                    [~, name, ~] = fileparts(app.inputvolume.volume(n).loadingpath_semantic);
                    app.inputvolume.volume(n).semantic_filename_input=name;
                end
                if strcmp(app.SegmentationtypeDropDown.Value,'instance') || strcmp(app.SegmentationtypeDropDown.Value,'semantic and instance')
                    [~, name, ~] = fileparts(app.inputvolume.volume(n).loadingpath_instance);
                    app.inputvolume.volume(n).instance_filename_input=name;
                end

                % Middle slice
                app.inputvolume.volume(n).middleslice_semantic = [];
                app.inputvolume.volume(n).middleslice_instance = [];
                if ~isempty(app.current_middleslice_semantic)
                    app.inputvolume.volume(n).middleslice_semantic=app.current_middleslice_semantic;
                end
                if ~isempty(app.current_middleslice_instance)
                    app.inputvolume.volume(n).middleslice_instance=app.current_middleslice_instance;
                end                

                % Initial size
                if strcmp(app.ModeDropDown.Value,'Set parameters for each volume independently')
                    if strcmp(app.SegmentationtypeDropDown.Value,'semantic') || strcmp(app.SegmentationtypeDropDown.Value,'semantic and instance')
                        app.inputvolume.volume(n).initial_sz = app.semantic_fileinfo.sz;
                    elseif strcmp(app.SegmentationtypeDropDown.Value,'instance')
                        app.inputvolume.volume(n).initial_sz = app.instance_fileinfo.sz;
                    end
                else
                    if strcmp(app.SegmentationtypeDropDown.Value,'semantic') || strcmp(app.SegmentationtypeDropDown.Value,'semantic and instance')
                        app.inputvolume.volume(n).initial_sz = app.semantic_fileinfo(n).sz;
                    elseif strcmp(app.SegmentationtypeDropDown.Value,'instance')
                        app.inputvolume.volume(n).initial_sz = app.instance_fileinfo(n).sz;
                    end
                end

                % ROI
                if strcmp(app.inputvolume.mode,'Set parameters for each volume independently')
                    app.inputvolume.volume(n).ROI = cell2mat(app.ROI_UITable.Data(:,3:4));
                else
                    app.inputvolume.volume(n).ROI = [[app.inputvolume.volume(n).initial_sz./app.inputvolume.volume(n).initial_sz]' app.inputvolume.volume(n).initial_sz'];
                end

                % Direction name
                app.inputvolume.volume(n).directionname = app.ROI_UITable.Data(:,6);
                app.direction_name_sav = app.ROI_UITable.Data(:,6); % For next volume

                % Phase name
                if strcmp(app.SegmentationtypeDropDown.Value,'semantic') || strcmp(app.SegmentationtypeDropDown.Value,'semantic and instance')
                    app.inputvolume.volume(n).phasename_semantic = app.PhasecolornameSemantic_UITable.Data(:,4);
                    app.phase_name_semantic_sav = app.PhasecolornameSemantic_UITable.Data(:,4); % For next volume
                    % Phase color
                    app.inputvolume.volume(n).phasecolor_semantic = app.color_phase_semantic;
                    % Label
                    app.inputvolume.volume(n).phaselabel_semantic = cell2mat(app.PhasecolornameSemantic_UITable.Data(:,5));
                end
                if strcmp(app.SegmentationtypeDropDown.Value,'instance') || strcmp(app.SegmentationtypeDropDown.Value,'semantic and instance')
                    app.inputvolume.volume(n).phasename_instance = app.PhasecolornameInstance_UITable.Data(:,4);
                    app.phase_name_instance_sav = app.PhasecolornameInstance_UITable.Data(:,4); % For next volume
                    % Phase color
                    app.inputvolume.volume(n).phasecolor_instance = app.color_phase_instance;
                    % Label
                    app.inputvolume.volume(n).phaselabel_instance = cell2mat(app.PhasecolornameInstance_UITable.Data(:,5));
                end

                % Voxel size
                app.inputvolume.volume(n).initial_voxelsize =  app.VoxelsizeEditField.Value;
                app.inputvolume.volume(n).scaling_factor =  app.ScalingfactorEditField.Value;
                app.inputvolume.volume(n).voxelsize =  app.VoxelsizeEditField.Value * app.ScalingfactorEditField.Value;
                % Unit
                app.inputvolume.volume(n).unit = app.VoxelsizeUnit_EditField.Value;

                % Volume information
                app.inputvolume.volume(n).volumeinformation = app.Info_UITable.Data;
                app.volumeinfo_sav = app.Info_UITable.Data; % For next volume

                % Saving path
                app.inputvolume.volume(n).mainfolder = app.mainfolder;
                if strcmp(app.ModeDropDown.Value,'Set parameters for each volume independently')
                    app.inputvolume.volume(n).volumesubfolder = app.VolumefolderEditField.Value;
                else
                    if strcmp(app.SegmentationtypeDropDown.Value,'semantic') || strcmp(app.SegmentationtypeDropDown.Value,'semantic and instance')
                        [~,name,~] = fileparts( char(app.pathnames_semantic(n)) );
                        ROI_length = app.semantic_fileinfo(n).sz;
                    elseif strcmp(app.SegmentationtypeDropDown,Value,'instance')
                        [~,name,~] = fileparts( char(app.pathnames_instance(n)) );
                        ROI_length = app.instance_fileinfo(n).sz;
                    end
                    voxel_size = app.VoxelsizeEditField.Value;
                    scaling_factor = app.ScalingfactorEditField.Value;
                    voxel_sizeunit = app.VoxelsizeUnit_EditField.Value;
                    
                    dimension = length(ROI_length);
                    nvoxels = floor(ROI_length./scaling_factor);
                    new_voxel_size = voxel_size*scaling_factor;
                    length_root = (prod(nvoxels*new_voxel_size)).^(1/dimension);

                    new_voxel_size_str = round(new_voxel_size,3);
                    new_voxel_size_str = num2str(new_voxel_size_str);
                    new_voxel_size_str = strrep(new_voxel_size_str,'.','p');
                    length_root = round(length_root,1);
                    length_root = num2str(length_root);
                    length_root = strrep(length_root,'.','p');

                    str_volumefolder = [name '_voxel_' new_voxel_size_str voxel_sizeunit '_ROI_' length_root voxel_sizeunit];
                    app.inputvolume.volume(n).volumesubfolder = str_volumefolder;

                    if dimension == 2
                        str_ROI_short = [num2str(nvoxels(1),'%i') ' x ' num2str(nvoxels(2),'%i') ' voxels, ' num2str(nvoxels(1)*new_voxel_size,'%1.1f') ' x ' num2str(nvoxels(2)*new_voxel_size,'%1.1f') ' ' voxel_sizeunit num2str(dimension)];
                    else
                        str_ROI_short = [num2str(nvoxels(1),'%i') ' x ' num2str(nvoxels(2),'%i') ' x ' num2str(nvoxels(3),'%i') ' voxels, ' num2str(nvoxels(1)*new_voxel_size,'%1.1f') ' x ' num2str(nvoxels(2)*new_voxel_size,'%1.1f') ' x ' num2str(nvoxels(3)*new_voxel_size,'%1.1f') ' ' voxel_sizeunit num2str(dimension)];
                    end
                    app.ROI.lengthroot = [num2str(length_root) ' ' voxel_sizeunit];
                    app.ROI.nvoxels = prod(nvoxels);
                    app.ROI.str = str_ROI_short;
                end
                app.inputvolume.volume(n).ROI_lengthroot = strrep(app.ROI.lengthroot,'p','.');
                app.inputvolume.volume(n).ROI_nvoxels = app.ROI.nvoxels;
                app.inputvolume.volume(n).ROI_str = app.ROI.str;
                app.inputvolume.volume(n).volpath = fullfile(app.inputvolume.volume(n).mainfolder,app.inputvolume.volume(n).volumesubfolder);

                % Check this save path is not already used
                if app.number_volume>1
                    for kk=1:1:app.number_volume-1
                        if strcmp(app.inputvolume.volume(n).volpath, app.inputvolume.volume(kk).volpath)
                            app.Import_indication.Text = 'Error:  Main folder\Volume folder save path is already used.';
                            app.inputvolume.volume(n) = [];
                            return
                        end
                    end
                end

                % Increment 
                app.number_volume = app.number_volume+1;
            end

            % Find semantinc-instance pair based on name comparison (renumeroate n for instance files)
            if strcmp(app.SegmentationtypeDropDown.Value,'semantic and instance')
                if ~strcmp(app.ModeDropDown.Value,'Set parameters for each volume independently')
                    if n1>1
                        inputvolume_sav = app.inputvolume;

                        bestmatch = zeros(n1,1);
                        for ni = n0:1:n1
                            instance_name = app.inputvolume.volume(ni).instance_filename_input;
                            l_inst_name = length(instance_name);
                            instance_sz = app.instance_fileinfo(ni).sz;
                            match_sz = zeros(n1,1)-1; % Initialize
                            match_score = zeros(n1,1)-1; % Initialize
                            for ns = n0:1:n1
                                semantic_name = app.inputvolume.volume(ns).semantic_filename_input;
                                sematic_sz = app.semantic_fileinfo(ns).sz;
                                l_sem_name = length(semantic_name);
                                if length(instance_sz) == length(sematic_sz)
                                    if sum(instance_sz==sematic_sz)==length(instance_sz)
                                        match_sz(ns)=1;
                                        l_min = min([l_inst_name l_sem_name]);
                                        for ks=1:1:l_min
                                            if strncmpi(instance_name,semantic_name,ks)
                                                match_score(ns) = ks;
                                            end
                                        end
                                    end
                                end
                            end

                            if max(match_sz)<1
                                app.Import_indication.Text = ['Error: instance file ' instance_name ' has no size match with any semantic file'];
                                return
                            end

                            if max(match_score)<1
                                app.Import_indication.Text = ['Error: instance file ' instance_name ' has no name match with any semantic file'];
                                return
                            end

                            if sum(match_score == max(match_score))>1
                                app.Import_indication.Text = ['Error: instance file ' instance_name ' could be name-matched with at least 2 semantic files'];
                                return
                            end

                            % One semantic file has same size and best name match
                            bestmatch(ni) = find(match_score==max(match_score));
                            app.inputvolume.volume(ni) = inputvolume_sav.volume(bestmatch(ni));
                        end

                        if length(bestmatch) ~= length(unique(bestmatch))
                            app.Import_indication.Text = 'Error: some instance files have been matched with the same semantic file';
                            return
                        end
                        clear inputvolume_sav;
                    end
                end
            end

            % Reset import tab
            app.Semantic_NofileimportedLabel.Text ='No file imported.';
            app.Semantic_NofileimportedLabel.Tooltip = app.Semantic_NofileimportedLabel.Text;
            app.Instance_NofileimportedLabel.Text ='No file imported.';
            app.Instance_NofileimportedLabel.Tooltip = app.Instance_NofileimportedLabel.Text;
            if ~strcmp(app.Nanoporosity_file_Label.Text,'n/a')
                app.Nanoporosity_file_Label.Text ='No file imported.';
                app.Nanoporosity_file_Label.Tooltip = app.Nanoporosity_file_Label.Text;
            end
            if ~strcmp(app.Bulkdiffusivity_file_Label.Text,'n/a')
                app.Bulkdiffusivity_file_Label.Text ='No file imported.';
                app.Bulkdiffusivity_file_Label.Tooltip = app.Bulkdiffusivity_file_Label.Text;    
            end
            if ~strcmp(app.BulkConductivity_file_Label.Text,'n/a')
                app.BulkConductivity_file_Label.Text ='No file imported.';
                app.BulkConductivity_file_Label.Tooltip = app.BulkConductivity_file_Label.Text;
            end
            if ~strcmp(app.Airsaturation_file_Label.Text,'n/a')
                app.Airsaturation_file_Label.Text ='No file imported.';
                app.Airsaturation_file_Label.Tooltip = app.Airsaturation_file_Label.Text;                   
            end

            app.Volumenumber_Label.Visible = 'off';
            app.VoxelsizeEditField.Enable = 'off'; app.VoxelsizeUnit_EditField.Enable = 'off'; app.ScalingfactorEditField.Enable = 'off';
            app.Scaledvoxelsize_Label.Text = 'Scaled voxel size: n/a';
            app.ROI_Label.Text = 'ROI: n/a';

            app.ROI_UITable.Data = [];
            app.PhasecolornameSemantic_UITable.Data = [];
            app.PhasecolornameInstance_UITable.Data = [];
            app.AirSaturation_UITable.Data = [];
            app.LowerscaleTransport_UITable.Data = [];

            app.LowerScale_Nanoporosity_DropDown.Enable = "off";
            app.LowerScale_PoreTransport_DropDown.Enable = "off";
            app.LowerScale_SolidTransport_DropDown.Enable = "off";
            app.ImportnanoporosityButton.Enable = "off";
            app.ImportporediffusivityButton.Enable = "off";
            app.ImportsolidconductivityButton.Enable = "off";
            app.PartialwettingCheckBox.Enable = "off";
            app.Airsaturation_terminology_DropDown.Enable = "off";
            app.Airsaturation_uniform_DropDown.Enable = "off";
            app.ImportAirSaturation_Button.Enable = "off";
            
            app.VolumefolderEditField.Value = 'Not defined';
            app.Import_SavesettingsButton.Enable = 'off';

            app.current_semantic_microstructure = [];
            app.current_middleslice_semantic = [];
            app.current_instance_microstructure = [];
            app.current_instanceBW_microstructure = [];
            app.current_middleslice_instance = [];

            % Imported tab
            for n=n0:1:n1
                % One by one
                voxel_size = [num2str(app.inputvolume.volume(n).voxelsize) ' ' app.inputvolume.volume(n).unit];

                if strcmp(app.SegmentationtypeDropDown.Value,'semantic')
                    newrow = [{app.inputvolume.volume(n).semantic_filename_input} {app.inputvolume.volume(n).nanoporosity_representation} {app.inputvolume.volume(n).poretransport_representation} {app.inputvolume.volume(n).solidtransport_representation} {app.inputvolume.volume(n).partial_wetting_representation} {num2str(app.inputvolume.volume(n).nphase,'%i')} {'n/a'} {'n/a'}  {logical(app.inputvolume.volume(n).isbackground)} {app.inputvolume.volume(n).ROI_str} {num2str(app.inputvolume.volume(n).ROI_nvoxels)} {app.inputvolume.volume(n).ROI_lengthroot} {voxel_size} {'Unknown'} {app.inputvolume.volume(n).orderofcalculation}];
                elseif strcmp(app.SegmentationtypeDropDown.Value,'instance')
                    newrow = [{'n/a'} {'n/a'} {'n/a'} {'n/a'} {'n/a'} {'n/a'}  {app.inputvolume.volume(n).instance_filename_input} {num2str(app.inputvolume.volume(n).ninstance,'%i')} {logical(app.inputvolume.volume(n).isbackground)} {app.inputvolume.volume(n).ROI_str} {num2str(app.inputvolume.volume(n).ROI_nvoxels)} {app.inputvolume.volume(n).ROI_lengthroot} {voxel_size} {'Unknown'} {app.inputvolume.volume(n).orderofcalculation}];
                elseif strcmp(app.SegmentationtypeDropDown.Value,'semantic and instance')
                    newrow = [{app.inputvolume.volume(n).semantic_filename_input} {app.inputvolume.volume(n).nanoporosity_representation} {app.inputvolume.volume(n).poretransport_representation} {app.inputvolume.volume(n).solidtransport_representation} {app.inputvolume.volume(n).partial_wetting_representation} {num2str(app.inputvolume.volume(n).nphase,'%i')} {app.inputvolume.volume(n).instance_filename_input} {num2str(app.inputvolume.volume(n).ninstance,'%i')} {logical(app.inputvolume.volume(n).isbackground)} {app.inputvolume.volume(n).ROI_str} {num2str(app.inputvolume.volume(n).ROI_nvoxels)} {app.inputvolume.volume(n).ROI_lengthroot} {voxel_size} {'Unknown'} {app.inputvolume.volume(n).orderofcalculation}] ;
                end
                if isempty(app.Imported_UITable.Data)
                    app.Imported_UITable.Data = newrow;
                else
                    app.Imported_UITable.Data = [app.Imported_UITable.Data; newrow];
                end
            end

            if ~strcmp(app.ModeDropDown.Value,'Set parameters for each volume independently') 
                children = get(app.GridLayout_import,'Children');
                set(children,'Enable','off');                
            end

            % Enable imported tab
            children = get(app.GridLayout_imported,'Children');
            set(children,'Enable','on');               

            % Next step
            if strcmp(app.ModeDropDown.Value,'Set parameters for each volume independently') 
                app.Import_indication.Text = 'Repeat steps 3-8, or go to ''imported'' tab to continue';
            else
                app.Import_indication.Text = 'Done!'; pause(0.5);
                app.FilesTabGroup.SelectedTab = app.ImportedTab;
            end
        end

        % Button pushed function: SelectmainfolderButton
        function SelectmainfolderButtonPushed(app, event)
            % Set string of the dialog box
            str_dialogbox = 'Select main folder';
            % Open dialog box to choose folder path
            if ~isempty(app.mainfolder)
                res = uigetdir(app.mainfolder,str_dialogbox);
            else
                res = uigetdir(pwd,str_dialogbox);
            end
            if res
                app.mainfolder = res;
                app.Mainfolder_Label.Text = res;
                app.Mainfolder_Label.Tooltip = res;
            end
        end

        % Cell selection callback: PhasecolornameInstance_UITable
        function PhasecolornameInstance_UITableCellSelection(app, event)
            indices = event.Indices;
            if ~isempty(indices)
                if indices(2)==3 % color
                    c = uisetcolor;
                    if length(c)==3 % RGB
                        app.color_phase_instance(indices(1),:) = c;
                        s = uistyle('BackgroundColor',app.color_phase_instance(indices(1),:));
                        addStyle(app.PhasecolornameInstance_UITable,s,'cell',[indices(1),3]);
                    end
                end
            end
        end

        % Button pushed function: VisualizeallfilesButton
        function VisualizeallfilesButtonPushed(app, event)
            app.VisualizeallfilesButton.Text = 'Please wait...'; pause(0.1);

            n_file = length(app.inputvolume.volume);
            if n_file >1
                n = sqrt(n_file);
                r = min([1, round(n/1.5)]);
                c = floor(n_file/r);
                if r*c<n
                    c=c+1;
                end
            else
                r = 1; c = 1;
            end
            scrsz = get(0,'ScreenSize'); % Screen resolution

            if strcmp(app.inputvolume.segmentation_type,'semantic') || strcmp(app.inputvolume.segmentation_type,'semantic and instance')
                Fig = figure;
                Fig.Name= 'Semantic files';
                Fig.Color='white';
                set(Fig,'position',[scrsz(1) scrsz(2) scrsz(3)*4/5 scrsz(4)*4/5]);
                tiles = tiledlayout(r,c);
                for k_file = 1:1:n_file
                    ax = nexttile(k_file);

                    crop = false;
                    ROI = app.inputvolume.volume(k_file).ROI;
                    length_ROI = ROI(:,2) - ROI(:,1) + 1;
                    if min(app.inputvolume.volume(k_file).initial_sz == length_ROI')==0
                        crop = true;
                    end

                    % Import
                    if isempty(app.inputvolume.volume(k_file).middleslice_semantic) || (crop && strcmp(app.VisualizeROI_DropDown.Value,'with region of interest'))
                        M = function_load_tif(app.inputvolume.volume(k_file).loadingpath_semantic);
                    else
                        M = app.inputvolume.volume(k_file).middleslice_semantic;
                    end
                    
                    % ROI
                    if crop && strcmp(app.VisualizeROI_DropDown.Value,'with region of interest')
                        dimension = length(size(M));
                        if dimension == 2
                            M = M(ROI(1,1):ROI(1,2), ROI(2,1):ROI(2,2));
                        else
                            M = M(ROI(1,1):ROI(1,2), ROI(2,1):ROI(2,2), ROI(3,1):ROI(3,2));
                        end
                    end

                    % Scaling
                    if app.inputvolume.volume(k_file).scaling_factor~=1 && strcmp(app.VisualizeROI_DropDown.Value,'with region of interest')
                        p.scaling_factor = app.inputvolume.volume(k_file).scaling_factor;
                        p.label_or_greylevel = 'Label';
                        p.background = 0;
                        [M] = function_scaling(M,p);
                    end

                    sz = size(M);
                    if length(sz)==3
                        imagesc(M(:,:,round(sz(3)/2)));
                    else
                        imagesc(M);
                    end
                    axis equal; axis tight;
                    colormap(ax,'gray'); colorbar(ax);

                    set(ax,'Fontsize',10,'FontName',app.FontnameDropDown.Value);

                    title_str = strrep(app.inputvolume.volume(k_file).semantic_filename_input,'_',' ');
                    h=title(title_str);
                    h.FontSize = 12;
                    h.FontName = app.FontnameDropDown.Value;

                    subtitle_str = strrep(app.inputvolume.volume(k_file).volumesubfolder,'_',' ');
                    subtitle(subtitle_str,'Fontsize',10,'FontName',app.FontnameDropDown.Value,'FontAngle','italic');
                    
                end
                tiles.TileSpacing = 'compact';
                tiles.Padding = 'compact';
            end

            if strcmp(app.inputvolume.segmentation_type,'instance') || strcmp(app.inputvolume.segmentation_type,'semantic and instance')
                Fig = figure;
                Fig.Name= 'Instance files';
                Fig.Color='white';
                set(Fig,'position',[scrsz(1) scrsz(2) scrsz(3)*4/5 scrsz(4)*4/5]);
                tiles = tiledlayout(r,c);
                for k_file = 1:1:n_file
                    ax = nexttile(k_file);

                    crop = false;
                    ROI = app.inputvolume.volume(k_file).ROI;
                    length_ROI = ROI(:,2) - ROI(:,1) + 1;
                    if min(app.inputvolume.volume(k_file).initial_sz == length_ROI')==0
                        crop = true;
                    end

                    % Import
                    if isempty(app.inputvolume.volume(k_file).middleslice_instance) || (crop && strcmp(app.VisualizeROI_DropDown.Value,'with region of interest'))
                        M = function_load_tif(app.inputvolume.volume(k_file).loadingpath_instance);
                    else
                        M = app.inputvolume.volume(k_file).middleslice_instance;
                    end

                    % ROI
                    if crop && strcmp(app.VisualizeROI_DropDown.Value,'with region of interest')
                        dimension = length(size(M));
                        if dimension == 2
                            M = M(ROI(1,1):ROI(1,2), ROI(2,1):ROI(2,2));
                        else
                            M = M(ROI(1,1):ROI(1,2), ROI(2,1):ROI(2,2), ROI(3,1):ROI(3,2));
                        end
                    end

                    % Scaling
                    if app.inputvolume.volume(k_file).scaling_factor~=1 && strcmp(app.VisualizeROI_DropDown.Value,'with region of interest')
                        p.scaling_factor = app.inputvolume.volume(k_file).scaling_factor;
                        p.label_or_greylevel = 'Label';
                        p.background = 0;
                        [M] = function_scaling(M,p);
                    end

                    sz = size(M);
                    if length(sz)==3
                        imagesc(M(:,:,round(sz(3)/2)));
                    else
                        imagesc(M);
                    end
                    axis equal; axis tight;

                    n=length(unique(M));
                    custom_turbo = turbo(n);
                    custom_turbo(1,:) = [1.0 1.0 1.0]; % White background
                    colormap(ax,custom_turbo);

                    set(ax,'Fontsize',10,'FontName',app.FontnameDropDown.Value);

                    title_str = strrep(app.inputvolume.volume(k_file).instance_filename_input,'_',' ');
                    h=title(title_str);
                    h.FontSize = 12;
                    h.FontName = app.FontnameDropDown.Value;

                    subtitle_str = strrep(app.inputvolume.volume(k_file).volumesubfolder,'_',' ');
                    subtitle(subtitle_str,'Fontsize',10,'FontName',app.FontnameDropDown.Value,'FontAngle','italic');  
                end
                tiles.TileSpacing = 'compact';
                tiles.Padding = 'compact';
            end   

            app.VisualizeallfilesButton.Text = 'Visualize all files';

        end

        % Button pushed function: SearchtagButton
        function SearchtagButtonPushed(app, event)
            data = app.Imported_UITable.Data;
            [n,~] = size(data);
            for k=1:1:n
                name = char(data(k,1));
                id = find(name=='_');
                l = length(name);
                if ~isempty(id) && id(end)<l
                    str_tag = name(id(end)+1:end);
                    num_tag = str2num(str_tag);
                    if ~isempty(num_tag)
                        app.Imported_UITable.Data(k,end-1) = {num_tag};
                    else
                        app.Imported_UITable.Data(k,end-1) = {'None'};
                    end
                else
                    app.Imported_UITable.Data(k,end-1) = {'None'};
                end

            end
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            url = 'https://www.nrel.gov/';
            web(url)   
        end

        % Button pushed function: ClicktoseethelicenceButton
        function ClicktoseethelicenceButtonPushed(app, event)
            url = 'https://github.com/NREL/MATBOX_Microstructure_analysis_toolbox/blob/master/License.txt/';
            web(url)   
        end

        % Button pushed function: Imported_SavesettingsButton
        function Imported_SavesettingsButtonPushed(app, event)
            app.Imported_SavesettingsButton.Text = 'Please wait...';
            pause(0.01);

            % Number of volumes
            orders = cell2mat(app.Imported_UITable.Data(:,end));
            numbervolume = sum(orders~=0);
            numbervolume_sav = numbervolume;
            table_statutrun = cell(numbervolume,4); 
            sort_orders = sort(orders);
            sort_orders(sort_orders==0) = [];
            [~,neworders] = ismember(orders,sort_orders);
            app.Imported_UITable.Data(:,end) = num2cell(neworders);
            [number_rows,~] = size(app.Imported_UITable.Data);
            app.order_calculation = cell2mat(app.Imported_UITable.Data(:,end));
            app.tags = app.Imported_UITable.Data(:,end-1);
            if ~strcmp(app.inputvolume.mode,'Set parameters for each volume independently')
                numbervolume=1;
            end            

            % UI
            if ~strcmp(app.inputvolume.mode,'Apply macro''s parameters to all volumes')
                app.GreyOut('on');
                if strcmp(app.inputvolume.segmentation_type,'semantic')
                    app.Volumefraction_Semantic_UITable.Enable = 'on';
                    app.Volumefraction_Instance_UITable.Enable = 'off'; app.Volumefraction_Instance_UITable.Data = [];
                    app.Tau_Semantic_UITable.Enable = 'on';
                    app.Tau_Instance_UITable.Enable = 'off'; app.Tau_Instance_UITable.Data = [];                    
                    % app.Cpsd_Semantic_UITable.Enable = 'on';
                    % app.Cpsd_Instance_UITable.Enable = 'off'; app.Cpsd_Instance_UITable.Data = [];
                    % app.Cpsd_ExcludetruncatedparticlesCheckBox.Enable = 'off';
                    % app.MetricsPerParticle_UITable.Enable = 'off'; app.MetricsPerParticle_UITable.Data = [];
                    % app.ExcludetruncatedparticlesCheckBox.Enable = 'off';
                    % app.WeightMetrics_withvolume_CheckBox.Enable = 'off';

                elseif strcmp(app.inputvolume.segmentation_type,'instance')
                    app.Volumefraction_Semantic_UITable.Enable = 'off'; app.Volumefraction_Semantic_UITable.Data = [];
                    app.Volumefraction_Instance_UITable.Enable = 'on';
                    app.Tau_Semantic_UITable.Enable = 'off'; app.Tau_Semantic_UITable.Data = [];
                    app.Tau_Instance_UITable.Enable = 'on';                    
                    % app.Cpsd_Semantic_UITable.Enable = 'off'; app.Cpsd_Semantic_UITable.Data = [];
                    % app.Cpsd_Instance_UITable.Enable = 'on';
                    % app.Cpsd_ExcludetruncatedparticlesCheckBox.Enable = 'on';
                    % app.MetricsPerParticle_UITable.Enable = 'on';
                    % app.ExcludetruncatedparticlesCheckBox.Enable = 'on';
                    % app.WeightMetrics_withvolume_CheckBox.Enable = 'on';

                elseif strcmp(app.inputvolume.segmentation_type,'semantic and instance')
                    app.Volumefraction_Semantic_UITable.Enable = 'on';
                    app.Volumefraction_Instance_UITable.Enable = 'on';
                    app.Tau_Semantic_UITable.Enable = 'on';
                    app.Tau_Instance_UITable.Enable = 'on';                    
                    % app.Cpsd_Semantic_UITable.Enable = 'on';
                    % app.Cpsd_Instance_UITable.Enable = 'on';
                    % app.Cpsd_ExcludetruncatedparticlesCheckBox.Enable = 'on';
                    % app.MetricsPerParticle_UITable.Enable = 'on';
                    % app.ExcludetruncatedparticlesCheckBox.Enable = 'on';
                    % app.WeightMetrics_withvolume_CheckBox.Enable = 'on';
                end

            else
                children = get(app.Run_GridLayout,'Children');
                set(children,'Enable','on');
            end

            if strcmp(app.inputvolume.mode,"Set parameters for each volume independently")
                app.Run_SearchtagCheckBox.Enable = 'off'; app.Run_SearchtagCheckBox.Value = false;
            else
                app.Run_SearchtagCheckBox.Enable = 'on';
            end

            names = cell(numbervolume,1);
            if strcmp(app.inputvolume.segmentation_type,'semantic')
                for k=1:1:numbervolume_sav
                    kvol = find(neworders==k);
                    names(k,1) = {app.inputvolume.volume(kvol).semantic_filename_input};
                end
            end
            if strcmp(app.inputvolume.segmentation_type,'instance')
                for k=1:1:numbervolume_sav
                    kvol = find(neworders==k);
                    names(k,1) = {app.inputvolume.volume(kvol).instance_filename_input};
                end
            end
            if strcmp(app.inputvolume.segmentation_type,'semantic and instance')
                for k=1:1:numbervolume_sav
                    kvol = find(neworders==k);
                    names(k,1) = {[app.inputvolume.volume(kvol).semantic_filename_input ' and ' app.inputvolume.volume(kvol).instance_filename_input]};
                end               
            end

            for k=1:1:numbervolume_sav
                names(k,1) = {[char(names(k,1)) ' (#' num2str(k) ')']};
            end
            % Run table
            table_statutrun(:,1) = names(:,1);


            if ~strcmp(app.inputvolume.mode,'Apply macro''s parameters to all volumes')
                if strcmp(app.inputvolume.segmentation_type,'semantic') || strcmp(app.inputvolume.segmentation_type,'semantic and instance')

                    % Number of phase
                    number_phasemax = 0;
                    for kvol = 1:1:number_rows
                        if orders(kvol)~=0 && ~isempty(app.inputvolume.volume(kvol).nphase)
                            if app.inputvolume.volume(kvol).isbackground
                                number_phasemax = max(number_phasemax, app.inputvolume.volume(kvol).nphase-1);
                            else
                                number_phasemax = max(number_phasemax, app.inputvolume.volume(kvol).nphase);
                            end
                        end
                    end

                    if ~strcmp(app.inputvolume.mode,'Set parameters for each volume independently')
                        for k=2:1:numbervolume_sav
                            kvol = find(neworders==k);
                            app.inputvolume.volume(kvol).phasename_semantic = app.inputvolume.volume(1).phasename_semantic;
                            app.inputvolume.volume(kvol).phaselabel_semantic = app.inputvolume.volume(1).phaselabel_semantic;
                        end
                    end                 

                    % Volume fraction
                    table_phaseselection = cell(numbervolume,2*number_phasemax+1+1);
                    for k=1:1:numbervolume
                        kvol = find(neworders==k);
                        table_phaseselection(k,1) = {[app.inputvolume.volume(kvol).semantic_filename_input ' (#' num2str(k) ')']};
                        table_phaseselection(k,2) = {true};
                        nphase = app.inputvolume.volume(kvol).nphase;
                        phasename = app.inputvolume.volume(kvol).phasename_semantic;
                        if app.inputvolume.volume(kvol).isbackground
                            nphase=nphase-1;
                            phasename(1)=[];
                        end
                        
                        for kphase=1:1:nphase
                            table_phaseselection(k,2*kphase+1)= phasename(kphase);
                            table_phaseselection(k,2*kphase+2)={true};
                        end
                    end

                    cname_phaseselection = cell(1,2*number_phasemax+1+3);
                    cname_phaseselection(1) = {'Volume'};
                    cname_phaseselection(2) = {'Combined'};
                    kk=0;
                    for kphase=1:1:number_phasemax
                        kk=kk+1;
                        cname_phaseselection(2*kphase+1)={['Phase #' num2str(kk)]};
                    end
                    cedit_phaseselection = [false true];
                    for kphase=1:1:number_phasemax
                        cedit_phaseselection( 2*kphase+1 )=false;
                        cedit_phaseselection( 2*kphase+2 )=true;
                    end

                    app.Volumefraction_Semantic_UITable.Data = table_phaseselection;
                    app.Volumefraction_Semantic_UITable.ColumnName =cname_phaseselection;
                    app.Volumefraction_Semantic_UITable.ColumnEditable =cedit_phaseselection;
                    
                    
                    % Tortuosity factor
                    table_phaseselection_tau = cell(numbervolume,2*number_phasemax+1+2);
                    for k=1:1:numbervolume
                        kvol = find(neworders==k);
                        table_phaseselection_tau(k,1) = {[app.inputvolume.volume(kvol).semantic_filename_input ' (#' num2str(k) ')']};
                        table_phaseselection_tau(k,2) = {true};
                        table_phaseselection_tau(k,3) = {true};
                        nphase = app.inputvolume.volume(kvol).nphase;
                        phasename = app.inputvolume.volume(kvol).phasename_semantic;
                        if app.inputvolume.volume(kvol).isbackground
                            nphase=nphase-1;
                            phasename(1)=[];
                        end
                        for kphase=1:1:nphase
                            table_phaseselection_tau(k,2*kphase+2)= phasename(kphase);
                            table_phaseselection_tau(k,2*kphase+3)={true};
                        end
                    end

                    cname_phaseselection_tau = cell(1,2*number_phasemax+1+4);
                    cname_phaseselection_tau(1) = {'Volume'};
                    cname_phaseselection_tau(2) = {'Pore (combined)'};
                    cname_phaseselection_tau(3) = {'Solid (combined)'};
                    
                    kk=0;
                    for kphase=1:1:number_phasemax
                        kk=kk+1;
                        cname_phaseselection_tau(2*kphase+2)={['Phase #' num2str(kk)]};
                    end
                    cedit_phaseselection_tau = [false true true];
                    for kphase=1:1:number_phasemax
                        cedit_phaseselection_tau( 2*kphase+2 )=false;
                        cedit_phaseselection_tau( 2*kphase+3 )=true;
                    end

                    app.Tau_Semantic_UITable.Data = table_phaseselection_tau;
                    app.Tau_Semantic_UITable.ColumnName =cname_phaseselection_tau;
                    app.Tau_Semantic_UITable.ColumnEditable =cedit_phaseselection_tau;


                    % 
                    % % Create table for phase selection
                    % table_phaseselection = cell(numbervolume,2*number_phasemax+1);
                    % app.table_sem_phaseselection_tau_onescale = cell(numbervolume,3*number_phasemax+1);
                    % app.table_sem_phaseselection_tau_dualscale_dbulk = cell(numbervolume,2*number_phasemax+3);
                    % app.table_sem_phaseselection_tau_dualscale_epsp = cell(numbervolume,2*number_phasemax+3);
                    % 
                    % table_phase_direction_selection_section = cell(numbervolume,2*number_phasemax+1+6);
                    % table_phase_direction_selection_allphase = cell(numbervolume,2*number_phasemax+1+3);
                    % table_2phaseselection = cell(numbervolume,4);
                    % table_3phaseselection = cell(numbervolume,4);
                    % table_coupledphaseselection = cell(numbervolume,6);
                    % for k=1:1:numbervolume
                    %     kvol = find(neworders==k);
                    %     table_phaseselection(k,1) = {[app.inputvolume.volume(kvol).semantic_filename_input ' (#' num2str(k) ')']};
                    %     if isempty(app.inputvolume.volume(kvol).nphase_reassign)
                    %         nphase = app.inputvolume.volume(kvol).nphase;
                    %         phaselabel = app.inputvolume.volume(kvol).phaselabel_semantic;
                    %     else
                    %         nphase = app.inputvolume.volume(kvol).nphase_reassign;
                    %         phaselabel = app.inputvolume.volume(kvol).phaselabel_semantic(:,2);
                    %     end
                    %     phasename = app.inputvolume.volume(kvol).phasename_semantic;
                    %     ulabel = unique(phaselabel);
                    %     app.inputvolume.volume(kvol).phasename_semantic=cell(nphase,1);
                    %     for kphase=1:1:nphase
                    %         idx = find(phaselabel==ulabel(kphase));
                    %         str = char(phasename(idx(1)));
                    %         if length(idx)==1
                    %             table_phaseselection(k,2*kphase)= phasename(idx);
                    %         else
                    %             for kstr=2:1:length(idx)
                    %                 str = [str ' and ' char(phasename(idx(kstr)))];
                    %             end
                    %             table_phaseselection(k,2*kphase)={str};
                    %         end
                    %         % 2 and 3 phase property
                    %         tmp = [num2str(ulabel(kphase)) ': ' char(table_phaseselection(k,2*kphase))];
                    %         if kphase==1
                    %             strphases=tmp;
                    %         else
                    %             strphases = [strphases ', ' tmp];
                    %         end
                    %         table_phaseselection(k,2*kphase+1)={true};
                    %         app.inputvolume.volume(kvol).phasename_semantic(kphase) = {str};
                    %     end
                    %     app.inputvolume.volume(kvol).phaselabel_semantic = ulabel;
                    % 
                    %     % For phase i - phase j properties (interface)
                    %     table_2phaseselection(k,1) = table_phaseselection(k,1);
                    %     table_2phaseselection(k,2) = {strphases};
                    %     if nphase==2
                    %         table_2phaseselection(k,3)={false};
                    %         table_2phaseselection(k,4) = {'Do specific surface area instead'};
                    %     elseif nphase>=3
                    %         table_2phaseselection(k,3)={true};
                    %         table_2phaseselection(k,4) = {[num2str(ulabel(1)) ',' num2str(ulabel(2)) ';' num2str(ulabel(1)) ',' num2str(ulabel(3)) ';' num2str(ulabel(2)) ',' num2str(ulabel(3))  ]};
                    %     end
                    % 
                    %     % Tortuosity, one scale
                    %     app.table_sem_phaseselection_tau_onescale(k,1) = table_phaseselection(k,1);
                    %     for kphase=1:1:nphase
                    %         app.table_sem_phaseselection_tau_onescale(k,3*kphase-1) = table_phaseselection(k,2*kphase);
                    %         app.table_sem_phaseselection_tau_onescale(k,3*kphase)={num2str([1 1 1])};
                    %         app.table_sem_phaseselection_tau_onescale(k,3*kphase+1)={num2str([1 1 1])};
                    %     end
                    % 
                    %     % Tortuosity, dual scale, dbulk
                    %     app.table_sem_phaseselection_tau_dualscale_dbulk(k,1) = table_phaseselection(k,1);
                    %     app.table_sem_phaseselection_tau_dualscale_dbulk(k,2) = {num2str([1 1 1])};
                    %     app.table_sem_phaseselection_tau_dualscale_dbulk(k,3) = {num2str([1 1 1])};
                    %     for kphase=1:1:nphase
                    %         app.table_sem_phaseselection_tau_dualscale_dbulk(k,2*(kphase+1)) = table_phaseselection(k,2*kphase);
                    %         app.table_sem_phaseselection_tau_dualscale_dbulk(k,2*(kphase+1)+1)={1};
                    %     end
                    % 
                    %     % Tortuosity, dual scale, eps and p
                    %     app.table_sem_phaseselection_tau_dualscale_epsp(k,1) = table_phaseselection(k,1);
                    %     app.table_sem_phaseselection_tau_dualscale_epsp(k,2) = {num2str([1 1 1])};
                    %     app.table_sem_phaseselection_tau_dualscale_epsp(k,3) = {num2str([1 1 1])};
                    %     for kphase=1:1:nphase
                    %         app.table_sem_phaseselection_tau_dualscale_epsp(k,2*(kphase+1)) = table_phaseselection(k,2*kphase);
                    %         app.table_sem_phaseselection_tau_dualscale_epsp(k,2*(kphase+1)+1)={num2str([1 1])};
                    %     end
                    % 
                    % 
                    % 
                    %     % For phase i - phase j - phase l properties (TPBL)
                    %     table_3phaseselection(k,1) = table_phaseselection(k,1);
                    %     table_3phaseselection(k,2) = {strphases};
                    %     if nphase<3
                    %         table_3phaseselection(k,3)={false};
                    %         table_3phaseselection(k,4) = {'n/a'};
                    %     elseif nphase>=3
                    %         table_3phaseselection(k,3)={true};
                    %         table_3phaseselection(k,4) = {[num2str(ulabel(1)) ',' num2str(ulabel(2)) ',' num2str(ulabel(3)) ]};
                    %     end
                    % 
                    %     % For couple-phase (connectivity)
                    %     table_coupledphaseselection(k,1) = table_phaseselection(k,1);
                    %     table_coupledphaseselection(k,2) = {strphases};
                    %     table_coupledphaseselection(k,3)={true};
                    %     str = num2str(ulabel(1));
                    %     for kphase=2:1:nphase-1
                    %         str = [str ',' num2str(ulabel(kphase))];
                    %     end
                    %     str = [str ',' num2str(ulabel(nphase))];
                    %     table_coupledphaseselection(k,4)={str};
                    %     if nphase==2
                    %         table_coupledphaseselection(k,5)={'n/a'};
                    %     elseif nphase==3
                    %         table_coupledphaseselection(k,5) = {[num2str(ulabel(2)) ',' num2str(ulabel(3))]};
                    %     else
                    %         table_coupledphaseselection(k,5) = {[num2str(ulabel(1)) ',' num2str(ulabel(2)) ';' num2str(ulabel(1)) ',' num2str(ulabel(3)) ';' num2str(ulabel(2)) ',' num2str(ulabel(3))  ]};
                    %     end
                    %     table_coupledphaseselection(k,6)=table_coupledphaseselection(k,5);
                    % 
                    %     % For 1-phase-property with directions
                    %     table_phase_direction_selection_allphase(k,1) = table_phaseselection(k,1);
                    %     table_phase_direction_selection_allphase(k,2) = {true};
                    %     table_phase_direction_selection_allphase(k,3) = {true};
                    %     table_phase_direction_selection_allphase(k,4) = {true};
                    %     table_phase_direction_selection_allphase(k,5:end) = table_phaseselection(k,2:end);
                    % 
                    %     % For 1-phase-property with directions and section cut (Tortuosity)
                    %     table_phase_direction_selection_section(k,1) = table_phaseselection(k,1);
                    %     table_phase_direction_selection_section(k,2) = {true};
                    %     table_phase_direction_selection_section(k,3) = {1};
                    %     table_phase_direction_selection_section(k,4) = {true};
                    %     table_phase_direction_selection_section(k,5) = {1};
                    %     table_phase_direction_selection_section(k,6) = {true};
                    %     table_phase_direction_selection_section(k,7) = {1};
                    %     table_phase_direction_selection_section(k,8:end) = table_phaseselection(k,2:end);
                    %     for kk=11:2:2*number_phasemax+1+6
                    %         if ~isempty(cell2mat(table_phase_direction_selection_section(k,kk)))
                    %             table_phase_direction_selection_section(k,kk) = {false}; % By default, only pore domain
                    %         end
                    %     end
                    % end
                    % 
                    % if ~strcmp(app.inputvolume.mode,'Set parameters for each volume independently')
                    %     for k=2:1:numbervolume_sav
                    %         kvol = find(neworders==k);
                    %         app.inputvolume.volume(kvol).phasename_semantic = app.inputvolume.volume(1).phasename_semantic;
                    %         app.inputvolume.volume(kvol).phaselabel_semantic = app.inputvolume.volume(1).phaselabel_semantic;
                    %     end
                    % end
                    % 
                    % 
                    % 
                    % 
                    % % Name and edit for 1-phase property
                    % cname_phaseselection = cell(1,2*number_phasemax+1);
                    % cname_phaseselection(1) = {'Volume'};
                    % for k=2:2:2*number_phasemax
                    %     cname_phaseselection(k)={['Phase #' num2str(k/2)]};
                    % end
                    % cedit_phaseselection = [false];
                    % for kphase=1:1:number_phasemax
                    %     cedit_phaseselection(2*kphase)=false;
                    %     cedit_phaseselection(2*kphase+1)=true;
                    % end
                    % 
                    % % Name and edit for tortuosity, one scale
                    % app.cname_sem_tau_onescale = cell(1,2*number_phasemax+3);
                    % app.cname_sem_tau_onescale(1) = {'Volume'};
                    % app.cedit_sem_tau_onescale = [false];
                    % for k=1:1:number_phasemax
                    %     app.cname_sem_tau_onescale(3*k-1)={['Phase #' num2str(k)]};
                    %     app.cname_sem_tau_onescale(3*k)={'Direction'};
                    %     app.cname_sem_tau_onescale(3*k+1)={'Section'};
                    %     app.cedit_sem_tau_onescale(3*k-1) = [false];
                    %     app.cedit_sem_tau_onescale(3*k) = [true];
                    %     app.cedit_sem_tau_onescale(3*k+1) = [true];
                    % end
                    % 
                    % % Name and edit for tortuosity, dual scale
                    % app.cname_sem_tau_dualscale = cell(1,2*number_phasemax+3);
                    % app.cname_sem_tau_dualscale(1) = {'Volume'};
                    % app.cname_sem_tau_dualscale(2) = {'Direction'};
                    % app.cname_sem_tau_dualscale(3) = {'Section'};
                    % for kphase=1:1:number_phasemax
                    %     app.cname_sem_tau_dualscale(2*(kphase+1))={['Phase #' num2str(kphase)]};
                    %     app.cname_sem_tau_dualscale(2*(kphase+1)+1)={'Transport'};
                    % end
                    % app.cedit_sem_tau_dualscale = [false true true];
                    % for kphase=1:1:number_phasemax
                    %     app.cedit_sem_tau_dualscale(2*(kphase+1))=false;
                    %     app.cedit_sem_tau_dualscale(2*(kphase+1)+1)=true;
                    % end
                    % 
                    % 
                    % % Name and edit for 2-phase property
                    % cname_2phaseselection = {'Volume','Phase labels','To do','Label doublets'};
                    % cedit_2phaseselection = [false false true true];
                    % 
                    % % Name and edit for 3-phase property
                    % cname_3phaseselection = {'Volume','Phase labels','To do','Label triplets'};
                    % cedit_3phaseselection = [false false true true];
                    % 
                    % % Name and edit for coupledphase property
                    % cname_coupledphaseselection = {'Volume','Phase labels','To do','Individuals','Union','Coupled'};
                    % cedit_coupledphaseselection = [false false  true true true true];
                    % 
                    % % Name and edit for 1-phase property with directions
                    % cname_phase_direction_selection = cell(1,2*number_phasemax+1+3);
                    % cname_phase_direction_selection(1) = {'Volume'};
                    % cname_phase_direction_selection(2) = {'Direction 1'};
                    % cname_phase_direction_selection(3) = {'Direction 2'};
                    % cname_phase_direction_selection(4) = {'Direction 3'};
                    % cname_phase_direction_selection(5:end) = cname_phaseselection(2:end);
                    % cedit_phase_direction_selection = [false];
                    % cedit_phase_direction_selection(1) = false;
                    % cedit_phase_direction_selection(2) = true;
                    % cedit_phase_direction_selection(3) = true;
                    % cedit_phase_direction_selection(4) = true;
                    % for k=6:2:2*number_phasemax+1+3
                    %     cedit_phase_direction_selection(k-1) = false;
                    %     cedit_phase_direction_selection(k) = true;
                    % end
                    % 
                    % % Name and edit for 1-phase property with directions and sectoin
                    % cname_phase_direction_selection_section = cell(1,2*number_phasemax+1+6);
                    % cname_phase_direction_selection_section(1) = {'Volume'};
                    % cname_phase_direction_selection_section(2) = {'Direction 1'};
                    % cname_phase_direction_selection_section(3) = {'#Section'};
                    % cname_phase_direction_selection_section(4) = {'Direction 2'};
                    % cname_phase_direction_selection_section(5) = {'#Section'};
                    % cname_phase_direction_selection_section(6) = {'Direction 3'};
                    % cname_phase_direction_selection_section(7) = {'#Section'};
                    % cname_phase_direction_selection_section(8:end) = cname_phaseselection(2:end);
                    % cedit_phase_direction_selection_section = [false];
                    % cedit_phase_direction_selection_section(1) = false;
                    % cedit_phase_direction_selection_section(2) = true;
                    % cedit_phase_direction_selection_section(3) = true;
                    % cedit_phase_direction_selection_section(4) = true;
                    % cedit_phase_direction_selection_section(5) = true;
                    % cedit_phase_direction_selection_section(6) = true;
                    % cedit_phase_direction_selection_section(7) = true;
                    % for k=9:2:2*number_phasemax+1+6
                    %     cedit_phase_direction_selection_section(k-1) = false;
                    %     cedit_phase_direction_selection_section(k) = true;
                    % end
                    % 
                    % 
                    % 
                    % 
                    % % % Update tables (1phase-property)
                    % % Volume fraction
                    % app.Volumefraction_Semantic_UITable.Data = table_phaseselection;
                    % app.Volumefraction_Semantic_UITable.ColumnName =cname_phaseselection;
                    % app.Volumefraction_Semantic_UITable.ColumnEditable =cedit_phaseselection;
                    % % Cpsd
                    % app.Cpsd_Semantic_UITable.Data = table_phaseselection;
                    % app.Cpsd_Semantic_UITable.ColumnName =cname_phaseselection;
                    % app.Cpsd_Semantic_UITable.ColumnEditable =cedit_phaseselection;
                    % % Tortuosity one scale
                    % if strcmp(app.ScaleDropDown.Value,'One scale')
                    %     app.Tau_Semantic_UITable.Data = app.table_sem_phaseselection_tau_onescale;
                    %     app.Tau_Semantic_UITable.ColumnName =app.cname_sem_tau_onescale;
                    %     app.Tau_Semantic_UITable.ColumnEditable =app.cedit_sem_tau_onescale;
                    % elseif strcmp(app.ScaleDropDown.Value,'Dual scale')
                    %     if strcmp(app.DualScale_InputsDropDown.Value,'Lower scale diffusivity')
                    %         app.Tau_Semantic_UITable.Data = app.table_sem_phaseselection_tau_dualscale_dbulk;
                    %     elseif strcmp(app.DualScale_InputsDropDown.Value,'Lower scale porosity and Bruggeman exponent')
                    %         app.Tau_Semantic_UITable.Data = app.table_sem_phaseselection_tau_dualscale_epsp;
                    %     end
                    %     app.Tau_Semantic_UITable.ColumnName =app.cname_sem_tau_dualscale;
                    %     app.Tau_Semantic_UITable.ColumnEditable =app.cedit_sem_tau_dualscale;
                    % end

                end
                if strcmp(app.inputvolume.segmentation_type,'instance') || strcmp(app.inputvolume.segmentation_type,'semantic and instance')
                    % Number of phase
                    nphase = 2;

                    if ~strcmp(app.inputvolume.mode,'Set parameters for each volume independently')
                        for k=2:1:numbervolume_sav
                            kvol = find(neworders==k);
                            app.inputvolume.volume(kvol).phasename_instance = app.inputvolume.volume(1).phasename_instance;
                            app.inputvolume.volume(kvol).phaselabel_instance = app.inputvolume.volume(1).phaselabel_instance;
                        end
                    end         

                    % Volume fraction
                    table_phaseselection = cell(numbervolume,2*nphase+1);
                    for k=1:1:numbervolume
                        kvol = find(neworders==k);
                        table_phaseselection(k,1) = {[app.inputvolume.volume(kvol).instance_filename_input ' (#' num2str(k) ')']};
                        phasename = app.inputvolume.volume(kvol).phasename_instance;
                        if app.inputvolume.volume(kvol).isbackground
                            phasename(1)=[];
                        end
                        for kphase=1:1:nphase
                            table_phaseselection(k,2*kphase)= phasename(kphase);
                            table_phaseselection(k,2*kphase+1)={true};
                        end
                    end

                    cname_phaseselection = cell(1,2*nphase+1);
                    cname_phaseselection(1) = {'Volume'};
                    kk=0;
                    for kphase=1:nphase
                        kk=kk+1;
                        cname_phaseselection(2*kphase)={['Phase #' num2str(kk)]};
                    end
                    cedit_phaseselection = [false];
                    for kphase=1:nphase
                        cedit_phaseselection( 2*kphase )=false;
                        cedit_phaseselection( 2*kphase+1 )=true;
                    end

                    app.Volumefraction_Instance_UITable.Data = table_phaseselection;
                    app.Volumefraction_Instance_UITable.ColumnName =cname_phaseselection;
                    app.Volumefraction_Instance_UITable.ColumnEditable =cedit_phaseselection;
                    
                    % Tortuosity factor
                    app.Tau_Instance_UITable.Data = table_phaseselection;
                    app.Tau_Instance_UITable.ColumnName =cname_phaseselection;
                    app.Tau_Instance_UITable.ColumnEditable =cedit_phaseselection;


                    % 
                    % % Create table for phase selection
                    % table_volselection = cell(numbervolume,2);
                    % app.table_ins_phaseselection_tau_onescale = cell(numbervolume,3*number_phasemax+1);
                    % app.table_ins_phaseselection_tau_dualscale_dbulk = cell(numbervolume,2*number_phasemax+3);
                    % app.table_ins_phaseselection_tau_dualscale_epsp = cell(numbervolume,2*number_phasemax+3);
                    % 
                    % table_phaseselection = cell(numbervolume,2*number_phasemax+1);
                    % table_phase_direction_selection_section = cell(numbervolume,1+2*number_phasemax+6);
                    % table_phase_direction_selection_allphase = cell(numbervolume,1+2*number_phasemax+3);
                    % 
                    % 
                    % for k=1:1:numbervolume
                    %     kvol = find(neworders==k);
                    %     % name
                    %     table_phaseselection(k,1) = {[app.inputvolume.volume(kvol).instance_filename_input ' (#' num2str(k) ')']};
                    % 
                    %     % Phase name
                    %     phaselabel = app.inputvolume.volume(kvol).phaselabel_instance(:,2);
                    %     phasename = app.inputvolume.volume(kvol).phasename_instance;
                    %     app.inputvolume.volume(kvol).phasename_instance = phasename;
                    %     ulabel = unique(phaselabel);
                    %     for kphase=1:1:number_phasemax
                    %         idx = find(phaselabel==ulabel(kphase));
                    %         str = char(phasename(idx(1)));
                    %         table_phaseselection(k,2*kphase)= app.inputvolume.volume(kvol).phasename_instance(idx);
                    %         table_phaseselection(k,2*kphase+1)={true};
                    %         app.inputvolume.volume(kvol).phasename_instance(kphase) = {str};
                    %     end
                    %     app.inputvolume.volume(kvol).phaselabel_instance = ulabel;
                    % 
                    % 
                    % 
                    % 
                    %     % Tortuosity, one scale
                    %     app.table_ins_phaseselection_tau_onescale(k,1) = table_phaseselection(k,1);
                    %     for kphase=1:1:number_phasemax
                    %         app.table_ins_phaseselection_tau_onescale(k,3*kphase-1) = table_phaseselection(k,2*kphase);
                    %         app.table_ins_phaseselection_tau_onescale(k,3*kphase)={num2str([1 1 1])};
                    %         app.table_ins_phaseselection_tau_onescale(k,3*kphase+1)={num2str([1 1 1])};
                    %     end
                    % 
                    %     % Tortuosity, dual scale, dbulk
                    %     app.table_ins_phaseselection_tau_dualscale_dbulk(k,1) = table_phaseselection(k,1);
                    %     app.table_ins_phaseselection_tau_dualscale_dbulk(k,2) = {num2str([1 1 1])};
                    %     app.table_ins_phaseselection_tau_dualscale_dbulk(k,3) = {num2str([1 1 1])};
                    %     for kphase=1:1:number_phasemax
                    %         app.table_ins_phaseselection_tau_dualscale_dbulk(k,2*(kphase+1)) = table_phaseselection(k,2*kphase);
                    %         app.table_ins_phaseselection_tau_dualscale_dbulk(k,2*(kphase+1)+1)={1};
                    %     end
                    % 
                    %     % Tortuosity, dual scale, eps and p
                    %     app.table_ins_phaseselection_tau_dualscale_epsp(k,1) = table_phaseselection(k,1);
                    %     app.table_ins_phaseselection_tau_dualscale_epsp(k,2) = {num2str([1 1 1])};
                    %     app.table_ins_phaseselection_tau_dualscale_epsp(k,3) = {num2str([1 1 1])};
                    %     for kphase=1:1:number_phasemax
                    %         app.table_ins_phaseselection_tau_dualscale_epsp(k,2*(kphase+1)) = table_phaseselection(k,2*kphase);
                    %         app.table_ins_phaseselection_tau_dualscale_epsp(k,2*(kphase+1)+1)={num2str([1 1])};
                    %     end
                    % 
                    % 
                    %     % For volume section
                    %     table_volselection(k,1) = table_phaseselection(k,1);
                    %     table_volselection(k,2) = {true};
                    % 
                    %     % For 1-phase-property with directions
                    %     table_phase_direction_selection_allphase(k,1) = table_phaseselection(k,1);
                    %     table_phase_direction_selection_allphase(k,2) = {true};
                    %     table_phase_direction_selection_allphase(k,3) = {true};
                    %     table_phase_direction_selection_allphase(k,4) = {true};
                    %     table_phase_direction_selection_allphase(k,5:end) = table_phaseselection(k,2:end);
                    % 
                    %     % For 1-phase-property with directions and section cut (Tortuosity)
                    %     table_phase_direction_selection_section(k,1) = table_phaseselection(k,1);
                    %     table_phase_direction_selection_section(k,2) = {true};
                    %     table_phase_direction_selection_section(k,3) = {1};
                    %     table_phase_direction_selection_section(k,4) = {true};
                    %     table_phase_direction_selection_section(k,5) = {1};
                    %     table_phase_direction_selection_section(k,6) = {true};
                    %     table_phase_direction_selection_section(k,7) = {1};
                    %     table_phase_direction_selection_section(k,8:end) = table_phaseselection(k,2:end);
                    %     for kk=11:2:2*number_phasemax+1+6
                    %         if ~isempty(cell2mat(table_phase_direction_selection_section(k,kk)))
                    %             table_phase_direction_selection_section(k,kk) = {false}; % By default, only pore domain
                    %         end
                    %     end
                    % 
                    % end
                    % 
                    % % Name and edit for 1-phase property
                    % cname_phaseselection = cell(1,2*number_phasemax+1);
                    % cname_phaseselection(1) = {'Volume'};
                    % for k=2:2:2*number_phasemax
                    %     cname_phaseselection(k)={['Phase #' num2str(k/2)]};
                    % end
                    % cedit_phaseselection = [false];
                    % for kphase=1:1:number_phasemax
                    %     cedit_phaseselection(2*kphase)=false;
                    %     cedit_phaseselection(2*kphase+1)=true;
                    % end
                    % 
                    % % Name and edit for tortuosity, one scale
                    % app.cname_ins_tau_onescale = cell(1,2*number_phasemax+3);
                    % app.cname_ins_tau_onescale(1) = {'Volume'};
                    % app.cedit_ins_tau_onescale = [false];
                    % for k=1:1:number_phasemax
                    %     app.cname_ins_tau_onescale(3*k-1)={['Phase #' num2str(k)]};
                    %     app.cname_ins_tau_onescale(3*k)={'Direction'};
                    %     app.cname_ins_tau_onescale(3*k+1)={'Section'};
                    %     app.cedit_ins_tau_onescale(3*k-1) = [false];
                    %     app.cedit_ins_tau_onescale(3*k) = [true];
                    %     app.cedit_ins_tau_onescale(3*k+1) = [true];
                    % end
                    % 
                    % % Name and edit for tortuosity, dual scale
                    % app.cname_ins_tau_dualscale = cell(1,2*number_phasemax+3);
                    % app.cname_ins_tau_dualscale(1) = {'Volume'};
                    % app.cname_ins_tau_dualscale(2) = {'Direction'};
                    % app.cname_ins_tau_dualscale(3) = {'Section'};
                    % for kphase=1:1:number_phasemax
                    %     app.cname_ins_tau_dualscale(2*(kphase+1))={['Phase #' num2str(kphase)]};
                    %     app.cname_ins_tau_dualscale(2*(kphase+1)+1)={'Transport'};
                    % end
                    % app.cedit_ins_tau_dualscale = [false true true];
                    % for kphase=1:1:number_phasemax
                    %     app.cedit_ins_tau_dualscale(2*(kphase+1))=false;
                    %     app.cedit_ins_tau_dualscale(2*(kphase+1)+1)=true;
                    % end
                    % 
                    % 
                    % 
                    % 
                    % 
                    % 
                    % 
                    % 
                    % 
                    % 
                    % % Name and edit for volume selection
                    % cname_volselection = cell(1,2);
                    % cname_volselection(1) = {'Volume'};
                    % cname_volselection(2) = {'To do'};
                    % cedit_volselection = [false true];
                    % 
                    % % Name and edit for 1-phase property with directions
                    % cname_phase_direction_selection = cell(1,2*number_phasemax+1+3);
                    % cname_phase_direction_selection(1) = {'Volume'};
                    % cname_phase_direction_selection(2) = {'Direction 1'};
                    % cname_phase_direction_selection(3) = {'Direction 2'};
                    % cname_phase_direction_selection(4) = {'Direction 3'};
                    % cname_phase_direction_selection(5:end) = cname_phaseselection(2:end);
                    % cedit_phase_direction_selection = [false];
                    % cedit_phase_direction_selection(1) = false;
                    % cedit_phase_direction_selection(2) = true;
                    % cedit_phase_direction_selection(3) = true;
                    % cedit_phase_direction_selection(4) = true;
                    % for k=6:2:2*number_phasemax+1+3
                    %     cedit_phase_direction_selection(k-1) = false;
                    %     cedit_phase_direction_selection(k) = true;
                    % end
                    % 
                    % % Name and edit for 1-phase property with directions and sectoin
                    % cname_phase_direction_selection_section = cell(1,2*number_phasemax+1+6);
                    % cname_phase_direction_selection_section(1) = {'Volume'};
                    % cname_phase_direction_selection_section(2) = {'Direction 1'};
                    % cname_phase_direction_selection_section(3) = {'#Section'};
                    % cname_phase_direction_selection_section(4) = {'Direction 2'};
                    % cname_phase_direction_selection_section(5) = {'#Section'};
                    % cname_phase_direction_selection_section(6) = {'Direction 3'};
                    % cname_phase_direction_selection_section(7) = {'#Section'};
                    % cname_phase_direction_selection_section(8:end) = cname_phaseselection(2:end);
                    % cedit_phase_direction_selection_section = [false];
                    % cedit_phase_direction_selection_section(1) = false;
                    % cedit_phase_direction_selection_section(2) = true;
                    % cedit_phase_direction_selection_section(3) = true;
                    % cedit_phase_direction_selection_section(4) = true;
                    % cedit_phase_direction_selection_section(5) = true;
                    % cedit_phase_direction_selection_section(6) = true;
                    % cedit_phase_direction_selection_section(7) = true;
                    % for k=9:2:2*number_phasemax+1+6
                    %     cedit_phase_direction_selection_section(k-1) = false;
                    %     cedit_phase_direction_selection_section(k) = true;
                    % end
                    % 
                    % % % Update tables (1phase-property)
                    % % Volume fraction
                    % app.Volumefraction_Instance_UITable.Data = table_phaseselection;
                    % app.Volumefraction_Instance_UITable.ColumnName =cname_phaseselection;
                    % app.Volumefraction_Instance_UITable.ColumnEditable =cedit_phaseselection;
                    % % C-PSD
                    % app.Cpsd_Instance_UITable.Data = table_phaseselection;
                    % app.Cpsd_Instance_UITable.ColumnName =cname_phaseselection;
                    % app.Cpsd_Instance_UITable.ColumnEditable =cedit_phaseselection;
                    % 
                    % % Tortuosity one scale
                    % if strcmp(app.ScaleDropDown.Value,'One scale')
                    %     app.Tau_Instance_UITable.Data = app.table_ins_phaseselection_tau_onescale;
                    %     app.Tau_Instance_UITable.ColumnName =app.cname_ins_tau_onescale;
                    %     app.Tau_Instance_UITable.ColumnEditable =app.cedit_ins_tau_onescale;
                    % elseif strcmp(app.ScaleDropDown.Value,'Dual scale')
                    %     if strcmp(app.DualScale_InputsDropDown.Value,'Lower scale diffusivity')
                    %         app.Tau_Instance_UITable.Data = app.table_ins_phaseselection_tau_dualscale_dbulk;
                    %     elseif strcmp(app.DualScale_InputsDropDown.Value,'Lower scale porosity and Bruggeman exponent')
                    %         app.Tau_Instance_UITable.Data = app.table_ins_phaseselection_tau_dualscale_epsp;
                    %     end
                    %     app.Tau_Instance_UITable.ColumnName =app.cname_ins_tau_dualscale;
                    %     app.Tau_Instance_UITable.ColumnEditable =app.cedit_ins_tau_dualscale;
                    % end
                    % 
                    % 
                    % % Update tables (volume selection)
                    % % Metrics per particle
                    % app.MetricsPerParticle_UITable.Data = table_volselection;
                    % app.MetricsPerParticle_UITable.ColumnName = cname_volselection;
                    % app.MetricsPerParticle_UITable.ColumnEditable = cedit_volselection;


                    % % Run table
                    % if strcmp(app.inputvolume.segmentation_type,'semantic') || strcmp(app.inputvolume.segmentation_type,'semantic and instance')
                    %     for k=1:1:numbervolume
                    %         table_statutrun(k,1) = {[char(table_statutrun(k,1)) ' and ' char(table_phaseselection(k,1))]};
                    %     end
                    % else
                    %     table_statutrun(:,1) = table_phaseselection(:,1);
                    % end
                end
                      
                % % Errors: image resolution
                app.Voxelsize_VolumeselectionDropDown.Items =  table_phaseselection(:,1);
                % Create table for voxel size dependence analysis
                properties = app.property_resolution_list;                
                n_properties = length(properties);
                app.default_voxelsizeanalysis = cell(n_properties,6);
                app.default_voxelsizeanalysis(:,1) = properties;
                app.default_voxelsizeanalysis(:,2) = {'1'};
                app.default_voxelsizeanalysis(:,4) = {'None'};
                app.default_voxelsizeanalysis(:,5) = app.toplogy_dimension;
                app.default_voxelsizeanalysis(:,6) = {false};
                % Assign
                for k=1:1:numbervolume
                    app.Voxelsize_VolumeselectionDropDown.Value = app.Voxelsize_VolumeselectionDropDown.Items(k);
                    app.Voxelsize_VolumeselectionDropDownValueChanged;
                end
                app.Voxelsize_VolumeselectionDropDown.Value = app.Voxelsize_VolumeselectionDropDown.Items(1);
                app.Voxelsize_VolumeselectionDropDownValueChanged;

                % RVE
                app.RVE_ChoiceDropDownValueChanged;
                app.RVE_ForvolumeDropDown.Items = table_phaseselection(:,1);
                app.OneSub_ForvolumeDropDown.Items = table_phaseselection(:,1);
                app.Allanalyses_ForvolumeDropDown.Items = table_phaseselection(:,1);
                app.RVEconvergencewithfieldofviewCheckBoxValueChanged;
                app.OneSub_ChoiceDropDownValueChanged;

                for kvol=1:1:100
                    for kpro=1:1:100
                        app.RVE_list.vol(kvol).pro(kpro).number_RVE = 0;
                        app.RVE_list.vol(kvol).pro(kpro).RVE = [];
                    end
                end
                app.Check_RVEtable;

            end
            
            % Run tab
            app.RunStatut_UITable.Data=table_statutrun;
            
            % Move to next tab
            app.Imported_SavesettingsButton.Text = 'Save settings';
            if ~strcmp(app.inputvolume.mode,'Apply macro''s parameters to all volumes')
                app.Main_TabGroup.SelectedTab = app.VolumeTab;     
                app.TabGroup2.SelectedTab = app.VolumefractionTab;             
            else
                app.Main_TabGroup.SelectedTab = app.RunTab;   
            end
        end

        % Cell edit callback: Imported_UITable
        function Imported_UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            if indices(2) == 10 % Calculation order
                if ~strcmp(app.inputvolume.mode,'Set parameters for each volume independently') && indices(1)==1
                    app.Imported_UITable.Data(indices(1),indices(2)) = {event.PreviousData};
                else
                    if ~ischar(newData) && ~isnan(newData) && newData==round(newData) && newData>=0
                        vals = cell2mat(app.Imported_UITable.Data(:,10));
                        vals(vals==0)=[];
                        if length(vals)~=length(unique(vals))
                            app.Imported_UITable.Data(indices(1),indices(2)) = {event.PreviousData};
                        end
                    else
                        app.Imported_UITable.Data(indices(1),indices(2)) = {event.PreviousData};
                    end
                end
            elseif indices(2) == 5 % Tag
                if ischar(newData)
                    newData = str2num(newData);
                end
                if isempty(newData) || isnan(newData)
                    app.Imported_UITable.Data(indices(1),indices(2)) = {event.PreviousData};
                end
            end
        end

        % Button pushed function: Restart
        function RestartPushed(app, event)
            Microstructure_characterization2;
            delete(app);            
        end

        % Button pushed function: Run_button
        function Run_buttonButtonPushed(app, event)
            app.savemacro = false;
            app.use_macro = false;
            if strcmp(app.ModeDropDown.Value,"Apply macro's parameters to all volumes") && ~isempty(app.macro_pars)
                app.use_macro = true;
            end
            app.Create_pars_for_run;            
        end

        % Value changed function: Voxelsize_VolumeselectionDropDown
        function Voxelsize_VolumeselectionDropDownValueChanged(app, event)
            idx = app.Voxelsize_VolumeselectionDropDown.ValueIndex;
            k = find(app.order_calculation==idx);
            if ~isfield(app.inputvolume.volume(k),'voxel_resize') || isempty(app.inputvolume.volume(k).voxel_resize)
                app.Voxelsize_UITable.Data = app.default_voxelsizeanalysis;
                app.Voxelsize_UITable.Data(:,3) = {[num2str(app.inputvolume.volume(k).voxelsize) ' ' app.inputvolume.volume(k).unit]};
                app.inputvolume.volume(k).voxel_resize = app.Voxelsize_UITable.Data;
            else
                app.Voxelsize_UITable.Data = app.inputvolume.volume(k).voxel_resize;
            end            
        end

        % Cell edit callback: Voxelsize_UITable
        function Voxelsize_UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;

            if indices(2)==6 % Fractal dimension
                scalingfactors = app.Voxelsize_UITable.Data(indices(1),2);
                scalingfactors = str2num(char(strrep(scalingfactors,',',' ')));
                if length(scalingfactors)<2
                    app.Voxelsize_UITable.Data(indices(1),indices(2)) = {false};
                end

            elseif indices(2)==2 % Scaling factor
                multiplier = str2num(newData);
                if isempty(multiplier) % Incorrect input
                    multiplier = 1;
                    app.Voxelsize_UITable.Data(indices(1),indices(2)) = {'1'};
                else
                    if length(multiplier)==length(unique(multiplier)) && length(multiplier)==sum(multiplier>0) % No duplicate, no negative.
                        foo=1;
                    else
                        multiplier = 1;
                        app.Voxelsize_UITable.Data(indices(1),indices(2)) = {'1'};
                    end
                end

                if ~isempty(multiplier)
                    multiplier = unique([1 multiplier]);
                    multiplier = sort(multiplier);
                    idx = app.Voxelsize_VolumeselectionDropDown.ValueIndex;
                    k = find(app.order_calculation==idx);
                    vunit = app.inputvolume.volume(k).unit;
                    vsize = app.inputvolume.volume(k).voxelsize;
                    if length(multiplier)==1
                        str1 = num2str(multiplier);
                        str2 = [num2str(multiplier*vsize) ' ' vunit];
                    else
                        str1 = num2str(multiplier(1));
                        str2 = num2str(multiplier(1)*vsize);
                        for k=2:1:length(multiplier)
                            str1 = [str1 ', ' num2str(multiplier(k))];
                            str2 = [str2 ', ' num2str(multiplier(k)*vsize)];
                        end
                        str2 = [str2 ' ' vunit];
                    end
                    app.Voxelsize_UITable.Data(indices(1),2) = {str1};
                    app.Voxelsize_UITable.Data(indices(1),3) = {str2};
                end

                scalingfactors = app.Voxelsize_UITable.Data(indices(1),2);
                scalingfactors = str2num(char(strrep(scalingfactors,',',' ')));
                if length(scalingfactors)<2
                    app.Voxelsize_UITable.Data(indices(1),6) = {false};
                    app.Voxelsize_UITable.Data(indices(1),4) = {'None'};
                end                

            elseif indices(2)==4 % Extrapolation
                str = lower(newData);
                if strcmp(str,'linear') || strcmp(str,'l') || strcmp(str,'1')
                    app.Voxelsize_UITable.Data(indices(1),4) = {'Linear'};
                elseif strcmp(str,'quadratic') || strcmp(str,'q') || strcmp(str,'2')
                    app.Voxelsize_UITable.Data(indices(1),4) = {'Quadratic'};  
                elseif strcmp(str,'cubic') || strcmp(str,'c') || strcmp(str,'3')
                    app.Voxelsize_UITable.Data(indices(1),4) = {'Cubic'};     
                %elseif strcmp(str,'best') || strcmp(str,'b') 
                %    app.Voxelsize_UITable.Data(indices(1),4) = {'Best'};                        
                elseif strcmp(str,'none') || strcmp(str,'no') || strcmp(str,'n')  || strcmp(str,'0')
                    app.Voxelsize_UITable.Data(indices(1),4) = {'None'};                      
                else
                    app.Voxelsize_UITable.Data(indices(1),4) = {'None'};
                end
            end

            if indices(1)==app.voxelidx.tortuosityfactor || indices(1)==app.voxelidx.Bruggeman || indices(1)==app.voxelidx.Deff
                app.Voxelsize_UITable.Data(app.voxelidx.tortuosityfactor,2) = app.Voxelsize_UITable.Data(indices(1),2);
                app.Voxelsize_UITable.Data(app.voxelidx.tortuosityfactor,3) = app.Voxelsize_UITable.Data(indices(1),3);
                app.Voxelsize_UITable.Data(app.voxelidx.tortuosityfactor,6) = app.Voxelsize_UITable.Data(indices(1),6);
                app.Voxelsize_UITable.Data(app.voxelidx.Bruggeman,2) = app.Voxelsize_UITable.Data(indices(1),2);
                app.Voxelsize_UITable.Data(app.voxelidx.Bruggeman,3) = app.Voxelsize_UITable.Data(indices(1),3);
                app.Voxelsize_UITable.Data(app.voxelidx.Bruggeman,6) = app.Voxelsize_UITable.Data(indices(1),6);
                app.Voxelsize_UITable.Data(app.voxelidx.Deff,2) = app.Voxelsize_UITable.Data(indices(1),2);
                app.Voxelsize_UITable.Data(app.voxelidx.Deff,3) = app.Voxelsize_UITable.Data(indices(1),3);
                app.Voxelsize_UITable.Data(app.voxelidx.Deff,6) = app.Voxelsize_UITable.Data(indices(1),6);    
            end


            idx = app.Voxelsize_VolumeselectionDropDown.ValueIndex;
            k = find(app.order_calculation==idx);
            app.inputvolume.volume(k).voxel_resize = app.Voxelsize_UITable.Data;  
            
        end

        % Button pushed function: Voxelsize_Applytoallvolumes_Button
        function Voxelsize_Applytoallvolumes_ButtonPushed(app, event)
            for idx = 1:1:length(app.Voxelsize_VolumeselectionDropDown.Items)
                k = find(app.order_calculation==idx);
                app.inputvolume.volume(k).voxel_resize = app.Voxelsize_UITable.Data;  
            end
        end

        % Button pushed function: HowtointerpretateRVEresultsButton
        function HowtointerpretateRVEresultsButtonPushed(app, event)
            winopen('RVE_interpretation.png');
        end

        % Value changed function: RVE_ChoiceDropDown
        function RVE_ChoiceDropDownValueChanged(app, event)
            value = app.RVE_ChoiceDropDown.Value;
            if strcmp(value,'Subvolumes have one constant length')
                app.RVE_ConstantlengthsDropDown.Enable = 'on';
                app.RVE_ConstantlengthsDropDown.Items = {'Direction 1','Direction 2','Direction 3'};
                app.RVE_2subs_CheckBox.Enable = 'on';
                app.RVE_SubvolumeaspectratioEditField.Enable = 'off'; app.RVE_SubvolumeaspectratioEditField.Value = 'n/a';
                app.RVEname_label.Text = 'Representative Section Area (RSA) analysis';
            elseif strcmp(value,'Subvolumes have two constant lengths (3D only)')
                app.RVE_ConstantlengthsDropDown.Enable = 'on';
                app.RVE_ConstantlengthsDropDown.Items = {'Directions 1 and 2','Directions 1 and 3','Directions 2 and 3'};
                app.RVE_2subs_CheckBox.Enable = 'off'; app.RVE_2subs_CheckBox.Value = 0;
                app.RVE_SubvolumeaspectratioEditField.Enable = 'off'; app.RVE_SubvolumeaspectratioEditField.Value = 'n/a';
                app.RVEname_label.Text = 'Representative Direction Length (RDL) analysis';
            elseif strcmp(value,'Subvolumes aspect ratio is user-defined')
                app.RVE_ConstantlengthsDropDown.Enable = 'off';
                app.RVE_ConstantlengthsDropDown.Items = {'n/a'};
                app.RVE_2subs_CheckBox.Enable = 'off'; app.RVE_2subs_CheckBox.Value = 0;
                app.RVE_SubvolumeaspectratioEditField.Enable = 'on'; app.RVE_SubvolumeaspectratioEditField.Value = '1, 1, 1';
                app.RVEname_label.Text = 'Representative Volume Element (RVE) analysis';
            elseif strcmp(value,'Subvolumes inherit aspect ratio from initial FOV')
                app.RVE_ConstantlengthsDropDown.Enable = 'off';
                app.RVE_ConstantlengthsDropDown.Items = {'n/a'};
                app.RVE_2subs_CheckBox.Enable = 'off'; app.RVE_2subs_CheckBox.Value = 0;
                app.RVE_SubvolumeaspectratioEditField.Enable = 'off'; app.RVE_SubvolumeaspectratioEditField.Value = 'n/a';
                app.RVEname_label.Text = 'Representative Volume Element (RVE) analysis';
            end
            app.RVE_DividerEditFieldValueChanged; 
            app.DonotcutsubvolumesmallerthanCheckBoxValueChanged;
        end

        % Value changed function: RVE_ConstantlengthsDropDown, 
        % ...and 1 other component
        function RVE_DividerEditFieldValueChanged(app, event)
            value = app.RVE_DividerEditField.Value;
            divider = str2num(value);
            if isempty(divider) % Incorrect input
                app.RVE_DividerEditField.Value = ['2,3,4,5'];
            else
                if length(divider)==length(unique(divider)) && length(divider)==sum(divider>=2) && sum(divider==round(divider))==length(divider) && sum(divider==sort(divider))==length(divider) && length(divider)>=2 % No duplicate, >=2, integer, increasing.
                    foo=1;
                else
                    app.RVE_DividerEditField.Value = ['2,3,4,5'];
                end
            end
                       
            value = app.RVE_DividerEditField.Value;
            divider = str2num(value);
            min_ = num2str(min(divider));
            max_ = num2str(max(divider));
            ch = app.RVE_ChoiceDropDown.Value;
            if strcmp(ch,'Subvolumes have one constant length')
                if strcmp(app.RVE_ConstantlengthsDropDown.Value,'Direction 1')
                    app.RVEdivider_Label.Text = ['from 1 x ' min_ ' x ' min_ ' to 1 x ' max_ ' x ' max_ ' subvolumes'];
                elseif strcmp(app.RVE_ConstantlengthsDropDown.Value,'Direction 2')
                    app.RVEdivider_Label.Text = ['from ' min_ ' x 1 x ' min_ ' to ' max_ ' x 1 x ' max_ ' subvolumes'];
                elseif strcmp(app.RVE_ConstantlengthsDropDown.Value,'Direction 3')
                    app.RVEdivider_Label.Text = ['from ' min_ ' x ' min_ ' x 1 to ' max_ ' x ' max_ ' x 1 subvolumes'];
                end                
            elseif strcmp(ch,'Subvolumes have two constant lengths (3D only)')
                if strcmp(app.RVE_ConstantlengthsDropDown.Value,'Directions 1 and 2')
                    app.RVEdivider_Label.Text = ['from 1 x 1 x ' min_ ' to 1 x 1 x ' max_ ' subvolumes'];
                elseif strcmp(app.RVE_ConstantlengthsDropDown.Value,'Directions 1 and 3')
                    app.RVEdivider_Label.Text = ['from 1 x ' min_ ' x 1 to 1 x ' max_ ' x 1 subvolumes'];
                elseif strcmp(app.RVE_ConstantlengthsDropDown.Value,'Directions 2 and 3')
                    app.RVEdivider_Label.Text = ['from ' min_ ' x 1 x 1 to ' max_ ' x 1 x 1 subvolumes'];
                end
            else
                app.RVEdivider_Label.Text = ['from ' min_ ' x ' min_ ' x ' min_ ' to ' max_ ' x ' max_ ' x ' max_ ' subvolumes'];
            end
        end

        % Value changed function: 
        % RelativestandarddeviationthresholdsEditField
        function RelativestandarddeviationthresholdsEditFieldValueChanged(app, event)
            value = app.RelativestandarddeviationthresholdsEditField.Value;
            thresholds = str2num(value);
            if isempty(thresholds) % Incorrect input
                app.RelativestandarddeviationthresholdsEditField.Value = ['1, 2, 3, 4, 5, 6, 7, 8, 9, 10'];
            else
                if length(thresholds)==length(unique(thresholds)) && length(thresholds)==sum(thresholds>0) && sum(thresholds==sort(thresholds))==length(thresholds) % No duplicate, >0, increasing.
                    foo=1;
                else
                    app.RelativestandarddeviationthresholdsEditField.Value = ['1, 2, 3, 4, 5, 6, 7, 8, 9, 10'];
                end
            end
        end

        % Value changed function: RVE_SubvolumeaspectratioEditField
        function RVE_SubvolumeaspectratioEditFieldValueChanged(app, event)
            value = app.RVE_SubvolumeaspectratioEditField.Value;
            AR = str2num(value);
            if isempty(AR) % Incorrect input
                app.RVE_SubvolumeaspectratioEditField.Value = ['1, 1, 1'];
            else
                if length(AR)==3 && length(AR)==sum(AR>0) % 3 number, >0.
                    foo=1;
                else
                    app.RVE_SubvolumeaspectratioEditField.Value = ['1, 1, 1'];
                end
            end    
        end

        % Value changed function: DonotcutsubvolumesmallerthanCheckBox
        function DonotcutsubvolumesmallerthanCheckBoxValueChanged(app, event)
            value = app.DonotcutsubvolumesmallerthanCheckBox.Value;
            app.RVE_minsize_EditField.Enable = value; 
        end

        % Value changed function: RVEconvergencewithfieldofviewCheckBox
        function RVEconvergencewithfieldofviewCheckBoxValueChanged(app, event)
            value = app.RVEconvergencewithfieldofviewCheckBox.Value;
            children = get(app.RVEconvergence_GridLayout,'Children');
            set(children,'Enable',value);
        end

        % Value changed function: RVEconvergence_CropDropDown
        function RVEconvergence_CropDropDownValueChanged(app, event)
            value = app.RVEconvergence_CropDropDown.Value;
            if strcmp(value,'1 direction')
                app.RVE_thatisDropDown.Enable = 'on'; app.thatisDropDownLabel.Enable = 'on';
                app.RVE_thatisDropDown.Items = {'Shrink along direction 1, from x min to x max','Shrink along direction 1, from x max to x min','Shrink along direction 1, from both ends','Shrink along direction 2, from y min to y max','Shrink along direction 2, from y max to y min','Shrink along direction 2, from both ends','Shrink along direction 3, from z min to z max','Shrink along direction 3, from z max to z min','Shrink along direction 3, from both ends'};
                app.RVE_thatisDropDown.Value = app.RVE_thatisDropDown.Items(1);
            elseif strcmp(value,'2 directions')
                app.RVE_thatisDropDown.Enable = 'on'; app.thatisDropDownLabel.Enable = 'on';
                app.RVE_thatisDropDown.Items = {'And keep direction 1 uncropped','And keep direction 2 uncropped','And keep direction 3 uncropped'};
                app.RVE_thatisDropDown.Value = app.RVE_thatisDropDown.Items(1);
            elseif strcmp(value,'3 directions')
                app.RVE_thatisDropDown.Items = {' '};
                app.RVE_thatisDropDown.Value = {' '};
                app.RVE_thatisDropDown.Enable = 'off'; app.thatisDropDownLabel.Enable = 'off';
            end      

        end

        % Button pushed function: RVE_AddrepresentativityanalysisButton
        function RVE_AddrepresentativityanalysisButtonPushed(app, event)
            % Update table
            kvol = app.RVE_ForvolumeDropDown.ValueIndex;
            kpro = app.RVE_ForpropertyDropDown.ValueIndex;

            nRVE = app.RVE_list.vol(kvol).pro(kpro).number_RVE + 1;
            app.RVE_list.vol(kvol).pro(kpro).number_RVE = nRVE;

            % Must follow this order
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).analysis = [];
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).choice_RVE = [];
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).Constantdirection = [];
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).subs2 = [];
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).divisions = [];
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).donotcut_smaller = [];
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).donotcut_smaller_than = [];
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).Aspectratio_RVE = [];
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).threshold_subs_choice = [];
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).threshold_subs_val = [];
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).RVEconvergence_with_FOV = [];
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).RVEconvergence_Crop = [];
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).RVEconvergence_thatis = [];
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).RVEconvergence_Ateachstep = [];
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).RVEconvergence_Ateachstep_x = [];
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).RVEconvergence_CropUntil = [];
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).choice_Onsub = [];
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).Growthdirection = [];
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).firstuniquevolume_size = [];
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).Growthrelativeto = [];
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).Growthperstep = [];
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).Aspectratio_Onesub = [];
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).threshold_onesub_choice = [];
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).threshold_onesub_val = [];
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).type = [];  
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).savename = [];  

            ch = app.RVE_ChoiceDropDown.Value;

            % Common to all cases
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).analysis = 'Independent subvolumes';
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).choice_RVE = ch;
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).divisions = str2num(app.RVE_DividerEditField.Value);
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).donotcut_smaller = app.DonotcutsubvolumesmallerthanCheckBox.Value;
            if app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).donotcut_smaller
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).donotcut_smaller_than = app.RVE_minsize_EditField.Value;
            else
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).donotcut_smaller_than = 'n/a';
            end
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).threshold_subs_choice = app.ThresholdDropDown.Value;
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).threshold_subs_val = str2num(app.RelativestandarddeviationthresholdsEditField.Value);

            % RVE Convergence
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).RVEconvergence_with_FOV = app.RVEconvergencewithfieldofviewCheckBox.Value;
            if app.RVEconvergencewithfieldofviewCheckBox.Value
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).RVEconvergence_Crop = app.RVEconvergence_CropDropDown.Value;
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).RVEconvergence_thatis = app.RVE_thatisDropDown.Value;
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).RVEconvergence_Ateachstep = app.AteachstepDropDown.Value;
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).RVEconvergence_Ateachstep_x = app.RVEconvergence_xEditField.Value;
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).RVEconvergence_CropUntil = app.RVEconvCropuntilnewFOVisEditField.Value;
            else
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).RVEconvergence_Crop = 'n/a';
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).RVEconvergence_thatis = 'n/a';
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).RVEconvergence_Ateachstep = 'n/a';
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).RVEconvergence_Ateachstep_x = 'n/a';
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).RVEconvergence_CropUntil = 'n/a';
            end
    
            % One subdomain
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).choice_Onsub = 'n/a';
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).Aspectratio_Onesub = 'n/a';
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).Growthdirection = 'n/a';
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).firstuniquevolume_size = 'n/a';
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).Growthperstep = 'n/a';
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).Growthrelativeto = 'n/a';
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).threshold_onesub_choice = 'n/a';
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).threshold_onesub_val = 'n/a';
            
            if strcmp(ch,'Subvolumes inherit aspect ratio from initial FOV')
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).type = 'A';
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).subs2 = 0;
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).Constantdirection = 'n/a';
                orders = app.order_calculation;
                k = find(orders==kvol);
                ROI = app.inputvolume.volume(k).ROI;
                if numel(ROI)==6
                    AR_initial = [(ROI(1,2)-ROI(1,1)+1)/(ROI(3,2)-ROI(3,1)+1) (ROI(2,2)-ROI(2,1)+1)/(ROI(3,2)-ROI(3,1)+1) 1.0];
                    str = ['SubInitialAR_' num2str(AR_initial(1),'%1.3f\t') ' ' num2str(AR_initial(2),'%1.3f\t') ' ' ' ' num2str(AR_initial(3),'%1.3f\t')];
                else
                    AR_initial = [(ROI(1,2)-ROI(1,1)+1)/(ROI(2,2)-ROI(2,1)+1) 1.0];
                    str = ['SubInitialAR_' num2str(AR_initial(1),'%1.3f\t') ' ' num2str(AR_initial(2),'%1.3f\t')];                    
                end 
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).Aspectratio_RVE = AR_initial;
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).savename = str;
            elseif strcmp(ch,'Subvolumes aspect ratio is user-defined')
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).type = 'B';
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).subs2 = 0;
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).Constantdirection = 'n/a';
                AR = str2num( app.RVE_SubvolumeaspectratioEditField.Value );
                orders = app.order_calculation;
                k = find(orders==kvol);
                if numel(app.inputvolume.volume(k).ROI)==6
                    app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).Aspectratio_RVE = AR;
                    app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).savename = ['SubCustomAR_' num2str(AR(1),'%1.3f\t') ' ' num2str(AR(2),'%1.3f\t') ' ' ' ' num2str(AR(3),'%1.3f\t')];
                else
                    app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).Aspectratio_RVE = AR(1:2);
                    app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).savename = ['SubCustomAR_' num2str(AR(1),'%1.3f\t') ' ' num2str(AR(2),'%1.3f\t')];
                end
                
            elseif strcmp(ch,'Subvolumes have one constant length')
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).type = 'C';
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).subs2 = app.RVE_2subs_CheckBox.Value;
                dir = app.RVE_ConstantlengthsDropDown.Value;
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).Constantdirection = dir;
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).savename = ['SubConstL_' dir(end)];
                
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).Aspectratio_RVE = 'n/a';
            elseif strcmp(ch,'Subvolumes have two constant lengths (3D only)')
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).type = 'D';
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).subs2 = 0;
                dir = app.RVE_ConstantlengthsDropDown.Value;
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).Constantdirection = dir;
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).savename = ['SubConstL_' dir(end-6) '_' dir(end)];                
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).Aspectratio_RVE = 'n/a';
            end

            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).savename = function_remove_emptyandspecialcharacter_string(app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).savename);

            % Update table
            app.Check_RVEtable;
        end

        % Value changed function: OneSub_ChoiceDropDown
        function OneSub_ChoiceDropDownValueChanged(app, event)
            value = app.OneSub_ChoiceDropDown.Value;
            if strcmp(value,'Growing from volume center with initial aspect ratio')
                app.OneSub_DropDown.Enable = 'off';
                app.OneSub_DropDown.Items = {};
                app.DropDown_2Label.Text = 'n/a';
                app.OneSub_SubvolumeaspectratioEditField.Enable = 'off';
                app.OneSub_SubvolumeaspectratioEditField.Value = 'n/a';
            elseif strcmp(value,'Growing from volume center with user-defined aspect ratio')
                app.OneSub_DropDown.Enable = 'off';
                app.OneSub_DropDown.Items = {};
                app.DropDown_2Label.Text = 'n/a';                
                app.OneSub_SubvolumeaspectratioEditField.Enable = 'on';
                app.OneSub_SubvolumeaspectratioEditField.Value = ['1, 1, 1'];
            elseif strcmp(value,'Growing from volume edge')
                app.OneSub_DropDown.Enable = 'on';
                app.OneSub_DropDown.Items = {'Direction 1, from x min to x max','Direction 1, from x max to x min','Direction 2, from y min to y max','Direction 2, from y max to y min','Direction 3, from z min to z max','Direction 3, from z max to z min'};
                app.DropDown_2Label.Text = 'Growth direction';      
                app.OneSub_SubvolumeaspectratioEditField.Enable = 'off';    
                app.OneSub_SubvolumeaspectratioEditField.Value = 'n/a';
            elseif strcmp(value,'Growing with one constant length')
                app.OneSub_DropDown.Enable = 'on'; 
                app.OneSub_DropDown.Items = {'Direction 1','Direction 2','Direction 3'};
                app.DropDown_2Label.Text = 'Constent length';     
                app.OneSub_SubvolumeaspectratioEditField.Enable = 'off';
                app.OneSub_SubvolumeaspectratioEditField.Value = 'n/a';
            end            
        end

        % Value changed function: RelativedifferencethresholdsEditField
        function RelativedifferencethresholdsEditFieldValueChanged(app, event)
            value = app.RelativedifferencethresholdsEditField.Value;
            thresholds = str2num(value);
            if isempty(thresholds) % Incorrect input
                app.RelativedifferencethresholdsEditField.Value = ['1, 2, 3, 4, 5, 6, 7, 8, 9, 10'];
            else
                if length(thresholds)==length(unique(thresholds)) && length(thresholds)==sum(thresholds>0) && sum(thresholds==sort(thresholds))==length(thresholds) % No duplicate, >0, increasing.
                    foo=1;
                else
                    app.RelativedifferencethresholdsEditField.Value = ['1, 2, 3, 4, 5, 6, 7, 8, 9, 10'];
                end
            end
        end

        % Button pushed function: OneSub_AddrepresentativityanalysisButton
        function OneSub_AddrepresentativityanalysisButtonPushed(app, event)
            % Update table
            kvol = app.OneSub_ForvolumeDropDown.ValueIndex;
            kpro = app.OneSub_ForpropertyDropDown.ValueIndex;

            nRVE = app.RVE_list.vol(kvol).pro(kpro).number_RVE + 1;
            app.RVE_list.vol(kvol).pro(kpro).number_RVE = nRVE;

            % Must follow this order
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).analysis = [];
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).choice_RVE = [];
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).Constantdirection = [];
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).subs2 = [];
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).divisions = [];
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).donotcut_smaller = [];
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).donotcut_smaller_than = [];
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).Aspectratio_RVE = [];
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).threshold_subs_choice = [];
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).threshold_subs_val = [];            
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).RVEconvergence_with_FOV = [];
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).RVEconvergence_Crop = [];
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).RVEconvergence_thatis = [];
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).RVEconvergence_Ateachstep = [];
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).RVEconvergence_Ateachstep_x = [];
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).RVEconvergence_CropUntil = [];
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).choice_Onsub = [];
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).Growthdirection = [];
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).firstuniquevolume_size = [];
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).Growthrelativeto = [];
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).Growthperstep = [];
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).Aspectratio_Onesub = [];
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).threshold_onesub_choice = [];
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).threshold_onesub_val = [];            
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).type = [];  
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).savename = [];  

            ch = app.OneSub_ChoiceDropDown.Value;

            % Common to all cases
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).analysis = 'One subvolume';
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).choice_Onsub = ch;
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).firstuniquevolume_size = app.SizeofthefirstsubvolumeEditField.Value;
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).Growthrelativeto = app.OneSub_Growthrealtiveto_DropDown.Value;
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).Growthperstep = app.SubvolumegrowthEditField.Value;
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).threshold_onesub_choice = app.ThresholdDropDown_2.Value;
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).threshold_onesub_val = str2num(app.RelativedifferencethresholdsEditField.Value);

            % RVE options
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).choice_RVE = 'n/a';
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).Constantdirection = 'n/a';
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).subs2 = 'n/a';
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).divisions = 'n/a';
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).donotcut_smaller = 'n/a';
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).donotcut_smaller_than = 'n/a';
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).Aspectratio_RVE = 'n/a';
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).threshold_subs_choice = 'n/a';
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).threshold_subs_val = 'n/a';
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).RVEconvergence_with_FOV = 'n/a';
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).RVEconvergence_Crop = 'n/a';
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).RVEconvergence_thatis = 'n/a';
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).RVEconvergence_Ateachstep = 'n/a';
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).RVEconvergence_Ateachstep_x = 'n/a';
            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).RVEconvergence_CropUntil = 'n/a';
            
            if strcmp(ch,'Growing from volume center with initial aspect ratio')
                orders = app.order_calculation;
                k = find(orders==kvol);
                ROI = app.inputvolume.volume(k).ROI;
                if numel(ROI)==6
                    AR_initial = [(ROI(1,2)-ROI(1,1)+1)/(ROI(3,2)-ROI(3,1)+1) (ROI(2,2)-ROI(2,1)+1)/(ROI(3,2)-ROI(3,1)+1) 1.0];
                    str = ['GrowthCenter_InitialAR_' num2str(AR_initial(1),'%1.3f\t') ' ' num2str(AR_initial(2),'%1.3f\t') ' ' ' ' num2str(AR_initial(3),'%1.3f\t')];
                else
                    AR_initial = [(ROI(1,2)-ROI(1,1)+1)/(ROI(2,2)-ROI(2,1)+1) 1.0];
                    str = ['GrowthCenter_InitialAR_' num2str(AR_initial(1),'%1.3f\t') ' ' num2str(AR_initial(2),'%1.3f\t')];  
                end 
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).Growthdirection = 'n/a';
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).Aspectratio_Onesub = AR_initial;
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).type = 'E';
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).savename = str;

            elseif strcmp(ch,'Growing from volume center with user-defined aspect ratio')
                AR = str2num( app.OneSub_SubvolumeaspectratioEditField.Value );
                orders = app.order_calculation;
                k = find(orders==kvol);
                if numel(app.inputvolume.volume(k).ROI)==6
                    app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).Aspectratio_Onesub = AR;
                    app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).savename = ['GrowthCenter_CustomAR_' num2str(AR(1),'%1.3f\t') ' ' num2str(AR(2),'%1.3f\t') ' ' ' ' num2str(AR(3),'%1.3f\t')];
                else
                    app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).Aspectratio_Onesub = AR(1:2);
                    app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).savename = ['GrowthCenter_CustomAR_' num2str(AR(1),'%1.3f\t') ' ' num2str(AR(2),'%1.3f\t')];
                end
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).Growthdirection = 'n/a';
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).type = 'F';

            elseif strcmp(ch,'Growing from volume edge')
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).Aspectratio_Onesub = 'n/a';
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).type = 'G';
                dir = app.OneSub_DropDown.Value;
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).Growthdirection = dir;
                if strcmp(dir, 'Direction 1, from x min to x max')
                    app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).savename = 'GrowthEdge_D1_min2max';
                elseif strcmp(dir,'Direction 1, from x max to x min')
                    app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).savename = 'GrowthEdge_D1_max2min';
                elseif strcmp(dir,'Direction 2, from y min to y max')
                    app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).savename = 'GrowthEdge_D2_min2max';
                elseif strcmp(dir,'Direction 2, from y max to y min')
                    app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).savename = 'GrowthEdge_D2_max2min';
                elseif strcmp(dir,'Direction 3, from z min to z max')
                    app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).savename = 'GrowthEdge_D3_min2max';
                elseif strcmp(dir,'Direction 3, from z max to z min')
                    app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).savename = 'GrowthEdge_D3_max2min';
                end                

            elseif strcmp(ch,'Growing with one constant length')
                dir = app.OneSub_DropDown.Value;
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).Growthdirection = dir;
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).Aspectratio_Onesub = 'n/a';
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).type = 'H';
                app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).savename = ['Growth_ConstL_' dir(end)];
            end                

            app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).savename = function_remove_emptyandspecialcharacter_string(app.RVE_list.vol(kvol).pro(kpro).RVE(nRVE).savename);

            % Update table
            app.Check_RVEtable;
        end

        % Value changed function: OneSub_SubvolumeaspectratioEditField
        function OneSub_SubvolumeaspectratioEditFieldValueChanged(app, event)
            value = app.OneSub_SubvolumeaspectratioEditField.Value;
            AR = str2num(value);
            if isempty(AR) % Incorrect input
                app.OneSub_SubvolumeaspectratioEditField.Value = ['1, 1, 1'];
            else
                if length(AR)==3 && length(AR)==sum(AR>0) % 3 number, >0.
                    foo=1;
                else
                    app.OneSub_SubvolumeaspectratioEditField.Value = ['1, 1, 1'];
                end
            end  
        end

        % Value changed function: Allanalyses_ForpropertyDropDown, 
        % ...and 1 other component
        function Allanalyses_ForvolumeDropDownValueChanged(app, event)
            app.Check_RVEtable;
        end

        % Button pushed function: RemoveallanalysesforthisvolumeButton
        function RemoveallanalysesforthisvolumeButtonPushed(app, event)
            kvol = app.Allanalyses_ForvolumeDropDown.ValueIndex;
            kpro = app.Allanalyses_ForpropertyDropDown.ValueIndex;
            app.RVE_list.vol(kvol).pro(kpro).number_RVE = 0;
            app.RVE_list.vol(kvol).pro(kpro).RVE = [];
            app.ListAnalysis_UITable.ColumnName = {'Parameter','Analysis #1'};
            app.ListAnalysis_UITable.Data = [{'Type';'Choice (independent subvolumes)';'Constant length(s)';'Also calculate the 2 subvolumes case';'Dividers';'Do not cut volume smaller than';'Do not cut volume smaller than (value)';'Subvolume aspect ratio';'Threshold choice';'Threshold value(s)';'RVE convergence with field of view';'Crop';'that is';'At each step';'x(%)';'Crop until new FOV is % of the initial FOV';'Choice (one subvolume)';'That is';'Size of the first subvolume % of the initial volume';'Subvolume growth';'Subvolume growth (value)';'Subvolume aspect ratio';'Threshold choice';'Threshold value(s)'} {' ';' ';' ';' ';' ';' ';' ';' ';' ';' ';' ';' ';' ';' ';' ';' ';' ';' ';' ';' ';' ';' ';' ';' '}];
            app.RemoveallanalysesforthisvolumeButton.Enable = 'off';
            app.ApplyonALLpropertiesforthisvolumeButton.Enable = 'off';
            app.ApplyonthispropertyforALLvolumesButton.Enable = 'off';
            app.ApplyonALLpropertiesforALLvolumesButton.Enable = 'off';
            app.ApplyPERpropertyforALLvolumesButton.Enable = 'off';
        end

        % Button pushed function: ApplyonALLpropertiesforthisvolumeButton
        function ApplyonALLpropertiesforthisvolumeButtonPushed(app, event)
            kvol = app.Allanalyses_ForvolumeDropDown.ValueIndex;
            kpro = app.Allanalyses_ForpropertyDropDown.ValueIndex;
            n_property = length(app.property_RVE_list);
            for kkpro=1:1:n_property
                app.RVE_list.vol(kvol).pro(kkpro) = app.RVE_list.vol(kvol).pro(kpro);
            end            
        end

        % Button pushed function: ApplyonthispropertyforALLvolumesButton
        function ApplyonthispropertyforALLvolumesButtonPushed(app, event)
            kvol = app.Allanalyses_ForvolumeDropDown.ValueIndex;
            kpro = app.Allanalyses_ForpropertyDropDown.ValueIndex;
            n_volume = length(app.Allanalyses_ForvolumeDropDown.Items);
            for kkvol=1:1:n_volume
                app.RVE_list.vol(kkvol).pro(kpro) = app.RVE_list.vol(kvol).pro(kpro);
            end    
        end

        % Button pushed function: ApplyonALLpropertiesforALLvolumesButton
        function ApplyonALLpropertiesforALLvolumesButtonPushed(app, event)
            kvol = app.Allanalyses_ForvolumeDropDown.ValueIndex;
            kpro = app.Allanalyses_ForpropertyDropDown.ValueIndex;
            n_property = length(app.property_RVE_list);
            n_volume = length(app.Allanalyses_ForvolumeDropDown.Items);
            for kkvol=1:1:n_volume
                for kkpro=1:1:n_property
                    app.RVE_list.vol(kkvol).pro(kkpro) = app.RVE_list.vol(kvol).pro(kpro);
                end
            end
        end

        % Button pushed function: ApplyPERpropertyforALLvolumesButton
        function ApplyPERpropertyforALLvolumesButtonPushed(app, event)
            kvol = app.Allanalyses_ForvolumeDropDown.ValueIndex;
            n_property = length(app.property_RVE_list);
            n_volume = length(app.Allanalyses_ForvolumeDropDown.Items);
            for kkvol=1:1:n_volume
                for kkpro=1:1:n_property
                    app.RVE_list.vol(kkvol).pro(kkpro) = app.RVE_list.vol(kvol).pro(kkpro);
                end
            end   
        end

        % Value changed function: Cpsd_AllphasesCheckBox
        function Cpsd_AllphasesCheckBoxValueChanged(app, event)
            value = app.Cpsd_AllphasesCheckBox.Value;
            if app.Cpsd_Semantic_UITable.Enable
                t = app.Cpsd_Semantic_UITable.Data;
                t = app.func_selectall(t,value);
                app.Cpsd_Semantic_UITable.Data = t;
            end
            if app.Cpsd_Instance_UITable.Enable
                t = app.Cpsd_Instance_UITable.Data;
                t = app.func_selectall(t,value);
                app.Cpsd_Instance_UITable.Data = t;
            end
        end

        % Value changed function: SelectmethodDropDown
        function SelectmethodDropDownValueChanged(app, event)
            value = app.SelectmethodDropDown.Value;
            if strcmp(value,'Iterative')
                app.ToleranceEditField.Enable = 'on';
                app.MaxiterEditField.Enable = 'on';  
            else
                app.ToleranceEditField.Enable = 'off';
                app.MaxiterEditField.Enable = 'off';  
            end
        end

        % Button pushed function: SavefolderButton
        function SavefolderButtonPushed(app, event)
            str_dialogbox = 'Select macro folder';
            % Open dialog box to choose folder path
            res = uigetdir(pwd,str_dialogbox);
            if res
                app.Macrosavedolfer_Label.Text = res;
            end
        end

        % Button pushed function: SavemacroButton
        function SavemacroButtonPushed(app, event)
            app.savemacro = true;
            app.Create_pars_for_run;
        end

        % Value changed function: LowerScale_Nanoporosity_DropDown
        function LowerScale_Nanoporosity_DropDownValueChanged(app, event)
            value = app.LowerScale_Nanoporosity_DropDown.Value;
            app.nanoporosity_array = [];
            if strcmp(value,'Mixed (heterogeneous)')
                app.Nanoporosity_file_Label.Text = 'No file imported';
            else
                app.Nanoporosity_file_Label.Text = 'n/a';
            end
            app.Nanoporosity_file_Label.Tooltip = app.Nanoporosity_file_Label.Text;

            [nphase,~] = size(app.PhasecolornameSemantic_UITable.Data);
            if strcmp(value,'Binary') || strcmp(value,'Mixed (uniform)')
                nanoporosity = num2cell(zeros(nphase,1));
                if app.ThereisabackgroundCheckBox.Value
                    nanoporosity(1,1) = {'n/a'};
                    nanoporosity(2,1) = {1.0};
                else
                    nanoporosity(1,1) = {1.0};
                end
                app.PhasecolornameSemantic_UITable.Data(:,6) = nanoporosity;
                app.PhasecolornameSemantic_UITable.ColumnEditable(6)=true;
                app.ImportnanoporosityButton.Enable = 'off';
                app.Nanoporosity_file_Label.Enable = 'off';
                if strcmp(value,'Binary')
                    app.PhasecolornameSemantic_UITable.Tooltip = {'Phase color and name will be used in figures and tables, so you do not have to reformat them manually. Nanoporosity is either 0 (fully solid) or 1 (fully porous).'};
                else
                    app.PhasecolornameSemantic_UITable.Tooltip = {'Phase color and name will be used in figures and tables, so you do not have to reformat them manually. Nanoporosity is between 0 and 1 and modifies volume fraction, interface, and transport properties.'};
                end
            elseif strcmp(value,'Mixed (heterogeneous)')
                notimported = cell(nphase,1);
                for k=1:nphase
                    notimported(k,1) = {'not yet imported'};
                end
                if app.ThereisabackgroundCheckBox.Value
                    notimported(1,1) = {'n/a'};
                end
                app.PhasecolornameSemantic_UITable.Data(:,6) = notimported;
                app.PhasecolornameSemantic_UITable.ColumnEditable(6)=false;
                app.ImportnanoporosityButton.Enable = 'on';
                app.Nanoporosity_file_Label.Enable = 'on';
                app.PhasecolornameSemantic_UITable.Tooltip = {'Phase color and name will be used in figures and tables, so you do not have to reformat them manually. Nanoporosity is between 0 and 1 and modifies volume fraction, interface, and transport properties. The averaged value is indicated.'};
            end
            app.check_import;


        end

        % Button pushed function: ImportnanoporosityButton
        function ImportnanoporosityButtonPushed(app, event)
            app.nanoporosity_array = [];
            if isempty(app.PathImport)
                [tmp_FileName,tmp_PathImport,~] = uigetfile({'*.tif;*.tiff','Tif image (*.tif, *.tiff)'},'Select a .tif file (nanoporosity [0,1])',pwd);
            else
                [tmp_FileName,tmp_PathImport,~] = uigetfile({'*.tif;*.tiff','Tif image (*.tif, *.tiff)'},'Select a .tif file (nanoporosity [0,1])',app.PathImport);
            end
            if ~tmp_FileName==0
                app.Nanoporosity_file_Label.Text = 'Loading file... please wait'; pause(0.1);
                [tmp,outcome] = function_load_tif([tmp_PathImport tmp_FileName],'double');
                if outcome
                    sz = size(tmp);
                    if length(sz) == length(app.semantic_fileinfo.sz) && sum(sz==app.semantic_fileinfo.sz)==length(sz)
                        if min(min(min(tmp)))>=0 % && max(max(max(tmp)))<=1
                            if max(max(max(tmp)))>1
                                tmp = tmp/max(max(max(tmp)));
                            end
                            app.nanoporosity_path = fullfile(tmp_PathImport,tmp_FileName);
                            app.nanoporosity_array = tmp;
                            app.Nanoporosity_file_Label.Text = fullfile(tmp_PathImport,tmp_FileName);
                            res = num2cell(app.get_average(tmp)); % Calculate average nanoporosity      
                            if app.ThereisabackgroundCheckBox.Value
                                res(1) = {'n/a'};
                            end
                            app.PhasecolornameSemantic_UITable.Data(:,6) = res;
                        else
                            app.Nanoporosity_file_Label.Text = 'Array has <0 values!';
                        end
                    else
                        app.Nanoporosity_file_Label.Text = 'Incompatible dimension!';
                    end
                end
                app.LowerScale_PoreTransport_DropDownValueChanged;
                app.LowerScale_SolidTransport_DropDownValueChanged;
                app.PartialwettingCheckBoxValueChanged;
                app.check_import;
            else
                app.Nanoporosity_file_Label.Text ='No file imported.';
            end
            app.Nanoporosity_file_Label.Tooltip = app.Nanoporosity_file_Label.Text;
        end

        % Button pushed function: ImportporediffusivityButton
        function ImportporediffusivityButtonPushed(app, event)
            app.bulkdiffusivity_array = [];
            if isempty(app.PathImport)
                [tmp_FileName,tmp_PathImport,~] = uigetfile({'*.tif;*.tiff','Tif image (*.tif, *.tiff)'},'Select a .tif file (pore bulk diffusivity [0,1])',pwd);
            else
                [tmp_FileName,tmp_PathImport,~] = uigetfile({'*.tif;*.tiff','Tif image (*.tif, *.tiff)'},'Select a .tif file (pore bulk diffusivity [0,1])',app.PathImport);
            end
            if ~tmp_FileName==0
                app.Bulkdiffusivity_file_Label.Text = 'Loading file... please wait'; pause(0.1);
                [tmp,outcome] = function_load_tif([tmp_PathImport tmp_FileName],'double');
                if outcome
                    sz = size(tmp);
                    if length(sz) == length(app.semantic_fileinfo.sz) && sum(sz==app.semantic_fileinfo.sz)==length(sz)
                        if min(min(min(tmp)))>=0 % && max(max(max(tmp)))<=1
                            if max(max(max(tmp)))>1
                                tmp = tmp/max(max(max(tmp)));
                            end
                            app.bulkdiffusivity_path = fullfile(tmp_PathImport,tmp_FileName);
                            app.bulkdiffusivity_array = tmp;
                            app.Bulkdiffusivity_file_Label.Text = fullfile(tmp_PathImport,tmp_FileName);

                            res = num2cell(app.get_average(tmp)); % Calculate average nanoporosity
                            if app.ThereisabackgroundCheckBox.Value
                                res(1) = {'n/a'};
                            end
                            app.LowerscaleTransport_UITable.Data(:,1) = res;

                        else
                            app.Bulkdiffusivity_file_Label.Text = 'Array has <0 values!';
                        end
                    else
                        app.Bulkdiffusivity_file_Label.Text = 'Incompatible dimension!';
                    end
                end
            else
                app.Bulkdiffusivity_file_Label.Text ='No file imported.';
            end
            app.Bulkdiffusivity_file_Label.Tooltip = app.Bulkdiffusivity_file_Label.Text; 
            app.check_import;
        end

        % Button pushed function: ImportAirSaturation_Button
        function ImportAirSaturation_ButtonPushed(app, event)
            app.airsaturation_array = [];
            if isempty(app.PathImport)
                [tmp_FileName,tmp_PathImport,~] = uigetfile({'*.tif;*.tiff','Tif image (*.tif, *.tiff)'},['Select a .tif file (' app.Airsaturation_terminology_DropDown.Value ' [0,1])'],pwd);
            else
                [tmp_FileName,tmp_PathImport,~] = uigetfile({'*.tif;*.tiff','Tif image (*.tif, *.tiff)'},['Select a .tif file (' app.Airsaturation_terminology_DropDown.Value ' [0,1])'],app.PathImport);
            end
            if ~tmp_FileName==0
                app.Airsaturation_file_Label.Text = 'Loading file... please wait'; pause(0.1);
                [tmp,outcome] = function_load_tif([tmp_PathImport tmp_FileName],'double');
                if outcome
                    sz = size(tmp);
                    if length(sz) == length(app.semantic_fileinfo.sz) && sum(sz==app.semantic_fileinfo.sz)==length(sz)
                        if min(min(min(tmp)))>=0 % && max(max(max(tmp)))<=1
                            if max(max(max(tmp)))>1
                                tmp = tmp/max(max(max(tmp))); % Normalize assuming max is saturation or wetting = 1
                            end
                            app.airsaturation_path = fullfile(tmp_PathImport,tmp_FileName);
                            app.airsaturation_array = tmp;
                            app.Airsaturation_file_Label.Text = fullfile(tmp_PathImport,tmp_FileName);
                            res = num2cell(app.get_average(tmp));           
                            if app.ThereisabackgroundCheckBox.Value
                                res(1) = {'n/a';}
                            end
                            app.AirSaturation_UITable.Data = res;
                        else
                            app.Airsaturation_file_Label.Text = 'Array has <0 values!';
                        end
                    else
                        app.Airsaturation_file_Label.Text = 'Incompatible dimension!';
                    end
                end
            else
                app.Airsaturation_file_Label.Text ='No file imported.';
            end

            if isempty(app.airsaturation_array)
                [nphase,~] = size(app.PhasecolornameSemantic_UITable.Data);
                notimported = cell(nphase,1);
                for k=1:1:nphase
                    notimported(k,1) = {'not yet imported'};
                end
                app.AirSaturation_UITable.Data = notimported;
            end
         
            app.Airsaturation_file_Label.Enable = 'on';
            app.Airsaturation_file_Label.Tooltip = app.Airsaturation_file_Label.Text; 
            app.check_import;    
        end

        % Value changed function: Airsaturation_terminology_DropDown
        function Airsaturation_terminology_DropDownValueChanged(app, event)
            value = app.Airsaturation_terminology_DropDown.Value;
            app.AirSaturation_UITable.ColumnName = value;
            if ~isempty(app.AirSaturation_UITable.Data)
                [nphase,~] = size(app.AirSaturation_UITable.Data);
                for k=1:nphase
                    if ~ischar( cell2mat(app.AirSaturation_UITable.Data(k,1)) )
                        app.AirSaturation_UITable.Data(k,1) = {abs( (1- cell2mat(app.AirSaturation_UITable.Data(k,1)))) };
                    end                
                end
            end
        end

        % Value changed function: Airsaturation_uniform_DropDown
        function Airsaturation_uniform_DropDownValueChanged(app, event)
            value = app.Airsaturation_uniform_DropDown.Value;
            app.airsaturation_array = [];
            if strcmp(value,'Uniform')
                app.Airsaturation_file_Label.Text = 'n/a';
                app.Airsaturation_file_Label.Enable = 'off';
                app.AirSaturation_UITable.Tooltip = 'Wettability and air saturation values ([0,1]) are only considered for phases with nanoporosity > 0. It modifies volume fraction, interface, and transport properties.';
                app.ImportAirSaturation_Button.Enable = 'off';
                [~, ~, ~, ~, wettability, airsaturation] = app.Porosity_modified;
                if strcmp(app.Airsaturation_terminology_DropDown.Value,'Air saturation')
                    app.AirSaturation_UITable.Data = airsaturation;
                elseif strcmp(app.Airsaturation_terminology_DropDown.Value,'Wettability')
                    app.AirSaturation_UITable.Data = wettability;
                end
                app.AirSaturation_UITable.ColumnEditable = true;

            elseif strcmp(value,'Heterogeneous')
                app.Airsaturation_file_Label.Text = 'No file imported';
                app.Airsaturation_file_Label.Enable = 'on';
                app.AirSaturation_UITable.Tooltip = 'Wettability and air saturation values ([0,1]) are only considered for phases with nanoporosity > 0. It modifies volume fraction, interface, and transport properties. Averaged per phase.';
                [nphase,~] = size(app.PhasecolornameSemantic_UITable.Data);
                notimported = cell(nphase,1);
                for k=1:1:nphase
                    notimported(k,1) = {'not yet imported'};
                end
                if app.ThereisabackgroundCheckBox.Value
                    notimported(1) = {'n/a'};
                end
                app.AirSaturation_UITable.Data = notimported;
                app.ImportAirSaturation_Button.Enable = 'on';
                app.AirSaturation_UITable.ColumnEditable = [false];
            end    
            app.Airsaturation_file_Label.Tooltip = app.Airsaturation_file_Label.Text;
            app.check_import;    
        end

        % Value changed function: PartialwettingCheckBox
        function PartialwettingCheckBoxValueChanged(app, event)
            value = app.PartialwettingCheckBox.Value;
            app.Airsaturation_terminology_DropDown.Enable = value;
            app.Airsaturation_uniform_DropDown.Enable = value;
            if value
                app.Airsaturation_terminology_DropDownValueChanged;
                app.Airsaturation_uniform_DropDownValueChanged;
                app.AirSaturation_UITable.Enable = 'on';
            else
                app.AirSaturation_UITable.Enable  = 'off';
                app.AirSaturation_UITable.Data = [];
                app.Airsaturation_file_Label.Text = {'n/a'};
                app.Airsaturation_file_Label.Tooltip = app.Airsaturation_file_Label.Text;
            end
            app.check_import;                
        end

        % Cell edit callback: AirSaturation_UITable
        function AirSaturation_UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            oldData = event.PreviousData;
            if ischar(oldData) || isnan(newData) || newData>1 || newData<0
                app.AirSaturation_UITable.Data(indices(1),indices(2)) = {oldData};
            end   
        end

        % Cell edit callback: LowerscaleTransport_UITable
        function LowerscaleTransport_UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            oldData = event.PreviousData;
            if ischar(oldData) % 'n/a'
                app.LowerscaleTransport_UITable.Data(indices(1),indices(2)) = {oldData};
            else
                if indices(2)==1 % Pore
                    if strcmp(app.LowerScale_PoreTransport_DropDown.Value,'Diffusivity D (uniform)') % [0,1]
                        if isnan(newData) || newData<0 || newData>1
                            app.LowerscaleTransport_UITable.Data(indices(1),indices(2)) = {oldData};
                        end
                    elseif strcmp(app.LowerScale_PoreTransport_DropDown.Value,'Bruggeman exponent p (uniform)') % >=1
                        if isnan(newData) || newData<1
                            app.LowerscaleTransport_UITable.Data(indices(1),indices(2)) = {oldData};
                        end
                    end

                elseif indices(2)==2 % Solid
                    if strcmp(app.LowerScale_SolidTransport_DropDown.Value,'Conductivity K (uniform)') % [0,1]
                        if isnan(newData) || newData<0 || newData>1
                            app.LowerscaleTransport_UITable.Data(indices(1),indices(2)) = {oldData};
                        end
                    elseif strcmp(app.LowerScale_SolidTransport_DropDown.Value,'Bruggeman exponent p (uniform)') % >=1
                        if isnan(newData) || newData<1
                            app.LowerscaleTransport_UITable.Data(indices(1),indices(2)) = {oldData};
                        end
                    end
                end
                
            end
        end

        % Value changed function: LowerScale_PoreTransport_DropDown
        function LowerScale_PoreTransport_DropDownValueChanged(app, event)
            value = app.LowerScale_PoreTransport_DropDown.Value;
            app.bulkdiffusivity_array = [];
            if strcmp(value,'Diffusivity D (heterogeneous)')
                app.Bulkdiffusivity_file_Label.Text = 'No file imported';
            else
                app.Bulkdiffusivity_file_Label.Text = 'n/a';
            end
            app.Bulkdiffusivity_file_Label.Tooltip = app.Bulkdiffusivity_file_Label.Text;

            [nphase,~] = size(app.PhasecolornameSemantic_UITable.Data);
                        
            [diffusivity, Bruggeman_pore, ~, ~, ~, ~] = app.Porosity_modified;
            if strcmp(value,'Diffusivity D (uniform)')
                app.LowerscaleTransport_UITable.Data(:,1) = diffusivity;
                app.LowerscaleTransport_UITable.ColumnEditable(1)=true;
                app.LowerscaleTransport_UITable.ColumnName(1)={'Pore D'};
                app.ImportporediffusivityButton.Enable = 'off';
                app.Bulkdiffusivity_file_Label.Enable = 'off';

            elseif  strcmp(value,'Diffusivity D (heterogeneous)')
                notimported = cell(nphase,1);
                for k=1:nphase
                    notimported(k,1) = {'not yet imported'};
                end
                if app.ThereisabackgroundCheckBox.Value
                    notimported(1,1) = {'n/a'};
                end
                app.LowerscaleTransport_UITable.Data(:,1) = notimported;
                app.LowerscaleTransport_UITable.ColumnEditable(1)=false;
                app.LowerscaleTransport_UITable.ColumnName(1)={'Pore D'};
                app.ImportporediffusivityButton.Enable = 'on';
                app.Bulkdiffusivity_file_Label.Enable = 'on';

            elseif  strcmp(value,'Bruggeman exponent p (uniform)')
                app.LowerscaleTransport_UITable.Data(:,1) = Bruggeman_pore;
                app.LowerscaleTransport_UITable.ColumnEditable(1)=true;
                app.LowerscaleTransport_UITable.ColumnName(1)={'Pore p'};
                app.ImportporediffusivityButton.Enable = 'off';
                app.Bulkdiffusivity_file_Label.Enable = 'off';

            end
            app.check_import;
        end

        % Button pushed function: ImportsolidconductivityButton
        function ImportsolidconductivityButtonPushed(app, event)
            app.bulkconductivity_array = [];
            if isempty(app.PathImport)
                [tmp_FileName,tmp_PathImport,~] = uigetfile({'*.tif;*.tiff','Tif image (*.tif, *.tiff)'},'Select a .tif file (solid bulk conductivity [0,1])',pwd);
            else
                [tmp_FileName,tmp_PathImport,~] = uigetfile({'*.tif;*.tiff','Tif image (*.tif, *.tiff)'},'Select a .tif file (solid bulk conductivity [0,1])',app.PathImport);
            end
            if ~tmp_FileName==0
                app.BulkConductivity_file_Label.Text = 'Loading file... please wait'; pause(0.1);
                [tmp,outcome] = function_load_tif([tmp_PathImport tmp_FileName],'double');
                if outcome
                    sz = size(tmp);
                    if length(sz) == length(app.semantic_fileinfo.sz) && sum(sz==app.semantic_fileinfo.sz)==length(sz)
                        if min(min(min(tmp)))>=0 % && max(max(max(tmp)))<=1
                            if max(max(max(tmp)))>1
                                tmp = tmp/max(max(max(tmp)));
                            end
                            app.bulkconductivity_path = fullfile(tmp_PathImport,tmp_FileName);
                            app.bulkconductivity_array = tmp;
                            app.BulkConductivity_file_Label.Text = fullfile(tmp_PathImport,tmp_FileName);

                            res = num2cell(app.get_average(tmp)); % Calculate average nanoporosity
                            if app.ThereisabackgroundCheckBox.Value
                                res(1) = {'n/a'};
                            end
                            app.LowerscaleTransport_UITable.Data(:,2) = res;

                        else
                            app.BulkConductivity_file_Label.Text = 'Array has <0 values!';
                        end
                    else
                        app.BulkConductivity_file_Label.Text = 'Incompatible dimension!';
                    end
                end
            else
                app.BulkConductivity_file_Label.Text ='No file imported.';
            end
            app.BulkConductivity_file_Label.Tooltip = app.BulkConductivity_file_Label.Text; 
            app.check_import;
        end

        % Value changed function: LowerScale_SolidTransport_DropDown
        function LowerScale_SolidTransport_DropDownValueChanged(app, event)
            value = app.LowerScale_SolidTransport_DropDown.Value;
            app.bulkconductivity_array = [];
            if strcmp(value,'Conductivity K (heterogeneous)')
                app.BulkConductivity_file_Label.Text = 'No file imported';
            else
                app.BulkConductivity_file_Label.Text = 'n/a';
            end
            app.BulkConductivity_file_Label.Tooltip = app.BulkConductivity_file_Label.Text;

            [nphase,~] = size(app.PhasecolornameSemantic_UITable.Data);
            [~, ~, conductivity, Bruggeman_solid, ~, ~] = app.Porosity_modified;

            if strcmp(value,'Conductivity K (uniform)')
                app.LowerscaleTransport_UITable.Data(:,2) = conductivity;
                app.LowerscaleTransport_UITable.ColumnEditable(2)=true;
                app.LowerscaleTransport_UITable.ColumnName(2)={'Solid K'};
                app.ImportsolidconductivityButton.Enable = 'off';
                app.BulkConductivity_file_Label.Enable = 'off';

            elseif  strcmp(value,'Conductivity K (heterogeneous)')
                notimported = cell(nphase,1);
                for k=1:nphase
                    notimported(k,1) = {'not yet imported'};
                end
                if app.ThereisabackgroundCheckBox.Value
                    notimported(1,1) = {'n/a'};
                end
                app.LowerscaleTransport_UITable.Data(:,2) = notimported;
                app.LowerscaleTransport_UITable.ColumnEditable(2)=false;
                app.LowerscaleTransport_UITable.ColumnName(2)={'Solid K'};
                app.ImportsolidconductivityButton.Enable = 'on';
                app.BulkConductivity_file_Label.Enable = 'on';

            elseif  strcmp(value,'Bruggeman exponent p (uniform)')
                app.LowerscaleTransport_UITable.Data(:,2) = Bruggeman_solid;
                app.LowerscaleTransport_UITable.ColumnEditable(2)=true;
                app.LowerscaleTransport_UITable.ColumnName(2)={'Solid p'};
                app.ImportsolidconductivityButton.Enable = 'off';
                app.BulkConductivity_file_Label.Enable = 'off';

            end
            app.check_import;
        end

        % Cell edit callback: Volumefraction_Semantic_UITable
        function Volumefraction_Semantic_UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            oldData = event.PreviousData;
            if isempty(oldData)
                app.Volumefraction_Semantic_UITable.Data(indices(1),indices(2)) = {''};
            end
            if indices(2)==2 && newData % Combined
                [~,c] = size(app.Volumefraction_Semantic_UITable.Data);
                for k=4:2:c
                    if ~isempty(app.Volumefraction_Semantic_UITable.Data(indices(1),k))
                        app.Volumefraction_Semantic_UITable.Data(indices(1),k) = {logical(1)};
                    end
                end
            end

        end

        % Callback function
        function SelectUnselectallButtonPushed(app, event)
            
        end

        % Button pushed function: 
        % Applyfirstvolumeselections_volfraction_Button
        function Applyfirstvolumeselections_volfraction_ButtonPushed(app, event)
            if ~isempty(app.Volumefraction_Semantic_UITable.Data) 
                [data] = app.ApplyFirstSelection_to_all(app.Volumefraction_Semantic_UITable.Data);
                app.Volumefraction_Semantic_UITable.Data = data;
            end
            if ~isempty(app.Volumefraction_Instance_UITable.Data) 
                [data] = app.ApplyFirstSelection_to_all(app.Volumefraction_Instance_UITable.Data);
                app.Volumefraction_Instance_UITable.Data = data;
            end            
        end

        % Button pushed function: SelectUnselectall_volfraction_Button
        function SelectUnselectall_volfraction_ButtonPushed2(app, event)
            if ~isempty(app.Volumefraction_Semantic_UITable.Data)
                [data] = app.Swicthselections(app.Volumefraction_Semantic_UITable.Data);
                app.Volumefraction_Semantic_UITable.Data = data;
            end
            if ~isempty(app.Volumefraction_Instance_UITable.Data)
                [data] = app.Swicthselections(app.Volumefraction_Instance_UITable.Data);
                app.Volumefraction_Instance_UITable.Data = data;
            end            
        end

        % Value changed function: pngCheckBox
        function pngCheckBoxValueChanged(app, event)
            value = app.pngCheckBox.Value;
            app.DPIresolutionEditField.Enable = value;
        end

        % Value changed function: SavefiguresinfollowingfileformatCheckBox
        function SavefiguresinfollowingfileformatCheckBoxValueChanged(app, event)
            value = app.SavefiguresinfollowingfileformatCheckBox.Value;
            app.figCheckBox.Enable = value;
            app.pngCheckBox.Enable = value; app.pngCheckBoxValueChanged;
            app.svgscalablevectorgraphicsCheckBox.Enable = value;
            app.AllowoverwritefigurefilesCheckBox.Enable = value;
            app.ClosefigureautomaticallyoncesavedCheckBox.Enable = value;
            if ~value
                app.ClosefigureautomaticallyoncesavedCheckBox.Value = false;
            end            
        end

        % Button pushed function: 
        % Tau_Applyfirstvolumeselections_volfraction_Button
        function Tau_Applyfirstvolumeselections_volfraction_ButtonPushed(app, event)
            if ~isempty(app.Tau_Semantic_UITable.Data) 
                [data] = app.ApplyFirstSelection_to_all(app.Tau_Semantic_UITable.Data);
                app.Tau_Semantic_UITable.Data = data;
            end
            if ~isempty(app.Volumefraction_Instance_UITable.Data) 
                [data] = app.ApplyFirstSelection_to_all(app.Tau_Instance_UITable.Data);
                app.Tau_Instance_UITable.Data = data;
            end          
        end

        % Button pushed function: Tau_SelectUnselectall_volfraction_Button
        function Tau_SelectUnselectall_volfraction_ButtonPushed(app, event)
            if ~isempty(app.Tau_Semantic_UITable.Data)
                [data] = app.Swicthselections(app.Tau_Semantic_UITable.Data);
                app.Tau_Semantic_UITable.Data = data;
            end
            if ~isempty(app.Tau_Instance_UITable.Data)
                [data] = app.Swicthselections(app.Tau_Instance_UITable.Data);
                app.Tau_Instance_UITable.Data = data;
            end    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MicrostructurecharacterizationUIFigure = uifigure('Visible', 'off');
            app.MicrostructurecharacterizationUIFigure.Name = 'Microstructure characterization';
            app.MicrostructurecharacterizationUIFigure.Icon = 'Icon_Characterization.png';
            app.Main_TabGroup = uitabgroup(app.MicrostructurecharacterizationUIFigure);
            app.InstructionsTab = uitab(app.Main_TabGroup);
            app.InstructionsTab.Title = 'Instructions';
            app.Instructions_GridLayout = uigridlayout(app.InstructionsTab);
            app.Restart = uibutton(app.Instructions_GridLayout, 'push');
            app.Restart.ButtonPushedFcn = createCallbackFcn(app, @RestartPushed, true);
            app.Restart.Tooltip = {'This will re-initialize the app.'};
            app.Restart.Text = 'Restart app';
            app.CharacterizationmoduleLabel = uilabel(app.Instructions_GridLayout);
            app.CharacterizationmoduleLabel.Text = 'Characterization module';
            app.ThismoduleLabel = uilabel(app.Instructions_GridLayout);
            app.ThismoduleLabel.Text = 'This module allows you to characterize microstructure(s), that is extracting parameters or metrics from 2D or 3D segmented image(s).';
            app.MicrostructureparametersaregroupedintothreemaincategoriesLabel = uilabel(app.Instructions_GridLayout);
            app.MicrostructureparametersaregroupedintothreemaincategoriesLabel.Text = 'Microstructure parameters are grouped into three main categories:';
            app.VolumeLabel = uilabel(app.Instructions_GridLayout);
            app.VolumeLabel.Text = '- Volume';
            app.TheseareparametersthatcanonlyberelatedtoaphaseLabel = uilabel(app.Instructions_GridLayout);
            app.TheseareparametersthatcanonlyberelatedtoaphaseLabel.Text = 'Parameters that can only be defined per phase, e.g.: volume fractions, connectivity, effective diffusivity.';
            app.ParticleLabel = uilabel(app.Instructions_GridLayout);
            app.ParticleLabel.Text = '- Particle';
            app.InterfaceLabel = uilabel(app.Instructions_GridLayout);
            app.InterfaceLabel.Text = '- Interface';
            app.Label_38 = uilabel(app.Instructions_GridLayout);
            app.Label_38.Text = 'Parameters that can be defined per individual particle and/or per phase (e.g.: diameter). Some parameters can be then correlated per particle (e.g.: crack as function of particle diameter).';
            app.Label_39 = uilabel(app.Instructions_GridLayout);
            app.Label_39.Text = 'Specific surface area, specific interface area, and triple phase boundary length density.';
            app.Label_40 = uilabel(app.Instructions_GridLayout);
            app.Label_40.Text = 'Errors or uncertainties associated with each parameter can be calculated:';
            app.ImageresolutionLabel = uilabel(app.Instructions_GridLayout);
            app.ImageresolutionLabel.Text = '- Image resolution';
            app.RepresentativityLabel = uilabel(app.Instructions_GridLayout);
            app.RepresentativityLabel.Text = '- Representativity';
            app.Label_41 = uilabel(app.Instructions_GridLayout);
            app.Label_41.Text = 'Image resolution may not be enough to describe the smallest features. You can calculate parameters at different resolutions to evaluate their error. Furthermore, fractal dimensions can be calculated.';
            app.FieldofviewFOVLabel = uilabel(app.Instructions_GridLayout);
            app.FieldofviewFOVLabel.Text = 'Field of view (FOV) may not be large enough to be representative of the microstructure. You can calculate size of the representative volume element (RVE) and its dependency with the FOV.';
            app.FormatLabel = uilabel(app.Instructions_GridLayout);
            app.FormatLabel.Text = '- Format';
            app.Label_42 = uilabel(app.Instructions_GridLayout);
            app.Label_42.Text = '(optional) Set formatting options so that you do not have to post-process the numerous figures generated by the characterization analysis.';
            app.FilesLabel = uilabel(app.Instructions_GridLayout);
            app.FilesLabel.Text = '- Files';
            app.HereyoucanchoosehowtoLabel = uilabel(app.Instructions_GridLayout);
            app.HereyoucanchoosehowtoLabel.Text = {'- You are not limited to a single file: you can import mulitple files, and they will be characterized sequentially. You can choose to set parameters for each volume independently, set them only for one volume and use them for all the '; 'other volumes, or import a macro.'; '- Imported files must be segmented already (use the segmentation module for this step if you only have grey level files). You can import semantic and instance segmentations. Instance segmentation allows to calculate'; 'metrics per particle. If you choose to import both segmentation types, you must follow the label requirements detailed in the ''Files'' tab.'; '- You can indicate if a background is present within the field of view (FOV). For instance, a nano-Computed Tomography imaging with a cylindridal FOV has a background. In such a case, the background will be managed so that'; 'it does not bias the results.'; '- For each phase, you must indicate their nanoporosity. By default, the module is pre-set for a single scale analysis, so put 0 (solid) or 1 (pore). This allows to characterize union of porous domain (e.g. phase ''pore'' + phase ''crack'')'; 'in addition of individual phases. Alternatively, you can perform a dual scale analysis (''mixed''). Use a nanoporosity value between 0 and 1 and assign a bulk transport value for each phase. You can also set wetting (or air saturation)'; 'to model the impact of a partial electrolyte wetting. You can choose to assign constant values per phase, or import files to model heterogeneous distributions of wetting and lower scale properties.'; '- Make sure to name and assign colors to all phases and to name directions adequately as they will be used to create figures and tables, saving you time otherwise required to reformat.'; '- Once you have imported all your files, you must validate your choice in the ''Files/Imported'' tab before moving on. Then, If you have imported a macro, go directly to the ''Run'' tab to start the calculations.'; 'Otherwise, you must configure the microstructure parameters (and their associated error anslysis) in the subsequent tabs before running the calculations as explained below.'};
            app.IfyouareusingamacroLabel = uilabel(app.Instructions_GridLayout);
            app.IfyouareusingamacroLabel.Text = 'Save the characterization analysis parameters set for the first volume in a macro that you can re-use for other volumes.';
            app.LastlytostartthecalculationsgototheRunTabLabel = uilabel(app.Instructions_GridLayout);
            app.LastlytostartthecalculationsgototheRunTabLabel.Text = 'Lastly, to start the calculations, go to the ''Run'' Tab.';
            app.MacroLabel = uilabel(app.Instructions_GridLayout);
            app.MacroLabel.Text = '- Macro';
            app.RunLabel = uilabel(app.Instructions_GridLayout);
            app.RunLabel.Text = '- Run';
            app.OnceallparametersLabel = uilabel(app.Instructions_GridLayout);
            app.OnceallparametersLabel.Text = 'Once you have setup all the parameters of the characterization analysis, you can:';
            app.FormatTab = uitab(app.Main_TabGroup);
            app.FormatTab.Title = 'Format';
            app.Format_GridLayout = uigridlayout(app.FormatTab);
            app.ThismodulecreatesalotoffiguresYoucanselectformatoptionsLabel = uilabel(app.Format_GridLayout);
            app.ThismodulecreatesalotoffiguresYoucanselectformatoptionsLabel.Text = 'This module creates a lot of figures. You can select format options beforehand.';
            app.DiametercolormapDropDownLabel = uilabel(app.Format_GridLayout);
            app.DiametercolormapDropDownLabel.Text = 'Diameter color map';
            app.DiametercolormapDropDown = uidropdown(app.Format_GridLayout);
            app.DiametercolormapDropDown.Items = {'turbo', 'parula'};
            app.DiametercolormapDropDown.Value = 'turbo';
            app.DiameterbackgroundcolormapDropDownLabel = uilabel(app.Format_GridLayout);
            app.DiameterbackgroundcolormapDropDownLabel.Text = 'Diameter background color map';
            app.DiameterbackgroundcolormapDropDown = uidropdown(app.Format_GridLayout);
            app.DiameterbackgroundcolormapDropDown.Items = {'white', 'black', 'grey'};
            app.DiameterbackgroundcolormapDropDown.Value = 'white';
            app.ColormapoptionsLabel = uilabel(app.Format_GridLayout);
            app.ColormapoptionsLabel.Text = 'Color map options';
            app.SavingoptionsPanel = uipanel(app.Format_GridLayout);
            app.SavingoptionsPanel.Title = 'Saving options';
            app.GridLayout8 = uigridlayout(app.SavingoptionsPanel);
            app.Saveimportedtiffiles_CheckBox = uicheckbox(app.GridLayout8);
            app.Saveimportedtiffiles_CheckBox.Tooltip = {''};
            app.Saveimportedtiffiles_CheckBox.Text = 'Save imported tif files in the result folder';
            app.Saveimportedtiffiles_CheckBox.Value = true;
            app.Savemodifiedtiffiles_CheckBox = uicheckbox(app.GridLayout8);
            app.Savemodifiedtiffiles_CheckBox.Text = 'Save modified tif files (if ROI or scaling) in the result folder';
            app.Savemodifiedtiffiles_CheckBox.Value = true;
            app.SavingtiffilesintheresultfolderwillhelpyoukeepthingsorganizedasinputsandresultswillbelocatedunderthesamerootfolderbutitrequiresmorediskspaceLabel = uilabel(app.GridLayout8);
            app.SavingtiffilesintheresultfolderwillhelpyoukeepthingsorganizedasinputsandresultswillbelocatedunderthesamerootfolderbutitrequiresmorediskspaceLabel.Text = {'Saving tif files in the result folder will help you keep things'; 'organized as inputs and results will be located under the same'; 'root folder, but it requires more disk space.'};
            app.figCheckBox = uicheckbox(app.GridLayout8);
            app.figCheckBox.Text = '.fig';
            app.figCheckBox.Value = true;
            app.pngCheckBox = uicheckbox(app.GridLayout8);
            app.pngCheckBox.ValueChangedFcn = createCallbackFcn(app, @pngCheckBoxValueChanged, true);
            app.pngCheckBox.Text = '.png';
            app.pngCheckBox.Value = true;
            app.svgscalablevectorgraphicsCheckBox = uicheckbox(app.GridLayout8);
            app.svgscalablevectorgraphicsCheckBox.Text = '.svg (scalable vector graphics)';
            app.DPIresolutionEditFieldLabel = uilabel(app.GridLayout8);
            app.DPIresolutionEditFieldLabel.Text = 'DPI resolution';
            app.DPIresolutionEditField = uieditfield(app.GridLayout8, 'numeric');
            app.DPIresolutionEditField.Limits = [50 Inf];
            app.DPIresolutionEditField.RoundFractionalValues = 'on';
            app.DPIresolutionEditField.Value = 300;
            app.AllowoverwritefigurefilesCheckBox = uicheckbox(app.GridLayout8);
            app.AllowoverwritefigurefilesCheckBox.Tooltip = {'If not checked, ''_bis'' is appended to filename if a file with the same name already exists.'};
            app.AllowoverwritefigurefilesCheckBox.Text = 'Allow overwrite figure files';
            app.AllowoverwritefigurefilesCheckBox.Value = true;
            app.SavefiguresinfollowingfileformatCheckBox = uicheckbox(app.GridLayout8);
            app.SavefiguresinfollowingfileformatCheckBox.ValueChangedFcn = createCallbackFcn(app, @SavefiguresinfollowingfileformatCheckBoxValueChanged, true);
            app.SavefiguresinfollowingfileformatCheckBox.Text = 'Save figures in following file format:';
            app.SavefiguresinfollowingfileformatCheckBox.Value = true;
            app.ClosefigureautomaticallyoncesavedCheckBox = uicheckbox(app.GridLayout8);
            app.ClosefigureautomaticallyoncesavedCheckBox.Tooltip = {'Dozens of figures can be created. It may be preferable to close them as they are saved to avoid clogging the desktop.'};
            app.ClosefigureautomaticallyoncesavedCheckBox.Text = 'Close figure automatically once saved';
            app.ClosefigureautomaticallyoncesavedCheckBox.Value = true;
            app.InputfilesLabel = uilabel(app.GridLayout8);
            app.InputfilesLabel.Text = 'Input files';
            app.FigurefileformatLabel = uilabel(app.GridLayout8);
            app.FigurefileformatLabel.Text = 'Figure file format';
            app.FiguresizeLabel_2 = uilabel(app.GridLayout8);
            app.FiguresizeLabel_2.Text = 'Figure size';
            app.FigureheightDropDown = uidropdown(app.GridLayout8);
            app.FigureheightDropDown.Items = {'centimeters', 'inches'};
            app.FigureheightDropDown.Value = 'centimeters';
            app.FigureheightEditFieldLabel = uilabel(app.GridLayout8);
            app.FigureheightEditFieldLabel.Text = 'Figure height';
            app.FigureheightEditField = uieditfield(app.GridLayout8, 'numeric');
            app.FigureheightEditField.Limits = [1 Inf];
            app.FigureheightEditField.Tooltip = {'Dimension for a single plot.'};
            app.FigureheightEditField.Value = 8;
            app.PaddingDropDownLabel = uilabel(app.GridLayout8);
            app.PaddingDropDownLabel.Text = 'Padding';
            app.PaddingDropDown = uidropdown(app.GridLayout8);
            app.PaddingDropDown.Items = {'tight', 'figure'};
            app.PaddingDropDown.Value = 'tight';
            app.FiguredimensionsaremodifiedforthesavingsothatimageswillhaveaconsistentsizewhateveristhewindowsizeLabel = uilabel(app.GridLayout8);
            app.FiguredimensionsaremodifiedforthesavingsothatimageswillhaveaconsistentsizewhateveristhewindowsizeLabel.Text = {'Figure dimensions are modified for the saving, so that images'; 'will have a consistent size, whatever is the window size.'};
            app.FigureformatPanel = uipanel(app.Format_GridLayout);
            app.FigureformatPanel.Title = 'Figure format';
            app.GridLayout9 = uigridlayout(app.FigureformatPanel);
            app.FontoptionsLabel = uilabel(app.GridLayout9);
            app.FontoptionsLabel.Text = 'Font options:';
            app.FontnameDropDownLabel = uilabel(app.GridLayout9);
            app.FontnameDropDownLabel.Text = 'Fontname';
            app.FontnameDropDown = uidropdown(app.GridLayout9);
            app.AxeFontsizeEditFieldLabel = uilabel(app.GridLayout9);
            app.AxeFontsizeEditFieldLabel.Text = 'Axe Font size';
            app.AxeFontsizeEditField = uieditfield(app.GridLayout9, 'numeric');
            app.AxeFontsizeEditField.Limits = [1 Inf];
            app.AxeFontsizeEditField.RoundFractionalValues = 'on';
            app.AxeFontsizeEditField.Value = 9;
            app.LegendfontsizeEditFieldLabel = uilabel(app.GridLayout9);
            app.LegendfontsizeEditFieldLabel.Text = 'Legend font size';
            app.LegendfontsizeEditField = uieditfield(app.GridLayout9, 'numeric');
            app.LegendfontsizeEditField.Limits = [1 Inf];
            app.LegendfontsizeEditField.RoundFractionalValues = 'on';
            app.LegendfontsizeEditField.Value = 7;
            app.TitlefontsizeEditFieldLabel = uilabel(app.GridLayout9);
            app.TitlefontsizeEditFieldLabel.Text = 'Title font size';
            app.TitlefontsizeEditField = uieditfield(app.GridLayout9, 'numeric');
            app.TitlefontsizeEditField.Limits = [1 Inf];
            app.TitlefontsizeEditField.RoundFractionalValues = 'on';
            app.TitlefontsizeEditField.Value = 11;
            app.SgtitlefontsizeEditFieldLabel = uilabel(app.GridLayout9);
            app.SgtitlefontsizeEditFieldLabel.Text = 'Sgtitle font size';
            app.SgtitlefontsizeEditField = uieditfield(app.GridLayout9, 'numeric');
            app.SgtitlefontsizeEditField.Limits = [1 Inf];
            app.SgtitlefontsizeEditField.RoundFractionalValues = 'on';
            app.SgtitlefontsizeEditField.Value = 13;
            app.LinewidthEditFieldLabel = uilabel(app.GridLayout9);
            app.LinewidthEditFieldLabel.Text = 'Linewidth';
            app.LinewidthEditField = uieditfield(app.GridLayout9, 'numeric');
            app.LinewidthEditField.Limits = [1 Inf];
            app.LinewidthEditField.RoundFractionalValues = 'on';
            app.LinewidthEditField.Value = 2;
            app.GridDropDownLabel = uilabel(app.GridLayout9);
            app.GridDropDownLabel.Text = 'Grid';
            app.GridDropDown = uidropdown(app.GridLayout9);
            app.GridDropDown.Items = {'No grid', 'Major grid', 'Major and minor grid'};
            app.GridDropDown.Value = 'Major grid';
            app.IncludefilenameinfiguretitleCheckBox = uicheckbox(app.GridLayout9);
            app.IncludefilenameinfiguretitleCheckBox.Text = 'Include filename in figure title';
            app.PaddingDropDown_2Label = uilabel(app.GridLayout9);
            app.PaddingDropDown_2Label.Text = 'Padding';
            app.PaddingDropDown_2 = uidropdown(app.GridLayout9);
            app.PaddingDropDown_2.Items = {'loose', 'compact', 'tight'};
            app.PaddingDropDown_2.Value = 'tight';
            app.TilespacingDropDownLabel = uilabel(app.GridLayout9);
            app.TilespacingDropDownLabel.Text = 'Tile spacing';
            app.TilespacingDropDown = uidropdown(app.GridLayout9);
            app.TilespacingDropDown.Items = {'loose', 'compact', 'tight'};
            app.TilespacingDropDown.Value = 'compact';
            app.TilelayoutLabel = uilabel(app.GridLayout9);
            app.TilelayoutLabel.Text = 'Tile layout';
            app.FilesTab = uitab(app.Main_TabGroup);
            app.FilesTab.Title = 'Files';
            app.FilesTabGroup = uitabgroup(app.FilesTab);
            app.ImportTab = uitab(app.FilesTabGroup);
            app.ImportTab.Title = 'Import';
            app.GridLayout_import = uigridlayout(app.ImportTab);
            app.SelectsegmentationtypeofvolumestoanalyseLabel = uilabel(app.GridLayout_import);
            app.SelectsegmentationtypeofvolumestoanalyseLabel.Text = '2) Select segmentation type of volume(s) to analyse';
            app.SegmentationtypeDropDown = uidropdown(app.GridLayout_import);
            app.SegmentationtypeDropDown.Items = {'semantic', 'instance', 'semantic and instance'};
            app.SegmentationtypeDropDown.ValueChangedFcn = createCallbackFcn(app, @SegmentationtypeDropDownValueChanged, true);
            app.SegmentationtypeDropDown.Value = 'semantic';
            app.SelectanalyismodeLabel = uilabel(app.GridLayout_import);
            app.SelectanalyismodeLabel.Tooltip = {''};
            app.SelectanalyismodeLabel.Text = '1) Select analyis mode';
            app.ModeDropDown = uidropdown(app.GridLayout_import);
            app.ModeDropDown.Items = {'Set parameters for each volume independently', 'Set parameters only once and apply to all volumes', 'Apply macro''s parameters to all volumes'};
            app.ModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ModeDropDownValueChanged, true);
            app.ModeDropDown.Tooltip = {''};
            app.ModeDropDown.Value = 'Set parameters for each volume independently';
            app.ClicktoimportmacroButton = uibutton(app.GridLayout_import, 'push');
            app.ClicktoimportmacroButton.ButtonPushedFcn = createCallbackFcn(app, @ClicktoimportmacroButtonPushed, true);
            app.ClicktoimportmacroButton.Text = 'Click to import macro';
            app.NomacroloadedLabel = uilabel(app.GridLayout_import);
            app.NomacroloadedLabel.Text = 'No macro loaded';
            app.ImportfilesLabel = uilabel(app.GridLayout_import);
            app.ImportfilesLabel.Text = '3) Import file(s)';
            app.ClicktoimportsemanticsegmentationButton = uibutton(app.GridLayout_import, 'push');
            app.ClicktoimportsemanticsegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @ClicktoimportsemanticsegmentationButtonPushed, true);
            app.ClicktoimportsemanticsegmentationButton.Text = 'Click to import semantic segmentation';
            app.ClicktoimportinstancesegmentationButton = uibutton(app.GridLayout_import, 'push');
            app.ClicktoimportinstancesegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @ClicktoimportinstancesegmentationButtonPushed, true);
            app.ClicktoimportinstancesegmentationButton.Text = 'Click to import instance segmentation';
            app.Label_semanticdescription = uilabel(app.GridLayout_import);
            app.Label_semanticdescription.Tooltip = {''};
            app.Label_semanticdescription.Text = '1st label = 1st domain, 2nd label = 2nd domain, 3rd label = 3rd domain, etc.';
            app.Label_instancedescription = uilabel(app.GridLayout_import);
            app.Label_instancedescription.Tooltip = {''};
            app.Label_instancedescription.Text = 'Label 0 = complementary volume, label 1 = 1st instance, label 2 = 2nd instance,..., label n = nth instance';
            app.Semantic_NofileimportedLabel = uilabel(app.GridLayout_import);
            app.Semantic_NofileimportedLabel.Text = 'No file imported';
            app.Instance_NofileimportedLabel = uilabel(app.GridLayout_import);
            app.Instance_NofileimportedLabel.Text = 'No file imported';
            app.Volumenumber_Label = uilabel(app.GridLayout_import);
            app.Volumenumber_Label.Text = 'Volume number: 0';
            app.SetRegionofInterestROILabel = uilabel(app.GridLayout_import);
            app.SetRegionofInterestROILabel.Tooltip = {'For more advanced ROI (e.g., rotation), please use the segmentation module first.'};
            app.SetRegionofInterestROILabel.Text = '4) Set Region of Interest (ROI)';
            app.ROI_UITable = uitable(app.GridLayout_import);
            app.ROI_UITable.ColumnName = {'Direction'; 'Number of voxels'; 'Start'; 'End'; 'Length'; 'Name'};
            app.ROI_UITable.RowName = {};
            app.ROI_UITable.ColumnEditable = [false false true true false true];
            app.ROI_UITable.CellEditCallback = createCallbackFcn(app, @ROI_UITableCellEdit, true);
            app.ROI_UITable.Tooltip = {'Direction name will be used in figures and tables. ROI can only be set up if volumes are set independently.'};
            app.SetvoxelsizeandscalingfactorLabel = uilabel(app.GridLayout_import);
            app.SetvoxelsizeandscalingfactorLabel.Text = '5) Set voxel size and scaling factor';
            app.VoxelsizeEditFieldLabel = uilabel(app.GridLayout_import);
            app.VoxelsizeEditFieldLabel.Text = 'Voxel size';
            app.VoxelsizeEditField = uieditfield(app.GridLayout_import, 'numeric');
            app.VoxelsizeEditField.Limits = [1e-09 Inf];
            app.VoxelsizeEditField.ValueChangedFcn = createCallbackFcn(app, @VoxelsizeEditFieldValueChanged, true);
            app.VoxelsizeEditField.Value = 1;
            app.VoxelsizeUnit_EditField = uieditfield(app.GridLayout_import, 'text');
            app.VoxelsizeUnit_EditField.ValueChangedFcn = createCallbackFcn(app, @VoxelsizeEditFieldValueChanged, true);
            app.VoxelsizeUnit_EditField.Tooltip = {'Unit will be used in table and figure'};
            app.VoxelsizeUnit_EditField.Value = 'um';
            app.ScalingfactorEditFieldLabel = uilabel(app.GridLayout_import);
            app.ScalingfactorEditFieldLabel.Text = 'Scaling factor';
            app.ScalingfactorEditField = uieditfield(app.GridLayout_import, 'numeric');
            app.ScalingfactorEditField.Limits = [1e-09 Inf];
            app.ScalingfactorEditField.ValueChangedFcn = createCallbackFcn(app, @VoxelsizeEditFieldValueChanged, true);
            app.ScalingfactorEditField.Tooltip = {'> 1: downscaling'; '< 1: upscaling'; '= 1: ignore'};
            app.ScalingfactorEditField.Value = 1;
            app.AssignlabelcolorandnameLabel = uilabel(app.GridLayout_import);
            app.AssignlabelcolorandnameLabel.Text = '6) Assign label color and name';
            app.Phasenamecolor_DropDown = uidropdown(app.GridLayout_import);
            app.Phasenamecolor_DropDown.Items = {'semantic segmentation', 'instance segmentation'};
            app.Phasenamecolor_DropDown.ValueChangedFcn = createCallbackFcn(app, @Phasenamecolor_DropDownValueChanged, true);
            app.Phasenamecolor_DropDown.Value = 'semantic segmentation';
            app.PhasecolornameSemantic_UITable = uitable(app.GridLayout_import);
            app.PhasecolornameSemantic_UITable.ColumnName = {'Phase#'; 'Volume fraction (ROI)'; 'Color'; 'Name'; 'label'; 'Nanoporosity'};
            app.PhasecolornameSemantic_UITable.RowName = {};
            app.PhasecolornameSemantic_UITable.ColumnEditable = [false false false true false true];
            app.PhasecolornameSemantic_UITable.CellEditCallback = createCallbackFcn(app, @PhasecolornameSemantic_UITableCellEdit, true);
            app.PhasecolornameSemantic_UITable.CellSelectionCallback = createCallbackFcn(app, @PhasecolornameSemantic_UITableCellSelection, true);
            app.PhasecolornameSemantic_UITable.Tooltip = {'Phase color and name will be used in figures and tables, so you do not have to reformat them manually.'};
            app.FilesmustsharesamesizeLabel = uilabel(app.GridLayout_import);
            app.FilesmustsharesamesizeLabel.Text = 'Files must share same size';
            app.OptionalPanel = uipanel(app.GridLayout_import);
            app.OptionalPanel.Title = 'Optional';
            app.GridLayout3 = uigridlayout(app.OptionalPanel);
            app.TohelpselectingROIandscalingparametersLabel = uilabel(app.GridLayout3);
            app.TohelpselectingROIandscalingparametersLabel.Text = 'To help selecting ROI and scaling parameters';
            app.VisualizevolumeButton = uibutton(app.GridLayout3, 'push');
            app.VisualizevolumeButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizevolumeButtonPushed, true);
            app.VisualizevolumeButton.Text = 'Visualize volume';
            app.PlotvolumefractionsButton = uibutton(app.GridLayout3, 'push');
            app.PlotvolumefractionsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotvolumefractionsButtonPushed, true);
            app.PlotvolumefractionsButton.Text = 'Plot volume fractions';
            app.withDropDown_3Label = uilabel(app.GridLayout3);
            app.withDropDown_3Label.Text = 'with';
            app.visualized_ROI_withDropDown = uidropdown(app.GridLayout3);
            app.visualized_ROI_withDropDown.Items = {'original field of view', 'region of interest'};
            app.visualized_ROI_withDropDown.Value = 'original field of view';
            app.visualize_resolution_withDropDown = uidropdown(app.GridLayout3);
            app.visualize_resolution_withDropDown.Items = {'original resolution', 'after scaling'};
            app.visualize_resolution_withDropDown.Value = 'original resolution';
            app.VolumeinformationLabel = uilabel(app.GridLayout3);
            app.VolumeinformationLabel.Text = 'Volume information';
            app.Info_UITable = uitable(app.GridLayout3);
            app.Info_UITable.ColumnName = {'Information'; 'Text'};
            app.Info_UITable.RowName = {};
            app.Info_UITable.ColumnEditable = [false true];
            app.Info_UITable.Tooltip = {'This table with be saved. Use it to keep record of your different calculations.'};
            app.SetsavelocationLabel = uilabel(app.GridLayout_import);
            app.SetsavelocationLabel.Tooltip = {'Results will be saved in main folder\volume folder\. You can reuse the same main folder for different volumes.'};
            app.SetsavelocationLabel.Text = '7) Set save location';
            app.SelectmainfolderButton = uibutton(app.GridLayout_import, 'push');
            app.SelectmainfolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectmainfolderButtonPushed, true);
            app.SelectmainfolderButton.Tooltip = {'Results will be saved in main folder\volume folder\. You can reuse the same main folder for different volumes.'};
            app.SelectmainfolderButton.Text = 'Select main folder';
            app.Mainfolder_Label = uilabel(app.GridLayout_import);
            app.Mainfolder_Label.Tooltip = {'Results will be saved in main folder\volume folder\. You can reuse the same main folder for different volumes.'};
            app.Mainfolder_Label.Text = 'Not defined';
            app.VolumefolderEditFieldLabel = uilabel(app.GridLayout_import);
            app.VolumefolderEditFieldLabel.Tooltip = {'Results will be saved in main folder\volume folder\. You can reuse the same main folder for different volumes.'};
            app.VolumefolderEditFieldLabel.Text = 'Volume folder';
            app.VolumefolderEditField = uieditfield(app.GridLayout_import, 'text');
            app.VolumefolderEditField.Tooltip = {'Results will be saved in main folder\volume folder\. You can reuse the same main folder for different volumes.'};
            app.SaveimportsettingstocontinueLabel = uilabel(app.GridLayout_import);
            app.SaveimportsettingstocontinueLabel.Text = '8) Save import settings to continue';
            app.Import_SavesettingsButton = uibutton(app.GridLayout_import, 'push');
            app.Import_SavesettingsButton.ButtonPushedFcn = createCallbackFcn(app, @Import_SavesettingsButtonPushed, true);
            app.Import_SavesettingsButton.Text = 'Save settings';
            app.Import_indication = uilabel(app.GridLayout_import);
            app.Import_indication.Text = 'What''s next ?';
            app.Scaledvoxelsize_Label = uilabel(app.GridLayout_import);
            app.Scaledvoxelsize_Label.Text = 'Scaled voxel size: n/a';
            app.ROI_Label = uilabel(app.GridLayout_import);
            app.ROI_Label.Text = 'ROI: n/a';
            app.PhasecolornameInstance_UITable = uitable(app.GridLayout_import);
            app.PhasecolornameInstance_UITable.ColumnName = {'Phase#'; 'Volume fraction (ROI)'; 'Color'; 'Name'; 'Label'};
            app.PhasecolornameInstance_UITable.RowName = {};
            app.PhasecolornameInstance_UITable.ColumnEditable = [false false false true false];
            app.PhasecolornameInstance_UITable.CellSelectionCallback = createCallbackFcn(app, @PhasecolornameInstance_UITableCellSelection, true);
            app.PhasecolornameInstance_UITable.Tooltip = {'Phase color and name will be used in figures and tables, so you do not have to reformat them manually.'};
            app.ItwillresetallparametersLabel = uilabel(app.GridLayout_import);
            app.ItwillresetallparametersLabel.Text = 'It will reset all parameters';
            app.ThereisabackgroundCheckBox = uicheckbox(app.GridLayout_import);
            app.ThereisabackgroundCheckBox.ValueChangedFcn = createCallbackFcn(app, @SegmentationtypeDropDownValueChanged, true);
            app.ThereisabackgroundCheckBox.Tooltip = {'For instance, if the FOV is cylindrical then there is a background.'};
            app.ThereisabackgroundCheckBox.Text = 'There is a background ';
            app.LowerScale_Nanoporosity_DropDown = uidropdown(app.GridLayout_import);
            app.LowerScale_Nanoporosity_DropDown.Items = {'Binary', 'Mixed (uniform)', 'Mixed (heterogeneous)'};
            app.LowerScale_Nanoporosity_DropDown.ValueChangedFcn = createCallbackFcn(app, @LowerScale_Nanoporosity_DropDownValueChanged, true);
            app.LowerScale_Nanoporosity_DropDown.Tooltip = {'Options for the nanoporosity assigned to each voxel. Binary: nanoporosity is either 0 (fully solid) or 1 (fully porous). Mixed: a pixel can be both solid and porous with nanoporosity ranging from 0 to 1. Macroscale tortuosity factor and Bruggeman exponent will be calculated using the total porosity (i.e., considering nanoporosity).'};
            app.LowerScale_Nanoporosity_DropDown.Value = 'Binary';
            app.ImportnanoporosityButton = uibutton(app.GridLayout_import, 'push');
            app.ImportnanoporosityButton.ButtonPushedFcn = createCallbackFcn(app, @ImportnanoporosityButtonPushed, true);
            app.ImportnanoporosityButton.Tooltip = {''};
            app.ImportnanoporosityButton.Text = 'Import nanoporosity';
            app.ImportporediffusivityButton = uibutton(app.GridLayout_import, 'push');
            app.ImportporediffusivityButton.ButtonPushedFcn = createCallbackFcn(app, @ImportporediffusivityButtonPushed, true);
            app.ImportporediffusivityButton.Tooltip = {''};
            app.ImportporediffusivityButton.Text = 'Import pore diffusivity';
            app.Bulkdiffusivity_file_Label = uilabel(app.GridLayout_import);
            app.Bulkdiffusivity_file_Label.Text = 'No file imported';
            app.ImportAirSaturation_Button = uibutton(app.GridLayout_import, 'push');
            app.ImportAirSaturation_Button.ButtonPushedFcn = createCallbackFcn(app, @ImportAirSaturation_ButtonPushed, true);
            app.ImportAirSaturation_Button.Tooltip = {'Wettability = 1 - air saturation and ranges from 0 to 1.'};
            app.ImportAirSaturation_Button.Text = 'Import';
            app.Airsaturation_terminology_DropDown = uidropdown(app.GridLayout_import);
            app.Airsaturation_terminology_DropDown.Items = {'Air saturation', 'Wettability'};
            app.Airsaturation_terminology_DropDown.ValueChangedFcn = createCallbackFcn(app, @Airsaturation_terminology_DropDownValueChanged, true);
            app.Airsaturation_terminology_DropDown.Tooltip = {'Wettability = 1 - air saturation and ranges from 0 to 1.'};
            app.Airsaturation_terminology_DropDown.Value = 'Air saturation';
            app.LowerscaleTransport_UITable = uitable(app.GridLayout_import);
            app.LowerscaleTransport_UITable.ColumnName = {'Pore D'; 'Solid K'};
            app.LowerscaleTransport_UITable.RowName = {};
            app.LowerscaleTransport_UITable.ColumnEditable = true;
            app.LowerscaleTransport_UITable.CellEditCallback = createCallbackFcn(app, @LowerscaleTransport_UITableCellEdit, true);
            app.LowerscaleTransport_UITable.Tooltip = {'Bulk diffusivity D and conductivity K must be [0,1]. Bruggeman exponent p must be >= 1.0. For heterogeneous distribution, the averaged value is indicated.'};
            app.AirSaturation_UITable = uitable(app.GridLayout_import);
            app.AirSaturation_UITable.ColumnName = {'Air saturation'};
            app.AirSaturation_UITable.RowName = {};
            app.AirSaturation_UITable.ColumnEditable = true;
            app.AirSaturation_UITable.CellEditCallback = createCallbackFcn(app, @AirSaturation_UITableCellEdit, true);
            app.AirSaturation_UITable.Tooltip = {'Wettability and air saturation values ([0,1]) are only considered for phases with nanoporosity > 0. It modifies volume fraction, interface, and transport properties.'};
            app.Airsaturation_uniform_DropDown = uidropdown(app.GridLayout_import);
            app.Airsaturation_uniform_DropDown.Items = {'Uniform', 'Heterogeneous'};
            app.Airsaturation_uniform_DropDown.ValueChangedFcn = createCallbackFcn(app, @Airsaturation_uniform_DropDownValueChanged, true);
            app.Airsaturation_uniform_DropDown.Value = 'Uniform';
            app.Airsaturation_file_Label = uilabel(app.GridLayout_import);
            app.Airsaturation_file_Label.Text = 'No file imported';
            app.PartialwettingCheckBox = uicheckbox(app.GridLayout_import);
            app.PartialwettingCheckBox.ValueChangedFcn = createCallbackFcn(app, @PartialwettingCheckBoxValueChanged, true);
            app.PartialwettingCheckBox.Text = 'Partial wetting';
            app.LowerScale_PoreTransport_DropDown = uidropdown(app.GridLayout_import);
            app.LowerScale_PoreTransport_DropDown.Items = {'Diffusivity D (uniform)', 'Diffusivity D (heterogeneous)', 'Bruggeman exponent p (uniform)'};
            app.LowerScale_PoreTransport_DropDown.ValueChangedFcn = createCallbackFcn(app, @LowerScale_PoreTransport_DropDownValueChanged, true);
            app.LowerScale_PoreTransport_DropDown.Tooltip = {'Options for the pore lowerscale transport coefficient assigned to each voxel. Bruggeman exponent: bulk diffusivity is calculated using Bruggeman exponent and nanoporosity. Diffusivity: bulk diffusivity is directly provided.'};
            app.LowerScale_PoreTransport_DropDown.Value = 'Diffusivity D (uniform)';
            app.Nanoporosity_file_Label = uilabel(app.GridLayout_import);
            app.Nanoporosity_file_Label.Text = 'No file imported';
            app.SelectscaleLabel = uilabel(app.GridLayout_import);
            app.SelectscaleLabel.Text = 'for porosity';
            app.ImportsolidconductivityButton = uibutton(app.GridLayout_import, 'push');
            app.ImportsolidconductivityButton.ButtonPushedFcn = createCallbackFcn(app, @ImportsolidconductivityButtonPushed, true);
            app.ImportsolidconductivityButton.Tooltip = {''};
            app.ImportsolidconductivityButton.Text = 'Import solid conductivity';
            app.BulkConductivity_file_Label = uilabel(app.GridLayout_import);
            app.BulkConductivity_file_Label.Text = 'No file imported';
            app.forsolidtransportDropDownLabel = uilabel(app.GridLayout_import);
            app.forsolidtransportDropDownLabel.Text = 'for solid transport';
            app.LowerScale_SolidTransport_DropDown = uidropdown(app.GridLayout_import);
            app.LowerScale_SolidTransport_DropDown.Items = {'Conductivity K (uniform)', 'Conductivity K (heterogeneous)', 'Bruggeman exponent p (uniform)'};
            app.LowerScale_SolidTransport_DropDown.ValueChangedFcn = createCallbackFcn(app, @LowerScale_SolidTransport_DropDownValueChanged, true);
            app.LowerScale_SolidTransport_DropDown.Tooltip = {'Options for the solid lowerscale transport coefficient assigned to each voxel. Bruggeman exponent: bulk conductivity is calculated using Bruggeman exponent and (1-nanoporosity). Conductivity: bulk conductivity is directly provided.'};
            app.LowerScale_SolidTransport_DropDown.Value = 'Conductivity K (uniform)';
            app.ForporeLabel = uilabel(app.GridLayout_import);
            app.ForporeLabel.Text = 'for pore transport';
            app.BulkpropertiesLabel = uilabel(app.GridLayout_import);
            app.BulkpropertiesLabel.Text = 'Bulk properties:';
            app.ImportedTab = uitab(app.FilesTabGroup);
            app.ImportedTab.Title = 'Imported';
            app.GridLayout_imported = uigridlayout(app.ImportedTab);
            app.VisualizeallfilesButton = uibutton(app.GridLayout_imported, 'push');
            app.VisualizeallfilesButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizeallfilesButtonPushed, true);
            app.VisualizeallfilesButton.Text = 'Visualize all files';
            app.VisualizeROI_DropDown = uidropdown(app.GridLayout_imported);
            app.VisualizeROI_DropDown.Items = {'with original field of view', 'with region of interest'};
            app.VisualizeROI_DropDown.Value = 'with original field of view';
            app.Imported_UITable = uitable(app.GridLayout_imported);
            app.Imported_UITable.ColumnName = {'Semantic'; 'Nanoporosity'; 'Pore transport'; 'Solid transport'; 'Wetting'; 'Phase#'; 'Instance'; 'Instance#'; 'Background'; 'ROI Size'; 'Voxel#'; 'ROI size square (2D) or cubic (3D) root'; 'Voxel size'; 'Tag'; 'Calculation order'};
            app.Imported_UITable.RowName = {};
            app.Imported_UITable.ColumnEditable = [false false false false false false false false false false false false false true true];
            app.Imported_UITable.CellEditCallback = createCallbackFcn(app, @Imported_UITableCellEdit, true);
            app.Label_3 = uilabel(app.GridLayout_imported);
            app.Label_3.Text = {'Tag is the substring located after the last underscore of the filename. Tag must be a number. For instance, tag for'; '"MyMicrostructure_year2024_5.tif" is 5. It can be used as an axis in the correlation module. For instance, if tag is time, it will allow'; 'to plot evolution of metrics calculated in this module with time. You can also manually edit a tag value in the table below. '};
            app.TagunitEditFieldLabel = uilabel(app.GridLayout_imported);
            app.TagunitEditFieldLabel.Text = 'Tag unit';
            app.TagunitEditField = uieditfield(app.GridLayout_imported, 'text');
            app.VisualizationLabel = uilabel(app.GridLayout_imported);
            app.VisualizationLabel.Text = 'Visualization';
            app.TagLabel = uilabel(app.GridLayout_imported);
            app.TagLabel.Text = 'Tag';
            app.Label_4 = uilabel(app.GridLayout_imported);
            app.Label_4.Text = '';
            app.Setcalculationorderto0nottoanalyseafileLabel = uilabel(app.GridLayout_imported);
            app.Setcalculationorderto0nottoanalyseafileLabel.Text = 'Set calculation order to 0 not to analyse a file';
            app.Label_5 = uilabel(app.GridLayout_imported);
            app.Label_5.Text = 'Confirm your choice to continue';
            app.Imported_SavesettingsButton = uibutton(app.GridLayout_imported, 'push');
            app.Imported_SavesettingsButton.ButtonPushedFcn = createCallbackFcn(app, @Imported_SavesettingsButtonPushed, true);
            app.Imported_SavesettingsButton.Text = 'Save settings';
            app.itwillinitializeorresetthesubsequenttabsLabel = uilabel(app.GridLayout_imported);
            app.itwillinitializeorresetthesubsequenttabsLabel.Text = '(it will initialize or reset the subsequent tabs)';
            app.Label_6 = uilabel(app.GridLayout_imported);
            app.Label_6.Text = 'Instructions: all actions are optional. You only need to click on the bottom right green button to proceed.';
            app.SearchtagButton = uibutton(app.GridLayout_imported, 'push');
            app.SearchtagButton.ButtonPushedFcn = createCallbackFcn(app, @SearchtagButtonPushed, true);
            app.SearchtagButton.Text = 'Search tag';
            app.PhaseandInstancecorrespondtotheimportednotnecessarlytheROILabel = uilabel(app.GridLayout_imported);
            app.PhaseandInstancecorrespondtotheimportednotnecessarlytheROILabel.Text = 'Phase# and Instance# correspond to the imported volume (not necessarly the ROI)';
            app.VolumeTab = uitab(app.Main_TabGroup);
            app.VolumeTab.Title = 'Volume';
            app.TabGroup2 = uitabgroup(app.VolumeTab);
            app.VolumefractionTab = uitab(app.TabGroup2);
            app.VolumefractionTab.Title = 'Volume fraction';
            app.Volumefraction_GridLayout = uigridlayout(app.VolumefractionTab);
            app.VolumefractionInstructions_Label = uilabel(app.Volumefraction_GridLayout);
            app.VolumefractionInstructions_Label.Text = 'Select phases for which you want to calculate volume fraction';
            app.Volumefraction_Semantic_UITable = uitable(app.Volumefraction_GridLayout);
            app.Volumefraction_Semantic_UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.Volumefraction_Semantic_UITable.RowName = {};
            app.Volumefraction_Semantic_UITable.CellEditCallback = createCallbackFcn(app, @Volumefraction_Semantic_UITableCellEdit, true);
            app.Volumefraction_Semantic_UITable.Tooltip = {'For semantic volumes.'; '- If a phase is mixed (i.e., nanoporosity ]0,1[), both phase volume fraction (nanoporosity is not considered) and porous and solid volume fractions (nanoporosity is considered) are calculated.'; '- Wetting is only considered for voxels with a nanoporosity > 0. For phases with a partial wetting, both ideal (wetting = 1) and real (imported wetting) volume fractions are calculated. '; '- "Combined" required to analyse all phases. True porosity and solid volume fractions will be calculated considered nanoporosity (and wetting) of all phases.'};
            app.Volumefraction_Instance_UITable = uitable(app.Volumefraction_GridLayout);
            app.Volumefraction_Instance_UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.Volumefraction_Instance_UITable.RowName = {};
            app.Volumefraction_Instance_UITable.Tooltip = {'Instance volumes'};
            app.BackgrounddescriptionLabel = uilabel(app.Volumefraction_GridLayout);
            app.BackgrounddescriptionLabel.Text = 'If a background is present, volume fractions will be renormalized by (1-backgroud volume fraction)';
            app.SelectUnselectall_volfraction_Button = uibutton(app.Volumefraction_GridLayout, 'push');
            app.SelectUnselectall_volfraction_Button.ButtonPushedFcn = createCallbackFcn(app, @SelectUnselectall_volfraction_ButtonPushed2, true);
            app.SelectUnselectall_volfraction_Button.Text = 'Select/Unselect all';
            app.Applyfirstvolumeselections_volfraction_Button = uibutton(app.Volumefraction_GridLayout, 'push');
            app.Applyfirstvolumeselections_volfraction_Button.ButtonPushedFcn = createCallbackFcn(app, @Applyfirstvolumeselections_volfraction_ButtonPushed, true);
            app.Applyfirstvolumeselections_volfraction_Button.Text = 'Apply first volume selections to all volumes';
            app.PlotresultsalongLabel = uilabel(app.Volumefraction_GridLayout);
            app.PlotresultsalongLabel.Text = 'Plot results along';
            app.vf_Direction1_CheckBox = uicheckbox(app.Volumefraction_GridLayout);
            app.vf_Direction1_CheckBox.Text = 'Direction 1';
            app.vf_Direction1_CheckBox.Value = true;
            app.vf_Direction2_CheckBox = uicheckbox(app.Volumefraction_GridLayout);
            app.vf_Direction2_CheckBox.Text = 'Direction 2';
            app.vf_Direction2_CheckBox.Value = true;
            app.vf_Direction3_CheckBox = uicheckbox(app.Volumefraction_GridLayout);
            app.vf_Direction3_CheckBox.Text = 'Direction 3';
            app.vf_Direction3_CheckBox.Value = true;
            app.TortuosityfactorTab = uitab(app.TabGroup2);
            app.TortuosityfactorTab.Title = 'Tortuosity factor';
            app.TortuosityFactor_GridLayout = uigridlayout(app.TortuosityfactorTab);
            app.TauInstructions_Label = uilabel(app.TortuosityFactor_GridLayout);
            app.TauInstructions_Label.Text = 'Select phases for which you want to calculate effective transport properties';
            app.Tau_Semantic_UITable = uitable(app.TortuosityFactor_GridLayout);
            app.Tau_Semantic_UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.Tau_Semantic_UITable.RowName = {};
            app.Tau_Semantic_UITable.Tooltip = {'For semantic volumes. Combined (pore) and Combined (solid) will calculate transport properties in, respectively, all voxels with a nanoporosity*wetting > 0 and a nanoporosity <1. All other calculations are performed per label (only considering the segmented image).'};
            app.Tau_Instance_UITable = uitable(app.TortuosityFactor_GridLayout);
            app.Tau_Instance_UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.Tau_Instance_UITable.RowName = {};
            app.Tau_Instance_UITable.Tooltip = {'Instance volumes'};
            app.TauFactor2isusedPleasequoteLabel = uilabel(app.TortuosityFactor_GridLayout);
            app.TauFactor2isusedPleasequoteLabel.Text = 'TauFactor 2 from Steve Kench, Isaac Squires, and Samuel Cooper is used. See Help tab for installation instructions.';
            app.Hyperlink5 = uihyperlink(app.TortuosityFactor_GridLayout);
            app.Hyperlink5.URL = 'https://doi.org/10.21105/joss.05358';
            app.Hyperlink5.Text = 'https://doi.org/10.21105/joss.05358';
            app.BackgroundoptionifanyPanel = uipanel(app.TortuosityFactor_GridLayout);
            app.BackgroundoptionifanyPanel.Title = 'Background option (if any)';
            app.TauBackground_GridLayout = uigridlayout(app.BackgroundoptionifanyPanel);
            app.ToleranceEditFieldLabel = uilabel(app.TauBackground_GridLayout);
            app.ToleranceEditFieldLabel.Text = 'Tolerance';
            app.ToleranceEditField = uieditfield(app.TauBackground_GridLayout, 'numeric');
            app.ToleranceEditField.Limits = [1e-06 0.1];
            app.ToleranceEditField.Tooltip = {'Absolue tolerance on effective diffusivity. The max-min detla calculated on the last 5 iterations must be below this tolerance.'};
            app.ToleranceEditField.Value = 0.0005;
            app.SelectmethodLabel = uilabel(app.TauBackground_GridLayout);
            app.SelectmethodLabel.Text = 'Select method:';
            app.SelectmethodDropDown = uidropdown(app.TauBackground_GridLayout);
            app.SelectmethodDropDown.Items = {'Iterative', 'Symmetry', 'Crop'};
            app.SelectmethodDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectmethodDropDownValueChanged, true);
            app.SelectmethodDropDown.Tooltip = {'- Iterative method: background domain is assigned with a bulk diffusivity equals to the effective diffusivity calculated at previous step (assumption: transport is isotropic). Iterate until covergence.'; '- Symmetry: background domain is filled with a symmetry operator.'; '- Crop: largest subvolume without background is used.'};
            app.SelectmethodDropDown.Value = 'Iterative';
            app.MaxiterEditFieldLabel = uilabel(app.TauBackground_GridLayout);
            app.MaxiterEditFieldLabel.Text = 'Max iter';
            app.MaxiterEditField = uieditfield(app.TauBackground_GridLayout, 'numeric');
            app.MaxiterEditField.Limits = [2 Inf];
            app.MaxiterEditField.RoundFractionalValues = 'on';
            app.MaxiterEditField.Value = 10;
            app.Tau_Applyfirstvolumeselections_volfraction_Button = uibutton(app.TortuosityFactor_GridLayout, 'push');
            app.Tau_Applyfirstvolumeselections_volfraction_Button.ButtonPushedFcn = createCallbackFcn(app, @Tau_Applyfirstvolumeselections_volfraction_ButtonPushed, true);
            app.Tau_Applyfirstvolumeselections_volfraction_Button.Text = 'Apply first volume selections to all volumes';
            app.Tau_SelectUnselectall_volfraction_Button = uibutton(app.TortuosityFactor_GridLayout, 'push');
            app.Tau_SelectUnselectall_volfraction_Button.ButtonPushedFcn = createCallbackFcn(app, @Tau_SelectUnselectall_volfraction_ButtonPushed, true);
            app.Tau_SelectUnselectall_volfraction_Button.Text = 'Select/Unselect all';
            app.CalculateLabel = uilabel(app.TortuosityFactor_GridLayout);
            app.CalculateLabel.Text = 'Calculate';
            app.Tau_d1_CheckBox = uicheckbox(app.TortuosityFactor_GridLayout);
            app.Tau_d1_CheckBox.Text = '';
            app.Tau_d1_CheckBox.Value = true;
            app.Tau_d2_CheckBox = uicheckbox(app.TortuosityFactor_GridLayout);
            app.Tau_d2_CheckBox.Text = '';
            app.Tau_d2_CheckBox.Value = true;
            app.Tau_d3_CheckBox = uicheckbox(app.TortuosityFactor_GridLayout);
            app.Tau_d3_CheckBox.Text = '';
            app.Tau_d3_CheckBox.Value = true;
            app.alongLabel = uilabel(app.TortuosityFactor_GridLayout);
            app.alongLabel.Text = 'along';
            app.tau_1Label = uilabel(app.TortuosityFactor_GridLayout);
            app.tau_1Label.Interpreter = 'tex';
            app.tau_1Label.Text = '\tau_{1}';
            app.tau_2Label = uilabel(app.TortuosityFactor_GridLayout);
            app.tau_2Label.Interpreter = 'tex';
            app.tau_2Label.Text = '\tau_{2}';
            app.tau_3Label = uilabel(app.TortuosityFactor_GridLayout);
            app.tau_3Label.Interpreter = 'tex';
            app.tau_3Label.Text = '\tau_{3}';
            app.Direction1EditFieldLabel = uilabel(app.TortuosityFactor_GridLayout);
            app.Direction1EditFieldLabel.Tooltip = {'Use 1 to calculate transport properties for the whole volume only. If you enter a positive integer > 1, for instance 3, then, in addition, the transport properties will be also calculated in 3 subvolumes along this axis to determine if they change along this axis.'};
            app.Direction1EditFieldLabel.Text = 'Direction 1';
            app.Direction1EditField = uieditfield(app.TortuosityFactor_GridLayout, 'numeric');
            app.Direction1EditField.Limits = [1 Inf];
            app.Direction1EditField.RoundFractionalValues = 'on';
            app.Direction1EditField.Tooltip = {'Use 1 to calculate transport properties for the whole volume only. If you enter a positive integer > 1, for instance 3, then, in addition, the transport properties will be also calculated in 3 subvolumes along this axis to determine if they change along this axis.'};
            app.Direction1EditField.Value = 1;
            app.Direction2EditFieldLabel = uilabel(app.TortuosityFactor_GridLayout);
            app.Direction2EditFieldLabel.Tooltip = {'Use 1 to calculate transport properties for the whole volume only. If you enter a positive integer > 1, for instance 3, then, in addition, the transport properties will be also calculated in 3 subvolumes along this axis to determine if they change along this axis.'};
            app.Direction2EditFieldLabel.Text = 'Direction 2';
            app.Direction2EditField = uieditfield(app.TortuosityFactor_GridLayout, 'numeric');
            app.Direction2EditField.Limits = [1 Inf];
            app.Direction2EditField.RoundFractionalValues = 'on';
            app.Direction2EditField.Tooltip = {'Use 1 to calculate transport properties for the whole volume only. If you enter a positive integer > 1, for instance 3, then, in addition, the transport properties will be also calculated in 3 subvolumes along this axis to determine if they change along this axis.'};
            app.Direction2EditField.Value = 1;
            app.Direction3EditFieldLabel = uilabel(app.TortuosityFactor_GridLayout);
            app.Direction3EditFieldLabel.Tooltip = {'Use 1 to calculate transport properties for the whole volume only. If you enter a positive integer > 1, for instance 3, then, in addition, the transport properties will be also calculated in 3 subvolumes along this axis to determine if they change along this axis.'};
            app.Direction3EditFieldLabel.Text = 'Direction 3';
            app.Direction3EditField = uieditfield(app.TortuosityFactor_GridLayout, 'numeric');
            app.Direction3EditField.Limits = [1 Inf];
            app.Direction3EditField.RoundFractionalValues = 'on';
            app.Direction3EditField.Tooltip = {'Use 1 to calculate transport properties for the whole volume only. If you enter a positive integer > 1, for instance 3, then, in addition, the transport properties will be also calculated in 3 subvolumes along this axis to determine if they change along this axis.'};
            app.Direction3EditField.Value = 1;
            app.ConnectivityTab = uitab(app.TabGroup2);
            app.ConnectivityTab.Title = 'Connectivity';
            app.ItwillbereintroducedwithnextupdateInthemeantimethesegmentationmodulecancalculateconnectivityLabel = uilabel(app.ConnectivityTab);
            app.ItwillbereintroducedwithnextupdateInthemeantimethesegmentationmodulecancalculateconnectivityLabel.Text = {'It will be re-introduced with next update.'; 'In the meantime, the segmentation module can calculate connectivity'};
            app.FractaldimensionTab = uitab(app.TabGroup2);
            app.FractaldimensionTab.Title = 'Fractal dimension';
            app.ItwillbeintroducedwithnextupdateBoxingmethodLabel = uilabel(app.FractaldimensionTab);
            app.ItwillbeintroducedwithnextupdateBoxingmethodLabel.Text = {'It will be introduced with next update.'; 'Boxing method'};
            app.Volume_Numerical_error_Tab = uitab(app.TabGroup2);
            app.Volume_Numerical_error_Tab.Title = 'About numerical error';
            app.BoundaryconditionTauFactoruseDirichletDiricheltwhichmeansyouhavetheboundforFinitedifferencebasedcalculationsoorder1ErrorcanbesignificantwithimageresolutionseeUsseglioarticleTobeinsensitivewithimageresoltuionFEwithhighorderelementworkswellSmallfieldofviewedgeeffectsbiasthecalculationlesspathavailabletortuositywillincreaseLabel = uilabel(app.Volume_Numerical_error_Tab);
            app.BoundaryconditionTauFactoruseDirichletDiricheltwhichmeansyouhavetheboundforFinitedifferencebasedcalculationsoorder1ErrorcanbesignificantwithimageresolutionseeUsseglioarticleTobeinsensitivewithimageresoltuionFEwithhighorderelementworkswellSmallfieldofviewedgeeffectsbiasthecalculationlesspathavailabletortuositywillincreaseLabel.Text = {'Boundary condition. TauFactor use Dirichlet-Dirichelt, which means you have the * bound for *'; ''; 'Finite difference based calculation, so order 1. Error can be significant with image resolution - see Usseglio article. To be insensitive with image resoltuion FE with high-order element works well.'; ''; 'Small field of view: edge effects bias the calculation: less path available: tortuosity will increase'; ''; ''};
            app.ParticleTab = uitab(app.Main_TabGroup);
            app.ParticleTab.Title = 'Particle';
            app.TabGroup4 = uitabgroup(app.ParticleTab);
            app.DiameterTab = uitab(app.TabGroup4);
            app.DiameterTab.Title = 'Diameter';
            app.TabGroup7 = uitabgroup(app.DiameterTab);
            app.ContinuousparticlesizedistributionTab = uitab(app.TabGroup7);
            app.ContinuousparticlesizedistributionTab.Title = 'Continuous particle size distribution';
            app.Cpsd_GridLayout = uigridlayout(app.ContinuousparticlesizedistributionTab);
            app.SelectphasesforwhichyouwanttocalculatesizedistributionLabel = uilabel(app.Cpsd_GridLayout);
            app.SelectphasesforwhichyouwanttocalculatesizedistributionLabel.Text = 'Select phases for which you want to calculate size distribution';
            app.Cpsd_Semantic_UITable = uitable(app.Cpsd_GridLayout);
            app.Cpsd_Semantic_UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.Cpsd_Semantic_UITable.RowName = {};
            app.Cpsd_Instance_UITable = uitable(app.Cpsd_GridLayout);
            app.Cpsd_Instance_UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.Cpsd_Instance_UITable.RowName = {};
            app.Cpsd_ExcludetruncatedparticlesCheckBox = uicheckbox(app.Cpsd_GridLayout);
            app.Cpsd_ExcludetruncatedparticlesCheckBox.Text = 'Exclude truncated particles';
            app.Cpsd_AllphasesCheckBox = uicheckbox(app.Cpsd_GridLayout);
            app.Cpsd_AllphasesCheckBox.ValueChangedFcn = createCallbackFcn(app, @Cpsd_AllphasesCheckBoxValueChanged, true);
            app.Cpsd_AllphasesCheckBox.Text = 'All phases';
            app.Cpsd_method_Image = uiimage(app.Cpsd_GridLayout);
            app.Cpsd_limitations_Image = uiimage(app.Cpsd_GridLayout);
            app.MethoddescriptionLabel = uilabel(app.Cpsd_GridLayout);
            app.MethoddescriptionLabel.Text = 'Method description';
            app.MethodprosandconsLabel = uilabel(app.Cpsd_GridLayout);
            app.MethodprosandconsLabel.Text = 'Method pros and cons';
            app.Cpsd_Usesymmetry_CheckBox = uicheckbox(app.Cpsd_GridLayout);
            app.Cpsd_Usesymmetry_CheckBox.Text = 'Use symmetry to remove edge effects (only if there is a background)';
            app.VolumebasedTab = uitab(app.TabGroup7);
            app.VolumebasedTab.Title = 'Volume-based';
            app.MetricsperparticleTab = uitab(app.TabGroup4);
            app.MetricsperparticleTab.Title = 'Metrics per particle';
            app.MetricsPerParticle_GridLayout = uigridlayout(app.MetricsperparticleTab);
            app.VolumefractionInstructions_Label_2 = uilabel(app.MetricsPerParticle_GridLayout);
            app.VolumefractionInstructions_Label_2.Text = 'Select volumes for which you want to calculate metrics per particle';
            app.MetricsPerParticle_UITable = uitable(app.MetricsPerParticle_GridLayout);
            app.MetricsPerParticle_UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.MetricsPerParticle_UITable.RowName = {};
            app.ExcludetruncatedparticlesCheckBox = uicheckbox(app.MetricsPerParticle_GridLayout);
            app.ExcludetruncatedparticlesCheckBox.Text = 'Exclude truncated particles';
            app.requiresinstanceorsemanticandinstancesegmentationLabel = uilabel(app.MetricsPerParticle_GridLayout);
            app.requiresinstanceorsemanticandinstancesegmentationLabel.Text = '(requires "instance" or "semantic and instance" segmentation)';
            app.WeightMetrics_withvolume_CheckBox = uicheckbox(app.MetricsPerParticle_GridLayout);
            app.WeightMetrics_withvolume_CheckBox.Tooltip = {'Otherwise, each particle has the same weight, regardless of their size.'};
            app.WeightMetrics_withvolume_CheckBox.Text = 'Weight distributions with particle volumes';
            app.WeightMetrics_withvolume_CheckBox.Value = true;
            app.InterfaceTab = uitab(app.Main_TabGroup);
            app.InterfaceTab.Title = 'Interface';
            app.TabGroup3 = uitabgroup(app.InterfaceTab);
            app.SpecificsurfaceareaTab = uitab(app.TabGroup3);
            app.SpecificsurfaceareaTab.Title = 'Specific surface area';
            app.Volumefraction_GridLayout_2 = uigridlayout(app.SpecificsurfaceareaTab);
            app.VolumefractionInstructions_Label_3 = uilabel(app.Volumefraction_GridLayout_2);
            app.VolumefractionInstructions_Label_3.Text = 'Select phases for which you want to calculate volume fraction';
            app.Volumefractions_AllphasesCheckBox_2 = uicheckbox(app.Volumefraction_GridLayout_2);
            app.Volumefractions_AllphasesCheckBox_2.Text = 'All phases';
            app.Volumefraction_Semantic_UITable_2 = uitable(app.Volumefraction_GridLayout_2);
            app.Volumefraction_Semantic_UITable_2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.Volumefraction_Semantic_UITable_2.RowName = {};
            app.Volumefraction_Semantic_UITable_2.Tooltip = {'Semantic volumes'};
            app.Volumefraction_Instance_UITable_2 = uitable(app.Volumefraction_GridLayout_2);
            app.Volumefraction_Instance_UITable_2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.Volumefraction_Instance_UITable_2.RowName = {};
            app.Volumefraction_Instance_UITable_2.Tooltip = {'Instance volumes'};
            app.CovariancedoonlyfirststepCheckBox = uicheckbox(app.Volumefraction_GridLayout_2);
            app.CovariancedoonlyfirststepCheckBox.Text = 'Covariance (do only first step)';
            app.CroftonformulaCheckBox = uicheckbox(app.Volumefraction_GridLayout_2);
            app.CroftonformulaCheckBox.Text = 'Crofton formula';
            app.SumCheckBox = uicheckbox(app.Volumefraction_GridLayout_2);
            app.SumCheckBox.Text = 'Sum';
            app.GradientmethodCheckBox = uicheckbox(app.Volumefraction_GridLayout_2);
            app.GradientmethodCheckBox.Text = 'Gradient method';
            app.IsgradientmethodsamewithcovarianceLabel = uilabel(app.Volumefraction_GridLayout_2);
            app.IsgradientmethodsamewithcovarianceLabel.Text = 'Is gradient method same with covariance?';
            app.CorrectivefactorEditField_2Label = uilabel(app.Volumefraction_GridLayout_2);
            app.CorrectivefactorEditField_2Label.Text = 'Corrective factor';
            app.CorrectivefactorEditField_2 = uieditfield(app.Volumefraction_GridLayout_2, 'numeric');
            app.CorrectivefactorEditField_2.Limits = [0 Inf];
            app.CorrectivefactorEditField_2.Value = 0.666666666666667;
            app.SpecificinterfaceareaTab = uitab(app.TabGroup3);
            app.SpecificinterfaceareaTab.Title = 'Specific interface area';
            app.SpInterface_GridLayout = uigridlayout(app.SpecificinterfaceareaTab);
            app.Interface_Instructions_Label = uilabel(app.SpInterface_GridLayout);
            app.Interface_Instructions_Label.Text = 'Select phases for which you want to calculate volume fraction';
            app.CorrectivefactorEditFieldLabel = uilabel(app.SpInterface_GridLayout);
            app.CorrectivefactorEditFieldLabel.Text = 'Corrective factor';
            app.CorrectivefactorEditField = uieditfield(app.SpInterface_GridLayout, 'numeric');
            app.CorrectivefactorEditField.Limits = [0 Inf];
            app.CorrectivefactorEditField.Value = 0.666666666666667;
            app.BackgrounddescriptionLabel_2 = uilabel(app.SpInterface_GridLayout);
            app.BackgrounddescriptionLabel_2.Text = 'If a background is present, interface area are normalized with: array volume minus backgroud volume';
            app.TriplephaseboundarylengthdensityTab = uitab(app.TabGroup3);
            app.TriplephaseboundarylengthdensityTab.Title = 'Triple phase boundary length density';
            app.Sp_Numerical_error = uitab(app.TabGroup3);
            app.Sp_Numerical_error.Title = 'About numerical error';
            app.TabGroup8 = uitabgroup(app.Sp_Numerical_error);
            app.VoxelrepresentationTab = uitab(app.TabGroup8);
            app.VoxelrepresentationTab.Title = 'Voxel representation';
            app.GridLayout10 = uigridlayout(app.VoxelrepresentationTab);
            app.Sp_correctivefactor_UIAxes = uiaxes(app.GridLayout10);
            title(app.Sp_correctivefactor_UIAxes, 'Title')
            xlabel(app.Sp_correctivefactor_UIAxes, 'X')
            ylabel(app.Sp_correctivefactor_UIAxes, 'Y')
            zlabel(app.Sp_correctivefactor_UIAxes, 'Z')
            app.Onesourceofnumericalerrorforinterfacecalculationsboth2Dand1DistheoverestimationinducedbythemicrostructurevoxelrepresentationThisoverestimationisintrinsictothevoxelrepresentationandpersistsevenwithaninfiniteimageresolutionLabel = uilabel(app.GridLayout10);
            app.Onesourceofnumericalerrorforinterfacecalculationsboth2Dand1DistheoverestimationinducedbythemicrostructurevoxelrepresentationThisoverestimationisintrinsictothevoxelrepresentationandpersistsevenwithaninfiniteimageresolutionLabel.Text = {'One source of numerical error for interface calculations (both 2D and 1D)  is the overestimation induced by the microstructure voxel representation. This overestimation is intrinsic to the voxel representation and persists even with an infinite'; 'image resolution. '};
            app.Image4 = uiimage(app.GridLayout10);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'Sp_overestimation.png');
            app.LeftAreaoverestimationillustratedonavoxelizedspherethesphereareais6timestheareaofadiscprojectedalonganaxisRightthecorrectivefactoranalyticallycalculatedasafunctionoftheparticleelongationLabel = uilabel(app.GridLayout10);
            app.LeftAreaoverestimationillustratedonavoxelizedspherethesphereareais6timestheareaofadiscprojectedalonganaxisRightthecorrectivefactoranalyticallycalculatedasafunctionoftheparticleelongationLabel.Text = '(Left) Area overestimation illustrated on a voxelized sphere (the sphere area is 6 times the area of a disc projected along an axis). (Right) the corrective factor analytically calculated as a function of the particle elongation.';
            app.Thedemonstrationofthe23correctivefactorcanbefoundthereLabel = uilabel(app.GridLayout10);
            app.Thedemonstrationofthe23correctivefactorcanbefoundthereLabel.Text = 'The demonstration of the 2/3 corrective factor can be found there:';
            app.Hyperlink8 = uihyperlink(app.GridLayout10);
            app.Hyperlink8.URL = 'https://doi.org/10.1118/1.1470207';
            app.Hyperlink8.Text = 'D. A. Rajon, P. W. Patton, A. P. Shah, C. J. Watchman, and W. E. Bolch, Med. Phys., 29, 682 (2002).';
            app.In2Dtheperimetercalculatednumericallyfromapixelizedrepresentationmustbemultipliedbypi4forforanidealdisc11Label = uilabel(app.GridLayout10);
            app.In2Dtheperimetercalculatednumericallyfromapixelizedrepresentationmustbemultipliedbypi4forforanidealdisc11Label.Text = 'In 2D, the perimeter calculated numerically from a pixelized representation must be multiplied by pi/4 for for an ideal disc (1:1).';
            app.ForTPBLacorrectivefactorof11455hasbeenproposedthereLabel = uilabel(app.GridLayout10);
            app.ForTPBLacorrectivefactorof11455hasbeenproposedthereLabel.Text = 'For TPBL, a corrective factor of 1/1.455 has been proposed there:';
            app.Hyperlink9 = uihyperlink(app.GridLayout10);
            app.Hyperlink9.URL = 'https://doi.org/10.1016/j.jpowsour.2009.09.074';
            app.Hyperlink9.Text = 'J.R. Wilson, J.S. Cronin, A.T. Duong, S. Rukes, H.-Y. Chen, K. Thornton,D.R. Mumm, S. Barnett, J. Power Sources 195 (2010) 1829-1840.';
            app.ImageresolutionTab_2 = uitab(app.TabGroup8);
            app.ImageresolutionTab_2.Title = 'Image resolution';
            app.GridLayout10_2 = uigridlayout(app.ImageresolutionTab_2);
            app.IdealgeometrieswithoutsurfaceroughnessLabel = uilabel(app.GridLayout10_2);
            app.IdealgeometrieswithoutsurfaceroughnessLabel.Text = '1) Ideal geometries without surface roughness';
            app.InthiscasetheactualvaluecanbeobtainedbyincreasingtheimageresolutionYoujustneedtobecarefulforthedefinitionofthediameterforanoddorevennumberofpixelsalongtheparticlediameterLabel = uilabel(app.GridLayout10_2);
            app.InthiscasetheactualvaluecanbeobtainedbyincreasingtheimageresolutionYoujustneedtobecarefulforthedefinitionofthediameterforanoddorevennumberofpixelsalongtheparticlediameterLabel.Text = 'In this case, the actual value can be obtained by increasing the image resolution. You just need to be careful for the definition of the diameter for an odd or even number of pixels along the particle diameter. ';
            app.Image5 = uiimage(app.GridLayout10_2);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'Specificsurfacearea_convergence_illustration.png');
            app.RealgeometrieswithsurfaceroughnessLabel = uilabel(app.GridLayout10_2);
            app.RealgeometrieswithsurfaceroughnessLabel.Text = '2) Real geometries with surface roughness';
            app.SurfaceandlinearpropertiescanexhibitafractalbehaviorasmoredetailsarerevealedwithfinerimageresolutionsuntilacutoffresolutionisreachedabovewhichthefractalbehaviorcollapsesforrealmaterialsSeeErrorsImageresolutionforadditionalreferencesandtoseehowtocalculatethefractaldimensionLabel = uilabel(app.GridLayout10_2);
            app.SurfaceandlinearpropertiescanexhibitafractalbehaviorasmoredetailsarerevealedwithfinerimageresolutionsuntilacutoffresolutionisreachedabovewhichthefractalbehaviorcollapsesforrealmaterialsSeeErrorsImageresolutionforadditionalreferencesandtoseehowtocalculatethefractaldimensionLabel.Text = {'Surface and linear properties can exhibit a fractal behavior, as more details are revealed with finer image resolutions (until a cut-off resolution is reached, above which the fractal behavior collapses for real materials).'; 'See Errors/Image resolution for additional references and to see how to calculate the fractal dimension.'};
            app.Label_43 = uilabel(app.GridLayout10_2);
            app.Label_43.Text = '';
            % Create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
            app. = uilabel(app.GridLayout10_2);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
            app..Layout.Row = [15 17];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
            app..Layout.Column = [1 21];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
            app..Text = {'This fractal behavior induces several pratical consequences:'; '- It is likely the calculated values are an underestimation of the real interface area or TPBL density (even considering the overestimation induced by the voxel representation). Therefore, it is recommended to fit this parameter, while the numerical'; 'calculations can still be useful to compare different microstructures imaged at similar voxel size.'; '- Specific surface area Sp is used to scale the reaction rate j along with the exchange current density io, i.e., j = Sp * io * (exp(...)-exp(...)). An incorrect/overfitted value for io or Sp means an incorrect/overfitted value for the other parameter.'; '- Particle diameter and specific surface area should be decoupled in a Pseudo-2D battery model.'};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
            app.Asecondsourceoferrorforinterfacecalculationsboth2Dand1DisthelimitedimageresolutionTwocasesmustbeconsideredLabel = uilabel(app.ImageresolutionTab_2);
            app.Asecondsourceoferrorforinterfacecalculationsboth2Dand1DisthelimitedimageresolutionTwocasesmustbeconsideredLabel.Text = 'A second source of error for interface calculations (both 2D and 1D)  is the limited image resolution. Two cases must be considered.';
            app.ErrorsTab = uitab(app.Main_TabGroup);
            app.ErrorsTab.Title = 'Errors';
            app.TabGroup5 = uitabgroup(app.ErrorsTab);
            app.ImageresolutionTab = uitab(app.TabGroup5);
            app.ImageresolutionTab.Title = 'Image resolution';
            app.Imageresolution_GridLayout = uigridlayout(app.ImageresolutionTab);
            app.SelectedmicrostructurepropertiesarecalculatedattheLabel = uilabel(app.Imageresolution_GridLayout);
            app.SelectedmicrostructurepropertiesarecalculatedattheLabel.Text = {'Selected microstructure properties are calculated at the original image resolution set in Files/Import. However, this resolution may not be high enough and thus it can induce an error. You can estimate this error by downscaling'; 'the image and then plot the calculated properties as function of the voxel size (ideally, you would have access to several imaging of the same material performed at different resolution).'; ''; 'Image resolution mostly modify interfaces, while keeping relatively intact volumes, therefore surface (e.g., interface area) and linear (e.g. TPBL) properties are much more impacted than volume (e.g. volume fractions, tortuosity)'; 'properties. Surface and linear properties can exhibit a fractal behavior, as more details are revealed with finer image resolutions (until a cut-off resolution is reached, above which the fractal behavior collapses for real materials).'; 'For such fractal properties, it is recommended not to extrapolate to a zero voxel size, while properties converging with voxel size (typically volume properties) can be extrapolated safely.'};
            app.VolumeselectionDropDownLabel_2 = uilabel(app.Imageresolution_GridLayout);
            app.VolumeselectionDropDownLabel_2.Text = 'Volume selection ';
            app.Voxelsize_VolumeselectionDropDown = uidropdown(app.Imageresolution_GridLayout);
            app.Voxelsize_VolumeselectionDropDown.Items = {};
            app.Voxelsize_VolumeselectionDropDown.ValueChangedFcn = createCallbackFcn(app, @Voxelsize_VolumeselectionDropDownValueChanged, true);
            app.Voxelsize_VolumeselectionDropDown.Value = {};
            app.Voxelsize_UITable = uitable(app.Imageresolution_GridLayout);
            app.Voxelsize_UITable.ColumnName = {'Property'; 'Scaling factor'; 'Voxel size'; 'Extrapolation'; 'Topology dimension'; 'Calculate fractal dimension'};
            app.Voxelsize_UITable.RowName = {};
            app.Voxelsize_UITable.ColumnEditable = [false true false true false true];
            app.Voxelsize_UITable.CellEditCallback = createCallbackFcn(app, @Voxelsize_UITableCellEdit, true);
            app.Voxelsize_Applytoallvolumes_Button = uibutton(app.Imageresolution_GridLayout, 'push');
            app.Voxelsize_Applytoallvolumes_Button.ButtonPushedFcn = createCallbackFcn(app, @Voxelsize_Applytoallvolumes_ButtonPushed, true);
            app.Voxelsize_Applytoallvolumes_Button.Text = 'Apply options above to all volumes';
            app.Label_28 = uilabel(app.Imageresolution_GridLayout);
            app.Label_28.Text = 'Fractal dimension F of a property p can be calculated using the Mandelbrot formula:';
            app.TheoriginalconceptLabel_2 = uilabel(app.Imageresolution_GridLayout);
            app.TheoriginalconceptLabel_2.Text = 'The original concept:';
            app.andrevisitedformicrostructureLabel_2 = uilabel(app.Imageresolution_GridLayout);
            app.andrevisitedformicrostructureLabel_2.Text = '...and revisited for microstructure:';
            app.Label_29 = uilabel(app.Imageresolution_GridLayout);
            app.Label_29.Text = {'With m a scaling factor, T the topology dimension (e.g., T=3 for volume fraction, T=2 for'; 'surface area, T=1 for TPBL), and v the voxel size. Fractal propension P = F-T >= 0'};
            app.Hyperlink4_2 = uihyperlink(app.Imageresolution_GridLayout);
            app.Hyperlink4_2.URL = 'https://doi.org/10.1016/j.nanoen.2017.06.028';
            app.Hyperlink4_2.Text = 'A. Bertei et al., Nano Energy, 2017';
            app.Hyperlink3_2 = uihyperlink(app.Imageresolution_GridLayout);

            app.Hyperlink3_2.URL = 'https://doi.org/10.1126/science.156.3775.636';
            app.Hyperlink3_2.Text = 'B. Mandelbrot, Science, 1967 (How Long Is the Coast of Britain ?)';
            app.TocompareafractalpropertyF0twoormorevolumesLabel = uilabel(app.Imageresolution_GridLayout);
            app.TocompareafractalpropertyF0twoormorevolumesLabel.Text = {'To compare a fractal property (F>0) between two or more volumes, (e.g., which volume has'; 'the higher value), fractal propension P should be calculated. For instance, if at the same'; 'image resolution, two materials have the same p value, but one has a higher P, then it is likely'; 'this material has also a higher actual p value (the variation with voxel size is higher).'};
            app.ExtrapolationchoicenonelinearquadraticcubicLabel = uilabel(app.Imageresolution_GridLayout);
            app.ExtrapolationchoicenonelinearquadraticcubicLabel.Text = 'Extrapolation choice: none, linear, quadratic, cubic';
            app.Scalingfactorexample1152255writeasitiswiththecommasLabel = uilabel(app.Imageresolution_GridLayout);
            app.Scalingfactorexample1152255writeasitiswiththecommasLabel.Text = 'Scaling factor example: 1, 1.5, 2, 2.5, 5 (write as it is, with the commas)';
            app.LogpmTFlogvLabel = uilabel(app.Imageresolution_GridLayout);
            app.LogpmTFlogvLabel.Interpreter = 'latex';
            app.LogpmTFlogvLabel.Text = 'Log(p) = m + (T - F)log(v)';
            app.RepresentativityTab = uitab(app.TabGroup5);
            app.RepresentativityTab.Title = 'Representativity';
            app.TabGroup6 = uitabgroup(app.RepresentativityTab);
            app.RVEanalysis_Tab = uitab(app.TabGroup6);
            app.RVEanalysis_Tab.Title = 'Independent subvolumes';
            app.Set_RRVE_GridLayout = uigridlayout(app.RVEanalysis_Tab);
            app.RVEconvergencewithfieldofviewCheckBox = uicheckbox(app.Set_RRVE_GridLayout);
            app.RVEconvergencewithfieldofviewCheckBox.ValueChangedFcn = createCallbackFcn(app, @RVEconvergencewithfieldofviewCheckBoxValueChanged, true);
            app.RVEconvergencewithfieldofviewCheckBox.Tooltip = {'Time expensive but required to avoid underestimating true size of the RVE (RVE is FOV-dependent).'};
            app.RVEconvergencewithfieldofviewCheckBox.Text = 'RVE convergence with field of view';
            app.ConvergenceanalysisPanel = uipanel(app.Set_RRVE_GridLayout);
            app.ConvergenceanalysisPanel.Title = 'Convergence analysis';
            app.RVEconvergence_GridLayout = uigridlayout(app.ConvergenceanalysisPanel);
            app.CropDropDownLabel = uilabel(app.RVEconvergence_GridLayout);
            app.CropDropDownLabel.Tooltip = {'For batteries, select "2 directions" and set the uncropped direction as the electrode thickness.'};
            app.CropDropDownLabel.Text = 'Crop';
            app.RVEconvergence_CropDropDown = uidropdown(app.RVEconvergence_GridLayout);
            app.RVEconvergence_CropDropDown.Items = {'1 direction', '2 directions', '3 directions'};
            app.RVEconvergence_CropDropDown.ValueChangedFcn = createCallbackFcn(app, @RVEconvergence_CropDropDownValueChanged, true);
            app.RVEconvergence_CropDropDown.Tooltip = {'For batteries, select "2 directions" and set the uncropped direction as the electrode thickness.'};
            app.RVEconvergence_CropDropDown.Value = '1 direction';
            app.thatisDropDownLabel = uilabel(app.RVEconvergence_GridLayout);
            app.thatisDropDownLabel.Text = 'that is';
            app.RVE_thatisDropDown = uidropdown(app.RVEconvergence_GridLayout);
            app.RVE_thatisDropDown.Tooltip = {'For batteries, select "2 directions" and set the uncropped direction as the electrode thickness.'};
            app.HowtocroptheinitialFOVLabel = uilabel(app.RVEconvergence_GridLayout);
            app.HowtocroptheinitialFOVLabel.Text = 'How to crop the initial FOV:';
            app.AteachstepDropDownLabel = uilabel(app.RVEconvergence_GridLayout);
            app.AteachstepDropDownLabel.Text = 'At each step';
            app.AteachstepDropDown = uidropdown(app.RVEconvergence_GridLayout);
            app.AteachstepDropDown.Items = {'remove x %vol of the initial volume '};
            app.AteachstepDropDown.Value = 'remove x %vol of the initial volume ';
            app.andhowmuchLabel = uilabel(app.RVEconvergence_GridLayout);
            app.andhowmuchLabel.Text = 'and how much:';
            app.xLabel = uilabel(app.RVEconvergence_GridLayout);
            app.xLabel.Text = 'x(%)';
            app.RVEconvergence_xEditField = uieditfield(app.RVEconvergence_GridLayout, 'numeric');
            app.RVEconvergence_xEditField.Limits = [0 Inf];
            app.RVEconvergence_xEditField.Value = 5;
            app.CropuntilnewFOVisEditFieldLabel = uilabel(app.RVEconvergence_GridLayout);
            app.CropuntilnewFOVisEditFieldLabel.Text = 'Crop until new  FOV is';
            app.RVEconvCropuntilnewFOVisEditField = uieditfield(app.RVEconvergence_GridLayout, 'numeric');
            app.RVEconvCropuntilnewFOVisEditField.Limits = [1e-09 Inf];
            app.RVEconvCropuntilnewFOVisEditField.Value = 10;
            app.oftheinitialFOVLabel = uilabel(app.RVEconvergence_GridLayout);
            app.oftheinitialFOVLabel.Text = '% of the initial FOV';
            app.Label_33 = uilabel(app.Set_RRVE_GridLayout);
            app.Label_33.Text = {'Selected microstructure properties are calculated in the ROI set in Files/Import. However, this ROI (or fielf of view FOV) may not be large enough to be representative of the whole microstructure and thus it can induce an error.'; ''};
            app.Hyperlink3 = uihyperlink(app.Set_RRVE_GridLayout);
            app.Hyperlink3.URL = 'https://doi.org/10.1016/S0020-7683(03)00143-4';
            app.Hyperlink3.Text = '[1] Kanit et al., Determination of the size of the representative volume element for random composites: statistical and numerical approach, Int. J. Solids and and Structures, 40, 2003';
            app.HowtointerpretateRVEresultsButton = uibutton(app.Set_RRVE_GridLayout, 'push');
            app.HowtointerpretateRVEresultsButton.ButtonPushedFcn = createCallbackFcn(app, @HowtointerpretateRVEresultsButtonPushed, true);
            app.HowtointerpretateRVEresultsButton.Text = {'How to'; 'interpretate'; 'RVE results'};
            app.Image3 = uiimage(app.Set_RRVE_GridLayout);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'RVE_question.png');
            app.Label_34 = uilabel(app.Set_RRVE_GridLayout);
            app.Label_34.Text = 'Notes: (i) RVE is property-specific, (ii) RVE is aspect-ratio dependent, (iii) RVE is FOV-dependent, and (iv) representativity threshold should be deduced with a propagation analysis. These items are further explained in [2].';
            app.Label_35 = uilabel(app.Set_RRVE_GridLayout);
            app.Label_35.Text = {'Baseline representativity approach [1] consists in cutting the initial volume into independent (i.e., non'; 'overlapping) subvolumes of same size. Property is then calculated for each of them and the relative'; 'standard deviation is deduced. Analysis is repeated with another subvolume size. Relative standard'; 'deviation is then plotted as a function of the subvolume size. The subvolume size for which the curve'; 'crosses a low threshold (e.g. 5%) is considered to be representatitve and is called a Representative'; 'Volume Element (RVE).'};
            app.Hyperlink3_3 = uihyperlink(app.Set_RRVE_GridLayout);
            app.Hyperlink3_3.URL = 'https://doi.org/10.1149/1945-7111/adf35d';
            app.Hyperlink3_3.Text = '[2] F. L. E. Usseglio-Viretta et al., Microstructure Scale Lithium-Ion Battery Modeling, Part IV: The Representativity of Microstructure Parameters and Electrochemical Response, JES, 172, 2025';
            app.ChoiceDropDownLabel = uilabel(app.Set_RRVE_GridLayout);
            app.ChoiceDropDownLabel.Tooltip = {'For batteries, select "Subvolumes have one constant length" and set the thickness as the constant direction.'};
            app.ChoiceDropDownLabel.Text = 'Choice';
            app.RVE_ChoiceDropDown = uidropdown(app.Set_RRVE_GridLayout);
            app.RVE_ChoiceDropDown.Items = {'Subvolumes inherit aspect ratio from initial FOV', 'Subvolumes aspect ratio is user-defined', 'Subvolumes have one constant length', 'Subvolumes have two constant lengths (3D only)'};
            app.RVE_ChoiceDropDown.ValueChangedFcn = createCallbackFcn(app, @RVE_ChoiceDropDownValueChanged, true);
            app.RVE_ChoiceDropDown.Tooltip = {'For batteries, select "Subvolumes have one constant length" and set the thickness as the constant direction.'};
            app.RVE_ChoiceDropDown.Value = 'Subvolumes inherit aspect ratio from initial FOV';
            app.DividersLabel = uilabel(app.Set_RRVE_GridLayout);
            app.DividersLabel.Text = 'Dividers';
            app.RVE_DividerEditField = uieditfield(app.Set_RRVE_GridLayout, 'text');
            app.RVE_DividerEditField.ValueChangedFcn = createCallbackFcn(app, @RVE_DividerEditFieldValueChanged, true);
            app.RVE_DividerEditField.Tooltip = {'It must be a list of increasing integers.'};
            app.RVE_DividerEditField.Value = '2, 3, 4, 5';
            app.RVEdivider_Label = uilabel(app.Set_RRVE_GridLayout);
            app.RVEdivider_Label.Tooltip = {'Text formatted for a 3D volume.'};
            app.RVEdivider_Label.Text = 'From a x b x c to a x b x c';
            app.ConstantlengthsDropDownLabel = uilabel(app.Set_RRVE_GridLayout);
            app.ConstantlengthsDropDownLabel.Text = 'Constant length(s)';
            app.RVE_ConstantlengthsDropDown = uidropdown(app.Set_RRVE_GridLayout);
            app.RVE_ConstantlengthsDropDown.ValueChangedFcn = createCallbackFcn(app, @RVE_DividerEditFieldValueChanged, true);
            app.RVE_ConstantlengthsDropDown.Tooltip = {'For batteries, select "Subvolumes have one constant length" and set the thickness as the constant direction.'};
            app.RelativestandarddeviationthresholdsEditField = uieditfield(app.Set_RRVE_GridLayout, 'text');
            app.RelativestandarddeviationthresholdsEditField.ValueChangedFcn = createCallbackFcn(app, @RelativestandarddeviationthresholdsEditFieldValueChanged, true);
            app.RelativestandarddeviationthresholdsEditField.Tooltip = {'It must be a list of increasing positive real numbers.'};
            app.RelativestandarddeviationthresholdsEditField.Value = '1, 2, 3, 4, 5, 6, 7, 8, 9, 10';
            app.RVE_2subs_CheckBox = uicheckbox(app.Set_RRVE_GridLayout);
            app.RVE_2subs_CheckBox.Text = 'Also caclulate the 2 subvolumes case';
            app.SubvolumeaspectratioEditFieldLabel = uilabel(app.Set_RRVE_GridLayout);
            app.SubvolumeaspectratioEditFieldLabel.Text = 'Subvolume aspect ratio';
            app.RVE_SubvolumeaspectratioEditField = uieditfield(app.Set_RRVE_GridLayout, 'text');
            app.RVE_SubvolumeaspectratioEditField.ValueChangedFcn = createCallbackFcn(app, @RVE_SubvolumeaspectratioEditFieldValueChanged, true);
            app.RVE_SubvolumeaspectratioEditField.Tooltip = {'Format: a, b, c'; 'With a,b and c positive real numbers. If 2D, last value c will be ignored.'};
            app.RVEname_label = uilabel(app.Set_RRVE_GridLayout);
            app.RVEname_label.Text = 'place holder';
            app.ForvolumeDropDownLabel = uilabel(app.Set_RRVE_GridLayout);
            app.ForvolumeDropDownLabel.Text = 'For volume';
            app.RVE_ForvolumeDropDown = uidropdown(app.Set_RRVE_GridLayout);
            app.ForpropertyDropDownLabel = uilabel(app.Set_RRVE_GridLayout);
            app.ForpropertyDropDownLabel.Text = 'For property';
            app.RVE_ForpropertyDropDown = uidropdown(app.Set_RRVE_GridLayout);
            app.RVE_AddrepresentativityanalysisButton = uibutton(app.Set_RRVE_GridLayout, 'push');
            app.RVE_AddrepresentativityanalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RVE_AddrepresentativityanalysisButtonPushed, true);
            app.RVE_AddrepresentativityanalysisButton.Tooltip = {'You can stack several representativity analyses. They are listed in the next tab ("See all analyses planned")'};
            app.RVE_AddrepresentativityanalysisButton.Text = 'Add representativity analysis';
            app.SetyouroptionsbelowandsavethemLabel = uilabel(app.Set_RRVE_GridLayout);
            app.SetyouroptionsbelowandsavethemLabel.Text = '1) Set your options below';
            app.DonotcutsubvolumesmallerthanCheckBox = uicheckbox(app.Set_RRVE_GridLayout);
            app.DonotcutsubvolumesmallerthanCheckBox.ValueChangedFcn = createCallbackFcn(app, @DonotcutsubvolumesmallerthanCheckBoxValueChanged, true);
            app.DonotcutsubvolumesmallerthanCheckBox.Tooltip = {'Useful if same representativity analysis is setup for volumes of different size and/or if RVE convergence with FOV analysis is used.'};
            app.DonotcutsubvolumesmallerthanCheckBox.Text = 'Do not cut subvolume smaller than';
            app.RVE_minsize_EditField = uieditfield(app.Set_RRVE_GridLayout, 'numeric');
            app.RVE_minsize_EditField.Limits = [1e-09 Inf];
            app.RVE_minsize_EditField.Tooltip = {'Subvolumes below this size will be ignored. Without constant length, this number refers to the subvolume cubic root (or square root in 2D). With one constant length, this refers to the square root of the section area. With two constant lengths, this number refers to the length of the variable dimension. Unit used is the voxel size unit set in Files/Import for each file.'};
            app.RVE_minsize_EditField.Value = 1;
            app.OncedoneclicktoaddthisrepresentativityanalysisLabel = uilabel(app.Set_RRVE_GridLayout);
            app.OncedoneclicktoaddthisrepresentativityanalysisLabel.Tooltip = {'You can stack several representativity analyses. They are listed in the next tab ("See all analyses planned")'};
            app.OncedoneclicktoaddthisrepresentativityanalysisLabel.Text = '2) Once done, click to add this representativity analysis.';
            app.ThresholdDropDownLabel = uilabel(app.Set_RRVE_GridLayout);
            app.ThresholdDropDownLabel.Text = 'Threshold';
            app.ThresholdDropDown = uidropdown(app.Set_RRVE_GridLayout);
            app.ThresholdDropDown.Items = {'Relative standard deviation', 'Standard deviation', 'Maximum - minimum'};
            app.ThresholdDropDown.Value = 'Relative standard deviation';
            app.ConvergenceAnalysis_Tab = uitab(app.TabGroup6);
            app.ConvergenceAnalysis_Tab.Title = 'One subvolume';
            app.Set_Rconvergence_GridLayout = uigridlayout(app.ConvergenceAnalysis_Tab);
            app.Label_31 = uilabel(app.Set_Rconvergence_GridLayout);
            app.Label_31.Text = {'Selected microstructure properties are calculated in the ROI set in Files/Import. However, this ROI may not be large enough to be representative of the whole microstructure and thus it can induce an error.'; 'One simple approach consists in calculating the property on volumes of different size. At each iteration the initial ROI is cropped further down and the property is recalculated in this unique subvolume. However, this method never'; 'properly answers the representativity question (see "Independent subvolumes" tab) as independent subvolumes of same size are never compared with this approach, and result should be therefore interpretated with caution .'; 'This method is therefore not recommended and is only here for the sake of completeness.'};
            app.SetyouroptionsbelowLabel = uilabel(app.Set_Rconvergence_GridLayout);
            app.SetyouroptionsbelowLabel.Text = '1) Set your options below';
            app.ForvolumeDropDown_2Label = uilabel(app.Set_Rconvergence_GridLayout);
            app.ForvolumeDropDown_2Label.Text = 'For volume';
            app.OneSub_ForvolumeDropDown = uidropdown(app.Set_Rconvergence_GridLayout);
            app.ForvolumeDropDown_2Label_2 = uilabel(app.Set_Rconvergence_GridLayout);
            app.ForvolumeDropDown_2Label_2.Text = 'For property';
            app.OneSub_ForpropertyDropDown = uidropdown(app.Set_Rconvergence_GridLayout);
            app.OncedoneclicktoaddthisrepresentativityanalysisLabel_2 = uilabel(app.Set_Rconvergence_GridLayout);
            app.OncedoneclicktoaddthisrepresentativityanalysisLabel_2.Tooltip = {'You can stack several representativity analyses. They are listed in the next tab ("See all analyses planned")'};
            app.OncedoneclicktoaddthisrepresentativityanalysisLabel_2.Text = '2) Once done, click to add this representativity analysis.';
            app.OneSub_AddrepresentativityanalysisButton = uibutton(app.Set_Rconvergence_GridLayout, 'push');
            app.OneSub_AddrepresentativityanalysisButton.ButtonPushedFcn = createCallbackFcn(app, @OneSub_AddrepresentativityanalysisButtonPushed, true);
            app.OneSub_AddrepresentativityanalysisButton.Tooltip = {'You can stack several representativity analyses. They are listed in the next tab ("See all analyses planned")'};
            app.OneSub_AddrepresentativityanalysisButton.Text = 'Add representativity analysis';
            app.ChoiceDropDownLabel_2 = uilabel(app.Set_Rconvergence_GridLayout);
            app.ChoiceDropDownLabel_2.Text = 'Choice';
            app.OneSub_ChoiceDropDown = uidropdown(app.Set_Rconvergence_GridLayout);
            app.OneSub_ChoiceDropDown.Items = {'Growing from volume center with initial aspect ratio', 'Growing from volume center with user-defined aspect ratio', 'Growing from volume edge', 'Growing with one constant length'};
            app.OneSub_ChoiceDropDown.ValueChangedFcn = createCallbackFcn(app, @OneSub_ChoiceDropDownValueChanged, true);
            app.OneSub_ChoiceDropDown.Value = 'Growing from volume center with initial aspect ratio';
            app.SubvolumeaspectratioEditFieldLabel_2 = uilabel(app.Set_Rconvergence_GridLayout);
            app.SubvolumeaspectratioEditFieldLabel_2.Text = 'Subvolume aspect ratio';
            app.OneSub_SubvolumeaspectratioEditField = uieditfield(app.Set_Rconvergence_GridLayout, 'text');
            app.OneSub_SubvolumeaspectratioEditField.ValueChangedFcn = createCallbackFcn(app, @OneSub_SubvolumeaspectratioEditFieldValueChanged, true);
            app.OneSub_SubvolumeaspectratioEditField.Tooltip = {'Format: a, b, c'; 'With a,b and c positive real numbers. If 2D, last value c will be ignored.'};
            app.SizeofthefirstsubvolumeEditFieldLabel = uilabel(app.Set_Rconvergence_GridLayout);
            app.SizeofthefirstsubvolumeEditFieldLabel.Text = 'Size of the first subvolume';
            app.SizeofthefirstsubvolumeEditField = uieditfield(app.Set_Rconvergence_GridLayout, 'numeric');
            app.SizeofthefirstsubvolumeEditField.Limits = [1e-09 Inf];
            app.SizeofthefirstsubvolumeEditField.Value = 10;
            app.oftheinitialvolumeLabel = uilabel(app.Set_Rconvergence_GridLayout);
            app.oftheinitialvolumeLabel.Text = '% of the initial volume';
            app.OneSub_Growthrealtiveto_DropDown = uidropdown(app.Set_Rconvergence_GridLayout);
            app.OneSub_Growthrealtiveto_DropDown.Items = {'% of the initial volume', '% of the previous subvolume'};
            app.OneSub_Growthrealtiveto_DropDown.Value = '% of the initial volume';
            app.SubvolumegrowthEditFieldLabel = uilabel(app.Set_Rconvergence_GridLayout);
            app.SubvolumegrowthEditFieldLabel.Text = 'Subvolume growth';
            app.SubvolumegrowthEditField = uieditfield(app.Set_Rconvergence_GridLayout, 'numeric');
            app.SubvolumegrowthEditField.Limits = [1e-09 Inf];
            app.SubvolumegrowthEditField.Value = 5;
            app.DropDown_2Label = uilabel(app.Set_Rconvergence_GridLayout);
            app.DropDown_2Label.Text = 'Drop Down';
            app.OneSub_DropDown = uidropdown(app.Set_Rconvergence_GridLayout);
            app.RelativedifferencethresholdsEditField = uieditfield(app.Set_Rconvergence_GridLayout, 'text');
            app.RelativedifferencethresholdsEditField.ValueChangedFcn = createCallbackFcn(app, @RelativedifferencethresholdsEditFieldValueChanged, true);
            app.RelativedifferencethresholdsEditField.Tooltip = {'It must be a list of increasing positive real numbers.'};
            app.RelativedifferencethresholdsEditField.Value = '1, 2, 3, 4, 5, 6, 7, 8, 9, 10';
            app.ThresholdDropDown_2Label = uilabel(app.Set_Rconvergence_GridLayout);
            app.ThresholdDropDown_2Label.Text = 'Threshold';
            app.ThresholdDropDown_2 = uidropdown(app.Set_Rconvergence_GridLayout);
            app.ThresholdDropDown_2.Items = {'Relative difference', 'Difference'};
            app.ThresholdDropDown_2.Value = 'Relative difference';
            app.SeeallanalysesplannedTab = uitab(app.TabGroup6);
            app.SeeallanalysesplannedTab.Title = 'See all analyses planned';
            app.Set_Rplanned_GridLayout = uigridlayout(app.SeeallanalysesplannedTab);
            app.ForvolumeDropDownLabel_2 = uilabel(app.Set_Rplanned_GridLayout);
            app.ForvolumeDropDownLabel_2.Text = 'For volume';
            app.Allanalyses_ForvolumeDropDown = uidropdown(app.Set_Rplanned_GridLayout);
            app.Allanalyses_ForvolumeDropDown.ValueChangedFcn = createCallbackFcn(app, @Allanalyses_ForvolumeDropDownValueChanged, true);
            app.ForpropertyDropDownLabel_2 = uilabel(app.Set_Rplanned_GridLayout);
            app.ForpropertyDropDownLabel_2.Text = 'For property';
            app.Allanalyses_ForpropertyDropDown = uidropdown(app.Set_Rplanned_GridLayout);
            app.Allanalyses_ForpropertyDropDown.ValueChangedFcn = createCallbackFcn(app, @Allanalyses_ForvolumeDropDownValueChanged, true);
            app.ListAnalysis_UITable = uitable(app.Set_Rplanned_GridLayout);
            app.ListAnalysis_UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.ListAnalysis_UITable.RowName = {};
            app.RemoveallanalysesforthisvolumeButton = uibutton(app.Set_Rplanned_GridLayout, 'push');
            app.RemoveallanalysesforthisvolumeButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveallanalysesforthisvolumeButtonPushed, true);
            app.RemoveallanalysesforthisvolumeButton.Text = 'Remove all analyses for this volume';
            app.CopyanalysesoptionsPanel = uipanel(app.Set_Rplanned_GridLayout);
            app.CopyanalysesoptionsPanel.Title = 'Copy analyses options';
            app.GridLayout6 = uigridlayout(app.CopyanalysesoptionsPanel);
            app.ApplyonALLpropertiesforthisvolumeButton = uibutton(app.GridLayout6, 'push');
            app.ApplyonALLpropertiesforthisvolumeButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyonALLpropertiesforthisvolumeButtonPushed, true);
            app.ApplyonALLpropertiesforthisvolumeButton.Text = 'Apply on ALL properties for this volume';
            app.ApplyonthispropertyforALLvolumesButton = uibutton(app.GridLayout6, 'push');
            app.ApplyonthispropertyforALLvolumesButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyonthispropertyforALLvolumesButtonPushed, true);
            app.ApplyonthispropertyforALLvolumesButton.Text = 'Apply on this property for ALL volumes';
            app.ApplyonALLpropertiesforALLvolumesButton = uibutton(app.GridLayout6, 'push');
            app.ApplyonALLpropertiesforALLvolumesButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyonALLpropertiesforALLvolumesButtonPushed, true);
            app.ApplyonALLpropertiesforALLvolumesButton.Text = 'Apply on ALL properties for ALL volumes';
            app.ApplyPERpropertyforALLvolumesButton = uibutton(app.GridLayout6, 'push');
            app.ApplyPERpropertyforALLvolumesButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyPERpropertyforALLvolumesButtonPushed, true);
            app.ApplyPERpropertyforALLvolumesButton.Text = 'Apply PER property for ALL volumes';
            app.MacroTab = uitab(app.Main_TabGroup);
            app.MacroTab.Title = 'Macro';
            app.Macro_GridLayout = uigridlayout(app.MacroTab);
            app.YoucansaveoptionsinamacroandthenapplyittoothersvolumesLabel = uilabel(app.Macro_GridLayout);
            app.YoucansaveoptionsinamacroandthenapplyittoothersvolumesLabel.Text = 'You can save options in a macro and then apply it to others volumes. All parameters except ROI selection will be re-used. Only parameters for the first volume are saved.';
            app.MacronameEditFieldLabel = uilabel(app.Macro_GridLayout);
            app.MacronameEditFieldLabel.Text = 'Macro name';
            app.MacronameEditField = uieditfield(app.Macro_GridLayout, 'text');
            app.MacronameEditField.Value = 'characterization_macro';
            app.SavefolderButton = uibutton(app.Macro_GridLayout, 'push');
            app.SavefolderButton.ButtonPushedFcn = createCallbackFcn(app, @SavefolderButtonPushed, true);
            app.SavefolderButton.Text = 'Save folder';
            app.Macrosavedolfer_Label = uilabel(app.Macro_GridLayout);
            app.Macrosavedolfer_Label.Text = 'Not defined';
            app.SavemacroButton = uibutton(app.Macro_GridLayout, 'push');
            app.SavemacroButton.ButtonPushedFcn = createCallbackFcn(app, @SavemacroButtonPushed, true);
            app.SavemacroButton.Text = 'Save macro';
            app.MacrodescriptionTextAreaLabel = uilabel(app.Macro_GridLayout);
            app.MacrodescriptionTextAreaLabel.Text = 'Macro description';
            app.MacrodescriptionTextArea = uitextarea(app.Macro_GridLayout);
            app.RunTab = uitab(app.Main_TabGroup);
            app.RunTab.Title = 'Run';
            app.Run_GridLayout = uigridlayout(app.RunTab);
            app.Run_Title = uilabel(app.Run_GridLayout);
            app.Run_Title.Text = 'Run all calculations';
            app.Run_Label = uilabel(app.Run_GridLayout);
            app.Run_Label.Text = 'Once you have setup all previous tabs, you can click on the button below to characterize all volumes sequentially';
            app.Run_button = uibutton(app.Run_GridLayout, 'push');
            app.Run_button.ButtonPushedFcn = createCallbackFcn(app, @Run_buttonButtonPushed, true);
            app.Run_button.Tooltip = {''};
            app.Run_button.Text = '>>> CLICK TO START CALCULATIONS <<<';
            app.RunStatut_UITable = uitable(app.Run_GridLayout);
            app.RunStatut_UITable.ColumnName = {'Volume'; 'Statut'; 'Started'; 'Wallclock time'};
            app.RunStatut_UITable.RowName = {};
            app.Run_SearchtagCheckBox = uicheckbox(app.Run_GridLayout);
            app.Run_SearchtagCheckBox.Text = 'Search tag';
            app.HelpTab = uitab(app.Main_TabGroup);
            app.HelpTab.Title = 'Help';
            app.GridLayout5_3 = uigridlayout(app.HelpTab);
            app.Label_19 = uilabel(app.GridLayout5_3);
            app.Label_19.Text = 'If you run into problems, please read the open issues listed in the GitHub webpage. If your problem is not listed, please open a new issue.';
            app.Hyperlink_8 = uihyperlink(app.GridLayout5_3);
            app.Hyperlink_8.URL = 'https://github.com/NREL/MATBOX_Microstructure_analysis_toolbox/issues';
            app.Hyperlink_8.Text = 'https://github.com/NREL/MATBOX_Microstructure_analysis_toolbox/issues';
            app.FAQimageexampleslinktotutorialsLabel = uilabel(app.GridLayout5_3);
            app.FAQimageexampleslinktotutorialsLabel.Text = 'FAQ / image examples / link to tutorials';
            app.TauFactor2installationinstructionsLabel = uilabel(app.GridLayout5_3);
            app.TauFactor2installationinstructionsLabel.Text = 'Tau Factor 2 installation instructions';
            app.Label_37 = uilabel(app.GridLayout5_3);
            app.Label_37.Text = 'Unlike TauFactor 1, the second iteration is based on Python and has some additional requirements (PyTorch)';
            app.Hyperlink6 = uihyperlink(app.GridLayout5_3);
            app.Hyperlink6.URL = 'https://github.com/tldr-group/taufactor';
            app.Hyperlink6.Text = 'Instructions from TauFactor 2 developpers can be found here';
            app.TextArea = uitextarea(app.GridLayout5_3);
            app.TextArea.Value = {'You can also use these commands. It worked for Windows 11 with MATLAB 2024b.'; '1) Check Python version supported with MATLAB at: https://www.mathworks.com/support/requirements/python-compatibility.html?s_tid=srchtitle_site_search_1_python%20compatibility'; '2) Install Python (I used 3.12 since I have 2024b) at: https://www.python.org/downloads/release/python-3120/'; '3) Check MATLAB python environment: type pyenv in the MATLAB command window. If you have empty strings, you can set the paths manually typing: '; 'pyenv(Version="C:\Users\<username>\AppData\Local\Programs\Python\Python312\python.exe")'; '4) Install pytorch, type (in cmd for instance): pip3 install torch torchvision torchaudio --index-url https://download.pytorch.org/whl/cu118'; '5) install TauFactor 2: py -3.12 -m pip install taufactor  '; ''};
            app.ReferencesTab = uitab(app.Main_TabGroup);
            app.ReferencesTab.Title = 'References';
            app.GridLayout5_4 = uigridlayout(app.ReferencesTab);
            app.Label_20 = uilabel(app.GridLayout5_4);
            app.Label_20.Text = 'Below is a selection of journal articles that rely on MATBOX''s algorithms for microstructure characterization. You can read them to have a better understanding of the different methods used in MATBOX.';
            app.Label_21 = uilabel(app.GridLayout5_4);
            app.Label_21.Text = {'- Connectivity: start with [1] for a simple analysis (i.e., isolated, unknown, and connected clusters). For an in-depth analysis focused on the solid phase (percolation, impact of additives, impact on effective solid conductivity) see [6].'; '- Particle size: start with [1] for standard methods (covariance, c-psd, and watershed). For an in-depth discussion of the different particle size methods see [3].'; '- Tortuosity factor: start with [1, 2]. For impact of additives on tortuosity factor and comparison with experimentally deternined and macroscale fitted values see [2,6]. Have a look at the supplementary material of [2] to'; 'see difference between FEniCS (Finite element method) and MATBOX/Taufactor (Finite difference method). For in-depth discussion on relationships between microstructure and tortuosity see [3].'; '- Specific surface area: start with [1]. For correlation with porosity and comparison with baseline theoritical relationship see [4].'; '- Nanoporosity and wetting, see [8 to add]'; '- Image resolution error: see [3] and supplementary material of [2].'; '- Representativity analysis: start with [1, 5]. For an in-depth review see [7].'};
            app.Hyperlink_9 = uihyperlink(app.GridLayout5_4);
            app.Hyperlink_9.URL = 'https://doi.org/10.1149/1945-7111/ac7ef9';
            app.Hyperlink_9.Text = {'[6] F. L. E. Usseglio-Viretta, A. M. Colclasure, A. R. Dunlop, S. E. Trask, A. Jansen, D. P. Abraham, M-T F. Rodrigues, E. J. Dufek, T. Tanim, P. Chinnam, Y. Ha and K. Smith'; 'Carbon-Binder Weight Loading Optimization for Improved Lithium-Ion Battery Rate Capability, Journal of the Electrochemical Society, 2022'};
            app.Hyperlink_10 = uihyperlink(app.GridLayout5_4);
            app.Hyperlink_10.URL = 'https://doi.org/10.1149/1945-7111/ab913b';
            app.Hyperlink_10.Text = {'[3] F. L. E. Usseglio-Viretta, D. P. Finegan, A. Colclasure, T. M. M. Heenan, D. Abraham, P. Shearing, and K. Smith, Quantitative Relationships Between Pore Tortuosity, Pore Topology, and Solid Particle'; 'Morphology Using a Novel Discrete Particle Size Algorithm, Journal of the Electrochemical Society, 167, 2020'};
            app.Hyperlink_11 = uihyperlink(app.GridLayout5_4);
            app.Hyperlink_11.URL = 'https://doi.org/10.1149/2.0731814jes';
            app.Hyperlink_11.Text = {'[2] F. L. E. Usseglio-Viretta, A. Colclasure, A. N. Mistry, K. P. Y. Claver, F. Pouraghajan, D. P. Finegan, T. M. M. Heenan, D. Abraham, P. P. Mukherjee, D. Wheeler, P. Shearing, S. J. Cooper, and K. Smith'; 'Resolving the Discrepancy in Tortuosity Factor Estimation for Li-Ion Battery Electrodes through Micro-Macro Modeling and Experiment, Journal of the electrochemical Society, 165, 2018'};
            app.Hyperlink_12 = uihyperlink(app.GridLayout5_4);
            app.Hyperlink_12.URL = 'https://doi.org/10.1149/07711.1095ecst';
            app.Hyperlink_12.Text = '[1] F. L. E. Usseglio-Viretta and K. Smith, Quantitative Microstructure Characterization of a NMC Electrode, ECS Transactions, 77, 2017';
            app.Hyperlink_13 = uihyperlink(app.GridLayout5_4);
            app.Hyperlink_13.URL = 'https://doi.org/10.1002/ente.202000666';
            app.Hyperlink_13.Text = {'[4] D. C. Robertson, L. Flores, A. R. Dunlop, S. E. Trask, F. L. E. Usseglio-Viretta, A. M. Colclasure, Z. Yang, and I. Bloom'; 'Effect of Anode Porosity and Temperature on the Performance and Lithium Plating During Fast-Charging of Lithium-Ion Cells, Energy Technology, 2020'};
            app.Hyperlink_14 = uihyperlink(app.GridLayout5_4);
            app.Hyperlink_14.URL = 'https://doi.org/10.1016/j.jpowsour.2020.229101';
            app.Hyperlink_14.Text = {'[5] H. Xu, F. L.E. Usseglio-Viretta, S. Kench, S. J. Cooper, and D. P. Finegan'; 'Microstructure reconstruction of battery polymer separators by fusing 2D and 3D image data for transport property analysis, Journal of Power Sources, 480, 2020'};
            app.Hyperlink7 = uihyperlink(app.GridLayout5_4);
            app.Hyperlink7.URL = 'https://doi.org/10.1149/1945-7111/adee50';
            app.Hyperlink7.Text = ['[7] F. L. E. Usseglio-Viretta, A. M. Colclasure, J. Allen, P. J. Weddle, D. P. Finegan, P. Graf and K. Smith' newline 'Microstructure Scale Lithium-Ion Battery Modeling, Part IV: The Representativity of Microstructure Parameters and Electrochemical Response,  Journal of the Electrochemical Society, 172, 2025'];
            app.HowtoquoteTab = uitab(app.Main_TabGroup);
            app.HowtoquoteTab.Title = 'How to quote';
            app.GridLayout5_2 = uigridlayout(app.HowtoquoteTab);
            app.Label_15 = uilabel(app.GridLayout5_2);
            app.Label_15.Text = 'If you use these algorithms or report results produced thanks to this algorithms, please quote it as follow:';
            app.Label_16 = uilabel(app.GridLayout5_2);
            app.Label_16.Text = {'F. L. E. Usseglio-Viretta, P. Patel, E. Bernhardt, A Mistry, P. P. Mukherjee, J. Allen, S. J. Cooper, J. Laurencin, and K. Smith'; 'MATBOX: An Open-source Microstructure Analysis Toolbox for microstructure generation, segmentation, characterization, visualization, correlation, and meshing'; 'SoftwareX, 17, 2022'};
            app.Hyperlink_6 = uihyperlink(app.GridLayout5_2);
            app.Hyperlink_6.URL = 'https://doi.org/10.1016/j.softx.2021.100915';
            app.Hyperlink_6.Text = 'https://doi.org/10.1016/j.softx.2021.100915';
            app.Label_17 = uilabel(app.GridLayout5_2);
            app.Label_17.Text = 'If you calculate tortuosity factor, please ALSO quote:';
            app.Label_18 = uilabel(app.GridLayout5_2);
            app.Label_18.Text = {'S. J. Cooper, A. Bertei, P. R. Shearing, J. A. Kilner, and N. P. Brandon'; 'TauFactor: An open-source application for calculating tortuosity factors from tomographic data'; 'SoftwareX, 5, 2016'};
            app.Hyperlink_7 = uihyperlink(app.GridLayout5_2);
            app.Hyperlink_7.URL = 'https://doi.org/10.1016/j.softx.2016.09.002';
            app.Hyperlink_7.Text = 'https://doi.org/10.1016/j.softx.2016.09.002';
            app.Hyperlink5_2 = uihyperlink(app.GridLayout5_2);
            app.Hyperlink5_2.URL = 'https://doi.org/10.21105/joss.05358';
            app.Hyperlink5_2.Text = 'https://doi.org/10.21105/joss.05358';
            app.Label_36 = uilabel(app.GridLayout5_2);
            app.Label_36.Text = {'S. Kench, I. Squires, and S. Cooper'; 'TauFactor 2: A GPU accelerated python tool for microstructural analysis'; 'The Journal of Open Source Software, 8, 2023'};
            app.AboutTab = uitab(app.Main_TabGroup);
            app.AboutTab.Title = 'About';
            app.GridLayout5 = uigridlayout(app.AboutTab);
            app.Label_7 = uilabel(app.GridLayout5);
            app.Label_7.Text = 'National Renewable Energy Laboratory (NREL) developed the Microstructure Analysis Toolbox (MATBOX) to analyze electrode microstructures for lithium-ion (Li-ion) batteries and other 3D heterogeneous materials.';
            app.Image = uiimage(app.GridLayout5);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = 'logo_NREL.png';
            app.Hyperlink = uihyperlink(app.GridLayout5);
            app.Hyperlink.URL = 'https://www.nrel.gov/transportation/matbox.html';
            app.Hyperlink.Text = 'MATBOX official webpage';
            app.Hyperlink_2 = uihyperlink(app.GridLayout5);
            app.Hyperlink_2.URL = 'https://github.com/NREL/MATBOX_Microstructure_analysis_toolbox';
            app.Hyperlink_2.Text = 'MATBOX GitHub repository';
            app.Onlineversion_Label = uilabel(app.GridLayout5);
            app.Onlineversion_Label.Text = '';
            app.Label_8 = uilabel(app.GridLayout5);
            app.Label_8.Text = 'If you like this tool, please consider ''Star'' the GitHub repository to increase its visbility.';
            app.Label_9 = uilabel(app.GridLayout5);
            app.Label_9.Text = 'You can register there (optional). You will be notified when major updates are released.';
            app.Hyperlink2 = uihyperlink(app.GridLayout5);
            app.Hyperlink2.URL = 'https://www.nrel.gov/transportation/energy-storage.html';
            app.Hyperlink2.Text = 'NREL energy storage group';
            app.ClicktoseethelicenceButton = uibutton(app.GridLayout5, 'push');
            app.ClicktoseethelicenceButton.ButtonPushedFcn = createCallbackFcn(app, @ClicktoseethelicenceButtonPushed, true);
            app.ClicktoseethelicenceButton.Text = 'Click to see the licence';
            app.NRELSoftwareRecordofInventionSWR2076Label = uilabel(app.GridLayout5);
            app.NRELSoftwareRecordofInventionSWR2076Label.Text = 'NREL Software Record of Invention: SWR-20-76';
            app.BSDLicenceLabel = uilabel(app.GridLayout5);
            app.BSDLicenceLabel.Text = 'BSD Licence';
            app.SetvolumefractionerrorsmacrotagrunthenaddotherpropertiesLabel = uilabel(app.MicrostructurecharacterizationUIFigure);
            app.SetvolumefractionerrorsmacrotagrunthenaddotherpropertiesLabel.Text = {'To do list:'; ''; 'tortuosity'; 'fix summary'; 'fix corelation'; ''; 'tortuosity flux images ?'; ''; 'diameter'; ''; 'interfaces'; ''; 'all particle metrics'; ''; 'connectivity'; 'fractal dimension'; ''; 'Github public'; ''; 'videos, help'};
            app.TortuosityifheterogeneousplotdistributionandpositonalongaxisLabel = uilabel(app.MicrostructurecharacterizationUIFigure);
            app.TortuosityifheterogeneousplotdistributionandpositonalongaxisLabel.Text = 'Tortuosity: if heterogeneous: plot distribution, and positon along axis';
            app.VolumefractionifwettingisheterogeneousplotdistributionandpositonalongaxisLabel = uilabel(app.MicrostructurecharacterizationUIFigure);
            app.VolumefractionifwettingisheterogeneousplotdistributionandpositonalongaxisLabel.Text = 'Volume fraction: if wetting is heterogeneous: plot distribution, and positon along axis';
            app.NextversionindicateforeachphaseiftheyareeconductorionconductorgazconductorLabel = uilabel(app.MicrostructurecharacterizationUIFigure);
            app.NextversionindicateforeachphaseiftheyareeconductorionconductorgazconductorLabel.Text = {'Next version: indicate for each phase if they are'; 'e- conductor, ion conductor, gaz conductor'};
            app.Sp1223labellabellssgcombinedliquidsolidgas1llabelcombinedsolidliquidexamplefortwopixelAandBsolidAliquidBliquidAsolidB1npAnpBwetBnpAwetA1npBDonotconsiderpercolationyetLabel = uilabel(app.MicrostructurecharacterizationUIFigure);
            app.Sp1223labellabellssgcombinedliquidsolidgas1llabelcombinedsolidliquidexamplefortwopixelAandBsolidAliquidBliquidAsolidB1npAnpBwetBnpAwetA1npBDonotconsiderpercolationyetLabel.Text = {'Sp:'; '- 1:2, 2:3 (label-label)'; '- l:s, s:g (combined liquid solid gas)'; '- 1:l (label-combined)'; ''; 'solid- liquid example for two pixel A and B'; 'solidA*liquidB + liquidA*solidB'; '(1-npA)*(npB*wetB) + (npA*wetA)*(1-npB)'; ''; 'Do not consider percolation yet'; ''};
            app.SurfaceareaGradientmethodseemstobeeasytoimplementlookathttpsiopscienceioporgarticle10114919457111ad9a07Label = uilabel(app.MicrostructurecharacterizationUIFigure);
            app.SurfaceareaGradientmethodseemstobeeasytoimplementlookathttpsiopscienceioporgarticle10114919457111ad9a07Label.Text = {'Surface area.'; 'Gradient method seems to be easy to implement: look at '; 'https://iopscience.iop.org/article/10.1149/1945-7111/ad9a07'};
            % Show the figure after all components are created
            app.MicrostructurecharacterizationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Microstructure_characterization2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MicrostructurecharacterizationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MicrostructurecharacterizationUIFigure)
        end
    end
end