classdef Microstructure_characterization2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MicrostructurecharacterizationUIFigure  matlab.ui.Figure
        SetvolumefractionerrorsmacrotagrunthenaddotherpropertiesLabel  matlab.ui.control.Label
        Label_30                        matlab.ui.control.Label
        Main_TabGroup                   matlab.ui.container.TabGroup
        InstructionsTab                 matlab.ui.container.Tab
        Instructions_GridLayout         matlab.ui.container.GridLayout
        Restart                         matlab.ui.control.Button
        FormatTab                       matlab.ui.container.Tab
        Format_GridLayout               matlab.ui.container.GridLayout
        SgtitlefontsizeEditField        matlab.ui.control.NumericEditField
        SgtitlefontsizeEditFieldLabel   matlab.ui.control.Label
        ColormapoptionsLabel            matlab.ui.control.Label
        DiameterbackgroundcolormapDropDown  matlab.ui.control.DropDown
        DiameterbackgroundcolormapDropDownLabel  matlab.ui.control.Label
        DiametercolormapDropDown        matlab.ui.control.DropDown
        DiametercolormapDropDownLabel   matlab.ui.control.Label
        ClosefigureautomaticallyoncesavedCheckBox  matlab.ui.control.CheckBox
        GridDropDown                    matlab.ui.control.DropDown
        GridDropDownLabel               matlab.ui.control.Label
        TitlefontsizeEditField          matlab.ui.control.NumericEditField
        TitlefontsizeEditFieldLabel     matlab.ui.control.Label
        LegendfontsizeEditField         matlab.ui.control.NumericEditField
        LegendfontsizeEditFieldLabel    matlab.ui.control.Label
        AxeFontsizeEditField            matlab.ui.control.NumericEditField
        AxeFontsizeEditFieldLabel       matlab.ui.control.Label
        LinewidthEditField              matlab.ui.control.NumericEditField
        LinewidthEditFieldLabel         matlab.ui.control.Label
        DefaultsizefigureoptionsLabel   matlab.ui.control.Label
        ThismodulecreatesalotoffiguresYoucanselectformatoptionsLabel  matlab.ui.control.Label
        FontnameDropDown                matlab.ui.control.DropDown
        FontnameDropDownLabel           matlab.ui.control.Label
        FilesTab                        matlab.ui.container.Tab
        FilesTabGroup                   matlab.ui.container.TabGroup
        ImportTab                       matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        ItwillresetallparametersLabel   matlab.ui.control.Label
        PhasecolornameInstance_UITable  matlab.ui.control.Table
        ROI_Label                       matlab.ui.control.Label
        Scaledvoxelsize_Label           matlab.ui.control.Label
        Import_indication               matlab.ui.control.Label
        Import_SavesettingsButton       matlab.ui.control.Button
        SaveimportsettingstocontinueLabel  matlab.ui.control.Label
        VolumefolderEditField           matlab.ui.control.EditField
        VolumefolderEditFieldLabel      matlab.ui.control.Label
        Mainfolder_Label                matlab.ui.control.Label
        SelectmainfolderButton          matlab.ui.control.Button
        SetsavelocationLabel            matlab.ui.control.Label
        OptionalPanel                   matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        Info_UITable                    matlab.ui.control.Table
        VolumeinformationLabel          matlab.ui.control.Label
        visualize_resolution_withDropDown  matlab.ui.control.DropDown
        visualized_ROI_withDropDown     matlab.ui.control.DropDown
        withDropDown_3Label             matlab.ui.control.Label
        PlotvolumefractionsButton       matlab.ui.control.Button
        VisualizevolumeButton           matlab.ui.control.Button
        TohelpselectingROIandscalingparametersLabel  matlab.ui.control.Label
        FilesmustsharesamesizeLabel     matlab.ui.control.Label
        PhasecolornameSemantic_UITable  matlab.ui.control.Table
        Phasenamecolor_DropDown         matlab.ui.control.DropDown
        AssignlabelcolorandnameLabel    matlab.ui.control.Label
        ScalingfactorEditField          matlab.ui.control.NumericEditField
        ScalingfactorEditFieldLabel     matlab.ui.control.Label
        VoxelsizeUnit_EditField         matlab.ui.control.EditField
        VoxelsizeEditField              matlab.ui.control.NumericEditField
        VoxelsizeEditFieldLabel         matlab.ui.control.Label
        SetvoxelsizeandscalingfactorLabel  matlab.ui.control.Label
        ROI_UITable                     matlab.ui.control.Table
        SetRegionofInterestROILabel     matlab.ui.control.Label
        Volumenumber_Label              matlab.ui.control.Label
        Instance_NofileimportedLabel    matlab.ui.control.Label
        Semantic_NofileimportedLabel    matlab.ui.control.Label
        Label_instancedescription       matlab.ui.control.Label
        Label_semanticdescription       matlab.ui.control.Label
        ClicktoimportinstancesegmentationButton  matlab.ui.control.Button
        ClicktoimportsemanticsegmentationButton  matlab.ui.control.Button
        ImportfilesLabel                matlab.ui.control.Label
        NomacroloadedLabel              matlab.ui.control.Label
        ClicktoimportmacroButton        matlab.ui.control.Button
        ModeDropDown                    matlab.ui.control.DropDown
        SelectanalyismodeLabel          matlab.ui.control.Label
        SegmentationtypeDropDown        matlab.ui.control.DropDown
        SelectsegmentationtypeofvolumestoanalyseLabel  matlab.ui.control.Label
        ImportedTab                     matlab.ui.container.Tab
        GridLayout_imported             matlab.ui.container.GridLayout
        PhaseandInstancecorrespondtotheimportednotnecessarlytheROILabel  matlab.ui.control.Label
        SearchtagButton                 matlab.ui.control.Button
        Label_6                         matlab.ui.control.Label
        itwillinitializeorresetthesubsequenttabsLabel  matlab.ui.control.Label
        Imported_SavesettingsButton     matlab.ui.control.Button
        Label_5                         matlab.ui.control.Label
        Setcalculationorderto0nottoanalyseafileLabel  matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        TagLabel                        matlab.ui.control.Label
        VisualizationLabel              matlab.ui.control.Label
        TagunitEditField                matlab.ui.control.EditField
        TagunitEditFieldLabel           matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Imported_UITable                matlab.ui.control.Table
        VisualizeROI_DropDown           matlab.ui.control.DropDown
        VisualizeallfilesButton         matlab.ui.control.Button
        VolumeTab                       matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        VolumefractionTab               matlab.ui.container.Tab
        Volumefraction_GridLayout       matlab.ui.container.GridLayout
        Volumefraction_Instance_UITable  matlab.ui.control.Table
        Volumefraction_Semantic_UITable  matlab.ui.control.Table
        Volumefractions_AllphasesCheckBox  matlab.ui.control.CheckBox
        VolumefractionInstructions_Label  matlab.ui.control.Label
        ConnectivityTab                 matlab.ui.container.Tab
        TortuosityfactorTab             matlab.ui.container.Tab
        FractaldimensionTab             matlab.ui.container.Tab
        BoxingmethodLabel               matlab.ui.control.Label
        ParticleTab                     matlab.ui.container.Tab
        TabGroup4                       matlab.ui.container.TabGroup
        DiameterTab                     matlab.ui.container.Tab
        MetricsperparticleTab           matlab.ui.container.Tab
        KineticTab                      matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        SpecificsurfaceareaTab          matlab.ui.container.Tab
        SpecificinterfaceareaTab        matlab.ui.container.Tab
        TriplephaseboundarylengthdensityTab  matlab.ui.container.Tab
        ErrorsTab                       matlab.ui.container.Tab
        TabGroup5                       matlab.ui.container.TabGroup
        ImageresolutionTab              matlab.ui.container.Tab
        Imageresolution_GridLayout      matlab.ui.container.GridLayout
        ExtrapolationchoicenonelinearquadraticcubicLabel  matlab.ui.control.Label
        TocompareafractalpropertyF0twoormorevolumesLabel  matlab.ui.control.Label
        Hyperlink3_2                    matlab.ui.control.Hyperlink
        Hyperlink4_2                    matlab.ui.control.Hyperlink
        Label_29                        matlab.ui.control.Label
        andrevisitedformicrostructureLabel_2  matlab.ui.control.Label
        TheoriginalconceptLabel_2       matlab.ui.control.Label
        Image2_2                        matlab.ui.control.Image
        Label_28                        matlab.ui.control.Label
        Voxelsize_Applytoallvolumes_Button  matlab.ui.control.Button
        Voxelsize_UITable               matlab.ui.control.Table
        Voxelsize_VolumeselectionDropDown  matlab.ui.control.DropDown
        VolumeselectionDropDownLabel_2  matlab.ui.control.Label
        SelectedmicrostructurepropertiesarecalculatedattheLabel  matlab.ui.control.Label
        RepresentativityTab             matlab.ui.container.Tab
        Representativity_GridLayout     matlab.ui.container.GridLayout
        MacroTab                        matlab.ui.container.Tab
        Macro_GridLayout                matlab.ui.container.GridLayout
        SavemacroButton                 matlab.ui.control.Button
        NotdefinedLabel                 matlab.ui.control.Label
        SavefolderButton                matlab.ui.control.Button
        MacronameEditField              matlab.ui.control.EditField
        MacronameEditFieldLabel         matlab.ui.control.Label
        YoucansaveoptionsinamacroandthenapplyittoothersvolumesLabel  matlab.ui.control.Label
        RunTab                          matlab.ui.container.Tab
        Run_GridLayout                  matlab.ui.container.GridLayout
        Run_SearchtagCheckBox           matlab.ui.control.CheckBox
        RunStatut_UITable               matlab.ui.control.Table
        Run_button                      matlab.ui.control.Button
        Run_Label                       matlab.ui.control.Label
        Run_Title                       matlab.ui.control.Label
        HelpTab                         matlab.ui.container.Tab
        GridLayout5_3                   matlab.ui.container.GridLayout
        FAQimageexampleslinktotutorialsLabel  matlab.ui.control.Label
        Hyperlink_8                     matlab.ui.control.Hyperlink
        Label_19                        matlab.ui.control.Label
        ReferencesTab                   matlab.ui.container.Tab
        GridLayout5_4                   matlab.ui.container.GridLayout
        Hyperlink_14                    matlab.ui.control.Hyperlink
        Hyperlink_13                    matlab.ui.control.Hyperlink
        Hyperlink_12                    matlab.ui.control.Hyperlink
        Hyperlink_11                    matlab.ui.control.Hyperlink
        Hyperlink_10                    matlab.ui.control.Hyperlink
        Hyperlink_9                     matlab.ui.control.Hyperlink
        Label_21                        matlab.ui.control.Label
        Label_20                        matlab.ui.control.Label
        HowtoquoteTab                   matlab.ui.container.Tab
        GridLayout5_2                   matlab.ui.container.GridLayout
        Hyperlink_7                     matlab.ui.control.Hyperlink
        Label_18                        matlab.ui.control.Label
        Label_17                        matlab.ui.control.Label
        Hyperlink_6                     matlab.ui.control.Hyperlink
        Label_16                        matlab.ui.control.Label
        Label_15                        matlab.ui.control.Label
        AboutTab                        matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        CheckonlineversionButton        matlab.ui.control.Button
        BSDLicenceLabel                 matlab.ui.control.Label
        NRELSoftwareRecordofInventionSWR2076Label  matlab.ui.control.Label
        ClicktoseethelicenceButton      matlab.ui.control.Button
        Hyperlink2                      matlab.ui.control.Hyperlink
        Label_9                         matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        Onlineversion_Label             matlab.ui.control.Label
        Localversion                    matlab.ui.control.Label
        MATBOXinstalledversionLabel     matlab.ui.control.Label
        Hyperlink_2                     matlab.ui.control.Hyperlink
        Hyperlink                       matlab.ui.control.Hyperlink
        Image                           matlab.ui.control.Image
        Label_7                         matlab.ui.control.Label
    end

    
    properties (Access = private)
        % Import
        number_volume = [];
        inputvolume = [];

        PathImport = [];
        mainfolder = [];

        FileName = [];

        current_semantic_microstructure = [];
        current_middleslice_semantic = [];
        semantic_fileinfo = [];
        phase_name_semantic_sav = [];
        color_phase_semantic = NaN(1e6,3);
        pathnames_semantic = [];

        current_instance_microstructure = [];
        current_instanceBW_microstructure = [];
        current_middleslice_instance = [];
        instance_fileinfo = [];
        phase_name_instance_sav = [];
        color_phase_instance = NaN(1e6,3);
        pathnames_instance = [];

        volumeinfo_sav = [];
        direction_name_sav = [];
        ROI = [];

        order_calculation = [];

        default_voxelsizeanalysis = [];
        voxelidx = [];
    end
    
    methods (Access = private)
                        
        function [] = check_import(app)
            if isempty(app.current_semantic_microstructure) && isempty(app.current_instance_microstructure)
                app.VisualizevolumeButton.Enable = 'off'; app.PlotvolumefractionsButton.Enable='off';
            else
                app.VisualizevolumeButton.Enable = 'on'; app.PlotvolumefractionsButton.Enable='on';
            end
            if (strcmp(app.SegmentationtypeDropDown.Value,'semantic') && ~isempty(app.current_semantic_microstructure)) || ...
               (strcmp(app.SegmentationtypeDropDown.Value,'instance') && ~isempty(app.current_instance_microstructure)) || ...
               (strcmp(app.SegmentationtypeDropDown.Value,'semantic and instance') && ~isempty(app.current_semantic_microstructure) && ~isempty(app.current_instance_microstructure)) ...
               && ~strcmp(app.FilesmustsharesamesizeLabel.Text,'Incompatible dimension!') && ~strcmp(app.FilesmustsharesamesizeLabel.Text,'Incompatible size!')
                app.Import_SavesettingsButton.Enable = 'on';
            else
                app.Import_SavesettingsButton.Enable = 'off';
            end
        end
        
        function [] = check_dimensions(app)
            if strcmp(app.SegmentationtypeDropDown.Value,'semantic and instance')
                if ~isempty(app.current_semantic_microstructure) && ~isempty(app.current_instance_microstructure)
                    sz_sem = size(app.current_semantic_microstructure);
                    sz_ins = size(app.current_instance_microstructure);
                    dim_sem = length(sz_sem);
                    dim_ins = length(sz_ins);
                    if dim_sem == dim_ins
                        if sum(sz_sem==sz_ins)==dim_sem
                            app.FilesmustsharesamesizeLabel.Text = 'Files must share same size';
                            app.FilesmustsharesamesizeLabel.FontColor = 'k';
                            app.Volumenumber_Label.Text = ['Volume number ' num2str(app.number_volume,'%i')];
                            app.Volumenumber_Label.Visible = 'on';
                        else
                            app.Volumenumber_Label.Visible = 'off';
                            app.FilesmustsharesamesizeLabel.Text = 'Incompatible size!';
                            app.FilesmustsharesamesizeLabel.FontColor = 'r';
                        end
                    else
                        app.Volumenumber_Label.Visible = 'off';
                        app.FilesmustsharesamesizeLabel.Text = 'Incompatible dimension!';
                        app.FilesmustsharesamesizeLabel.FontColor = 'r';
                    end
                else
                    app.Volumenumber_Label.Visible = 'off';
                    app.FilesmustsharesamesizeLabel.Text = 'Files must share same size';
                    app.FilesmustsharesamesizeLabel.FontColor = 'k';
                end
            else
                app.Volumenumber_Label.Visible = 'on';
                app.Volumenumber_Label.Text = ['Volume number ' num2str(app.number_volume,'%i')];
            end
        end        
        
        function [] = fill_ROI_UITable(app,M)
            number_direction = length(size(M));
            direction_number = cell(number_direction,1);
            direction_voxel = size(M)';
            ROI_start = ones(number_direction,1);
            if strcmp(app.ModeDropDown.Value,'Set parameters for each volume independently')
                ROI_end = size(M)';
            else
                ROI_end = ROI_start+1e9;
            end
            ROI_length = ROI_end-ROI_start+1;
            direction_name = cell(number_direction,1);
            for k=1:1:number_direction
                direction_number(k,1)={num2str(k)};
                if ~isempty(app.direction_name_sav) && length(app.direction_name_sav)>=k
                    direction_name(k,1) = app.direction_name_sav(k,1); % Avoid re-writting each time if volumes are same materials
                else
                    direction_name(k,1) = {['Direction ' num2str(k)]};
                end
            end
            app.ROI_UITable.Data = [direction_number num2cell(direction_voxel) num2cell(ROI_start) num2cell(ROI_end) num2cell(ROI_length) direction_name];
        end

        function [] = update_volumefractions(app)
            if ~isempty(app.PhasecolornameSemantic_UITable.Data) || ~isempty(app.PhasecolornameInstance_UITable.Data)

                if strcmp(app.ModeDropDown.Value,'Set parameters for each volume independently')
                    ROI = cell2mat(app.ROI_UITable.Data(:,3:4));
                else
                    tmp = app.semantic_fileinfo(1).sz;
                    dimension = length(tmp);
                    ROI = ones(dimension,2);
                    ROI(:,2) = tmp';
                end

                if strcmp(app.Phasenamecolor_DropDown.Value,'semantic segmentation')
                    phase_label = cell2mat(app.PhasecolornameSemantic_UITable.Data(:,5));
                elseif strcmp(app.Phasenamecolor_DropDown.Value,'instance segmentation')
                    phase_label = cell2mat(app.PhasecolornameInstance_UITable.Data(:,5));
                end
                [dimension,~] = size(ROI);
                M = [];
                if ~isempty(app.current_semantic_microstructure) && strcmp(app.Phasenamecolor_DropDown.Value,'semantic segmentation')
                    M = app.current_semantic_microstructure;
                elseif ~isempty(app.current_instanceBW_microstructure) && strcmp(app.Phasenamecolor_DropDown.Value,'instance segmentation')
                    M = app.current_instanceBW_microstructure;
                end

                if dimension == 2
                    M = M(ROI(1,1):ROI(1,2), ROI(2,1):ROI(2,2));
                else
                    M = M(ROI(1,1):ROI(1,2), ROI(2,1):ROI(2,2), ROI(3,1):ROI(3,2));
                end

                p.scaling_factor = app.ScalingfactorEditField.Value;
                if p.scaling_factor ~=1
                    p.label_or_greylevel = 'Label';
                    p.background = 0;
                    [M] = function_scaling(M,p);
                end

                for k=1:1:length(phase_label)
                    if strcmp(app.Phasenamecolor_DropDown.Value,'semantic segmentation')
                        app.PhasecolornameSemantic_UITable.Data(k,2) ={sum(sum(sum( M==phase_label(k) )))/numel(M)};
                    elseif strcmp(app.Phasenamecolor_DropDown.Value,'instance segmentation')
                        app.PhasecolornameInstance_UITable.Data(k,2) ={sum(sum(sum( M==phase_label(k) )))/numel(M)};
                    end
                end

            end
        end
        
        function M = Prepare_visualization(app,M)
            % ROI
            if strcmp(app.visualized_ROI_withDropDown.Value,'region of interest')
                ROI = cell2mat(app.ROI_UITable.Data(:,3:4));
                dimension = length(size(M));
                if strcmp(app.ModeDropDown.Value,'Set parameters for each volume independently')
                    if dimension == 2
                        M = M(ROI(1,1):ROI(1,2), ROI(2,1):ROI(2,2));
                    else
                        M = M(ROI(1,1):ROI(1,2), ROI(2,1):ROI(2,2), ROI(3,1):ROI(3,2));
                    end
                end
            end
            if strcmp(app.visualize_resolution_withDropDown.Value,'after scaling')
                p.scaling_factor = app.ScalingfactorEditField.Value;
                p.label_or_greylevel = 'Label';
                p.background = 0;
                [M] = function_scaling(M,p);
            end
        end
        

        function [] = function_vf_alongthickness(app,M,voxel_size,figtitle)
            sz = size(M);
            dimension = length(sz);
            if dimension==2 % Case 2D
                sz=[sz(1) sz(2) 1];
            end
            if strcmp(app.Phasenamecolor_DropDown.Value,'semantic segmentation')
                phase_label = cell2mat(app.PhasecolornameSemantic_UITable.Data(:,5));
                phase_color =  app.color_phase_semantic;
                phase_name = app.PhasecolornameSemantic_UITable.Data(:,4);
            elseif strcmp(app.Phasenamecolor_DropDown.Value,'instance segmentation')
                phase_label = cell2mat(app.PhasecolornameInstance_UITable.Data(:,5));
                phase_color =  app.color_phase_instance;
                phase_name = app.PhasecolornameInstance_UITable.Data(:,4);
            end
            number_phase = length(phase_label);
            direction_name = app.ROI_UITable.Data(:,6); 
            strunit = app.VoxelsizeUnit_EditField.Value;
            if strcmp(strunit,'um') || strcmp(strunit,'micrometer') || strcmp(strunit,'Micrometer') || strcmp(strunit,'micrometers') || strcmp(strunit,'Micrometers')
                axisunit = '(\mum)';
            else
                axisunit = ['(' strunit ')'];
            end

            % Initialize
            for current_direction=1:1:dimension % Loop over all directions
                res(current_direction).volumefraction = zeros(sz(current_direction),number_phase+1); % Initialization
                res(current_direction).volumefraction(:,1) = (1:1:sz(current_direction))*voxel_size; % Axis (position along direction)
            end
            for current_phase = 1:1:number_phase % Loop over all phases
                % Direction 1
                for current_position=1:1:sz(1) % Loop over postion
                    Numbervoxel= sum(sum( M(current_position,:,:)==phase_label(current_phase) ));
                    res(1).volumefraction(current_position,current_phase+1) = Numbervoxel / (sz(2)*sz(3));
                end
                % Direction 2
                for current_position=1:1:sz(2) % Loop over postion
                    Numbervoxel= sum(sum(M(:,current_position,:)==phase_label(current_phase)));
                    res(2).volumefraction(current_position,current_phase+1) = Numbervoxel / (sz(1)*sz(3));
                end
                if dimension==3 % 3D case
                    % Direction 3
                    for current_position=1:1:sz(3) % Loop over postion
                        Numbervoxel= sum(sum(M(:,:,current_position)==phase_label(current_phase)));
                        res(3).volumefraction(current_position,current_phase+1) = Numbervoxel / (sz(1)*sz(2));
                    end
                end
            end

            scrsz = get(0,'ScreenSize'); % Screen resolution
            Fig = figure; % Create figure
            Fig.Name= 'Volume fractions'; % Figure name
            Fig.Color='white'; % Background colour
            set(Fig,'position',[scrsz(1) scrsz(2) scrsz(3)*dimension/3 scrsz(4)*1/2]); % Full screen figure
            for current_direction=1:1:dimension % Iterate over axe
                sub_axes=subplot(1,dimension,current_direction,'Parent',Fig);
                hold(sub_axes,'on'); % Active subplot
                % Plot graphs
                for current_phase=1:1:number_phase % Loop over phases
                    x_ = res(current_direction).volumefraction(:,1);
                    y_ = res(current_direction).volumefraction(:,current_phase+1);
                    plot(x_,y_,'Color', phase_color(current_phase,:),'LineWidth',2,'DisplayName',char(phase_name(current_phase)));
                end
                % Axis label
                t_ = xlabel(' ');
                t_1 = sprintf('Position along %s ',char(direction_name(current_direction)));
                t_2 = axisunit;
                t_.String= [t_1 t_2]; % Sprintf does not accept greek characters
                ylabel('Volume fractions');
                % Legend
                h_legend = legend(sub_axes,'Location','best');
                % - Grid
                grid(sub_axes,'on'); % Display grid
                set(sub_axes,'XMinorGrid','on','YMinorGrid','on'); % Display grid for minor thicks
                set(sub_axes,'FontName','Times new roman','FontSize',14); % Fontname and fontsize
                h_legend.FontSize = 14; % Set title fontsize
                hold(sub_axes,'off'); % Relase figure
            end
            figtitle = strrep(figtitle,'_',' ');
            sgtitle(Fig,figtitle,'FontWeight','bold','FontSize',16,'FontName','Times new roman');
        end

        
        function [] = Initialize_Phasecolorname_UITable(app)
            if strcmp(app.Phasenamecolor_DropDown.Value,'semantic segmentation')
                app.PhasecolornameSemantic_UITable.Visible = 'on';
                app.PhasecolornameSemantic_UITable.Enable = 'on';
                app.PhasecolornameInstance_UITable.Visible = 'off';
                app.PhasecolornameInstance_UITable.Enable = 'off';           
                if ~isempty(app.current_semantic_microstructure)
                    col_ = [colororder; rand(1e6,3)];
                    phase_label = unique(app.current_semantic_microstructure);
                    number_phase = length(phase_label);
                    phase_number = cell(number_phase,1);
                    volume_fractions = zeros(number_phase,2);
                    phase_name = cell(number_phase,1);
                                        
                    for k=1:1:number_phase
                        phase_number(k,1)={num2str(k)};
                        volume_fractions(k,1)=sum(sum(sum( app.current_semantic_microstructure==phase_label(k) )))/numel(app.current_semantic_microstructure);
                        if isnan(app.color_phase_semantic(k,1))
                            app.color_phase_semantic(k,:) = col_(k,:);
                        end
                        
                        if ~isempty(app.phase_name_semantic_sav) && length(app.phase_name_semantic_sav)>=k
                            phase_name(k,1) = app.phase_name_semantic_sav(k,1); % Avoid re-writting each time if volumes are same materials
                        else
                            if strcmp(app.SegmentationtypeDropDown.Value,'semantic')
                                phase_name(k,1) = {['Label ' num2str(phase_label(k))]};
                            else
                                if k==1
                                    phase_name(k,1) = {'Pore'};
                                elseif k==2
                                    phase_name(k,1) = {'Cracks'};
                                elseif k==3
                                    phase_name(k,1) = {'Particles'};
                                else
                                    phase_name(k,1) = {['Label ' num2str(phase_label(k))]};
                                end
                            end
                        end                        
                        
                        s = uistyle('BackgroundColor',app.color_phase_semantic(k,:));
                        addStyle(app.PhasecolornameSemantic_UITable,s,'cell',[k,3]);
                    end
                    app.PhasecolornameSemantic_UITable.Data = [phase_number num2cell(volume_fractions(:,1)) cell(number_phase,1) phase_name num2cell(phase_label) num2cell(phase_label)];
                else
                    app.PhasecolornameSemantic_UITable.Data = [];
                end

            elseif strcmp(app.Phasenamecolor_DropDown.Value,'instance segmentation')
                app.PhasecolornameSemantic_UITable.Visible = 'off';
                app.PhasecolornameSemantic_UITable.Enable = 'off';
                app.PhasecolornameInstance_UITable.Visible = 'on';
                app.PhasecolornameInstance_UITable.Enable = 'on';
                if ~isempty(app.current_instanceBW_microstructure)
                    col_ = colororder;
                    phase_label = unique(app.current_instanceBW_microstructure); % always 0 and 1
                    number_phase = length(phase_label);
                    phase_number = cell(number_phase,1);
                    volume_fractions = zeros(number_phase,2);
                    phase_name = cell(number_phase,1);

                    for k=1:1:number_phase
                        phase_number(k,1)={num2str(k)};
                        volume_fractions(k,1)=sum(sum(sum( app.current_instanceBW_microstructure==phase_label(k) )))/numel(app.current_instanceBW_microstructure);
                        if isnan(app.color_phase_instance(k,1))
                            app.color_phase_instance(k,:) = col_(k,:);
                        end

                        if ~isempty(app.phase_name_instance_sav) && length(app.phase_name_instance_sav)>=k
                            phase_name(k,1) = app.phase_name_instance_sav(k,1); % Avoid re-writting each time if volumes are same materials
                        else
                            if k==1
                                phase_name(k,1) = {'Complementary volume'};
                            else
                                phase_name(k,1) = {'Instances union'};
                            end
                        end

                        s = uistyle('BackgroundColor',app.color_phase_instance(k,:));
                        addStyle(app.PhasecolornameInstance_UITable,s,'cell',[k,3]);
                    end

                    app.PhasecolornameInstance_UITable.Data = [phase_number num2cell(volume_fractions(:,1)) cell(number_phase,1) phase_name num2cell(phase_label) num2cell(phase_label)];
                else
                    app.PhasecolornameInstance_UITable.Data = [];
                end
            end
        end
        
        function [] = GreyOut(app,statut)
            % Import
            children = get(app.GridLayout_imported,'Children');
            set(children,'Enable',statut);

            % Properties
            children = get(app.Volumefraction_GridLayout,'Children');
            set(children,'Enable',statut);   

            % Error
            children = get(app.Imageresolution_GridLayout,'Children');
            set(children,'Enable',statut);     
            children = get(app.Representativity_GridLayout,'Children');
            set(children,'Enable',statut);              

            % Macro
            children = get(app.Macro_GridLayout,'Children');
            set(children,'Enable',statut); 

            % Run
            children = get(app.Run_GridLayout,'Children');
            set(children,'Enable',statut);  
        end
        
        function t = func_selectall(app,t,val)
            [rn,cn]=size(t);
            for r=1:1:rn
                for c=3:2:cn
                    t(r,c) = {logical(val)};
                end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Format tab
            app.FontnameDropDown.Items = listfonts;
            app.FontnameDropDown.Value = 'Times New Roman'; % Default

            % Initialize import tab
            app.ModeDropDownValueChanged;
            app.PhasecolornameInstance_UITable.Visible = 'off';
            app.PhasecolornameInstance_UITable.Enable = 'off'; 
            app.PhasecolornameInstance_UITable.ColumnName = app.PhasecolornameSemantic_UITable.ColumnName;
            app.PhasecolornameInstance_UITable.ColumnWidth = app.PhasecolornameSemantic_UITable.ColumnWidth;
            app.PhasecolornameInstance_UITable.ColumnEditable = app.PhasecolornameSemantic_UITable.ColumnEditable;   

            % Voxel size index
            app.voxelidx.volumefraction = 1;
        end

        % Value changed function: ModeDropDown
        function ModeDropDownValueChanged(app, event)
            value = app.ModeDropDown.Value;
            if strcmp(value,'Set parameters for each volume independently')
                app.ClicktoimportmacroButton.Enable = 'off';
                app.NomacroloadedLabel.Enable = 'off';
                app.NomacroloadedLabel.Text = 'No macro loaded';
                app.ModeDropDown.Tooltip = 'Import file one by one, then set parameters for each volume in the other tabs.';
                app.VolumefolderEditField.Enable = 'on';
                app.ROI_UITable.ColumnEditable = [false,false,true,true,false,true];
            elseif strcmp(value,'Set parameters only once and apply to all volumes') 
                app.ClicktoimportmacroButton.Enable = 'off';
                app.NomacroloadedLabel.Enable = 'off';
                app.NomacroloadedLabel.Text = 'No macro loaded';
                app.ModeDropDown.Tooltip = 'Select all the files to analyse, then set parameter for only the first volume in the other tabs. The first volume should have the larger number of phases among all the volumes.';
                app.VolumefolderEditField.Enable = 'off';
                app.ROI_UITable.ColumnEditable = [false,false,false,false,false,true];
            elseif strcmp(value,'Apply macro''s parameters to all volumes') 
                app.ClicktoimportmacroButton.Enable = 'on';
                app.NomacroloadedLabel.Enable = 'on';
                app.NomacroloadedLabel.Text = 'No macro loaded';
                app.ModeDropDown.Tooltip = 'Import a macro, select all the files to analyse, then direcly go to the run tab without looking at the other tabs.';       
                app.VolumefolderEditField.Enable = 'off';
                app.ROI_UITable.ColumnEditable = [false,false,false,false,false,true];
            end
            app.SegmentationtypeDropDownValueChanged;
        end

        % Button pushed function: ClicktoimportmacroButton
        function ClicktoimportmacroButtonPushed(app, event)
            if isempty(app.PathImport)
                [macro_fileName,tmp_PathImport,~] = uigetfile({'*.mat','mat file (*.mat)'},'Select a macro .mat file previosuly saved with this module',pwd);
            else
                [macro_fileName,tmp_PathImport,~] = uigetfile({'*.tif;*.tiff','Tif image (*.tif, *.tiff)'},'Select a macro .mat file previosuly saved with this module',app.PathImport);
            end
            if ~macro_fileName==0
                app.NomacroloadedLabel.Text = [tmp_PathImport macro_fileName];
                app.NomacroloadedLabel.Tooltip = app.NomacroloadedLabel.Text;
                app.PathImport = tmp_PathImport;
            end
        end

        % Value changed function: SegmentationtypeDropDown
        function SegmentationtypeDropDownValueChanged(app, event)
            % Disable UI for all elements except for the Import tab
            app.GreyOut('off');        
            
            % Reset parameters
            app.number_volume = 1;
            app.inputvolume = [];
            app.FileName = [];

            app.current_semantic_microstructure = [];
            app.current_middleslice_semantic = [];
            app.semantic_fileinfo = [];
            app.pathnames_semantic = [];

            app.current_instance_microstructure = [];
            app.current_instanceBW_microstructure = [];
            app.current_middleslice_instance = [];
            app.instance_fileinfo = [];
            app.pathnames_instance = [];

            app.ROI = [];


            % Reset Import UI
            app.Semantic_NofileimportedLabel.Text = 'No file imported';
            app.Instance_NofileimportedLabel.Text = 'No file imported';
            app.Volumenumber_Label.Visible = 'off';
            app.Volumenumber_Label.Text = ['Volume number: ' num2str(app.number_volume,'%i')]; 

            app.VoxelsizeEditField.Enable = 'off'; app.VoxelsizeUnit_EditField.Enable = 'off'; app.ScalingfactorEditField.Enable = 'off';
            app.Scaledvoxelsize_Label.Text = 'Scaled voxel size: n/a';
            app.ROI_Label.Text = 'ROI: n/a';
            app.VolumefolderEditField.Value = 'Not defined';
            app.Mainfolder_Label.Text = 'Not defined';
            app.Import_indication.Text = [' '];   

            app.Import_SavesettingsButton.Enable = 'off';
            app.VisualizevolumeButton.Enable = 'off';app.PlotvolumefractionsButton.Enable = 'off';            

            % Tables
            app.Info_UITable.Data = [{'Material source';'Material name';'Volume description';'Raw data source';'Raw data technique';'Segmentation source';'Segmentation technique';'Analysis decription'} {'Not defined';'Not defined';'Not defined';'Not defined';'Not defined';'Not defined';'Not defined';'Not defined'}];
            app.ROI_UITable.Data = [];
            app.PhasecolornameSemantic_UITable.Data = [];
            app.PhasecolornameInstance_UITable.Data = [];
            app.Imported_UITable.Data = [];
            
            
            value = app.SegmentationtypeDropDown.Value;
            if strcmp(value,'semantic')
                app.ClicktoimportsemanticsegmentationButton.Enable = 'on'; app.Label_semanticdescription.Enable = 'on';
                app.ClicktoimportinstancesegmentationButton.Enable = 'off';app.Label_instancedescription.Enable = 'off';
                app.ClicktoimportsemanticsegmentationButton.Tooltip = 'Label requirement: at least two different labels';
                app.ClicktoimportinstancesegmentationButton.Tooltip = 'Label requirement: n/a';
                app.Semantic_NofileimportedLabel.Enable = 'on';
                app.Instance_NofileimportedLabel.Enable = 'off';                
                app.FilesmustsharesamesizeLabel.Visible = 'off';
                app.Phasenamecolor_DropDown.Items = {'semantic segmentation'};
                
            elseif strcmp(value,'instance')
                app.ClicktoimportsemanticsegmentationButton.Enable = 'off'; app.Label_semanticdescription.Enable = 'off';
                app.ClicktoimportinstancesegmentationButton.Enable = 'on'; app.Label_instancedescription.Enable = 'on';
                app.ClicktoimportsemanticsegmentationButton.Tooltip = 'Label requirement: n/a';
                app.ClicktoimportinstancesegmentationButton.Tooltip = 'Label requirement: at least two different labels (but preferably a lot). In addition to metrics calculated per instance, other metrics can be calculated on phase 1 = label 0, and phase 2 = union of all other labels';
                app.Semantic_NofileimportedLabel.Enable = 'off';
                app.Instance_NofileimportedLabel.Enable = 'on';   
                app.FilesmustsharesamesizeLabel.Visible = 'off';
                app.Phasenamecolor_DropDown.Items = {'instance segmentation'};
                
            elseif strcmp(value,'semantic and instance')
                app.ClicktoimportsemanticsegmentationButton.Enable = 'on'; app.Label_semanticdescription.Enable = 'on';
                app.ClicktoimportinstancesegmentationButton.Enable = 'on'; app.Label_instancedescription.Enable = 'on';      
                app.ClicktoimportsemanticsegmentationButton.Tooltip = 'Label requirements: label 0  must be pore, label 1 must be cracks within particle shells, label 2 must be particles, and subsequent labels can be any other domains (e.g., additives)';
                app.ClicktoimportinstancesegmentationButton.Tooltip = 'Label requirements: label i (i>0) = instance i of union of cracks and particle from semantic segementation (i.e. particle shell instance).';
                app.Semantic_NofileimportedLabel.Enable = 'on';
                app.Instance_NofileimportedLabel.Enable = 'on'; 
                if strcmp(app.ModeDropDown.Value,'Set parameters for each volume independently')
                    app.FilesmustsharesamesizeLabel.Text = 'Files must share same size';
                else
                    app.FilesmustsharesamesizeLabel.Text = 'Each pair of files must share same size. Each pair of file must share the same substring before the last "_" of their name (e.g. if file_1.tif is the semantic file for the first pair of file, the instance filename is either file_1.tif (located in another folder) or file_1_<whatever string without underscore>.tif).';
                end
                app.FilesmustsharesamesizeLabel.FontColor = 'k';
                app.FilesmustsharesamesizeLabel.Visible = 'on';
                app.Phasenamecolor_DropDown.Items = {'semantic segmentation' 'instance segmentation'};
                app.FilesmustsharesamesizeLabel.Tooltip = app.FilesmustsharesamesizeLabel.Text;
            end         
        end

        % Button pushed function: ClicktoimportsemanticsegmentationButton
        function ClicktoimportsemanticsegmentationButtonPushed(app, event)
            outcome = false;
            % Reset
            app.current_semantic_microstructure=[];
            app.current_middleslice_semantic = [];
            app.semantic_fileinfo = [];
            app.FileName = [];

            % Open dialog box to choose file path
            if strcmp(app.ModeDropDown.Value,'Set parameters for each volume independently') % Load one file
                if isempty(app.PathImport)
                    [app.FileName,tmp_PathImport,~] = uigetfile({'*.tif;*.tiff','Tif image (*.tif, *.tiff)'},'Select a .tif file (semantic segmentation)',pwd);
                else
                    [app.FileName,tmp_PathImport,~] = uigetfile({'*.tif;*.tiff','Tif image (*.tif, *.tiff)'},'Select a .tif file (semantic segmentation)',app.PathImport);
                end
                if ~app.FileName==0
                    app.Semantic_NofileimportedLabel.Text = 'Loading file... please wait'; pause(0.1);
                    [tmp,outcome] = function_load_tif([tmp_PathImport app.FileName]);
                    if outcome
                        sz = size(tmp);
                        app.semantic_fileinfo.sz = sz;
                        if length(sz)==3
                            app.current_middleslice_semantic = tmp(:,:,round(sz(3)/2));
                        else
                            app.current_middleslice_semantic = tmp;
                        end
                        app.semantic_fileinfo.nphase = length(unique(tmp));
                    end
                end

            else % Select multiple files
                % Douglas Schwarz (2022). uipickfiles: uigetfile on steroids (https://www.mathworks.com/matlabcentral/fileexchange/10867-uipickfiles-uigetfile-on-steroids), MATLAB Central File Exchange. Retrieved September 29, 2022.
                % Lisence in 'Third-party licenses' folder
                str_dialogbox = 'Select all .tif files (semantic segmentation). You will be able to set parameters only on the first file.';
                if isempty(app.PathImport)
                    app.pathnames_semantic = uipickfiles('FilterSpec',pwd,'REFilter','\.tif$|\.tiff$','Prompt',str_dialogbox);
                else
                    app.pathnames_semantic = uipickfiles('FilterSpec',app.PathImport,'REFilter','\.tif$|\.tiff$','Prompt',str_dialogbox);
                end
                if iscell(app.pathnames_semantic)
                    app.Semantic_NofileimportedLabel.Text = 'Checking files size...'; pause(0.01);
                    for k = length(app.pathnames_semantic):-1:1
                        [sz,outcome] = function_checksize_tif(char(app.pathnames_semantic(k)));
                        app.semantic_fileinfo(k).sz = sz;
                        if ~outcome
                            app.Semantic_NofileimportedLabel.Text = ['File ' char(app.pathnames_semantic(k)) 'can not be read'];
                            break
                        end
                    end
                    app.Semantic_NofileimportedLabel.Text = 'Loading only the first file... please wait'; pause(0.1);
                    [tmp,outcome] = function_load_tif(char(app.pathnames_semantic(1)));
                    [tmp_PathImport,app.FileName,~] = fileparts( char(app.pathnames_semantic(1)) );   
                    app.semantic_fileinfo(1).nphase = length(unique(tmp));
                    if ispc
                        tmp_PathImport = [tmp_PathImport '\'];
                    else
                        tmp_PathImport = [tmp_PathImport '/'];
                    end
                end
            end

            if outcome % Success to import
                app.PathImport = tmp_PathImport;
                app.current_semantic_microstructure = tmp;
                app.Semantic_NofileimportedLabel.Text = [app.PathImport app.FileName];
                app.check_dimensions;

                % Fill direction table
                app.fill_ROI_UITable(app.current_semantic_microstructure);

                % Voxel size info
                app.VoxelsizeEditField.Enable = 'on'; app.VoxelsizeUnit_EditField.Enable = 'on'; app.ScalingfactorEditField.Enable = 'on';
                app.VoxelsizeEditFieldValueChanged;

                % Fill volume fraction table
                app.Phasenamecolor_DropDown.Value = 'semantic segmentation';
                app.Initialize_Phasecolorname_UITable;

                % Volume info table
                if ~isempty(app.volumeinfo_sav)
                    app.Info_UITable.Data = app.volumeinfo_sav;
                else
                    app.Info_UITable.Data = [{'Material source';'Material name';'Volume description';'Raw data source';'Raw data technique';'Segmentation source';'Segmentation technique';'Analysis decription'} {'Not defined';'Not defined';'Not defined';'Not defined';'Not defined';'Not defined';'Not defined';'Not defined'}];
                end

                % Saving folder
                if ~isempty(app.mainfolder)
                    app.Mainfolder_Label.Text = app.mainfolder;
                else
                    app.Mainfolder_Label.Text = app.PathImport;
                end
                app.Mainfolder_Label.Tooltip = app.Mainfolder_Label.Text;
                app.mainfolder = app.Mainfolder_Label.Text;
            end
            if ~outcome
                % User clicked cancel button or closed the dialog box or could not import file
                app.current_semantic_microstructure=[];
                app.Semantic_NofileimportedLabel.Text ='No file imported.';
                app.Volumenumber_Label.Visible = 'off';
                if isempty(app.current_instance_microstructure)
                    app.VoxelsizeEditField.Enable = 'off'; app.VoxelsizeUnit_EditField.Enable = 'off'; app.ScalingfactorEditField.Enable = 'off';
                    app.Scaledvoxelsize_Label.Text = 'Scaled voxel size: n/a';
                    app.ROI_Label.Text = 'ROI: n/a';                              
                    app.ROI_UITable.Data = [];
                end
                app.PhasecolornameSemantic_UITable.Data = [];
                app.Mainfolder_Label.Text = 'Not defined';
                app.VolumefolderEditField.Value = 'Not defined';
                app.Import_SavesettingsButton.Enable = 'off';
                app.Info_UITable.Data = [{'Material source';'Material name';'Volume description';'Raw data source';'Raw data technique';'Segmentation source';'Segmentation technique';'Analysis decription'} {'Not defined';'Not defined';'Not defined';'Not defined';'Not defined';'Not defined';'Not defined';'Not defined'}];
            end
            app.check_import;
            app.Semantic_NofileimportedLabel.Tooltip = app.Semantic_NofileimportedLabel.Text;
        end

        % Button pushed function: ClicktoimportinstancesegmentationButton
        function ClicktoimportinstancesegmentationButtonPushed(app, event)
            outcome = false;
            % Reset
            app.current_instance_microstructure=[];
            app.current_instanceBW_microstructure=[];
            app.current_middleslice_instance = [];
            app.instance_fileinfo = [];
            app.FileName = [];

            % Open dialog box to choose file path
            if strcmp(app.ModeDropDown.Value,'Set parameters for each volume independently') % Load one file
                if isempty(app.PathImport)
                    [app.FileName,tmp_PathImport,~] = uigetfile({'*.tif;*.tiff','Tif image (*.tif, *.tiff)'},'Select a .tif file (instance segmentation)',pwd);
                else
                    [app.FileName,tmp_PathImport,~] = uigetfile({'*.tif;*.tiff','Tif image (*.tif, *.tiff)'},'Select a .tif file (instance segmentation)',app.PathImport);
                end
                if ~app.FileName==0
                    app.Instance_NofileimportedLabel.Text = 'Loading file... please wait'; pause(0.1);
                    [tmp,outcome] = function_load_tif([tmp_PathImport app.FileName]);
                    if outcome
                        sz = size(tmp);
                        app.instance_fileinfo.sz = sz;
                        if length(sz)==3
                            app.current_middleslice_instance = tmp(:,:,round(sz(3)/2));
                        else
                            app.current_middleslice_instance = tmp;
                        end
                        app.instance_fileinfo.ninstance = length(unique(tmp));
                    end
                end

            else % Select multiple files
                % Douglas Schwarz (2022). uipickfiles: uigetfile on steroids (https://www.mathworks.com/matlabcentral/fileexchange/10867-uipickfiles-uigetfile-on-steroids), MATLAB Central File Exchange. Retrieved September 29, 2022.
                % Lisence in 'Third-party licenses' folder
                str_dialogbox = 'Select all .tif files (instance segmentation). You will be able to set parameters only on the first file.';
                if isempty(app.PathImport)
                    app.pathnames_instance = uipickfiles('FilterSpec',pwd,'REFilter','\.tif$|\.tiff$','Prompt',str_dialogbox);
                else
                    app.pathnames_instance = uipickfiles('FilterSpec',app.PathImport,'REFilter','\.tif$|\.tiff$','Prompt',str_dialogbox);
                end
                if iscell(app.pathnames_instance)
                    app.Instance_NofileimportedLabel.Text = 'Checking files size...'; pause(0.01);
                    for k = length(app.pathnames_instance):-1:1
                        [sz,outcome] = function_checksize_tif(char(app.pathnames_instance(k)));
                        app.instance_fileinfo(k).sz = sz;
                        if ~outcome
                            app.Instance_NofileimportedLabel.Text = ['File ' char(app.pathnames_instance(k)) 'can not be read'];
                            break
                        end
                    end
                    app.Instance_NofileimportedLabel.Text = 'Loading only the first file... please wait'; pause(0.1);
                    [tmp,outcome] = function_load_tif(char(app.pathnames_instance(1)));
                    [tmp_PathImport,app.FileName,~] = fileparts( char(app.pathnames_instance(1)) );   
                    app.instance_fileinfo(1).ninstance = length(unique(tmp));
                    if ispc
                        tmp_PathImport = [tmp_PathImport '\'];
                    else
                        tmp_PathImport = [tmp_PathImport '/'];
                    end
                end
            end

            if outcome
                app.PathImport = tmp_PathImport;
                app.current_instance_microstructure = tmp;
                app.current_instanceBW_microstructure = zeros(size(app.current_instance_microstructure));
                app.current_instanceBW_microstructure(app.current_instance_microstructure~=0)=1;
                app.Instance_NofileimportedLabel.Text = [app.PathImport app.FileName];
                app.check_dimensions;

                % Fill direction table
                app.fill_ROI_UITable(app.current_instance_microstructure);

                % Voxel size info
                app.VoxelsizeEditField.Enable = 'on'; app.VoxelsizeUnit_EditField.Enable = 'on'; app.ScalingfactorEditField.Enable = 'on';
                app.VoxelsizeEditFieldValueChanged;

                % Fill volume fraction table
                app.Phasenamecolor_DropDown.Value = 'instance segmentation';
                app.Initialize_Phasecolorname_UITable;

                % Volume info table
                if ~isempty(app.volumeinfo_sav)
                    app.Info_UITable.Data = app.volumeinfo_sav;
                else
                    app.Info_UITable.Data = [{'Material source';'Material name';'Volume description';'Raw data source';'Raw data technique';'Segmentation source';'Segmentation technique';'Analysis decription'} {'Not defined';'Not defined';'Not defined';'Not defined';'Not defined';'Not defined';'Not defined';'Not defined'}];
                end

                % Saving folder
                if ~isempty(app.mainfolder)
                    app.Mainfolder_Label.Text = app.mainfolder;
                else
                    app.Mainfolder_Label.Text = app.PathImport;
                end
                app.Mainfolder_Label.Tooltip = app.Mainfolder_Label.Text;
                app.mainfolder = app.Mainfolder_Label.Text;
            end
            if ~outcome
                % User clicked cancel button or closed the dialog box or could not import file
                app.Instance_NofileimportedLabel.Text ='No file imported.';
                app.current_instance_microstructure=[];
                app.current_instanceBW_microstructure=[];
                app.Volumenumber_Label.Visible = 'off';
                if isempty(app.current_semantic_microstructure)
                    app.VoxelsizeEditField.Enable = 'off'; app.VoxelsizeUnit_EditField.Enable = 'off'; app.ScalingfactorEditField.Enable = 'off';
                    app.Scaledvoxelsize_Label.Text = 'Scaled voxel size: n/a';
                    app.ROI_Label.Text = 'ROI: n/a';                              
                    app.ROI_UITable.Data = [];
                end
                app.PhasecolornameInstance_UITable.Data = [];
                app.Mainfolder_Label.Text = 'Not defined';
                app.VolumefolderEditField.Value = 'Not defined';
                app.Import_SavesettingsButton.Enable = 'off';
                app.Info_UITable.Data = [{'Material source';'Material name';'Volume description';'Raw data source';'Raw data technique';'Segmentation source';'Segmentation technique';'Analysis decription'} {'Not defined';'Not defined';'Not defined';'Not defined';'Not defined';'Not defined';'Not defined';'Not defined'}];
            end

            app.check_import;
            app.Instance_NofileimportedLabel.Tooltip = app.Instance_NofileimportedLabel.Text;            
        end

        % Value changed function: ScalingfactorEditField, 
        % ...and 2 other components
        function VoxelsizeEditFieldValueChanged(app, event)
            voxel_size = app.VoxelsizeEditField.Value;
            scaling_factor = app.ScalingfactorEditField.Value;
            voxel_sizeunit = app.VoxelsizeUnit_EditField.Value;
            if strcmp(app.ModeDropDown.Value,'Set parameters for each volume independently')
                ROI_length = cell2mat(app.ROI_UITable.Data(:,5));
            else
                if strcmp(app.SegmentationtypeDropDown.Value,'semantic')
                    ROI_length = app.semantic_fileinfo(1).sz;
                else
                    ROI_length = app.instance_fileinfo(1).sz;
                end
            end
            dimension = length(ROI_length);
            nvoxels = floor(ROI_length./scaling_factor);
            new_voxel_size = voxel_size*scaling_factor;
            app.Scaledvoxelsize_Label.Text = ['Scaled voxel size: ' num2str(new_voxel_size,'%1.3f') voxel_sizeunit];
            length_root = (prod(nvoxels*new_voxel_size)).^(1/dimension);
            if dimension == 2
                str_ROI = ['ROI: ' num2str(nvoxels(1),'%i') ' x ' num2str(nvoxels(2),'%i') ' voxels, ' num2str(nvoxels(1)*new_voxel_size,'%1.1f') ' x ' num2str(nvoxels(2)*new_voxel_size,'%1.1f') ' ' voxel_sizeunit num2str(dimension) ', square root = ' num2str(length_root,'%1.1f') voxel_sizeunit];
                str_ROI_short = [num2str(nvoxels(1),'%i') ' x ' num2str(nvoxels(2),'%i') ' voxels, ' num2str(nvoxels(1)*new_voxel_size,'%1.1f') ' x ' num2str(nvoxels(2)*new_voxel_size,'%1.1f') ' ' voxel_sizeunit num2str(dimension)];
            else
                str_ROI = ['ROI: ' num2str(nvoxels(1),'%i') ' x ' num2str(nvoxels(2),'%i') ' x ' num2str(nvoxels(3),'%i') ' voxels, ' num2str(nvoxels(1)*new_voxel_size,'%1.1f') ' x ' num2str(nvoxels(2)*new_voxel_size,'%1.1f') ' x ' num2str(nvoxels(3)*new_voxel_size,'%1.1f') ' ' voxel_sizeunit num2str(dimension) ', cubic root = ' num2str(length_root,'%1.1f') voxel_sizeunit];
                str_ROI_short = [num2str(nvoxels(1),'%i') ' x ' num2str(nvoxels(2),'%i') ' x ' num2str(nvoxels(3),'%i') ' voxels, ' num2str(nvoxels(1)*new_voxel_size,'%1.1f') ' x ' num2str(nvoxels(2)*new_voxel_size,'%1.1f') ' x ' num2str(nvoxels(3)*new_voxel_size,'%1.1f') ' ' voxel_sizeunit num2str(dimension)];
            end
            app.ROI_Label.Text = str_ROI;
            
            app.ROI.lengthroot = [num2str(length_root) ' ' voxel_sizeunit];
            app.ROI.nvoxels = prod(nvoxels);
            app.ROI.str = str_ROI_short;

            [~,name,~] = fileparts(app.FileName);
            new_voxel_size = round(new_voxel_size,3);
            new_voxel_size = num2str(new_voxel_size);
            new_voxel_size = strrep(new_voxel_size,'.','p');
            length_root = round(length_root,1);
            length_root = num2str(length_root);
            length_root = strrep(length_root,'.','p');    

            str_volumefolder = [name '_voxel_' new_voxel_size voxel_sizeunit '_ROI_' length_root voxel_sizeunit];
            app.VolumefolderEditField.Value = str_volumefolder;  

            app.update_volumefractions; 
        end

        % Cell edit callback: ROI_UITable
        function ROI_UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            oldData = event.PreviousData;
            if ~isempty(app.current_semantic_microstructure)
                sz = size(app.current_semantic_microstructure);
            elseif ~isempty(app.current_instance_microstructure)
                sz = size(app.current_instance_microstructure);
            end

            if indices(2)==3 || indices(2)==4 % ROI
                if isnan(newData) || newData~=round(newData) || newData<1 || newData>sz(indices(1))
                    app.ROI_UITable.Data(indices(1),indices(2)) = {oldData};
                end
                ROI = cell2mat(app.ROI_UITable.Data(:,3:5)); % Extract array
                ROI(:,1:2) = sort(ROI(:,1:2),2); % Start < End
                ROI(:,3) = ROI(:,2) - ROI(:,1) + 1;
                app.ROI_UITable.Data(:,3:5)= num2cell(ROI); % Insert table
                app.VoxelsizeEditFieldValueChanged;                       
            end            
        end

        % Cell edit callback: PhasecolornameSemantic_UITable
        function PhasecolornameSemantic_UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            oldData = event.PreviousData;
            if indices(2)==6
                if isnan(newData) || newData~=round(newData) || newData<0
                    app.PhasecolornameSemantic_UITable.Data(indices(1),indices(2)) = {oldData};
                end
            end            
        end

        % Cell selection callback: PhasecolornameSemantic_UITable
        function PhasecolornameSemantic_UITableCellSelection(app, event)
            indices = event.Indices;
            if ~isempty(indices)
                if indices(2)==3 % color
                    c = uisetcolor;
                    if length(c)==3 % RGB
                        app.color_phase_semantic(indices(1),:) = c;
                        s = uistyle('BackgroundColor',app.color_phase_semantic(indices(1),:));
                        addStyle(app.PhasecolornameSemantic_UITable,s,'cell',[indices(1),3]);
                    end
                end
            end
        end

        % Value changed function: Phasenamecolor_DropDown
        function Phasenamecolor_DropDownValueChanged(app, event)
            if strcmp(app.Phasenamecolor_DropDown.Value,'semantic segmentation')
                app.PhasecolornameSemantic_UITable.Visible = 'on';
                app.PhasecolornameSemantic_UITable.Enable = 'on';
                app.PhasecolornameInstance_UITable.Visible = 'off';
                app.PhasecolornameInstance_UITable.Enable = 'off';
            elseif strcmp(app.Phasenamecolor_DropDown.Value,'instance segmentation')
                app.PhasecolornameSemantic_UITable.Visible = 'off';
                app.PhasecolornameSemantic_UITable.Enable = 'off';
                app.PhasecolornameInstance_UITable.Visible = 'on';
                app.PhasecolornameInstance_UITable.Enable = 'on';
            end            
        end

        % Button pushed function: VisualizevolumeButton
        function VisualizevolumeButtonPushed(app, event)
            voxel_size = app.VoxelsizeEditField.Value;
            scaling_factor = app.ScalingfactorEditField.Value;
            new_voxel_size = voxel_size*scaling_factor;
            voxelsize_unitname = app.VoxelsizeUnit_EditField.Value;
            arrayunit = '';  

            if ~isempty(app.current_semantic_microstructure)
                % ROI and scaling
                Msem = Prepare_visualization(app,app.current_semantic_microstructure);
            end
            if ~isempty(app.current_instance_microstructure)
                % ROI and scaling
                Mins = Prepare_visualization(app,app.current_instance_microstructure);
                MinsBW = zeros(size(Mins));
                MinsBW(Mins~=0)=1;
            end            

            if strcmp(app.visualize_resolution_withDropDown.Value,'after scaling')
                voxel_size = new_voxel_size;
            end

            if ~isempty(app.current_semantic_microstructure) && isempty(app.current_instance_microstructure)
                [~,filename,~] = fileparts(app.Semantic_NofileimportedLabel.Text);
                microstructure_visualization_slices(Msem, filename, voxel_size, voxelsize_unitname, arrayunit);
            elseif isempty(app.current_semantic_microstructure) && ~isempty(app.current_instance_microstructure)
                [~,filename,~] = fileparts(app.Instance_NofileimportedLabel.Text);
                p(1).name = filename;
                p(2).name = [p(1).name ', binarized'];                
                Visualization_multiple_volumes(p,Mins,MinsBW);
            elseif ~isempty(app.current_semantic_microstructure) && ~isempty(app.current_instance_microstructure)
                [~,filename,~] = fileparts(app.Semantic_NofileimportedLabel.Text);
                p(1).name = filename;
                [~,filename,~] = fileparts(app.Instance_NofileimportedLabel.Text);
                p(2).name = filename;    
                p(3).name = [p(2).name ', binarized'];  
                Visualization_multiple_volumes(p,Msem,Mins,MinsBW);
            end
        end

        % Button pushed function: PlotvolumefractionsButton
        function PlotvolumefractionsButtonPushed(app, event)
            voxel_size = app.VoxelsizeEditField.Value;
            scaling_factor = app.ScalingfactorEditField.Value;
            new_voxel_size = voxel_size*scaling_factor;

            if ~isempty(app.current_semantic_microstructure)
                % ROI and scaling
                Msem = Prepare_visualization(app,app.current_semantic_microstructure);
            end
            if ~isempty(app.current_instance_microstructure)
                % ROI and scaling
                Mins = Prepare_visualization(app,app.current_instance_microstructure);
                MinsBW = zeros(size(Mins));
                MinsBW(Mins~=0)=1;
            end            

            if strcmp(app.visualize_resolution_withDropDown.Value,'after scaling')
                voxel_size = new_voxel_size;
            end

            if ~isempty(app.current_semantic_microstructure) && isempty(app.current_instance_microstructure)
                [~,filename,~] = fileparts(app.Semantic_NofileimportedLabel.Text);
                app.function_vf_alongthickness(Msem,voxel_size,filename);
            elseif isempty(app.current_semantic_microstructure) && ~isempty(app.current_instance_microstructure)
                [~,filename,~] = fileparts(app.Instance_NofileimportedLabel.Text);
                app.function_vf_alongthickness(MinsBW,voxel_size,filename);
            elseif ~isempty(app.current_semantic_microstructure) && ~isempty(app.current_instance_microstructure)
                app.Phasenamecolor_DropDown.Value = 'semantic segmentation';
                app.Phasenamecolor_DropDownValueChanged;                
                [~,filename,~] = fileparts(app.Semantic_NofileimportedLabel.Text);
                app.function_vf_alongthickness(Msem,voxel_size,filename);       

                app.Phasenamecolor_DropDown.Value = 'instance segmentation';
                app.Phasenamecolor_DropDownValueChanged;    
                [~,filename,~] = fileparts(app.Instance_NofileimportedLabel.Text);
                filename = [filename ', binarized'];
                app.function_vf_alongthickness(MinsBW,voxel_size,filename);
            end       
        end

        % Button pushed function: Import_SavesettingsButton
        function Import_SavesettingsButtonPushed(app, event)
            if strcmp(app.ModeDropDown.Value,'Set parameters for each volume independently')
                n0 = app.number_volume;
                n1 = app.number_volume;
            else
                app.inputvolume = [];
                n0 = 1;
                if strcmp(app.SegmentationtypeDropDown.Value,'semantic')
                    n1 = length(app.pathnames_semantic);
                elseif strcmp(app.SegmentationtypeDropDown.Value,'instance')
                    n1 = length(app.pathnames_instance);
                elseif strcmp(app.SegmentationtypeDropDown.Value,'semantic and instance')
                    n1_sem = length(app.pathnames_semantic);
                    n1_ins = length(app.pathnames_instance);
                    if n1_sem ~= n1_ins
                        app.Import_indication.Text = 'Number of semantic and instance files is different!';
                        app.Import_indication.FontColor = 'r';
                        app.inputvolume = [];
                        return;
                    else
                        n1 = n1_sem;
                    end
                end 
            end

            app.inputvolume.mode = app.ModeDropDown.Value;
            app.inputvolume.segmentation_type = app.SegmentationtypeDropDown.Value;            

            for n = n0:1:n1
                app.inputvolume.volume(n).orderofcalculation=app.number_volume;

                if strcmp(app.ModeDropDown.Value,'Set parameters for each volume independently')
                    if strcmp(app.SegmentationtypeDropDown.Value,'semantic')
                        app.inputvolume.volume(n).loadingpath_semantic = app.Semantic_NofileimportedLabel.Text;
                        app.inputvolume.volume(n).nphase = app.semantic_fileinfo.nphase;
                    elseif strcmp(app.SegmentationtypeDropDown.Value,'instance')
                        app.inputvolume.volume(n).loadingpath_instance = app.Instance_NofileimportedLabel.Text;
                        app.inputvolume.volume(n).ninstance = app.instance_fileinfo.ninstance;
                    elseif strcmp(app.SegmentationtypeDropDown.Value,'semantic and instance')
                        app.inputvolume.volume(n).loadingpath_semantic = app.Semantic_NofileimportedLabel.Text;
                        app.inputvolume.volume(n).loadingpath_instance = app.Instance_NofileimportedLabel.Text;
                        app.inputvolume.volume(n).nphase = app.semantic_fileinfo.nphase;
                        app.inputvolume.volume(n).ninstance = app.instance_fileinfo.ninstance;
                    end
                else
                    if strcmp(app.SegmentationtypeDropDown.Value,'semantic')
                        app.inputvolume.volume(n).loadingpath_semantic = char(app.pathnames_semantic(n));
                        if n==1
                            app.inputvolume.volume(n).nphase = app.semantic_fileinfo.nphase;
                        else
                            app.inputvolume.volume(n).nphase = []; % We only imported 1st file to save time
                        end
                    elseif strcmp(app.SegmentationtypeDropDown.Value,'instance')
                        app.inputvolume.volume(n).loadingpath_instance = char(app.pathnames_instance(n));
                        if n==1
                            app.inputvolume.volume(n).ninstance = app.instance_fileinfo.ninstance;
                        else
                            app.inputvolume.volume(n).ninstance = []; % We only imported 1st file to save time
                        end
                    elseif strcmp(app.SegmentationtypeDropDown.Value,'semantic and instance')
                        app.inputvolume.volume(n).loadingpath_semantic = char(app.pathnames_semantic(n));
                        app.inputvolume.volume(n).loadingpath_instance = char(app.pathnames_instance(n));
                        if n==1
                            app.inputvolume.volume(n).nphase = app.semantic_fileinfo.nphase;
                        else
                            app.inputvolume.volume(n).nphase = []; % We only imported 1st file to save time
                        end
                        if n==1
                            app.inputvolume.volume(n).ninstance = app.instance_fileinfo.ninstance;
                        else
                            app.inputvolume.volume(n).ninstance = []; % We only imported 1st file to save time
                        end
                    end                    
                end

                if strcmp(app.SegmentationtypeDropDown.Value,'semantic')
                    [~, name, ~] = fileparts(app.inputvolume.volume(n).loadingpath_semantic);
                    app.inputvolume.volume(n).semantic_filename_input=name;
                elseif strcmp(app.SegmentationtypeDropDown.Value,'instance') 
                    [~, name, ~] = fileparts(app.inputvolume.volume(n).loadingpath_instance);
                    app.inputvolume.volume(n).instance_filename_input=name;
                elseif strcmp(app.SegmentationtypeDropDown.Value,'semantic and instance')
                    [~, name, ~] = fileparts(app.inputvolume.volume(n).loadingpath_semantic);
                    app.inputvolume.volume(n).semantic_filename_input=name;                    
                    [~, name, ~] = fileparts(app.inputvolume.volume(n).loadingpath_instance);
                    app.inputvolume.volume(n).instance_filename_input=name;
                end

                % Middle slice
                app.inputvolume.volume(n).middleslice_semantic = [];
                app.inputvolume.volume(n).middleslice_instance = [];
                if ~isempty(app.current_middleslice_semantic)
                    app.inputvolume.volume(n).middleslice_semantic=app.current_middleslice_semantic;
                end
                if ~isempty(app.current_middleslice_instance)
                    app.inputvolume.volume(n).middleslice_instance=app.current_middleslice_instance;
                end                

                % Initial size
                if strcmp(app.ModeDropDown.Value,'Set parameters for each volume independently')
                    if strcmp(app.SegmentationtypeDropDown.Value,'semantic') || strcmp(app.SegmentationtypeDropDown.Value,'semantic and instance')
                        app.inputvolume.volume(n).initial_sz = app.semantic_fileinfo.sz;
                    elseif strcmp(app.SegmentationtypeDropDown.Value,'instance')
                        app.inputvolume.volume(n).initial_sz = app.instance_fileinfo.sz;
                    end
                else
                    if strcmp(app.SegmentationtypeDropDown.Value,'semantic') || strcmp(app.SegmentationtypeDropDown.Value,'semantic and instance')
                        app.inputvolume.volume(n).initial_sz = app.semantic_fileinfo(n).sz;
                    elseif strcmp(app.SegmentationtypeDropDown.Value,'instance')
                        app.inputvolume.volume(n).initial_sz = app.instance_fileinfo(n).sz;
                    end
                end

                % ROI
                if strcmp(app.inputvolume.mode,'Set parameters for each volume independently')
                    app.inputvolume.volume(n).ROI = cell2mat(app.ROI_UITable.Data(:,3:4));
                else
                    app.inputvolume.volume(n).ROI = [[app.inputvolume.volume(n).initial_sz./app.inputvolume.volume(n).initial_sz]' app.inputvolume.volume(n).initial_sz'];
                end

                % Direction name
                app.inputvolume.volume(n).directionname = app.ROI_UITable.Data(:,6);
                app.direction_name_sav = app.ROI_UITable.Data(:,6); % For next volume

                % Phase name
                if strcmp(app.SegmentationtypeDropDown.Value,'semantic') || strcmp(app.SegmentationtypeDropDown.Value,'semantic and instance')
                    app.inputvolume.volume(n).initial_phasename_semantic = app.PhasecolornameSemantic_UITable.Data(:,4);
                    app.phase_name_semantic_sav = app.PhasecolornameSemantic_UITable.Data(:,4); % For next volume
                    % Phase color
                    app.inputvolume.volume(n).phasecolor_semantic = app.color_phase_semantic;
                    % Label
                    app.inputvolume.volume(n).initial_phaselabel_semantic = cell2mat(app.PhasecolornameSemantic_UITable.Data(:,5:6));
                end
                if strcmp(app.SegmentationtypeDropDown.Value,'instance') || strcmp(app.SegmentationtypeDropDown.Value,'semantic and instance')
                    app.inputvolume.volume(n).initial_phasename_instance = app.PhasecolornameInstance_UITable.Data(:,4);
                    app.phase_name_instance_sav = app.PhasecolornameInstance_UITable.Data(:,4); % For next volume
                    % Phase color
                    app.inputvolume.volume(n).phasecolor_instance = app.color_phase_instance;
                    % Label
                    app.inputvolume.volume(n).initial_phaselabel_instance = cell2mat(app.PhasecolornameInstance_UITable.Data(:,5:6));
                end

                % Voxel size
                app.inputvolume.volume(n).initial_voxelsize =  app.VoxelsizeEditField.Value;
                app.inputvolume.volume(n).scaling_factor =  app.ScalingfactorEditField.Value;
                app.inputvolume.volume(n).voxelsize =  app.VoxelsizeEditField.Value * app.ScalingfactorEditField.Value;
                % Unit
                app.inputvolume.volume(n).unit = app.VoxelsizeUnit_EditField.Value;

                % Volume information
                app.inputvolume.volume(n).volumeinformation = app.Info_UITable.Data;
                app.volumeinfo_sav = app.Info_UITable.Data; % For next volume

                % Saving path
                app.inputvolume.volume(n).mainfolder = app.mainfolder;
                if strcmp(app.ModeDropDown.Value,'Set parameters for each volume independently')
                    app.inputvolume.volume(n).volumesubfolder = app.VolumefolderEditField.Value;
                else
                    if strcmp(app.SegmentationtypeDropDown.Value,'semantic') || strcmp(app.SegmentationtypeDropDown.Value,'semantic and instance')
                        [~,name,~] = fileparts( char(app.pathnames_semantic(n)) );
                        ROI_length = app.semantic_fileinfo(n).sz;
                    elseif strcmp(app.SegmentationtypeDropDown,Value,'instance')
                        [~,name,~] = fileparts( char(app.pathnames_instance(n)) );
                        ROI_length = app.instance_fileinfo(n).sz;
                    end
                    voxel_size = app.VoxelsizeEditField.Value;
                    scaling_factor = app.ScalingfactorEditField.Value;
                    voxel_sizeunit = app.VoxelsizeUnit_EditField.Value;
                    
                    dimension = length(ROI_length);
                    nvoxels = floor(ROI_length./scaling_factor);
                    new_voxel_size = voxel_size*scaling_factor;
                    length_root = (prod(nvoxels*new_voxel_size)).^(1/dimension);

                    new_voxel_size_str = round(new_voxel_size,3);
                    new_voxel_size_str = num2str(new_voxel_size_str);
                    new_voxel_size_str = strrep(new_voxel_size_str,'.','p');
                    length_root = round(length_root,1);
                    length_root = num2str(length_root);
                    length_root = strrep(length_root,'.','p');

                    str_volumefolder = [name '_voxel_' new_voxel_size_str voxel_sizeunit '_ROI_' length_root voxel_sizeunit];
                    app.inputvolume.volume(n).volumesubfolder = str_volumefolder;

                    if dimension == 2
                        str_ROI_short = [num2str(nvoxels(1),'%i') ' x ' num2str(nvoxels(2),'%i') ' voxels, ' num2str(nvoxels(1)*new_voxel_size,'%1.1f') ' x ' num2str(nvoxels(2)*new_voxel_size,'%1.1f') ' ' voxel_sizeunit num2str(dimension)];
                    else
                        str_ROI_short = [num2str(nvoxels(1),'%i') ' x ' num2str(nvoxels(2),'%i') ' x ' num2str(nvoxels(3),'%i') ' voxels, ' num2str(nvoxels(1)*new_voxel_size,'%1.1f') ' x ' num2str(nvoxels(2)*new_voxel_size,'%1.1f') ' x ' num2str(nvoxels(3)*new_voxel_size,'%1.1f') ' ' voxel_sizeunit num2str(dimension)];
                    end
                    app.ROI.lengthroot = [num2str(length_root) ' ' voxel_sizeunit];
                    app.ROI.nvoxels = prod(nvoxels);
                    app.ROI.str = str_ROI_short;
                end
                app.inputvolume.volume(n).ROI_lengthroot = strrep(app.ROI.lengthroot,'p','.');
                app.inputvolume.volume(n).ROI_nvoxels = app.ROI.nvoxels;
                app.inputvolume.volume(n).ROI_str = app.ROI.str;
                app.inputvolume.volume(n).volpath = fullfile(app.inputvolume.volume(n).mainfolder,app.inputvolume.volume(n).volumesubfolder);

                % Check this save path is not already used
                if app.number_volume>1
                    for kk=1:1:app.number_volume-1
                        if strcmp(app.inputvolume.volume(n).volpath, app.inputvolume.volume(kk).volpath)
                            app.Import_indication.Text = 'Error:  Main folder\Volume folder save path is already used.';
                            app.inputvolume.volume(n) = [];
                            return
                        end
                    end
                end

                % Increment 
                app.number_volume = app.number_volume+1;
            end

            % Find semantinc-instance pair based on name comparison (renumeroate n for instance files)
            if strcmp(app.SegmentationtypeDropDown.Value,'semantic and instance')
                if ~strcmp(app.ModeDropDown.Value,'Set parameters for each volume independently')
                    if n1>1
                        inputvolume_sav = app.inputvolume;

                        bestmatch = zeros(n1,1);
                        for ni = n0:1:n1
                            instance_name = app.inputvolume.volume(ni).instance_filename_input;
                            l_inst_name = length(instance_name);
                            instance_sz = app.instance_fileinfo(ni).sz;
                            match_sz = zeros(n1,1)-1; % Initialize
                            match_score = zeros(n1,1)-1; % Initialize
                            for ns = n0:1:n1
                                semantic_name = app.inputvolume.volume(ns).semantic_filename_input;
                                sematic_sz = app.semantic_fileinfo(ns).sz;
                                l_sem_name = length(semantic_name);
                                if length(instance_sz) == length(sematic_sz)
                                    if sum(instance_sz==sematic_sz)==length(instance_sz)
                                        match_sz(ns)=1;
                                        l_min = min([l_inst_name l_sem_name]);
                                        for ks=1:1:l_min
                                            if strncmpi(instance_name,semantic_name,ks)
                                                match_score(ns) = ks;
                                            end
                                        end
                                    end
                                end
                            end

                            if max(match_sz)<1
                                app.Import_indication.Text = ['Error: instance file ' instance_name ' has no size match with any semantic file'];
                                return
                            end

                            if max(match_score)<1
                                app.Import_indication.Text = ['Error: instance file ' instance_name ' has no name match with any semantic file'];
                                return
                            end

                            if sum(match_score == max(match_score))>1
                                app.Import_indication.Text = ['Error: instance file ' instance_name ' could be name-matched with at least 2 semantic files'];
                                return
                            end

                            % One semantic file has same size and best name match
                            bestmatch(ni) = find(match_score==max(match_score));
                            app.inputvolume.volume(ni) = inputvolume_sav.volume(bestmatch(ni));
                        end

                        if length(bestmatch) ~= length(unique(bestmatch))
                            app.Import_indication.Text = 'Error: some instance files have been matched with the same semantic file';
                            return
                        end
                        clear inputvolume_sav;
                    end
                end
            end

            % Import tab
            app.Semantic_NofileimportedLabel.Text ='No file imported.';
            app.Semantic_NofileimportedLabel.Tooltip = app.Semantic_NofileimportedLabel.Text;
            app.Instance_NofileimportedLabel.Text ='No file imported.';
            app.Instance_NofileimportedLabel.Tooltip = app.Instance_NofileimportedLabel.Text;

            app.Volumenumber_Label.Visible = 'off';
            app.VoxelsizeEditField.Enable = 'off'; app.VoxelsizeUnit_EditField.Enable = 'off'; app.ScalingfactorEditField.Enable = 'off';
            app.Scaledvoxelsize_Label.Text = 'Scaled voxel size: n/a';
            app.ROI_Label.Text = 'ROI: n/a';

            app.ROI_UITable.Data = [];
            app.PhasecolornameSemantic_UITable.Data = [];
            app.PhasecolornameInstance_UITable.Data = [];

            app.VolumefolderEditField.Value = 'Not defined';
            app.Import_SavesettingsButton.Enable = 'off';

            app.current_semantic_microstructure = [];
            app.current_middleslice_semantic = [];
            app.current_instance_microstructure = [];
            app.current_instanceBW_microstructure = [];
            app.current_middleslice_instance = [];
        
            % Imported tab
            for n=n0:1:n1
                % One by one
                voxel_size = [num2str(app.inputvolume.volume(n).voxelsize) ' ' app.inputvolume.volume(n).unit];

                if strcmp(app.SegmentationtypeDropDown.Value,'semantic')
                    newrow = [{app.inputvolume.volume(n).semantic_filename_input} {num2str(app.inputvolume.volume(n).nphase,'%i')} {'n/a'}                                             {'n/a'}                                             {'Unknown'} {app.inputvolume.volume(n).ROI_str} {num2str(app.inputvolume.volume(n).ROI_nvoxels)} {app.inputvolume.volume(n).ROI_lengthroot} {voxel_size} {app.inputvolume.volume(n).orderofcalculation}];
                elseif strcmp(app.SegmentationtypeDropDown.Value,'instance')
                    newrow = [{'n/a'}                                             {'n/a'}                                          {app.inputvolume.volume(n).instance_filename_input} {num2str(app.inputvolume.volume(n).ninstance,'%i')} {'Unknown'} {app.inputvolume.volume(n).ROI_str} {num2str(app.inputvolume.volume(n).ROI_nvoxels)} {app.inputvolume.volume(n).ROI_lengthroot} {voxel_size} {app.inputvolume.volume(n).orderofcalculation}];
                elseif strcmp(app.SegmentationtypeDropDown.Value,'semantic and instance')
                    newrow = [{app.inputvolume.volume(n).semantic_filename_input} {num2str(app.inputvolume.volume(n).nphase,'%i')} {app.inputvolume.volume(n).instance_filename_input} {num2str(app.inputvolume.volume(n).ninstance,'%i')} {'Unknown'} {app.inputvolume.volume(n).ROI_str} {num2str(app.inputvolume.volume(n).ROI_nvoxels)} {app.inputvolume.volume(n).ROI_lengthroot} {voxel_size} {app.inputvolume.volume(n).orderofcalculation}] ;
                end
                if isempty(app.Imported_UITable.Data)
                    app.Imported_UITable.Data = newrow;
                else
                    app.Imported_UITable.Data = [app.Imported_UITable.Data; newrow];
                end
            end

            if ~strcmp(app.ModeDropDown.Value,'Set parameters for each volume independently') 
                children = get(app.GridLayout,'Children');
                set(children,'Enable','off');                
            end

            % Enable imported tab
            children = get(app.GridLayout_imported,'Children');
            set(children,'Enable','on');               

            % Next step
            if strcmp(app.ModeDropDown.Value,'Set parameters for each volume independently') 
                app.Import_indication.Text = 'Repeat steps 3-8, or go to ''imported'' tab to continue';
            else
                app.Import_indication.Text = 'Done!';
                app.FilesTabGroup.SelectedTab = app.ImportedTab;
            end
        end

        % Button pushed function: SelectmainfolderButton
        function SelectmainfolderButtonPushed(app, event)
            % Set string of the dialog box
            str_dialogbox = 'Select main folder';
            % Open dialog box to choose folder path
            if ~isempty(app.mainfolder)
                res = uigetdir(app.mainfolder,str_dialogbox);
            else
                res = uigetdir(pwd,str_dialogbox);
            end
            if res
                app.mainfolder = res;
                app.Mainfolder_Label.Text = res;
                app.Mainfolder_Label.Tooltip = res;
            end
        end

        % Cell edit callback: PhasecolornameInstance_UITable
        function PhasecolornameInstance_UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            oldData = event.PreviousData;
            if indices(2)==6
                if isnan(newData) || newData~=round(newData) || newData<0 || strcmp(app.Phasenamecolor_DropDown.Value,'instance segmentation')
                    app.PhasecolornameInstance_UITable.Data(indices(1),indices(2)) = {oldData};
                end
            end  
        end

        % Cell selection callback: PhasecolornameInstance_UITable
        function PhasecolornameInstance_UITableCellSelection(app, event)
            indices = event.Indices;
            if ~isempty(indices)
                if indices(2)==3 % color
                    c = uisetcolor;
                    if length(c)==3 % RGB
                        app.color_phase_instance(indices(1),:) = c;
                        s = uistyle('BackgroundColor',app.color_phase_instance(indices(1),:));
                        addStyle(app.PhasecolornameInstance_UITable,s,'cell',[indices(1),3]);
                    end
                end
            end
        end

        % Button pushed function: VisualizeallfilesButton
        function VisualizeallfilesButtonPushed(app, event)
            app.VisualizeallfilesButton.Text = 'Please wait...'; pause(0.1);

            n_file = length(app.inputvolume.volume);
            if n_file >1
                n = sqrt(n_file);
                r = min([1, round(n/1.5)]);
                c = floor(n_file/r);
                if r*c<n
                    c=c+1;
                end
            else
                r = 1; c = 1;
            end
            scrsz = get(0,'ScreenSize'); % Screen resolution

            if strcmp(app.inputvolume.segmentation_type,'semantic') || strcmp(app.inputvolume.segmentation_type,'semantic and instance')
                Fig = figure;
                Fig.Name= 'Semantic files';
                Fig.Color='white';
                set(Fig,'position',[scrsz(1) scrsz(2) scrsz(3)*4/5 scrsz(4)*4/5]);
                tiles = tiledlayout(r,c);
                for k_file = 1:1:n_file
                    ax = nexttile(k_file);

                    crop = false;
                    ROI = app.inputvolume.volume(k_file).ROI;
                    length_ROI = ROI(:,2) - ROI(:,1) + 1;
                    if min(app.inputvolume.volume(k_file).initial_sz == length_ROI')==0
                        crop = true;
                    end

                    % Import
                    if isempty(app.inputvolume.volume(k_file).middleslice_semantic) || (crop && strcmp(app.VisualizeROI_DropDown.Value,'with region of interest'))
                        M = function_load_tif(app.inputvolume.volume(k_file).loadingpath_semantic);
                    else
                        M = app.inputvolume.volume(k_file).middleslice_semantic;
                    end
                    
                    % ROI
                    if crop && strcmp(app.VisualizeROI_DropDown.Value,'with region of interest')
                        dimension = length(size(M));
                        if dimension == 2
                            M = M(ROI(1,1):ROI(1,2), ROI(2,1):ROI(2,2));
                        else
                            M = M(ROI(1,1):ROI(1,2), ROI(2,1):ROI(2,2), ROI(3,1):ROI(3,2));
                        end
                    end

                    % Scaling
                    if app.inputvolume.volume(k_file).scaling_factor~=1 && strcmp(app.VisualizeROI_DropDown.Value,'with region of interest')
                        p.scaling_factor = app.inputvolume.volume(k_file).scaling_factor;
                        p.label_or_greylevel = 'Label';
                        p.background = 0;
                        [M] = function_scaling(M,p);
                    end

                    sz = size(M);
                    if length(sz)==3
                        imagesc(M(:,:,round(sz(3)/2)));
                    else
                        imagesc(M);
                    end
                    axis equal; axis tight;
                    colormap(ax,'gray'); colorbar(ax);

                    set(ax,'Fontsize',10,'FontName',app.FontnameDropDown.Value);

                    title_str = strrep(app.inputvolume.volume(k_file).semantic_filename_input,'_',' ');
                    h=title(title_str);
                    h.FontSize = 12;
                    h.FontName = app.FontnameDropDown.Value;

                    subtitle_str = strrep(app.inputvolume.volume(k_file).volumesubfolder,'_',' ');
                    subtitle(subtitle_str,'Fontsize',10,'FontName',app.FontnameDropDown.Value,'FontAngle','italic');
                    
                end
                tiles.TileSpacing = 'compact';
                tiles.Padding = 'compact';
            end

            if strcmp(app.inputvolume.segmentation_type,'instance') || strcmp(app.inputvolume.segmentation_type,'semantic and instance')
                Fig = figure;
                Fig.Name= 'Instance files';
                Fig.Color='white';
                set(Fig,'position',[scrsz(1) scrsz(2) scrsz(3)*4/5 scrsz(4)*4/5]);
                tiles = tiledlayout(r,c);
                for k_file = 1:1:n_file
                    ax = nexttile(k_file);

                    crop = false;
                    ROI = app.inputvolume.volume(k_file).ROI;
                    length_ROI = ROI(:,2) - ROI(:,1) + 1;
                    if min(app.inputvolume.volume(k_file).initial_sz == length_ROI')==0
                        crop = true;
                    end

                    % Import
                    if isempty(app.inputvolume.volume(k_file).middleslice_instance) || (crop && strcmp(app.VisualizeROI_DropDown.Value,'with region of interest'))
                        M = function_load_tif(app.inputvolume.volume(k_file).loadingpath_instance);
                    else
                        M = app.inputvolume.volume(k_file).middleslice_instance;
                    end

                    % ROI
                    if crop && strcmp(app.VisualizeROI_DropDown.Value,'with region of interest')
                        dimension = length(size(M));
                        if dimension == 2
                            M = M(ROI(1,1):ROI(1,2), ROI(2,1):ROI(2,2));
                        else
                            M = M(ROI(1,1):ROI(1,2), ROI(2,1):ROI(2,2), ROI(3,1):ROI(3,2));
                        end
                    end

                    % Scaling
                    if app.inputvolume.volume(k_file).scaling_factor~=1 && strcmp(app.VisualizeROI_DropDown.Value,'with region of interest')
                        p.scaling_factor = app.inputvolume.volume(k_file).scaling_factor;
                        p.label_or_greylevel = 'Label';
                        p.background = 0;
                        [M] = function_scaling(M,p);
                    end

                    sz = size(M);
                    if length(sz)==3
                        imagesc(M(:,:,round(sz(3)/2)));
                    else
                        imagesc(M);
                    end
                    axis equal; axis tight;

                    n=length(unique(M));
                    custom_turbo = turbo(n);
                    custom_turbo(1,:) = [1.0 1.0 1.0]; % White background
                    colormap(ax,custom_turbo);

                    set(ax,'Fontsize',10,'FontName',app.FontnameDropDown.Value);

                    title_str = strrep(app.inputvolume.volume(k_file).instance_filename_input,'_',' ');
                    h=title(title_str);
                    h.FontSize = 12;
                    h.FontName = app.FontnameDropDown.Value;

                    subtitle_str = strrep(app.inputvolume.volume(k_file).volumesubfolder,'_',' ');
                    subtitle(subtitle_str,'Fontsize',10,'FontName',app.FontnameDropDown.Value,'FontAngle','italic');  
                end
                tiles.TileSpacing = 'compact';
                tiles.Padding = 'compact';
            end   

            app.VisualizeallfilesButton.Text = 'Visualize all files';

        end

        % Button pushed function: SearchtagButton
        function SearchtagButtonPushed(app, event)
            data = app.Imported_UITable.Data;
            [n,~] = size(data);
            for k=1:1:n
                name = char(data(k,1));
                id = find(name=='_');
                l = length(name);
                if ~isempty(id) && id(end)<l
                    str_tag = name(id(end)+1:end);
                    num_tag = str2num(str_tag);
                    if ~isempty(num_tag)
                        app.Imported_UITable.Data(k,5) = {num_tag};
                    else
                        app.Imported_UITable.Data(k,5) = {'None'};
                    end
                else
                    app.Imported_UITable.Data(k,5) = {'None'};
                end

            end
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            url = 'https://www.nrel.gov/';
            web(url)   
        end

        % Button pushed function: ClicktoseethelicenceButton
        function ClicktoseethelicenceButtonPushed(app, event)
            url = 'https://github.com/NREL/MATBOX_Microstructure_analysis_toolbox/blob/master/License.txt/';
            web(url)   
        end

        % Button pushed function: CheckonlineversionButton
        function CheckonlineversionButtonPushed(app, event)
            localversion = app.Localversion.Text;
            app.Onlineversion_Label.Text = 'Please wait...'; pause(0.1);
            try
                githubversionfile=webread('https://raw.githubusercontent.com/NREL/MATBOX_Microstructure_analysis_toolbox/master/License.txt');
                if ~strcmp(localversion,githubversionfile)
                    app.Onlineversion_Label.Text = ['There is a new version (' localversion ') available online. Please go to the Github repositorty to update your MATBOX installation'];
                    app.Onlineversion_Label.FontColor = 'r';
                else
                    app.Onlineversion_Label.Text = 'You are using the up-to-date version of MATBOX';
                    app.Onlineversion_Label.FontColor = 'k';
                end
            catch ME
                if strcmp(ME.identifier,'MATLAB:webservices:Timeout')
                    app.Onlineversion_Label.Text = 'Could not check current version online.';
                end
            end
            % Sometimes the catch ME fails
            if strcmp(app.Onlineversion_Label.Text,'Please wait...')
                app.Onlineversion_Label.Text = 'Could not check current version online.';
            end
        end

        % Button pushed function: Imported_SavesettingsButton
        function Imported_SavesettingsButtonPushed(app, event)
            app.Imported_SavesettingsButton.Text = 'Please wait...';
            pause(0.01);

            % Number of volumes
            orders = cell2mat(app.Imported_UITable.Data(:,end));
            numbervolume = sum(orders~=0);
            numbervolume_sav = numbervolume;
            table_statutrun = cell(numbervolume,4); 
            sort_orders = sort(orders);
            sort_orders(sort_orders==0) = [];
            [~,neworders] = ismember(orders,sort_orders);
            app.Imported_UITable.Data(:,end) = num2cell(neworders);
            [number_rows,~] = size(app.Imported_UITable.Data);
            if ~strcmp(app.inputvolume.mode,'Set parameters for each volume independently')
                numbervolume=1;
            end            

            % UI
            app.GreyOut('on');
            if strcmp(app.inputvolume.segmentation_type,'semantic')
                app.Volumefraction_Semantic_UITable.Enable = 'on';
                app.Volumefraction_Instance_UITable.Enable = 'off';
            elseif strcmp(app.inputvolume.segmentation_type,'instance')
                app.Volumefraction_Semantic_UITable.Enable = 'off';
                app.Volumefraction_Instance_UITable.Enable = 'on';
            elseif strcmp(app.inputvolume.segmentation_type,'semantic and instance')
                app.Volumefraction_Semantic_UITable.Enable = 'on';
                app.Volumefraction_Instance_UITable.Enable = 'on';
            end

            if strcmp(app.inputvolume.mode,"Set parameters for each volume independently")
                app.Run_SearchtagCheckBox.Enable = 'off'; app.Run_SearchtagCheckBox.Value = false;
            else
                app.Run_SearchtagCheckBox.Enable = 'on';
            end

            if strcmp(app.inputvolume.segmentation_type,'semantic') || strcmp(app.inputvolume.segmentation_type,'semantic and instance')
                names = cell(numbervolume,1);
                for k=1:1:numbervolume_sav
                    kvol = find(neworders==k);
                    names(k,1) = {app.inputvolume.volume(kvol).semantic_filename_input};
                end
            end
            if strcmp(app.inputvolume.segmentation_type,'instance') || strcmp(app.inputvolume.segmentation_type,'semantic and instance')
                for k=1:1:numbervolume_sav
                    kvol = find(neworders==k);
                    names(k,1) = {[char(names(k,1)) ' and ' app.inputvolume.volume(kvol).instance_filename_input]};
                end
            end
            for k=1:1:numbervolume_sav
                names(k,1) = {[char(names(k,1)) ' (#' num2str(k) ')']};
            end
            % Run table
            table_statutrun(:,1) = names(:,1);


            if strcmp(app.inputvolume.segmentation_type,'semantic') || strcmp(app.inputvolume.segmentation_type,'semantic and instance')
                % Number of phase
                number_phasemax = 0;
                for kvol = 1:1:number_rows
                    if orders(kvol)~=0 && ~isempty(app.inputvolume.volume(kvol).nphase)
                        number_phasemax = max(number_phasemax, app.inputvolume.volume(kvol).nphase);
                    end
                end

                % Create table for phase selection
                table_phaseselection = cell(numbervolume,2*number_phasemax+1);
                table_phase_direction_selection_section = cell(numbervolume,2*number_phasemax+1+6);
                table_phase_direction_selection_allphase = cell(numbervolume,2*number_phasemax+1+3);
                table_2phaseselection = cell(numbervolume,4);
                table_3phaseselection = cell(numbervolume,4);
                table_coupledphaseselection = cell(numbervolume,6);
                for k=1:1:numbervolume
                    kvol = find(neworders==k);
                    table_phaseselection(k,1) = {[app.inputvolume.volume(kvol).semantic_filename_input ' (#' num2str(k) ')']};
                    nphase = app.inputvolume.volume(kvol).nphase;
                    phaselabel = app.inputvolume.volume(kvol).initial_phaselabel_semantic(:,2);
                    phasename = app.inputvolume.volume(kvol).initial_phasename_semantic;
                    ulabel = unique(phaselabel);
                    app.inputvolume.volume(kvol).phasename_semantic=cell(nphase,1);
                    for kphase=1:1:nphase
                        idx = find(phaselabel==ulabel(kphase));
                        str = char(phasename(idx(1)));
                        if length(idx)==1
                            table_phaseselection(k,2*kphase)= phasename(idx);
                        else
                            for kstr=2:1:length(idx)
                                str = [str ' and ' char(phasename(idx(kstr)))];
                            end
                            table_phaseselection(k,2*kphase)={str};
                        end
                        % 2 and 3 phase property
                        tmp = [num2str(ulabel(kphase)) ': ' char(table_phaseselection(k,2*kphase))];
                        if kphase==1
                            strphases=tmp;
                        else
                            strphases = [strphases ', ' tmp];
                        end
                        table_phaseselection(k,2*kphase+1)={true};
                        app.inputvolume.volume(kvol).phasename_semantic(kphase) = {str};
                    end
                    app.inputvolume.volume(kvol).phaselabel_semantic = ulabel;

                    % For phase i - phase j properties (interface)
                    table_2phaseselection(k,1) = table_phaseselection(k,1);
                    table_2phaseselection(k,2) = {strphases};
                    if nphase==2
                        table_2phaseselection(k,3)={false};
                        table_2phaseselection(k,4) = {'Do specific surface area instead'};
                    elseif nphase>=3
                        table_2phaseselection(k,3)={true};
                        table_2phaseselection(k,4) = {[num2str(ulabel(1)) ',' num2str(ulabel(2)) ';' num2str(ulabel(1)) ',' num2str(ulabel(3)) ';' num2str(ulabel(2)) ',' num2str(ulabel(3))  ]};
                    end

                    % For phase i - phase j - phase l properties (TPBL)
                    table_3phaseselection(k,1) = table_phaseselection(k,1);
                    table_3phaseselection(k,2) = {strphases};
                    if nphase<3
                        table_3phaseselection(k,3)={false};
                        table_3phaseselection(k,4) = {'n/a'};
                    elseif nphase>=3
                        table_3phaseselection(k,3)={true};
                        table_3phaseselection(k,4) = {[num2str(ulabel(1)) ',' num2str(ulabel(2)) ',' num2str(ulabel(3)) ]};
                    end

                    % For couple-phase (connectivity)
                    table_coupledphaseselection(k,1) = table_phaseselection(k,1);
                    table_coupledphaseselection(k,2) = {strphases};
                    table_coupledphaseselection(k,3)={true};
                    str = num2str(ulabel(1));
                    for kphase=2:1:nphase-1
                        str = [str ',' num2str(ulabel(kphase))];
                    end
                    str = [str ',' num2str(ulabel(nphase))];
                    table_coupledphaseselection(k,4)={str};
                    if nphase==2
                        table_coupledphaseselection(k,5)={'n/a'};
                    elseif nphase==3
                        table_coupledphaseselection(k,5) = {[num2str(ulabel(2)) ',' num2str(ulabel(3))]};
                    else
                        table_coupledphaseselection(k,5) = {[num2str(ulabel(1)) ',' num2str(ulabel(2)) ';' num2str(ulabel(1)) ',' num2str(ulabel(3)) ';' num2str(ulabel(2)) ',' num2str(ulabel(3))  ]};
                    end
                    table_coupledphaseselection(k,6)=table_coupledphaseselection(k,5);

                    % For 1-phase-property with directions
                    table_phase_direction_selection_allphase(k,1) = table_phaseselection(k,1);
                    table_phase_direction_selection_allphase(k,2) = {true};
                    table_phase_direction_selection_allphase(k,3) = {true};
                    table_phase_direction_selection_allphase(k,4) = {true};
                    table_phase_direction_selection_allphase(k,5:end) = table_phaseselection(k,2:end);

                    % For 1-phase-property with directions and section cut (Tortuosity)
                    table_phase_direction_selection_section(k,1) = table_phaseselection(k,1);
                    table_phase_direction_selection_section(k,2) = {true};
                    table_phase_direction_selection_section(k,3) = {1};
                    table_phase_direction_selection_section(k,4) = {true};
                    table_phase_direction_selection_section(k,5) = {1};
                    table_phase_direction_selection_section(k,6) = {true};
                    table_phase_direction_selection_section(k,7) = {1};
                    table_phase_direction_selection_section(k,8:end) = table_phaseselection(k,2:end);
                    for kk=11:2:2*number_phasemax+1+6
                        if ~isempty(cell2mat(table_phase_direction_selection_section(k,kk)))
                            table_phase_direction_selection_section(k,kk) = {false}; % By default, only pore domain
                        end
                    end
                end

                if ~strcmp(app.inputvolume.mode,'Set parameters for each volume independently')
                    for k=2:1:numbervolume_sav
                        kvol = find(neworders==k);
                        app.inputvolume.volume(kvol).phasename_semantic = app.inputvolume.volume(1).phasename_semantic;
                        app.inputvolume.volume(kvol).phaselabel_semantic = app.inputvolume.volume(1).phaselabel_semantic;
                    end
                end


                % Name and edit for 1-phase property
                cname_phaseselection = cell(1,2*number_phasemax+1);
                cname_phaseselection(1) = {'Volume'};
                for k=2:2:2*number_phasemax
                    cname_phaseselection(k)={['Phase #' num2str(k/2)]};
                end
                cedit_phaseselection = [false];
                for kphase=1:1:number_phasemax
                    cedit_phaseselection(2*kphase)=false;
                    cedit_phaseselection(2*kphase+1)=true;
                end

                % Name and edit for 2-phase property
                cname_2phaseselection = {'Volume','Phase labels','To do','Label doublets'};
                cedit_2phaseselection = [false false true true];

                % Name and edit for 3-phase property
                cname_3phaseselection = {'Volume','Phase labels','To do','Label triplets'};
                cedit_3phaseselection = [false false true true];

                % Name and edit for coupledphase property
                cname_coupledphaseselection = {'Volume','Phase labels','To do','Individuals','Union','Coupled'};
                cedit_coupledphaseselection = [false false  true true true true];

                % Name and edit for 1-phase property with directions
                cname_phase_direction_selection = cell(1,2*number_phasemax+1+3);
                cname_phase_direction_selection(1) = {'Volume'};
                cname_phase_direction_selection(2) = {'Direction 1'};
                cname_phase_direction_selection(3) = {'Direction 2'};
                cname_phase_direction_selection(4) = {'Direction 3'};
                cname_phase_direction_selection(5:end) = cname_phaseselection(2:end);
                cedit_phase_direction_selection = [false];
                cedit_phase_direction_selection(1) = false;
                cedit_phase_direction_selection(2) = true;
                cedit_phase_direction_selection(3) = true;
                cedit_phase_direction_selection(4) = true;
                for k=6:2:2*number_phasemax+1+3
                    cedit_phase_direction_selection(k-1) = false;
                    cedit_phase_direction_selection(k) = true;
                end

                % Name and edit for 1-phase property with directions and sectoin
                cname_phase_direction_selection_section = cell(1,2*number_phasemax+1+6);
                cname_phase_direction_selection_section(1) = {'Volume'};
                cname_phase_direction_selection_section(2) = {'Direction 1'};
                cname_phase_direction_selection_section(3) = {'#Section'};
                cname_phase_direction_selection_section(4) = {'Direction 2'};
                cname_phase_direction_selection_section(5) = {'#Section'};
                cname_phase_direction_selection_section(6) = {'Direction 3'};
                cname_phase_direction_selection_section(7) = {'#Section'};
                cname_phase_direction_selection_section(8:end) = cname_phaseselection(2:end);
                cedit_phase_direction_selection_section = [false];
                cedit_phase_direction_selection_section(1) = false;
                cedit_phase_direction_selection_section(2) = true;
                cedit_phase_direction_selection_section(3) = true;
                cedit_phase_direction_selection_section(4) = true;
                cedit_phase_direction_selection_section(5) = true;
                cedit_phase_direction_selection_section(6) = true;
                cedit_phase_direction_selection_section(7) = true;
                for k=9:2:2*number_phasemax+1+6
                    cedit_phase_direction_selection_section(k-1) = false;
                    cedit_phase_direction_selection_section(k) = true;
                end


                % % Update tables (1phase-property)
                % Volume fraction
                app.Volumefraction_Semantic_UITable.Data = table_phaseselection;
                app.Volumefraction_Semantic_UITable.ColumnName =cname_phaseselection;
                app.Volumefraction_Semantic_UITable.ColumnEditable =cedit_phaseselection;


            end
            if strcmp(app.inputvolume.segmentation_type,'instance') || strcmp(app.inputvolume.segmentation_type,'semantic and instance')
                % Number of phase
                number_phasemax = 2;

                % Create table for phase selection
                table_phaseselection = cell(numbervolume,2*number_phasemax+1);
                table_phase_direction_selection_section = cell(numbervolume,1+2*number_phasemax+6);
                table_phase_direction_selection_allphase = cell(numbervolume,1+2*number_phasemax+3);
                for k=1:1:numbervolume
                    kvol = find(neworders==k);
                    % name
                    table_phaseselection(k,1) = {[app.inputvolume.volume(kvol).instance_filename_input ' (#' num2str(k) ')']};

                    % Phase name 
                    phaselabel = app.inputvolume.volume(kvol).initial_phaselabel_instance(:,2);
                    phasename = app.inputvolume.volume(kvol).initial_phasename_instance;
                    app.inputvolume.volume(kvol).phasename_instance = phasename;
                    ulabel = unique(phaselabel);
                    for kphase=1:1:2
                        idx = find(phaselabel==ulabel(kphase));
                        str = char(phasename(idx(1)));
                        table_phaseselection(k,2*kphase)= app.inputvolume.volume(kvol).initial_phasename_instance(idx);
                        table_phaseselection(k,2*kphase+1)={true};
                        app.inputvolume.volume(kvol).phasename_instance(kphase) = {str};
                    end
                    app.inputvolume.volume(kvol).phaselabel_instance = ulabel;

                    % For 1-phase-property with directions
                    table_phase_direction_selection_allphase(k,1) = table_phaseselection(k,1);
                    table_phase_direction_selection_allphase(k,2) = {true};
                    table_phase_direction_selection_allphase(k,3) = {true};
                    table_phase_direction_selection_allphase(k,4) = {true};
                    table_phase_direction_selection_allphase(k,5:end) = table_phaseselection(k,2:end);

                    % For 1-phase-property with directions and section cut (Tortuosity)
                    table_phase_direction_selection_section(k,1) = table_phaseselection(k,1);
                    table_phase_direction_selection_section(k,2) = {true};
                    table_phase_direction_selection_section(k,3) = {1};
                    table_phase_direction_selection_section(k,4) = {true};
                    table_phase_direction_selection_section(k,5) = {1};
                    table_phase_direction_selection_section(k,6) = {true};
                    table_phase_direction_selection_section(k,7) = {1};
                    table_phase_direction_selection_section(k,8:end) = table_phaseselection(k,2:end);
                    for kk=11:2:2*number_phasemax+1+6
                        if ~isempty(cell2mat(table_phase_direction_selection_section(k,kk)))
                            table_phase_direction_selection_section(k,kk) = {false}; % By default, only pore domain
                        end
                    end

                end

                % Name and edit for 1-phase property
                cname_phaseselection = cell(1,2*number_phasemax+1);
                cname_phaseselection(1) = {'Volume'};
                for k=2:2:2*number_phasemax
                    cname_phaseselection(k)={['Phase #' num2str(k/2)]};
                end
                cedit_phaseselection = [false];
                for kphase=1:1:number_phasemax
                    cedit_phaseselection(2*kphase)=false;
                    cedit_phaseselection(2*kphase+1)=true;
                end

                % Name and edit for 1-phase property with directions
                cname_phase_direction_selection = cell(1,2*number_phasemax+1+3);
                cname_phase_direction_selection(1) = {'Volume'};
                cname_phase_direction_selection(2) = {'Direction 1'};
                cname_phase_direction_selection(3) = {'Direction 2'};
                cname_phase_direction_selection(4) = {'Direction 3'};
                cname_phase_direction_selection(5:end) = cname_phaseselection(2:end);
                cedit_phase_direction_selection = [false];
                cedit_phase_direction_selection(1) = false;
                cedit_phase_direction_selection(2) = true;
                cedit_phase_direction_selection(3) = true;
                cedit_phase_direction_selection(4) = true;
                for k=6:2:2*number_phasemax+1+3
                    cedit_phase_direction_selection(k-1) = false;
                    cedit_phase_direction_selection(k) = true;
                end

                % Name and edit for 1-phase property with directions and sectoin
                cname_phase_direction_selection_section = cell(1,2*number_phasemax+1+6);
                cname_phase_direction_selection_section(1) = {'Volume'};
                cname_phase_direction_selection_section(2) = {'Direction 1'};
                cname_phase_direction_selection_section(3) = {'#Section'};
                cname_phase_direction_selection_section(4) = {'Direction 2'};
                cname_phase_direction_selection_section(5) = {'#Section'};
                cname_phase_direction_selection_section(6) = {'Direction 3'};
                cname_phase_direction_selection_section(7) = {'#Section'};
                cname_phase_direction_selection_section(8:end) = cname_phaseselection(2:end);
                cedit_phase_direction_selection_section = [false];
                cedit_phase_direction_selection_section(1) = false;
                cedit_phase_direction_selection_section(2) = true;
                cedit_phase_direction_selection_section(3) = true;
                cedit_phase_direction_selection_section(4) = true;
                cedit_phase_direction_selection_section(5) = true;
                cedit_phase_direction_selection_section(6) = true;
                cedit_phase_direction_selection_section(7) = true;
                for k=9:2:2*number_phasemax+1+6
                    cedit_phase_direction_selection_section(k-1) = false;
                    cedit_phase_direction_selection_section(k) = true;
                end       

                % % Update tables (1phase-property)
                % Volume fraction
                app.Volumefraction_Instance_UITable.Data = table_phaseselection;
                app.Volumefraction_Instance_UITable.ColumnName =cname_phaseselection;
                app.Volumefraction_Instance_UITable.ColumnEditable =cedit_phaseselection;                

                % % Run table
                % if strcmp(app.inputvolume.segmentation_type,'semantic') || strcmp(app.inputvolume.segmentation_type,'semantic and instance')
                %     for k=1:1:numbervolume
                %         table_statutrun(k,1) = {[char(table_statutrun(k,1)) ' and ' char(table_phaseselection(k,1))]};
                %     end
                % else
                %     table_statutrun(:,1) = table_phaseselection(:,1);
                % end
            end

            app.order_calculation = cell2mat(app.Imported_UITable.Data(:,end));

            % Voxel size
            app.Voxelsize_VolumeselectionDropDown.Items =  table_phaseselection(:,1);
            % Create table for voxel size dependence analysis
            properties = {'Volume fraction';'foo'};
            n_properties = length(properties);
            app.default_voxelsizeanalysis = cell(n_properties,6);
            app.default_voxelsizeanalysis(:,1) = properties;
            app.default_voxelsizeanalysis(:,2) = {'1'};
            app.default_voxelsizeanalysis(:,4) = {'None'};
            app.default_voxelsizeanalysis(:,5) = num2cell([3;1]);      
            app.default_voxelsizeanalysis(:,6) = {false};
            % Assign
            for k=1:1:numbervolume
                app.Voxelsize_VolumeselectionDropDown.Value = app.Voxelsize_VolumeselectionDropDown.Items(k);
                app.Voxelsize_VolumeselectionDropDownValueChanged;
            end
            app.Voxelsize_VolumeselectionDropDown.Value = app.Voxelsize_VolumeselectionDropDown.Items(1);
            app.Voxelsize_VolumeselectionDropDownValueChanged;
            
            % Run tab
            app.RunStatut_UITable.Data=table_statutrun;
            
            % Move to next tab
            app.Imported_SavesettingsButton.Text = 'Save settings';
            app.Main_TabGroup.SelectedTab = app.VolumeTab;     
            app.TabGroup2.SelectedTab = app.VolumefractionTab;             
        end

        % Cell edit callback: Imported_UITable
        function Imported_UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            if indices(2) == 10 % Calculation order
                if ~strcmp(app.inputvolume.mode,'Set parameters for each volume independently') && indices(1)==1
                    app.Imported_UITable.Data(indices(1),indices(2)) = {event.PreviousData};
                else
                    if ~ischar(newData) && ~isnan(newData) && newData==round(newData) && newData>=0
                        vals = cell2mat(app.Imported_UITable.Data(:,10));
                        vals(vals==0)=[];
                        if length(vals)~=length(unique(vals))
                            app.Imported_UITable.Data(indices(1),indices(2)) = {event.PreviousData};
                        end
                    else
                        app.Imported_UITable.Data(indices(1),indices(2)) = {event.PreviousData};
                    end
                end
            elseif indices(2) == 5 % Tag
                if ischar(newData)
                    newData = str2num(newData);
                end
                if isempty(newData) || isnan(newData)
                    app.Imported_UITable.Data(indices(1),indices(2)) = {event.PreviousData};
                end
            end
        end

        % Button pushed function: Restart
        function RestartPushed(app, event)
            Microstructure_characterization2;
            delete(app);            
        end

        % Value changed function: Volumefractions_AllphasesCheckBox
        function Volumefractions_AllphasesCheckBoxValueChanged(app, event)
            value = app.Volumefractions_AllphasesCheckBox.Value;
            if app.Volumefraction_Semantic_UITable.Enable
                t = app.Volumefraction_Semantic_UITable.Data;
                t = app.func_selectall(t,value);
                app.Volumefraction_Semantic_UITable.Data = t;
            end
            if app.Volumefraction_Instance_UITable.Enable
                t = app.Volumefraction_Instance_UITable.Data;
                t = app.func_selectall(t,value);
                app.Volumefraction_Instance_UITable.Data = t;
            end
        end

        % Button pushed function: Run_button
        function Run_buttonButtonPushed(app, event)
            % optc: options common to all volumes
            % opts: options volume specific 
            
            % Toolbox info (hardcoded)
            optc.toolbox.toolboxname = 'Microstructure analysis toolbox (MATBOX)';
            optc.toolbox.version = app.Localversion.Text;
            optc.toolbox.githubrepo = 'https://github.com/NREL/MATBOX_Microstructure_analysis_toolbox';
            optc.toolbox.officialwebpage = 'https://github.com/NREL/MATBOX_Microstructure_analysis_toolbox';            
            optc.toolbox.article = 'F.L.E.Usseglio-Viretta et al.,MATBOX: An Open-source Microstructure Analysis Toolbox for microstructure generation, segmentation, characterization, visualization, correlation, and meshing, SoftwareX, Volume 17, January 2022, 100915 https://doi.org/10.1016/j.softx.2021.100915';
            optc.toolbox.authorlist = {'Francois L. E. Usseglio-Viretta','National Renewable Energy Laboratory';
                                      'Prehit Patel','National Renewable Energy Laboratory';
                                       'Elizabeth Bernhardt','National Renewable Energy Laboratory';
                                       'Aashutosh Mistry', 'Argonne National Laboratory';
                                       'Partha P. Mukherjee', 'Purdue University';
                                       'Jeffery Allen', 'National Renewable Energy Laboratory';
                                       'Samuel J. Cooper','Imperial College London';
                                       'Jerome Laurencin','CEA-Liten';
                                       'Kandler Smith','National Renewable Energy Laboratory'};

            % % PRINT IMFORMATION ABOUT TOOLBOX AND USER
            Table_toolbox = table({'Toolbox';'Version';'GitHub repository';'Journal article'},{optc.toolbox.toolboxname;optc.toolbox.version;optc.toolbox.githubrepo;optc.toolbox.article},...
                'VariableNames',{'Item','Value'});            
            Table_author = table(optc.toolbox.authorlist(:,1),optc.toolbox.authorlist(:,2),...
                'VariableNames',{'Author','Affiliation'});
            username = getenv('USERNAME');
            computername = getenv('COMPUTERNAME');
            os = getenv('OS');
            Table_User = table({username},{computername},{os},...
                'VariableNames',{'User_name','Computer_name','Operating_system'});

            clc % Clear command window
            disp '***************************************';
            disp '>>> MICROSTRUCTURE ANALYSIS TOOLBOX <<<';
            disp '    Characterization module';
            disp '***************************************';
            disp ' ';
            disp(Table_toolbox);disp ' ';
            disp(Table_author);disp ' ';
            disp 'Run by:';            
            disp(Table_User);disp ' ';

            if strcmp(app.inputvolume.segmentation_type,'semantic')
                disp('Characterization of semantic segmentation file(s)');disp ' ';
            elseif strcmp(app.inputvolume.segmentation_type,'instance')
                 disp('Characterization of instance segmentation file(s)');disp ' ';
            elseif strcmp(app.inputvolume.segmentation_type,'semantic and instance')
                disp('Characterization of semantic and instance segmentation files');disp ' ';
            end

            % Save options
            optc.save.mat = true;
            optc.save.xls = true;
            optc.save.fig_infig = true;
            optc.save.fig_format = {'png'};
            optc.save.savefig = true;

            % Format options
            optc.format.fontname = app.FontnameDropDown.Value;
            optc.format.axefontsize = app.AxeFontsizeEditField.Value;
            optc.format.legendfontsize = app.LegendfontsizeEditField.Value;
            optc.format.titlefontsize = app.TitlefontsizeEditField.Value;
            optc.format.sgtitlefontsize = app.SgtitlefontsizeEditField.Value;
            optc.format.linewidth = app.LinewidthEditField.Value;
            if strcmp(app.GridDropDown.Value,'No grid')
                optc.format.grid = 'off';
                optc.format.minorgrid = 'off';
            elseif strcmp(app.GridDropDown.Value,'Major grid')
                optc.format.grid = 'on';
                optc.format.minorgrid = 'off';
            elseif strcmp(app.GridDropDown.Value,'Major and minor grid')
                optc.format.grid = 'on';
                optc.format.minorgrid = 'on';
            end
            optc.format.autoclosefig = app.ClosefigureautomaticallyoncesavedCheckBox.Value;

            % Loop over all files
            orders = app.order_calculation;
            number_volume_to_characterize = sum(orders~=0);
            for k=1:1:number_volume_to_characterize
                kvol = find(orders==k);

                if strcmp(app.inputvolume.mode,'Set parameters only once and apply to all volumes') && k>1
                    opts_sem = opts_sem1;
                    opts_ins = opts_ins1;
                else
                    % Initialize semantic and instance options
                    opts_sem = [];
                    opts_ins = [];
                    
                    % % Semantic options
                    if strcmp(app.inputvolume.segmentation_type,'semantic') || strcmp(app.inputvolume.segmentation_type,'semantic and instance')
                        number_phase = app.inputvolume.volume(kvol).nphase;

                        % Volume fractions
                        opts_sem.volumefraction.todo = zeros(number_phase,1);
                        for kphase=1:1:number_phase
                            opts_sem.volumefraction.todo(kphase,1) = cell2mat(app.Volumefraction_Semantic_UITable.Data(k,2*kphase+1));
                        end
                        % Voxel size
                        scalingfactors = app.inputvolume.volume(kvol).voxel_resize(app.voxelidx.volumefraction, 2);
                        opts_sem.volumefraction.scaling = str2num(char(strrep(scalingfactors,',',' ')));
                        opts_sem.volumefraction.scaling_extrapolation = char(app.inputvolume.volume(kvol).voxel_resize(app.voxelidx.volumefraction, 4));
                        % Fractal analysis
                        opts_sem.volumefraction.fractal_bertei.todo = cell2mat(app.inputvolume.volume(kvol).voxel_resize(app.voxelidx.volumefraction, 6));

                        opts_sem.volumefraction.fractal_boxcounting.todo = 0;
                        opts_sem.volumefraction.RVE.number_RVE = 0;


                    end

                    % % Instance options
                    if strcmp(app.inputvolume.segmentation_type,'instance') || strcmp(app.inputvolume.segmentation_type,'semantic and instance')
                        number_phase = 2;

                        % Volume fractions
                        opts_ins.volumefraction.todo = zeros(number_phase,1);
                        for kphase=1:1:number_phase
                            opts_ins.volumefraction.todo(kphase,1) = cell2mat(app.Volumefraction_Instance_UITable.Data(k,2*kphase+1));
                        end
                        % Voxel size
                        scalingfactors = app.inputvolume.volume(kvol).voxel_resize(app.voxelidx.volumefraction, 2);
                        opts_ins.volumefraction.scaling = str2num(char(strrep(scalingfactors,',',' ')));
                        opts_ins.volumefraction.scaling_extrapolation = char(app.inputvolume.volume(kvol).voxel_resize(app.voxelidx.volumefraction, 4));
                        % Fractal analysis
                        opts_ins.volumefraction.fractal_bertei.todo = cell2mat(app.inputvolume.volume(kvol).voxel_resize(app.voxelidx.volumefraction, 6));

                        opts_sem.volumefraction.fractal_boxcounting.todo = 0;
                        opts_sem.volumefraction.RVE.number_RVE = 0;


                    end


                end

                % % Start
                date_start = datetime('now','TimeZone','local','Format','eeee, HH:mm:ss Z, MMMM d, y');
                Table_date = table({char(date_start)},...
                    'VariableNames',{'Date'});
                disp '>>>';
                disp(['>>> VOLUME #' num2str(k) ' <<<']);
                disp '>>>';
                disp ' ';
                disp(Table_date);disp ' ';
                app.RunStatut_UITable.Data(k,2) = {'On-going'};
                app.RunStatut_UITable.Data(k,3) = {char(date_start)};
                app.RunStatut_UITable.Data(k,4) = {' '};
                sPass = uistyle("Icon","none","IconAlignment","rightmargin");
                addStyle(app.RunStatut_UITable,sPass,"cell",[k 2]);                
                pause(0.01);
                tic
                function_microstructure_characterization_segmentedvolume2(app.inputvolume.mode, app.inputvolume.segmentation_type, app.inputvolume.volume(kvol),optc,opts_sem,opts_ins); % Call main characterization function
                app.RunStatut_UITable.Data(k,4) = {[num2str(toc,'%1.1f') ' s']};
                app.RunStatut_UITable.Data(k,2) = {'Done'};
                sPass = uistyle("Icon","success","IconAlignment","rightmargin");
                addStyle(app.RunStatut_UITable,sPass,"cell",[k 2]);
                pause(0.01);

                if k==1
                    opts_sem1 = opts_sem;
                    opts_ins1 = opts_ins;
                end
            end
            disp ' ';
            disp '****************************************************************************************************';
            disp '>>> ALL CALCULATIONS FOR ALL VOLUMES HAVE BEEN PERFORMED <<<';
            disp '    Results (figures and tables) are located within the ''main folder\volume folder'' of each volume';
            disp '****************************************************************************************************';
        end

        % Value changed function: Voxelsize_VolumeselectionDropDown
        function Voxelsize_VolumeselectionDropDownValueChanged(app, event)
            idx = app.Voxelsize_VolumeselectionDropDown.ValueIndex;
            k = find(app.order_calculation==idx);
            if ~isfield(app.inputvolume.volume(k),'voxel_resize') || isempty(app.inputvolume.volume(k).voxel_resize)
                app.Voxelsize_UITable.Data = app.default_voxelsizeanalysis;
                app.Voxelsize_UITable.Data(:,3) = {[num2str(app.inputvolume.volume(k).voxelsize) ' ' app.inputvolume.volume(k).unit]};
                app.inputvolume.volume(k).voxel_resize = app.Voxelsize_UITable.Data;
            else
                app.Voxelsize_UITable.Data = app.inputvolume.volume(k).voxel_resize;
            end            
        end

        % Cell edit callback: Voxelsize_UITable
        function Voxelsize_UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;

            if indices(2)==6 % Fractal dimension
                scalingfactors = app.Voxelsize_UITable.Data(indices(1),2);
                scalingfactors = str2num(char(strrep(scalingfactors,',',' ')));
                if length(scalingfactors)<2
                    app.Voxelsize_UITable.Data(indices(1),indices(2)) = {false};
                end

            elseif indices(2)==2 % Scaling factor
                multiplier = str2num(newData);
                if isempty(multiplier) % Incorrect input
                    multiplier = 1;
                    app.Voxelsize_UITable.Data(indices(1),indices(2)) = {'1'};
                else
                    if length(multiplier)==length(unique(multiplier)) && length(multiplier)==sum(multiplier>0) % No duplicate, no negative.
                        foo=1;
                    else
                        multiplier = 1;
                        app.Voxelsize_UITable.Data(indices(1),indices(2)) = {'1'};
                    end
                end

                if ~isempty(multiplier)
                    multiplier = unique([1 multiplier]);
                    multiplier = sort(multiplier);
                    idx = app.Voxelsize_VolumeselectionDropDown.ValueIndex;
                    k = find(app.order_calculation==idx);
                    vunit = app.inputvolume.volume(k).unit;
                    vsize = app.inputvolume.volume(k).voxelsize;
                    if length(multiplier)==1
                        str1 = num2str(multiplier);
                        str2 = [num2str(multiplier*vsize) ' ' vunit];
                    else
                        str1 = num2str(multiplier(1));
                        str2 = num2str(multiplier(1)*vsize);
                        for k=2:1:length(multiplier)
                            str1 = [str1 ', ' num2str(multiplier(k))];
                            str2 = [str2 ', ' num2str(multiplier(k)*vsize)];
                        end
                        str2 = [str2 ' ' vunit];
                    end
                    app.Voxelsize_UITable.Data(indices(1),2) = {str1};
                    app.Voxelsize_UITable.Data(indices(1),3) = {str2};
                end

                scalingfactors = app.Voxelsize_UITable.Data(indices(1),2);
                scalingfactors = str2num(char(strrep(scalingfactors,',',' ')));
                if length(scalingfactors)<2
                    app.Voxelsize_UITable.Data(indices(1),6) = {false};
                    app.Voxelsize_UITable.Data(indices(1),4) = {'None'};
                end                

            elseif indices(2)==4 % Extrapolation
                str = lower(newData);
                if strcmp(str,'linear') || strcmp(str,'l') || strcmp(str,'1')
                    app.Voxelsize_UITable.Data(indices(1),4) = {'Linear'};
                elseif strcmp(str,'quadratic') || strcmp(str,'q') || strcmp(str,'2')
                    app.Voxelsize_UITable.Data(indices(1),4) = {'Quadratic'};  
                elseif strcmp(str,'cubic') || strcmp(str,'c') || strcmp(str,'3')
                    app.Voxelsize_UITable.Data(indices(1),4) = {'Cubic'};                      
                elseif strcmp(str,'none') || strcmp(str,'no') || strcmp(str,'n')  || strcmp(str,'0')
                    app.Voxelsize_UITable.Data(indices(1),4) = {'None'};                      
                else
                    app.Voxelsize_UITable.Data(indices(1),4) = {'None'};
                end
            end

            idx = app.Voxelsize_VolumeselectionDropDown.ValueIndex;
            k = find(app.order_calculation==idx);
            app.inputvolume.volume(k).voxel_resize = app.Voxelsize_UITable.Data;  
            
        end

        % Button pushed function: Voxelsize_Applytoallvolumes_Button
        function Voxelsize_Applytoallvolumes_ButtonPushed(app, event)
            for idx = 1:1:length(app.Voxelsize_VolumeselectionDropDown.Items)
                k = find(app.order_calculation==idx);
                app.inputvolume.volume(k).voxel_resize = app.Voxelsize_UITable.Data;  
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MicrostructurecharacterizationUIFigure = uifigure('Visible', 'off');
            app.MicrostructurecharacterizationUIFigure.Name = 'Microstructure characterization';
            app.MicrostructurecharacterizationUIFigure.Icon = 'Icon_Characterization.png';
            app.Main_TabGroup = uitabgroup(app.MicrostructurecharacterizationUIFigure);
            app.InstructionsTab = uitab(app.Main_TabGroup);
            app.InstructionsTab.Title = 'Instructions';
            app.Instructions_GridLayout = uigridlayout(app.InstructionsTab);
            app.Restart = uibutton(app.Instructions_GridLayout, 'push');
            app.Restart.ButtonPushedFcn = createCallbackFcn(app, @RestartPushed, true);
            app.Restart.Text = 'Restart app';
            app.FormatTab = uitab(app.Main_TabGroup);
            app.FormatTab.Title = 'Format';
            app.Format_GridLayout = uigridlayout(app.FormatTab);
            app.FontnameDropDownLabel = uilabel(app.Format_GridLayout);
            app.FontnameDropDownLabel.Text = 'Fontname';
            app.FontnameDropDown = uidropdown(app.Format_GridLayout);
            app.ThismodulecreatesalotoffiguresYoucanselectformatoptionsLabel = uilabel(app.Format_GridLayout);
            app.ThismodulecreatesalotoffiguresYoucanselectformatoptionsLabel.Text = 'This module creates a lot of figures. You can select format options beforehand.';
            app.DefaultsizefigureoptionsLabel = uilabel(app.Format_GridLayout);
            app.DefaultsizefigureoptionsLabel.Text = 'Default size figure options';
            app.LinewidthEditFieldLabel = uilabel(app.Format_GridLayout);
            app.LinewidthEditFieldLabel.Text = 'Linewidth';
            app.LinewidthEditField = uieditfield(app.Format_GridLayout, 'numeric');
            app.LinewidthEditField.Limits = [1 Inf];
            app.LinewidthEditField.RoundFractionalValues = 'on';
            app.LinewidthEditField.Value = 2;
            app.AxeFontsizeEditFieldLabel = uilabel(app.Format_GridLayout);
            app.AxeFontsizeEditFieldLabel.Text = 'Axe Font size';
            app.AxeFontsizeEditField = uieditfield(app.Format_GridLayout, 'numeric');
            app.AxeFontsizeEditField.Limits = [1 Inf];
            app.AxeFontsizeEditField.RoundFractionalValues = 'on';
            app.AxeFontsizeEditField.Value = 12;
            app.LegendfontsizeEditFieldLabel = uilabel(app.Format_GridLayout);
            app.LegendfontsizeEditFieldLabel.Text = 'Legend font size';
            app.LegendfontsizeEditField = uieditfield(app.Format_GridLayout, 'numeric');
            app.LegendfontsizeEditField.Limits = [1 Inf];
            app.LegendfontsizeEditField.RoundFractionalValues = 'on';
            app.LegendfontsizeEditField.Value = 10;
            app.TitlefontsizeEditFieldLabel = uilabel(app.Format_GridLayout);
            app.TitlefontsizeEditFieldLabel.Text = 'Title font size';
            app.TitlefontsizeEditField = uieditfield(app.Format_GridLayout, 'numeric');
            app.TitlefontsizeEditField.Limits = [1 Inf];
            app.TitlefontsizeEditField.RoundFractionalValues = 'on';
            app.TitlefontsizeEditField.Value = 14;
            app.GridDropDownLabel = uilabel(app.Format_GridLayout);
            app.GridDropDownLabel.Text = 'Grid';
            app.GridDropDown = uidropdown(app.Format_GridLayout);
            app.GridDropDown.Items = {'No grid', 'Major grid', 'Major and minor grid'};
            app.GridDropDown.Value = 'Major grid';
            app.ClosefigureautomaticallyoncesavedCheckBox = uicheckbox(app.Format_GridLayout);
            app.ClosefigureautomaticallyoncesavedCheckBox.Tooltip = {''};
            app.ClosefigureautomaticallyoncesavedCheckBox.Text = 'Close figure automatically once saved';
            app.ClosefigureautomaticallyoncesavedCheckBox.Value = true;
            app.DiametercolormapDropDownLabel = uilabel(app.Format_GridLayout);
            app.DiametercolormapDropDownLabel.Text = 'Diameter color map';
            app.DiametercolormapDropDown = uidropdown(app.Format_GridLayout);
            app.DiametercolormapDropDown.Items = {'turbo', 'parula'};
            app.DiametercolormapDropDown.Value = 'turbo';
            app.DiameterbackgroundcolormapDropDownLabel = uilabel(app.Format_GridLayout);
            app.DiameterbackgroundcolormapDropDownLabel.Text = 'Diameter background color map';
            app.DiameterbackgroundcolormapDropDown = uidropdown(app.Format_GridLayout);
            app.DiameterbackgroundcolormapDropDown.Items = {'white', 'black', 'grey'};
            app.DiameterbackgroundcolormapDropDown.Value = 'white';
            app.ColormapoptionsLabel = uilabel(app.Format_GridLayout);
            app.ColormapoptionsLabel.Text = 'Color map options';
            app.SgtitlefontsizeEditFieldLabel = uilabel(app.Format_GridLayout);
            app.SgtitlefontsizeEditFieldLabel.Text = 'Sgtitle font size';
            app.SgtitlefontsizeEditField = uieditfield(app.Format_GridLayout, 'numeric');
            app.SgtitlefontsizeEditField.Limits = [1 Inf];
            app.SgtitlefontsizeEditField.RoundFractionalValues = 'on';
            app.SgtitlefontsizeEditField.Value = 18;
            app.FilesTab = uitab(app.Main_TabGroup);
            app.FilesTab.Title = 'Files';
            app.FilesTabGroup = uitabgroup(app.FilesTab);
            app.ImportTab = uitab(app.FilesTabGroup);
            app.ImportTab.Title = 'Import';
            app.GridLayout = uigridlayout(app.ImportTab);
            app.SelectsegmentationtypeofvolumestoanalyseLabel = uilabel(app.GridLayout);
            app.SelectsegmentationtypeofvolumestoanalyseLabel.Text = '2) Select segmentation type of volume(s) to analyse';
            app.SegmentationtypeDropDown = uidropdown(app.GridLayout);
            app.SegmentationtypeDropDown.Items = {'semantic', 'instance', 'semantic and instance'};
            app.SegmentationtypeDropDown.ValueChangedFcn = createCallbackFcn(app, @SegmentationtypeDropDownValueChanged, true);
            app.SegmentationtypeDropDown.Value = 'semantic';
            app.SelectanalyismodeLabel = uilabel(app.GridLayout);
            app.SelectanalyismodeLabel.Tooltip = {''};
            app.SelectanalyismodeLabel.Text = '1) Select analyis mode';
            app.ModeDropDown = uidropdown(app.GridLayout);
            app.ModeDropDown.Items = {'Set parameters for each volume independently', 'Set parameters only once and apply to all volumes', 'Apply macro''s parameters to all volumes'};
            app.ModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ModeDropDownValueChanged, true);
            app.ModeDropDown.Tooltip = {''};
            app.ModeDropDown.Value = 'Set parameters for each volume independently';
            app.ClicktoimportmacroButton = uibutton(app.GridLayout, 'push');
            app.ClicktoimportmacroButton.ButtonPushedFcn = createCallbackFcn(app, @ClicktoimportmacroButtonPushed, true);
            app.ClicktoimportmacroButton.Text = 'Click to import macro';
            app.NomacroloadedLabel = uilabel(app.GridLayout);
            app.NomacroloadedLabel.Text = 'No macro loaded';
            app.ImportfilesLabel = uilabel(app.GridLayout);
            app.ImportfilesLabel.Text = '3) Import file(s)';
            app.ClicktoimportsemanticsegmentationButton = uibutton(app.GridLayout, 'push');
            app.ClicktoimportsemanticsegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @ClicktoimportsemanticsegmentationButtonPushed, true);
            app.ClicktoimportsemanticsegmentationButton.Text = 'Click to import semantic segmentation';
            app.ClicktoimportinstancesegmentationButton = uibutton(app.GridLayout, 'push');
            app.ClicktoimportinstancesegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @ClicktoimportinstancesegmentationButtonPushed, true);
            app.ClicktoimportinstancesegmentationButton.Text = 'Click to import instance segmentation';
            app.Label_semanticdescription = uilabel(app.GridLayout);
            app.Label_semanticdescription.Tooltip = {''};
            app.Label_semanticdescription.Text = '1st label = 1st domain, 2nd label = 2nd domain, 3rd label = 3rd domain, etc.';
            app.Label_instancedescription = uilabel(app.GridLayout);
            app.Label_instancedescription.Tooltip = {'Typically, instance = particle or pore'};
            app.Label_instancedescription.Text = 'Label 0 = complementary volume, label 1 = 1st instance, label 2 = 2nd instance,..., label n = nth instance';
            app.Semantic_NofileimportedLabel = uilabel(app.GridLayout);
            app.Semantic_NofileimportedLabel.Text = 'No file imported';
            app.Instance_NofileimportedLabel = uilabel(app.GridLayout);
            app.Instance_NofileimportedLabel.Text = 'No file imported';
            app.Volumenumber_Label = uilabel(app.GridLayout);
            app.Volumenumber_Label.Text = 'Volume number: 0';
            app.SetRegionofInterestROILabel = uilabel(app.GridLayout);
            app.SetRegionofInterestROILabel.Tooltip = {'For more advanced ROI (e.g., rotation), please use the segmentation module first.'};
            app.SetRegionofInterestROILabel.Text = '4) Set Region of Interest (ROI)';
            app.ROI_UITable = uitable(app.GridLayout);
            app.ROI_UITable.ColumnName = {'Direction'; 'Number of voxels'; 'Start'; 'End'; 'Length'; 'Name'};
            app.ROI_UITable.RowName = {};
            app.ROI_UITable.ColumnEditable = [false false true true false true];
            app.ROI_UITable.CellEditCallback = createCallbackFcn(app, @ROI_UITableCellEdit, true);
            app.ROI_UITable.Tooltip = {'Direction name will be used in figures and tables. ROI can only be set up if volumes are set independently.'};
            app.SetvoxelsizeandscalingfactorLabel = uilabel(app.GridLayout);
            app.SetvoxelsizeandscalingfactorLabel.Text = '5) Set voxel size and scaling factor';
            app.VoxelsizeEditFieldLabel = uilabel(app.GridLayout);
            app.VoxelsizeEditFieldLabel.Text = 'Voxel size';
            app.VoxelsizeEditField = uieditfield(app.GridLayout, 'numeric');
            app.VoxelsizeEditField.Limits = [1e-09 Inf];
            app.VoxelsizeEditField.ValueChangedFcn = createCallbackFcn(app, @VoxelsizeEditFieldValueChanged, true);
            app.VoxelsizeEditField.Value = 1;
            app.VoxelsizeUnit_EditField = uieditfield(app.GridLayout, 'text');
            app.VoxelsizeUnit_EditField.ValueChangedFcn = createCallbackFcn(app, @VoxelsizeEditFieldValueChanged, true);
            app.VoxelsizeUnit_EditField.Tooltip = {'Unit will be used in table and figure'};
            app.VoxelsizeUnit_EditField.Value = 'um';
            app.ScalingfactorEditFieldLabel = uilabel(app.GridLayout);
            app.ScalingfactorEditFieldLabel.Text = 'Scaling factor';
            app.ScalingfactorEditField = uieditfield(app.GridLayout, 'numeric');
            app.ScalingfactorEditField.Limits = [1e-09 Inf];
            app.ScalingfactorEditField.ValueChangedFcn = createCallbackFcn(app, @VoxelsizeEditFieldValueChanged, true);
            app.ScalingfactorEditField.Tooltip = {'> 1: downscaling'; '< 1: upscaling'; '= 1: ignore'};
            app.ScalingfactorEditField.Value = 1;
            app.AssignlabelcolorandnameLabel = uilabel(app.GridLayout);
            app.AssignlabelcolorandnameLabel.Text = '6) Assign label color and name';
            app.Phasenamecolor_DropDown = uidropdown(app.GridLayout);
            app.Phasenamecolor_DropDown.Items = {'semantic segmentation', 'instance segmentation'};
            app.Phasenamecolor_DropDown.ValueChangedFcn = createCallbackFcn(app, @Phasenamecolor_DropDownValueChanged, true);
            app.Phasenamecolor_DropDown.Value = 'semantic segmentation';
            app.PhasecolornameSemantic_UITable = uitable(app.GridLayout);
            app.PhasecolornameSemantic_UITable.ColumnName = {'Phase #'; 'Volume fraction (ROI)'; 'Color'; 'Name'; 'label'; 'Reassign label'};
            app.PhasecolornameSemantic_UITable.RowName = {};
            app.PhasecolornameSemantic_UITable.ColumnEditable = [false false false true false true];
            app.PhasecolornameSemantic_UITable.CellEditCallback = createCallbackFcn(app, @PhasecolornameSemantic_UITableCellEdit, true);
            app.PhasecolornameSemantic_UITable.CellSelectionCallback = createCallbackFcn(app, @PhasecolornameSemantic_UITableCellSelection, true);
            app.PhasecolornameSemantic_UITable.Tooltip = {'Phase color and name will be used in figures and tables.'};
            app.FilesmustsharesamesizeLabel = uilabel(app.GridLayout);
            app.FilesmustsharesamesizeLabel.Text = 'Files must share same size';
            app.OptionalPanel = uipanel(app.GridLayout);
            app.OptionalPanel.Title = 'Optional';
            app.GridLayout3 = uigridlayout(app.OptionalPanel);
            app.TohelpselectingROIandscalingparametersLabel = uilabel(app.GridLayout3);
            app.TohelpselectingROIandscalingparametersLabel.Text = 'To help selecting ROI and scaling parameters';
            app.VisualizevolumeButton = uibutton(app.GridLayout3, 'push');
            app.VisualizevolumeButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizevolumeButtonPushed, true);
            app.VisualizevolumeButton.Text = 'Visualize volume';
            app.PlotvolumefractionsButton = uibutton(app.GridLayout3, 'push');
            app.PlotvolumefractionsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotvolumefractionsButtonPushed, true);
            app.PlotvolumefractionsButton.Text = 'Plot volume fractions';
            app.withDropDown_3Label = uilabel(app.GridLayout3);
            app.withDropDown_3Label.Text = 'with';
            app.visualized_ROI_withDropDown = uidropdown(app.GridLayout3);
            app.visualized_ROI_withDropDown.Items = {'original field of view', 'region of interest'};
            app.visualized_ROI_withDropDown.Value = 'original field of view';
            app.visualize_resolution_withDropDown = uidropdown(app.GridLayout3);
            app.visualize_resolution_withDropDown.Items = {'original resolution', 'after scaling'};
            app.visualize_resolution_withDropDown.Value = 'original resolution';
            app.VolumeinformationLabel = uilabel(app.GridLayout3);
            app.VolumeinformationLabel.Text = 'Volume information';
            app.Info_UITable = uitable(app.GridLayout3);
            app.Info_UITable.ColumnName = {'Information'; 'Text'};
            app.Info_UITable.RowName = {};
            app.Info_UITable.ColumnEditable = [false true];
            app.Info_UITable.Tooltip = {'This table with be saved. Use it to keep record of your different calculations.'};
            app.SetsavelocationLabel = uilabel(app.GridLayout);
            app.SetsavelocationLabel.Tooltip = {'Results will be saved in main folder\volume folder\. You can reuse the same main folder for different volumes.'};
            app.SetsavelocationLabel.Text = '7) Set save location';
            app.SelectmainfolderButton = uibutton(app.GridLayout, 'push');
            app.SelectmainfolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectmainfolderButtonPushed, true);
            app.SelectmainfolderButton.Tooltip = {'Results will be saved in main folder\volume folder\. You can reuse the same main folder for different volumes.'};
            app.SelectmainfolderButton.Text = 'Select main folder';
            app.Mainfolder_Label = uilabel(app.GridLayout);
            app.Mainfolder_Label.Tooltip = {'Results will be saved in main folder\volume folder\. You can reuse the same main folder for different volumes.'};
            app.Mainfolder_Label.Text = 'Not defined';
            app.VolumefolderEditFieldLabel = uilabel(app.GridLayout);
            app.VolumefolderEditFieldLabel.Tooltip = {'Results will be saved in main folder\volume folder\. You can reuse the same main folder for different volumes.'};
            app.VolumefolderEditFieldLabel.Text = 'Volume folder';
            app.VolumefolderEditField = uieditfield(app.GridLayout, 'text');
            app.VolumefolderEditField.Tooltip = {'Results will be saved in main folder\volume folder\. You can reuse the same main folder for different volumes.'};
            app.SaveimportsettingstocontinueLabel = uilabel(app.GridLayout);
            app.SaveimportsettingstocontinueLabel.Text = '8) Save import settings to continue';
            app.Import_SavesettingsButton = uibutton(app.GridLayout, 'push');
            app.Import_SavesettingsButton.ButtonPushedFcn = createCallbackFcn(app, @Import_SavesettingsButtonPushed, true);
            app.Import_SavesettingsButton.Text = 'Save settings';
            app.Import_indication = uilabel(app.GridLayout);
            app.Import_indication.Text = 'What''s next ?';
            app.Scaledvoxelsize_Label = uilabel(app.GridLayout);
            app.Scaledvoxelsize_Label.Text = 'Scaled voxel size: n/a';
            app.ROI_Label = uilabel(app.GridLayout);
            app.ROI_Label.Text = 'ROI: n/a';
            app.PhasecolornameInstance_UITable = uitable(app.GridLayout);
            app.PhasecolornameInstance_UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'; 'Column 5'; 'Column 6'};
            app.PhasecolornameInstance_UITable.RowName = {};
            app.PhasecolornameInstance_UITable.CellEditCallback = createCallbackFcn(app, @PhasecolornameInstance_UITableCellEdit, true);
            app.PhasecolornameInstance_UITable.CellSelectionCallback = createCallbackFcn(app, @PhasecolornameInstance_UITableCellSelection, true);
            app.PhasecolornameInstance_UITable.Tooltip = {'Phase color and name will be used in figures and tables. You cannot reassign labels for the instance segmentation.'};
            app.ItwillresetallparametersLabel = uilabel(app.GridLayout);
            app.ItwillresetallparametersLabel.Text = 'It will reset all parameters';
            app.ImportedTab = uitab(app.FilesTabGroup);
            app.ImportedTab.Title = 'Imported';
            app.GridLayout_imported = uigridlayout(app.ImportedTab);
            app.VisualizeallfilesButton = uibutton(app.GridLayout_imported, 'push');
            app.VisualizeallfilesButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizeallfilesButtonPushed, true);
            app.VisualizeallfilesButton.Text = 'Visualize all files';
            app.VisualizeROI_DropDown = uidropdown(app.GridLayout_imported);
            app.VisualizeROI_DropDown.Items = {'with original field of view', 'with region of interest'};
            app.VisualizeROI_DropDown.Value = 'with original field of view';
            app.Imported_UITable = uitable(app.GridLayout_imported);
            app.Imported_UITable.ColumnName = {'Semantic'; 'Phase#'; 'Instance'; 'Instance#'; 'Tag'; 'ROI Size'; 'Voxel#'; 'ROI size square (2D) or cubic (3D) root'; 'Voxel size'; 'Calculation order'};
            app.Imported_UITable.RowName = {};
            app.Imported_UITable.ColumnEditable = [false false false false true false false false false true];
            app.Imported_UITable.CellEditCallback = createCallbackFcn(app, @Imported_UITableCellEdit, true);
            app.Label_3 = uilabel(app.GridLayout_imported);
            app.Label_3.Text = {'Tag is the substring located after the last underscore of the filename. Tag must be a number For instance, for'; '"MyMicrostructure_year2024_5.tif", tag is 5. It can be used as an axis in the correlation module. For instance, if tag is time, it will allow'; 'to plot evolution of metrics calculated in this module with time. You can also manually edit a tag value in the table below. '};
            app.TagunitEditFieldLabel = uilabel(app.GridLayout_imported);
            app.TagunitEditFieldLabel.Text = 'Tag unit';
            app.TagunitEditField = uieditfield(app.GridLayout_imported, 'text');
            app.VisualizationLabel = uilabel(app.GridLayout_imported);
            app.VisualizationLabel.Text = 'Visualization';
            app.TagLabel = uilabel(app.GridLayout_imported);
            app.TagLabel.Text = 'Tag';
            app.Label_4 = uilabel(app.GridLayout_imported);
            app.Label_4.Text = '';
            app.Setcalculationorderto0nottoanalyseafileLabel = uilabel(app.GridLayout_imported);
            app.Setcalculationorderto0nottoanalyseafileLabel.Text = 'Set calculation order to 0 not to analyse a file';
            app.Label_5 = uilabel(app.GridLayout_imported);
            app.Label_5.Text = 'Confirm your choice to continue';
            app.Imported_SavesettingsButton = uibutton(app.GridLayout_imported, 'push');
            app.Imported_SavesettingsButton.ButtonPushedFcn = createCallbackFcn(app, @Imported_SavesettingsButtonPushed, true);
            app.Imported_SavesettingsButton.Text = 'Save settings';
            app.itwillinitializeorresetthesubsequenttabsLabel = uilabel(app.GridLayout_imported);
            app.itwillinitializeorresetthesubsequenttabsLabel.Text = '(it will initialize or reset the subsequent tabs)';
            app.Label_6 = uilabel(app.GridLayout_imported);
            app.Label_6.Text = 'Instructions: all actions are optional. You only need to click on the bottom right green button to proceed.';
            app.SearchtagButton = uibutton(app.GridLayout_imported, 'push');
            app.SearchtagButton.ButtonPushedFcn = createCallbackFcn(app, @SearchtagButtonPushed, true);
            app.SearchtagButton.Text = 'Search tag';
            app.PhaseandInstancecorrespondtotheimportednotnecessarlytheROILabel = uilabel(app.GridLayout_imported);
            app.PhaseandInstancecorrespondtotheimportednotnecessarlytheROILabel.Text = 'Phase# and Instance# correspond to the imported volume (not necessarly the ROI)';
            app.VolumeTab = uitab(app.Main_TabGroup);
            app.VolumeTab.Title = 'Volume';
            app.TabGroup2 = uitabgroup(app.VolumeTab);
            app.VolumefractionTab = uitab(app.TabGroup2);
            app.VolumefractionTab.Title = 'Volume fraction';
            app.Volumefraction_GridLayout = uigridlayout(app.VolumefractionTab);
            app.VolumefractionInstructions_Label = uilabel(app.Volumefraction_GridLayout);
            app.VolumefractionInstructions_Label.Text = 'Select phases for which you want to calculate volume fractions';
            app.Volumefractions_AllphasesCheckBox = uicheckbox(app.Volumefraction_GridLayout);
            app.Volumefractions_AllphasesCheckBox.ValueChangedFcn = createCallbackFcn(app, @Volumefractions_AllphasesCheckBoxValueChanged, true);
            app.Volumefractions_AllphasesCheckBox.Text = 'All phases';
            app.Volumefraction_Semantic_UITable = uitable(app.Volumefraction_GridLayout);
            app.Volumefraction_Semantic_UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.Volumefraction_Semantic_UITable.RowName = {};
            app.Volumefraction_Semantic_UITable.Tooltip = {'Semantic volumes'};
            app.Volumefraction_Instance_UITable = uitable(app.Volumefraction_GridLayout);
            app.Volumefraction_Instance_UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.Volumefraction_Instance_UITable.RowName = {};
            app.Volumefraction_Instance_UITable.Tooltip = {'Instance volumes'};
            app.ConnectivityTab = uitab(app.TabGroup2);
            app.ConnectivityTab.Title = 'Connectivity';
            app.TortuosityfactorTab = uitab(app.TabGroup2);
            app.TortuosityfactorTab.Title = 'Tortuosity factor';
            app.FractaldimensionTab = uitab(app.TabGroup2);
            app.FractaldimensionTab.Title = 'Fractal dimension';
            app.BoxingmethodLabel = uilabel(app.FractaldimensionTab);
            app.BoxingmethodLabel.Text = 'Boxing method';
            app.ParticleTab = uitab(app.Main_TabGroup);
            app.ParticleTab.Title = 'Particle';
            app.TabGroup4 = uitabgroup(app.ParticleTab);
            app.DiameterTab = uitab(app.TabGroup4);
            app.DiameterTab.Title = 'Diameter';
            app.MetricsperparticleTab = uitab(app.TabGroup4);
            app.MetricsperparticleTab.Title = 'Metrics per particle';
            app.KineticTab = uitab(app.Main_TabGroup);
            app.KineticTab.Title = 'Kinetic';
            app.TabGroup3 = uitabgroup(app.KineticTab);
            app.SpecificsurfaceareaTab = uitab(app.TabGroup3);
            app.SpecificsurfaceareaTab.Title = 'Specific surface area';
            app.SpecificinterfaceareaTab = uitab(app.TabGroup3);
            app.SpecificinterfaceareaTab.Title = 'Specific interface area';
            app.TriplephaseboundarylengthdensityTab = uitab(app.TabGroup3);
            app.TriplephaseboundarylengthdensityTab.Title = 'Triple phase boundary length density';
            app.ErrorsTab = uitab(app.Main_TabGroup);
            app.ErrorsTab.Title = 'Errors';
            app.TabGroup5 = uitabgroup(app.ErrorsTab);
            app.ImageresolutionTab = uitab(app.TabGroup5);
            app.ImageresolutionTab.Title = 'Image resolution';
            app.Imageresolution_GridLayout = uigridlayout(app.ImageresolutionTab);
            app.SelectedmicrostructurepropertiesarecalculatedattheLabel = uilabel(app.Imageresolution_GridLayout);
            app.SelectedmicrostructurepropertiesarecalculatedattheLabel.Text = {'Selected microstructure properties are calculated at the original image resolution set in Files/Import. However, this resolution may not be high enough and thus it can induce an error. You can estimate this error by downscaling'; 'the image and then plot the calculated properties as function of the voxel size (ideally, you would have access to several imaging of the same material performed at different resolution).'; ''; 'Image resolution mostly modify interfaces, while keeping relatively intact volumes, therefore surface (e.g., interface area) and linear (e.g. TPBL) properties are much more impacted than volume (e.g. volume fractions, tortuosity)'; 'properties. Surface and linear properties can exhibit a fractal behavior, as more details are revealed with finer image resolutions (until a cut-off resolution is reached, above which the fractal behavior collapses for real materials).'; 'For such fractal properties, it is recommended not to extrapolate to a zero voxel size, while properties converging with voxel size (typically volume properties) can be extrapolated safely.'};
            app.VolumeselectionDropDownLabel_2 = uilabel(app.Imageresolution_GridLayout);
            app.VolumeselectionDropDownLabel_2.Text = 'Volume selection ';
            app.Voxelsize_VolumeselectionDropDown = uidropdown(app.Imageresolution_GridLayout);
            app.Voxelsize_VolumeselectionDropDown.Items = {};
            app.Voxelsize_VolumeselectionDropDown.ValueChangedFcn = createCallbackFcn(app, @Voxelsize_VolumeselectionDropDownValueChanged, true);
            app.Voxelsize_VolumeselectionDropDown.Value = {};
            app.Voxelsize_UITable = uitable(app.Imageresolution_GridLayout);
            app.Voxelsize_UITable.ColumnName = {'Property'; 'Scaling factor'; 'Voxel size'; 'Extrapolation'; 'Topology dimension'; 'Calculate fractal dimension'};
            app.Voxelsize_UITable.RowName = {};
            app.Voxelsize_UITable.ColumnEditable = [false true false true false true];
            app.Voxelsize_UITable.CellEditCallback = createCallbackFcn(app, @Voxelsize_UITableCellEdit, true);
            app.Voxelsize_Applytoallvolumes_Button = uibutton(app.Imageresolution_GridLayout, 'push');
            app.Voxelsize_Applytoallvolumes_Button.ButtonPushedFcn = createCallbackFcn(app, @Voxelsize_Applytoallvolumes_ButtonPushed, true);
            app.Voxelsize_Applytoallvolumes_Button.Text = 'Apply options above to all volumes';
            app.Label_28 = uilabel(app.Imageresolution_GridLayout);
            app.Label_28.Text = 'Fractal dimension F of a property p can be calculated using the Mandelbrot formula:';
            app.Image2_2 = uiimage(app.Imageresolution_GridLayout);
            app.Image2_2.ImageSource = fullfile(pathToMLAPP, 'Mandelbrot.png');
            app.TheoriginalconceptLabel_2 = uilabel(app.Imageresolution_GridLayout);
            app.TheoriginalconceptLabel_2.Text = 'The original concept:';
            app.andrevisitedformicrostructureLabel_2 = uilabel(app.Imageresolution_GridLayout);
            app.andrevisitedformicrostructureLabel_2.Text = '...and revisited for microstructure:';
            app.Label_29 = uilabel(app.Imageresolution_GridLayout);
            app.Label_29.Text = {'With m a scaling factor, T the topology dimension (e.g., T=3 for volume fraction, T=2 for'; 'surface area, T=1 for TPBL), and v the voxel size. Fractal propension P = F-T >= 0'};
            app.Hyperlink4_2 = uihyperlink(app.Imageresolution_GridLayout);
            app.Hyperlink4_2.URL = 'https://doi.org/10.1016/j.nanoen.2017.06.028';
            app.Hyperlink4_2.Text = 'A. Bertei et al., Nano Energy, 2017';
            app.Hyperlink3_2 = uihyperlink(app.Imageresolution_GridLayout);
            app.Hyperlink3_2.URL = 'https://doi.org/10.1126/science.156.3775.636';
            app.Hyperlink3_2.Text = 'B. Mandelbrot, Science, 1967 (How Long Is the Coast of Britain ?)';
            app.TocompareafractalpropertyF0twoormorevolumesLabel = uilabel(app.Imageresolution_GridLayout);
            app.TocompareafractalpropertyF0twoormorevolumesLabel.Text = {'To compare a fractal property (F>0) between two or more volumes, (e.g., which volume has'; 'the higher value), fractal propension P should be calculated. For instance, if at the same'; 'image resolution, two materials have the same p value, but one has a higher P, then it is likely'; 'this material has also a higher actual p value (the variation with voxel size is higher).'};
            app.ExtrapolationchoicenonelinearquadraticcubicLabel = uilabel(app.Imageresolution_GridLayout);
            app.ExtrapolationchoicenonelinearquadraticcubicLabel.Text = 'Extrapolation choice: none, linear, quadratic, cubic';
            app.RepresentativityTab = uitab(app.TabGroup5);
            app.RepresentativityTab.Title = 'Representativity';
            app.Representativity_GridLayout = uigridlayout(app.RepresentativityTab);
            app.MacroTab = uitab(app.Main_TabGroup);
            app.MacroTab.Title = 'Macro';
            app.Macro_GridLayout = uigridlayout(app.MacroTab);
            app.YoucansaveoptionsinamacroandthenapplyittoothersvolumesLabel = uilabel(app.Macro_GridLayout);
            app.YoucansaveoptionsinamacroandthenapplyittoothersvolumesLabel.Text = 'You can save options in a macro and then apply it to others volumes. All parameters except ROI selection will be re-used. Only parameters for the first volume are saved.';
            app.MacronameEditFieldLabel = uilabel(app.Macro_GridLayout);
            app.MacronameEditFieldLabel.Text = 'Macro name';
            app.MacronameEditField = uieditfield(app.Macro_GridLayout, 'text');
            app.MacronameEditField.Value = 'characterization_macro';
            app.SavefolderButton = uibutton(app.Macro_GridLayout, 'push');
            app.SavefolderButton.Text = 'Save folder';
            app.NotdefinedLabel = uilabel(app.Macro_GridLayout);
            app.NotdefinedLabel.Text = 'Not defined';
            app.SavemacroButton = uibutton(app.Macro_GridLayout, 'push');
            app.SavemacroButton.Text = 'Save macro';
            app.RunTab = uitab(app.Main_TabGroup);
            app.RunTab.Title = 'Run';
            app.Run_GridLayout = uigridlayout(app.RunTab);
            app.Run_Title = uilabel(app.Run_GridLayout);
            app.Run_Title.Text = 'Run all calculations';
            app.Run_Label = uilabel(app.Run_GridLayout);
            app.Run_Label.Text = 'Once you have setup all previous tabs, you can click on the button below to characterize all volumes sequentially';
            app.Run_button = uibutton(app.Run_GridLayout, 'push');
            app.Run_button.ButtonPushedFcn = createCallbackFcn(app, @Run_buttonButtonPushed, true);
            app.Run_button.Tooltip = {''};
            app.Run_button.Text = '>>> CLICK TO START CALCULATIONS <<<';
            app.RunStatut_UITable = uitable(app.Run_GridLayout);
            app.RunStatut_UITable.ColumnName = {'Volume'; 'Statut'; 'Started'; 'Wallclock time'};
            app.RunStatut_UITable.RowName = {};
            app.Run_SearchtagCheckBox = uicheckbox(app.Run_GridLayout);
            app.Run_SearchtagCheckBox.Text = 'Search tag';
            app.HelpTab = uitab(app.Main_TabGroup);
            app.HelpTab.Title = 'Help';
            app.GridLayout5_3 = uigridlayout(app.HelpTab);
            app.Label_19 = uilabel(app.GridLayout5_3);
            app.Label_19.Text = 'If you run into problems, please read the open issues listed in the GitHub webpage. If your problem is not listed, please open a new issue.';
            app.Hyperlink_8 = uihyperlink(app.GridLayout5_3);
            app.Hyperlink_8.URL = 'https://github.com/NREL/MATBOX_Microstructure_analysis_toolbox/issues';
            app.Hyperlink_8.Text = 'https://github.com/NREL/MATBOX_Microstructure_analysis_toolbox/issues';
            app.FAQimageexampleslinktotutorialsLabel = uilabel(app.GridLayout5_3);
            app.FAQimageexampleslinktotutorialsLabel.Text = 'FAQ / image examples / link to tutorials';
            app.ReferencesTab = uitab(app.Main_TabGroup);
            app.ReferencesTab.Title = 'References';
            app.GridLayout5_4 = uigridlayout(app.ReferencesTab);
            app.Label_20 = uilabel(app.GridLayout5_4);
            app.Label_20.Text = 'Below is a selection of journal articles that rely on MATBOX''s algorithms for microstructure characterization. You can read them to have a better understanding of the different methods used in MATBOX.';
            app.Label_21 = uilabel(app.GridLayout5_4);
            app.Label_21.Text = {'- Connectivity: start with [1] for a simple analysis (i.e., isolated, unknown, and connected clusters). For an in-depth analysis focused on the solid phase (percolation, impact of additives, impact on effective solid conductivity) see [6].'; '- Particle size: start with [1] for standard methods (covariance, c-psd, and watershed). For an in-depth discussion of the different particle size methods see [3].'; '- Tortuosity factor: start with [1, 2]. For impact of additives on tortuosity factor and comparison with experimentally deternined and macroscale fitted values see [2,6]. Have a look at the supplementary material of [2] to'; 'see difference between FEniCS (Finite element method) and MATBOX/Taufactor (Finite difference method). For in-depth discussion on relationships between microstructure and tortuosity see [3].'; '- Specific surface area: start with [1]. For correlation with porosity and comparison with baseline theoritical relationship see [4].'; '- Image resolution error: see [3] and supplementary material of [2].'; '- Representativity analysis: see [1, 5].'};
            app.Hyperlink_9 = uihyperlink(app.GridLayout5_4);
            app.Hyperlink_9.URL = 'https://doi.org/10.1149/1945-7111/ac7ef9';
            app.Hyperlink_9.Text = {'[6] F. L. E. Usseglio-Viretta, A. M. Colclasure, A. R. Dunlop, S. E. Trask, A. Jansen, D. P. Abraham, M-T F. Rodrigues, E. J. Dufek, T. Tanim, P. Chinnam, Y. Ha and K. Smith'; 'Carbon-Binder Weight Loading Optimization for Improved Lithium-Ion Battery Rate Capability, Journal of the Electrochemical Society, 2022'};
            app.Hyperlink_10 = uihyperlink(app.GridLayout5_4);
            app.Hyperlink_10.URL = 'https://doi.org/10.1149/1945-7111/ab913b';
            app.Hyperlink_10.Text = {'[3] F. L. E. Usseglio-Viretta, D. P. Finegan, A. Colclasure, T. M. M. Heenan, D. Abraham, P. Shearing, and K. Smith, Quantitative Relationships Between Pore Tortuosity, Pore Topology, and Solid Particle'; 'Morphology Using a Novel Discrete Particle Size Algorithm, Journal of the Electrochemical Society, 167, 2020'};
            app.Hyperlink_11 = uihyperlink(app.GridLayout5_4);
            app.Hyperlink_11.URL = 'https://doi.org/10.1149/2.0731814jes';
            app.Hyperlink_11.Text = {'[2] F. L. E. Usseglio-Viretta, A. Colclasure, A. N. Mistry, K. P. Y. Claver, F. Pouraghajan, D. P. Finegan, T. M. M. Heenan, D. Abraham, P. P. Mukherjee, D. Wheeler, P. Shearing, S. J. Cooper, and K. Smith'; 'Resolving the Discrepancy in Tortuosity Factor Estimation for Li-Ion Battery Electrodes through Micro-Macro Modeling and Experiment, Journal of the electrochemical Society, 165, 2018'};
            app.Hyperlink_12 = uihyperlink(app.GridLayout5_4);
            app.Hyperlink_12.URL = 'https://doi.org/10.1149/07711.1095ecst';
            app.Hyperlink_12.Text = '[1] F. L. E. Usseglio-Viretta and K. Smith, Quantitative Microstructure Characterization of a NMC Electrode, ECS Transactions, 77, 2017';
            app.Hyperlink_13 = uihyperlink(app.GridLayout5_4);
            app.Hyperlink_13.URL = 'https://doi.org/10.1002/ente.202000666';
            app.Hyperlink_13.Text = {'[4] D. C. Robertson, L. Flores, A. R. Dunlop, S. E. Trask, F. L. E. Usseglio-Viretta, A. M. Colclasure, Z. Yang, and I. Bloom'; 'Effect of Anode Porosity and Temperature on the Performance and Lithium Plating During Fast-Charging of Lithium-Ion Cells, Energy Technology, 2020'};
            app.Hyperlink_14 = uihyperlink(app.GridLayout5_4);
            app.Hyperlink_14.URL = 'https://doi.org/10.1016/j.jpowsour.2020.229101';
            app.Hyperlink_14.Text = {'[5] H. Xu, F. L.E. Usseglio-Viretta, S. Kench, S. J. Cooper, and D. P. Finegan'; 'Microstructure reconstruction of battery polymer separators by fusing 2D and 3D image data for transport property analysis, Journal of Power Sources, 480, 2020'};
            app.HowtoquoteTab = uitab(app.Main_TabGroup);
            app.HowtoquoteTab.Title = 'How to quote';
            app.GridLayout5_2 = uigridlayout(app.HowtoquoteTab);
            app.Label_15 = uilabel(app.GridLayout5_2);
            app.Label_15.Text = 'If you use these algorithms or report results produced thanks to this algorithms, please quote it as follow:';
            app.Label_16 = uilabel(app.GridLayout5_2);
            app.Label_16.Text = {'F. L. E. Usseglio-Viretta, P. Patel, E. Bernhardt, A Mistry, P. P. Mukherjee, J. Allen, S. J. Cooper, J. Laurencin, and K. Smith'; 'MATBOX: An Open-source Microstructure Analysis Toolbox for microstructure generation, segmentation, characterization, visualization, correlation, and meshing'; 'SoftwareX, 17, 2022'};
            app.Hyperlink_6 = uihyperlink(app.GridLayout5_2);
            app.Hyperlink_6.URL = 'https://doi.org/10.1016/j.softx.2021.100915';
            app.Hyperlink_6.Text = 'https://doi.org/10.1016/j.softx.2021.100915';
            app.Label_17 = uilabel(app.GridLayout5_2);
            app.Label_17.Text = 'If you calculate tortuosity factor, please ALSO quote:';
            app.Label_18 = uilabel(app.GridLayout5_2);
            app.Label_18.Text = {'S. J. Cooper, A. Bertei, P. R. Shearing, J. A. Kilner, and N. P. Brandon'; 'TauFactor: An open-source application for calculating tortuosity factors from tomographic data'; 'SoftwareX, 5, 2016'};
            app.Hyperlink_7 = uihyperlink(app.GridLayout5_2);
            app.Hyperlink_7.URL = 'https://doi.org/10.1016/j.softx.2016.09.002';
            app.Hyperlink_7.Text = 'https://doi.org/10.1016/j.softx.2016.09.002';
            app.AboutTab = uitab(app.Main_TabGroup);
            app.AboutTab.Title = 'About';
            app.GridLayout5 = uigridlayout(app.AboutTab);
            app.Label_7 = uilabel(app.GridLayout5);
            app.Label_7.Text = 'National Renewable Energy Laboratory (NREL) developed the Microstructure Analysis Toolbox (MATBOX) to analyze electrode microstructures for lithium-ion (Li-ion) batteries and other 3D heterogeneous materials.';
            app.Image = uiimage(app.GridLayout5);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = 'logo_NREL.png';
            app.Hyperlink = uihyperlink(app.GridLayout5);
            app.Hyperlink.URL = 'https://www.nrel.gov/transportation/matbox.html';
            app.Hyperlink.Text = 'MATBOX official webpage';
            app.Hyperlink_2 = uihyperlink(app.GridLayout5);
            app.Hyperlink_2.URL = 'https://github.com/NREL/MATBOX_Microstructure_analysis_toolbox';
            app.Hyperlink_2.Text = 'MATBOX GitHub repository';
            app.MATBOXinstalledversionLabel = uilabel(app.GridLayout5);
            app.MATBOXinstalledversionLabel.Text = 'MATBOX installed version:';
            app.Localversion = uilabel(app.GridLayout5);
            app.Localversion.Text = '2.0 beta January 2024';
            app.Onlineversion_Label = uilabel(app.GridLayout5);
            app.Onlineversion_Label.Text = '';
            app.Label_8 = uilabel(app.GridLayout5);
            app.Label_8.Text = 'If you like this tool, please consider ''Star'' the GitHub repository to increase its visbility.';
            app.Label_9 = uilabel(app.GridLayout5);
            app.Label_9.Text = 'You can register there (optional). You will be notified when major updates are released.';
            app.Hyperlink2 = uihyperlink(app.GridLayout5);
            app.Hyperlink2.URL = 'https://www.nrel.gov/transportation/energy-storage.html';
            app.Hyperlink2.Text = 'NREL energy storage group';
            app.ClicktoseethelicenceButton = uibutton(app.GridLayout5, 'push');
            app.ClicktoseethelicenceButton.ButtonPushedFcn = createCallbackFcn(app, @ClicktoseethelicenceButtonPushed, true);
            app.ClicktoseethelicenceButton.Text = 'Click to see the licence';
            app.NRELSoftwareRecordofInventionSWR2076Label = uilabel(app.GridLayout5);
            app.NRELSoftwareRecordofInventionSWR2076Label.Text = 'NREL Software Record of Invention: SWR-20-76';
            app.BSDLicenceLabel = uilabel(app.GridLayout5);
            app.BSDLicenceLabel.Text = 'BSD Licence';
            app.CheckonlineversionButton = uibutton(app.GridLayout5, 'push');
            app.CheckonlineversionButton.ButtonPushedFcn = createCallbackFcn(app, @CheckonlineversionButtonPushed, true);
            app.CheckonlineversionButton.Tooltip = {'You need to be connected to internet.'};
            app.CheckonlineversionButton.Text = 'Check online version';
            app.Label_30 = uilabel(app.MicrostructurecharacterizationUIFigure);
            app.Label_30.Text = {'Volume fraction:'; '- add rve'; '- check several files'; '- check set parameter for one file'; '- set macro'; '- check macro'};
            app.SetvolumefractionerrorsmacrotagrunthenaddotherpropertiesLabel = uilabel(app.MicrostructurecharacterizationUIFigure);
            app.SetvolumefractionerrorsmacrotagrunthenaddotherpropertiesLabel.Text = {'Set volume fraction'; 'errors'; 'macro'; 'tag'; 'run'; ''; 'Set metrics per particle'; ''; 'then add other properties'};
            % Show the figure after all components are created
            app.MicrostructurecharacterizationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Microstructure_characterization2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MicrostructurecharacterizationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MicrostructurecharacterizationUIFigure)
        end
    end
end