classdef Microstructure_generation_additives < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Microstructure_additive_phase_generation  matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        InstructionsTab                 matlab.ui.container.Tab
        Load_instructions_2             matlab.ui.control.Label
        Load_title_3                    matlab.ui.control.Label
        LoadvolumeandvolumefractionTab  matlab.ui.container.Tab
        GenerateInConnectedCluster      matlab.ui.control.CheckBox
        VisualizeloadedmicrostructureButton  matlab.ui.control.Button
        Load_Additive_id                matlab.ui.control.NumericEditField
        AdditiveidLabel                 matlab.ui.control.Label
        Load_targetvolumefraction       matlab.ui.control.NumericEditField
        TargetvolumefractionLabel_3     matlab.ui.control.Label
        Load_nanoporosity               matlab.ui.control.NumericEditField
        NanoporosityLabel_3             matlab.ui.control.Label
        Load_volumefraction_dense       matlab.ui.control.NumericEditField
        VolumefractiondenseLabel_3      matlab.ui.control.Label
        Load_NewphaseparameterLabel     matlab.ui.control.Label
        Load_ExistingphasesLabel        matlab.ui.control.Label
        Load_ExistingphasesUITable      matlab.ui.control.Table
        Load_VolumeloaedLabel           matlab.ui.control.Label
        Load_LoadvolumeButton           matlab.ui.control.Button
        Load_title                      matlab.ui.control.Label
        Load_instructions               matlab.ui.control.Label
        BetweenneighboursparticlesTab   matlab.ui.container.Tab
        Label_4                         matlab.ui.control.Label
        Bridged_AchievedEditField       matlab.ui.control.NumericEditField
        AchievedEditFieldLabel          matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        ProgressionGauge                matlab.ui.control.LinearGauge
        ProgressionGaugeLabel           matlab.ui.control.Label
        Bridge_generation               matlab.ui.control.Button
        Bridge_parameters_UITable       matlab.ui.control.Table
        Bridge_targetvolumefraction     matlab.ui.control.NumericEditField
        TargetvolumefractionLabel       matlab.ui.control.Label
        Bridge_NewphaseparameterLabel   matlab.ui.control.Label
        Bridge_title                    matlab.ui.control.Label
        Bridge_instructions             matlab.ui.control.Label
        EnergycriterionTab              matlab.ui.container.Tab
        PD_ProgressionGauge             matlab.ui.control.LinearGauge
        ProgressionGauge_2Label         matlab.ui.control.Label
        PD_TextArea                     matlab.ui.control.TextArea
        PD_generation                   matlab.ui.control.Button
        PD_targetvolumefraction         matlab.ui.control.NumericEditField
        TargetvolumefractionLabel_4     matlab.ui.control.Label
        PD_AchievedEditField            matlab.ui.control.NumericEditField
        AchievedEditFieldLabel_2        matlab.ui.control.Label
        PD_parameters_UITable           matlab.ui.control.Table
        PD_NewphaseparameterLabel       matlab.ui.control.Label
        PD_instructions                 matlab.ui.control.Label
        PD_title                        matlab.ui.control.Label
        CoatingTab                      matlab.ui.container.Tab
        Coating_AchievedEditField_2     matlab.ui.control.NumericEditField
        AchievedEditFieldLabel_4        matlab.ui.control.Label
        Coating_targetvolumefraction_2  matlab.ui.control.NumericEditField
        TargetvolumefractionLabel_6     matlab.ui.control.Label
        IfLabel_3                       matlab.ui.control.Label
        Coating_TargetDropDown          matlab.ui.control.DropDown
        TargetisDropDownLabel           matlab.ui.control.Label
        Coating_statut                  matlab.ui.control.TextArea
        Coating_generation              matlab.ui.control.Button
        Coating_targetvolumefraction    matlab.ui.control.NumericEditField
        TargetvolumefractionLabel_5     matlab.ui.control.Label
        Coating_AchievedEditField       matlab.ui.control.NumericEditField
        AchievedEditFieldLabel_3        matlab.ui.control.Label
        Coating_DepositDropDown_2       matlab.ui.control.DropDown
        IfLabel_2                       matlab.ui.control.Label
        IfLabel                         matlab.ui.control.Label
        Coating_DepositDropDown         matlab.ui.control.DropDown
        DepositDropDownLabel            matlab.ui.control.Label
        Coating_instructions            matlab.ui.control.Label
        Coating_title                   matlab.ui.control.Label
        OutcomeTab                      matlab.ui.container.Tab
        O_savefolderLabel               matlab.ui.control.Label
        O_savefolderButton              matlab.ui.control.Button
        O_filename_save                 matlab.ui.control.EditField
        O_FilenameLabel                 matlab.ui.control.Label
        O_Label                         matlab.ui.control.Label
        O_visualize                     matlab.ui.control.Button
        O_vf_Label                      matlab.ui.control.Label
        O_instructions                  matlab.ui.control.Label
        O_vf_UITable                    matlab.ui.control.Table
        O_savebutton                    matlab.ui.control.Button
        O_title                         matlab.ui.control.Label
        ReferencesTab                   matlab.ui.container.Tab
        Hyperlink_5                     matlab.ui.control.Hyperlink
        Label_9                         matlab.ui.control.Label
        Hyperlink_3                     matlab.ui.control.Hyperlink
        Label_7                         matlab.ui.control.Label
        Coating_instructions_2          matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        O_title_3                       matlab.ui.control.Label
        HowtoquoteTab                   matlab.ui.container.Tab
        Hyperlink_2                     matlab.ui.control.Hyperlink
        Label_3                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Hyperlink                       matlab.ui.control.Hyperlink
        Label                           matlab.ui.control.Label
        SelectasolidphaseandsetthenumberofLabel  matlab.ui.control.Label
        Load_title_2                    matlab.ui.control.Label
        HelpTab                         matlab.ui.container.Tab
        Load_title_4                    matlab.ui.control.Label
        Hyperlink_7                     matlab.ui.control.Hyperlink
        Hyperlink_6                     matlab.ui.control.Hyperlink
        Label_13                        matlab.ui.control.Label
        Label_12                        matlab.ui.control.Label
        Label_11                        matlab.ui.control.Label
        Label_10                        matlab.ui.control.Label
        OpendocumentationButton_2       matlab.ui.control.Button
        AboutTab                        matlab.ui.container.Tab
        OnlineversioncheckLabel         matlab.ui.control.Label
        Localversion                    matlab.ui.control.Label
        MATBOXinstalledversionLabel     matlab.ui.control.Label
        Hyperlink_9                     matlab.ui.control.Hyperlink
        Hyperlink_8                     matlab.ui.control.Hyperlink
        NREL_logo                       matlab.ui.control.Image
        Label_14                        matlab.ui.control.Label
        O_title_2                       matlab.ui.control.Label
    end

    
    properties (Access = public)
        Microstructure=[]; % Microstructure initialized empty
        Microstructure_initial=[]; % Microstructure initialized empty
        History_operations=[]; History_parameters=[]; History_elapsedtime=[]; % Log
        Savefolder=[]; % save folder
        filesave_ini=[]; % file savename
    end
    
    methods (Access = private)
        
        function [] = Turn_on_off(app,status_GUI,tab)
            child_handles = allchild(tab); % Get all children
            [n_children,~]=size(child_handles);
            for k=1:1:n_children
                child_handles(k).Visible=status_GUI;
                if isprop(child_handles(k),'Enable')
                    child_handles(k).Enable=status_GUI;
                end
            end
            
            if strcmp(tab.Title,'OutcomeTab')
                app.O_savebutton.Enable='off';
                app.O_visualize.Enable='off';
            end            
        end
        
        
        function [] = check_turnonoff_tab(app)
            idx = find( cell2mat(app.Load_ExistingphasesUITable.Data(:,3))==1 );
            target_volume_fraction = app.Load_targetvolumefraction.Value;
            if ~isempty(idx) && target_volume_fraction<=cell2mat(app.Load_ExistingphasesUITable.Data(idx,2))
                statut_tab = 'on';
            else
                statut_tab = 'off';
            end
            app.Turn_on_off(statut_tab,app.BetweenneighboursparticlesTab)
            app.Turn_on_off(statut_tab,app.EnergycriterionTab)    
            app.Turn_on_off(statut_tab,app.CoatingTab)
            app.Turn_on_off(statut_tab,app.OutcomeTab)            
        end
        
        function [] = finished_generation(app)
            % Update volume fractions
            phases_before = unique(app.Microstructure_initial);
            phases_after = unique(app.Microstructure);
            phases = unique([phases_before; phases_after]);
            number_phase = length(phases);
            tabledata=zeros(number_phase,3);
            for current_phase=1:1:number_phase
                tabledata(current_phase,1)=phases(current_phase);
                tabledata(current_phase,2)=sum(sum(sum( app.Microstructure_initial==phases(current_phase)  ))) / numel(app.Microstructure_initial);
                tabledata(current_phase,3)=sum(sum(sum( app.Microstructure==phases(current_phase)  ))) / numel(app.Microstructure);
            end
            app.O_vf_UITable.Data = tabledata;
            app.O_savebutton.Enable='on';
            app.O_visualize.Enable='on';
            app.Turn_on_off('on',app.OutcomeTab)
            app.TabGroup.SelectedTab = app.OutcomeTab; % Go to outcome tab
        end
        
        function currentDir = getcurrentdir(app)
            if isdeployed % Stand-alone mode.
                [status, result] = system('path');
                currentDir = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
            else % MATLAB mode.
                currentDir = pwd;
            end
        end            
                    

        function binary_phase = removeisolatedcompvol(app,binary_phase)
            [L,n] = bwlabeln(binary_phase,6);
            cluster_ids = unique(L);
            cluster_ids(cluster_ids==0) = []; % Remove background
            sz = size(binary_phase);
            for kcluster = 1:1:n
                idx = find(L==cluster_ids(kcluster));
                [IX, IY, IZ] = ind2sub(sz,idx);
                if min(IX)>1 && min(IY)>1 && min(IZ)>1 && max(IX)<sz(1) && max(IY)<sz(2) && max(IZ)<sz(3)
                    binary_phase(idx)=0;
                end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Turn_on_off('off',app.BetweenneighboursparticlesTab)
            app.Turn_on_off('off',app.EnergycriterionTab)
            app.Turn_on_off('off',app.OutcomeTab)
            app.Turn_on_off('off',app.CoatingTab)

            % Check online version
            % localversion = app.Localversion.Text;
            % githubversionfile=webread('https://raw.githubusercontent.com/NREL/MATBOX_Microstructure_analysis_toolbox/master/License.txt');
            % if ~strcmp(localversion,githubversionfile)
            %     app.OnlineversioncheckLabel.Text = ['There is a new version (' localversion ') available online. Please go to the Github repositorty to update your MATBOX installation'];
            %     app.OnlineversioncheckLabel.FontColor = 'r';
            % else
            %     app.OnlineversioncheckLabel.Text = 'You are using the up-to-date version of MATBOX';
            %     app.OnlineversioncheckLabel.FontColor = 'k';
            % end

        end

        % Button pushed function: Load_LoadvolumeButton
        function Load_LoadvolumeButtonPushed(app, event)
            % Open dialog box to choose file path
            [FileName,PathName,~] = uigetfile({'*.tif;*.tiff','Tif image (*.tif, *.tiff)'},'Select a .tif file');
            if FileName==0
                % User clicked cancel button or closed the dialog box
                app.Load_VolumeloaedLabel.Text ='No volume loaded. Please load a volume with pre-existing phase(s).';
                app.O_savefolderLabel.Text = 'No save folder selected. Please select a folder.';
                app.Microstructure=[]; % Reset Microstructure
                app.Microstructure_initial=[]; % Reset Microstructure
                app.History_operations=[]; app.History_parameters=[]; app.History_elapsedtime=[]; % Reset log
                app.Savefolder=[];
                app.filesave_ini=[];
                app.Turn_on_off('off',app.BetweenneighboursparticlesTab)
                app.Turn_on_off('off',app.EnergycriterionTab)
                app.Turn_on_off('off',app.CoatingTab)
                app.Turn_on_off('off',app.OutcomeTab)
                app.Load_ExistingphasesUITable.Data = [];
                
                app.VisualizeloadedmicrostructureButton.Enable = 'off';
                app.Load_volumefraction_dense.Enable = 'off'; app.VolumefractiondenseLabel_3.Enable = 'off';
                app.Load_nanoporosity.Enable = 'off'; app.NanoporosityLabel_3.Enable = 'off';
                app.Load_Additive_id.Enable = 'off'; app.AdditiveidLabel.Enable = 'off';
                
            else
                [Microstructure_local,outcome] = function_load_tif([PathName FileName]);     
                if outcome % Success to import

                    % Update global variables
                    app.Microstructure=Microstructure_local; % Update Microstructure
                    app.Microstructure_initial = app.Microstructure;
                    
                    %% Load tab
                    % Update save folder
                    app.Savefolder = PathName;
                    app.Load_VolumeloaedLabel.Text = [PathName FileName];
                    app.O_savefolderLabel.Text = app.Savefolder;
                    [~,tmp,~] = fileparts(FileName); % Remove .tif
                    app.filesave_ini = tmp;
                    app.Turn_on_off('on',app.BetweenneighboursparticlesTab)
                    app.Turn_on_off('on',app.EnergycriterionTab)
                    app.Turn_on_off('on',app.CoatingTab)
                    % Volume fraction and background
                    phases = unique(Microstructure_local);
                    number_phase = length(phases);
                    tabledata=cell(number_phase,3);
                    for current_phase=1:1:number_phase
                        tabledata(current_phase,1)={num2str(phases(current_phase))};
                        tabledata(current_phase,2)={sum(sum(sum( Microstructure_local==phases(current_phase)  ))) / numel(Microstructure_local)};
                        if phases(current_phase)==0
                            tabledata(current_phase,3)={true}; % By default, background is phase 0
                        else
                            tabledata(current_phase,3)={false};
                        end
                        
                    end
                    app.Load_ExistingphasesUITable.Data = tabledata;
                    
                    %% Between neighbours particles
                    tabledata=cell(3,3);
                    tabledata(1,:)={'Randomize cluster selection','[0,1]',0};
                    tabledata(2,:)={'Minimum distance from boundary in voxel length','[0,+Inf]',0};
                    tabledata(3,:)={'Erosion depth in voxel length','[0,+Inf] ',0};
                    app.Bridge_parameters_UITable.Data = tabledata;
                    
                    %% Preferential deposition
                    tabledata=cell(2,3);
                    tabledata(1,:)={'Morphology parameter w','[0.001,0.999]',0.5};
                    tabledata(2,:)={'Fraction of additive phase, relative to target volume fraction, added at each step ','[0,1]',0.01};
                    app.PD_parameters_UITable.Data = tabledata;                    
                    
                    %% Coating
                    tmp=cell(number_phase-1,1);
                    k=0;
                    for current_phase=1:1:number_phase
                        if phases(current_phase)~=0
                            k=k+1;
                            tmp(k,1)={num2str(phases(current_phase))};
                        end
                    end
                    app.Coating_DepositDropDown_2.Items = tmp;
                    app.Coating_DepositDropDown_2.Value = tmp(1);


                    %% Load
                    app.VisualizeloadedmicrostructureButton.Enable = 'on';
                    app.Load_volumefraction_dense.Enable = 'on';  app.VolumefractiondenseLabel_3.Enable = 'on';
                    app.Load_nanoporosity.Enable = 'on'; app.NanoporosityLabel_3.Enable = 'on';
                    app.Load_Additive_id.Enable = 'on'; app.AdditiveidLabel.Enable = 'on';
                    app.Load_Additive_id.Value = double(max(phases)+1);
                    app.Load_volumefraction_denseValueChanged;


                else
                    app.Load_VolumeloaedLabel.Text ='Error: volume cannot be loaded! File is not a .tif file';
                    app.Load_savefolderLabel.Text = 'No save folder selected. Please select a folder.';
                    app.Microstructure=[]; % Reset Microstructure
                    app.Microstructure_initial=[]; % Reset Microstructure
                    app.History_operations=[]; app.History_parameters=[]; app.History_elapsedtime=[]; % Reset log
                    app.Savefolder=[];
                    app.filesave_ini=[];
                    app.Turn_on_off('off',app.BetweenneighboursparticlesTab)
                    app.Turn_on_off('off',app.EnergycriterionTab)
                    app.Turn_on_off('off',app.CoatingTab)
                    app.Turn_on_off('off',app.OutcomeTab)
                    app.Load_ExistingphasesUITable.Data = [];
                    app.VisualizeloadedmicrostructureButton.Enable = 'off';
                    app.Load_volumefraction_dense.Enable = 'off';  app.VolumefractiondenseLabel_3.Enable = 'off';
                    app.Load_nanoporosity.Enable = 'off'; app.NanoporosityLabel_3.Enable = 'off';
                    app.Load_Additive_id.Enable = 'off'; app.AdditiveidLabel.Enable = 'off';
                end
            end
            
            
        end

        % Cell edit callback: Load_ExistingphasesUITable
        function Load_ExistingphasesUITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            if indices(2)==3
                tabledata = app.Load_ExistingphasesUITable.Data;
                tabledata(:,3) = {false};
                if newData==1
                    tabledata(indices(1),3)={true};
                    background = str2num(cell2mat(tabledata(indices(1),1)));
                end
                app.Load_ExistingphasesUITable.Data = tabledata;
            end
            app.Load_volumefraction_denseValueChanged;

            phases=str2num(cell2mat(tabledata(:,1)));
            number_phase=length(phases);
            tmp=cell(number_phase-1,1);
            k=0;
            for current_phase=1:1:number_phase
                if phases(current_phase) ~=background
                    k=k+1;
                    tmp(k,1)={num2str(phases(current_phase))};
                end
            end
            app.Coating_DepositDropDown_2.Items = tmp;
            app.Coating_DepositDropDown_2.Value = tmp(1);

        end

        % Button pushed function: Bridge_generation
        function Bridge_generationButtonPushed(app, event)
            app.Turn_on_off('off',app.OutcomeTab) 
                        
            % Prepare parameters
            idx = find( cell2mat(app.Load_ExistingphasesUITable.Data(:,3))==1 );
            app.Microstructure = app.Microstructure_initial; % Reset
            background_id = str2num(cell2mat(app.Load_ExistingphasesUITable.Data(idx,1)));
            target_volume_fraction = app.Load_targetvolumefraction.Value;
            additive_id = app.Load_Additive_id.Value;
            randomize_level = cell2mat(app.Bridge_parameters_UITable.Data(1,3));
            minimum_dist = cell2mat(app.Bridge_parameters_UITable.Data(2,3));
            erosiondilatation_depth = cell2mat(app.Bridge_parameters_UITable.Data(3,3));
            
            % You could use it as a function as
            %function [app.Microstructure] = generate_additive(app.Microstructure,background_id,additive_id,target_volume_fraction,minimum_dist,randomize_level,erosiondilatation_depth)
            
            time_cpu_start = cputime; % CPU start
            tic; % Stopwatch start
            
            binary_phase=zeros(size(app.Microstructure)); % Initialization
            binary_phase(app.Microstructure == background_id) = 1;

            if app.GenerateInConnectedCluster.Value
                app.ProgressionGauge.Value = 0; pause(0.001); % Update progression bar
                app.TextArea.Value = {'Calculating','Isolated background...'}; pause(0.01);
                binary_phase = app.removeisolatedcompvol(binary_phase);
            end

            app.ProgressionGauge.Value = 0; pause(0.001); % Update progression bar
            app.TextArea.Value = {'Calculating','distance to boundary...'}; pause(0.01);            
            [distance_bnd2bnd] = Function_particle_size_CPSD_Algorithm(binary_phase, true, false); % Calculate distance from boundary to boundary
            
%            % Visualization step-by-step
            % figure
            % axis equal tight
            % imagesc(distance_bnd2bnd(:,:,2))  
            % set(gca,'YDir','normal')
            % custommap = turbo; custommap(1,:)=[0.5 0.5 0.5];
            % colormap(custommap)
            
            distance_bnd2bnd(distance_bnd2bnd==0)=9e9; % Remove 0 (background) from the potential additive location
            distance_bnd2bnd(distance_bnd2bnd<=minimum_dist*sqrt(3))=9e9; % Remove small features from the potential additive location
            distance_bnd2bnd(~binary_phase)=9e9;

            app.TextArea.Value = {'Generating','additive phase...'}; pause(0.01);
                        
            ntot = numel(app.Microstructure);
            current_additive_volumefraction = 0; % Initialize
            increment = 1;
            iter=0;
            d=0; % Initialize
            while current_additive_volumefraction < target_volume_fraction
                d=d+increment; % Increment distance
                iter=iter+1;
                if d>minimum_dist*sqrt(3)
                    potential_additives = distance_bnd2bnd<=d; % Select all voxels that could be assigned to additive
                    n = sum(sum(sum(potential_additives))); % How many of them ?
                    if n>0 % At least one
                        if randomize_level==0
                            if n/ntot <= target_volume_fraction-current_additive_volumefraction % All potential voxel are not enough to match target volume fraction
                                app.Microstructure(potential_additives)=additive_id;
                            else % Randomize among possible location is mandatory not to overshoot the target volume fraction
                                [L,ncluster] = bwlabeln(potential_additives,6); % Identify cluster among these potential locations

                                % Randomize L
                                p.basedondistance = false;
                                p.dist = [];
                                p.keepunchangedsomelabels = true;
                                p.cst = '0';
                                [L,~,~] = fct_randomize(L,p);

                                % Number of pixel per cluster
                                [GC,GR] = groupcounts( reshape(L,[numel(L),1])  );
                                id0 = find(GR==0);
                                GC(id0)=[]; GR(id0)=[]; % Remove background
                                GCvf = GC/numel(potential_additives); % Volume fractions
                                cumsum_GCvf = cumsum(GCvf); % Cummulative sum

                                % Find clusters to reach target volume fraction
                                idxC = find(cumsum_GCvf >= target_volume_fraction-current_additive_volumefraction);
                                idxC = idxC(1);
                                tmp = app.Microstructure;
                                id0 = find(L==0);
                                app.Microstructure(L<=idxC)=additive_id;
                                app.Microstructure(id0)=tmp(id0);

                                % Too slow
                                % cluster_possibility=1:1:ncluster;
                                % while current_additive_volumefraction <= target_volume_fraction
                                %     cluster_choice=randi(length(cluster_possibility)); % Random selection of cluster
                                %     app.Microstructure(L==cluster_possibility(cluster_choice))=additive_id;
                                %     current_additive_volumefraction = sum(sum(sum(app.Microstructure==additive_id)))/ntot; % Update current volume fraction
                                %     cluster_possibility(cluster_choice)=[]; % Avoid re-assignment
                                % end
                            end
                        else % Use a portion of them, per cluster randomly chosen
                            [L,ncluster] = bwlabeln(potential_additives,18); % Identify cluster among these potential locations
                            cluster_possibility=1:1:ncluster;
                            volume_increment=0; % initalize
                            while volume_increment <= (1-randomize_level)*n
                                cluster_choice=randi(length(cluster_possibility)); % Random selection of cluster
                                loc = L==cluster_possibility(cluster_choice);
                                app.Microstructure(loc)=additive_id;
                                volume_increment = volume_increment + sum(sum(sum( loc )));
                                cluster_possibility(cluster_choice)=[]; % Avoid re-assignment
                            end
                        end
                    end
                end
                distance_bnd2bnd(app.Microstructure==additive_id)=9e9; % Remove already assigned additive from the potential additive new location
                current_additive_volumefraction = sum(sum(sum(app.Microstructure==additive_id)))/ntot; % Update current volume fraction
                
                app.ProgressionGauge.Value = 100*current_additive_volumefraction/target_volume_fraction; pause(0.001); % Update progression bar
                
                progress(iter,1) = current_additive_volumefraction;
                if iter>10 && length(unique(progress(end-5:end,1)))==1
                    progress
                    break
                end
                
%                %Visualization step-by-step
%                 figure
%                 axis equal tight
%                 imagesc(app.Microstructure(:,:,2))
%                 set(gca,'YDir','normal')
%                 custommap = copper; custommap(1,:)=[0 0 0];
%                 colormap(custommap)
                
                
            end
            app.ProgressionGauge.Value = 100; pause(0.001); % Update progression bar
                        
            if erosiondilatation_depth>0 % Optional step
                app.TextArea.Value = {'Erosion of additive phase...'}; pause(0.01);
                binary_phase=zeros(size(app.Microstructure)); % Initialization
                binary_phase(app.Microstructure == additive_id) = 1;
                [distance_bnd2bnd] = Function_particle_size_CPSD_Algorithm(binary_phase, true, false); % Calculate distance from boundary to boundary
                distance_bnd2bnd(distance_bnd2bnd==0)=9e9; % Remove 0 (background) from the potential additive location
                app.Microstructure(distance_bnd2bnd<(erosiondilatation_depth*sqrt(3)+0.0001))=background_id;                
            end
             
% %             Visualization step-by-step
%             figure
%             axis equal tight
%             imagesc(app.Microstructure(:,:,2))
%             set(gca,'YDir','normal')
%             custommap = copper; custommap(1,:)=[0 0 0];
%             colormap(custommap)
            
            if minimum_dist>0
                app.TextArea.Value = {'Correcting minimum','distance...'}; pause(0.01);
                binary_phase=zeros(size(app.Microstructure)); % Initialization
                binary_phase(app.Microstructure == background_id) = 1;
                [distance_bnd2bnd] = Function_particle_size_CPSD_Algorithm(binary_phase, true, false); % Calculate distance from boundary to boundary
                distance_bnd2bnd(distance_bnd2bnd==0)=9e9; % Remove 0 (background) from the potential additive location
                app.Microstructure(distance_bnd2bnd<minimum_dist*sqrt(3)+0.0001)=additive_id;
            end
            
% %             Visualization step-by-step
%             figure
%             axis equal tight
%             imagesc(app.Microstructure(:,:,2))
%             set(gca,'YDir','normal')
%             custommap = copper; custommap(1,:)=[0 0 0];
%             colormap(custommap)            
    

            delta = round(sum(sum(sum( app.Microstructure==additive_id  ))) - numel(app.Microstructure)*target_volume_fraction);
            erosion_cluster = true;
            while delta>0
                %erosion_cluster
                %delta
                app.TextArea.Value = {'Resorbing volume fraction','difference...'}; pause(0.01);
                binary_phase=zeros(size(app.Microstructure)); % Create binary matrix
                if erosion_cluster==false
                    % Random voxel erosion
                    binary_phase(app.Microstructure==background_id) = 1;
                    distance_map = bwdist(binary_phase,'chessboard'); % Distance map
                    distance_map(app.Microstructure ~= additive_id)=2; % Consider only additive
                    idx = find(distance_map<2);
                    if length(idx)<=delta
                        app.Microstructure(idx)=background_id;
                    else
                        tmp = randi(length(idx),delta,1);
                        app.Microstructure(idx(tmp))=background_id;
                    end
                else
                    % Random cluster erosion
                    binary_phase(app.Microstructure==additive_id) = 1;
                    [L,~] = bwlabeln(binary_phase,6);
        
                    % Randomize L
                    p.basedondistance = false;
                    p.dist = [];
                    p.keepunchangedsomelabels = true;
                    p.cst = '0';
                    [L,~,~] = fct_randomize(L,p);

                    % Number of pixel per cluster
                    [GC,GR] = groupcounts( reshape(L,[numel(L),1])  );
                    id0 = find(GR==0);
                    GC(id0)=[]; GR(id0)=[]; % Remove background
                    cumsum_GC = cumsum(GC); % Cummulative sum

                    % Find clusters to reach target volume fraction
                    idxC = find(cumsum_GC >= delta);
                    idxC = idxC(1);
                    tmp = app.Microstructure;
                    id0 = find(L==0);
                    app.Microstructure(L<=idxC)=background_id;
                    app.Microstructure(id0)=tmp(id0);
   
                    % Too slow
                    % % Determine cluster size
                    % [C,~,ic] = unique(L);
                    % a_counts = accumarray(ic,1);
                    % size_cluster = [C, a_counts];
                    % size_cluster = sortrows(size_cluster,2); % Sort in ascending order
                    % while size_cluster(1,2)<delta && delta>0 
                    %     app.Microstructure(L==size_cluster(1,1))=background_id; % Smallest cluster is removed
                    %     delta = round(sum(sum(sum( app.Microstructure==additive_id  ))) - numel(app.Microstructure)*target_volume_fraction);
                    %     size_cluster(1,:)=[]; % Smallest cluster is removed from the list 
                    % end

                    erosion_cluster = false; % Switch to voxel erosion
                end
                delta = round(sum(sum(sum( app.Microstructure==additive_id  ))) - numel(app.Microstructure)*target_volume_fraction);
            end
           
              
% %             Visualization step-by-step
%             figure
%             axis equal tight
%             imagesc(app.Microstructure(:,:,2))
%             set(gca,'YDir','normal')
%             custommap = copper; custommap(1,:)=[0 0 0];
%             colormap(custommap)               
            
            % CPU and stopwatch time - end
            time_cpu_elapsed = cputime-time_cpu_start; % CPU elapsed time
            time_stopwatch_elapsed = toc; % Stopwatch elapsed time
            
            app.TextArea.Value = {[num2str(ntot,'%i'),' voxels (domain)'],['Stopwatch time:',num2str(time_stopwatch_elapsed,'%1.1f'), 's'],['CPU time:',num2str(time_cpu_elapsed,'%1.1f'), 's']}; pause(0.01);
            app.Bridged_AchievedEditField.Value = sum(sum(sum( app.Microstructure==additive_id  ))) / numel(app.Microstructure);
            
            % Done
            app.O_filename_save.Value = [app.filesave_ini '_bridge.tif'];
            app.finished_generation
        end

        % Cell edit callback: Bridge_parameters_UITable
        function Bridge_parameters_UITableCellEdit(app, event)
            % Check parameters
            tabledata = app.Bridge_parameters_UITable.Data;
            v=cell2mat(tabledata(1,3));
            if isnan(v) || v<0 || v>1
                tabledata(1,3) = {0};
            end            
            v=cell2mat(tabledata(2,3));
            if isnan(v) || v<0
                tabledata(2,3) = {1};
            end                  
            v=cell2mat(tabledata(3,3));
            if isnan(v) || v<0 || v~=round(v)
                tabledata(3,3) = {1};
            end           
            app.Bridge_parameters_UITable.Data = tabledata;
        end

        % Value changed function: Load_nanoporosity, 
        % ...and 1 other component
        function Load_volumefraction_denseValueChanged(app, event)
            volumefraction_dense = app.Load_volumefraction_dense.Value;
            nanoporosity = app.Load_nanoporosity.Value;
            target_volume_fraction = volumefraction_dense * 1/(1-nanoporosity);
            if ~isempty(app.Load_ExistingphasesUITable.Data)
                idx = find( cell2mat(app.Load_ExistingphasesUITable.Data(:,3))==1 );
                if ~isempty(idx)
                    max_vf = cell2mat(app.Load_ExistingphasesUITable.Data(idx,2));
                else
                    max_vf = 9e9;
                end
                app.Load_targetvolumefraction.Value = min([max_vf, target_volume_fraction]);
            else
                app.Load_targetvolumefraction.Value = target_volume_fraction;
            end
            app.Bridge_targetvolumefraction.Value = app.Load_targetvolumefraction.Value;
            app.PD_targetvolumefraction.Value = app.Load_targetvolumefraction.Value;
            app.Coating_targetvolumefraction.Value = app.Load_targetvolumefraction.Value;
        end

        % Value changed function: Load_Additive_id
        function Load_Additive_idValueChanged(app, event)
            additive_id = app.Load_Additive_id.Value;
            existing_id = str2num(cell2mat(app.Load_ExistingphasesUITable.Data(:,1)));
            if sum(ismember(existing_id,additive_id))>0
                app.Load_Additive_id.Value = max(existing_id)+1;
            end
        end

        % Button pushed function: O_savebutton
        function O_savebuttonButtonPushed(app, event)
            function_save_tif(app.Microstructure, [app.Savefolder app.O_filename_save.Value]);
        end

        % Button pushed function: PD_generation
        function PD_generationButtonPushed(app, event)
            app.Turn_on_off('off',app.OutcomeTab) 
                        
            % Prepare parameters
            idx = find( cell2mat(app.Load_ExistingphasesUITable.Data(:,3))==1 );
            app.Microstructure = app.Microstructure_initial; % Reset
            background_id = str2num(cell2mat(app.Load_ExistingphasesUITable.Data(idx,1)));
            target_volume_fraction = app.Load_targetvolumefraction.Value;
            additive_id = app.Load_Additive_id.Value;
            morphology_parameter = cell2mat(app.PD_parameters_UITable.Data(1,3));
            fractionstep = cell2mat(app.PD_parameters_UITable.Data(2,3));
             
            time_cpu_start = cputime; % CPU start
            tic; % Stopwatch start
            
            app.PD_ProgressionGauge.Value = 0; pause(0.001); % Update progression bar
            app.PD_TextArea.Value = {'Calculating...'}; pause(0.001);
            
            % Prepare data set
            I  = zeros(size(app.Microstructure));
            I(app.Microstructure~=background_id) = 1; % 0, background, 1 other phases, 2 additive phase
            
            %__________________________________________________________________________
            % Programmed by     : Aashutosh Mistry (START)
            % Created on        : Jul 05, 2020
            % Article ref.      : https://doi.org/10.1021/acsami.7b17771
            %   Mistry, Smith & Mukherjee (2018) Secondary Phase Stochastics in
            %   Lithium-Ion Battery Electrodes, ACS Appl. Mater. Interfaces 10(7) pp.
            %   6317-6326
            
            [Nx, Ny, Nz] = size(I);                     % voxel ranges
            N = Nx*Ny*Nz;                               % total voxels
            Nsimultaneous = round(N*target_volume_fraction*fractionstep); % how many new CBD voxels are in a single run
            % 1D index for each of the 3D indices
            Pid = zeros(Nx,Ny,Nz);
            for k=1:Nz
                for j=1:Ny
                    for i=1:Nx
                        Pid(i,j,k) = Index1D(i,j,k, Nx,Ny,Nz);
                    end
                end
            end
            
            % generating a neighbor list - ensuring periodicity of boundaries _________
            [Eid, Wid, Nid, Sid, Uid, Lid] = Neighbors(Nx,Ny,Nz);
            % multiple rounds of deposition
            e2 = 0; % Initialization
            while e2<target_volume_fraction
                I = AddCBDwMorphology_step (I, morphology_parameter, Pid, Eid,Wid,Nid,Sid,Uid,Lid, Nsimultaneous);
                e2 = sum(sum(sum( I==2 )))/N;  % new CBD volume fraction
                app.PD_AchievedEditField.Value = e2;
                app.PD_ProgressionGauge.Value = 100*e2/target_volume_fraction; pause(0.001); % Update progression bar
            end
            app.Microstructure(I==2)= additive_id;
            % Programmed by     : Aashutosh Mistry (END)
            %__________________________________________________________________________
            
            % CPU and stopwatch time - end
            time_cpu_elapsed = cputime-time_cpu_start; % CPU elapsed time
            time_stopwatch_elapsed = toc; % Stopwatch elapsed time
            
            app.PD_TextArea.Value = {[num2str(N,'%i'),' voxels (domain)'],['Stopwatch time:',num2str(time_stopwatch_elapsed,'%1.1f'), 's'],['CPU time:',num2str(time_cpu_elapsed,'%1.1f'), 's']}; pause(0.01);
            app.PD_AchievedEditField.Value = sum(sum(sum( app.Microstructure==additive_id  ))) / numel(app.Microstructure);
            
            % Done
            app.O_filename_save.Value = [app.filesave_ini '_w' num2str(morphology_parameter,'%1.3f') '.tif'];
            app.finished_generation            
        end

        % Button pushed function: O_visualize
        function O_visualizeButtonPushed(app, event)
            direction_name = {'Normal to Axis 1';'Normal to Axis 2';'Normal to Axis 3'};
            Microstructure_basic_visualization_interface(app.Microstructure, direction_name);            
        end

        % Button pushed function: VisualizeloadedmicrostructureButton
        function VisualizeloadedmicrostructureButtonPushed(app, event)
            direction_name = {'Normal to Axis 1';'Normal to Axis 2';'Normal to Axis 3'};
            Microstructure_basic_visualization_interface(app.Microstructure_initial, direction_name);
        end

        % Cell edit callback: PD_parameters_UITable
        function PD_parameters_UITableCellEdit(app, event)
            % Check parameters
            tabledata = app.PD_parameters_UITable.Data;
            v=cell2mat(tabledata(1,3));
            if isnan(v) || v<0.001 || v>0.999
                tabledata(1,3) = {0.5};
            end            
            v=cell2mat(tabledata(2,3));
            if isnan(v) || v<0 || v>1
                tabledata(2,3) = {0.1};
            end                  
            app.PD_parameters_UITable.Data = tabledata;            
        end

        % Button pushed function: O_savefolderButton
        function O_savefolderButtonPushed(app, event)
            if isempty(app.Savefolder)
                selpath = uigetdir(pwd,'Select save folder');
            else
                selpath = uigetdir(app.Savefolder,'Select save folder');
            end
            if selpath~=0
                if ispc
                    selpath=[selpath '\'];
                else
                    selpath=[selpath '/'];
                end
                app.Savefolder=selpath;
                app.O_savefolderLabel.Text = app.Savefolder;
            end
        end

        % Callback function
        function OpendocumentationButtonPushed(app, event)
            Find_file('NREL_MATBOX_Microstructure_analysis_toolbox_documentation.pdf','MATBOX_Microstructure_analysis_toolbox','Default location is \MATBOX_Microstructure_analysis_toolbox\Documentation\');
        end

        % Callback function
        function About_Logo_NRELImageClicked(app, event)
            url = 'https://www.nrel.gov/transportation/';
            web(url)
        end

        % Callback function
        function Github_ushbuttonPushed(app, event)
            url = 'https://github.com/NREL/MATBOX_Microstructure_analysis_toolbox';
            web(url)
        end

        % Callback function
        function MATBOX_articlePushed(app, event)
            url = 'https://doi.org/10.1016/j.softx.2021.100915';
            web(url)
        end

        % Button pushed function: Coating_generation
        function Coating_generationButtonPushed(app, event)
           app.Turn_on_off('off',app.OutcomeTab) 
                        
            % Prepare parameters
            idx = find( cell2mat(app.Load_ExistingphasesUITable.Data(:,3))==1 );
            app.Microstructure = app.Microstructure_initial; % Reset
            background_id = str2num(cell2mat(app.Load_ExistingphasesUITable.Data(idx,1)));
            target_volume_fraction = app.Load_targetvolumefraction.Value;
            target_depth = app.Coating_targetvolumefraction_2.Value;
            target_choice = app.Coating_TargetDropDown.Value;
            deposit_sens = app.Coating_DepositDropDown.Value;
            deposit_phase = str2num(app.Coating_DepositDropDown_2.Value);
            additive_id = app.Load_Additive_id.Value;
                   
            time_cpu_start = cputime; % CPU start
            tic; % Stopwatch start
            app.Coating_statut.Value = {'Calculating...'}; pause(0.01);

            % Call function
            [app.Microstructure,achieved_volume_fraction,achieved_depth] = function_generate_coating(app.Microstructure,additive_id,deposit_sens,deposit_phase,target_volume_fraction,target_depth,target_choice);
            
            %achieved_volume_fraction
            %achieved_depth


            % CPU and stopwatch time - end
            time_cpu_elapsed = cputime-time_cpu_start; % CPU elapsed time
            time_stopwatch_elapsed = toc; % Stopwatch elapsed time

            ntot = numel(app.Microstructure);
            app.Coating_statut.Value = {[num2str(ntot,'%i'),' voxels (domain)'],['Stopwatch time:',num2str(time_stopwatch_elapsed,'%1.1f'), 's'],['CPU time:',num2str(time_cpu_elapsed,'%1.1f'), 's']}; pause(0.01);
            app.Coating_AchievedEditField.Value = sum(sum(sum( app.Microstructure==additive_id  ))) / numel(app.Microstructure);
            app.Coating_AchievedEditField_2.Value = achieved_depth;

            % Done
            app.O_filename_save.Value = [app.filesave_ini '_coating.tif'];
            app.finished_generation
        end

        % Value changed function: Coating_TargetDropDown
        function Coating_TargetDropDownValueChanged(app, event)
            value = app.Coating_TargetDropDown.Value;
            if strcmp(value,'Volume fraction')
                app.TargetvolumefractionLabel_6.Enable = 'off';
                app.TargetvolumefractionLabel_5.Enable = 'on';
                app.Coating_targetvolumefraction_2.Editable = 'off';
            elseif strcmp(value,'Coating depth in voxel')
                app.TargetvolumefractionLabel_6.Enable = 'on';
                app.TargetvolumefractionLabel_5.Enable = 'off';
                app.Coating_targetvolumefraction_2.Editable = 'on';
            end

        end

        % Callback function
        function Mistry_articleButtonPushed(app, event)
            url = 'https://doi.org/10.1021/acsami.7b17771';
            web(url)            
        end

        % Button pushed function: OpendocumentationButton_2
        function OpendocumentationButton_2Pushed(app, event)
            Find_file('NREL_MATBOX_Microstructure_analysis_toolbox_documentation.pdf','MATBOX_Microstructure_analysis_toolbox','Default location is \MATBOX_Microstructure_analysis_toolbox\Documentation\');
        end

        % Image clicked function: NREL_logo
        function NREL_logoImageClicked(app, event)
            url = 'https://www.nrel.gov/';
            web(url)      
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Microstructure_additive_phase_generation = uifigure('Visible', 'off');
            app.Microstructure_additive_phase_generation.Name = 'Additive phase generation';
            app.Microstructure_additive_phase_generation.Icon = 'Icon_generation_additive.png';
            app.TabGroup = uitabgroup(app.Microstructure_additive_phase_generation);
            app.InstructionsTab = uitab(app.TabGroup);
            app.InstructionsTab.Title = 'Instructions';
            app.Load_title_3 = uilabel(app.InstructionsTab);
            app.Load_title_3.Text = 'Main instructions';
            app.Load_instructions_2 = uilabel(app.InstructionsTab);
            app.Load_instructions_2.Text = {'This module enables you to generate an additive phase within the backgroud phase of a microstructure. For instance, for a battery'; 'electrode material that would be the carbon black and binder additives.'; ''; '- Algorithms require a segemented volume with one phase or more already identified.'; ''; '- First import your volume in the dedicated tab. Once loaded, the GUI will initialize.'; ''; '- Then choose one algorithm among the three additive generation methods available (blue tabs): '; '1) Additive is preferentially located between neighboring particles close from each other.'; '2) Additive is preferentially located either at the surface of existing particles or at the surface of already deposited additive,'; 'based on a user-defined energy criteria.'; '3) Additive is a thin layer at the surface of the existing particles (uniform coating).'; ''; '- Once generation is finished, you can visualize the outcome in the corresponding tab and save it.'};
            app.LoadvolumeandvolumefractionTab = uitab(app.TabGroup);
            app.LoadvolumeandvolumefractionTab.Title = 'Load volume and volume fraction';
            app.Load_instructions = uilabel(app.LoadvolumeandvolumefractionTab);
            app.Load_instructions.Text = {'Instructions: load a volume, select background and choose your target volume fraction for the additive phase.'; 'Then move to the tab that corresponds to the type of additive/method you want to generate.'};
            app.Load_title = uilabel(app.LoadvolumeandvolumefractionTab);
            app.Load_title.Text = 'Volume with pre-existing phases';
            app.Load_LoadvolumeButton = uibutton(app.LoadvolumeandvolumefractionTab, 'push');
            app.Load_LoadvolumeButton.ButtonPushedFcn = createCallbackFcn(app, @Load_LoadvolumeButtonPushed, true);
            app.Load_LoadvolumeButton.Text = 'Load volume';
            app.Load_VolumeloaedLabel = uilabel(app.LoadvolumeandvolumefractionTab);
            app.Load_VolumeloaedLabel.Text = 'No volume loaded. Please load a volume with pre-existing phase(s).';
            app.Load_ExistingphasesUITable = uitable(app.LoadvolumeandvolumefractionTab);
            app.Load_ExistingphasesUITable.ColumnName = {'Id'; 'Volume fraction'; 'Background'};
            app.Load_ExistingphasesUITable.RowName = {};
            app.Load_ExistingphasesUITable.ColumnEditable = [false false true];
            app.Load_ExistingphasesUITable.CellEditCallback = createCallbackFcn(app, @Load_ExistingphasesUITableCellEdit, true);
            app.Load_ExistingphasesLabel = uilabel(app.LoadvolumeandvolumefractionTab);
            app.Load_ExistingphasesLabel.Text = 'Existing phases. Select background where additive will be generated';
            app.Load_NewphaseparameterLabel = uilabel(app.LoadvolumeandvolumefractionTab);
            app.Load_NewphaseparameterLabel.Text = 'Additive phase';
            app.VolumefractiondenseLabel_3 = uilabel(app.LoadvolumeandvolumefractionTab);
            app.VolumefractiondenseLabel_3.Enable = 'off';
            app.VolumefractiondenseLabel_3.Text = 'Volume fraction (dense)';
            app.Load_volumefraction_dense = uieditfield(app.LoadvolumeandvolumefractionTab, 'numeric');
            app.Load_volumefraction_dense.Limits = [0 1];
            app.Load_volumefraction_dense.ValueChangedFcn = createCallbackFcn(app, @Load_volumefraction_denseValueChanged, true);
            app.Load_volumefraction_dense.Enable = 'off';
            app.Load_volumefraction_dense.Value = 0.1;
            app.NanoporosityLabel_3 = uilabel(app.LoadvolumeandvolumefractionTab);
            app.NanoporosityLabel_3.Enable = 'off';
            app.NanoporosityLabel_3.Text = 'Nanoporosity';
            app.Load_nanoporosity = uieditfield(app.LoadvolumeandvolumefractionTab, 'numeric');
            app.Load_nanoporosity.Limits = [0 1];
            app.Load_nanoporosity.ValueChangedFcn = createCallbackFcn(app, @Load_volumefraction_denseValueChanged, true);
            app.Load_nanoporosity.Enable = 'off';
            app.TargetvolumefractionLabel_3 = uilabel(app.LoadvolumeandvolumefractionTab);
            app.TargetvolumefractionLabel_3.Text = 'Target volume fraction';
            app.Load_targetvolumefraction = uieditfield(app.LoadvolumeandvolumefractionTab, 'numeric');
            app.Load_targetvolumefraction.Limits = [0 1];
            app.Load_targetvolumefraction.Editable = 'off';
            app.Load_targetvolumefraction.Value = 0.1;
            app.AdditiveidLabel = uilabel(app.LoadvolumeandvolumefractionTab);
            app.AdditiveidLabel.Enable = 'off';
            app.AdditiveidLabel.Text = 'Additive id';
            app.Load_Additive_id = uieditfield(app.LoadvolumeandvolumefractionTab, 'numeric');
            app.Load_Additive_id.Limits = [0 Inf];
            app.Load_Additive_id.RoundFractionalValues = 'on';
            app.Load_Additive_id.ValueChangedFcn = createCallbackFcn(app, @Load_Additive_idValueChanged, true);
            app.Load_Additive_id.Enable = 'off';
            app.Load_Additive_id.Value = 2;
            app.VisualizeloadedmicrostructureButton = uibutton(app.LoadvolumeandvolumefractionTab, 'push');
            app.VisualizeloadedmicrostructureButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizeloadedmicrostructureButtonPushed, true);
            app.VisualizeloadedmicrostructureButton.Enable = 'off';
            app.VisualizeloadedmicrostructureButton.Text = 'Visualize loaded microstructure';
            app.GenerateInConnectedCluster = uicheckbox(app.LoadvolumeandvolumefractionTab);
            app.GenerateInConnectedCluster.Tooltip = {'No phase generated within isolated clusters.'};
            app.GenerateInConnectedCluster.Text = 'Only generate within the background clusters connected to the FOV edges';
            app.BetweenneighboursparticlesTab = uitab(app.TabGroup);
            app.BetweenneighboursparticlesTab.Title = 'Between neighbours particles';
            app.Bridge_instructions = uilabel(app.BetweenneighboursparticlesTab);
            app.Bridge_instructions.Text = 'Instructions: Choose algorithms parameters and click generate. Once done, you can go to the outcome tab for viusalization and saving.';
            app.Bridge_title = uilabel(app.BetweenneighboursparticlesTab);
            app.Bridge_title.Text = 'Additive phase will be generated so that they will connect neighbours particles, effectively forming bridges';
            app.Bridge_NewphaseparameterLabel = uilabel(app.BetweenneighboursparticlesTab);
            app.Bridge_NewphaseparameterLabel.Text = 'New phase parameters';
            app.TargetvolumefractionLabel = uilabel(app.BetweenneighboursparticlesTab);
            app.TargetvolumefractionLabel.Text = 'Target volume fraction';
            app.Bridge_targetvolumefraction = uieditfield(app.BetweenneighboursparticlesTab, 'numeric');
            app.Bridge_targetvolumefraction.Limits = [0 1];
            app.Bridge_targetvolumefraction.Editable = 'off';
            app.Bridge_targetvolumefraction.Value = 0.1;
            app.Bridge_parameters_UITable = uitable(app.BetweenneighboursparticlesTab);
            app.Bridge_parameters_UITable.ColumnName = {'Parameter'; 'Range'; 'Value'};
            app.Bridge_parameters_UITable.RowName = {};
            app.Bridge_parameters_UITable.ColumnEditable = [false false true];
            app.Bridge_parameters_UITable.CellEditCallback = createCallbackFcn(app, @Bridge_parameters_UITableCellEdit, true);
            app.Bridge_generation = uibutton(app.BetweenneighboursparticlesTab, 'push');
            app.Bridge_generation.ButtonPushedFcn = createCallbackFcn(app, @Bridge_generationButtonPushed, true);
            app.Bridge_generation.Enable = 'off';
            app.Bridge_generation.Text = 'Generate';
            app.ProgressionGaugeLabel = uilabel(app.BetweenneighboursparticlesTab);
            app.ProgressionGaugeLabel.Text = 'Progression';
            app.ProgressionGauge = uigauge(app.BetweenneighboursparticlesTab, 'linear');
            app.ProgressionGauge.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.TextArea = uitextarea(app.BetweenneighboursparticlesTab);
            app.TextArea.Value = {'Not running'};
            app.AchievedEditFieldLabel = uilabel(app.BetweenneighboursparticlesTab);
            app.AchievedEditFieldLabel.Text = 'Achieved';
            app.Bridged_AchievedEditField = uieditfield(app.BetweenneighboursparticlesTab, 'numeric');
            app.Bridged_AchievedEditField.Editable = 'off';
            app.Label_4 = uilabel(app.BetweenneighboursparticlesTab);
            app.Label_4.Text = 'General recommendation: set ''Erosion depth'' < "Minimum distance from voxel boundary" or 0.';
            app.EnergycriterionTab = uitab(app.TabGroup);
            app.EnergycriterionTab.Title = 'Energy criterion';
            app.PD_title = uilabel(app.EnergycriterionTab);
            app.PD_title.Text = 'Additive phase preferenital deposition is controlled through a energy criterion';
            app.PD_instructions = uilabel(app.EnergycriterionTab);
            app.PD_instructions.Text = 'Instructions: Choose algorithms parameters and click generate. Once done, you can go to the outcome tab for viusalization and saving.';
            app.PD_NewphaseparameterLabel = uilabel(app.EnergycriterionTab);
            app.PD_NewphaseparameterLabel.Text = 'New phase parameters';
            app.PD_parameters_UITable = uitable(app.EnergycriterionTab);
            app.PD_parameters_UITable.ColumnName = {'Parameter'; 'Range'; 'Value'};
            app.PD_parameters_UITable.RowName = {};
            app.PD_parameters_UITable.ColumnEditable = [false false true];
            app.PD_parameters_UITable.CellEditCallback = createCallbackFcn(app, @PD_parameters_UITableCellEdit, true);
            app.AchievedEditFieldLabel_2 = uilabel(app.EnergycriterionTab);
            app.AchievedEditFieldLabel_2.Text = 'Achieved';
            app.PD_AchievedEditField = uieditfield(app.EnergycriterionTab, 'numeric');
            app.PD_AchievedEditField.Editable = 'off';
            app.TargetvolumefractionLabel_4 = uilabel(app.EnergycriterionTab);
            app.TargetvolumefractionLabel_4.Text = 'Target volume fraction';
            app.PD_targetvolumefraction = uieditfield(app.EnergycriterionTab, 'numeric');
            app.PD_targetvolumefraction.Limits = [0 1];
            app.PD_targetvolumefraction.Editable = 'off';
            app.PD_targetvolumefraction.Value = 0.1;
            app.PD_generation = uibutton(app.EnergycriterionTab, 'push');
            app.PD_generation.ButtonPushedFcn = createCallbackFcn(app, @PD_generationButtonPushed, true);
            app.PD_generation.Enable = 'off';
            app.PD_generation.Text = 'Generate';
            app.PD_TextArea = uitextarea(app.EnergycriterionTab);
            app.PD_TextArea.Value = {'Not running'};
            app.ProgressionGauge_2Label = uilabel(app.EnergycriterionTab);
            app.ProgressionGauge_2Label.Text = 'Progression';
            app.PD_ProgressionGauge = uigauge(app.EnergycriterionTab, 'linear');
            app.PD_ProgressionGauge.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.CoatingTab = uitab(app.TabGroup);
            app.CoatingTab.Title = 'Coating';
            app.Coating_title = uilabel(app.CoatingTab);
            app.Coating_title.Text = 'Additive phase is a uniform layer at the surface of an existing phase';
            app.Coating_instructions = uilabel(app.CoatingTab);
            app.Coating_instructions.Text = 'Instructions: Choose algorithms parameters and click generate. Once done, you can go to the outcome tab for viusalization and saving.';
            app.DepositDropDownLabel = uilabel(app.CoatingTab);
            app.DepositDropDownLabel.Text = 'Deposit';
            app.Coating_DepositDropDown = uidropdown(app.CoatingTab);
            app.Coating_DepositDropDown.Items = {'From particle surface to background', 'From particle surface to particle interior'};
            app.Coating_DepositDropDown.Value = 'From particle surface to background';
            app.IfLabel = uilabel(app.CoatingTab);
            app.IfLabel.Text = 'If ''From particle surface to particle interior'' is selected, new phase is generated within a solid phase, not within background';
            app.IfLabel_2 = uilabel(app.CoatingTab);
            app.IfLabel_2.Text = 'for phase Id';
            app.Coating_DepositDropDown_2 = uidropdown(app.CoatingTab);
            app.Coating_DepositDropDown_2.Items = {'1'};
            app.Coating_DepositDropDown_2.Value = '1';
            app.AchievedEditFieldLabel_3 = uilabel(app.CoatingTab);
            app.AchievedEditFieldLabel_3.Text = 'Achieved';
            app.Coating_AchievedEditField = uieditfield(app.CoatingTab, 'numeric');
            app.Coating_AchievedEditField.Editable = 'off';
            app.TargetvolumefractionLabel_5 = uilabel(app.CoatingTab);
            app.TargetvolumefractionLabel_5.Text = 'Target volume fraction';
            app.Coating_targetvolumefraction = uieditfield(app.CoatingTab, 'numeric');
            app.Coating_targetvolumefraction.Limits = [0 1];
            app.Coating_targetvolumefraction.Editable = 'off';
            app.Coating_targetvolumefraction.Value = 0.1;
            app.Coating_generation = uibutton(app.CoatingTab, 'push');
            app.Coating_generation.ButtonPushedFcn = createCallbackFcn(app, @Coating_generationButtonPushed, true);
            app.Coating_generation.Enable = 'off';
            app.Coating_generation.Text = 'Generate';
            app.Coating_statut = uitextarea(app.CoatingTab);
            app.Coating_statut.Value = {'Not running'};
            app.TargetisDropDownLabel = uilabel(app.CoatingTab);
            app.TargetisDropDownLabel.Text = 'Target is';
            app.Coating_TargetDropDown = uidropdown(app.CoatingTab);
            app.Coating_TargetDropDown.Items = {'Volume fraction', 'Coating depth in voxel'};
            app.Coating_TargetDropDown.ValueChangedFcn = createCallbackFcn(app, @Coating_TargetDropDownValueChanged, true);
            app.Coating_TargetDropDown.Value = 'Volume fraction';
            app.IfLabel_3 = uilabel(app.CoatingTab);
            app.IfLabel_3.Text = 'If ''Coating depth in voxel'' is selected, target volume fraction of first tab is not used.';
            app.TargetvolumefractionLabel_6 = uilabel(app.CoatingTab);
            app.TargetvolumefractionLabel_6.Enable = 'off';
            app.TargetvolumefractionLabel_6.Text = 'Target depth in voxel';
            app.Coating_targetvolumefraction_2 = uieditfield(app.CoatingTab, 'numeric');
            app.Coating_targetvolumefraction_2.Limits = [1 Inf];
            app.Coating_targetvolumefraction_2.RoundFractionalValues = 'on';
            app.Coating_targetvolumefraction_2.Editable = 'off';
            app.Coating_targetvolumefraction_2.Enable = 'off';
            app.Coating_targetvolumefraction_2.Value = 1;
            app.AchievedEditFieldLabel_4 = uilabel(app.CoatingTab);
            app.AchievedEditFieldLabel_4.Text = 'Achieved';
            app.Coating_AchievedEditField_2 = uieditfield(app.CoatingTab, 'numeric');
            app.Coating_AchievedEditField_2.Editable = 'off';
            app.OutcomeTab = uitab(app.TabGroup);
            app.OutcomeTab.Title = 'Outcome';
            app.O_title = uilabel(app.OutcomeTab);
            app.O_title.Text = 'Check generation result and save';
            app.O_savebutton = uibutton(app.OutcomeTab, 'push');
            app.O_savebutton.ButtonPushedFcn = createCallbackFcn(app, @O_savebuttonButtonPushed, true);
            app.O_savebutton.Enable = 'off';
            app.O_savebutton.Text = 'Save';
            app.O_vf_UITable = uitable(app.OutcomeTab);
            app.O_vf_UITable.ColumnName = {'Id'; 'Inital'; 'After generation'};
            app.O_vf_UITable.RowName = {};
            app.O_instructions = uilabel(app.OutcomeTab);
            app.O_instructions.Text = 'Instructions: Verify volume fractions are those expected after generation. Select save folder and write filename, then save.';
            app.O_vf_Label = uilabel(app.OutcomeTab);
            app.O_vf_Label.Text = 'Volume fractions';
            app.O_visualize = uibutton(app.OutcomeTab, 'push');
            app.O_visualize.ButtonPushedFcn = createCallbackFcn(app, @O_visualizeButtonPushed, true);
            app.O_visualize.Enable = 'off';
            app.O_visualize.Text = 'Visualize';
            app.O_Label = uilabel(app.OutcomeTab);
            app.O_Label.Text = {'If you are not satisifed with the generated additive phase, you can try with other parameters or methods.'; 'The microstructure is reset each time you click on a ''Generate'' button.'};
            app.O_FilenameLabel = uilabel(app.OutcomeTab);
            app.O_FilenameLabel.Text = 'Save Filename';
            app.O_filename_save = uieditfield(app.OutcomeTab, 'text');
            app.O_filename_save.Value = 'none';
            app.O_savefolderButton = uibutton(app.OutcomeTab, 'push');
            app.O_savefolderButton.ButtonPushedFcn = createCallbackFcn(app, @O_savefolderButtonPushed, true);
            app.O_savefolderButton.Text = 'Save folder';
            app.O_savefolderLabel = uilabel(app.OutcomeTab);
            app.O_savefolderLabel.Text = 'No save folder selected. Please select a folder.';
            app.ReferencesTab = uitab(app.TabGroup);
            app.ReferencesTab.Title = 'References';
            app.O_title_3 = uilabel(app.ReferencesTab);
            app.O_title_3.Text = 'References';
            app.Label_6 = uilabel(app.ReferencesTab);
            app.Label_6.Text = {'- For illustration of the "energy criterion" method, see [1], of the "Between neighbors" (bridge) method, see [2].'; ''; '- For impacf of additives on tortuosity factor and comparison with experimentally determined and macroscale fitted values see [1, 2].'; ''; '- For impact on connectivity, see [2].'};
            app.Coating_instructions_2 = uilabel(app.ReferencesTab);
            app.Coating_instructions_2.Text = 'Below is a selection of peer-reviewed journal articles from the authors discussing additives for lithium-ion battery materials.';
            app.Label_7 = uilabel(app.ReferencesTab);
            app.Label_7.Text = {'[2] F. L. E. Usseglio-Viretta, A. M. Colclasure, A. R. Dunlop, S. E. Trask, A. Jansen, D. P. Abraham, M-T F. Rodrigues, E. J. Dufek,'; 'T. Tanim, P. Chinnam, Y. Ha and K. Smith,  Carbon-Binder Weight Loading Optimization for Improved Lithium-Ion Battery Rate'; 'Capability, Journal of the Electrochemical Society, 2022'};
            app.Hyperlink_3 = uihyperlink(app.ReferencesTab);
            app.Hyperlink_3.URL = 'https://doi.org/10.1149/1945-7111/ac7ef9';
            app.Hyperlink_3.Text = 'https://doi.org/10.1149/1945-7111/ac7ef9';
            app.Label_9 = uilabel(app.ReferencesTab);
            app.Label_9.Text = {'[1] F. L. E. Usseglio-Viretta, A. Colclasure, A. N. Mistry, K. P. Y. Claver, F. Pouraghajan, D. P. Finegan, T. M. M. Heenan,'; 'D. Abraham, P. P. Mukherjee, D. Wheeler, P. Shearing, S. J. Cooper, and K. Smith, Resolving the Discrepancy in Tortuosity'; 'Factor Estimation for Li-Ion Battery Electrodes through Micro-Macro Modeling'; 'and Experiment, Journal of the electrochemical Society, 165, 2018'};
            app.Hyperlink_5 = uihyperlink(app.ReferencesTab);
            app.Hyperlink_5.URL = 'https://doi.org/10.1149/2.0731814jes';
            app.Hyperlink_5.Text = 'https://doi.org/10.1149/2.0731814jes';
            app.HowtoquoteTab = uitab(app.TabGroup);
            app.HowtoquoteTab.Title = 'How to quote';
            app.Load_title_2 = uilabel(app.HowtoquoteTab);
            app.Load_title_2.Text = 'How to quote';
            app.SelectasolidphaseandsetthenumberofLabel = uilabel(app.HowtoquoteTab);
            app.SelectasolidphaseandsetthenumberofLabel.Text = 'If you use this algorithm or report results produced thanks to this algorithm, please quote it as follow:';
            app.Label = uilabel(app.HowtoquoteTab);
            app.Label.Text = {'F. L. E. Usseglio-Viretta, P. Patel, E. Bernhardt, A Mistry, P. P. Mukherjee, J. Allen, S. J. Cooper, J. Laurencin, and K. Smith'; 'MATBOX: An Open-source Microstructure Analysis Toolbox for microstructure generation, segmentation, characterization,'; 'visualization, correlation, and meshing, SoftwareX, 17, 2022'};
            app.Hyperlink = uihyperlink(app.HowtoquoteTab);
            app.Hyperlink.URL = 'https://doi.org/10.1016/j.softx.2021.100915';
            app.Hyperlink.Text = 'https://doi.org/10.1016/j.softx.2021.100915';
            app.Label_2 = uilabel(app.HowtoquoteTab);
            app.Label_2.Text = {'If you generate the phase using the energy criterion, please ALSO quote:'; ''};
            app.Label_3 = uilabel(app.HowtoquoteTab);
            app.Label_3.Text = {'A. N. Mistry, K. Smith, and P. P. Mukherjee, Secondary Phase Stochastics in Lithium-Ion Battery Electrodes,'; 'ACS Appl. Mater. Interfaces, 10(7) pp. 6317-6326, 2018 '};
            app.Hyperlink_2 = uihyperlink(app.HowtoquoteTab);
            app.Hyperlink_2.URL = 'https://doi.org/10.1021/acsami.7b17771';
            app.Hyperlink_2.Text = 'https://doi.org/10.1021/acsami.7b17771';
            app.HelpTab = uitab(app.TabGroup);
            app.HelpTab.Title = 'Help';
            app.OpendocumentationButton_2 = uibutton(app.HelpTab, 'push');
            app.OpendocumentationButton_2.ButtonPushedFcn = createCallbackFcn(app, @OpendocumentationButton_2Pushed, true);
            app.OpendocumentationButton_2.Text = 'Open documentation';
            app.Label_10 = uilabel(app.HelpTab);
            app.Label_10.Text = '- Please read the documentation for detailed instructions.';
            app.Label_11 = uilabel(app.HelpTab);
            app.Label_11.Text = '- You can also read the MATBOX journal article.';
            app.Label_12 = uilabel(app.HelpTab);
            app.Label_12.Text = 'In addition, you can find a selection of journal articles that are using MATBOX in the "References" tab.';
            app.Label_13 = uilabel(app.HelpTab);
            app.Label_13.Text = {'- If you run into problems, please read the open issues listed in the GitHub webpage. If your problem is not listed, please open a'; 'new issue.'};
            app.Hyperlink_6 = uihyperlink(app.HelpTab);
            app.Hyperlink_6.URL = 'https://doi.org/10.1016/j.softx.2021.100915';
            app.Hyperlink_6.Text = 'https://doi.org/10.1016/j.softx.2021.100915';
            app.Hyperlink_7 = uihyperlink(app.HelpTab);
            app.Hyperlink_7.URL = 'https://github.com/NREL/MATBOX_Microstructure_analysis_toolbox/issues';
            app.Hyperlink_7.Text = 'https://github.com/NREL/MATBOX_Microstructure_analysis_toolbox/issues';
            app.Load_title_4 = uilabel(app.HelpTab);
            app.Load_title_4.Text = 'Help';
            app.AboutTab = uitab(app.TabGroup);
            app.AboutTab.Title = 'About';
            app.O_title_2 = uilabel(app.AboutTab);
            app.O_title_2.Text = 'About';
            app.Label_14 = uilabel(app.AboutTab);
            app.Label_14.Text = {'National Renewable Energy Laboratory (NREL) developed the Microstructure Analysis Toolbox (MATBOX) to analyze electrode'; 'microstructures for lithium-ion (Li-ion) batteries and other 3D heterogeneous materials.'};
            app.NREL_logo = uiimage(app.AboutTab);
            app.NREL_logo.ImageClickedFcn = createCallbackFcn(app, @NREL_logoImageClicked, true);
            app.NREL_logo.ImageSource = 'logo_NREL.png';
            app.Hyperlink_8 = uihyperlink(app.AboutTab);
            app.Hyperlink_8.URL = 'https://www.nrel.gov/transportation/matbox.html';
            app.Hyperlink_8.Text = 'MATBOX official webpage';
            app.Hyperlink_9 = uihyperlink(app.AboutTab);
            app.Hyperlink_9.URL = 'https://github.com/NREL/MATBOX_Microstructure_analysis_toolbox';
            app.Hyperlink_9.Text = 'MATBOX GitHub repository';
            app.MATBOXinstalledversionLabel = uilabel(app.AboutTab);
            app.MATBOXinstalledversionLabel.Text = 'MATBOX installed version:';
            app.Localversion = uilabel(app.AboutTab);
            app.Localversion.Text = '1.1 February 2023';
            app.OnlineversioncheckLabel = uilabel(app.AboutTab);
            app.OnlineversioncheckLabel.Text = 'Online version check';
            % Show the figure after all components are created
            app.Microstructure_additive_phase_generation.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Microstructure_generation_additives

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Microstructure_additive_phase_generation)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Microstructure_additive_phase_generation)
        end
    end
end