classdef Microstructure_generation_surfaceroughness < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SurfaceroughnessgenerationUIFigure  matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        InstructionsTab                 matlab.ui.container.Tab
        Instructions_main               matlab.ui.control.Label
        GridLayout6                     matlab.ui.container.GridLayout
        Label_6                         matlab.ui.control.Label
        InitialgeometryTab              matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        DimensionnaLabel                matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        GenerateSingleparticle_Button   matlab.ui.control.Button
        GenerateInitial_UITable         matlab.ui.control.Table
        Volumeimported_Label            matlab.ui.control.Label
        Visualize_volume_button         matlab.ui.control.Button
        ImportvolumeButton              matlab.ui.control.Button
        Option2generateasimplegeometrysingleparticleLabel  matlab.ui.control.Label
        Option1importanexistingsegmentedgeometryLabel  matlab.ui.control.Label
        Instructions_generation         matlab.ui.control.Label
        StochasticTab                   matlab.ui.container.Tab
        GridLayout_stochastic           matlab.ui.container.GridLayout
        Label_8                         matlab.ui.control.Label
        Idle_stochastic_Label           matlab.ui.control.Label
        ScalingEditField                matlab.ui.control.NumericEditField
        NumberofiterationLabel_2        matlab.ui.control.Label
        ParameterillustrationClickontheimagetoenlargeitLabel  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        Generate_Button                 matlab.ui.control.Button
        NumIterEditField                matlab.ui.control.NumericEditField
        NumberofiterationLabel          matlab.ui.control.Label
        InsideratioEditField            matlab.ui.control.NumericEditField
        InsideratioEditFieldLabel       matlab.ui.control.Label
        OutsideratioEditField           matlab.ui.control.NumericEditField
        OutsideratioEditFieldLabel      matlab.ui.control.Label
        SurfaceroughnesssizeLabel       matlab.ui.control.Label
        SurfaceroughnessabundanceLabel  matlab.ui.control.Label
        Parameters_title                matlab.ui.control.Label
        GrainTab                        matlab.ui.container.Tab
        GridLayout_Grain                matlab.ui.container.GridLayout
        Grain_IdleLabel                 matlab.ui.control.Label
        GenerategrainsButton            matlab.ui.control.Button
        Grain_UITable                   matlab.ui.control.Table
        GraingridsizeLabel              matlab.ui.control.Label
        SurfaceroughnessgrainbasedgenerationLabel  matlab.ui.control.Label
        OutcomeTab                      matlab.ui.container.Tab
        GridLayout_Outcome              matlab.ui.container.GridLayout
        Mesh_stat                       matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        VisualizeresultmeshButton       matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        FilenameEditField               matlab.ui.control.EditField
        FilenameEditField_2Label        matlab.ui.control.Label
        NofolderselectedLabel           matlab.ui.control.Label
        SavefolderButton                matlab.ui.control.Button
        VisualizeresultButton           matlab.ui.control.Button
        Vf_UITable                      matlab.ui.control.Table
        VolumefractionsLabel            matlab.ui.control.Label
        CheckgenerationresultandsaveLabel  matlab.ui.control.Label
        HowtoquoteTab                   matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        SelectasolidphaseandsetthenumberofLabel_2  matlab.ui.control.Label
        Hyperlink_5                     matlab.ui.control.Hyperlink
        Label_5                         matlab.ui.control.Label
        Hyperlink_4                     matlab.ui.control.Hyperlink
        Label_4                         matlab.ui.control.Label
        SelectasolidphaseandsetthenumberofLabel  matlab.ui.control.Label
        About_title_3                   matlab.ui.control.Label
        AboutTab                        matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        OnlineversioncheckLabel         matlab.ui.control.Label
        Localversion                    matlab.ui.control.Label
        MATBOXinstalledversionLabel     matlab.ui.control.Label
        Hyperlink_2                     matlab.ui.control.Hyperlink
        Hyperlink                       matlab.ui.control.Hyperlink
        NREL_logo                       matlab.ui.control.Image
        Label_2                         matlab.ui.control.Label
        About_title                     matlab.ui.control.Label
    end

    
    properties (Access = private)
        Initialvolume = []; % Initial volume before adding surface roughness
        Newvolume = []; % Volume after adding surface roughness
        gentype = 0;
        savefolder = [];
    end
    
    methods (Access = private)
        
        function [] = finished_generation(app)
            % Update volume fractions
            phases_before = unique(app.Initialvolume);
            if strcmp(app.gentype,'stochastic')
                phases_after = unique(app.Newvolume);
            elseif strcmp(app.gentype,'grains')
                tmp = app.Newvolume;
                tmp(tmp~=0)=1;
                phases_after = unique(tmp);
            end           
            phases = unique([phases_before; phases_after]);
            number_phase = length(phases);
            tabledata=zeros(number_phase,3);
            for current_phase=1:1:number_phase
                tabledata(current_phase,1)=phases(current_phase);
                tabledata(current_phase,2)=sum(sum(sum( app.Initialvolume==phases(current_phase)  ))) / numel(app.Initialvolume);
                if strcmp(app.gentype,'stochastic')
                    tabledata(current_phase,3)=sum(sum(sum( app.Newvolume==phases(current_phase)  ))) / numel(app.Newvolume);
                elseif strcmp(app.gentype,'grains')
                    tabledata(current_phase,3)=sum(sum(sum( tmp==phases(current_phase)  ))) / numel(app.Newvolume);
                end
            end
            app.Vf_UITable.Data = tabledata;

            children = get(app.GridLayout_Outcome,'Children');
            set(children,'Enable','on');    
            if ~isempty(app.Newvolume) && ~isempty(app.savefolder) && ~isempty(app.FilenameEditField.Value)
                app.SaveButton.Enable = "on";
            else
                app.SaveButton.Enable = "off";
            end
            app.TabGroup.SelectedTab = app.OutcomeTab; % Go to outcome tab
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.GenerateInitial_UITable.ColumnFormat = {'char','numeric','numeric'};
            app.GenerateInitial_UITable.Data = [ {'Axis 1';'Axis 2';'Axis 3'} num2cell([50;50;50]) num2cell([35;35;35])];

            % % Check online version
            % localversion = app.Localversion.Text;
            % githubversionfile=webread('https://raw.githubusercontent.com/NREL/MATBOX_Microstructure_analysis_toolbox/master/License.txt');
            % if ~strcmp(localversion,githubversionfile)
            %     app.OnlineversioncheckLabel.Text = ['There is a new version (' localversion ') available online. Please go to the Github repositorty to update your MATBOX installation'];
            %     app.OnlineversioncheckLabel.FontColor = 'r';
            % else
            %     app.OnlineversioncheckLabel.Text = 'You are using the up-to-date version of MATBOX';
            %     app.OnlineversioncheckLabel.FontColor = 'k';
            % end          

            if ~exist('iso2meshver') % iso2mesh is installed
                app.Mesh_CheckBox.Value = 0;
                app.Mesh_CheckBox.Enable = "off";                
            end            

            app.Grain_UITable.ColumnFormat = {'char','numeric','numeric','logical'};
            app.Grain_UITable.Data = [ {'1';'2';'3'} num2cell([10;10;10]) num2cell([3;3;3]) {false;false;false}];

            % GUI
            children = get(app.GridLayout_stochastic,'Children');
            set(children,'Enable','off');
            children = get(app.GridLayout_Grain,'Children');
            set(children,'Enable','off');            
            children = get(app.GridLayout_Outcome,'Children');
            set(children,'Enable','off');                

        end

        % Button pushed function: ImportvolumeButton
        function ImportvolumeButtonPushed(app, event)
            [FileName,PathName,~] = uigetfile({'*.tif;*.tiff','Tif image (*.tif, *.tiff)'},'Select a .tif file',pwd);
            if FileName==0
                % User clicked cancel button or closed the dialog box
                app.Volumeimported_Label.Text ='No file imported.';
                app.Volumeimported_Label.Tooltip = 'No file imported.';
                app.DimensionnaLabel.Text = 'Dimension: n/a';
                app.Visualize_volume_button.Enable = "off";
                
                app.Initialvolume = [];
                children = get(app.GridLayout_stochastic,'Children');
                set(children,'Enable','off');
                children = get(app.GridLayout_Grain,'Children');
                set(children,'Enable','off');
                children = get(app.GridLayout_Outcome,'Children');
                set(children,'Enable','off');

            else
                app.Volumeimported_Label.Text = 'Loading file... please wait'; pause(0.1);
                [tmp,outcome] = function_load_tif([PathName FileName]);
                if outcome % Success to import
                    tmp(tmp~=0)=1; % Binary image for now.
                    app.Initialvolume=tmp;
                    app.Volumeimported_Label.Text = [PathName FileName];
                    app.Volumeimported_Label.Tooltip = app.Volumeimported_Label.Text;
                    sz = size(tmp);
                    if length(sz)==2
                        app.DimensionnaLabel.Text = ['Dimension = 2, ' num2str(sz(1),'%i') ' x ' num2str(sz(2),'%i') ' = ' num2str(numel(tmp),'%i') ' pixels'];
                    else
                        app.DimensionnaLabel.Text = ['Dimension = 3, ' num2str(sz(1),'%i') ' x ' num2str(sz(2),'%i') ' x ' num2str(sz(3),'%i') ' = ' num2str(numel(tmp),'%i') ' voxels'];
                    end
                    app.savefolder = PathName;
                    app.NofolderselectedLabel.Text = app.savefolder ;

                    % GUI
                    app.Visualize_volume_button.Enable = "on";
                    children = get(app.GridLayout_stochastic,'Children');
                    set(children,'Enable','on');
                    children = get(app.GridLayout_Grain,'Children');
                    set(children,'Enable','on');
                    children = get(app.GridLayout_Outcome,'Children');
                    set(children,'Enable','off');                    
                else
                    app.Volumeimported_Label.Text ='Error: volume cannot be loaded! File is not a .tif file';
                    app.Volumeimported_Label.Tooltip = 'No file imported.';
                    app.DimensionnaLabel.Text = 'Dimension: n/a';
                    app.Visualize_volume_button.Enable = "off";

                    app.Initialvolume = [];
                    children = get(app.GridLayout_stochastic,'Children');
                    set(children,'Enable','off');
                    children = get(app.GridLayout_Grain,'Children');
                    set(children,'Enable','off');
                    children = get(app.GridLayout_Outcome,'Children');
                    set(children,'Enable','off');
                end
            end

        end

        % Button pushed function: Visualize_volume_button
        function Visualize_volume_buttonButtonPushed(app, event)
            Microstructure_basic_visualization_interface(app.Initialvolume);
        end

        % Button pushed function: GenerateSingleparticle_Button
        function GenerateSingleparticle_ButtonPushed(app, event)
            data = cell2mat(app.GenerateInitial_UITable.Data(:,2:3));
            fovx = data(1,1); fovy = data(2,1); fovz = data(3,1); 
            dx = data(1,2); dy = data(2,2); dz = data(3,2);             

            if ~mod(dx,2) && ~mod(dy,2) && ~mod(dz,2) && dx==dy && dx==dz
                % Sphere, even diameter
                binary_ellipsoid = zeros(dx,dx,dx);
                center1 = dx/2;
                center2 = center1+1;
                binary_ellipsoid(center1,center1,center1) = 1;
                binary_ellipsoid(center2,center1,center1) = 1;
                binary_ellipsoid(center1,center2,center1) = 1;
                binary_ellipsoid(center2,center2,center1) = 1;
                binary_ellipsoid(center1,center1,center2) = 1;
                binary_ellipsoid(center2,center1,center2) = 1;
                binary_ellipsoid(center1,center2,center2) = 1;
                binary_ellipsoid(center2,center2,center2) = 1;
                dmap_sphere = bwdist(binary_ellipsoid);
                radius = (dx-2)/2;
                binary_ellipsoid(dmap_sphere<=radius) = 1;
            else
                % Exact for sphere and ellipsoid with odd diameters. Approximation if one diameter is even
                % Source: https://www.mathworks.com/matlabcentral/answers/58885-creating-a-spherical-matrix
                Rx = (dx)/2; Ry = (dy)/2; Rz = (dz)/2; % Three radius
                [X,Y,Z] = ndgrid(linspace(-Rx,Rx,dx),linspace(-Ry,Ry,dy),linspace(-Rz,Rz,dz));
                R = sqrt((X/Rx).^2 + (Y/Ry).^2 + (Z/Rz).^2);
                binary_ellipsoid = zeros(size(X));
                %binary_ellipsoid(R <= 1 ) = 1; % Assign 1 for ellipsoid, 0 for complementary volume
                idr = find(R<=1);
                if isempty(idr)
                    idr = find(R==min(min(min(R))));
                end
                binary_ellipsoid(idr) = 1; % Assign 1 for ellipsoid, 0 for complementary volume
            end 

            % Within FOV
            if fovx==dx && fovy==dy && fovz==dz
                app.Initialvolume = binary_ellipsoid;
            else
                app.Initialvolume = zeros(fovx,fovy,fovz);
                sz = size(binary_ellipsoid);
                x_start = round((fovx-sz(1))/2); x_end = x_start+sz(1)-1;
                y_start = round((fovy-sz(2))/2); y_end = y_start+sz(2)-1;
                z_start = round((fovz-sz(3))/2); z_end = z_start+sz(3)-1;                
                app.Initialvolume(x_start:x_end,y_start:y_end,z_start:z_end) = binary_ellipsoid;
            end

            % Update GUI
            app.savefolder = pwd;
            app.NofolderselectedLabel.Text = app.savefolder ;
            app.Volumeimported_Label.Text = 'Sphere';

            app.Visualize_volume_button.Enable = "on";
            children = get(app.GridLayout_stochastic,'Children');
            set(children,'Enable','on');
            children = get(app.GridLayout_Grain,'Children');
            set(children,'Enable','on');
            children = get(app.GridLayout_Outcome,'Children');
            set(children,'Enable','off');
            app.DimensionnaLabel.Text = 'Dimension: n/a';
        end

        % Cell edit callback: GenerateInitial_UITable
        function GenerateInitial_UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            if isnan(newData) || newData<1 || round(newData)~=newData % Incorrect input
                app.GenerateInitial_UITable.Data(indices(1), indices(2)) = num2cell(event.PreviousData);
                f = msgbox('Invalid Value: must be integer >=1', 'Error','warn');
            else
                data = cell2mat(app.GenerateInitial_UITable.Data(:,2:3));
                if sum(data(:,1)<data(:,2))
                    app.GenerateInitial_UITable.Data(indices(1), indices(2)) = num2cell(event.PreviousData);
                    f = msgbox('Invalid Value: FOV must be >= Particle diameter', 'Error','warn');
                end
            end
        end

        % Button pushed function: Generate_Button
        function Generate_ButtonPushed(app, event)
            app.gentype = 'stochastic';
            app.Newvolume = []; % Reset;

            opts.scaling = app.ScalingEditField.Value;
            opts.niter = app.NumIterEditField.Value;
            opts.ratio_outside_sr = app.OutsideratioEditField.Value;
            opts.ratio_inside_sr = app.InsideratioEditField.Value;
            app.Idle_stochastic_Label.Text = 'Running... please wait'; pause(0.1);
            tic
            [app.Newvolume] = function_add_surfaceroughness(app.Initialvolume,opts);
            t = toc;
            app.Idle_stochastic_Label.Text = ['Done! Wallclock time = ' num2str(t,'%1.0f') 's']; pause(0.1);

            [~,name,~] = fileparts(app.Volumeimported_Label.Text);
            app.FilenameEditField.Value = [name '_stochastic'];
            app.finished_generation;
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            winopen('Surfaceroughness_example.png');
        end

        % Image clicked function: NREL_logo
        function NREL_logoImageClicked(app, event)
            url = 'https://www.nrel.gov/';
            web(url)       
        end

        % Button pushed function: SavefolderButton
        function SelectsavefolderButtonPushed(app, event)
            % Set string of the dialog box
            str_dialogbox = 'Select location where the volume will be saved';
            % Open dialog box to choose folder path
            resultfolder_location = uigetdir(pwd,str_dialogbox);
            if resultfolder_location
                app.savefolder = resultfolder_location;
                app.NofolderselectedLabel.Text = app.savefolder;                
            end

            if ~isempty(app.Newvolume) && ~isempty(app.savefolder) && ~isempty(app.FilenameEditField.Value)
                app.SaveButton.Enable = "on";
            else
                app.SaveButton.Enable = "off";
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if ~isempty(app.Newvolume)
                [~,filesave,~] = fileparts(app.FilenameEditField.Value); % Remove .tif if any
                if strcmp(app.gentype,'stochastic')
                    f = fullfile(app.savefolder,[filesave '.tif']);
                    function_save_tif(app.Newvolume, f);
                elseif strcmp(app.gentype,'grains')
                    f = fullfile(app.savefolder,[filesave '_grainId.tif']);
                    function_save_tif(app.Newvolume, f);
                    f = fullfile(app.savefolder,[filesave '.tif']);
                    tmp = app.Newvolume;
                    tmp(tmp~=0)=1;                    
                    function_save_tif(uint8(tmp), f);  
                end
            end          
        end

        % Value changed function: FilenameEditField
        function FilenameEditFieldValueChanged(app, event)
            if ~isempty(app.Newvolume) && ~isempty(app.savefolder) && ~isempty(app.FilenameEditField.Value)
                app.SaveButton.Enable = "on";
            else
                app.SaveButton.Enable = "off";
            end            
        end

        % Button pushed function: GenerategrainsButton
        function GenerategrainsButtonPushed(app, event)
            app.gentype = 'grains';
            app.Newvolume = []; % Reset;
            
            app.Grain_IdleLabel.Text = 'Please wait...'; pause(0.1);  
            p.average_size = cell2mat(app.Grain_UITable.Data(:,2));
            p.sigma_ = cell2mat(app.Grain_UITable.Data(:,3));
            p.equiprobability = logical(cell2mat(app.Grain_UITable.Data(:,4)));
            tic
            [app.Newvolume] = function_add_grains(app.Initialvolume,p);
            wallclock_time = toc;
            app.Grain_IdleLabel.Text = ['Done! Wall clock time: ' num2str(wallclock_time,'%1.1f') ' s']; pause(0.1);

            [~,name,~] = fileparts(app.Volumeimported_Label.Text);
            app.FilenameEditField.Value = [name '_grains'];

            app.finished_generation;
        end

        % Button pushed function: VisualizeresultButton
        function VisualizeresultButtonPushed(app, event)
            if strcmp(app.gentype,'stochastic')
                p(1).name = 'Original binary volume';
                p(2).name = 'With stochastic surface roughness';
                Visualization_multiple_volumes(p,app.Initialvolume,app.Newvolume);                
            elseif strcmp(app.gentype,'grains')
                p(1).name = 'Original binary volume';
                p(2).name = 'With grain surface roughness';
                p(3).name = 'Grains Id';
                tmp = app.Newvolume;
                tmp(tmp~=0)=1;
                Visualization_multiple_volumes(p,app.Initialvolume,uint8(tmp),app.Newvolume);
            end
        end

        % Button pushed function: VisualizeresultmeshButton
        function VisualizeresultmeshButtonPushed(app, event)
            if exist('iso2meshver')
                app.Mesh_stat.Text = 'Meshing... please wait'; pause(0.1);
                % Parameter
                opts.radbound = 1;
                opts.distbound =  1;
                opts.method_surfacemesh = 'lowpass'; %'laplacian';
                opts.iteration_smoothing = 3;
                opts.useralpha = 0.5;
                opts.keepratio = 1;
                opts.maxvol = 10;

                % Create volumetric mesh
                % Please run the meshing module to preprocess adequately the 3D array.
                if strcmp(app.gentype,'stochastic')
                    [node_, face_, elem_, subdomain_] = function_iso2mesh_from_array(app.Newvolume+1, opts); % no 0
                elseif strcmp(app.gentype,'grains')
                    tmp = app.Newvolume;
                    tmp(tmp~=0)=1;
                    [node_, face_, elem_, subdomain_] = function_iso2mesh_from_array(tmp+1, opts); % no 0
                end

                % Retreive solid phase mesh
                current_phase = 2;
                idx = find(subdomain_==current_phase);
                vertices_idx_phase = unique(elem_(idx,:));
                vertices_phase = node_(vertices_idx_phase,:);
                check_face = ismember(face_,vertices_idx_phase);
                faces_idx_phase = find( sum(check_face,2)==3 );

                %[Lia,Locb] = ismember(round(node_,5), round(vertices_group,5),'rows'); % Precision with 5 digits (tolerance)
                [Lia,Locb] = ismembertol(node_, vertices_phase,1e-6,'ByRows',true);
                old_id = find(Lia==1);
                new_id = Locb(old_id);

                % Show solid phase mesh
                meshphase(1).node_ = vertices_phase;
                meshphase(1).face_ = my_changem(face_(faces_idx_phase,:), new_id, old_id);
                meshphase(1).elem_ = my_changem(elem_(idx,:), new_id, old_id);
                meshphase(1).subdomain_ = subdomain_(idx);
                meshphase(1).name = ['Surface roughness ' app.gentype];

                options.show = 'Volume';
                options.lighting = 'flat';
                options.edges = 'Not visible';
                options.coloris = 'z-axis';
                options.colormap = 'Default';
                options.transparency = 0;
                options.grid = 0;
                options.axislabel = 0;
                options.savefig = 0;
                options.savepng = 1;
                options.fig_infig = 0;
                options.indexstart_zero = 1;
                options.folder = [app.savefolder '\'];

                function_showmesh(meshphase(1).node_, meshphase(1).elem_, meshphase(1).face_, meshphase(1).subdomain_, meshphase(1).name, options);
                app.Mesh_stat.Text = 'Meshing... done!'; pause(0.1);

            else
                app.Mesh_stat.Text = 'Iso2mesh is not installed';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.SurfaceroughnessgenerationUIFigure = uifigure('Visible', 'off');
            app.SurfaceroughnessgenerationUIFigure.Name = 'Surface roughness generation';
            app.SurfaceroughnessgenerationUIFigure.Icon = 'Icon_generation_surfaceroughness.png';
            app.TabGroup = uitabgroup(app.SurfaceroughnessgenerationUIFigure);
            app.InstructionsTab = uitab(app.TabGroup);
            app.InstructionsTab.Title = 'Instructions';
            app.GridLayout6 = uigridlayout(app.InstructionsTab);
            app.Label_6 = uilabel(app.GridLayout6);
            app.Label_6.Text = {'- First import a segmented tif file or generate a simple sphere geometry in the next tab. It is recommended to start with the simple sphere geometry to experiment with the algorithm parameters before using more complex geometries.'; 'If you will use the stochastic method it is recommended to start with a small field of view as the surface roughness algorithm relies on iterative upscaling.'; ''; '- Then, set algorithm parameters in the tab that corresponds to the method of your choice (stochastic or grain), and run the generation. '; ''; '- Lastly, once generation is finished, you can visualize the obtained geometry and saved it in a tif file.'};
            app.Instructions_main = uilabel(app.InstructionsTab);
            app.Instructions_main.Text = 'Instructions';
            app.InitialgeometryTab = uitab(app.TabGroup);
            app.InitialgeometryTab.Title = 'Initial geometry';
            app.GridLayout = uigridlayout(app.InitialgeometryTab);
            app.Instructions_generation = uilabel(app.GridLayout);
            app.Instructions_generation.Text = 'Import or generate geometry';
            app.Option1importanexistingsegmentedgeometryLabel = uilabel(app.GridLayout);
            app.Option1importanexistingsegmentedgeometryLabel.Text = 'Option 1: import an existing segmented geometry';
            app.Option2generateasimplegeometrysingleparticleLabel = uilabel(app.GridLayout);
            app.Option2generateasimplegeometrysingleparticleLabel.Text = 'Option 2: generate a simple geometry (single particle centered in the FOV)';
            app.ImportvolumeButton = uibutton(app.GridLayout, 'push');
            app.ImportvolumeButton.ButtonPushedFcn = createCallbackFcn(app, @ImportvolumeButtonPushed, true);
            app.ImportvolumeButton.Text = 'Import volume';
            app.Visualize_volume_button = uibutton(app.GridLayout, 'push');
            app.Visualize_volume_button.ButtonPushedFcn = createCallbackFcn(app, @Visualize_volume_buttonButtonPushed, true);
            app.Visualize_volume_button.Enable = 'off';
            app.Visualize_volume_button.Text = 'Visualize volume';
            app.Volumeimported_Label = uilabel(app.GridLayout);
            app.Volumeimported_Label.Text = 'No volume imported';
            app.GenerateInitial_UITable = uitable(app.GridLayout);
            app.GenerateInitial_UITable.ColumnName = {'Direction'; 'FOV'; 'Particle diameters'};
            app.GenerateInitial_UITable.RowName = {};
            app.GenerateInitial_UITable.ColumnEditable = [false true true];
            app.GenerateInitial_UITable.CellEditCallback = createCallbackFcn(app, @GenerateInitial_UITableCellEdit, true);
            app.GenerateSingleparticle_Button = uibutton(app.GridLayout, 'push');
            app.GenerateSingleparticle_Button.ButtonPushedFcn = createCallbackFcn(app, @GenerateSingleparticle_ButtonPushed, true);
            app.GenerateSingleparticle_Button.Text = 'Generate';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = 'As the surface roughness generation method upscales the volume, a relatively small FOV is preferable.';
            app.DimensionnaLabel = uilabel(app.GridLayout);
            app.DimensionnaLabel.Text = 'Dimension: n/a';
            app.StochasticTab = uitab(app.TabGroup);
            app.StochasticTab.Title = 'Stochastic';
            app.GridLayout_stochastic = uigridlayout(app.StochasticTab);
            app.Parameters_title = uilabel(app.GridLayout_stochastic);
            app.Parameters_title.Text = 'Surface roughness stochastic-based generation';
            app.SurfaceroughnessabundanceLabel = uilabel(app.GridLayout_stochastic);
            app.SurfaceroughnessabundanceLabel.Tooltip = {'Normalized size of the subset of the randomly selected voxels at the particle surface.'};
            app.SurfaceroughnessabundanceLabel.Text = 'Surface roughness abundance ';
            app.SurfaceroughnesssizeLabel = uilabel(app.GridLayout_stochastic);
            app.SurfaceroughnesssizeLabel.Text = 'Surface roughness size';
            app.OutsideratioEditFieldLabel = uilabel(app.GridLayout_stochastic);
            app.OutsideratioEditFieldLabel.Tooltip = {'Normalized size of the subset of the randomly selected voxels at the particle surface.'};
            app.OutsideratioEditFieldLabel.Text = 'Outside ratio';
            app.OutsideratioEditField = uieditfield(app.GridLayout_stochastic, 'numeric');
            app.OutsideratioEditField.Limits = [0 1];
            app.OutsideratioEditField.Tooltip = {'Normalized size of the subset of the randomly selected voxels at the particle surface.'};
            app.OutsideratioEditField.Value = 0.25;
            app.InsideratioEditFieldLabel = uilabel(app.GridLayout_stochastic);
            app.InsideratioEditFieldLabel.Tooltip = {'Normalized size of the subset of the randomly selected voxels at the particle surface.'};
            app.InsideratioEditFieldLabel.Text = 'Inside ratio';
            app.InsideratioEditField = uieditfield(app.GridLayout_stochastic, 'numeric');
            app.InsideratioEditField.Limits = [0 1];
            app.InsideratioEditField.Tooltip = {'Normalized size of the subset of the randomly selected voxels at the particle surface.'};
            app.InsideratioEditField.Value = 0.25;
            app.NumberofiterationLabel = uilabel(app.GridLayout_stochastic);
            app.NumberofiterationLabel.Tooltip = {'Normalized size of the subset of the randomly selected voxels at the particle surface.'};
            app.NumberofiterationLabel.Text = 'Number of upscale ';
            app.NumIterEditField = uieditfield(app.GridLayout_stochastic, 'numeric');
            app.NumIterEditField.Limits = [1 10];
            app.NumIterEditField.RoundFractionalValues = 'on';
            app.NumIterEditField.Tooltip = {'Normalized size of the subset of the randomly selected voxels at the particle surface.'};
            app.NumIterEditField.Value = 2;
            app.Generate_Button = uibutton(app.GridLayout_stochastic, 'push');
            app.Generate_Button.ButtonPushedFcn = createCallbackFcn(app, @Generate_ButtonPushed, true);
            app.Generate_Button.Text = 'Run generation algorithm';
            app.Image = uiimage(app.GridLayout_stochastic);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Surfaceroughness_example.png');
            app.ParameterillustrationClickontheimagetoenlargeitLabel = uilabel(app.GridLayout_stochastic);
            app.ParameterillustrationClickontheimagetoenlargeitLabel.Text = 'Parameter illustration. Click on the image to enlarge it.';
            app.NumberofiterationLabel_2 = uilabel(app.GridLayout_stochastic);
            app.NumberofiterationLabel_2.Tooltip = {'Normalized size of the subset of the randomly selected voxels at the particle surface.'};
            app.NumberofiterationLabel_2.Text = 'Scaling factor';
            app.ScalingEditField = uieditfield(app.GridLayout_stochastic, 'numeric');
            app.ScalingEditField.Limits = [1.1 10];
            app.ScalingEditField.Tooltip = {'Normalized size of the subset of the randomly selected voxels at the particle surface.'};
            app.ScalingEditField.Value = 2;
            app.Idle_stochastic_Label = uilabel(app.GridLayout_stochastic);
            app.Idle_stochastic_Label.Text = 'Idle';
            app.Label_8 = uilabel(app.GridLayout_stochastic);
            app.Label_8.Text = {'The stochastic algorithm proceeds as follow. A subet of the voxels located at the pore-solid interface, from the solid side and from the'; 'pore side, are first randomly selected. Voxels from the pore side are assigned to the solid, voxels from the solid side are assigned to'; 'the pore. The modified geometry is then upscaled, and the process (subset selction followed by upscaling) is repeated. Connectivity'; 'post-processing removes any isolated pore and solid.'; 'The surface roughness generation algorithm could be then described as an iterative stochastic upscaled local dilatation and erosion'; 'followed by a connectivity step. In the parameters tab, a figure shows results obtained with a sphere using different parameters.'; 'For a description of the stochastic algorithm, please see second article in the ''How to quote'' tab.'};
            app.GrainTab = uitab(app.TabGroup);
            app.GrainTab.Title = 'Grain';
            app.GridLayout_Grain = uigridlayout(app.GrainTab);
            app.SurfaceroughnessgrainbasedgenerationLabel = uilabel(app.GridLayout_Grain);
            app.SurfaceroughnessgrainbasedgenerationLabel.Text = 'Surface roughness grain-based generation';
            app.GraingridsizeLabel = uilabel(app.GridLayout_Grain);
            app.GraingridsizeLabel.Text = 'Grain grid size';
            app.Grain_UITable = uitable(app.GridLayout_Grain);
            app.Grain_UITable.ColumnName = {'Axe'; 'Mean'; 'Standard deviation'; 'Equiprobability'};
            app.Grain_UITable.RowName = {};
            app.Grain_UITable.ColumnEditable = [false true true true];
            app.GenerategrainsButton = uibutton(app.GridLayout_Grain, 'push');
            app.GenerategrainsButton.ButtonPushedFcn = createCallbackFcn(app, @GenerategrainsButtonPushed, true);
            app.GenerategrainsButton.Text = 'Generate grains';
            app.Grain_IdleLabel = uilabel(app.GridLayout_Grain);
            app.Grain_IdleLabel.Text = 'Idle';
            app.OutcomeTab = uitab(app.TabGroup);
            app.OutcomeTab.Title = 'Outcome';
            app.GridLayout_Outcome = uigridlayout(app.OutcomeTab);
            app.CheckgenerationresultandsaveLabel = uilabel(app.GridLayout_Outcome);
            app.CheckgenerationresultandsaveLabel.Text = 'Check generation result and save';
            app.VolumefractionsLabel = uilabel(app.GridLayout_Outcome);
            app.VolumefractionsLabel.Text = 'Volume fractions';
            app.Vf_UITable = uitable(app.GridLayout_Outcome);
            app.Vf_UITable.ColumnName = {'Id'; 'Initial'; 'After generation'};
            app.Vf_UITable.RowName = {};
            app.VisualizeresultButton = uibutton(app.GridLayout_Outcome, 'push');
            app.VisualizeresultButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizeresultButtonPushed, true);
            app.VisualizeresultButton.Text = 'Visualize result';
            app.SavefolderButton = uibutton(app.GridLayout_Outcome, 'push');
            app.SavefolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectsavefolderButtonPushed, true);
            app.SavefolderButton.Text = 'Save folder';
            app.NofolderselectedLabel = uilabel(app.GridLayout_Outcome);
            app.NofolderselectedLabel.Text = 'No folder selected';
            app.FilenameEditField_2Label = uilabel(app.GridLayout_Outcome);
            app.FilenameEditField_2Label.Text = 'File name';
            app.FilenameEditField = uieditfield(app.GridLayout_Outcome, 'text');
            app.FilenameEditField.ValueChangedFcn = createCallbackFcn(app, @FilenameEditFieldValueChanged, true);
            app.SaveButton = uibutton(app.GridLayout_Outcome, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.VisualizeresultmeshButton = uibutton(app.GridLayout_Outcome, 'push');
            app.VisualizeresultmeshButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizeresultmeshButtonPushed, true);
            app.VisualizeresultmeshButton.Text = 'Visualize result (mesh)';
            app.Label_7 = uilabel(app.GridLayout_Outcome);
            app.Label_7.Text = 'Mesh for visualization only. Use the meshing module if you want to preprocess more correctly the 3D array.';
            app.Mesh_stat = uilabel(app.GridLayout_Outcome);
            app.Mesh_stat.Text = '';
            app.HowtoquoteTab = uitab(app.TabGroup);
            app.HowtoquoteTab.Title = 'How to quote';
            app.GridLayout5 = uigridlayout(app.HowtoquoteTab);
            app.About_title_3 = uilabel(app.GridLayout5);
            app.About_title_3.Text = 'How to quote';
            app.SelectasolidphaseandsetthenumberofLabel = uilabel(app.GridLayout5);
            app.SelectasolidphaseandsetthenumberofLabel.Text = 'Please always quote the main MATBOX article:';
            app.Label_4 = uilabel(app.GridLayout5);
            app.Label_4.Text = {'F. L. E. Usseglio-Viretta, P. Patel, E. Bernhardt, A Mistry, P. P. Mukherjee, J. Allen, S. J. Cooper, J. Laurencin, and K. Smith'; 'MATBOX: An Open-source Microstructure Analysis Toolbox for microstructure generation, segmentation, characterization,'; 'visualization, correlation, and meshing SoftwareX, 17, 2022'};
            app.Hyperlink_4 = uihyperlink(app.GridLayout5);
            app.Hyperlink_4.URL = 'https://doi.org/10.1016/j.softx.2021.100915';
            app.Hyperlink_4.Text = 'https://doi.org/10.1016/j.softx.2021.100915';
            app.Label_5 = uilabel(app.GridLayout5);
            app.Label_5.Text = {'F. L. E. Usseglio-Viretta et al., Microstructure Scale Lithium-ion Battery Modeling, Part I: on lithium plating prediction and'; 'heterogeneity, In redaction'};
            app.Hyperlink_5 = uihyperlink(app.GridLayout5);
            app.Hyperlink_5.URL = 'https://doi.org/10.1016/j.softx.2021.100915';
            app.Hyperlink_5.Text = 'DOI to update once published';
            app.SelectasolidphaseandsetthenumberofLabel_2 = uilabel(app.GridLayout5);
            app.SelectasolidphaseandsetthenumberofLabel_2.Text = 'If you use the stochastic method, please also quote this article:';
            app.AboutTab = uitab(app.TabGroup);
            app.AboutTab.Title = 'About';
            app.GridLayout3 = uigridlayout(app.AboutTab);
            app.About_title = uilabel(app.GridLayout3);
            app.About_title.Text = 'About';
            app.Label_2 = uilabel(app.GridLayout3);
            app.Label_2.Text = {'National Renewable Energy Laboratory (NREL) developed the Microstructure Analysis Toolbox (MATBOX) to analyze electrode'; 'microstructures for lithium-ion (Li-ion) batteries and other 3D heterogeneous materials.'};
            app.NREL_logo = uiimage(app.GridLayout3);
            app.NREL_logo.ImageClickedFcn = createCallbackFcn(app, @NREL_logoImageClicked, true);
            app.NREL_logo.ImageSource = 'logo_NREL.png';
            app.Hyperlink = uihyperlink(app.GridLayout3);
            app.Hyperlink.URL = 'https://www.nrel.gov/transportation/matbox.html';
            app.Hyperlink.Text = 'MATBOX official webpage';
            app.Hyperlink_2 = uihyperlink(app.GridLayout3);
            app.Hyperlink_2.URL = 'https://github.com/NREL/MATBOX_Microstructure_analysis_toolbox';
            app.Hyperlink_2.Text = 'MATBOX GitHub repository';
            app.MATBOXinstalledversionLabel = uilabel(app.GridLayout3);
            app.MATBOXinstalledversionLabel.Text = 'MATBOX installed version:';
            app.Localversion = uilabel(app.GridLayout3);
            app.Localversion.Text = '1.1 February 2023';
            app.OnlineversioncheckLabel = uilabel(app.GridLayout3);
            app.OnlineversioncheckLabel.Text = 'Online version check';
            % Show the figure after all components are created
            app.SurfaceroughnessgenerationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Microstructure_generation_surfaceroughness

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SurfaceroughnessgenerationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SurfaceroughnessgenerationUIFigure)
        end
    end
end