classdef Microstructure_generation_tubes < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TubebasedstochasticgenerationsubmoduleUIFigure  matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        InstructionsTab                 matlab.ui.container.Tab
        GridLayout2_10                  matlab.ui.container.GridLayout
        Imageprocessing_Label           matlab.ui.control.Label
        SelectasolidphaseandsetthenumberofLabel_2  matlab.ui.control.Label
        AdvicesLabel                    matlab.ui.control.Label
        SelectasolidphaseandsetthenumberofLabel  matlab.ui.control.Label
        TubesaregeneratedsequentiallyoneaftereachotherLabel  matlab.ui.control.Label
        Instructions_title_10           matlab.ui.control.Label
        SaveTab                         matlab.ui.container.Tab
        GridLayout2_4                   matlab.ui.container.GridLayout
        SavecontinuouslyslowerCheckBox  matlab.ui.control.CheckBox
        SelectwhereyouwanttosaveinputsandresultsLabel  matlab.ui.control.Label
        NosavefolderselectedLabel       matlab.ui.control.Label
        Save_folder_button              matlab.ui.control.Button
        Instructions_title_4            matlab.ui.control.Label
        ParametersTab                   matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        andchangewithDropDown           matlab.ui.control.DropDown
        andchangewithDropDownLabel      matlab.ui.control.Label
        SetorientationwithDropDown      matlab.ui.control.DropDown
        SetorientationwithDropDownLabel  matlab.ui.control.Label
        Forcestartingpointequidist_CheckBox  matlab.ui.control.CheckBox
        ClicktoenlargepictureLabel      matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        EntertubesparametersLabel_3     matlab.ui.control.Label
        TubetensionEditField            matlab.ui.control.NumericEditField
        TubetensionEditFieldLabel       matlab.ui.control.Label
        Tubeparameters_UITable_2        matlab.ui.control.Table
        EntertubesparametersLabel_2     matlab.ui.control.Label
        TubesonlystartsatdomainsedgesCheckBox  matlab.ui.control.CheckBox
        FinitelengthusetablebelowCheckBox  matlab.ui.control.CheckBox
        CrosssectionshapeDropDown       matlab.ui.control.DropDown
        CrosssectionshapeDropDownLabel  matlab.ui.control.Label
        orsetminimumtubelengthinvoxellengthEditField  matlab.ui.control.NumericEditField
        MinimumtubelengthinnumberofvoxelLabel  matlab.ui.control.Label
        DiscardtubeifbackwardprogressionfromlastxpointsEditField  matlab.ui.control.NumericEditField
        DiscardtubeifbackwardprogressionfromlastxpointsLabel  matlab.ui.control.Label
        CheckbackwardprogressionCheckBox  matlab.ui.control.CheckBox
        BackwardprogressionconstraintsLabel  matlab.ui.control.Label
        IncaseofdeadendmaximumretryfrompreviouspointEditField  matlab.ui.control.NumericEditField
        IncaseofdeadendmaximumretryfrompreviouspointEditFieldLabel  matlab.ui.control.Label
        DoubleorientationchangeeachxfailedattemptsprogressiveEditField  matlab.ui.control.NumericEditField
        DoubleorientationchangeeachfailedattemptsEditFieldLabel  matlab.ui.control.Label
        RelaxorientationlimitswithnumberofattempsCheckBox  matlab.ui.control.CheckBox
        MaximumnumberofresamplingfromcurrentpointEditField  matlab.ui.control.NumericEditField
        MaximumnumberofresamplingfromcurrentpointEditFieldLabel  matlab.ui.control.Label
        RetryparametersLabel            matlab.ui.control.Label
        PlotdistributionsoptionalButton  matlab.ui.control.Button
        YoucanplottheparameterdistribituionstocheckyourinputsLabel  matlab.ui.control.Label
        EntertubesparametersLabel       matlab.ui.control.Label
        Tubeparameters_UITable          matlab.ui.control.Table
        SelecttubesstartinglocationandcrosssectionshapeLabel  matlab.ui.control.Label
        EnterdomainsizeinnumberofvoxelsalongeachdirectionLabel  matlab.ui.control.Label
        Domainsize_UITable              matlab.ui.control.Table
        Instructions_title              matlab.ui.control.Label
        MaskTab                         matlab.ui.container.Tab
        GridLayout2_3                   matlab.ui.container.GridLayout
        BydefaulttubesarenotallowedtooverlappwitheachotherLabel  matlab.ui.control.Label
        TubescanoverlappwitheachotherCheckBox  matlab.ui.control.CheckBox
        UsenotwithinmaskCheckBox        matlab.ui.control.CheckBox
        mask_volumefraction_label       matlab.ui.control.Label
        Mask_volume_name_label          matlab.ui.control.Label
        Visualize_mask_button           matlab.ui.control.Button
        ImportmaskButton                matlab.ui.control.Button
        ClicktoimportnotwithinmaskLabel  matlab.ui.control.Label
        MaskmustsharethesamedimensionsizewiththegeneratedvolumeLabel  matlab.ui.control.Label
        EnterscalingfactorLabel_2       matlab.ui.control.Label
        Instructions_title_3            matlab.ui.control.Label
        StoppingconditionsTab           matlab.ui.container.Tab
        GridLayout2_6                   matlab.ui.container.GridLayout
        MaximumwallclocktimesLabel_2    matlab.ui.control.Label
        NumberoftubesEditField          matlab.ui.control.NumericEditField
        NumberoftubesEditField_2Label   matlab.ui.control.Label
        VolumefractionEditField         matlab.ui.control.NumericEditField
        VolumefractionEditField_2Label  matlab.ui.control.Label
        StoppertubeEditField            matlab.ui.control.NumericEditField
        PertubewallclokctimemaximumsLabel  matlab.ui.control.Label
        StoptotalEditField              matlab.ui.control.NumericEditField
        TotalwallclocktimemaximumLabel  matlab.ui.control.Label
        MaximumwallclocktimesLabel      matlab.ui.control.Label
        Instructions_title_6            matlab.ui.control.Label
        CroppingTab                     matlab.ui.container.Tab
        GridLayout2_9                   matlab.ui.container.GridLayout
        Cropping_UITable                matlab.ui.control.Table
        SetcroppingparametersappliedaftergenerationisfinishedLabel  matlab.ui.control.Label
        Instructions_title_9            matlab.ui.control.Label
        UpscaleTab                      matlab.ui.container.Tab
        GridLayout2_2                   matlab.ui.container.GridLayout
        Scalingfactor1upscaling1downscaling1noscalingEditField_2  matlab.ui.control.NumericEditField
        EditFieldLabel                  matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Scalingfactor1upscaling1downscaling1noscalingEditField  matlab.ui.control.NumericEditField
        Controlpointsscalingfactor1upscaling1downscaling1noscalingLabel  matlab.ui.control.Label
        EnterscalingfactorsLabel        matlab.ui.control.Label
        Instructions_title_2            matlab.ui.control.Label
        GenerationTab                   matlab.ui.container.Tab
        GridLayout2_5                   matlab.ui.container.GridLayout
        VerboseCheckBox                 matlab.ui.control.CheckBox
        RunStatut_UITable               matlab.ui.control.Table
        OutcomeandwallclocktimesLabel   matlab.ui.control.Label
        Vf_UITable                      matlab.ui.control.Table
        VolumefractionsLabel            matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        NumberofrunsEditField           matlab.ui.control.NumericEditField
        NumberofrunsEditFieldLabel      matlab.ui.control.Label
        RungenerationalgorithmButton    matlab.ui.control.Button
        EnterscalingfactorLabel_3       matlab.ui.control.Label
        Instructions_title_5            matlab.ui.control.Label
        VisualizationTab                matlab.ui.container.Tab
        GridLayout2_7                   matlab.ui.container.GridLayout
        ColormapDropDown                matlab.ui.control.DropDown
        ColormapDropDownLabel           matlab.ui.control.Label
        Visualize3DButton               matlab.ui.control.Button
        Visualize2DButton               matlab.ui.control.Button
        ShowDropDown                    matlab.ui.control.DropDown
        ShowDropDownLabel               matlab.ui.control.Label
        ScaledvolumesCheckBox           matlab.ui.control.CheckBox
        Visualization_UITable           matlab.ui.control.Table
        SelectwhatvolumesyouwouldliketovisualizeoneorseveralatonceLabel  matlab.ui.control.Label
        Instructions_title_7            matlab.ui.control.Label
        HowtoquoteTab                   matlab.ui.container.Tab
        GridLayout2_11                  matlab.ui.container.GridLayout
        ThismoduleisusingLabel          matlab.ui.control.Label
        Hyperlink_2                     matlab.ui.control.Hyperlink
        Label_3                         matlab.ui.control.Label
        SelectasolidphaseandsetthenumberofLabel_3  matlab.ui.control.Label
        Instructions_title_11           matlab.ui.control.Label
        HelpTab                         matlab.ui.container.Tab
        GridLayout2_8                   matlab.ui.container.GridLayout
        Hyperlink                       matlab.ui.control.Hyperlink
        reproducibilityLabel            matlab.ui.control.Label
        Instructions_title_8            matlab.ui.control.Label
        AboutTab                        matlab.ui.container.Tab
        GridLayout2_12                  matlab.ui.container.GridLayout
        IfyoulikethistoolpleasegiveitastarintheGithubrepositoryLabel  matlab.ui.control.Label
        Localversion                    matlab.ui.control.Label
        OnlineversioncheckLabel         matlab.ui.control.Label
        MATBOXinstalledversionLabel     matlab.ui.control.Label
        Hyperlink_4                     matlab.ui.control.Hyperlink
        Hyperlink_3                     matlab.ui.control.Hyperlink
        NREL_logo                       matlab.ui.control.Image
        Label_4                         matlab.ui.control.Label
        Instructions_title_12           matlab.ui.control.Label
    end

    
    properties (Access = private)
        mask_notwithin = [];
        Savefolder = [];
        generation_result = [];
    end
    
    methods (Access = private)
        
        function [x, pdf_, cdf_] = distributions(app, equiprobability, mean_, sigma_, maxdeviation)
            x = []; pdf_ = []; cdf_ =[];
            % Univariate Gaussian Distribution
            % Probability density function of a gaussian distribution
            pdf_GD = @(x,mu,sigma) 1./(2*pi*sigma.^2).^(0.5).*exp(-(x-mu).^2 ./ (2*sigma.^2));

            if ~equiprobability && maxdeviation~=0
                x=linspace(mean_-maxdeviation, mean_+maxdeviation, 100);
                pdf_=pdf_GD(x,mean_,sigma_);
                cdf_ = pdf2cdf(x,pdf_);
            end

            function c = pdf2cdf(x,pdf)
                n=length(x);
                c=zeros(1,n);
                for k=2:1:n
                    c(k) = trapz(x(1:k),pdf(1:k));
                end
                if c(end)<1
                    c(end)=1;
                end
            end
        end

        
        function t = Build_table_fromUItab(app,uit)
            d = uit.Data;
            [~,n_column] = size(d);
            str = 'table(d(:,1)';
            for k=2:1:n_column
                str = [str ',d(:,' num2str(k) ')'];
            end
            str = [str ',''VariableNames'',(uit.ColumnName)'');'];
            t = eval(str);            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Domainsize_UITable.Data = [100 100 100];
            app.SetorientationwithDropDownValueChanged;
            app.Tubeparameters_UITable_2.ColumnFormat = {'char','char','char','numeric','numeric','numeric','logical'};
            app.Tubeparameters_UITable_2.Data = [ {'Length'} {'Voxel'} {'[1,Inf]'} num2cell([50]) num2cell([10]) num2cell([20]) {false}];
            app.Visualization_UITable.ColumnFormat = {'numeric','logical'};
            app.Cropping_UITable.Data = [ [1;2;3] [0;0;0] [0.1;0.1;0.1] ];
            app.FinitelengthusetablebelowCheckBoxValueChanged;
            app.TubesonlystartsatdomainsedgesCheckBoxValueChanged;

            % Check online version
            localversion = app.Localversion.Text;
            try
                githubversionfile=webread('https://raw.githubusercontent.com/NREL/MATBOX_Microstructure_analysis_toolbox/master/License.txt');
                if ~strcmp(localversion,githubversionfile)
                    app.OnlineversioncheckLabel.Text = ['There is a new version (' localversion ') available online. Please go to the Github repositorty to update your MATBOX installation'];
                    app.OnlineversioncheckLabel.FontColor = 'r';
                else
                    app.OnlineversioncheckLabel.Text = 'You are using the up-to-date version of MATBOX';
                    app.OnlineversioncheckLabel.FontColor = 'k';
                end
            catch ME
                if strcmp(ME.identifier,'MATLAB:webservices:Timeout')
                    app.OnlineversioncheckLabel.Text = 'Could not check current version online.';
                end
            end
 
            is_installed_imagetoolbox = license('test','Image_Toolbox');
            if ~is_installed_imagetoolbox
                app.Imageprocessing_Label.Visible = 'on';
            end
        end

        % Button pushed function: RungenerationalgorithmButton
        function RungenerationalgorithmButtonPushed(app, event)
            % Clear table
            app.Vf_UITable.Data = [];
            app.RunStatut_UITable.Data = [];
            app.Visualization_UITable.Data = [];            

            % Bezier curve parameters
            Bezier_pars.cycle = false;
            Bezier_pars.tension = app.TubetensionEditField.Value;

            % FOV
            domain_size = app.Domainsize_UITable.Data;

            % Read parameter table
            pars = app.Tubeparameters_UITable.Data(:,4:end);
            pars = [cell2mat(pars(:,1:3)) cell2mat(pars(:,4))];

            % Tube radius
            p.mean_radius = pars(1,1);
            p.sigma_radius = pars(1,2);
            p.maxdeviation_radius = pars(1,3);
            p.equiprobability_radius = logical(pars(1,4));
            [p.x_radius, ~, p.cdf_radius] = app.distributions(p.equiprobability_radius, p.mean_radius, p.sigma_radius, p.maxdeviation_radius);

            % Update tube orientation
            p.mean_distancebetweenangleupdate = pars(2,1);
            p.sigma_distancebetweenangleupdate = pars(2,2);
            p.maxdeviation_distancebetweenangleupdate = pars(2,3);
            p.equiprobability_distancebetweenangleupdate = logical(pars(2,4));
            [p.x_distancebetweenangleupdate, ~, p.cdf_distancebetweenangleupdate] = app.distributions(p.equiprobability_distancebetweenangleupdate, p.mean_distancebetweenangleupdate, p.sigma_distancebetweenangleupdate, p.maxdeviation_distancebetweenangleupdate);

            % Tube initial orientation
            p.initial_orientation_choice = app.SetorientationwithDropDown.Value;
            if strcmp(p.initial_orientation_choice,'Angle')
                p.mean_initialazimuth = pars(3,1);
                p.sigma_initialazimuth = pars(3,2);
                p.maxdeviation_initialazimuth = pars(3,3);
                p.equiprobability_initialazimuth = logical(pars(3,4));
                p.mean_initialelevation = pars(4,1);
                p.sigma_initialelevation = pars(4,2);
                p.maxdeviation_initialelevation = pars(4,3);
                p.equiprobability_initialelevation = pars(4,4);
                [p.x_initialazimuth, ~, p.cdf_initialazimuth] = app.distributions(p.equiprobability_initialazimuth, p.mean_initialazimuth, p.sigma_initialazimuth, p.maxdeviation_initialazimuth);
                [p.x_initialelevation, ~, p.cdf_initialelevation] = app.distributions(p.equiprobability_initialelevation, p.mean_initialelevation, p.sigma_initialelevation, p.maxdeviation_initialelevation);
                r = 5;
            else
                % Tube initial orientation
                p.mean_initialUx = pars(3,1);
                p.sigma_initialUx = pars(3,2);
                p.maxdeviation_initialUx = pars(3,3);
                p.equiprobability_initialUx = logical(pars(3,4));
                p.mean_initialUy = pars(4,1);
                p.sigma_initialUy = pars(4,2);
                p.maxdeviation_initialUy = pars(4,3);
                p.equiprobability_initialUy = pars(4,4);
                p.mean_initialUz = pars(5,1);
                p.sigma_initialUz = pars(5,2);
                p.maxdeviation_initialUz = pars(5,3);
                p.equiprobability_initialUz = pars(5,4);                
                [p.x_initialUx, ~, p.cdf_initialUx] = app.distributions(p.equiprobability_initialUx, p.mean_initialUx, p.sigma_initialUx, p.maxdeviation_initialUx);
                [p.x_initialUy, ~, p.cdf_initialUy] = app.distributions(p.equiprobability_initialUy, p.mean_initialUy, p.sigma_initialUy, p.maxdeviation_initialUy);
                [p.x_initialUz, ~, p.cdf_initialUz] = app.distributions(p.equiprobability_initialUz, p.mean_initialUz, p.sigma_initialUz, p.maxdeviation_initialUz);
                r = 6;
            end

            % Tube angular change
            p.change_orientation_choice = app.andchangewithDropDown.Value;
            if strcmp(p.change_orientation_choice,'Angle')
                p.mean_azimuthchange = pars(r,1);
                p.sigma_azimuthchange = pars(r,2);
                p.maxdeviation_azimuthchange = pars(r,3);
                p.equiprobability_azimuthchange = logical(pars(r,4));
                p.mean_elevationchange = pars(r+1,1);
                p.sigma_elevationchange = pars(r+1,2);
                p.maxdeviation_elevationchange = pars(r+1,3);
                p.equiprobability_elevationchange = logical(pars(r+1,4));
                [p.x_azimuthchange, ~, p.cdf_azimuthchange] = app.distributions(p.equiprobability_azimuthchange, p.mean_azimuthchange, p.sigma_azimuthchange, p.maxdeviation_azimuthchange);
                [p.x_elevationchange, ~, p.cdf_elevationchange] = app.distributions(p.equiprobability_elevationchange, p.mean_elevationchange, p.sigma_elevationchange, p.maxdeviation_elevationchange);
            else
                p.mean_Uxchange = pars(r,1);
                p.sigma_Uxchange = pars(r,2);
                p.maxdeviation_Uxchange = pars(r,3);
                p.equiprobability_Uxchange = logical(pars(r,4));
                p.mean_Uychange = pars(r+1,1);
                p.sigma_Uychange = pars(r+1,2);
                p.maxdeviation_Uychange = pars(r+1,3);
                p.equiprobability_Uychange = logical(pars(r+1,4));
                p.mean_Uzchange = pars(r+2,1);
                p.sigma_Uzchange = pars(r+2,2);
                p.maxdeviation_Uzchange = pars(r+2,3);
                p.equiprobability_Uzchange = logical(pars(r+2,4));                
                [p.x_Uxchange, ~, p.cdf_Uxchange] = app.distributions(p.equiprobability_Uxchange, p.mean_Uxchange, p.sigma_Uxchange, p.maxdeviation_Uxchange);
                [p.x_Uychange, ~, p.cdf_Uychange] = app.distributions(p.equiprobability_Uychange, p.mean_Uychange, p.sigma_Uychange, p.maxdeviation_Uychange);
                [p.x_Uzchange, ~, p.cdf_Uzchange] = app.distributions(p.equiprobability_Uzchange, p.mean_Uzchange, p.sigma_Uzchange, p.maxdeviation_Uzchange);
            end

            % Tube length
            p.finitelength = app.FinitelengthusetablebelowCheckBox.Value;
            if p.finitelength
                pars = app.Tubeparameters_UITable_2.Data(:,4:end);
                pars = [cell2mat(pars(:,1:3)) cell2mat(pars(:,4))];
                p.mean_length = pars(1,1);
                p.sigma_length = pars(1,2);
                p.maxdeviation_length = pars(1,3);
                p.equiprobability_length = logical(pars(1,4));
                [p.x_length, ~, p.cdf_length] = app.distributions(p.equiprobability_length, p.mean_length, p.sigma_length, p.maxdeviation_length);
            end
            p.min_length = app.orsetminimumtubelengthinvoxellengthEditField.Value;

            % Number of attempt before trying a new seed
            p.n_attempt_max = app.MaximumnumberofresamplingfromcurrentpointEditField.Value;
            % Relaxation parameters
            p.relaxation_orientation = app.RelaxorientationlimitswithnumberofattempsCheckBox.Value;
            p.relaxation_rate = app.DoubleorientationchangeeachxfailedattemptsprogressiveEditField.Value;

            % Number of retry from previous point
            p.n_trygoback_max = app.IncaseofdeadendmaximumretryfrompreviouspointEditField.Value;

            % Cross section shape (bwdist rule)
            p.shape = app.CrosssectionshapeDropDown.Value;

            % Backward progression check
            p.check_backwardprogression = app.CheckbackwardprogressionCheckBox.Value;
            p.backwardprogression_maxnpoint = app.DiscardtubeifbackwardprogressionfromlastxpointsEditField.Value;
            
            % Seed location
            p.seeds_onlyatedges = app.TubesonlystartsatdomainsedgesCheckBox.Value;
            p.force_seedequidistribution = app.Forcestartingpointequidist_CheckBox.Value;
            
            % Mask
            p.maskused = [];
            if ~isempty(app.mask_notwithin)
                p.maskused = double(app.mask_notwithin);
            end
            if ~isempty(app.mask_notwithin) && app.UsenotwithinmaskCheckBox.Value
                p.maskused = ~app.mask_notwithin;
            end
            p.tube_can_overlap_with_tube = app.TubescanoverlappwitheachotherCheckBox.Value;

            % Cropping
            cropp.cut = app.Cropping_UITable.Data(:,2);
            cropp.distfromedge = app.Cropping_UITable.Data(:,3);

            % Continous save
            sav.save_continuously = app.SavecontinuouslyslowerCheckBox.Value;
            sav.Savefolder = app.Savefolder;

            % Stopping conditions
            stopcond.n_tube = app.NumberoftubesEditField.Value;
            stopcond.target_volumefraction = app.VolumefractionEditField.Value;            
            stopcond.totaltime = app.StoptotalEditField.Value;
            stopcond.pertube = app.StoppertubeEditField.Value;

            % Up/downscaling
            scaling_factors.loc = app.Scalingfactor1upscaling1downscaling1noscalingEditField.Value;
            scaling_factors.rad = app.Scalingfactor1upscaling1downscaling1noscalingEditField_2.Value;
            if scaling_factors.loc ~=1 || scaling_factors.rad ~=1
                app.ScaledvolumesCheckBox.Enable = "on";
            else
                app.ScaledvolumesCheckBox.Enable = "off";                
            end

            % Verbose
            p.verbose = app.VerboseCheckBox.Value;

            % Save inputs
            save([app.Savefolder 'Inputs.mat'],'domain_size','Bezier_pars','sav','stopcond','cropp','p');

            number_run = app.NumberofrunsEditField.Value;
            app.RunStatut_UITable.Data=cell(number_run,4);

            wallclocktime = zeros(number_run,2);
            for k_run = 1:1:number_run
                date_start = datetime('now','TimeZone','local','Format','eeee, HH:mm:ss Z, MMMM d, y');
                app.RunStatut_UITable.Data(k_run,1) = {'On-going'};
                app.RunStatut_UITable.Data(k_run,2) = {char(date_start)};
                app.RunStatut_UITable.Data(k_run,3) = {' '};
                app.RunStatut_UITable.Data(k_run,4) = {' '};
                sPass = uistyle("Icon","none","IconAlignment","rightmargin");
                addStyle(app.RunStatut_UITable,sPass,"cell",[k_run 1]);
                pause(0.01);

                p.k_run = k_run;

                tic
                % Call generation algorithm
                fprintf('Run #%i/%i is on-going\n',k_run,number_run)
                [tube_phase, tube_id, tube_skeleton, tubes, outcome] = function_generate_nanotube_3Dbeziercurves_v3(domain_size,Bezier_pars,sav,stopcond,cropp,p);
                tube_phase = uint8(tube_phase);
                if max(unique(tube_id))<=255
                    tube_id = uint8(tube_id);
                    tube_skeleton = uint8(tube_skeleton);
                else
                    tube_id = uint16(tube_id);
                    tube_skeleton = uint16(tube_skeleton);
                end
                wallclocktime(k_run,1) = toc;

                % Save for later visualization
                app.generation_result(k_run).tube_phase = tube_phase;
                app.generation_result(k_run).tube_id = tube_id;      
                app.generation_result(k_run).tube_skeleton = tube_skeleton;  

                % Save results
                function_save_tif( tube_phase, [app.Savefolder 'Tubelabel_run_' num2str(k_run) '.tif']);
                function_save_tif( tube_id, [app.Savefolder 'TubeId_run_' num2str(k_run) '.tif']);
                function_save_tif( tube_skeleton, [app.Savefolder 'Tubeskeleton_run_' num2str(k_run) '.tif']);
                save([app.Savefolder 'TubeInfo_run_' num2str(k_run) '.mat'],'tubes','-mat');

                if scaling_factors.loc ~=1 || scaling_factors.rad ~=1
                    tic
                    [tube_phase_scaled, tube_id_scaled, tube_skeleton_scaled] = function_upscale_tube(tube_id,tubes,domain_size,scaling_factors,Bezier_pars,cropp,p.shape,0,1);
                    tube_phase_scaled = uint8(tube_phase_scaled);
                    if max(unique(tube_id_scaled))<=255
                        tube_id_scaled = uint8(tube_id_scaled);
                        tube_skeleton_scaled = uint8(tube_skeleton_scaled);
                    else
                        tube_id_scaled = uint16(tube_id_scaled);
                        tube_skeleton_scaled = uint16(tube_skeleton_scaled);
                    end
                    % Save results
                    function_save_tif( tube_phase_scaled, [app.Savefolder 'Tubelabel_scaled_run_' num2str(k_run) '.tif']);
                    function_save_tif( tube_id_scaled, [app.Savefolder 'TubeId_scaled_run_' num2str(k_run) '.tif']);
                    function_save_tif( tube_skeleton_scaled, [app.Savefolder 'Tubeskeleton_scaled_run_' num2str(k_run) '.tif']);
                    % Save for later visualization
                    app.generation_result(k_run).tube_phase_scaled = tube_phase_scaled;
                    app.generation_result(k_run).tube_id_scaled = tube_id_scaled; 
                    app.generation_result(k_run).tube_skeleton_scaled = tube_skeleton_scaled;      
                    wallclocktime(k_run,2) = toc;
                end

                % Volume fractions
                vf = zeros(1,3);
                if scaling_factors.loc~=1 || scaling_factors.rad~=1
                    vf(1) = sum(sum(sum( app.generation_result(k_run).tube_phase_scaled==0 )))/numel(app.generation_result(k_run).tube_phase_scaled);
                else
                    vf(1) = sum(sum(sum( app.generation_result(k_run).tube_phase==0 )))/numel(app.generation_result(k_run).tube_phase);
                end
                vf(2) = 1 - vf(1);
                vf(3) = max(max(max(tube_id)));
                
                if k_run==1
                    app.Vf_UITable.Data = [k_run, vf];
                else
                    app.Vf_UITable.Data(k_run,:) = [k_run, vf];
                end

                % Outcome and wallclock time
                app.RunStatut_UITable.Data(k_run,1) = {outcome};
                if strcmp(outcome,'Success')
                    sPass = uistyle("Icon","success","IconAlignment","rightmargin");
                else
                     sPass = uistyle("Icon","warning","IconAlignment","rightmargin");
                end                   
                addStyle(app.RunStatut_UITable,sPass,"cell",[k_run 1]);
                app.RunStatut_UITable.Data(k_run,3) = {[num2str(wallclocktime(k_run,1),'%1.1f') ' s']};
                app.RunStatut_UITable.Data(k_run,4) = {[num2str(wallclocktime(k_run,2),'%1.1f') ' s']};              
                pause(1.0);  

                % Visualization
                if k_run==1
                    app.Visualization_UITable.Data = [k_run, true];
                else
                    app.Visualization_UITable.Data(k_run,:) = [k_run, true];
                end   
            end

            % Save characterization
            filename = 'Results'; % Filename without extension
            % Prepare the data
            clear DATA_writetable
            t = app.Build_table_fromUItab(app.Vf_UITable);
            DATA_writetable.sheet(1).name='Volume_fractions';
            DATA_writetable.sheet(1).table=t;
            t = app.Build_table_fromUItab(app.RunStatut_UITable);
            DATA_writetable.sheet(2).name='Outcome_time';
            DATA_writetable.sheet(2).table=t;
            % Save function
            Function_Writetable(app.Savefolder,filename,DATA_writetable)

            % Visualization tab
            app.Visualize2DButton.Enable = 'on';
            app.Visualize3DButton.Enable = 'on';
        end

        % Cell edit callback: Domainsize_UITable
        function Domainsize_UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            if isnan(newData) || newData<=1 || newData~=round(newData)
                app.Domainsize_UITable.Data(indices(1),indices(2)) = event.PreviousData;
            end                        
        end

        % Cell edit callback: Tubeparameters_UITable
        function Tubeparameters_UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            % prevent negative radius
            
        end

        % Button pushed function: PlotdistributionsoptionalButton
        function PlotdistributionsoptionalButtonPushed(app, event)
            % Read parameter table
            pars1 = app.Tubeparameters_UITable.Data(:,4:end);
            pars1 = [cell2mat(pars1(:,1:3)) cell2mat(pars1(:,4))];
            pars2 = app.Tubeparameters_UITable_2.Data(:,4:end);
            pars2 = [cell2mat(pars2(:,1:3)) cell2mat(pars2(:,4))];
            pars = [pars1;pars2];

            scrsz = get(0,'ScreenSize'); % Screen resolution
            Fig = figure; % Create figure
            Fig.Name= 'Tube parameters'; % Figure name
            Fig.Color='white'; % Background colour
            set(Fig,'position',[scrsz(1) scrsz(2) scrsz(3)*4/5 scrsz(4)*2/3]); % Full screen figure

            [naxe,~] = size(app.Tubeparameters_UITable.Data);
            if app.FinitelengthusetablebelowCheckBox.Value
                naxe = naxe+1;
            end
             
            for id_axe=1:1:naxe
                sub_axes=subplot(3,3,id_axe,'Parent',Fig);
                if id_axe==1
                    h_title=title ('Tube radius (diameter = r+1+r)');
                    xlabel('Number of voxels');
                elseif id_axe==2
                    h_title=title ('Distance between orientation change');
                    xlabel('Number of voxels');
                elseif id_axe==3
                    if strcmp(app.SetorientationwithDropDown.Value,'Angle')
                        h_title=title ('Initial azimuth');
                        xlabel('Degree');
                    else
                        h_title=title ('Initial Ux');
                        xlabel('[-]');
                    end
                elseif id_axe==4
                    if strcmp(app.SetorientationwithDropDown.Value,'Angle')
                        h_title=title ('Initial elevation');
                        xlabel('Degree');
                    else
                        h_title=title ('Initial Uy');
                        xlabel('[-]');
                    end
                elseif id_axe==5
                    if strcmp(app.SetorientationwithDropDown.Value,'Angle') && strcmp(app.andchangewithDropDown.Value,'Angle')
                        h_title=title ('Azimuth change');
                        xlabel('Degree');
                    elseif strcmp(app.SetorientationwithDropDown.Value,'Angle') && strcmp(app.andchangewithDropDown.Value,'Vector')
                        h_title=title ('Ux change');
                        xlabel('[-]');
                    elseif strcmp(app.SetorientationwithDropDown.Value,'Vector')
                        h_title=title ('Initial Uz');
                        xlabel('[-]');
                    end
                elseif id_axe==6
                    if strcmp(app.SetorientationwithDropDown.Value,'Angle') && strcmp(app.andchangewithDropDown.Value,'Angle')
                        h_title=title ('Elevation change');
                        xlabel('Degree');
                    elseif strcmp(app.SetorientationwithDropDown.Value,'Angle') && strcmp(app.andchangewithDropDown.Value,'Vector')
                        h_title=title ('Uy change');
                        xlabel('[-]');
                    elseif strcmp(app.SetorientationwithDropDown.Value,'Vector') && strcmp(app.andchangewithDropDown.Value,'Angle')
                        h_title=title ('Azimuth change');
                        xlabel('Degree');
                    elseif strcmp(app.SetorientationwithDropDown.Value,'Vector') && strcmp(app.andchangewithDropDown.Value,'Vector')
                        h_title=title ('Ux change');
                        xlabel('[-]');
                    end
                elseif id_axe==7
                    if strcmp(app.SetorientationwithDropDown.Value,'Angle') && strcmp(app.andchangewithDropDown.Value,'Vector')
                        h_title=title ('Uz change');
                        xlabel('[-]');
                    elseif strcmp(app.SetorientationwithDropDown.Value,'Vector') && strcmp(app.andchangewithDropDown.Value,'Angle')
                        h_title=title ('Elevation change');
                        xlabel('Degree');
                    elseif strcmp(app.SetorientationwithDropDown.Value,'Vector') && strcmp(app.andchangewithDropDown.Value,'Vector')
                        h_title=title ('Uy change');
                        xlabel('[-]');
                    end
                elseif id_axe==8
                    h_title=title ('Uz change');
                    xlabel('[-]');
                end

                if app.FinitelengthusetablebelowCheckBox.Value && id_axe==naxe
                    h_title=title ('Tube length');
                    xlabel('Number of voxels');
                end

                hold(sub_axes,'on'); % Active subplot
                % Plot
                [x_, pdf_, ~] = app.distributions(pars(id_axe,4), pars(id_axe,1), pars(id_axe,2), pars(id_axe,3));
                if pars(id_axe,3)==0
                    plot(pars(id_axe,1),1,'LineWidth',2,'Marker','o','MarkerSize',8);
                else
                    if pars(id_axe,4)
                        plot([pars(id_axe,1)-pars(id_axe,3) pars(id_axe,1)+pars(id_axe,3)],[1 1],'LineWidth',2);
                    else
                        plot(x_,pdf_,'LineWidth',2);
                    end
                end
                ylabel('a.u');
                % - Grid
                grid(sub_axes,'on'); % Display grid
                set(sub_axes,'XMinorGrid','on','YMinorGrid','on'); % Display grid for minor thicks
                set(sub_axes,'FontName','Times new roman','FontSize',14); % Fontname and fontsize
                h_title.FontSize = 16; % Set title fontsize
                sgtitle(Fig,'Tube parameters','FontWeight','bold','FontSize',20,'FontName','Times new roman');
                hold(sub_axes,'off'); % Relase figure
            end
        end

        % Button pushed function: ImportmaskButton
        function ImportmaskButtonPushed(app, event)
            % Open dialog box to choose file path
            [FileName,PathName,~] = uigetfile({'*.tif;*.tiff','Tif image (*.tif, *.tiff)'},'Select a .tif file');
            if FileName==0
                % User clicked cancel button or closed the dialog box
                app.Mask_volume_name_label.Text ='No mask file imported.';
                app.mask_volumefraction_label.Text ='No mask file imported.';
                app.mask_notwithin=[]; % Reset mask array
                app.Visualize_mask_button.Enable = 'off';
                app.UsenotwithinmaskCheckBox.Enable = 'off'; app.UsenotwithinmaskCheckBox.Value = 0;
            else
                [tmp,outcome] = function_load_tif([PathName FileName]);
                if outcome % Success to import
                    % Check dimension
                    domain_size = app.Domainsize_UITable.Data;
                    if sum(size(tmp) == domain_size)==3
                        % Create mask
                        tmp2 = zeros(size(tmp));
                        tmp2(tmp~=0)=1;
                        % Save
                        app.mask_notwithin=uint8(tmp2);
                        clear tmp tmp2;
                        app.Mask_volume_name_label.Text = [PathName FileName];
                        mask_vf = sum(sum(sum(app.mask_notwithin)))/numel(app.mask_notwithin);
                        app.mask_volumefraction_label.Text = ['Volume fractions: Mask (1) : ' num2str(mask_vf,"%1.3f") ', Background (0) : ' num2str(1-mask_vf,"%1.3f")];
                        app.Visualize_mask_button.Enable = 'on';
                        app.UsenotwithinmaskCheckBox.Enable = 'on'; app.UsenotwithinmaskCheckBox.Value = 1;  
                    else
                        sz = size(tmp);
                        f = msgbox(['Size mask ' num2str(sz(1),'%i') '-' num2str(sz(2),'%i') '-' num2str(sz(3),'%i') ' is different from domain size'], 'Error','warn');
                        app.Mask_volume_name_label.Text ='No mask file imported.';
                        app.mask_volumefraction_label.Text ='No mask file imported.';
                        app.mask_notwithin=[]; % Reset mask array
                        app.Visualize_mask_button.Enable = 'off';
                        app.UsenotwithinmaskCheckBox.Enable = 'off'; app.UsenotwithinmaskCheckBox.Value = 0;
                    end    
                else
                    app.Mask_volume_name_label.Text ='Error: volume cannot be loaded! File is not a .tif file';
                    app.mask_volumefraction_label.Text ='No mask file imported.';
                    app.mask_notwithin=[]; % Reset mask array
                    app.Visualize_mask_button.Enable = 'off';
                    app.UsenotwithinmaskCheckBox.Enable = 'off'; app.UsenotwithinmaskCheckBox.Value = 0;
                end
            end
        end

        % Button pushed function: Visualize_mask_button
        function Visualize_mask_buttonButtonPushed(app, event)
            Microstructure_basic_visualization_interface(app.mask_notwithin);
        end

        % Value changed function: 
        % RelaxorientationlimitswithnumberofattempsCheckBox
        function RelaxorientationlimitswithnumberofattempsCheckBoxValueChanged(app, event)
            value = app.RelaxorientationlimitswithnumberofattempsCheckBox.Value;
            if value
                app.DoubleorientationchangeeachxfailedattemptsprogressiveEditField.Enable = "on";
                app.DoubleorientationchangeeachxfailedattemptsprogressiveEditField.Value = round(app.MaximumnumberofresamplingfromcurrentpointEditField.Value)/2;
            else
                app.DoubleorientationchangeeachxfailedattemptsprogressiveEditField.Enable = "off";
            end
        end

        % Button pushed function: Save_folder_button
        function Save_folder_buttonButtonPushed(app, event)
            if isempty(app.Savefolder)
                selpath = uigetdir(pwd,'Select save folder');
            else
                selpath = uigetdir(app.Savefolder,'Select save folder');
            end
            if selpath~=0
                if ispc
                    selpath=[selpath '\'];
                else
                    selpath=[selpath '/'];
                end
                app.Savefolder=selpath;
                app.NosavefolderselectedLabel.Text = app.Savefolder;
                statut = 'on';
            else
                app.Savefolder = [];
                app.NosavefolderselectedLabel.Text = 'No save folder selected';
                statut = 'off';
            end
            app.RungenerationalgorithmButton.Enable = statut;
        end

        % Button pushed function: Visualize2DButton
        function Visualize2DButtonPushed(app, event)
            idx = find(app.Visualization_UITable.Data(:,2));
            runs = app.Visualization_UITable.Data(:,1);
            if ~isempty(idx)
                if length(idx)==1 % One volume to visualize
                    if app.ScaledvolumesCheckBox.Value
                        if strcmp(app.ShowDropDown.Value,'Label')
                            microstructure_visualization_slices(app.generation_result(runs(idx)).tube_phase_scaled, ['Tube label, run#' num2str(runs(idx)) ', scaled'], 1, 'voxel', [])
                        elseif strcmp(app.ShowDropDown.Value,'Tube id')
                            microstructure_visualization_slices(app.generation_result(runs(idx)).tube_id_scaled, ['Tube id, run#' num2str(runs(idx)) ', scaled'], 1, 'voxel', [])
                        elseif strcmp(app.ShowDropDown.Value,'Skeleton')
                            microstructure_visualization_slices(app.generation_result(runs(idx)).tube_skeleton_scaled, ['Tube skeleton, run#' num2str(runs(idx)) ', scaled'], 1, 'voxel', [])
                        end
                    else
                        if strcmp(app.ShowDropDown.Value,'Label')
                            microstructure_visualization_slices(app.generation_result(runs(idx)).tube_phase, ['Tube label, run#' num2str(runs(idx))], 1, 'voxel', [])
                        elseif strcmp(app.ShowDropDown.Value,'Tube id')
                            microstructure_visualization_slices(app.generation_result(runs(idx)).tube_id, ['Tube id, run#' num2str(runs(idx))], 1, 'voxel', [])
                        elseif strcmp(app.ShowDropDown.Value,'Skeleton')
                            microstructure_visualization_slices(app.generation_result(runs(idx)).tube_skeleton, ['Tube skeleton, run#' num2str(runs(idx))], 1, 'voxel', [])
                        end
                    end

                else % Several volumes to visualize
                    str = 'Microstructure_comparison_visualization_interface(';
                    for k=1:1:length(idx)
                        if app.ScaledvolumesCheckBox.Value
                            if strcmp(app.ShowDropDown.Value,'Label')
                                str = [str 'app.generation_result(runs(idx(' num2str(k) '))).tube_phase_scaled,'];
                            elseif strcmp(app.ShowDropDown.Value,'Tube id')
                                str = [str 'app.generation_result(runs(idx(' num2str(k) '))).tube_id_scaled,'];
                            elseif strcmp(app.ShowDropDown.Value,'Skeleton')
                                str = [str 'app.generation_result(runs(idx(' num2str(k) '))).tube_skeleton_scaled,'];
                            end
                        else
                            if strcmp(app.ShowDropDown.Value,'Label')
                                str = [str 'app.generation_result(runs(idx(' num2str(k) '))).tube_phase,'];
                            elseif strcmp(app.ShowDropDown.Value,'Tube id')
                                str = [str 'app.generation_result(runs(idx(' num2str(k) '))).tube_id,'];
                            elseif strcmp(app.ShowDropDown.Value,'Skeleton')
                                str = [str 'app.generation_result(runs(idx(' num2str(k) '))).tube_skeleton,'];
                            end
                        end
                    end
                    str(end)=[]; % Remove last ,
                    str = [str ');'];
                    eval(str);
                end
            end
        end

        % Button pushed function: Visualize3DButton
        function Visualize3DButtonPushed(app, event)
            idx = find(app.Visualization_UITable.Data(:,2));
            runs = app.Visualization_UITable.Data(:,1);
            col_ = eval(lower(app.ColormapDropDown.Value));
            if ~isempty(idx)
                for k=1:1:length(idx)
                    if app.ScaledvolumesCheckBox.Value
                        if strcmp(app.ShowDropDown.Value,'Label')
                            vol = volshow(app.generation_result(runs(idx(k))).tube_phase_scaled,Colormap=col_);   
                        elseif strcmp(app.ShowDropDown.Value,'Tube id')
                            vol = volshow(app.generation_result(runs(idx(k))).tube_id_scaled,Colormap=col_);    
                        elseif strcmp(app.ShowDropDown.Value,'Skeleton')
                            vol = volshow(app.generation_result(runs(idx(k))).tube_skeleton_scaled,Colormap=col_); 
                        end
                    else
                        if strcmp(app.ShowDropDown.Value,'Label')
                            vol = volshow(app.generation_result(runs(idx(k))).tube_phase,Colormap=col_); 
                        elseif strcmp(app.ShowDropDown.Value,'Tube id')
                            vol = volshow(app.generation_result(runs(idx(k))).tube_id,Colormap=col_); 
                        elseif strcmp(app.ShowDropDown.Value,'Skeleton')
                            vol = volshow(app.generation_result(runs(idx(k))).tube_skeleton,Colormap=col_); 
                        end
                    end
                end
            end
        end

        % Cell edit callback: Cropping_UITable
        function Cropping_UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            domain_size = app.Domainsize_UITable.Data;
            if indices(2)==2
                if isnan(newData) || newData<0 || newData~=round(newData) || newData>round(domain_size(indices(1))/2)
                    app.Cropping_UITable.Data(indices(1),indices(2)) = event.PreviousData;
                end
            elseif indices(2)==3
                if isnan(newData) || newData<0 || newData>0.5
                    app.Cropping_UITable.Data(indices(1),indices(2)) = event.PreviousData;
                end
            end
        end

        % Value changed function: CheckbackwardprogressionCheckBox
        function CheckbackwardprogressionCheckBoxValueChanged(app, event)
            value = app.CheckbackwardprogressionCheckBox.Value;
            if value
                app.DiscardtubeifbackwardprogressionfromlastxpointsEditField.Enable = "on";
            else
                app.DiscardtubeifbackwardprogressionfromlastxpointsEditField.Enable = "off";
            end
        end

        % Image clicked function: NREL_logo
        function NREL_logoImageClicked(app, event)
            url = 'https://www.nrel.gov/';
            web(url)          
        end

        % Value changed function: FinitelengthusetablebelowCheckBox
        function FinitelengthusetablebelowCheckBoxValueChanged(app, event)
            value = app.FinitelengthusetablebelowCheckBox.Value;
            if value
                app.Tubeparameters_UITable_2.Enable = "on";
                app.orsetminimumtubelengthinvoxellengthEditField.Enable = "off";
            else
                app.Tubeparameters_UITable_2.Enable = "off";
                app.orsetminimumtubelengthinvoxellengthEditField.Enable = "on";
            end
        end

        % Cell edit callback: Tubeparameters_UITable_2
        function Tubeparameters_UITable_2CellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            winopen('Tubegeneration_method.png');
        end

        % Value changed function: TubesonlystartsatdomainsedgesCheckBox
        function TubesonlystartsatdomainsedgesCheckBoxValueChanged(app, event)
            value = app.TubesonlystartsatdomainsedgesCheckBox.Value;
            app.Forcestartingpointequidist_CheckBox.Enable = value;
        end

        % Value changed function: SetorientationwithDropDown, 
        % ...and 1 other component
        function SetorientationwithDropDownValueChanged(app, event)
            initial = app.SetorientationwithDropDown.Value;
            change = app.andchangewithDropDown.Value;
            app.Tubeparameters_UITable.ColumnFormat = {'char','char','char','numeric','numeric','numeric','logical'};
            if strcmp(initial,'Angle') &&  strcmp(change,'Angle')
                app.Tubeparameters_UITable.Data = [ {'Radius';'Distance between change';'Initial azimuth';'Initial elevation';'Azimuth change';'Elevation change'} {'Voxel';'Voxel';'Degree';'Degree';'Degree';'Degree'} {'[1,Inf] (integer)';'[1,Inf]';'[-180,180]';'[-90,90]';'[-180,180]';'[-90,90]'} num2cell([5;10;0;0;0;0]) num2cell([3;2;25;25;25;25]) num2cell([4;3;180;90;50;50]) {false;false;true;true;false;false}];
            else
                if strcmp(initial,'Angle') &&  strcmp(change,'Vector')
                    app.Tubeparameters_UITable.Data = [ {'Radius';'Distance between change';'Initial azimuth';'Initial elevation';'Ux change';'Uy change';'Uz change'} {'Voxel';'Voxel';'Degree';'Degree';'-';'-';'-'} {'[1,Inf] (integer)';'[1,Inf]';'[-180,180]';'[-90,90]';'[0, Inf]';'[0, Inf]';'[0, Inf]'} num2cell([5;10;0;0;0.1;0.1;0.1]) num2cell([3;2;25;25;0.1;0.1;0.1]) num2cell([4;3;180;90;0.2;0.2;0.2]) {false;false;true;true;false;false;false}];
                elseif strcmp(initial,'Vector') &&  strcmp(change,'Angle')
                    app.Tubeparameters_UITable.Data = [ {'Radius';'Distance between change';'Initial Ux';'Initial Uy';'Initial Uz';'Azimuth change';'Elevation change'} {'Voxel';'Voxel';'-';'-';'-';'Degree';'Degree'} {'[1,Inf] (integer)';'[1,Inf]';'[0, Inf]';'[0, Inf]';'[0, Inf]';'[-180,180]';'[-90,90]'} num2cell([5;10;1;1;1;0;0]) num2cell([3;2;0.1;0.1;0.1;25;25]) num2cell([4;3;0.2;0.2;0.2;50;50]) {false;false;true;true;true;false;false}];
                elseif strcmp(initial,'Vector') &&  strcmp(change,'Vector')
                    app.Tubeparameters_UITable.Data = [ {'Radius';'Distance between change';'Initial Ux';'Initial Uy';'Initial Uz';'Ux change';'Uy change';'Uz change'} {'Voxel';'Voxel';'-';'-';'-';'-';'-';'-'} {'[1,Inf] (integer)';'[1,Inf]';'[0, Inf]';'[0, Inf]';'[0, Inf]';'[0, Inf]';'[0, Inf]';'[0, Inf]'} num2cell([5;10;1;1;1;0.1;0.1;0.1]) num2cell([3;2;0.1;0.1;0.1;0.1;0.1;0.1]) num2cell([4;3;0.2;0.2;0.2;0.2;0.2;0.2]) {false;false;true;true;true;false;false;false}];
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.TubebasedstochasticgenerationsubmoduleUIFigure = uifigure('Visible', 'off');
            app.TubebasedstochasticgenerationsubmoduleUIFigure.Name = 'Tube-based stochastic generation submodule';
            app.TubebasedstochasticgenerationsubmoduleUIFigure.Icon = 'Icon_tubegeneration.png';
            app.GridLayout = uigridlayout(app.TubebasedstochasticgenerationsubmoduleUIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.InstructionsTab = uitab(app.TabGroup);
            app.InstructionsTab.Title = 'Instructions';
            app.GridLayout2_10 = uigridlayout(app.InstructionsTab);
            app.Instructions_title_10 = uilabel(app.GridLayout2_10);
            app.Instructions_title_10.Text = 'Main instructions';
            app.TubesaregeneratedsequentiallyoneaftereachotherLabel = uilabel(app.GridLayout2_10);
            app.TubesaregeneratedsequentiallyoneaftereachotherLabel.Text = {'- Tubes are generated sequentially, one after each other.'; '- Tubes cannot overlap with each other. Mask can be used to prevent tubes going in certain regions of space.'; '- Each tube starts from a random location from the domain''s edge, or within the domain, excluding existing tubes and mask (if any). Tube starts with a radius and an initial'; 'orientation picked from a distribution. Orientation for a given tube is updated sequentially each time tube growths from a length picked from a distribution at each new orientation'; 'update. New orientation is also picked from a distribution.'; '- As-obtained points for a given tube are used as implicit control points to generate a 3D Bezier curve. Radius and cross section shape are then used to create a tube from this'; 'curve/skeleton. Overlapping constraint is checked on the tube, not on the curve.'; '- To maximize chance of success, orientation update is picked several times until a path is found (i.e., tube does not overlapp with other tubes or mask, if any). With each new try,'; 'orientation constraints are relaxed (but set back to their orginal values once a point has been found) to allow probing a larger space, increasing change to avoid obstacles in the'; 'way. If a path could not have been found with the number of attempts authorized by the user, then the tube growth process start back from the previous point (as maybe the'; 'current point is a dead end). If it still fails, the current tube is discarded and a new tube with another starting location, radius and initial orientation is picked.'; '- If a tube reaches the domain''s edges or a user-defined length, the generation algorithm moves to the next tube. In addition a constraint can be set to avoid having tubes that'; 'circles (moving back closer to the initial position).'; '- Volumes are saved in .tif files. Input parameters used for the generation are also saved.'};
            app.SelectasolidphaseandsetthenumberofLabel = uilabel(app.GridLayout2_10);
            app.SelectasolidphaseandsetthenumberofLabel.Text = {'Stochastic (i.e., based on randomness) tubular microstructure generation algorithm. Main features are listed below:'; ''};
            app.AdvicesLabel = uilabel(app.GridLayout2_10);
            app.AdvicesLabel.Text = {'- You can generate using a coarse representation and then upscale to save CPU time. Upscaling is performed on the Bezier control points.'; '- You can generate several times the same microstructure (''number of runs'') to check reproducibility.'; '- Consider increasing orientation update limits (''Azimuth change'' and ''Elevation change''), reducing distance between orientation update (''Distance between change''), reducing'; 'tube radius, and/or increasing re-try parameters if algorithm generation stalls (these would help tube being able to go around an obstacle).'; '- You can use this submodule or the ellipsoid-based generation submodule to create masks. Combining the results from the different generation submodules, you can generate'; 'complex geometries with ellipsoids-based particles, tubes, and amorph binder in between.'; '- All the information required to re-generate and re-scale the volumes are contained within files named: ''TubeInfo_run_*.mat'' and ''Inputs.mat''. You can then use'; 'function_upscale_tube.m to recreate the geometry.'};
            app.SelectasolidphaseandsetthenumberofLabel_2 = uilabel(app.GridLayout2_10);
            app.SelectasolidphaseandsetthenumberofLabel_2.Text = 'Tips:';
            app.Imageprocessing_Label = uilabel(app.GridLayout2_10);
            app.Imageprocessing_Label.Visible = 'off';
            app.Imageprocessing_Label.Text = 'Image processing toolbox is missing. Algorithm will not work!';
            app.SaveTab = uitab(app.TabGroup);
            app.SaveTab.Title = 'Save';
            app.GridLayout2_4 = uigridlayout(app.SaveTab);
            app.Instructions_title_4 = uilabel(app.GridLayout2_4);
            app.Instructions_title_4.Text = 'Save options';
            app.Save_folder_button = uibutton(app.GridLayout2_4, 'push');
            app.Save_folder_button.ButtonPushedFcn = createCallbackFcn(app, @Save_folder_buttonButtonPushed, true);
            app.Save_folder_button.Text = 'Click to select save folder';
            app.NosavefolderselectedLabel = uilabel(app.GridLayout2_4);
            app.NosavefolderselectedLabel.Text = 'No save folder selected';
            app.SelectwhereyouwanttosaveinputsandresultsLabel = uilabel(app.GridLayout2_4);
            app.SelectwhereyouwanttosaveinputsandresultsLabel.Text = '1) Select where you want to save inputs and results';
            app.SavecontinuouslyslowerCheckBox = uicheckbox(app.GridLayout2_4);
            app.SavecontinuouslyslowerCheckBox.Tooltip = {'Each time a tube has been generated, the volume is saved.'};
            app.SavecontinuouslyslowerCheckBox.Text = 'Save continuously (slower)';
            app.SavecontinuouslyslowerCheckBox.Value = true;
            app.ParametersTab = uitab(app.TabGroup);
            app.ParametersTab.Title = 'Parameters';
            app.GridLayout2 = uigridlayout(app.ParametersTab);
            app.Instructions_title = uilabel(app.GridLayout2);
            app.Instructions_title.Text = 'Parameters';
            app.Domainsize_UITable = uitable(app.GridLayout2);
            app.Domainsize_UITable.ColumnName = {'Direction 1'; 'Direction 2'; 'Direction 3'};
            app.Domainsize_UITable.RowName = {};
            app.Domainsize_UITable.ColumnEditable = true;
            app.Domainsize_UITable.CellEditCallback = createCallbackFcn(app, @Domainsize_UITableCellEdit, true);
            app.EnterdomainsizeinnumberofvoxelsalongeachdirectionLabel = uilabel(app.GridLayout2);
            app.EnterdomainsizeinnumberofvoxelsalongeachdirectionLabel.Text = '1) Enter domain size in number of voxels along each direction';
            app.SelecttubesstartinglocationandcrosssectionshapeLabel = uilabel(app.GridLayout2);
            app.SelecttubesstartinglocationandcrosssectionshapeLabel.Tooltip = {'Whichever come first.'};
            app.SelecttubesstartinglocationandcrosssectionshapeLabel.Text = '2) Select tubes starting location and cross section shape';
            app.Tubeparameters_UITable = uitable(app.GridLayout2);
            app.Tubeparameters_UITable.ColumnName = {'Parameter'; 'Unit'; 'Range'; 'Mean'; 'Standard deviation'; 'Max deviation'; 'Equipropable'};
            app.Tubeparameters_UITable.RowName = {};
            app.Tubeparameters_UITable.ColumnEditable = [false false false true true true true];
            app.Tubeparameters_UITable.CellEditCallback = createCallbackFcn(app, @Tubeparameters_UITableCellEdit, true);
            app.Tubeparameters_UITable.Tooltip = {'High orientation (azimuth and elevation) change with a low distance between orientation change correspond to a high curvature'};
            app.EntertubesparametersLabel = uilabel(app.GridLayout2);
            app.EntertubesparametersLabel.Tooltip = {'High orientation (azimuth and elevation) change with a low distance between orientation change correspond to a high curvature'};
            app.EntertubesparametersLabel.Text = '3) Enter tubes radius, initial orientation, orientation change, and point to point distance between orientation change';
            app.YoucanplottheparameterdistribituionstocheckyourinputsLabel = uilabel(app.GridLayout2);
            app.YoucanplottheparameterdistribituionstocheckyourinputsLabel.Text = 'You can plot the parameter distribituions to check your inputs';
            app.PlotdistributionsoptionalButton = uibutton(app.GridLayout2, 'push');
            app.PlotdistributionsoptionalButton.ButtonPushedFcn = createCallbackFcn(app, @PlotdistributionsoptionalButtonPushed, true);
            app.PlotdistributionsoptionalButton.Text = 'Plot distributions (optional)';
            app.RetryparametersLabel = uilabel(app.GridLayout2);
            app.RetryparametersLabel.Text = '5) Sample different orientations and distances to avoid obstacle in the way (other tube and mask)';
            app.MaximumnumberofresamplingfromcurrentpointEditFieldLabel = uilabel(app.GridLayout2);
            app.MaximumnumberofresamplingfromcurrentpointEditFieldLabel.Text = 'Maximum number of  re-sampling from current point';
            app.MaximumnumberofresamplingfromcurrentpointEditField = uieditfield(app.GridLayout2, 'numeric');
            app.MaximumnumberofresamplingfromcurrentpointEditField.Limits = [1 Inf];
            app.MaximumnumberofresamplingfromcurrentpointEditField.RoundFractionalValues = 'on';
            app.MaximumnumberofresamplingfromcurrentpointEditField.Value = 30;
            app.RelaxorientationlimitswithnumberofattempsCheckBox = uicheckbox(app.GridLayout2);
            app.RelaxorientationlimitswithnumberofattempsCheckBox.ValueChangedFcn = createCallbackFcn(app, @RelaxorientationlimitswithnumberofattempsCheckBoxValueChanged, true);
            app.RelaxorientationlimitswithnumberofattempsCheckBox.Text = 'Relax orientation limits with number of attemps';
            app.RelaxorientationlimitswithnumberofattempsCheckBox.Value = true;
            app.DoubleorientationchangeeachfailedattemptsEditFieldLabel = uilabel(app.GridLayout2);
            app.DoubleorientationchangeeachfailedattemptsEditFieldLabel.Text = 'Double orientation change each x failed attempts (progressive)';
            app.DoubleorientationchangeeachxfailedattemptsprogressiveEditField = uieditfield(app.GridLayout2, 'numeric');
            app.DoubleorientationchangeeachxfailedattemptsprogressiveEditField.Limits = [1 Inf];
            app.DoubleorientationchangeeachxfailedattemptsprogressiveEditField.RoundFractionalValues = 'on';
            app.DoubleorientationchangeeachxfailedattemptsprogressiveEditField.Tooltip = {'orientation change = orientation change + current number of attempt / <this parameter> * orientation change'};
            app.DoubleorientationchangeeachxfailedattemptsprogressiveEditField.Value = 15;
            app.IncaseofdeadendmaximumretryfrompreviouspointEditFieldLabel = uilabel(app.GridLayout2);
            app.IncaseofdeadendmaximumretryfrompreviouspointEditFieldLabel.Text = 'In case of dead end maximum  re-try from previous point';
            app.IncaseofdeadendmaximumretryfrompreviouspointEditField = uieditfield(app.GridLayout2, 'numeric');
            app.IncaseofdeadendmaximumretryfrompreviouspointEditField.Limits = [0 Inf];
            app.IncaseofdeadendmaximumretryfrompreviouspointEditField.RoundFractionalValues = 'on';
            app.IncaseofdeadendmaximumretryfrompreviouspointEditField.Value = 3;
            app.BackwardprogressionconstraintsLabel = uilabel(app.GridLayout2);
            app.BackwardprogressionconstraintsLabel.Text = '6) Backward progression constraints';
            app.CheckbackwardprogressionCheckBox = uicheckbox(app.GridLayout2);
            app.CheckbackwardprogressionCheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckbackwardprogressionCheckBoxValueChanged, true);
            app.CheckbackwardprogressionCheckBox.Text = 'Check backward progression';
            app.CheckbackwardprogressionCheckBox.Value = true;
            app.DiscardtubeifbackwardprogressionfromlastxpointsLabel = uilabel(app.GridLayout2);
            app.DiscardtubeifbackwardprogressionfromlastxpointsLabel.Text = 'Discard tube if backward progression from last x points';
            app.DiscardtubeifbackwardprogressionfromlastxpointsEditField = uieditfield(app.GridLayout2, 'numeric');
            app.DiscardtubeifbackwardprogressionfromlastxpointsEditField.Limits = [1 Inf];
            app.DiscardtubeifbackwardprogressionfromlastxpointsEditField.RoundFractionalValues = 'on';
            app.DiscardtubeifbackwardprogressionfromlastxpointsEditField.Tooltip = {'Distance d1 from point i (with i the current point) with tube starting point is compared with distance d0 from point (i - <this parameter> + 1) with tube starting point. If d1 < d0, that is tube is going back toward its initial starting point, then the current tube is discarded and algorithm moves to next tube.'};
            app.DiscardtubeifbackwardprogressionfromlastxpointsEditField.Value = 4;
            app.MinimumtubelengthinnumberofvoxelLabel = uilabel(app.GridLayout2);
            app.MinimumtubelengthinnumberofvoxelLabel.Tooltip = {'If finite length is not selected, tubes will stop only when they reach the domain''s edges, and will be keep only if they are longer than the user-defined minimum tube length. Lengths are calculated from control point to control point, therefore the Bezier curve length is higher due to its curvature.'};
            app.MinimumtubelengthinnumberofvoxelLabel.Text = '... or set minimum tube length (in voxel length)';
            app.orsetminimumtubelengthinvoxellengthEditField = uieditfield(app.GridLayout2, 'numeric');
            app.orsetminimumtubelengthinvoxellengthEditField.Limits = [1 Inf];
            app.orsetminimumtubelengthinvoxellengthEditField.Tooltip = {'If finite length is not selected, tubes will stop only when they reach the domain''s edges, and will be keep only if they are longer than the user-defined minimum tube length. Lengths are calculated from control point to control point, therefore the Bezier curve length is higher due to its curvature.'};
            app.orsetminimumtubelengthinvoxellengthEditField.Value = 50;
            app.CrosssectionshapeDropDownLabel = uilabel(app.GridLayout2);
            app.CrosssectionshapeDropDownLabel.Text = 'Cross section shape';
            app.CrosssectionshapeDropDown = uidropdown(app.GridLayout2);
            app.CrosssectionshapeDropDown.Items = {'Euclidean', 'Cityblock', 'Chessboard', 'Quasi-Euclidean'};
            app.CrosssectionshapeDropDown.Tooltip = {'Corresponds to the ''bwdist'' MATLAB function option. Euclidean is a circular cross section, Quasi euclidean is a ciruclar cross section approximated with 8 edges. Chessboard is a square cross section. Cityblock is a diamond cross section.'};
            app.CrosssectionshapeDropDown.Value = 'Euclidean';
            app.FinitelengthusetablebelowCheckBox = uicheckbox(app.GridLayout2);
            app.FinitelengthusetablebelowCheckBox.ValueChangedFcn = createCallbackFcn(app, @FinitelengthusetablebelowCheckBoxValueChanged, true);
            app.FinitelengthusetablebelowCheckBox.Tooltip = {'If finite length is not selected, tubes will stop only when they reach the domain''s edges, and will be keep only if they are longer than the user-defined minimum tube length. Lengths are calculated from control point to control point, therefore the Bezier curve length is higher due to its curvature.'};
            app.FinitelengthusetablebelowCheckBox.Text = 'Finite length (use table below)';
            app.TubesonlystartsatdomainsedgesCheckBox = uicheckbox(app.GridLayout2);
            app.TubesonlystartsatdomainsedgesCheckBox.ValueChangedFcn = createCallbackFcn(app, @TubesonlystartsatdomainsedgesCheckBoxValueChanged, true);
            app.TubesonlystartsatdomainsedgesCheckBox.Text = 'Tubes only starts at domain''s edges';
            app.TubesonlystartsatdomainsedgesCheckBox.Value = true;
            app.EntertubesparametersLabel_2 = uilabel(app.GridLayout2);
            app.EntertubesparametersLabel_2.Tooltip = {'If finite length is not selected, tubes will stop only when they reach the domain''s edges, and will be keep only if they are longer than the user-defined minimum tube length. Lengths are calculated from control point to control point, therefore the Bezier curve length is higher due to its curvature.'};
            app.EntertubesparametersLabel_2.Text = '3) Choose tube length';
            app.Tubeparameters_UITable_2 = uitable(app.GridLayout2);
            app.Tubeparameters_UITable_2.ColumnName = {'Parameter'; 'Unit'; 'Range'; 'Mean'; 'Standard deviation'; 'Max deviation'; 'Equipropable'};
            app.Tubeparameters_UITable_2.RowName = {};
            app.Tubeparameters_UITable_2.ColumnEditable = [false false false true true true true];
            app.Tubeparameters_UITable_2.CellEditCallback = createCallbackFcn(app, @Tubeparameters_UITable_2CellEdit, true);
            app.Tubeparameters_UITable_2.Tooltip = {'If finite length is not selected, tubes will stop only when they reach the domain''s edges, and will be keep only if they are longer than the user-defined minimum tube length. Lengths are calculated from control point to control point, therefore the Bezier curve length is higher due to its curvature.'};
            app.TubetensionEditFieldLabel = uilabel(app.GridLayout2);
            app.TubetensionEditFieldLabel.Text = 'Tube tension';
            app.TubetensionEditField = uieditfield(app.GridLayout2, 'numeric');
            app.TubetensionEditField.Limits = [0 Inf];
            app.TubetensionEditField.Tooltip = {'According to Hobby, tensions should not be specified less than 0.75 (although, this is not a hard constraint). A tension of 1 creates approximately circular plots. High tension value results in straighter lines between control points.'};
            app.TubetensionEditField.Value = 1;
            app.EntertubesparametersLabel_3 = uilabel(app.GridLayout2);
            app.EntertubesparametersLabel_3.Tooltip = {'If finite length is not selected, tubes will stop only when they reach the domain''s edges, and will be keep only if they are longer than the user-defined minimum tube length. Lengths are calculated from control point to control point, therefore the Bezier curve length is higher due to its curvature.'};
            app.EntertubesparametersLabel_3.Text = '4) 3D Bezier curve parameter';
            app.Image = uiimage(app.GridLayout2);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Tubegeneration_method.png');
            app.ClicktoenlargepictureLabel = uilabel(app.GridLayout2);
            app.ClicktoenlargepictureLabel.Text = 'Click to enlarge picture';
            app.Forcestartingpointequidist_CheckBox = uicheckbox(app.GridLayout2);
            app.Forcestartingpointequidist_CheckBox.Tooltip = {'By default, tube starting points are randomly selected at the domain''s edges. If checked, each new tube starting point will be randomly selected within the domain''s edge (among the  6 faces) that has the less tube starting points so far.'};
            app.Forcestartingpointequidist_CheckBox.Text = 'Force tube starting point to be equidistributed with the 6 faces of the domain';
            app.SetorientationwithDropDownLabel = uilabel(app.GridLayout2);
            app.SetorientationwithDropDownLabel.Text = 'Set orientation with';
            app.SetorientationwithDropDown = uidropdown(app.GridLayout2);
            app.SetorientationwithDropDown.Items = {'Angle', 'Vector'};
            app.SetorientationwithDropDown.ValueChangedFcn = createCallbackFcn(app, @SetorientationwithDropDownValueChanged, true);
            app.SetorientationwithDropDown.Tooltip = {'Orientation set with angle favors tube oriented along the axis z with an equidistribution (flat) of elevation and azimuth. Therefore, to setup an isotropic case, setting orientation with vector is recommended.'};
            app.SetorientationwithDropDown.Value = 'Angle';
            app.andchangewithDropDownLabel = uilabel(app.GridLayout2);
            app.andchangewithDropDownLabel.Text = 'and change with';
            app.andchangewithDropDown = uidropdown(app.GridLayout2);
            app.andchangewithDropDown.Items = {'Angle', 'Vector'};
            app.andchangewithDropDown.ValueChangedFcn = createCallbackFcn(app, @SetorientationwithDropDownValueChanged, true);
            app.andchangewithDropDown.Tooltip = {'Recommended : angle'};
            app.andchangewithDropDown.Value = 'Angle';
            app.MaskTab = uitab(app.TabGroup);
            app.MaskTab.Title = 'Mask';
            app.GridLayout2_3 = uigridlayout(app.MaskTab);
            app.Instructions_title_3 = uilabel(app.GridLayout2_3);
            app.Instructions_title_3.Text = 'Mask';
            app.EnterscalingfactorLabel_2 = uilabel(app.GridLayout2_3);
            app.EnterscalingfactorLabel_2.Text = 'You can import a mask (0=background, otherwise pre-existing material) to specify regions of space where tubes CAN NOT pass through. ';
            app.MaskmustsharethesamedimensionsizewiththegeneratedvolumeLabel = uilabel(app.GridLayout2_3);
            app.MaskmustsharethesamedimensionsizewiththegeneratedvolumeLabel.Text = 'Mask must share the same dimension (domain size) with the generated volume.';
            app.ClicktoimportnotwithinmaskLabel = uilabel(app.GridLayout2_3);
            app.ClicktoimportnotwithinmaskLabel.Text = '1) Click to import "not within" mask';
            app.ImportmaskButton = uibutton(app.GridLayout2_3, 'push');
            app.ImportmaskButton.ButtonPushedFcn = createCallbackFcn(app, @ImportmaskButtonPushed, true);
            app.ImportmaskButton.Text = 'Import mask';
            app.Visualize_mask_button = uibutton(app.GridLayout2_3, 'push');
            app.Visualize_mask_button.ButtonPushedFcn = createCallbackFcn(app, @Visualize_mask_buttonButtonPushed, true);
            app.Visualize_mask_button.Enable = 'off';
            app.Visualize_mask_button.Text = 'Visualize mask';
            app.Mask_volume_name_label = uilabel(app.GridLayout2_3);
            app.Mask_volume_name_label.Text = 'No mask file imported.';
            app.mask_volumefraction_label = uilabel(app.GridLayout2_3);
            app.mask_volumefraction_label.Text = 'No mask file imported.';
            app.UsenotwithinmaskCheckBox = uicheckbox(app.GridLayout2_3);
            app.UsenotwithinmaskCheckBox.Enable = 'off';
            app.UsenotwithinmaskCheckBox.Text = 'Use "not within" mask';
            app.TubescanoverlappwitheachotherCheckBox = uicheckbox(app.GridLayout2_3);
            app.TubescanoverlappwitheachotherCheckBox.Text = 'Tubes can overlapp with each other';
            app.BydefaulttubesarenotallowedtooverlappwitheachotherLabel = uilabel(app.GridLayout2_3);
            app.BydefaulttubesarenotallowedtooverlappwitheachotherLabel.Text = 'By default, tubes are not allowed to overlapp with each other.';
            app.StoppingconditionsTab = uitab(app.TabGroup);
            app.StoppingconditionsTab.Title = 'Stopping conditions';
            app.GridLayout2_6 = uigridlayout(app.StoppingconditionsTab);
            app.Instructions_title_6 = uilabel(app.GridLayout2_6);
            app.Instructions_title_6.Text = 'Stopping conditions';
            app.MaximumwallclocktimesLabel = uilabel(app.GridLayout2_6);
            app.MaximumwallclocktimesLabel.Text = 'Stop algorithm when total wallclock time exceeds a maximum, OR when wallclock time for the current tube exceeds a maximum';
            app.TotalwallclocktimemaximumLabel = uilabel(app.GridLayout2_6);
            app.TotalwallclocktimemaximumLabel.Text = 'Total wallclock time maximum (s)';
            app.StoptotalEditField = uieditfield(app.GridLayout2_6, 'numeric');
            app.StoptotalEditField.Limits = [1 Inf];
            app.StoptotalEditField.Value = 3600;
            app.PertubewallclokctimemaximumsLabel = uilabel(app.GridLayout2_6);
            app.PertubewallclokctimemaximumsLabel.Text = 'Per tube wallclock time maximum (s)';
            app.StoppertubeEditField = uieditfield(app.GridLayout2_6, 'numeric');
            app.StoppertubeEditField.Limits = [1 Inf];
            app.StoppertubeEditField.Value = 600;
            app.VolumefractionEditField_2Label = uilabel(app.GridLayout2_6);
            app.VolumefractionEditField_2Label.Tooltip = {'Whichever come first.'};
            app.VolumefractionEditField_2Label.Text = 'Volume fraction';
            app.VolumefractionEditField = uieditfield(app.GridLayout2_6, 'numeric');
            app.VolumefractionEditField.Limits = [0 1];
            app.VolumefractionEditField.Tooltip = {'Whichever come first.'};
            app.VolumefractionEditField.Value = 0.1;
            app.NumberoftubesEditField_2Label = uilabel(app.GridLayout2_6);
            app.NumberoftubesEditField_2Label.Tooltip = {'Whichever come first.'};
            app.NumberoftubesEditField_2Label.Text = 'Number of tubes';
            app.NumberoftubesEditField = uieditfield(app.GridLayout2_6, 'numeric');
            app.NumberoftubesEditField.Limits = [1 Inf];
            app.NumberoftubesEditField.RoundFractionalValues = 'on';
            app.NumberoftubesEditField.Tooltip = {'Whichever come first.'};
            app.NumberoftubesEditField.Value = 5;
            app.MaximumwallclocktimesLabel_2 = uilabel(app.GridLayout2_6);
            app.MaximumwallclocktimesLabel_2.Text = 'Stop aglorithm when a given number of tubes has been generated OR when a given tube total volume fraction has been reached';
            app.CroppingTab = uitab(app.TabGroup);
            app.CroppingTab.Title = 'Cropping';
            app.GridLayout2_9 = uigridlayout(app.CroppingTab);
            app.Instructions_title_9 = uilabel(app.GridLayout2_9);
            app.Instructions_title_9.Text = 'Cropping';
            app.SetcroppingparametersappliedaftergenerationisfinishedLabel = uilabel(app.GridLayout2_9);
            app.SetcroppingparametersappliedaftergenerationisfinishedLabel.Text = '1) Set cropping parameters (applied after generation is finished)';
            app.Cropping_UITable = uitable(app.GridLayout2_9);
            app.Cropping_UITable.ColumnName = {'Direction'; 'Crop by (in voxel length)'; 'Exclude tube starting points that are distant from edges less than (in domain''s length ratio)'};
            app.Cropping_UITable.RowName = {};
            app.Cropping_UITable.ColumnEditable = [false true true];
            app.Cropping_UITable.CellEditCallback = createCallbackFcn(app, @Cropping_UITableCellEdit, true);
            app.UpscaleTab = uitab(app.TabGroup);
            app.UpscaleTab.Title = 'Upscale';
            app.GridLayout2_2 = uigridlayout(app.UpscaleTab);
            app.Instructions_title_2 = uilabel(app.GridLayout2_2);
            app.Instructions_title_2.Text = 'Up/down scaling';
            app.EnterscalingfactorsLabel = uilabel(app.GridLayout2_2);
            app.EnterscalingfactorsLabel.Text = '1) Enter scaling factors';
            app.Controlpointsscalingfactor1upscaling1downscaling1noscalingLabel = uilabel(app.GridLayout2_2);
            app.Controlpointsscalingfactor1upscaling1downscaling1noscalingLabel.Text = 'Control points scaling factor (>1: upscaling, <1: downscaling, =1 no scaling)';
            app.Scalingfactor1upscaling1downscaling1noscalingEditField = uieditfield(app.GridLayout2_2, 'numeric');
            app.Scalingfactor1upscaling1downscaling1noscalingEditField.Value = 1;
            app.Label_2 = uilabel(app.GridLayout2_2);
            app.Label_2.Text = {'Volume(s) are re-generated using the bezier control points with scaled location, scaled radius, and a scaled domain size, and not from up/down scaling a 3D volume which'; 'would be suboptimal.'};
            app.EditFieldLabel = uilabel(app.GridLayout2_2);
            app.EditFieldLabel.Text = 'Tube radius scaling factor (>1: upscaling, <1: downscaling, =1 no scaling)';
            app.Scalingfactor1upscaling1downscaling1noscalingEditField_2 = uieditfield(app.GridLayout2_2, 'numeric');
            app.Scalingfactor1upscaling1downscaling1noscalingEditField_2.Value = 1;
            app.GenerationTab = uitab(app.TabGroup);
            app.GenerationTab.Title = 'Generation';
            app.GridLayout2_5 = uigridlayout(app.GenerationTab);
            app.Instructions_title_5 = uilabel(app.GridLayout2_5);
            app.Instructions_title_5.Text = 'Once all parameters have been set, run the generation algorithm';
            app.EnterscalingfactorLabel_3 = uilabel(app.GridLayout2_5);
            app.EnterscalingfactorLabel_3.Text = '1) Set number of runs. Algorithm will be run sequentially. Valuable to check reproducibility';
            app.RungenerationalgorithmButton = uibutton(app.GridLayout2_5, 'push');
            app.RungenerationalgorithmButton.ButtonPushedFcn = createCallbackFcn(app, @RungenerationalgorithmButtonPushed, true);
            app.RungenerationalgorithmButton.Enable = 'off';
            app.RungenerationalgorithmButton.Text = 'Run generation algorithm';
            app.NumberofrunsEditFieldLabel = uilabel(app.GridLayout2_5);
            app.NumberofrunsEditFieldLabel.Text = 'Number of runs';
            app.NumberofrunsEditField = uieditfield(app.GridLayout2_5, 'numeric');
            app.NumberofrunsEditField.Limits = [1 Inf];
            app.NumberofrunsEditField.RoundFractionalValues = 'on';
            app.NumberofrunsEditField.Value = 1;
            app.Label = uilabel(app.GridLayout2_5);
            app.Label.Text = '2) Click to run the algorithms. Tables below will be updated each time a run is finished.';
            app.VolumefractionsLabel = uilabel(app.GridLayout2_5);
            app.VolumefractionsLabel.Text = 'Volume fractions';
            app.Vf_UITable = uitable(app.GridLayout2_5);
            app.Vf_UITable.ColumnName = {'Run#'; 'Pores'; 'Tube'; 'Tube#'};
            app.Vf_UITable.RowName = {};
            app.OutcomeandwallclocktimesLabel = uilabel(app.GridLayout2_5);
            app.OutcomeandwallclocktimesLabel.Text = 'Outcome and wallclock time (s)';
            app.RunStatut_UITable = uitable(app.GridLayout2_5);
            app.RunStatut_UITable.ColumnName = {'Outcome'; 'Started'; 'Generation'; 'Scaling'};
            app.RunStatut_UITable.RowName = {};
            app.VerboseCheckBox = uicheckbox(app.GridLayout2_5);
            app.VerboseCheckBox.Text = 'Verbose';
            app.VisualizationTab = uitab(app.TabGroup);
            app.VisualizationTab.Title = 'Visualization';
            app.GridLayout2_7 = uigridlayout(app.VisualizationTab);
            app.Instructions_title_7 = uilabel(app.GridLayout2_7);
            app.Instructions_title_7.Text = 'Visualization';
            app.SelectwhatvolumesyouwouldliketovisualizeoneorseveralatonceLabel = uilabel(app.GridLayout2_7);
            app.SelectwhatvolumesyouwouldliketovisualizeoneorseveralatonceLabel.Text = '1) Select what volumes you would like to visualize (one or several at once).';
            app.Visualization_UITable = uitable(app.GridLayout2_7);
            app.Visualization_UITable.ColumnName = {'Run#'; 'Visualize'};
            app.Visualization_UITable.RowName = {};
            app.Visualization_UITable.ColumnEditable = [false true];
            app.ScaledvolumesCheckBox = uicheckbox(app.GridLayout2_7);
            app.ScaledvolumesCheckBox.Enable = 'off';
            app.ScaledvolumesCheckBox.Text = 'Scaled volume(s)';
            app.ShowDropDownLabel = uilabel(app.GridLayout2_7);
            app.ShowDropDownLabel.Text = 'Show';
            app.ShowDropDown = uidropdown(app.GridLayout2_7);
            app.ShowDropDown.Items = {'Label', 'Tube id', 'Skeleton'};
            app.ShowDropDown.Value = 'Label';
            app.Visualize2DButton = uibutton(app.GridLayout2_7, 'push');
            app.Visualize2DButton.ButtonPushedFcn = createCallbackFcn(app, @Visualize2DButtonPushed, true);
            app.Visualize2DButton.Enable = 'off';
            app.Visualize2DButton.Text = 'Visualize (2D)';
            app.Visualize3DButton = uibutton(app.GridLayout2_7, 'push');
            app.Visualize3DButton.ButtonPushedFcn = createCallbackFcn(app, @Visualize3DButtonPushed, true);
            app.Visualize3DButton.Enable = 'off';
            app.Visualize3DButton.Text = 'Visualize (3D)';
            app.ColormapDropDownLabel = uilabel(app.GridLayout2_7);
            app.ColormapDropDownLabel.Text = 'Colormap';
            app.ColormapDropDown = uidropdown(app.GridLayout2_7);
            app.ColormapDropDown.Items = {'turbo', 'jet', 'copper', 'gray', 'bone', 'parula'};
            app.ColormapDropDown.Value = 'gray';
            app.HowtoquoteTab = uitab(app.TabGroup);
            app.HowtoquoteTab.Title = 'How to quote';
            app.GridLayout2_11 = uigridlayout(app.HowtoquoteTab);
            app.Instructions_title_11 = uilabel(app.GridLayout2_11);
            app.Instructions_title_11.Text = 'How to quote';
            app.SelectasolidphaseandsetthenumberofLabel_3 = uilabel(app.GridLayout2_11);
            app.SelectasolidphaseandsetthenumberofLabel_3.Text = 'If you use this algorithm or report results produced thanks to this algorithm, please quote it as follow:';
            app.Label_3 = uilabel(app.GridLayout2_11);
            app.Label_3.Text = {'F. L. E. Usseglio-Viretta, P. Patel, E. Bernhardt, A Mistry, P. P. Mukherjee, J. Allen, S. J. Cooper, J. Laurencin, and K. Smith'; 'MATBOX: An Open-source Microstructure Analysis Toolbox for microstructure generation, segmentation, characterization, visualization, correlation, and meshing'; 'SoftwareX, 17, 2022'};
            app.Hyperlink_2 = uihyperlink(app.GridLayout2_11);
            app.Hyperlink_2.URL = 'https://doi.org/10.1016/j.softx.2021.100915';
            app.Hyperlink_2.Text = 'https://doi.org/10.1016/j.softx.2021.100915';
            app.ThismoduleisusingLabel = uilabel(app.GridLayout2_11);
            app.ThismoduleisusingLabel.Text = {'This module is using "hobbysplines" to create smooth 3D bezier curves from the MATLAB file exchange.'; 'Cite as: "Will Robertson (2023). Smooth 3D bezier curves with implicit control points (https://www.mathworks.com/matlabcentral/fileexchange/'; '42302-smooth-3d-bezier-curves-with-implicit-control-points), MATLAB Central File Exchange. Retrieved March 29, 2023."'};
            app.HelpTab = uitab(app.TabGroup);
            app.HelpTab.Title = 'Help';
            app.GridLayout2_8 = uigridlayout(app.HelpTab);
            app.Instructions_title_8 = uilabel(app.GridLayout2_8);
            app.Instructions_title_8.Text = 'Help';
            app.reproducibilityLabel = uilabel(app.GridLayout2_8);
            app.reproducibilityLabel.Text = '- If you run into problems, please read the open issues listed in the GitHub webpage. If your problem is not listed, please open a new issue.';
            app.Hyperlink = uihyperlink(app.GridLayout2_8);
            app.Hyperlink.URL = 'https://github.com/NREL/MATBOX_Microstructure_analysis_toolbox/issues';
            app.Hyperlink.Text = 'https://github.com/NREL/MATBOX_Microstructure_analysis_toolbox/issues';
            app.AboutTab = uitab(app.TabGroup);
            app.AboutTab.Title = 'About';
            app.GridLayout2_12 = uigridlayout(app.AboutTab);
            app.Instructions_title_12 = uilabel(app.GridLayout2_12);
            app.Instructions_title_12.Text = 'About';
            app.Label_4 = uilabel(app.GridLayout2_12);
            app.Label_4.Text = {'National Renewable Energy Laboratory (NREL) developed the Microstructure Analysis Toolbox (MATBOX) to analyze electrode microstructures for lithium-ion (Li-ion) batteries'; 'and other 3D heterogeneous materials.'};
            app.NREL_logo = uiimage(app.GridLayout2_12);
            app.NREL_logo.ImageClickedFcn = createCallbackFcn(app, @NREL_logoImageClicked, true);
            app.NREL_logo.ImageSource = 'logo_NREL.png';
            app.Hyperlink_3 = uihyperlink(app.GridLayout2_12);
            app.Hyperlink_3.URL = 'https://www.nrel.gov/transportation/matbox.html';
            app.Hyperlink_3.Text = 'MATBOX official webpage';
            app.Hyperlink_4 = uihyperlink(app.GridLayout2_12);
            app.Hyperlink_4.URL = 'https://github.com/NREL/MATBOX_Microstructure_analysis_toolbox';
            app.Hyperlink_4.Text = 'MATBOX GitHub repository';
            app.MATBOXinstalledversionLabel = uilabel(app.GridLayout2_12);
            app.MATBOXinstalledversionLabel.Text = 'MATBOX installed version:';
            app.OnlineversioncheckLabel = uilabel(app.GridLayout2_12);
            app.OnlineversioncheckLabel.Text = 'Online version check';
            app.Localversion = uilabel(app.GridLayout2_12);
            app.Localversion.Text = '1.1 February 2023';
            app.IfyoulikethistoolpleasegiveitastarintheGithubrepositoryLabel = uilabel(app.GridLayout2_12);
            app.IfyoulikethistoolpleasegiveitastarintheGithubrepositoryLabel.Text = 'If you like this tool, please give it a ''Star'' in the Github repository to increase its visibility.';
            % Show the figure after all components are created
            app.TubebasedstochasticgenerationsubmoduleUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Microstructure_generation_tubes

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TubebasedstochasticgenerationsubmoduleUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TubebasedstochasticgenerationsubmoduleUIFigure)
        end
    end
end