classdef Microstructure_generation_web < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        WebmicrostructuregenerationUIFigure  matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        InstructionsTab                 matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        Label_7                         matlab.ui.control.Label
        ChordradiusandnodeLabel         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        YoumustfirsthavealistofcentroidsYoucanproduceoneusingtheLabel  matlab.ui.control.Label
        InstructionsLabel               matlab.ui.control.Label
        CentroidsTab                    matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        FilenameEditField               matlab.ui.control.EditField
        FilenameEditFieldLabel          matlab.ui.control.Label
        NosavefolderselectedLabel       matlab.ui.control.Label
        SavingoptionsLabel              matlab.ui.control.Label
        ImportcentroidsLabel            matlab.ui.control.Label
        ClicktoselectsavefolderButton_  matlab.ui.control.Button
        NodataimportedLabel             matlab.ui.control.Label
        VisualizedistancemapanditsdistributionCheckBox  matlab.ui.control.CheckBox
        VisualizecentroidsButton        matlab.ui.control.Button
        DimensionnaLabel                matlab.ui.control.Label
        CentroidrangenaLabel            matlab.ui.control.Label
        NumberofnodesnaLabel            matlab.ui.control.Label
        ClicktoimportcentroidsButton    matlab.ui.control.Button
        ImportfromtheEllipsoidmodule_CheckBox  matlab.ui.control.CheckBox
        Centroidsarraysizemustben3withnthenumberofnodesLabel  matlab.ui.control.Label
        ImportcentroidsandsavingoptionsLabel  matlab.ui.control.Label
        ParametersTab                   matlab.ui.container.Tab
        GridLayout_Parameter            matlab.ui.container.GridLayout
        ConvertinspheresCheckBox        matlab.ui.control.CheckBox
        MaxdistanceEditField            matlab.ui.control.NumericEditField
        MaxdistanceEditFieldLabel       matlab.ui.control.Label
        MaxdistancebetweenconnectednodesLabel  matlab.ui.control.Label
        DilationiterationEditField      matlab.ui.control.NumericEditField
        DilationiterationEditFieldLabel  matlab.ui.control.Label
        NodechordjunctionLabel          matlab.ui.control.Label
        PlotdistributionsoptionalButton  matlab.ui.control.Button
        aEditField                      matlab.ui.control.NumericEditField
        aEditFieldLabel                 matlab.ui.control.Label
        ScalingfactorEditField          matlab.ui.control.NumericEditField
        ScalingfactorEditFieldLabel     matlab.ui.control.Label
        DistributionNoderadius_parameters_Table  matlab.ui.control.Table
        NoderadiusDropDown              matlab.ui.control.DropDown
        NoderadiusDropDownLabel         matlab.ui.control.Label
        Distribution_parameters_Table   matlab.ui.control.Table
        DistributionsLabel              matlab.ui.control.Label
        ExtendFOVEditField              matlab.ui.control.NumericEditField
        ExtendFOVEditFieldLabel         matlab.ui.control.Label
        RecenterCheckBox                matlab.ui.control.CheckBox
        FieldofviewFOVLabel             matlab.ui.control.Label
        ParametersLabel                 matlab.ui.control.Label
        GenerationTab                   matlab.ui.container.Tab
        GridLayout_generation           matlab.ui.container.GridLayout
        VisualizeButton                 matlab.ui.control.Button
        VolumefractionEditField         matlab.ui.control.NumericEditField
        VolumefractionEditFieldLabel    matlab.ui.control.Label
        IdleLabel                       matlab.ui.control.Label
        RungenerationalgorithmButton    matlab.ui.control.Button
        GenerationLabel                 matlab.ui.control.Label
        HowtoquoteTab                   matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        HowtoquoteLabel                 matlab.ui.control.Label
        Hyperlink                       matlab.ui.control.Hyperlink
        Label_5                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        HelpTab                         matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        Hyperlink2                      matlab.ui.control.Hyperlink
        Label_6                         matlab.ui.control.Label
        HelpLabel                       matlab.ui.control.Label
        AboutTab                        matlab.ui.container.Tab
        GridLayout6                     matlab.ui.container.GridLayout
        Localversion                    matlab.ui.control.Label
        OnlineversioncheckLabel         matlab.ui.control.Label
        MATBOXinstalledversionLabel     matlab.ui.control.Label
        Label_9                         matlab.ui.control.Label
        Hyperlink4                      matlab.ui.control.Hyperlink
        Hyperlink3                      matlab.ui.control.Hyperlink
        NREL_logo                       matlab.ui.control.Image
        Label_8                         matlab.ui.control.Label
        AboutLabel                      matlab.ui.control.Label
    end

    
    properties (Access = private)
        workingpath = []
        FileName = [];
        PathName = [];
        centroids = [];
        sz = [];
        dim = [];
        BW= [];
        BW_spheres = [];
        savefolder = [];
    end
    
    methods (Access = private)
        
        function [] = Set_GUI(app,bool)
            app.VisualizecentroidsButton.Enable = bool;        
            app.VisualizedistancemapanditsdistributionCheckBox.Enable = bool; 

            if bool
                str_bool = 'on';
            else
                str_bool = 'off';
            end
            children = get(app.GridLayout_Parameter,'Children');
            set(children,'Enable',str_bool);        
            children = get(app.GridLayout_generation,'Children');
            set(children,'Enable',str_bool);                 
        end
        
        function [x, pdf_, cdf_] = distributions(app,equiprobability, mean_, sigma_, maxdeviation)
            x = []; pdf_ = []; cdf_ =[];
            % Univariate Gaussian Distribution
            % Probability density function of a gaussian distribution
            pdf_GD = @(x,mu,sigma) 1./(2*pi*sigma.^2).^(0.5).*exp(-(x-mu).^2 ./ (2*sigma.^2));

            if ~equiprobability && maxdeviation~=0
                x=linspace(mean_-maxdeviation, mean_+maxdeviation, 1000);
                pdf_=pdf_GD(x,mean_,sigma_);
                cdf_ = pdf2cdf(x,pdf_);
            end

            function c = pdf2cdf(x,pdf)
                n=length(x);
                c=zeros(1,n);
                for i=2:1:n
                    c(i) = trapz(x(1:i),pdf(1:i));
                end
                if c(end)<1
                    c(end)=1;
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Set_GUI(false)
            
            app.Distribution_parameters_Table.ColumnFormat = {'char','char','char','numeric','numeric','numeric','logical'};
            app.Distribution_parameters_Table.Data = [ {'Coordination';'Chord radius'} {'-';'Voxel';} {'[1,Inf] (integer)';'[1,Inf]'} num2cell([3;2]) num2cell([1;1]) num2cell([1;1]) {false;false}];
            app.DistributionNoderadius_parameters_Table.ColumnFormat = {'char','char','char','numeric','numeric','numeric','logical'};
            app.DistributionNoderadius_parameters_Table.Data = [ {'Node radius'} {'Voxel'} {'[1,Inf]'} num2cell([4]) num2cell([1]) num2cell([1]) {false}];  

            % Check online version
            localversion = app.Localversion.Text;
            try
                githubversionfile=webread('https://raw.githubusercontent.com/NREL/MATBOX_Microstructure_analysis_toolbox/master/License.txt');
                if ~strcmp(localversion,githubversionfile)
                    app.OnlineversioncheckLabel.Text = ['There is a new version (' localversion ') available online. Please go to the Github repositorty to update your MATBOX installation'];
                    app.OnlineversioncheckLabel.FontColor = 'r';
                else
                    app.OnlineversioncheckLabel.Text = 'You are using the up-to-date version of MATBOX';
                    app.OnlineversioncheckLabel.FontColor = 'k';
                end
            catch ME
                if strcmp(ME.identifier,'MATLAB:webservices:Timeout')
                    app.OnlineversioncheckLabel.Text = 'Could not check current version online.';
                end
            end            

        end

        % Button pushed function: ClicktoimportcentroidsButton
        function ClicktoimportcentroidsButtonPushed(app, event)
            % Open dialog box to choose file path
            if isempty(app.workingpath)
                app.workingpath = pwd;
            end
            [app.FileName,app.PathName,~] = uigetfile({'*.mat','Mat file'},'Select a .mat file',app.workingpath);

            if app.FileName==0
                % User clicked cancel button or closed the dialog box
                app.NumberofnodesnaLabel.Text = 'Number of nodes: n/a';
                app.CentroidrangenaLabel.Text = 'Centroid range: n/a';
                app.DimensionnaLabel.Text = 'Dimension: n/a';
                app.NodataimportedLabel.Text = 'No data imported';
                app.centroids = [];
                app.sz = [];
                app.dim = [];
                app.Set_GUI(false);

            else
                app.workingpath = app.PathName;
                fullpath = fullfile(app.PathName,app.FileName);

                if isempty(app.savefolder)
                    app.savefolder = app.workingpath;
                    app.NosavefolderselectedLabel.Text = app.savefolder;
                    app.NosavefolderselectedLabel.Tooltip = app.savefolder;
                end
                [~,tmp,~] = fileparts(fullpath);
                app.FilenameEditField.Value = [tmp '_web'];   

                app.centroids = load(fullpath);
                if app.ImportfromtheEllipsoidmodule_CheckBox.Value
                    T = struct2table(app.centroids);
                    app.centroids = T.Variables;
                    app.centroids = app.centroids(1:end,3:5);
                    if app.centroids(end,1)==0 % Old bug
                        app.centroids(end,:)=[];
                    end
                else
                    if isstruct(app.centroids)
                        T = struct2table(app.centroids);
                        app.centroids = T.Variables;
                    end
                end

                [n_nodes,app.dim] = size(app.centroids);
                x0 = min(app.centroids(:,1)); x1 = max(app.centroids(:,1));
                y0 = min(app.centroids(:,2)); y1 = max(app.centroids(:,2));
                if app.dim==3
                    z0 = min(app.centroids(:,3)); z1 = max(app.centroids(:,3));
                end

                if app.dim==2
                    app.sz = [x1 y1];
                else
                    app.sz = [x1 y1 z1];
                end
                
                app.NumberofnodesnaLabel.Text = ['Number of nodes: ' num2str(n_nodes,'%i')];
                app.DimensionnaLabel.Text = ['Dimension: ' num2str(app.dim,'%i')];
                app.NodataimportedLabel.Text = fullpath;
                app.NodataimportedLabel.Tooltip = fullpath;

                if app.dim==3
                    app.CentroidrangenaLabel.Text = ['Centroid range: x from ' num2str(x0,'%i') ' to ' num2str(x1,'%i') ', y from ' num2str(y0,'%i') ' to ' num2str(y1,'%i') ', z from ' num2str(z0,'%i') ' to ' num2str(z1,'%i')];
                else
                    app.CentroidrangenaLabel.Text = ['Centroid range: x from ' num2str(x0,'%i') ' to ' num2str(x1,'%i') ', y from ' num2str(y0,'%i') ' to ' num2str(y1,'%i')];
                end

                app.Set_GUI(true);
                app.NoderadiusDropDownValueChanged;
                app.VisualizeButton.Enable = 'off';
            end
        end

        % Button pushed function: VisualizecentroidsButton
        function VisualizecentroidsButtonPushed(app, event)
            if app.dim==2
                idx = sub2ind(app.sz,app.centroids(:,1),app.centroids(:,2));
            elseif app.dim==3
                idx = sub2ind(app.sz,app.centroids(:,1),app.centroids(:,2),app.centroids(:,3));
            end
            center_map = zeros(app.sz);
            center_map(idx)=1;
            center_map = uint8(center_map);

            if app.VisualizedistancemapanditsdistributionCheckBox.Value
                dmap=bwdist(center_map);
                p.xlabel = 'Distance from centroids';
                if numel(dmap)>100e6
                    p.option = 'Bins number: auto';
                end
                fct_plothistogram_wholevolume(dmap,p)
            end

            if app.dim==2
                figure; imagesc(center_map); axis equal; axis tight; colormap gray;
                if app.VisualizedistancemapanditsdistributionCheckBox.Value
                    cmap = turbo(length(unique(dmap)));
                    cmap(1,:) = [0.5 0.5 0.5];
                    figure; imagesc(dmap); axis equal; axis tight; colormap(cmap); colorbar;
                end
            else
                if app.VisualizedistancemapanditsdistributionCheckBox.Value
                    p(1).name = 'Centroids';
                    p(2).name = 'Distance from centroids';
                    Visualization_multiple_volumes(p,center_map,round(dmap));
                else
                    p(1).name = 'Centroids';
                    Visualization_multiple_volumes(p,center_map);
                end
            end
        end

        % Button pushed function: RungenerationalgorithmButton
        function RungenerationalgorithmButtonPushed(app, event)
            app.VisualizeButton.Enable = 'off';
            app.IdleLabel.Text = 'Please wait...'; pause(0.1);
            app.VolumefractionEditField.Value = 0;

            app.BW = [];
            app.BW_spheres = [];
            
            % Parameters
            p.recenter = app.RecenterCheckBox.Value;
            p.extend_FOV = app.ExtendFOVEditField.Value;

            pars = app.Distribution_parameters_Table.Data(:,4:end);
            pars = [cell2mat(pars(:,1:3)) cell2mat(pars(:,4))];
            % Coordination number
            p.mean_coordination = pars(1,1);
            p.sigma_coordination = pars(1,2);
            p.maxdeviation_coordination = pars(1,3);
            p.equiprobability_coordination = logical(pars(1,4));
            % Chord radius
            p.mean_connectionradius = pars(2,1);
            p.sigma_connectionradius = pars(2,2);
            p.maxdeviation_connectionradius = pars(2,3);
            p.equiprobability_connectionradius = pars(2,4);
                                  
            % Node radius
            p.node_choice = app.NoderadiusDropDown.Value;
            p.node_radius_scalingfactor = app.ScalingfactorEditField.Value;
            p.node_radius_a = app.aEditField.Value;
            pars = app.DistributionNoderadius_parameters_Table.Data(:,4:end);
            pars = [cell2mat(pars(:,1:3)) cell2mat(pars(:,4))];
            p.mean_noderadius = pars(1,1);
            p.sigma_noderadius = pars(1,2);
            p.maxdeviation_noderadius = pars(1,3);
            p.equiprobability_noderadius = pars(1,4);
            
            p.max_distance = app.MaxdistanceEditField.Value;

            p.junction_dilationiteration = app.DilationiterationEditField.Value;

            p.convert_spheres = app.ConvertinspheresCheckBox.Value;

            % Run algorithm
            tic
            [app.BW, app.BW_spheres, nodes_centroids_radius_id, chord_centroids_radius_id,sphere_connectivity] = function_generate_web_microstructure(app.centroids,p);
            wallclock_time = toc;

            % Save
            fullpath = fullfile(app.savefolder,[app.FilenameEditField.Value '.tif']);
            function_save_tif(app.BW,fullpath);
            if p.convert_spheres
                fullpath = fullfile(app.savefolder,[app.FilenameEditField.Value '_spheres.tif']);
                function_save_tif(app.BW_spheres,fullpath);
                fullpath = fullfile(app.savefolder,[app.FilenameEditField.Value '_nodes_centroids_radius_id.txt']);
                writematrix(nodes_centroids_radius_id,fullpath,'Delimiter',';');  
                fullpath = fullfile(app.savefolder,[app.FilenameEditField.Value '_chord_centroids_radius_id.txt']);
                writematrix(chord_centroids_radius_id,fullpath,'Delimiter',';');       
                fullpath = fullfile(app.savefolder,[app.FilenameEditField.Value 'sphere_connectivity.txt']);
                writematrix(sphere_connectivity,fullpath,'Delimiter',';');                       
            end
            fullpath = fullfile(app.savefolder,[app.FilenameEditField.Value '_parameters.mat']);
            centroids = app.centroids;
            save(fullpath,'centroids','p');

            app.VisualizeButton.Enable = 'on';
            app.IdleLabel.Text = ['Done! Wall clock time: ' num2str(wallclock_time,'%1.1f') ' s'];
            app.VolumefractionEditField.Value = round( sum(sum(sum(app.BW)))/numel(app.BW), 3);
        end

        % Value changed function: NoderadiusDropDown
        function NoderadiusDropDownValueChanged(app, event)
            value = app.NoderadiusDropDown.Value;
            if strcmp(value,'Controlled with a distribution')
                app.NoderadiusDropDown.Tooltip = 'Distribution';
                app.DistributionNoderadius_parameters_Table.Enable = 'on';
                app.ScalingfactorEditField.Enable = 'off';
                app.aEditField.Enable = 'off';
            elseif strcmp(value,'Scaled with chord radius')
                app.NoderadiusDropDown.Tooltip = 'Node radius = scaling factor x max(chord radius in contact)';
                app.DistributionNoderadius_parameters_Table.Enable = 'off';
                app.ScalingfactorEditField.Enable = 'on';
                app.aEditField.Enable = 'off';                
            elseif strcmp(value,'Scaled with number of connection')
                app.NoderadiusDropDown.Tooltip = 'Node radius = scaling factor x ( a x number of connection + max(chord radius in contact) - a )';
                app.DistributionNoderadius_parameters_Table.Enable = 'off';
                app.ScalingfactorEditField.Enable = 'on';
                app.aEditField.Enable = 'on';                   
            end
        end

        % Button pushed function: PlotdistributionsoptionalButton
        function PlotdistributionsoptionalButtonPushed(app, event)
            % Read parameter table
            pars1 = app.Distribution_parameters_Table.Data(:,4:end);
            pars1 = [cell2mat(pars1(:,1:3)) cell2mat(pars1(:,4))];   
            pars2 = app.DistributionNoderadius_parameters_Table.Data(:,4:end);
            pars2 = [cell2mat(pars2(:,1:3)) cell2mat(pars2(:,4))]; 
            pars = [pars1;pars2];
            
            scrsz = get(0,'ScreenSize'); % Screen resolution
            Fig = figure; % Create figure
            Fig.Name= 'Distributions'; % Figure name
            Fig.Color='white'; % Background colour
            set(Fig,'position',[scrsz(1) scrsz(2) scrsz(3)*4/5 scrsz(4)*2/3]);

            if strcmp(app.NoderadiusDropDown.Value,'Controlled with a distribution') 
                n_axe = 3;
            else
                n_axe = 2;
            end

            for id_axe=1:1:n_axe
                if id_axe==1
                    sub_axes=subplot(1,n_axe,id_axe,'Parent',Fig);
                    h_title=title ('Coordination number'); 
                    xlabel('Coordination number');
                elseif id_axe==2
                    sub_axes=subplot(1,n_axe,id_axe,'Parent',Fig);
                    h_title=title ('Chord radius'); 
                    xlabel('Voxel');
                elseif id_axe==3
                    sub_axes=subplot(1,n_axe,id_axe,'Parent',Fig);
                    h_title=title ('Node radius');
                    xlabel('Voxel');                
                end
                hold(sub_axes,'on'); % Active subplot
                % Plot
                [x_, pdf_, ~] = app.distributions(pars(id_axe,4), pars(id_axe,1), pars(id_axe,2), pars(id_axe,3));
                if pars(id_axe,3)==0
                    plot(pars(id_axe,1),1,'LineWidth',2,'Marker','o','MarkerSize',8);
                else
                    if pars(id_axe,4)
                        plot([pars(id_axe,1)-pars(id_axe,3) pars(id_axe,1)+pars(id_axe,3)],[1 1],'LineWidth',2);
                    else
                        plot(x_,pdf_,'LineWidth',2);
                    end
                end
                ylabel('a.u');
                % - Grid
                grid(sub_axes,'on'); % Display grid
                set(sub_axes,'XMinorGrid','on','YMinorGrid','on'); % Display grid for minor thicks
                set(sub_axes,'FontName','Times new roman','FontSize',14); % Fontname and fontsize
                h_title.FontSize = 16; % Set title fontsize
                sgtitle(Fig,'Web parameters','FontWeight','bold','FontSize',20,'FontName','Times new roman');
                hold(sub_axes,'off'); % Relase figure
            end
        end

        % Button pushed function: VisualizeButton
        function VisualizeButtonPushed(app, event)
            if app.dim==2
                figure; imagesc(app.BW); axis tight; axis equal; colormap gray;
            else
                if isempty(app.BW_spheres)
                    voxelsize = 1;
                    voxelsize_unitname = '';
                    arrayunit = '';
                    titlename = 'Web microstructure';
                    microstructure_visualization_slices( app.BW, titlename, voxelsize, voxelsize_unitname, arrayunit);
                else
                    p(1).name = 'Web microstructure';
                    p(2).name = 'Sphere-only representation';
                    Visualization_multiple_volumes(p,app.BW,app.BW_spheres);
                end
            end  
        end

        % Button pushed function: ClicktoselectsavefolderButton_
        function ClicktoselectsavefolderButton_Pushed(app, event)
            if isempty(app.savefolder) 
                selpath = uigetdir(pwd,'Select save folder');
            else
                selpath = uigetdir(app.savefolder,'Select save folder');
            end
            if selpath~=0
                if ispc
                    selpath=[selpath '\'];
                else
                    selpath=[selpath '/'];
                end
                app.savefolder=selpath;
                app.NosavefolderselectedLabel.Text = app.savefolder;
                app.NosavefolderselectedLabel.Tooltip = app.savefolder;
            end
        end

        % Image clicked function: NREL_logo
        function NREL_logoImageClicked(app, event)
            url = 'https://www.nrel.gov/';
            web(url)   
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.WebmicrostructuregenerationUIFigure = uifigure('Visible', 'off');
            app.WebmicrostructuregenerationUIFigure.Name = 'Web microstructure generation';
            app.WebmicrostructuregenerationUIFigure.Icon = 'Icon_webgeneration.png';
            app.GridLayout = uigridlayout(app.WebmicrostructuregenerationUIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.InstructionsTab = uitab(app.TabGroup);
            app.InstructionsTab.Title = 'Instructions';
            app.GridLayout3 = uigridlayout(app.InstructionsTab);
            app.InstructionsLabel = uilabel(app.GridLayout3);
            app.InstructionsLabel.Text = 'Instructions';
            app.YoumustfirsthavealistofcentroidsYoucanproduceoneusingtheLabel = uilabel(app.GridLayout3);
            app.YoumustfirsthavealistofcentroidsYoucanproduceoneusingtheLabel.Text = 'You must first have a list of centroids. You can produce one using the ellipsoid-based stochastic module.';
            app.Label = uilabel(app.GridLayout3);
            app.Label.Text = {'Centroids define the location of nodes. Nodes are then connected to each other up to a maximum distance and up to a maximum'; 'number of connection (i.e., coordination number). The maximum number of connection for each node is determined with a'; 'normal distribution.'};
            app.Label_2 = uilabel(app.GridLayout3);
            app.Label_2.Text = 'Connection between nodes are colled chords. Chords radius are determined with a normal distribution.';
            app.Label_3 = uilabel(app.GridLayout3);
            app.Label_3.Text = {'Node radius are determined using one of the following options:'; '- with a normal distribution irrespective to connected chord radius and number of connections '; '- scaled with the maximum radius among the chord radius of all connected chords with the node'; '- linearly scaled with the number of connections with the node, with for one connection the maximum radius among the chord radius'; 'of all connected chords with the node'};
            app.ChordradiusandnodeLabel = uilabel(app.GridLayout3);
            app.ChordradiusandnodeLabel.Text = {'Junction between chords and nodes can be smoothed by locally dilating the regions close from both node, chords (and already'; 'exisitng junctions). You can specifiy the number of iteration for this operation'};
            app.Label_7 = uilabel(app.GridLayout3);
            app.Label_7.Text = 'Volumes generated are automaticalled saved in a tif file. Parameters are also saved in a mat file.';
            app.CentroidsTab = uitab(app.TabGroup);
            app.CentroidsTab.Title = 'Centroids';
            app.GridLayout2 = uigridlayout(app.CentroidsTab);
            app.ImportcentroidsandsavingoptionsLabel = uilabel(app.GridLayout2);
            app.ImportcentroidsandsavingoptionsLabel.Text = 'Import centroids and saving options';
            app.Centroidsarraysizemustben3withnthenumberofnodesLabel = uilabel(app.GridLayout2);
            app.Centroidsarraysizemustben3withnthenumberofnodesLabel.Text = 'Centroids array size must be [n,dim] with n the number of nodes and dim = 2 or 3. Array must contains only integers > 1';
            app.ImportfromtheEllipsoidmodule_CheckBox = uicheckbox(app.GridLayout2);
            app.ImportfromtheEllipsoidmodule_CheckBox.Text = 'Import from the Ellipsoid-based stochastic module (Additionalinfo_particle_run_x or Additionalinfo_particle_scaled_run_x files)';
            app.ClicktoimportcentroidsButton = uibutton(app.GridLayout2, 'push');
            app.ClicktoimportcentroidsButton.ButtonPushedFcn = createCallbackFcn(app, @ClicktoimportcentroidsButtonPushed, true);
            app.ClicktoimportcentroidsButton.Text = 'Click to import centroids';
            app.NumberofnodesnaLabel = uilabel(app.GridLayout2);
            app.NumberofnodesnaLabel.Text = 'Number of nodes: n/a';
            app.CentroidrangenaLabel = uilabel(app.GridLayout2);
            app.CentroidrangenaLabel.Text = 'Centroid range: n/a';
            app.DimensionnaLabel = uilabel(app.GridLayout2);
            app.DimensionnaLabel.Text = 'Dimension: n/a';
            app.VisualizecentroidsButton = uibutton(app.GridLayout2, 'push');
            app.VisualizecentroidsButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizecentroidsButtonPushed, true);
            app.VisualizecentroidsButton.Text = 'Visualize centroids';
            app.VisualizedistancemapanditsdistributionCheckBox = uicheckbox(app.GridLayout2);
            app.VisualizedistancemapanditsdistributionCheckBox.Tooltip = {'Helps choosing relevant node and chord radius'};
            app.VisualizedistancemapanditsdistributionCheckBox.Text = 'Visualize distance map and its distribution';
            app.NodataimportedLabel = uilabel(app.GridLayout2);
            app.NodataimportedLabel.Text = 'No data imported';
            app.ClicktoselectsavefolderButton_ = uibutton(app.GridLayout2, 'push');
            app.ClicktoselectsavefolderButton_.ButtonPushedFcn = createCallbackFcn(app, @ClicktoselectsavefolderButton_Pushed, true);
            app.ClicktoselectsavefolderButton_.Text = 'Click to select save folder';
            app.ImportcentroidsLabel = uilabel(app.GridLayout2);
            app.ImportcentroidsLabel.Text = '1) Import centroids';
            app.SavingoptionsLabel = uilabel(app.GridLayout2);
            app.SavingoptionsLabel.Text = '2) Saving options';
            app.NosavefolderselectedLabel = uilabel(app.GridLayout2);
            app.NosavefolderselectedLabel.Text = 'No save folder selected';
            app.FilenameEditFieldLabel = uilabel(app.GridLayout2);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.FilenameEditField = uieditfield(app.GridLayout2, 'text');
            app.ParametersTab = uitab(app.TabGroup);
            app.ParametersTab.Title = 'Parameters';
            app.GridLayout_Parameter = uigridlayout(app.ParametersTab);
            app.ParametersLabel = uilabel(app.GridLayout_Parameter);
            app.ParametersLabel.Text = 'Parameters';
            app.FieldofviewFOVLabel = uilabel(app.GridLayout_Parameter);
            app.FieldofviewFOVLabel.Text = '1) Field of view (FOV):';
            app.RecenterCheckBox = uicheckbox(app.GridLayout_Parameter);
            app.RecenterCheckBox.Tooltip = {'FOV is cropped to the min-max coordinates of the centroids.'};
            app.RecenterCheckBox.Text = 'Re-center';
            app.ExtendFOVEditFieldLabel = uilabel(app.GridLayout_Parameter);
            app.ExtendFOVEditFieldLabel.Text = 'Extend FOV';
            app.ExtendFOVEditField = uieditfield(app.GridLayout_Parameter, 'numeric');
            app.ExtendFOVEditField.Limits = [0 Inf];
            app.ExtendFOVEditField.RoundFractionalValues = 'on';
            app.ExtendFOVEditField.Tooltip = {'Ignored if set to 0. Otherwise, extend FOV in both directions (applied after re-center, if used).'};
            app.DistributionsLabel = uilabel(app.GridLayout_Parameter);
            app.DistributionsLabel.Text = '2) Coordination number (number of connection or chords per nodes), chord radius, node radius';
            app.Distribution_parameters_Table = uitable(app.GridLayout_Parameter);
            app.Distribution_parameters_Table.ColumnName = {'Parameter'; 'Unit'; 'Range'; 'Mean'; 'Standard deviation'; 'Max deviation'; 'Equiprobable'};
            app.Distribution_parameters_Table.RowName = {};
            app.Distribution_parameters_Table.ColumnEditable = [false false false true true true true];
            app.NoderadiusDropDownLabel = uilabel(app.GridLayout_Parameter);
            app.NoderadiusDropDownLabel.Text = 'Node radius';
            app.NoderadiusDropDown = uidropdown(app.GridLayout_Parameter);
            app.NoderadiusDropDown.Items = {'Controlled with a distribution', 'Scaled with chord radius', 'Scaled with number of connection'};
            app.NoderadiusDropDown.ValueChangedFcn = createCallbackFcn(app, @NoderadiusDropDownValueChanged, true);
            app.NoderadiusDropDown.Value = 'Controlled with a distribution';
            app.DistributionNoderadius_parameters_Table = uitable(app.GridLayout_Parameter);
            app.DistributionNoderadius_parameters_Table.ColumnName = {'Parameter'; 'Unit'; 'Range'; 'Mean'; 'Standard deviation'; 'Max deviation'; 'Equiprobable'};
            app.DistributionNoderadius_parameters_Table.RowName = {};
            app.DistributionNoderadius_parameters_Table.ColumnEditable = [false false false true true true true];
            app.ScalingfactorEditFieldLabel = uilabel(app.GridLayout_Parameter);
            app.ScalingfactorEditFieldLabel.Text = 'Scaling factor';
            app.ScalingfactorEditField = uieditfield(app.GridLayout_Parameter, 'numeric');
            app.ScalingfactorEditField.Limits = [0 Inf];
            app.ScalingfactorEditField.Value = 1;
            app.aEditFieldLabel = uilabel(app.GridLayout_Parameter);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.GridLayout_Parameter, 'numeric');
            app.aEditField.Limits = [0 Inf];
            app.aEditField.Value = 2;
            app.PlotdistributionsoptionalButton = uibutton(app.GridLayout_Parameter, 'push');
            app.PlotdistributionsoptionalButton.ButtonPushedFcn = createCallbackFcn(app, @PlotdistributionsoptionalButtonPushed, true);
            app.PlotdistributionsoptionalButton.Text = 'Plot distributions (optional)';
            app.NodechordjunctionLabel = uilabel(app.GridLayout_Parameter);
            app.NodechordjunctionLabel.Text = '4) Node chord junction';
            app.DilationiterationEditFieldLabel = uilabel(app.GridLayout_Parameter);
            app.DilationiterationEditFieldLabel.Text = 'Dilation iteration';
            app.DilationiterationEditField = uieditfield(app.GridLayout_Parameter, 'numeric');
            app.DilationiterationEditField.Limits = [0 Inf];
            app.DilationiterationEditField.RoundFractionalValues = 'on';
            app.DilationiterationEditField.Value = 30;
            app.MaxdistancebetweenconnectednodesLabel = uilabel(app.GridLayout_Parameter);
            app.MaxdistancebetweenconnectednodesLabel.Text = '3) Max distance between connected nodes';
            app.MaxdistanceEditFieldLabel = uilabel(app.GridLayout_Parameter);
            app.MaxdistanceEditFieldLabel.Text = 'Max distance ';
            app.MaxdistanceEditField = uieditfield(app.GridLayout_Parameter, 'numeric');
            app.MaxdistanceEditField.Limits = [1 Inf];
            app.MaxdistanceEditField.RoundFractionalValues = 'on';
            app.MaxdistanceEditField.Value = 60;
            app.ConvertinspheresCheckBox = uicheckbox(app.GridLayout_Parameter);
            app.ConvertinspheresCheckBox.Tooltip = {'If selected, another volume will be generated, using only juxtaposed spheres (for DEM models).'};
            app.ConvertinspheresCheckBox.Text = 'Convert in spheres';
            app.GenerationTab = uitab(app.TabGroup);
            app.GenerationTab.Title = 'Generation';
            app.GridLayout_generation = uigridlayout(app.GenerationTab);
            app.GenerationLabel = uilabel(app.GridLayout_generation);
            app.GenerationLabel.Text = 'Generation';
            app.RungenerationalgorithmButton = uibutton(app.GridLayout_generation, 'push');
            app.RungenerationalgorithmButton.ButtonPushedFcn = createCallbackFcn(app, @RungenerationalgorithmButtonPushed, true);
            app.RungenerationalgorithmButton.Text = 'Run generation algorithm';
            app.IdleLabel = uilabel(app.GridLayout_generation);
            app.IdleLabel.Text = 'Idle';
            app.VolumefractionEditFieldLabel = uilabel(app.GridLayout_generation);
            app.VolumefractionEditFieldLabel.Text = 'Volume fraction';
            app.VolumefractionEditField = uieditfield(app.GridLayout_generation, 'numeric');
            app.VolumefractionEditField.Editable = 'off';
            app.VisualizeButton = uibutton(app.GridLayout_generation, 'push');
            app.VisualizeButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizeButtonPushed, true);
            app.VisualizeButton.Text = 'Visualize';
            app.HowtoquoteTab = uitab(app.TabGroup);
            app.HowtoquoteTab.Title = 'How to quote';
            app.GridLayout4 = uigridlayout(app.HowtoquoteTab);
            app.Label_4 = uilabel(app.GridLayout4);
            app.Label_4.Text = 'If you use this algorithm or report results produced thanks to this algorithm, please quote it as follow:';
            app.Label_5 = uilabel(app.GridLayout4);
            app.Label_5.Text = {'F. L. E. Usseglio-Viretta, P. Patel, E. Bernhardt, A Mistry, P. P. Mukherjee, J. Allen, S. J. Cooper, J. Laurencin, and K. Smith'; 'MATBOX: An Open-source Microstructure Analysis Toolbox for microstructure generation, segmentation, characterization,'; 'visualization, correlation, and meshing, SoftwareX, 17, 2022'};
            app.Hyperlink = uihyperlink(app.GridLayout4);
            app.Hyperlink.URL = 'https://doi.org/10.1016/j.softx.2021.100915';
            app.Hyperlink.Text = 'https://doi.org/10.1016/j.softx.2021.100915';
            app.HowtoquoteLabel = uilabel(app.GridLayout4);
            app.HowtoquoteLabel.Text = 'How to quote';
            app.HelpTab = uitab(app.TabGroup);
            app.HelpTab.Title = 'Help';
            app.GridLayout5 = uigridlayout(app.HelpTab);
            app.HelpLabel = uilabel(app.GridLayout5);
            app.HelpLabel.Text = 'Help';
            app.Label_6 = uilabel(app.GridLayout5);
            app.Label_6.Text = {'If you run into problems, please read the open issues listed in the GitHub webpage. If your problem is not listed, please open'; 'a new issue.'};
            app.Hyperlink2 = uihyperlink(app.GridLayout5);
            app.Hyperlink2.URL = 'https://github.com/NREL/MATBOX_Microstructure_analysis_toolbox/issues';
            app.Hyperlink2.Text = 'https://github.com/NREL/MATBOX_Microstructure_analysis_toolbox/issues';
            app.AboutTab = uitab(app.TabGroup);
            app.AboutTab.Title = 'About';
            app.GridLayout6 = uigridlayout(app.AboutTab);
            app.AboutLabel = uilabel(app.GridLayout6);
            app.AboutLabel.Text = 'About';
            app.Label_8 = uilabel(app.GridLayout6);
            app.Label_8.Text = {'National Renewable Energy Laboratory (NREL) developed the Microstructure Analysis Toolbox (MATBOX) to analyze electrode'; 'microstructures for lithium-ion (Li-ion) batteries and other 3D heterogeneous materials.'};
            app.NREL_logo = uiimage(app.GridLayout6);
            app.NREL_logo.ImageClickedFcn = createCallbackFcn(app, @NREL_logoImageClicked, true);
            app.NREL_logo.ImageSource = 'logo_NREL.png';
            app.Hyperlink3 = uihyperlink(app.GridLayout6);
            app.Hyperlink3.URL = 'https://www.nrel.gov/transportation/matbox.html';
            app.Hyperlink3.Text = 'MATBOX official webpage';
            app.Hyperlink4 = uihyperlink(app.GridLayout6);
            app.Hyperlink4.URL = 'https://github.com/NREL/MATBOX_Microstructure_analysis_toolbox';
            app.Hyperlink4.Text = 'MATBOX GitHub repository';
            app.Label_9 = uilabel(app.GridLayout6);
            app.Label_9.Text = 'If you like this tool, please give it a ''Star'' in the Github repository to increase its visibility.';
            app.MATBOXinstalledversionLabel = uilabel(app.GridLayout6);
            app.MATBOXinstalledversionLabel.Text = 'MATBOX installed version:';
            app.OnlineversioncheckLabel = uilabel(app.GridLayout6);
            app.OnlineversioncheckLabel.Text = 'Online version check';
            app.Localversion = uilabel(app.GridLayout6);
            app.Localversion.Text = '1.1 February 2023';
            % Show the figure after all components are created
            app.WebmicrostructuregenerationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Microstructure_generation_web

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.WebmicrostructuregenerationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.WebmicrostructuregenerationUIFigure)
        end
    end
end