classdef Microstructure_library < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MicrostructureimagelibraryUIFigure  matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        InstructionsTab                 matlab.ui.container.Tab
        GridLayout_Instructions         matlab.ui.container.GridLayout
        YoucansetapredefinedlistoffolderstoexploreeditingLabel  matlab.ui.control.Label
        Label_10                        matlab.ui.control.Label
        YoucanalsoexportmetadatainanexcelfileLabel  matlab.ui.control.Label
        Label_9                         matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        InthisexamplethemetadataformatisasfollowsLabel  matlab.ui.control.Label
        ThepropertieswindowLabel        matlab.ui.control.Label
        TomakefulluseofthistoolyourfilesshouldhavemetadataLabel  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        ExploreimagelibraryTab          matlab.ui.container.Tab
        GridLayout_Explore              matlab.ui.container.GridLayout
        Metadata_ValueDropDown          matlab.ui.control.DropDown
        ValueDropDownLabel              matlab.ui.control.Label
        Metadata_ORButton               matlab.ui.control.Button
        ListfilterORButton              matlab.ui.control.Button
        RemovefilterButton              matlab.ui.control.Button
        CurrentfiltersEditField         matlab.ui.control.EditField
        CurrentfiltersEditFieldLabel    matlab.ui.control.Label
        IdleLabel                       matlab.ui.control.Label
        VisualizeorexportselectedfilesLabel  matlab.ui.control.Label
        ExportpreviewfilesalsoCheckBox  matlab.ui.control.CheckBox
        AddfilterButton                 matlab.ui.control.Button
        SetfiltersonthemetadatetoonlydisplaytheimagesofinterestLabel  matlab.ui.control.Label
        ExporttabletodestinationfolderButton  matlab.ui.control.Button
        CopyfilestodestinationfolderButton  matlab.ui.control.Button
        Library_destinationfolderabel   matlab.ui.control.Label
        ClicktosetdestinationfolderButton_2  matlab.ui.control.Button
        RemoveallfiltersButton          matlab.ui.control.Button
        MetadataDropDown_2              matlab.ui.control.DropDown
        MetadataDropDown_2Label         matlab.ui.control.Label
        PreviewfilesifanyCheckBox       matlab.ui.control.CheckBox
        VisualizeselectedfilesButton_2  matlab.ui.control.Button
        Explore_UITable                 matlab.ui.control.Table
        SearchButton                    matlab.ui.control.Button
        Folder_UITable                  matlab.ui.control.Table
        ClicktoremoveallfoldersButton   matlab.ui.control.Button
        ClicktoaddonefolderButton       matlab.ui.control.Button
        SelectfolderwheretosearchtifandtifffilesLabel  matlab.ui.control.Label
        AddmodifymetadataTab            matlab.ui.container.Tab
        GridLayout_Addmetadata          matlab.ui.container.GridLayout
        AutoassignButton                matlab.ui.control.Button
        MetadatawillbeupdatedinexploreronlyaftertheappisclosedLabel  matlab.ui.control.Label
        PredefinedchoiceDropDown        matlab.ui.control.DropDown
        PredefinedchoiceDropDownLabel   matlab.ui.control.Label
        DonotusecommainopenchoicesectionLabel  matlab.ui.control.Label
        EraseallmetadataButton          matlab.ui.control.Button
        UnselectselectallButton         matlab.ui.control.Button
        ErasemetadataButton             matlab.ui.control.Button
        AddmodifymetadataButton         matlab.ui.control.Button
        ExportPanel                     matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        NumberofslicesforpreviewEditField  matlab.ui.control.NumericEditField
        NumberofslicesforpreviewEditFieldLabel  matlab.ui.control.Label
        CreatepreviewfileCheckBox       matlab.ui.control.CheckBox
        SavemetadataButton              matlab.ui.control.Button
        CopytodestinationfolderButton   matlab.ui.control.Button
        CurrentmetadatavalueLabel       matlab.ui.control.Label
        Metadat_explanation_TextArea    matlab.ui.control.TextArea
        OpenchoiceEditField             matlab.ui.control.EditField
        OpenchoiceEditFieldLabel        matlab.ui.control.Label
        BooleanchoiceLabel              matlab.ui.control.Label
        TrueorFalseCheckBox             matlab.ui.control.CheckBox
        MetadataDropDown                matlab.ui.control.DropDown
        MetadataDropDownLabel           matlab.ui.control.Label
        VisualizeselectedfilesButton    matlab.ui.control.Button
        Destinationfolder_Label         matlab.ui.control.Label
        ClicktosetdestinationfolderButton  matlab.ui.control.Button
        Label2                          matlab.ui.control.Label
        AddMetadata_UITable             matlab.ui.control.Table
        Tifffolder_Label                matlab.ui.control.Label
        ClicktoselectfolderButton       matlab.ui.control.Button
    end

    
    properties (Access = private)
        metadata = [];
        
        % Metadata
        tifffolder = [];
        destinationfolder = [];
        tiffdata = [];
        tag = [];
        paths = [];
        importfolder = [];
        dimension = [];

        % Explore library
        foldernames = {};
        subfolder = {};
        Filefound = {};
        Previewfound = {};
        FolderSelect = {};
        meta_allvals = {};
        baselinetable = [];
        filters = {};
        filterrule = [];
    end
    
    methods (Access = private)
        
        function [] = Update_existing_metadata_label(app)
            s_nometadata = uistyle("BackgroundColor",[0.96 0.96 0.96]);
            s_metadata = uistyle("BackgroundColor",[0.3010 0.7450 0.9330]);

            existing_val = [];
            if ~isempty(app.AddMetadata_UITable.Data)
                selected = find(cell2mat(app.AddMetadata_UITable.Data(:,3).Variables));
                if length(selected)==1
                    if isfield(app.tag(selected).val, app.MetadataDropDown.Value)
                        existing_val = app.tag(selected).val.(app.MetadataDropDown.Value);
                        if isstr(existing_val)
                            app.CurrentmetadatavalueLabel.Text = ['Current metadata value: ' existing_val];
                        else
                            app.CurrentmetadatavalueLabel.Text = ['Current metadata value: ' num2str(existing_val)];
                        end
                    else
                        app.CurrentmetadatavalueLabel.Text = 'No metadata value';                        
                    end

                    for k=1:1:length(app.MetadataDropDown.Items)
                        if isfield(app.tag(selected).val, app.MetadataDropDown.Items(k))
                            addStyle(app.MetadataDropDown,s_metadata,"item",k);
                        else
                            addStyle(app.MetadataDropDown,s_nometadata,"item",k);
                        end
                    end                    

                else
                    app.CurrentmetadatavalueLabel.Text = 'Current metadata value: n/a (multiple selections)';
                    for k=1:1:length(app.MetadataDropDown.Items)
                        addStyle(app.MetadataDropDown,s_nometadata,"item",k);
                    end
                end

                n = length(app.AddMetadata_UITable.Data(:,1).Variables);
                listtMetadata = app.MetadataDropDown.Items;
                nmeta = zeros(n,1);
                for idx=1:1:n
                    for k = 1:1:length(listtMetadata)
                        currentMetadata = listtMetadata(k);
                        if isfield(app.tag(idx).val,currentMetadata)
                            nmeta(idx) = nmeta(idx)+1;
                        end
                    end
                end
                app.AddMetadata_UITable.Data(:,2).Variables = num2cell(nmeta);

            else
                app.CurrentmetadatavalueLabel.Text = 'Current metadata value: n/a (no file selected)';
                for k=1:1:length(app.MetadataDropDown.Items)
                    addStyle(app.MetadataDropDown,s_nometadata,"item",k);
                end
            end
            app.CurrentmetadatavalueLabel.Tooltip = app.CurrentmetadatavalueLabel.Text;           
        end
        
        function [] = Update_exploretable_withfilter(app)
            
            varnames = app.Explore_UITable.ColumnName;

            % AND/OR filter within a feature
            selected_index_features = [];
            [nfile,~] = size(app.baselinetable);
            n = length(app.MetadataDropDown_2.Items);
            nocondition = false;
            for k=1:1:n
                selected_index = [];
                listfilters = app.filters(k).list;
                if ~isempty(listfilters)
                    allfilters = strsplit(listfilters,',');
                    allfilters = erase(allfilters,' ');
                    selected_metadata = char(app.MetadataDropDown_2.Items(k));
                    col = find(ismember(varnames,selected_metadata));
                    vals = app.baselinetable(:,col).Variables;
                    for kf=1:1:nfile
                        substrings = strsplit(char(vals(kf)), ',');
                        substrings = erase(substrings,' ');
                        if app.filterrule(k).and % AND
                            if sum(double(ismember(allfilters,substrings))) == length(allfilters)
                                selected_index = [selected_index; kf];
                            end
                        else % OR
                            if sum(double(ismember(allfilters,substrings))) >= 1
                                selected_index = [selected_index; kf];
                            end
                        end
                    end
                    if isempty(selected_index)
                        nocondition = true;
                    end
                end
                selected_index_features(k).idx = selected_index;
            end
           
            if nocondition && strcmp(app.Metadata_ORButton.Text,'Metadata: AND')
                idx2 = [];
            else
                idx2 = [];
                for k=1:1:n
                    if ~isempty(selected_index_features(k).idx)
                        if isempty(idx2)
                            idx2 = selected_index_features(k).idx;
                        else
                            if strcmp(app.Metadata_ORButton.Text,'Metadata: AND')
                                idx2 = idx2(ismember(idx2,selected_index_features(k).idx));
                            elseif strcmp(app.Metadata_ORButton.Text,'Metadata: OR')
                                idx2 = [idx2; selected_index_features(k).idx];
                            end
                        end

                    end
                end
            end

            if strcmp(app.Metadata_ORButton.Text,'Metadata: OR')
                idx2 = unique(idx2);
            end

            if isempty(idx2)
                app.Explore_UITable.Data = app.baselinetable;
            else
                app.Explore_UITable.Data = app.baselinetable(idx2,:);
            end


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Dimension
            k=1; % Read-only
            app.metadata(k).name = 'Dimension';
            app.metadata(k).choice = 'Predefined';
            app.metadata(k).options = {'2D','3D'};    
            app.metadata(k).description = 'Read-only.';

            % Size
            k=2; % Read-only
            app.metadata(k).name = 'Size';
            app.metadata(k).choice = 'Open';
            app.metadata(k).description = 'Read-only.';            

            % Number of voxels
            k=3; % Read-only
            app.metadata(k).name = 'Voxels';
            app.metadata(k).choice = 'Open';
            app.metadata(k).description = 'Read-only.';        

            % Read metadata list
            fileID = fopen('Metadata_list.txt', 'r');
            fileContent = fscanf(fileID, '%c');
            fclose(fileID);
            eval(fileContent);

            % Read folder list
            app.SearchButton.Enable = 'off';  
            fileID = fopen('Library_folders.txt', 'r');
            if fileID ~= -1
                fileContent = fscanf(fileID, '%c');
                fclose(fileID);
                idx = strfind(fileContent,':');
                if ~isempty(idx)
                    nfolder = length(idx);
                    if nfolder == 1
                        folder(1).name = fileContent;
                    else
                        for f=1:1:nfolder-1
                            folder(f).name = fileContent(idx(f)-1:idx(f+1)-4);
                        end
                        folder(nfolder).name = fileContent(idx(nfolder)-1:end);
                    end

                    n=0;
                    for f=1:1:nfolder
                        n=n+1;
                        app.foldernames(n,1) = {folder(f).name};
                        app.subfolder(n,1) = {true};
                        app.Filefound(n,1) = {0};
                        app.Previewfound(n,1) = {0};
                        app.FolderSelect(n,1) = {true};
                    end
                    app.Folder_UITable.Data=table(app.foldernames,app.subfolder,app.Filefound,app.Previewfound,app.FolderSelect);
                    app.SearchButton.Enable = 'on';
                end            
            end
                 
            listmetadata = {};
            for k=1:1:length(app.metadata)
                listmetadata(k) = {app.metadata(k).name};
            end

            app.MetadataDropDown.Items = listmetadata;
            app.MetadataDropDownValueChanged;

            app.destinationfolder=pwd;
            app.Destinationfolder_Label.Text = app.destinationfolder;  

            app.VisualizeselectedfilesButton.Enable = 'off';
            app.ErasemetadataButton.Enable = 'off';
            app.EraseallmetadataButton.Enable = 'off';
            app.AddmodifymetadataButton.Enable = 'off';
            app.CopytodestinationfolderButton.Enable = 'off';
            app.SavemetadataButton.Enable = 'off';
            app.UnselectselectallButton.Enable = 'off';   

            app.AutoassignButton.Enable = 'off';

            app.Library_destinationfolderabel.Text = pwd;


        end

        % Value changed function: MetadataDropDown
        function MetadataDropDownValueChanged(app, event)
            ValueIndex = app.MetadataDropDown.ValueIndex;

            app.Update_existing_metadata_label;

            if strcmp(app.metadata(ValueIndex).choice,'boolean')
                app.TrueorFalseCheckBox.Enable = 'on';
                app.PredefinedchoiceDropDown.Enable = 'off'; app.PredefinedchoiceDropDown.Items = {};
                app.OpenchoiceEditField.Enable = 'off'; app.OpenchoiceEditField.Value = '';
            elseif strcmp(app.metadata(ValueIndex).choice,'Predefined')
                if ValueIndex==1 % Dimension, read-only
                    app.TrueorFalseCheckBox.Enable = 'off';
                    app.PredefinedchoiceDropDown.Enable = 'off'; app.PredefinedchoiceDropDown.Items = app.metadata(ValueIndex).options;
                    app.OpenchoiceEditField.Enable = 'off'; app.OpenchoiceEditField.Value = '';
                else
                    app.TrueorFalseCheckBox.Enable = 'off';
                    app.PredefinedchoiceDropDown.Enable = 'on'; app.PredefinedchoiceDropDown.Items = app.metadata(ValueIndex).options;
                    app.OpenchoiceEditField.Enable = 'off'; app.OpenchoiceEditField.Value = '';
                end
            elseif strcmp(app.metadata(ValueIndex).choice,'Open')
                if ValueIndex==2 || ValueIndex==3 % Dimension, read-only
                    app.TrueorFalseCheckBox.Enable = 'off';
                    app.PredefinedchoiceDropDown.Enable = 'off'; app.PredefinedchoiceDropDown.Items = {};
                    app.OpenchoiceEditField.Enable = 'off';
                    app.OpenchoiceEditField.Value = '';
                else
                    app.TrueorFalseCheckBox.Enable = 'off';
                    app.PredefinedchoiceDropDown.Enable = 'off'; app.PredefinedchoiceDropDown.Items = {};
                    app.OpenchoiceEditField.Enable = 'on';
                    app.OpenchoiceEditField.Value = '';
                end
            end

            
            if strcmp(app.MetadataDropDown.Value,'Porosity') && istable(app.AddMetadata_UITable.Data)
                app.AutoassignButton.Enable = 'on';
            else
                app.AutoassignButton.Enable = 'off';
            end

            if ~isempty(app.AddMetadata_UITable.Data)
                % if ValueIndex==1 || ValueIndex==2 || ValueIndex==3 % Dimension
                %     app.ErasemetadataButton.Enable = 'off';
                %     app.AddmodifymetadataButton.Enable = 'off';
                % else
                %     app.ErasemetadataButton.Enable = 'on';
                %     app.AddmodifymetadataButton.Enable = 'on';
                % end
                app.ErasemetadataButton.Enable = 'on';
                app.AddmodifymetadataButton.Enable = 'on';                
            end

            app.Metadat_explanation_TextArea.Value = app.metadata(ValueIndex).description; 
        end

        % Button pushed function: ClicktoselectfolderButton
        function ClicktoselectfolderButtonPushed(app, event)
            % Reset
            app.tag = [];
            app.tiffdata = [];
            app.paths = [];
            app.dimension = [];
            
            selpath = uigetdir(app.Tifffolder_Label.Text,'Select folder that contains tif or tiff file(s)');
            if selpath~=0
                app.tifffolder=selpath;

                str = fullfile(app.tifffolder,'*.tif');
                filestmp = dir(str);
                kk=0;
                for k=1:1:length(filestmp)
                    TF = contains(filestmp(k).name, 'PREVIEW');
                    if ~TF
                        kk=kk+1;
                        files1(kk) = filestmp(k);
                    end
                end

                str2 = fullfile(app.tifffolder,'*.tiff');
                filestmp = dir(str2);
                kk=0;
                for k=1:1:length(filestmp)
                    TF = contains(filestmp(k).name, 'PREVIEW');
                    if ~TF
                        kk=kk+1;
                        files2(kk) = filestmp(k);
                    end
                end

                if exist('files1') && exist('files2')
                    files = [files1 files2];
                elseif exist('files1')
                    files = files1;
                elseif exist('files2')
                    files = files2;
                else
                    files = [];
                end
                n = length(files);

                app.Tifffolder_Label.Text = 'Please wait...'; pause(0.01);

                if n > 0
                    names = cell(n,1);
                    nmeta = cell(n,1);
                    selected = cell(n,1);
                    app.dimension = cell(n,1);

                    for k=1:1:n
                        names(k) = {files(k).name};
                        app.paths(k).str = fullfile(files(k).folder, files(k).name);
                        app.tiffdata(k).t = Tiff(app.paths(k).str, 'r+');
                        InfoImage=imfinfo(app.paths(k).str);
                        x = getTag(app.tiffdata(k).t,'ImageLength');
                        y = getTag(app.tiffdata(k).t,'ImageWidth');

                        try
                           tagval = getTag(app.tiffdata(k).t,'ImageDescription');
                           tagval = jsondecode(tagval);
                           if length(fieldnames(tagval)) >= 3
                                app.tag(k).val = tagval;
                                nmeta(k) = {length(fieldnames(tagval))};          
                           else
                                if length(InfoImage)==1
                                    app.tag(k).val.Dimension= '2D';
                                    app.tag(k).val.Size = [num2str(x) ' x ' num2str(y)];
                                    app.tag(k).val.Voxels = x * y;
                                    app.dimension(k) = {'2D'};
                                else
                                    app.tag(k).val.Dimension = '3D';
                                    z = length(InfoImage);
                                    app.tag(k).val.Size = [num2str(x) ' x ' num2str(y) ' x '  num2str(z)];
                                    app.tag(k).val.Voxels = x * y * z;
                                    app.dimension(k) = {'3D'};
                                end
                                nmeta(k) = {3};     
                           end
                        catch ME
                           if (strcmp(ME.identifier,'MATLAB:imagesci:Tiff:tagRetrievalFailed'))
                                if length(InfoImage)==1
                                    app.tag(k).val.Dimension= '2D';
                                    app.tag(k).val.Size = [num2str(x) ' x ' num2str(y)];
                                    app.tag(k).val.Voxels = x * y;
                                    app.dimension(k) = {'2D'};
                                else
                                    app.tag(k).val.Dimension = '3D';
                                    z = length(InfoImage);
                                    app.tag(k).val.Size = [num2str(x) ' x ' num2str(y) ' x '  num2str(z)];
                                    app.tag(k).val.Voxels = x * y * z;
                                    app.dimension(k) = {'3D'};
                                end
                                nmeta(k) = {3};
                           end
                        end

                        selected(k) = {true};
                    end

                    % for k2=1:1:n2
                    %     names(k1+k2) = {files2(k2).name};
                    %     app.paths(k1+k2).str = fullfile(files2(k2).folder, files2(k2).name);
                    %     app.tiffdata(k1+k2).t = Tiff(app.paths(k1+k2).str, 'r+');
                    %     InfoImage=imfinfo(app.paths(k1+k2).str);
                    %     x = num2str(getTag(app.tiffdata(k1+k2).t,'ImageLength'));
                    %     y = num2str(getTag(app.tiffdata(k1+k2).t,'ImageWidth'));                        
                    % 
                    %     try
                    %        tagval = getTag(app.tiffdata(k1+k2).t,'ImageDescription');
                    %        tagval = jsondecode(tagval);
                    %        app.tag(k1+k2).val = tagval;
                    %        nmeta(k1+k2) = {length(fieldnames(tagval))};                            
                    %     catch ME
                    %        if (strcmp(ME.identifier,'MATLAB:imagesci:Tiff:tagRetrievalFailed'))
                    %             if length(InfoImage)==1
                    %                 app.tag(k1+k2).val.Dimension= '2D';
                    %                 app.tag(k1+k2).val.Size = [x ' x ' y];
                    %             else
                    %                 app.tag(k1+k2).val.Dimension = '3D';
                    %                 z = num2str(length(InfoImage));
                    %                 app.tag(k1+k2).val.Size = [x ' x ' y ' x ' z];                                    
                    %             end   
                    %             nmeta(k1+k2) = {1};
                    %        end
                    %     end
                    % 
                    %     selected(k1+k2) = {true};
                    % end 
                    
                    app.AddMetadata_UITable.Data=table(names,nmeta,selected);

                    app.Tifffolder_Label.Text = app.tifffolder;
                    app.Tifffolder_Label.Tooltip = app.Tifffolder_Label.Text;

                else
                    app.Tifffolder_Label.Text = 'No tif or tiff files in this folder';
                end

                app.VisualizeselectedfilesButton.Enable = 'on';
                app.ErasemetadataButton.Enable = 'on';
                app.EraseallmetadataButton.Enable = 'on';
                app.AddmodifymetadataButton.Enable = 'on';
                app.CopytodestinationfolderButton.Enable = 'on';
                app.SavemetadataButton.Enable = 'on';
                app.UnselectselectallButton.Enable = 'on';

                app.MetadataDropDownValueChanged;

            else
                app.Tifffolder_Label.Text = 'None selected';
                app.AddMetadata_UITable.Data = [];

                app.VisualizeselectedfilesButton.Enable = 'off';
                app.ErasemetadataButton.Enable = 'off';
                app.EraseallmetadataButton.Enable = 'off';
                app.AddmodifymetadataButton.Enable = 'off';
                app.CopytodestinationfolderButton.Enable = 'off';
                app.SavemetadataButton.Enable = 'off';
                app.UnselectselectallButton.Enable = 'off';
            end

            if strcmp(app.Destinationfolder_Label.Text, app.Tifffolder_Label.Text)
                app.CopytodestinationfolderButton.Enable = 'off';
            else
                app.CopytodestinationfolderButton.Enable = 'on';
            end            
        end

        % Button pushed function: ClicktosetdestinationfolderButton
        function ClicktosetdestinationfolderButtonPushed(app, event)
            selpath = uigetdir(app.Destinationfolder_Label.Text,'Select destination folder');
            if selpath~=0
                app.destinationfolder=selpath;
                app.Destinationfolder_Label.Text = app.destinationfolder;    
                app.Destinationfolder_Label.Tooltip = app.Destinationfolder_Label.Text;
            end

            if strcmp(app.Destinationfolder_Label.Text, app.Tifffolder_Label.Text)
                app.CopytodestinationfolderButton.Enable = 'off';
            else
                app.CopytodestinationfolderButton.Enable = 'on';
            end
        end

        % Button pushed function: VisualizeselectedfilesButton
        function VisualizeselectedfilesButtonPushed(app, event)
            % Build the string require to call the visualization function
            str = 'Visualization_multiple_volumes(p,';
            selected = find(cell2mat(app.AddMetadata_UITable.Data(:,3).Variables));
            first_volume = true;
            for k=1:1:length(selected)
                idx = selected(k);
                % import
                vol(k).array = function_load_tif(app.tiffdata(idx).t.FileName);

                app.tiffdata(idx).t
                if first_volume
                    str = [str 'vol(' num2str(k) ').array'];
                    first_volume = false;
                else
                    str = [str ',vol(' num2str(k) ').array'];
                end
                p(k).name = char(app.AddMetadata_UITable.Data(idx,1).Variables);
            end
            str = [str ');'];
            eval(str);
        end

        % Button pushed function: ErasemetadataButton
        function ErasemetadataButtonPushed(app, event)
            selected = find(cell2mat(app.AddMetadata_UITable.Data(:,3).Variables));
            currentMetadata = app.MetadataDropDown.Value;
            for k=1:1:length(selected)
                idx = selected(k);
                if isfield(app.tag(idx).val,currentMetadata)
                     app.tag(idx).val = rmfield( app.tag(idx).val, currentMetadata);
                end
            end
            app.Update_existing_metadata_label;
        end

        % Button pushed function: AddmodifymetadataButton
        function AddmodifymetadataButtonPushed(app, event)
            selected = find(cell2mat(app.AddMetadata_UITable.Data(:,3).Variables));
            if ~isempty(selected)
                currentMetadata = app.MetadataDropDown.Value;
                if app.TrueorFalseCheckBox.Enable
                    MetadataVal = app.TrueorFalseCheckBox.Value;
                elseif app.PredefinedchoiceDropDown.Enable
                    MetadataVal = app.PredefinedchoiceDropDown.Value;
                elseif app.OpenchoiceEditField.Enable
                    MetadataVal = app.OpenchoiceEditField.Value;
                    if isempty(MetadataVal)
                        return
                    end
                end

                MetadataVal_string = MetadataVal;
                if islogical(MetadataVal)
                    if MetadataVal
                        MetadataVal_string = 'True';
                    else
                        MetadataVal_string = 'False';
                    end
                end

                for k=1:1:length(selected)
                    idx = selected(k);
                    if isfield(app.tag(idx).val,currentMetadata) && ~app.TrueorFalseCheckBox.Enable
                        app.tag(idx).val.(currentMetadata) = [app.tag(idx).val.(currentMetadata) ', ' MetadataVal_string];
                    else
                        app.tag(idx).val.(currentMetadata) = MetadataVal_string;
                    end
                end

            end
            app.Update_existing_metadata_label;

        end

        % Button pushed function: CopytodestinationfolderButton
        function CopytodestinationfolderButtonPushed(app, event)
            app.CopytodestinationfolderButton.Text = 'Please wait...'; pause(0.01);
            selected = find(cell2mat(app.AddMetadata_UITable.Data(:,3).Variables));
            for k=1:1:length(selected)
                idx = selected(k);

                [filepath,name,ext] = fileparts(app.paths(idx).str);
                destinationpath = fullfile(app.destinationfolder,[name ext]);
                copyfile(app.paths(idx).str,destinationpath);

                previewfile = fullfile(filepath,[name '_PREVIEW' ext]);
                if isfile(previewfile)
                    destinationpath = fullfile(app.destinationfolder,[name '_PREVIEW' ext]);
                    copyfile(previewfile,destinationpath);
                end
            end
            app.CopytodestinationfolderButton.Text = 'Copy to destination folder';
        end

        % Cell edit callback: AddMetadata_UITable
        function AddMetadata_UITableCellEdit(app, event)
            selected = find(cell2mat(app.AddMetadata_UITable.Data(:,3).Variables));
            if ~isempty(selected)
                app.VisualizeselectedfilesButton.Enable = 'on';
                app.ErasemetadataButton.Enable = 'on';
                app.AddmodifymetadataButton.Enable = 'on';
                app.CopytodestinationfolderButton.Enable = 'on';
                app.SavemetadataButton.Enable = 'on';

                app.MetadataDropDown.Enable = 'on';
                app.MetadataDropDownValueChanged;
            else
                app.VisualizeselectedfilesButton.Enable = 'off';
                app.ErasemetadataButton.Enable = 'off';
                app.AddmodifymetadataButton.Enable = 'off';
                app.CopytodestinationfolderButton.Enable = 'off';
                app.SavemetadataButton.Enable = 'off';

                app.MetadataDropDown.Enable = 'off';
                app.TrueorFalseCheckBox.Enable = 'off';
                app.PredefinedchoiceDropDown.Enable = 'off';
                app.OpenchoiceEditField.Enable = 'off';

                app.CurrentmetadatavalueLabel.Text = 'Current metadata value: n/a (no file selected)';
            end
            app.Update_existing_metadata_label;
            
        end

        % Button pushed function: SavemetadataButton
        function SavemetadataButtonPushed(app, event)
            app.SavemetadataButton.Text = 'Please wait...'; pause(0.01);
            
            selected = find(cell2mat(app.AddMetadata_UITable.Data(:,3).Variables));
            for k=1:1:length(selected)
                idx = selected(k);
                setTag(app.tiffdata(idx).t,'ImageDescription', jsonencode(app.tag(idx).val));
                close(app.tiffdata(idx).t);

                % Preview
                if app.CreatepreviewfileCheckBox.Value
                    is_3D = false;

                    TF = isfield(app.tag(idx).val,'Dimension');
                    if TF
                        if strcmp(app.tag(idx).val.Dimension,'3D')
                            is_3D = true;
                        end
                    else
                        if strcmp(char(app.dimension(idx)),'3D')
                            is_3D = true;
                        end
                    end

                    if is_3D
                        M = function_load_tif( app.paths(idx).str,'uint32' );
                        sz = size(M);
                        nslice = app.NumberofslicesforpreviewEditField.Value;
                        if nslice==1
                            Mpreview = M(:,:,round(sz(3)/2));
                        else
                            z = round(linspace(1,sz(3),nslice));
                            Mpreview = zeros(sz(1),sz(2),nslice,'uint32');
                            for kz=1:1:nslice
                                Mpreview(:,:,kz) = M(:,:,z(kz));
                            end    
                        end
                        [filepath,name,ext] = fileparts(app.paths(idx).str);
                        pathpreview = fullfile(filepath,[name '_PREVIEW' ext]);
                        function_save_tif(Mpreview,pathpreview);
                        t_tmp = Tiff(pathpreview, 'r+');
                        setTag(t_tmp,'ImageDescription', jsonencode(app.tag(idx).val));
                        close(t_tmp); 
                    end

                end

            end            
            for k=1:1:length(selected)
                idx = selected(k);
                app.tiffdata(idx).t = Tiff(app.paths(idx).str, 'r+');
            end

            app.SavemetadataButton.Text = 'Save metadata';
        end

        % Button pushed function: UnselectselectallButton
        function UnselectselectallButtonPushed(app, event)
            selected = find(cell2mat(app.AddMetadata_UITable.Data(:,3).Variables));
            n = length(app.AddMetadata_UITable.Data(:,1).Variables);
            if isempty(selected)
                app.AddMetadata_UITable.Data(:,3).Variables = num2cell(logical(ones(n,1)));
            else
                app.AddMetadata_UITable.Data(:,3).Variables = num2cell(logical(zeros(n,1)));
            end
            app.AddMetadata_UITableCellEdit;
        end

        % Button pushed function: EraseallmetadataButton
        function EraseallmetadataButtonPushed(app, event)
            n = length(app.AddMetadata_UITable.Data(:,1).Variables);
            listtMetadata = app.MetadataDropDown.Items;
            for idx=1:1:n
                for k = 1:1:length(listtMetadata)
                    currentMetadata = listtMetadata(k);
                    if isfield(app.tag(idx).val,currentMetadata)
                        app.tag(idx).val = rmfield( app.tag(idx).val, currentMetadata);
                    end
                end
            end
            app.Update_existing_metadata_label;
        end

        % Button pushed function: ClicktoaddonefolderButton
        function ClicktoaddonefolderButtonPushed(app, event)
            if isempty(app.importfolder)
                selpath = uigetdir(pwd,'Select folder that contains tif or tiff file(s)');
            else
                selpath = uigetdir(app.importfolder,'Select folder that contains tif or tiff file(s)');
            end
            if selpath~=0
                app.importfolder = selpath;
                if isempty(app.Folder_UITable.Data)
                    n=0;
                else
                    n = length(app.foldernames);
                end
                app.foldernames(n+1,1) = {selpath};
                app.subfolder(n+1,1) = {true};
                app.Filefound(n+1,1) = {0};
                app.Previewfound(n+1,1) = {0};       
                app.FolderSelect(n+1,1) = {true};
                app.Folder_UITable.Data=table(app.foldernames,app.subfolder,app.Filefound,app.Previewfound,app.FolderSelect);
                app.SearchButton.Enable = 'on';
            end
        end

        % Button pushed function: ClicktoremoveallfoldersButton
        function ClicktoremoveallfoldersButtonPushed(app, event)
            app.foldernames = {};
            app.subfolder = {};
            app.Filefound = {};
            app.Previewfound = {};
            app.FolderSelect = {};
            app.Folder_UITable.Data = [];
            app.SearchButton.Enable = 'off';

            app.MetadataDropDown_2.Enable = 'off';
            app.MetadataDropDown_2Label.Enable = 'off';
            app.Metadata_ValueDropDown.Enable = 'off';
            app.ValueDropDownLabel.Enable = 'off';
            app.CurrentfiltersEditField.Enable = 'off';
            app.CurrentfiltersEditFieldLabel.Enable = 'off';
            app.AddfilterButton.Enable = 'on';
            app.RemovefilterButton.Enable = 'off';
            app.RemoveallfiltersButton.Enable = 'off';
            app.ExporttabletodestinationfolderButton.Enable = 'off';
            app.VisualizeselectedfilesButton_2.Enable = 'off';
            app.ListfilterORButton.Enable = 'off';
            app.Metadata_ORButton.Enable = 'off';
            app.ClicktosetdestinationfolderButton_2.Enable = 'off';
            app.CopyfilestodestinationfolderButton.Enable = 'off';
            app.AddfilterButton.Enable = 'off';
            app.Library_destinationfolderabel.Enable = 'off';
            app.ExportpreviewfilesalsoCheckBox.Enable = 'off';
            app.PreviewfilesifanyCheckBox.Enable = 'off';

            app.Explore_UITable.Data = [];
        end

        % Button pushed function: SearchButton
        function SearchButtonPushed(app, event)
                app.IdleLabel.Text = 'Please wait...'; pause(0.01);
                app.meta_allvals = {};
                app.filters = [];
                app.filterrule = [];

                n = length(app.foldernames);
                allfiles = [];
                for k=1:1:n
                    if cell2mat(app.Folder_UITable.Data(k,5).Variables)
                        includesubfolder =  cell2mat(app.Folder_UITable.Data(k,2).Variables);
                        strfolder = char(app.foldernames(k));
                        if includesubfolder
                            if ispc
                                str1 = [strfolder '\**\*.tiff'];
                                str2 = [strfolder '\**\*.tif'];
                            else
                                str1 = [strfolder '/**/*.tiff'];
                                str2 = [strfolder '/**/*.tif'];
                            end
                            files1 = dir(str1);
                            files2 = dir(str2);
                        else
                            str = fullfile(strfolder,'*.tiff');
                            files1 = dir(str);
                            str = fullfile(strfolder,'*.tif');
                            files2 = dir(str);
                        end

                        if exist('files1') && exist('files2')
                            files = [files1; files2];
                        elseif exist('files1')
                            files = files1;
                        elseif exist('files2')
                            files = files2;
                        else
                            files = [];
                        end

                        nfile = length(files);
                        app.Filefound(k) = {nfile};
                        app.Previewfound(k) = {0};
                        for f = 1:1:nfile
                            TF = contains(char(files(f).name), 'PREVIEW');
                            if TF
                                app.Filefound(k) =  {cell2mat(app.Filefound(k))-1};
                                app.Previewfound(k) =  {cell2mat(app.Previewfound(k))+1};
                            end
                        end
                        allfiles = [allfiles; files];
                    end

                end
                app.Folder_UITable.Data=table(app.foldernames,app.subfolder,app.Filefound,app.Previewfound,app.FolderSelect);

                % Associate full with preview
                nfiles = length(allfiles);
                
                allfiles_full = allfiles; idx_full_remove = [];
                allfiles_preview = allfiles; idx_preview_remove = [];
                k=0;
                for f=1:1:nfiles
                    TF = contains(char(allfiles(f).name), 'PREVIEW');
                    if TF
                        idx_full_remove = [idx_full_remove; f];
                    else
                        idx_preview_remove = [idx_preview_remove; f];
                    end
                end
                allfiles_full(idx_full_remove) = [];
                allfiles_preview(idx_preview_remove) = [];
                nfiles_full = length(allfiles_full);
                nfiles_preview = length(allfiles_preview);
                
                filename_full = cell(nfiles_full,1);
                location_full = cell(nfiles_full,1);
                haspreview_full = cell(nfiles_full,1);
                selected_full = cell(nfiles_full,1);
                preview_id = zeros(nfiles_full,1);
                for ff=1:1:nfiles_full
                    filename_full(ff) = {allfiles_full(ff).name};
                    location_full(ff) = {allfiles_full(ff).folder};
                    haspreview_full(ff) = {false};
                    selected_full(ff) = {true};
                end
              
                for fp=1:1:nfiles_preview
                    original_name = erase(allfiles_preview(fp).name,"_PREVIEW");
                    for ff=1:1:nfiles_full
                        if strcmp(original_name,allfiles_full(ff).name)
                            haspreview_full(ff) = {true};
                            preview_id(ff)=fp;
                            break
                        end
                    end
                end

                % Find metadata
                ti = [];
                meta = [];
                metadata_name = [];
                for ff=1:1:nfiles_full
                    if cell2mat(haspreview_full(ff)) % We will look at metadata of the preview file
                        p = fullfile(char(allfiles_preview(preview_id(ff)).folder), char(allfiles_preview(preview_id(ff)).name));
                    else
                        p = fullfile(char(allfiles_full(ff).folder), char(allfiles_full(ff).name));
                    end
                    ti(ff).val = Tiff(p, 'r+');
                  
                    try
                        tagval = getTag(ti(ff).val,'ImageDescription');
                        tagval = jsondecode(tagval);
                        meta(ff).val = tagval;
                        metadata_name = [metadata_name; fieldnames(meta(ff).val)];
                    catch ME
                        meta(ff).val = [];
                    end
                end

                if ~isempty(metadata_name)
                    metadata_name = unique(metadata_name);
                    app.MetadataDropDown_2.Items = metadata_name;

                    nm = length(metadata_name);
                    varNames = cell(1,4+nm);
                    varNames(1) = {'File'};
                    varNames(2) = {'Location'};
                    varNames(3) = {'Has preview'};
                    varNames(4) = {'Select'};
                    ColumnSortable = zeros(1,4+nm);
                    for km = 1:1:nm
                        varNames(4+km) = metadata_name(km);
                        ColumnSortable(4+km) = 1;
                    end

                    for km = 1:1:nm
                        if strcmp(app.ListfilterORButton.Text,'List filter: OR')
                            app.filterrule(km).and = false;
                        else
                            app.filterrule(km).and = true;
                        end
                    end

                    res = cell(nfiles_full,nm);
                    res_string = cell(nfiles_full,nm);
                    for ff=1:1:nfiles_full
                        for m=1:1:nm
                            try
                                res(ff,m) = {meta(ff).val.(char(metadata_name(m)))};
                                if isstr( meta(ff).val.(char(metadata_name(m))) )
                                    res_string(ff,m) = {meta(ff).val.(char(metadata_name(m)))};
                                else
                                    res_string(ff,m) = {num2str(meta(ff).val.(char(metadata_name(m))))};
                                end
                            catch ME
                                res(ff,m) = {'not specified'};
                                res_string(ff,m) = {'not specified'};
                            end

                        end
                    end    

                    C = cell(nfiles_full,4+nm);
                    C(:,1) = filename_full;
                    C(:,2) = location_full;
                    C(:,3) = haspreview_full;
                    C(:,4) = selected_full;
                    for k=1:1:nm
                        C(:,4+k) = res(:,k);
                        idx = find(~cellfun(@isempty,res_string(:,k)));
                        %app.meta_allvals(k).val = unique(res_string(idx,k));
                        tmp = unique(res_string(idx,k));
                        val = {};
                        iter = 0;
                        for ksub=1:1:length(tmp)
                            substrings = strsplit(char(tmp(ksub)), ',');
                            for kk=1:1:length(substrings)
                                iter = iter + 1;
                                val(iter,1) = substrings(kk);
                            end
                        end
                        app.meta_allvals(k).val = val;
                    end
                    T = cell2table(C);
                    T.Properties.VariableNames = varNames;
                    app.Explore_UITable.Data = T;
                    app.Explore_UITable.ColumnName = varNames;
                    app.Explore_UITable.ColumnSortable = logical(ColumnSortable);

                    % filters
                    for k=1:1:nm
                        app.filters(k).list  = [];
                    end
                    app.MetadataDropDown_2ValueChanged;

                else
                    app.MetadataDropDown_2.Items = {};
                    app.Metadata_ValueDropDown.Items = {};
                    app.AddfilterButton.Enable = 'off';
                    app.RemoveallfiltersButton.Enable = 'off';
                    varNames = {'File','Location','Has preview','Select'};
                    ColumnSortable = zeros(1,4);
                    app.Explore_UITable.Data = table(filename_full,location_full,haspreview_full,selected_full,'VariableNames',varNames);
                    app.Explore_UITable.ColumnName = varNames;
                    app.Explore_UITable.ColumnSortable = logical(ColumnSortable);
                end    

                app.baselinetable = app.Explore_UITable.Data;

                app.MetadataDropDown_2.Enable = 'on';
                app.MetadataDropDown_2Label.Enable = 'on';
                app.Metadata_ValueDropDown.Enable = 'on';
                app.ValueDropDownLabel.Enable = 'on';
                app.CurrentfiltersEditField.Enable = 'on';
                app.CurrentfiltersEditFieldLabel.Enable = 'on';
                app.AddfilterButton.Enable = 'on';
                app.RemovefilterButton.Enable = 'on';
                app.RemoveallfiltersButton.Enable = 'on';
                app.ExporttabletodestinationfolderButton.Enable = 'on';
                app.VisualizeselectedfilesButton_2.Enable = 'on';   
                app.ClicktosetdestinationfolderButton_2.Enable = 'on';
                app.CopyfilestodestinationfolderButton.Enable = 'on';     
                app.Library_destinationfolderabel.Enable = 'on';
                app.ExportpreviewfilesalsoCheckBox.Enable = 'on';
                app.PreviewfilesifanyCheckBox.Enable = 'on';
                app.Metadata_ORButton.Enable = 'on';
                app.ListfilterORButton.Enable = 'on';
                app.Metadata_ORButton.Enable = 'on';

                app.IdleLabel.Text = 'Idle';
        end

        % Value changed function: MetadataDropDown_2
        function MetadataDropDown_2ValueChanged(app, event)
            idx = app.MetadataDropDown_2.ValueIndex;
            app.Metadata_ValueDropDown.Items = app.meta_allvals(idx).val;
            if ~isempty(app.filters(idx).list)
                app.CurrentfiltersEditField.Value = app.filters(idx).list;
            else
                app.CurrentfiltersEditField.Value = 'None';
            end

            if app.filterrule(idx).and
                app.ListfilterORButton.Text = 'List filter: AND';
            else
                app.ListfilterORButton.Text = 'List filter: OR';
            end

        end

        % Button pushed function: AddfilterButton
        function AddfilterButtonPushed(app, event)
            %$selected_metadata = app.MetadataDropDown_2.Value;
            idx_metadata = app.MetadataDropDown_2.ValueIndex;
            selected_value = app.Metadata_ValueDropDown.Value;

            if ~isempty(idx_metadata)
                if ~isempty(app.filters(idx_metadata).list)
                    app.filters(idx_metadata).list = [app.filters(idx_metadata).list ', ' char(selected_value)];
                else
                    app.filters(idx_metadata).list = char(selected_value);
                end
                app.CurrentfiltersEditField.Value = app.filters(idx_metadata).list;

                app.Update_exploretable_withfilter;
            end
        end

        % Button pushed function: RemoveallfiltersButton
        function RemoveallfiltersButtonPushed(app, event)
            app.SearchButtonPushed;
        end

        % Button pushed function: VisualizeselectedfilesButton_2
        function VisualizeselectedfilesButton_2Pushed(app, event)
            % Build the string require to call the visualization function
            app.VisualizeselectedfilesButton_2.Text = 'Please wait...'; pause(0.01);

            str = 'Visualization_multiple_volumes(p,';
            if iscell(app.Explore_UITable.Data(:,4).Variables)
                selected = find(cell2mat(app.Explore_UITable.Data(:,4).Variables));
            else
                selected = find(app.Explore_UITable.Data(:,4).Variables);
            end
            first_volume = true;
            for k=1:1:length(selected)
                idx = selected(k);
                % import
                fullname = char(fullfile(app.Explore_UITable.Data(idx,2).Variables,app.Explore_UITable.Data(idx,1).Variables));
                [filepath,name,ext] = fileparts(fullname);
                previewname = fullfile(filepath,[name '_PREVIEW' ext]);

                if isfile(previewname) && app.PreviewfilesifanyCheckBox.Value
                    vol(k).array = function_load_tif(previewname,'uint32');
                else
                    vol(k).array = function_load_tif(fullname,'uint32');
                end              
                [vol(k).array] = fct_intconvert(vol(k).array);
               
                if first_volume
                    str = [str 'vol(' num2str(k) ').array'];
                    first_volume = false;
                else
                    str = [str ',vol(' num2str(k) ').array'];
                end
                p(k).name = char(app.Explore_UITable.Data(idx,1).Variables);
            end
            str = [str ');'];
            eval(str);
            app.VisualizeselectedfilesButton_2.Text = 'Visualize selected files';
        end

        % Button pushed function: ClicktosetdestinationfolderButton_2
        function ClicktosetdestinationfolderButton_2Pushed(app, event)
            selpath = uigetdir(app.Library_destinationfolderabel.Text,'Select destination folder');
            if selpath~=0
                app.Library_destinationfolderabel.Text = selpath;    
                app.Library_destinationfolderabel.Tooltip = app.Library_destinationfolderabel.Text;
            end
        end

        % Button pushed function: CopyfilestodestinationfolderButton
        function CopyfilestodestinationfolderButtonPushed(app, event)
            app.CopyfilestodestinationfolderButton.Text = 'Please wait...'; pause(0.01);

            if iscell(app.Explore_UITable.Data(:,4).Variables)
                selected = find(cell2mat(app.Explore_UITable.Data(:,4).Variables));
                haspreview = cell2mat(app.Explore_UITable.Data(:,3).Variables);
            else
                selected = find(app.Explore_UITable.Data(:,4).Variables);
                haspreview = app.Explore_UITable.Data(:,3).Variables;
            end

            for k=1:1:length(selected)
                idx = selected(k);

                path_source = char(fullfile(app.Explore_UITable.Data(idx,2).Variables,app.Explore_UITable.Data(idx,1).Variables));
                destination_source = char(fullfile(app.Library_destinationfolderabel.Text,app.Explore_UITable.Data(idx,1).Variables));
                copyfile(path_source,destination_source);

                if haspreview(idx) && app.ExportpreviewfilesalsoCheckBox.Value
                    [filepath,name,ext] = fileparts(path_source);
                    path_source = fullfile(filepath,[name '_PREVIEW' ext]);
                    destination_source = char(fullfile(app.Library_destinationfolderabel.Text,[name '_PREVIEW' ext]));
                    copyfile(path_source,destination_source);
                end
                
            end
            app.CopyfilestodestinationfolderButton.Text = 'Copy files to destination folder';
        end

        % Button pushed function: ExporttabletodestinationfolderButton
        function ExporttabletodestinationfolderButtonPushed(app, event)
            date = datetime('now','TimeZone','local','Format','d-MMM-y HH:mm:ss Z');
            filename = ['Metadata ' char(date)] ; % Filename without extension
            filename = 'Metadata';
            % Prepare the data
            DATA_writetable.sheet(1).name='Metadata';
            if iscell(app.Explore_UITable.Data(:,4).Variables)
                selected = find(cell2mat(app.Explore_UITable.Data(:,4).Variables));
            else
                selected = find(app.Explore_UITable.Data(:,4).Variables);
            end
            DATA_writetable.sheet(1).table=app.Explore_UITable.Data(selected,:);
            % Save function
            Function_Writetable(app.Library_destinationfolderabel.Text,filename,DATA_writetable)
        end

        % Cell edit callback: Folder_UITable
        function Folder_UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            if ~isempty(app.Folder_UITable.Data)
                if indices(2)==2                    
                    app.subfolder(indices(1)) = {newData};
                end
                if indices(2)==5                    
                    app.FolderSelect(indices(1)) = {newData};
                end                
            end       
        end

        % Button pushed function: RemovefilterButton
        function RemovefilterButtonPushed(app, event)
            idx_metadata = app.MetadataDropDown_2.ValueIndex;
            if ~isempty(idx_metadata)
                app.filters(idx_metadata).list = [];
                app.CurrentfiltersEditField.Value = 'None';
                app.Update_exploretable_withfilter;
            end
        end

        % Button pushed function: ListfilterORButton
        function ListfilterORButtonPushed(app, event)
            idx_metadata = app.MetadataDropDown_2.ValueIndex;
            if ~isempty(idx_metadata)
                if strcmp(app.ListfilterORButton.Text,'List filter: OR')
                    app.ListfilterORButton.Text = 'List filter: AND';
                    app.filterrule(idx_metadata).and = true;
                elseif strcmp(app.ListfilterORButton.Text,'List filter: AND')
                    app.ListfilterORButton.Text = 'List filter: OR';
                    app.filterrule(idx_metadata).and = false;
                end
                app.Update_exploretable_withfilter;
            end
        end

        % Button pushed function: Metadata_ORButton
        function Metadata_ORButtonPushed(app, event)
            if strcmp(app.Metadata_ORButton.Text,'Metadata: OR')
                app.Metadata_ORButton.Text = 'Metadata: AND';
            elseif strcmp(app.Metadata_ORButton.Text,'Metadata: AND')
                app.Metadata_ORButton.Text = 'Metadata: OR';
            end   
            app.Update_exploretable_withfilter;
        end

        % Button pushed function: AutoassignButton
        function AutoassignButtonPushed(app, event)
            
            app.AutoassignButton.Text = 'Please wait...'; pause(0.1);
            selected = cell2mat(app.AddMetadata_UITable.Data(:,end).Variables);
            currentMetadata = app.MetadataDropDown.Value;
            idx = find(selected);
            if ~isempty(idx)
                for k=1:length(idx)
                    M = function_load_tif(app.paths(idx(k)).str);
                    if strcmp(app.MetadataDropDown.Value,'Porosity')
                        porosity = sum(sum(sum(M==0)))/numel(M);
                        if porosity <0.1
                            MetadataVal_string = '0-10%';
                        elseif porosity >=0.1 && porosity <0.2
                            MetadataVal_string = '10-20%';
                        elseif porosity >=0.2 && porosity <0.3
                            MetadataVal_string = '20-30%';
                        elseif porosity >=0.3 && porosity <0.4
                            MetadataVal_string = '30-40%';
                        elseif porosity >=0.4 && porosity <0.5
                            MetadataVal_string = '40-50%';
                        elseif porosity >=0.5 && porosity <0.6
                            MetadataVal_string = '50%-60%';
                        elseif porosity >=0.6 && porosity <0.7
                            MetadataVal_string = '60%-70%';
                        elseif porosity >=0.7 && porosity <0.8
                            MetadataVal_string = '70%-80%';
                        elseif porosity >=0.8 && porosity <0.9
                            MetadataVal_string = '80%-90%';
                        elseif porosity >=0.9
                            MetadataVal_string = '90%-100%';
                        end                        
                        app.tag(idx(k)).val.(currentMetadata) = MetadataVal_string;
                    end
                end
            end
            app.AutoassignButton.Text = 'Auto-assign';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MicrostructureimagelibraryUIFigure = uifigure('Visible', 'off');
            app.MicrostructureimagelibraryUIFigure.Name = 'Microstructure image library';
            app.MicrostructureimagelibraryUIFigure.Icon = 'Icon_image_library.png';
            app.GridLayout = uigridlayout(app.MicrostructureimagelibraryUIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.InstructionsTab = uitab(app.TabGroup);
            app.InstructionsTab.Title = 'Instructions';
            app.GridLayout_Instructions = uigridlayout(app.InstructionsTab);
            app.Label = uilabel(app.GridLayout_Instructions);
            app.Label.Text = 'This module allows you to organize your multiple microstructure volumes in a library through the use of metadata';
            app.Label_2 = uilabel(app.GridLayout_Instructions);
            app.Label_2.Text = '{"Dimension":"3D","Size":"321 x 325 x 332","Voxels":3.46359E+7,"Voxel_size":"126 nm","Type":"Semantic segmentation"}';
            app.Image = uiimage(app.GridLayout_Instructions);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Properties.png');
            app.TomakefulluseofthistoolyourfilesshouldhavemetadataLabel = uilabel(app.GridLayout_Instructions);
            app.TomakefulluseofthistoolyourfilesshouldhavemetadataLabel.Text = 'To make full use of this tool, your files should have metadata. Metadata should be handled as illustrated below:';
            app.ThepropertieswindowLabel = uilabel(app.GridLayout_Instructions);
            app.ThepropertieswindowLabel.Text = '- In the properties window, metadata should be stored in the ''Title'' and ''Subject'' fields as illustrated in the example below:';
            app.InthisexamplethemetadataformatisasfollowsLabel = uilabel(app.GridLayout_Instructions);
            app.InthisexamplethemetadataformatisasfollowsLabel.Text = '- In this example, the metadata format is as follows:';
            app.Label_3 = uilabel(app.GridLayout_Instructions);
            app.Label_3.Text = 'You do not have to manually enter metadata! For this you can use the ''Add/modify metadata'' tab';
            app.Label_4 = uilabel(app.GridLayout_Instructions);
            app.Label_4.Text = '- You can assign metadata per file or on a selection of files to speed up the process.';
            app.Label_5 = uilabel(app.GridLayout_Instructions);
            app.Label_5.Text = '- You can save metadata on the original files, but also on a ''preview'' version of the image file (3D volume only). Preview files only store few slices of the images.';
            app.Label_6 = uilabel(app.GridLayout_Instructions);
            app.Label_6.Text = 'Once metada have been saved, you can read metadata in the ''Explore image library'' tab and filter images based on their metadata using OR or AND logic';
            app.Label_7 = uilabel(app.GridLayout_Instructions);
            app.Label_7.Text = '- By default, metadata of preview files will be imported. For remote files (e.g., saved in Onedrive), this avoid downloading the full file.';
            app.Label_8 = uilabel(app.GridLayout_Instructions);
            app.Label_8.Text = '- You can also visualize your selection of images. By default, Preview files will be used to increase speed, but you can choose to visualize the full volumes if desired.';
            app.Label_9 = uilabel(app.GridLayout_Instructions);
            app.Label_9.Text = '- You can then export your selection of files in a destination folder of your choice (both complete and preview files).';
            app.YoucanalsoexportmetadatainanexcelfileLabel = uilabel(app.GridLayout_Instructions);
            app.YoucanalsoexportmetadatainanexcelfileLabel.Text = '- You can also export metadata of your selection of files in an excel file.';
            app.Label_10 = uilabel(app.GridLayout_Instructions);
            app.Label_10.Text = '- You can customize the metadata choice editing \src\Microstructure_library\Metadata_list.txt.';
            app.YoucansetapredefinedlistoffolderstoexploreeditingLabel = uilabel(app.GridLayout_Instructions);
            app.YoucansetapredefinedlistoffolderstoexploreeditingLabel.Text = '- You can set a predefined list of folders to explore editing \src\Microstructure_library\Library_folders.txt (including remote folders like Onedrive).';
            app.ExploreimagelibraryTab = uitab(app.TabGroup);
            app.ExploreimagelibraryTab.Title = 'Explore image library';
            app.GridLayout_Explore = uigridlayout(app.ExploreimagelibraryTab);
            app.SelectfolderwheretosearchtifandtifffilesLabel = uilabel(app.GridLayout_Explore);
            app.SelectfolderwheretosearchtifandtifffilesLabel.Text = '1) Add one or several folders, then, click on the search button to find all tif and tiff files within these folders.';
            app.ClicktoaddonefolderButton = uibutton(app.GridLayout_Explore, 'push');
            app.ClicktoaddonefolderButton.ButtonPushedFcn = createCallbackFcn(app, @ClicktoaddonefolderButtonPushed, true);
            app.ClicktoaddonefolderButton.Text = 'Click to add one folder';
            app.ClicktoremoveallfoldersButton = uibutton(app.GridLayout_Explore, 'push');
            app.ClicktoremoveallfoldersButton.ButtonPushedFcn = createCallbackFcn(app, @ClicktoremoveallfoldersButtonPushed, true);
            app.ClicktoremoveallfoldersButton.Text = 'Click to remove all folders';
            app.Folder_UITable = uitable(app.GridLayout_Explore);
            app.Folder_UITable.ColumnName = {'Folder'; 'Include subfolder(s)'; 'File found'; 'Preview file found'; 'Select'};
            app.Folder_UITable.RowName = {};
            app.Folder_UITable.ColumnEditable = [false true false false true];
            app.Folder_UITable.CellEditCallback = createCallbackFcn(app, @Folder_UITableCellEdit, true);
            app.SearchButton = uibutton(app.GridLayout_Explore, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonPushed, true);
            app.SearchButton.Text = 'Search';
            app.Explore_UITable = uitable(app.GridLayout_Explore);
            app.Explore_UITable.ColumnName = {'File'; 'Location'; 'Has preview'; 'Select'; 'Metadata 1'};
            app.Explore_UITable.RowName = {};
            app.Explore_UITable.ColumnSortable = [false false false false true];
            app.Explore_UITable.ColumnEditable = [false false false true false];
            app.Explore_UITable.Tooltip = {'If not a single file can satisfies all the filter lists, then no filter at all is applied.'};
            app.VisualizeselectedfilesButton_2 = uibutton(app.GridLayout_Explore, 'push');
            app.VisualizeselectedfilesButton_2.ButtonPushedFcn = createCallbackFcn(app, @VisualizeselectedfilesButton_2Pushed, true);
            app.VisualizeselectedfilesButton_2.Enable = 'off';
            app.VisualizeselectedfilesButton_2.Text = 'Visualize selected files';
            app.PreviewfilesifanyCheckBox = uicheckbox(app.GridLayout_Explore);
            app.PreviewfilesifanyCheckBox.Enable = 'off';
            app.PreviewfilesifanyCheckBox.Text = 'Preview files, if any';
            app.PreviewfilesifanyCheckBox.Value = true;
            app.MetadataDropDown_2Label = uilabel(app.GridLayout_Explore);
            app.MetadataDropDown_2Label.Enable = 'off';
            app.MetadataDropDown_2Label.Text = 'Metadata';
            app.MetadataDropDown_2 = uidropdown(app.GridLayout_Explore);
            app.MetadataDropDown_2.ValueChangedFcn = createCallbackFcn(app, @MetadataDropDown_2ValueChanged, true);
            app.MetadataDropDown_2.Enable = 'off';
            app.RemoveallfiltersButton = uibutton(app.GridLayout_Explore, 'push');
            app.RemoveallfiltersButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveallfiltersButtonPushed, true);
            app.RemoveallfiltersButton.Enable = 'off';
            app.RemoveallfiltersButton.Text = 'Remove all filters';
            app.ClicktosetdestinationfolderButton_2 = uibutton(app.GridLayout_Explore, 'push');
            app.ClicktosetdestinationfolderButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClicktosetdestinationfolderButton_2Pushed, true);
            app.ClicktosetdestinationfolderButton_2.Enable = 'off';
            app.ClicktosetdestinationfolderButton_2.Text = 'Click to set destination folder';
            app.Library_destinationfolderabel = uilabel(app.GridLayout_Explore);
            app.Library_destinationfolderabel.Enable = 'off';
            app.Library_destinationfolderabel.Text = 'None selected';
            app.CopyfilestodestinationfolderButton = uibutton(app.GridLayout_Explore, 'push');
            app.CopyfilestodestinationfolderButton.ButtonPushedFcn = createCallbackFcn(app, @CopyfilestodestinationfolderButtonPushed, true);
            app.CopyfilestodestinationfolderButton.Enable = 'off';
            app.CopyfilestodestinationfolderButton.Text = 'Copy files to destination folder';
            app.ExporttabletodestinationfolderButton = uibutton(app.GridLayout_Explore, 'push');
            app.ExporttabletodestinationfolderButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttabletodestinationfolderButtonPushed, true);
            app.ExporttabletodestinationfolderButton.Enable = 'off';
            app.ExporttabletodestinationfolderButton.Text = 'Export table to destination folder';
            app.SetfiltersonthemetadatetoonlydisplaytheimagesofinterestLabel = uilabel(app.GridLayout_Explore);
            app.SetfiltersonthemetadatetoonlydisplaytheimagesofinterestLabel.Text = '2) Set filters on the metadate to only display the images of interest';
            app.AddfilterButton = uibutton(app.GridLayout_Explore, 'push');
            app.AddfilterButton.ButtonPushedFcn = createCallbackFcn(app, @AddfilterButtonPushed, true);
            app.AddfilterButton.Enable = 'off';
            app.AddfilterButton.Text = 'Add filter';
            app.ExportpreviewfilesalsoCheckBox = uicheckbox(app.GridLayout_Explore);
            app.ExportpreviewfilesalsoCheckBox.Enable = 'off';
            app.ExportpreviewfilesalsoCheckBox.Text = 'Export preview files also';
            app.VisualizeorexportselectedfilesLabel = uilabel(app.GridLayout_Explore);
            app.VisualizeorexportselectedfilesLabel.Text = '3) Visualize or export selected files';
            app.IdleLabel = uilabel(app.GridLayout_Explore);
            app.IdleLabel.Text = 'Idle';
            app.CurrentfiltersEditFieldLabel = uilabel(app.GridLayout_Explore);
            app.CurrentfiltersEditFieldLabel.Enable = 'off';
            app.CurrentfiltersEditFieldLabel.Text = 'Current filter(s)';
            app.CurrentfiltersEditField = uieditfield(app.GridLayout_Explore, 'text');
            app.CurrentfiltersEditField.Editable = 'off';
            app.CurrentfiltersEditField.Enable = 'off';
            app.CurrentfiltersEditField.Tooltip = {''};
            app.RemovefilterButton = uibutton(app.GridLayout_Explore, 'push');
            app.RemovefilterButton.ButtonPushedFcn = createCallbackFcn(app, @RemovefilterButtonPushed, true);
            app.RemovefilterButton.Enable = 'off';
            app.RemovefilterButton.Text = 'Remove filter';
            app.ListfilterORButton = uibutton(app.GridLayout_Explore, 'push');
            app.ListfilterORButton.ButtonPushedFcn = createCallbackFcn(app, @ListfilterORButtonPushed, true);
            app.ListfilterORButton.Enable = 'off';
            app.ListfilterORButton.Tooltip = {'Set per metadata'};
            app.ListfilterORButton.Text = 'List filter: OR';
            app.Metadata_ORButton = uibutton(app.GridLayout_Explore, 'push');
            app.Metadata_ORButton.ButtonPushedFcn = createCallbackFcn(app, @Metadata_ORButtonPushed, true);
            app.Metadata_ORButton.Enable = 'off';
            app.Metadata_ORButton.Tooltip = {'Rule applied for all metadata'};
            app.Metadata_ORButton.Text = 'Metadata: OR';
            app.ValueDropDownLabel = uilabel(app.GridLayout_Explore);
            app.ValueDropDownLabel.Enable = 'off';
            app.ValueDropDownLabel.Text = 'Value';
            app.Metadata_ValueDropDown = uidropdown(app.GridLayout_Explore);
            app.Metadata_ValueDropDown.Enable = 'off';
            app.AddmodifymetadataTab = uitab(app.TabGroup);
            app.AddmodifymetadataTab.Title = 'Add/modify metadata';
            app.GridLayout_Addmetadata = uigridlayout(app.AddmodifymetadataTab);
            app.ClicktoselectfolderButton = uibutton(app.GridLayout_Addmetadata, 'push');
            app.ClicktoselectfolderButton.ButtonPushedFcn = createCallbackFcn(app, @ClicktoselectfolderButtonPushed, true);
            app.ClicktoselectfolderButton.Text = 'Click to select folder';
            app.Tifffolder_Label = uilabel(app.GridLayout_Addmetadata);
            app.Tifffolder_Label.Text = 'None selected';
            app.AddMetadata_UITable = uitable(app.GridLayout_Addmetadata);
            app.AddMetadata_UITable.ColumnName = {'Name'; '#Metadata'; 'Select'};
            app.AddMetadata_UITable.RowName = {};
            app.AddMetadata_UITable.ColumnEditable = [false false true];
            app.AddMetadata_UITable.CellEditCallback = createCallbackFcn(app, @AddMetadata_UITableCellEdit, true);
            app.AddMetadata_UITable.Tooltip = {'If you select one file, you can view existing metadata, modify existing metadata, or add  new metadata. If you select multiple files, you can only modify existing metadata or add   new ones.'};
            app.Label2 = uilabel(app.GridLayout_Addmetadata);
            app.Label2.Text = 'Select folder that contain tiff files for which you want to add or modify metadata. You can also copy these files to another (destination) folder after adding metadata';
            app.ClicktosetdestinationfolderButton = uibutton(app.GridLayout_Addmetadata, 'push');
            app.ClicktosetdestinationfolderButton.ButtonPushedFcn = createCallbackFcn(app, @ClicktosetdestinationfolderButtonPushed, true);
            app.ClicktosetdestinationfolderButton.Text = 'Click to set destination folder';
            app.Destinationfolder_Label = uilabel(app.GridLayout_Addmetadata);
            app.Destinationfolder_Label.Text = 'None selected';
            app.VisualizeselectedfilesButton = uibutton(app.GridLayout_Addmetadata, 'push');
            app.VisualizeselectedfilesButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizeselectedfilesButtonPushed, true);
            app.VisualizeselectedfilesButton.Text = 'Visualize selected files';
            app.MetadataDropDownLabel = uilabel(app.GridLayout_Addmetadata);
            app.MetadataDropDownLabel.Text = 'Metadata';
            app.MetadataDropDown = uidropdown(app.GridLayout_Addmetadata);
            app.MetadataDropDown.ValueChangedFcn = createCallbackFcn(app, @MetadataDropDownValueChanged, true);
            app.MetadataDropDown.Tooltip = {''};
            app.TrueorFalseCheckBox = uicheckbox(app.GridLayout_Addmetadata);
            app.TrueorFalseCheckBox.Text = 'True or False';
            app.BooleanchoiceLabel = uilabel(app.GridLayout_Addmetadata);
            app.BooleanchoiceLabel.Text = 'Boolean choice:';
            app.OpenchoiceEditFieldLabel = uilabel(app.GridLayout_Addmetadata);
            app.OpenchoiceEditFieldLabel.Text = 'Open choice';
            app.OpenchoiceEditField = uieditfield(app.GridLayout_Addmetadata, 'text');
            app.OpenchoiceEditField.Tooltip = {'Do not use comma in the description.'};
            app.Metadat_explanation_TextArea = uitextarea(app.GridLayout_Addmetadata);
            app.Metadat_explanation_TextArea.Editable = 'off';
            app.CurrentmetadatavalueLabel = uilabel(app.GridLayout_Addmetadata);
            app.CurrentmetadatavalueLabel.Text = 'Current metadata value: n/a (multiple selections)';
            app.ExportPanel = uipanel(app.GridLayout_Addmetadata);
            app.ExportPanel.Title = 'Export (save metadata first, and then, if wanted, copy to destination folder)';
            app.GridLayout2 = uigridlayout(app.ExportPanel);
            app.CopytodestinationfolderButton = uibutton(app.GridLayout2, 'push');
            app.CopytodestinationfolderButton.ButtonPushedFcn = createCallbackFcn(app, @CopytodestinationfolderButtonPushed, true);
            app.CopytodestinationfolderButton.Text = 'Copy to destination folder';
            app.SavemetadataButton = uibutton(app.GridLayout2, 'push');
            app.SavemetadataButton.ButtonPushedFcn = createCallbackFcn(app, @SavemetadataButtonPushed, true);
            app.SavemetadataButton.Text = 'Save metadata';
            app.CreatepreviewfileCheckBox = uicheckbox(app.GridLayout2);
            app.CreatepreviewfileCheckBox.Tooltip = {'This will create a preview file for 3D volumes, using a limited number of slices along z-axis. Preview and full file share the same metadata. Preview files allows reading the library database quickly even for remote images. Always true.'};
            app.CreatepreviewfileCheckBox.Enable = 'off';
            app.CreatepreviewfileCheckBox.Text = 'Create preview file';
            app.CreatepreviewfileCheckBox.Value = true;
            app.NumberofslicesforpreviewEditFieldLabel = uilabel(app.GridLayout2);
            app.NumberofslicesforpreviewEditFieldLabel.Text = 'Number of slices for preview';
            app.NumberofslicesforpreviewEditField = uieditfield(app.GridLayout2, 'numeric');
            app.NumberofslicesforpreviewEditField.Limits = [1 Inf];
            app.NumberofslicesforpreviewEditField.RoundFractionalValues = 'on';
            app.NumberofslicesforpreviewEditField.Value = 5;
            app.AddmodifymetadataButton = uibutton(app.GridLayout_Addmetadata, 'push');
            app.AddmodifymetadataButton.ButtonPushedFcn = createCallbackFcn(app, @AddmodifymetadataButtonPushed, true);
            app.AddmodifymetadataButton.Tooltip = {'Except for Boolean choice, you can add several values for the same metadata by clicking several times (one click per metadata value).'};
            app.AddmodifymetadataButton.Text = 'Add/modify metadata';
            app.ErasemetadataButton = uibutton(app.GridLayout_Addmetadata, 'push');
            app.ErasemetadataButton.ButtonPushedFcn = createCallbackFcn(app, @ErasemetadataButtonPushed, true);
            app.ErasemetadataButton.Text = 'Erase metadata';
            app.UnselectselectallButton = uibutton(app.GridLayout_Addmetadata, 'push');
            app.UnselectselectallButton.ButtonPushedFcn = createCallbackFcn(app, @UnselectselectallButtonPushed, true);
            app.UnselectselectallButton.Text = 'Unselect/select all';
            app.EraseallmetadataButton = uibutton(app.GridLayout_Addmetadata, 'push');
            app.EraseallmetadataButton.ButtonPushedFcn = createCallbackFcn(app, @EraseallmetadataButtonPushed, true);
            app.EraseallmetadataButton.Text = 'Erase all metadata';
            app.DonotusecommainopenchoicesectionLabel = uilabel(app.GridLayout_Addmetadata);
            app.DonotusecommainopenchoicesectionLabel.Text = 'Do not use comma '','' in open choice section.';
            app.PredefinedchoiceDropDownLabel = uilabel(app.GridLayout_Addmetadata);
            app.PredefinedchoiceDropDownLabel.Text = 'Predefined choice';
            app.PredefinedchoiceDropDown = uidropdown(app.GridLayout_Addmetadata);
            app.MetadatawillbeupdatedinexploreronlyaftertheappisclosedLabel = uilabel(app.GridLayout_Addmetadata);
            app.MetadatawillbeupdatedinexploreronlyaftertheappisclosedLabel.Text = 'Metadata will be updated in explorer only after the app is closed.';
            app.AutoassignButton = uibutton(app.GridLayout_Addmetadata, 'push');
            app.AutoassignButton.ButtonPushedFcn = createCallbackFcn(app, @AutoassignButtonPushed, true);
            app.AutoassignButton.Text = 'Auto-assign';
            % Show the figure after all components are created
            app.MicrostructureimagelibraryUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Microstructure_library

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MicrostructureimagelibraryUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MicrostructureimagelibraryUIFigure)
        end
    end
end