classdef Properties_correlation_loadparameters < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LoadparameterUIFigure        matlab.ui.Figure
        InstructionsTextArea         matlab.ui.control.TextArea
        InstructionsTextAreaLabel    matlab.ui.control.Label
        SelectphasebyDropDown        matlab.ui.control.DropDown
        SelectphasebyDropDownLabel   matlab.ui.control.Label
        SaveapplytoallvolumesButton  matlab.ui.control.Button
        Message                      matlab.ui.control.Label
        AddnewparameterButton        matlab.ui.control.Button
        SelectedvolumeDropDown       matlab.ui.control.DropDown
        SelectedvolumeDropDownLabel  matlab.ui.control.Label
        CancelandcloseButton         matlab.ui.control.Button
        CloseandkeepyourselectionsButton  matlab.ui.control.Button
        SaveButton                   matlab.ui.control.Button
        LoadtemplateDropDown         matlab.ui.control.DropDown
        LoadtemplateDropDownLabel    matlab.ui.control.Label
        SavetemplateButton           matlab.ui.control.Button
        UITable                      matlab.ui.control.Table
        PhaseDropDown                matlab.ui.control.DropDown
        PhaseDropDownLabel           matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp % Main app        
        folder_separation = [];
        p=[];
        number_phase = [];
        ntemplate = 0;
        template = [];
        volumesaved = 0;
        parms = [];
    end
    
    methods (Access = private)
        
        function [] = Check_save(app)
            allid = cell2mat(app.UITable.Data(:,3));
            allid(allid==0)=[];
            allid=round(allid);
            if ~isempty(allid)
                app.SaveButton.Enable = 'on';
                app.SaveapplytoallvolumesButton.Enable = 'on';
                app.SavetemplateButton.Enable = 'on';
            else
                app.SaveButton.Enable = 'off';
                app.SaveapplytoallvolumesButton.Enable = 'off';
                app.SavetemplateButton.Enable = 'off';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, p)
            % Store main app object
            app.CallingApp = caller;            
            if ispc
                app.folder_separation = '\';
            else
                app.folder_separation = '/';
            end        
            n_volume = length(p);
            volume_names=cell(n_volume,1);
            for k=1:1:n_volume
                tmp = find(p(k).volume_main_folder==app.folder_separation);
                volume_names(k) = {p(k).volume_main_folder(tmp(end)+1:length(p(k).volume_main_folder))};
            end
            app.SelectedvolumeDropDown.Items = volume_names;
            app.SelectedvolumeDropDown.Value = volume_names(1);
            
            app.template = p(1).template;
            if ~isempty(app.template)
                app.ntemplate = length(app.template);
                tmp = cell(1,app.ntemplate+1);
                tmp(1)={' '};
                tmp(2:end)={app.template.name};
                app.LoadtemplateDropDown.Items = tmp;
                app.LoadtemplateDropDown.Value = app.LoadtemplateDropDown.Items(1);
            end

            % Store
            app.p=p;
            % Initialize with current volume
            app.SelectedvolumeDropDownValueChanged;         
        end

        % Value changed function: PhaseDropDown
        function PhaseDropDownValueChanged(app, event)
            kvolume = find(strcmp(app.SelectedvolumeDropDown.Items, app.SelectedvolumeDropDown.Value));
            current_folder = [app.p(kvolume).volume_main_folder  app.folder_separation 'Correlation' app.folder_separation];
            number_results = length(app.p(kvolume).results_filenames); % Number of results to summarize
            allresults={};
            k_data=0;
            for current_matfile=1:1:number_results % Loop over all saved results
                current_result=char(app.p(kvolume).results_filenames(current_matfile)); % Load result
                pathname = [current_folder current_result];
                datamat = load(pathname);
                if isfield(datamat,'results_correlation')
                    data = datamat.results_correlation;
                    k_data=k_data+1;
                    allresults(k_data).phase=data;
                end
            end         


            selected_phasename = app.PhaseDropDown.Value;
            k_parameter=0;
            for k_result=1:1:number_results
                parameter_name = fieldnames(allresults(k_result).phase);
                n_parameter = length(parameter_name);
                n_phase = length(allresults(k_result).phase);
                for k_phase=1:1:n_phase
                    current_phasename = allresults(k_result).phase(k_phase).name;
                    if strcmp(selected_phasename, current_phasename) % We calculated it!
                        for k_name=1:1:n_parameter
                            current_name = char(parameter_name(k_name));
                            if ~strcmp(current_name,'name')
                                k_parameter=k_parameter+1;
                                column1(k_parameter,1) = {current_name};
                                allvalues = struct2cell(allresults(k_result).phase(k_phase));
                                column2(k_parameter,1) = allvalues(k_name);
                                column3(k_parameter,1) = {[0]}; % By default
                            end
                        end
%                     else
%                         for k_name=1:1:n_parameter
%                             current_name = char(parameter_name(k_name));
%                             if ~strcmp(current_name,'name')
%                                 k_parameter=k_parameter+1;
%                                 column1(k_parameter,1) = {current_name};
%                                 column2(k_parameter,1) = {'not calculated'};
%                                 column3(k_parameter,1) = {[0]}; % By default
%                             end
%                         end
                    end
                end
            end
            input_ = [column1 column2 column3];
            app.UITable.Data=input_;
            app.LoadtemplateDropDown.Value = app.LoadtemplateDropDown.Items(1);
            app.Message.Text = 'Idle';
            % Can save
            app.Check_save;
            
        end

        % Value changed function: LoadtemplateDropDown
        function LoadtemplateDropDownValueChanged(app, event)
            value = app.LoadtemplateDropDown.Value;
            if ~strcmp(value,' ')
                ktemplate = find(strcmp(app.LoadtemplateDropDown.Items, app.LoadtemplateDropDown.Value))-1;
                nproperty = length(app.template(ktemplate).property);
                for k=1:1:nproperty
                    name = app.template(ktemplate).property(k).name;
                    names = app.UITable.Data(:,1);
                    id = find(strcmp(names, name));
                    if ~isempty(id)
                        val = cell2mat(app.UITable.Data(id,2));
                        if isnumeric(val) && ~isnan(val)
                            % Propperty has been calculated for this phase
                            app.UITable.Data(id,3) = app.template(ktemplate).property(k).parameterid;
                            app.Message.Text = 'Template loaded!';
                            app.SaveButton.Enable = 'on';
                            app.SaveapplytoallvolumesButton.Enable = 'on';
                        end
                    end
                end
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            vals = app.UITable.Data(:,2);
            ids = cell2mat(app.UITable.Data(:,3));            
            idx = find(ids~=0);
            if ~isempty(idx) % Should always be the case (due to save 'enable');
                app.volumesaved = app.volumesaved + 1;
                app.parms(app.volumesaved).pars = [cell2mat(vals(idx)) ids(idx)];
                app.parms(app.volumesaved).volumename = app.SelectedvolumeDropDown.Value;
                app.Message.Text = 'Saved!';
                app.CloseandkeepyourselectionsButton.Enable = 'on';
            end
        end

        % Button pushed function: CloseandkeepyourselectionsButton
        function CloseandkeepyourselectionsButtonPushed(app, event)
            % Sort results
            nvolume = 0;
            sortedparms = [];
            for k=1:1:app.volumesaved
                if nvolume==0
                    nvolume=nvolume+1;
                    kvolume = 1;
                    volumes(kvolume) = {app.parms(k).volumename};
                    sortedparms(kvolume).volumename = volumes(kvolume);
                    sortedparms(kvolume).pars = app.parms(k).pars;
                else
                    tmp = find(strcmp(volumes, app.parms(k).volumename));
                    if isempty(tmp)
                        nvolume=nvolume+1;
                        kvolume = nvolume;
                        volumes(kvolume) = {app.parms(k).volumename};
                        sortedparms(kvolume).volumename = volumes(kvolume);
                        sortedparms(kvolume).pars = app.parms(k).pars;
                    else
                        kvolume = tmp;
                        sortedparms(kvolume).pars = [sortedparms(kvolume).pars; app.parms(k).pars];
                    end
                end
            end
          
            for k=1:1:nvolume
                sortedparms(k).pars;
                tmp = unique(sortedparms(k).pars,'rows');
                tmp = sortrows(tmp,2);
                sortedparms(k).pars = tmp;
            end

            % Pass parameters and also templates
            update_mainapp(app.CallingApp ,sortedparms, app.template);

            app.delete; % Shut down the entire app
        end

        % Button pushed function: CancelandcloseButton
        function CancelandcloseButtonPushed(app, event)
            app.delete; % Shut down the entire app
        end

        % Button pushed function: SavetemplateButton
        function SavetemplateButtonPushed(app, event)
            allid = cell2mat(app.UITable.Data(:,3));
            idx=find(allid);
            n = length(idx);
            app.ntemplate = app.ntemplate+1;
            app.template(app.ntemplate).name = ['#' num2str(app.ntemplate,'%i') ' ' app.SelectedvolumeDropDown.Value ', ' app.PhaseDropDown.Value];
            for k=1:1:n
                app.template(app.ntemplate).property(k).name = app.UITable.Data(idx(k),1);
                app.template(app.ntemplate).property(k).parameterid = app.UITable.Data(idx(k),3);
            end
            tmp = cell(1,app.ntemplate+1);
            tmp(1)={' '};
            tmp(2:end)={app.template.name};
            app.LoadtemplateDropDown.Items = tmp;
            app.LoadtemplateDropDown.Value = app.LoadtemplateDropDown.Items(1);
            app.Message.Text = 'Template saved!';
        end

        % Value changed function: SelectedvolumeDropDown
        function SelectedvolumeDropDownValueChanged(app, event)
            value = app.SelectedvolumeDropDown.Value;
            kvolume = find(strcmp(app.SelectedvolumeDropDown.Items, value));
            number_results = length(app.p(kvolume).results_filenames); % Number of results to summarize
            current_folder = [app.p(kvolume).volume_main_folder  app.folder_separation 'Correlation' app.folder_separation];
            for current_matfile=1:1:number_results % Loop over all saved results
                current_result=char(app.p(kvolume).results_filenames(current_matfile)); % Load result
                pathname = [current_folder current_result];
                datamat = load(pathname);
                if isfield(datamat,'results_correlation')
                    data = datamat.results_correlation;
                    app.number_phase = length(data);
                    for current_phase=1:1:app.number_phase
                        phase_choice(current_phase,1)={char(data(current_phase).name)};
                    end
                end
            end
            app.PhaseDropDown.Items = phase_choice;
            app.PhaseDropDown.Value = phase_choice(1);
            app.LoadtemplateDropDown.Value = app.LoadtemplateDropDown.Items(1);
            app.Message.Text = 'Idle';
            app.PhaseDropDownValueChanged;
            
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            oldData = event.PreviousData;
            newData = event.NewData;
            if indices(2)==2
                if ~isnumeric(newData) || isnan(newData)
                    app.UITable.Data(indices(1),indices(2))={oldData};
                end
            elseif indices(2)==3
                if ~isnumeric(newData) || isnan(newData) || newData<0 || round(newData)~=newData
                    app.UITable.Data(indices(1),indices(2))={oldData};
                end
                % No doublons
                allid = cell2mat(app.UITable.Data(:,3));
                allid(allid==0)=[];
                allid=round(allid);
                if length(allid) ~= length(unique(allid))
                    app.UITable.Data(indices(1),indices(2))={oldData};
                end
                % Check if calculated
                tmp = cell2mat(app.UITable.Data(indices(1),2));
                if ~isnumeric(tmp)
                    app.UITable.Data(indices(1),indices(2))={oldData};
                end                
            end
            % Can save
            app.Check_save;            
        end

        % Button pushed function: AddnewparameterButton
        function AddnewparameterButtonPushed(app, event)
            tmp = app.UITable.Data;
            sz = size(app.UITable.Data);
            tmp = cell(sz(1)+1,3);
            tmp(1:end-1,:)=app.UITable.Data;
            tmp(end,1) = {'User parameter'}; tmp(end,2) = {[0.0]}; tmp(end,3) = {[0.0]};
            app.UITable.Data = tmp;
        end

        % Button pushed function: SaveapplytoallvolumesButton
        function SaveapplytoallvolumesButtonPushed(app, event)
            ids = cell2mat(app.UITable.Data(:,3));            
            idx = find(ids~=0);            
            par_names = app.UITable.Data(idx,1);
            par_ids = app.UITable.Data(idx,3);
            phaseselected_name = app.PhaseDropDown.Value;
            [~, phaseselected_id] = ismember(phaseselected_name, app.PhaseDropDown.Items);
            
            nvolume = length(app.SelectedvolumeDropDown.Items);
            for kvol=1:1:nvolume
                app.SelectedvolumeDropDown.Value = app.SelectedvolumeDropDown.Items(kvol);
                app.SelectedvolumeDropDownValueChanged; % Update UI with current volume
                findphase = false;
                if strcmp(app.SelectphasebyDropDown.Value,'Name')
                    [tf, ~] = ismember(phaseselected_name, app.PhaseDropDown.Items);
                    if tf
                        app.PhaseDropDown.Value = phaseselected_name;
                        app.PhaseDropDownValueChanged; % Update UI with current phase
                        findphase = true;
                    end
                else
                    nphase = length(app.PhaseDropDown.Items);
                    if phaseselected_id<=nphase
                        app.PhaseDropDown.Value = app.PhaseDropDown.Items(phaseselected_id);
                        app.PhaseDropDownValueChanged; % Update UI with current phase
                        findphase = true;
                    end
                end
                if findphase
                    current_par_names = app.UITable.Data(:,1);
                    for kpar=1:1:length(par_names)
                        [tf, id] = ismember(par_names(kpar), current_par_names);
                        if tf
                            val = cell2mat(app.UITable.Data(id,2));
                            if isnumeric(val) && ~isnan(val)
                                app.UITable.Data(id,3) = par_ids(kpar);
                            end
                        end
                    end
                    app.SaveButtonPushed; % Save
                end
                pause(0.1) % For the user to see
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LoadparameterUIFigure = uifigure('Visible', 'off');
            app.LoadparameterUIFigure.Name = 'Load parameter';
            app.PhaseDropDownLabel = uilabel(app.LoadparameterUIFigure);
            app.PhaseDropDownLabel.Text = 'Phase';
            app.PhaseDropDown = uidropdown(app.LoadparameterUIFigure);
            app.PhaseDropDown.ValueChangedFcn = createCallbackFcn(app, @PhaseDropDownValueChanged, true);
            app.UITable = uitable(app.LoadparameterUIFigure);
            app.UITable.ColumnName = {'Parameter name'; 'Value'; 'Parameter Id'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.SavetemplateButton = uibutton(app.LoadparameterUIFigure, 'push');
            app.SavetemplateButton.ButtonPushedFcn = createCallbackFcn(app, @SavetemplateButtonPushed, true);
            app.SavetemplateButton.Enable = 'off';
            app.SavetemplateButton.Text = {'Save'; 'template'};
            app.LoadtemplateDropDownLabel = uilabel(app.LoadparameterUIFigure);
            app.LoadtemplateDropDownLabel.Text = 'Load template';
            app.LoadtemplateDropDown = uidropdown(app.LoadparameterUIFigure);
            app.LoadtemplateDropDown.Items = {''};
            app.LoadtemplateDropDown.ValueChangedFcn = createCallbackFcn(app, @LoadtemplateDropDownValueChanged, true);
            app.LoadtemplateDropDown.Value = '';
            app.SaveButton = uibutton(app.LoadparameterUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save';
            app.CloseandkeepyourselectionsButton = uibutton(app.LoadparameterUIFigure, 'push');
            app.CloseandkeepyourselectionsButton.ButtonPushedFcn = createCallbackFcn(app, @CloseandkeepyourselectionsButtonPushed, true);
            app.CloseandkeepyourselectionsButton.Enable = 'off';
            app.CloseandkeepyourselectionsButton.Text = {'Close and keep'; 'your selections'};
            app.CancelandcloseButton = uibutton(app.LoadparameterUIFigure, 'push');
            app.CancelandcloseButton.ButtonPushedFcn = createCallbackFcn(app, @CancelandcloseButtonPushed, true);
            app.CancelandcloseButton.Text = {'Cancel and'; 'close'};
            app.SelectedvolumeDropDownLabel = uilabel(app.LoadparameterUIFigure);
            app.SelectedvolumeDropDownLabel.Text = 'Selected volume';
            app.SelectedvolumeDropDown = uidropdown(app.LoadparameterUIFigure);
            app.SelectedvolumeDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectedvolumeDropDownValueChanged, true);
            app.AddnewparameterButton = uibutton(app.LoadparameterUIFigure, 'push');
            app.AddnewparameterButton.ButtonPushedFcn = createCallbackFcn(app, @AddnewparameterButtonPushed, true);
            app.AddnewparameterButton.Text = {'Add new'; 'parameter'};
            app.Message = uilabel(app.LoadparameterUIFigure);
            app.Message.Text = 'Idle';
            app.SaveapplytoallvolumesButton = uibutton(app.LoadparameterUIFigure, 'push');
            app.SaveapplytoallvolumesButton.ButtonPushedFcn = createCallbackFcn(app, @SaveapplytoallvolumesButtonPushed, true);
            app.SaveapplytoallvolumesButton.Enable = 'off';
            app.SaveapplytoallvolumesButton.Text = 'Save (apply to all volumes)';
            app.SelectphasebyDropDownLabel = uilabel(app.LoadparameterUIFigure);
            app.SelectphasebyDropDownLabel.Text = 'Select phase by';
            app.SelectphasebyDropDown = uidropdown(app.LoadparameterUIFigure);
            app.SelectphasebyDropDown.Items = {'Name', 'Index number'};
            app.SelectphasebyDropDown.Tooltip = {'Control behavior of "Save (apply to all volumes)" button. If "name" is selected, current parameter selections will be applied to all volumes for the phase name currently selected. If "index number" is selected, current parameter selection will be applied to all volumes for the phase index (position in the popup menu) currently selected. Second choice is relevant for instance if volumes have a ''solid'' phase in the same index position but with a different name assigned (e.g.,''NMC'', ''graphite''...). Missing phase(s) will be skipped.'};
            app.SelectphasebyDropDown.Value = 'Name';
            app.InstructionsTextAreaLabel = uilabel(app.LoadparameterUIFigure);
            app.InstructionsTextAreaLabel.Text = 'Instructions';
            app.InstructionsTextArea = uitextarea(app.LoadparameterUIFigure);
            app.InstructionsTextArea.Value = {'- Manual: choose a volume, then a phase. Then enter a unique parameter Id for each parameter you want to import. The parameter Id corresponds to the # number in the top left table of the application main window. Once finished, click on "Save". Repeat for each {volume, phase} you want to import. Re-use same parameter Id for same parameter to be coherent. When all {volume,phase} have been saved, click on "Close and keep your selections". You can add a new parameter if you want to customize the parameter list with the "Add new parameter" button. You can also edit the value of each parameter directly in the table if you want to apply a correction.'; '- Semi automatic: choose a volume, then a phase. Then enter a unique parameter Id for each parameter you want to import. The parameter Id corresponds to the # number in the top left table of the application main window. Once finished, click on "Save template" and then on "Save". Select a new {volume,phase} for which you would like to re-use the same parameter selection, and then select the relevant template in the "Load template" popup menu. Then click on "Save". Missing parameters will be ignored. You can save several templates. Repeat for each {volume, phase} you want to import. When all {volume,phase} have been saved, click on "Close and keep your selections".'; '- Automatic: choose a volume, then a phase. Then enter a unique parameter Id for each parameter you want to import. The parameter Id corresponds to the # number in the top left table of the application main window. Once finished, pick a choice in the "Select phase by" popup menu (read the tooltip for explanations). Then click on "Save (apply to all volumes)". Your parameter selection will be applied on all volumes with your selected phase selection behavior. Missing parameters will be ignored. Repeat for other parameter selection you would like to import (for instance, one for all ''pore'' phase, and another for all ''solid'' phase). Once finished, click on "Close and keep your selections".'};
            % Show the figure after all components are created
            app.LoadparameterUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Properties_correlation_loadparameters(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LoadparameterUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LoadparameterUIFigure)
        end
    end
end