classdef Properties_correlation_main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        propertiescorrelationUIFigure   matlab.ui.Figure
        SavestateMenu                   matlab.ui.container.Menu
        LoadstateMenu                   matlab.ui.container.Menu
        ResetstateMenu                  matlab.ui.container.Menu
        PlotPanel                       matlab.ui.container.Panel
        PlotSpDButton                   matlab.ui.control.Button
        PlottortuosityfporosityButton   matlab.ui.control.Button
        PlotallselectedparametersButton  matlab.ui.control.Button
        HeatmapPanel                    matlab.ui.container.Panel
        ShowvalueincellCheckBox         matlab.ui.control.CheckBox
        SavecorrelationmapButton        matlab.ui.control.Button
        PlotcorrelationmapButton        matlab.ui.control.Button
        CorrelationmetricDropDown       matlab.ui.control.DropDown
        CorrelationmetricDropDownLabel  matlab.ui.control.Label
        CustomcorrelationPanel          matlab.ui.container.Panel
        SpecificsurfaceareaporosityradiuscorrelationLabel  matlab.ui.control.Label
        AddtheoriticallimitCheckBox     matlab.ui.control.CheckBox
        AddreferencelinesCheckBox       matlab.ui.control.CheckBox
        PolynomialorderEditField        matlab.ui.control.NumericEditField
        PolynomialorderEditFieldLabel   matlab.ui.control.Label
        SpecificsurfaceareaEditField    matlab.ui.control.NumericEditField
        SpecificsurfaceareaEditFieldLabel  matlab.ui.control.Label
        AsfunctionofDropDown            matlab.ui.control.DropDown
        AsfunctionofDropDownLabel       matlab.ui.control.Label
        ParticlediameterEditField       matlab.ui.control.NumericEditField
        ParticlediameterEditFieldLabel  matlab.ui.control.Label
        ParticlevolumefractionEditField  matlab.ui.control.NumericEditField
        ParticlevolumefractionEditFieldLabel  matlab.ui.control.Label
        TortuosityfactorporositycorrelationLabel  matlab.ui.control.Label
        FormDropDown                    matlab.ui.control.DropDown
        FormDropDownLabel               matlab.ui.control.Label
        TortuosityEditField             matlab.ui.control.NumericEditField
        TortuosityEditFieldLabel        matlab.ui.control.Label
        PorosityEditField               matlab.ui.control.NumericEditField
        PorosityEditFieldLabel          matlab.ui.control.Label
        FormatoptionsPanel              matlab.ui.container.Panel
        TextfontsizeEditField           matlab.ui.control.NumericEditField
        TextfontsizeEditFieldLabel      matlab.ui.control.Label
        AddtextvolumenameCheckBox       matlab.ui.control.CheckBox
        NumberofcolumnEditField         matlab.ui.control.NumericEditField
        NumberofcolumnEditFieldLabel    matlab.ui.control.Label
        LegendlocationDropDown          matlab.ui.control.DropDown
        LegendlocationDropDownLabel     matlab.ui.control.Label
        MinorgridCheckBox               matlab.ui.control.CheckBox
        GridCheckBox                    matlab.ui.control.CheckBox
        ForplotpervolumeonlyLabel       matlab.ui.control.Label
        MarkersizeEditField             matlab.ui.control.NumericEditField
        MarkersizeEditFieldLabel        matlab.ui.control.Label
        LineWidthEditField              matlab.ui.control.NumericEditField
        LineWidthEditFieldLabel         matlab.ui.control.Label
        LegendfontsizeEditField         matlab.ui.control.NumericEditField
        LegendfontsizeEditFieldLabel    matlab.ui.control.Label
        AxefontsizeEditField            matlab.ui.control.NumericEditField
        AxefontsizeEditFieldLabel       matlab.ui.control.Label
        SubtitlefontsizeEditField       matlab.ui.control.NumericEditField
        SubtitlefontsizeEditFieldLabel  matlab.ui.control.Label
        TitlefontsizeEditField          matlab.ui.control.NumericEditField
        TitlefontsizeEditFieldLabel     matlab.ui.control.Label
        FontDropDown                    matlab.ui.control.DropDown
        FontDropDownLabel               matlab.ui.control.Label
        SavematrixButton                matlab.ui.control.Button
        PlotcorrelateperDropDown        matlab.ui.control.DropDown
        PlotcorrelateperDropDownLabel   matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Group_UITable                   matlab.ui.control.Table
        Label                           matlab.ui.control.Label
        Volume_UITable                  matlab.ui.control.Table
        LoadparametersfromoneorseveralvolumesButton  matlab.ui.control.Button
        InstructionsTextArea            matlab.ui.control.TextArea
        InstructionsTextAreaLabel       matlab.ui.control.Label
        Parameter_UITable               matlab.ui.control.Table
    end

    
    properties (Access = private)
        folder_separation = [];
        PropertiesApp;
        allparms = [];
        template = [];
        col_ = [];
        markerlist = {'o','square','diamond','^','v','<','pentagram','hexagram','+','*','.','x','_','|'}
        linstylelist = {'none','-','--',':','-.'};
        savpath = [];
    end
    
    methods (Access = public)
        
        function update_mainapp(app, sortedparms, template) % Call by Properties_correlation_loadparameters when user click on "Close and keep your selections"
            if isempty(app.allparms)
                app.allparms = sortedparms;
            else
                volumes = app.Volume_UITable.Data(:,1);
                n_new_volume = length(sortedparms);
                n_current_volume = length(app.allparms);
                for k=1:1:n_new_volume
                    idx = find(strcmp(volumes,sortedparms(k).volumename));
                    if isempty(idx) % New volume
                        n_current_volume = n_current_volume+1;
                        app.allparms(n_current_volume) = sortedparms(k);
                    else
                        app.allparms(idx) = sortedparms(k);
                    end
                end
                app.template = template;
            end

            % Update tables
            app.update_parameter;
            app.update_group;

            % UI enable
            volume_choice = app.Volume_UITable.Data;
            statut = 'on';
            if isempty(volume_choice)
                statut = 'off';
            end
            app.SavestateMenu.Enable = statut;
            app.SavematrixButton.Enable = statut;
            app.PlotallselectedparametersButton.Enable = statut;
            app.PlottortuosityfporosityButton.Enable = statut;
            app.PlotSpDButton.Enable = statut;
            app.PlotcorrelationmapButton.Enable = statut;
            app.SavecorrelationmapButton.Enable = statut;
        end
    end
    
    methods (Access = private)
        
        function update_group(app)
            tmp = app.Volume_UITable.Data;
            groupid = cell2mat(tmp(:,3));
            plotchoice = cell2mat(tmp(:,6));
            id0=find(~plotchoice);
            if ~isempty(id0)
                groupid(id0)=[];
            end

            unique_group = unique(groupid);
            ngroup = length(unique_group);

            tablegr = cell(ngroup,9);
            for kg=1:1:ngroup
                tablegr(kg,1) = {[unique_group(kg)]};
                tablegr(kg,2) = {['Group ' num2str(kg)]};
                s = uistyle('BackgroundColor',app.col_(kg,:));
                addStyle(app.Group_UITable,s,'cell',[kg,3]);

                tablegr(kg,3)  = {[num2str(app.col_(kg,1),'%1.3f') ' ' num2str(app.col_(kg,2),'%1.3f') ' ' num2str(app.col_(kg,3),'%1.3f')]};

                %tablegr(kg,3) = {num2str(app.col_(kg,:),'%1.3f')};
                tablegr(kg,4) = {['none']};
                tablegr(kg,5) = {[1]};
                if kg<=length(app.markerlist)
                    tablegr(kg,6) = app.markerlist(kg);
                else
                    tablegr(kg,6) = app.markerlist(randi(length(app.markerlist)));
                end
                tablegr(kg,7) = {[8]};
                tablegr(kg,8) = {false};
                tablegr(kg,9) = {true};
            end
            app.Group_UITable.Data = tablegr;  
        end
        
        function M = CreateMatrix(app)
            parameter_choice = app.Parameter_UITable.Data;
            id0 = find(~cell2mat(parameter_choice(:,5)));
            parameter_choice(id0,:)=[];

            volume_choicetot = app.Volume_UITable.Data;
            [nvoltot,~]=size(volume_choicetot);

            if ~isempty(parameter_choice) && nvoltot>0

                id0 = find(~cell2mat(volume_choicetot(:,6)));
                volume_choice = volume_choicetot;
                volume_choice(id0,:)=[];

                % Number of volumes
                [nvol,~]=size(volume_choice);

                % Number of parameters
                pid1 = str2num(char(parameter_choice(:,1)));
                pid2 = [];
                for kvol = 1:1:nvol
                    pid2 = [pid2 str2num(cell2mat(volume_choice(kvol,5)))];
                end
                pid2 = unique(pid2);
                id12 = ismember(pid1', pid2);
                pid1(~id12) = [];
                id21 = ismember(pid2, pid1');
                pid2(~id21) = [];
                pid = unique([pid1' pid2]);
                npar = length(pid);
                parameter_choice = app.Parameter_UITable.Data;
                parameter_choice = parameter_choice(pid,:);

                % % Correlation Matrix
                % Column and row names
                M=cell(npar,nvol+2);
                var_names = cell(1,nvol+2);
                var_names(1) = {'Parameter'};
                var_names(2) = {'Unit'};
                for kvol=1:1:nvol
                    var_names(kvol+2) =volume_choice(kvol,2);
                end
                for kpar=1:1:npar
                    M(kpar,1) =parameter_choice(kpar,2);
                    M(kpar,2) =parameter_choice(kpar,3);
                end
                % Parameter values
                kvol = 0;
                for kvoltot=1:1:nvoltot
                    if cell2mat(volume_choicetot(kvoltot,6))
                        kvol=kvol+1;
                        list_par = str2num(cell2mat(volume_choice(kvol,5)));
                        parms = app.allparms(kvoltot).pars;
                        [npartot,~]=size(parms);
                        for kpartot=1:1:npartot
                            if ismember(parms(kpartot,2),list_par) && ismember(parms(kpartot,2),pid)
                                kpar = find(parms(kpartot,2)==pid);
                                M(kpar,kvol+2) = {parms(kpartot,1)};
                            end
                        end
                    end
                end

                M = cell2table(M,"VariableNames",var_names);

            else
                msgbox('No parameters or volumes selected', 'Not enough data','warn');
                M= [];
            end

        end
        
        function update_parameter(app)
            nvolume = length(app.allparms);
            tablevol = cell(nvolume,5);
            current_number = zeros(1e3,1);
            allpar = [];
            for kvol = 1:1:nvolume
                tmp = app.allparms(kvol).pars;
                [npar,~] = size(tmp);
                % Parameter table
                for kpar=1:1:npar
                    current_number(tmp(kpar,2)) = current_number(tmp(kpar,2)) + 1;
                    app.Parameter_UITable.Data( tmp(kpar,2),4 ) = {[current_number(tmp(kpar,2))]};
                    app.Parameter_UITable.Data( tmp(kpar,2),5) = {true};
                end
                % Volume table
                tablevol(kvol,1) = app.allparms(kvol).volumename;
                tablevol(kvol,2) = app.allparms(kvol).volumename;
                tablevol(kvol,3) = {[kvol]};
                tablevol(kvol,4) = {['Total=' num2str(npar) ': ' num2str(tmp(:,2)')]};
                tablevol(kvol,5) = {num2str(tmp(:,2)')};
                tablevol(kvol,6) = {true};
                allpar = [allpar; tmp(:,2)];
            end
            app.Volume_UITable.Data = tablevol;

            % Check irrelevant parameters (can occur if user overwritte/reload same volume)
            [GC,GR] = groupcounts(allpar);
            for k=1:1:1e3
                idx=find(GR==k);
                if isempty(idx)
                    app.Parameter_UITable.Data( k,4 ) = {[0]};
                    app.Parameter_UITable.Data( k,5) = {false};
                else
                    app.Parameter_UITable.Data( k,4 ) = {[GC(idx)]};
                    app.Parameter_UITable.Data( k,5) = {true};
                end
            end
        end
        
        function [CorrMatrix,Parnames,corrmetric,plotper] = Create_correlationmatrix(app)
            plotper = app.PlotcorrelateperDropDown.Value;

            volume_choice = app.Volume_UITable.Data;
            if isempty(volume_choice)
                msgbox('No parameters or volumes selected', 'Not enough data','warn');
                return
            end
            id0 = find(~cell2mat(volume_choice(:,6)));
            volume_choice(id0,:)=[];

            group_choice = app.Group_UITable.Data;
            id0 = find(~cell2mat(group_choice(:,9)));
            group_choice(id0,:)=[];

            % Create matrix
            ParmsMatrix = app.CreateMatrix;
            if isempty(ParmsMatrix)
                msgbox('No parameters or volumes selected', 'Not enough data','warn');
                return
            end

            % Create correlation matrix
            Parnames = ParmsMatrix(:,1).Variables;
            % Clean vars
            [number_parameter,number_volume] = size(ParmsMatrix);
            number_volume = number_volume-2;
            tmp = ParmsMatrix(:,3:end);
            ValMatrix = NaN(number_parameter,number_volume);
            for kpar=1:1:number_parameter
                for kvol=1:1:number_volume
                    v = tmp(kpar,kvol).Variables;
                    if iscell(v)
                        v = cell2mat(v);
                        if ~isempty(v)
                            ValMatrix(kpar,kvol) = v;
                        end
                    else
                        ValMatrix(kpar,kvol) = v;
                    end
                end
            end
            %ValMatrix = ParmsMatrix(:,3:end).Variables;

            corrmetric = app.CorrelationmetricDropDown.Value;
            if strcmp(plotper,'volume')
                CorrMatrix = Function_correlation_metric(ValMatrix,Parnames,corrmetric);
            elseif strcmp(plotper,'group')
                subset_CorrMatrix = zeros(number_parameter,number_parameter);
                group_id = cell2mat(group_choice(:,1));
                volume_group = cell2mat(volume_choice(:,3));
                [number_group,~] = size(group_choice);
                for kgr = 1:1:number_group
                    idg = group_id(kgr);
                    idv = find(volume_group==idg);
                    if ~isempty(idv)
                        subset_ValMatrix = ValMatrix(:,idv);
                        subset_CorrMatrix = subset_CorrMatrix + Function_correlation_metric(subset_ValMatrix,Parnames,corrmetric);
                    end
                end
                CorrMatrix = subset_CorrMatrix/number_group;
            end

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if ispc
                app.folder_separation = '\';
            else
                app.folder_separation = '/';
            end
            app.savpath = [pwd app.folder_separation];

            for k_id=1:1:100
                column_1(k_id,1) = {num2str(k_id)};
                column_2(k_id,1) = {['Parameter ' num2str(k_id)]};
                column_3(k_id,1) = {['']};
                column_4(k_id,1) = {'0'};
                column_5(k_id,1) = {false};
            end
            app.Parameter_UITable.Data=[column_1 column_2 column_3 column_4 column_5];
            app.col_ = [colororder; rand(1e6,3)];

            % Format
            app.FontDropDown.Items = listfonts;
            app.FontDropDown.Value = 'Times New Roman';
        end

        % Menu selected function: SavestateMenu
        function SavestateMenuSelected(app, event)
            [filename,foldername,idx] = uiputfile([app.savpath],'Enter filename without extension');
            if idx
                [ ~ , filename , ~] = fileparts(filename); % No extension
                app.savpath = foldername; % For future save
                all_parms=app.allparms;
                Parameter_UITable_Data = app.Parameter_UITable.Data;
                Volume_UITable_Data = app.Volume_UITable.Data;
                Group_UITable_Data = app.Group_UITable.Data; 
                save([app.savpath filename '.mat'],'all_parms','Parameter_UITable_Data','Volume_UITable_Data','Group_UITable_Data','-mat');                
            end


        end

        % Menu selected function: LoadstateMenu
        function LoadstateMenuSelected(app, event)
            [filename,foldername,idx] = uigetfile({'*.mat'},'Select a .mat file previously created using the save state button',app.savpath);
            if idx
                tmp = load([foldername filename]);
                app.allparms = tmp.all_parms;
                app.update_parameter;
                app.update_group; 
                app.Parameter_UITable.Data = tmp.Parameter_UITable_Data;
                app.Volume_UITable.Data = tmp.Volume_UITable_Data;
                app.Group_UITable.Data = tmp.Group_UITable_Data;

                [n,~]=size(tmp.Group_UITable_Data);
                for k=1:1:n
                    c = str2num(cell2mat(tmp.Group_UITable_Data(k,3)));
                    s = uistyle('BackgroundColor',c);
                    addStyle(app.Group_UITable,s,'cell',[k,3]);
                end
                % UI enable
                app.SavestateMenu.Enable = "on";
                app.SavematrixButton.Enable = "on";
                app.PlotallselectedparametersButton.Enable = "on";
                app.PlottortuosityfporosityButton.Enable = "on";
                app.PlotSpDButton.Enable = "on";     
                app.PlotcorrelationmapButton.Enable = "on";    
                app.SavecorrelationmapButton.Enable = "on";                    
            end
        end

        % Button pushed function: 
        % LoadparametersfromoneorseveralvolumesButton
        function LoadparametersfromoneorseveralvolumesButtonPushed(app, event)
            % uigetfile has the 'MultiSelect' option, uigetdir has not... odd ommission. uipickfiles is used to remedy it.
            % Douglas Schwarz (2022). uipickfiles: uigetfile on steroids (https://www.mathworks.com/matlabcentral/fileexchange/10867-uipickfiles-uigetfile-on-steroids), MATLAB Central File Exchange. Retrieved September 29, 2022.
            % Lisence in 'Third-party licenses' folder
            str_dialogbox = 'Select the main folder of each volume for which your want to load results.';
            Desktop_path=winqueryreg('HKEY_CURRENT_USER', 'Software\Microsoft\Windows\CurrentVersion\Explorer\Shell Folders', 'Desktop');
            pathnames = uipickfiles('FilterSpec',Desktop_path,'Prompt',str_dialogbox);
         
            if ~iscell(pathnames)
                % User clicked cancel button or closed the dialog box
            else
                n_folder = length(pathnames);
                kdetected=0;
                for k=1:1:n_folder
                    clear results_filenames;
                    volume_main_folder = char(pathnames(k));
                    current_folder = [volume_main_folder app.folder_separation 'Correlation' app.folder_separation];
                    % Find all mat files that start with 'Correlation'
                    MyFolderInfo=dir(current_folder);
                    n_files=length(MyFolderInfo);
                    detected_files=0;
                    for k_file=1:1:n_files
                        [~,filename,ext] = fileparts(MyFolderInfo(k_file).name);
                        if strcmp(ext,'.mat')
                            sub_name = filename(1:11);
                            if strcmp(sub_name,'Correlation')
                                detected_files=detected_files+1;
                                results_filenames(detected_files)={[filename '.mat']};
                            end
                        end
                    end
                    if detected_files
                        kdetected = kdetected+1;
                        p(kdetected).volume_main_folder = volume_main_folder;
                        p(kdetected).results_filenames = results_filenames;
                    else
                        tmp = find(volume_main_folder==app.folder_separation);
                        volume_name = volume_main_folder(tmp(end)+1:length(volume_main_folder));
                        msgbox({volume_name,'Please select this <folder>: ...\<folder>\Correlation\','The subfolder Correlation must also contain files named Correlation_<property name>.mat'}, 'Incorrect folder','warn');
                    end

                end

                if kdetected>=1
                    p(1).template = app.template;
                    app.PropertiesApp = Properties_correlation_loadparameters(app,p);
                end
            end

        end

        % Cell edit callback: Volume_UITable
        function Volume_UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            oldData = event.PreviousData;  
            if indices(2)==5
                newData = str2num(newData);
                if isempty(newData)
                    app.Volume_UITable.Data(indices(1),5) = {oldData};
                else
                    possiblechoices = cell2mat(app.Volume_UITable.Data(indices(1),4));
                    idx = find(possiblechoices==':');
                    possiblechoices=str2num(possiblechoices(idx+2:end));                    
                    if sum(ismember(newData,possiblechoices))<length(newData)
                        app.Volume_UITable.Data(indices(1),5) = {oldData};
                    end
                end
            elseif indices(2)==3
                if isnumeric(newData) && round(newData)==newData && newData>=1
                    app.update_group;
                else
                    app.Volume_UITable.Data(indices(1),indices(1)) = {oldData};
                end      
            elseif indices(2)==6
                app.update_group;
            end
        end

        % Cell edit callback: Group_UITable
        function Group_UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            oldData = event.PreviousData;

            if indices(2)==4 % Line style
                idx = find(strcmp(app.linstylelist,newData));
                if isempty(idx)
                    app.Group_UITable.Data(indices(1),indices(2)) = {oldData};
                    msgbox(['Possible entries: '  app.linstylelist], 'Incorrect choice','warn');
                end
            elseif indices(2)==5 || indices(2)==7 % Line width
                if ~isnumeric(newData) || isnan(newData) || newData<=0
                    app.Group_UITable.Data(indices(1),indices(2)) = {oldData};
                end
            elseif indices(2)==6 % Marker
                idx = find(strcmp(app.markerlist,newData));
                if isempty(idx)
                    app.Group_UITable.Data(indices(1),indices(2)) = {oldData};
                    msgbox(['Possible entries: '  app.markerlist], 'Incorrect choice','warn');
                end
            end            
        end

        % Cell selection callback: Group_UITable
        function Group_UITableCellSelection(app, event)
            indices = event.Indices;
            if ~isempty(indices)
                if indices(2)==3 % color
                    c = uisetcolor;
                    if length(c)==3 % RGB
                        app.col_(indices(1),:) = c;
                        s = uistyle('BackgroundColor',c);
                        addStyle(app.Group_UITable,s,'cell',[indices(1),3]);
                        %app.Group_UITable.Data(indices(1),3) = {num2str(c,'%1.3f')};
                        app.Group_UITable.Data(indices(1),3) = {[num2str(c(1),'%1.3f') ' ' num2str(c(2),'%1.3f') ' ' num2str(c(3),'%1.3f')]};
                    end
                end
            end
        end

        % Button pushed function: PlotallselectedparametersButton
        function PlotallselectedparametersButtonPushed(app, event)
            plotper = app.PlotcorrelateperDropDown.Value;

            volume_choice = app.Volume_UITable.Data;
            if isempty(volume_choice)
                msgbox('No parameters or volumes selected', 'Not enough data','warn');
                return
            end
            id0 = find(~cell2mat(volume_choice(:,6)));
            volume_choice(id0,:)=[];

            group_choice = app.Group_UITable.Data;
            id0 = find(~cell2mat(group_choice(:,9)));
            group_choice(id0,:)=[]; 

            % Create matrix
            ParmsMatrix = app.CreateMatrix;
            if isempty(ParmsMatrix)
                msgbox('No parameters or volumes selected', 'Not enough data','warn');
                return
            end

            % Create figure
            pformat.fontname = app.FontDropDown.Value;
            pformat.size.sgtitle_main = app.TitlefontsizeEditField.Value;
            pformat.size.sgtitle_sub = app.SubtitlefontsizeEditField.Value;
            pformat.size.axes = app.AxefontsizeEditField.Value;
            pformat.size.legend = app.LegendfontsizeEditField.Value;
            pformat.linewidth = app.LineWidthEditField.Value;
            pformat.markersize = app.MarkersizeEditField.Value;
            pformat.text = app.AddtextvolumenameCheckBox.Value;
            pformat.textsize = app.TextfontsizeEditField.Value;
            if app.GridCheckBox.Value
                pformat.grid = 'on';
            else
                pformat.grid = 'off';
            end
            if app.MinorgridCheckBox.Value
                pformat.minorgrid = 'on';
            else
                pformat.minorgrid = 'off';
            end            
            pformat.legendposition = app.LegendlocationDropDown.Value; % South East
            pformat.legendcolumn = app.NumberofcolumnEditField.Value;            
            figure_correlation_matrix(ParmsMatrix, volume_choice, group_choice, plotper, pformat);
        end

        % Button pushed function: SavematrixButton
        function SavematrixButtonPushed(app, event)
            % Create matrix
            ParmsMatrix = app.CreateMatrix;
            if ~isempty(ParmsMatrix)
                % Save it
                [filename,foldername,idx] = uiputfile([app.savpath],'Enter filename without extension');
                if idx
                    [ ~ , filename , ~] = fileparts(filename); % No extension
                    app.savpath = foldername; % For future save
                    DATA_writetable.sheet(1).name='Correlation matrix';
                    DATA_writetable.sheet(1).table=ParmsMatrix;
                    Function_Writetable(foldername,filename,DATA_writetable);
                end
            end
        end

        % Menu selected function: ResetstateMenu
        function ResetstateMenuSelected(app, event)
            app.startupFcn;
            app.allparms = [];
            app.Volume_UITable.Data = [];
            app.Group_UITable.Data = [];   
            % UI enable
            app.SavestateMenu.Enable = "off";
            app.SavematrixButton.Enable = "off";
            app.PlotallselectedparametersButton.Enable = "off";
            app.PlottortuosityfporosityButton.Enable = "off";
            app.PlotSpDButton.Enable = "off";
            app.PlotcorrelationmapButton.Enable = "off";
            app.SavecorrelationmapButton.Enable = "off";            
        end

        % Button pushed function: PlottortuosityfporosityButton
        function PlottortuosityfporosityButtonPushed(app, event)
            volume_choice = app.Volume_UITable.Data;
            if isempty(volume_choice)
                msgbox('No parameters or volumes selected', 'Not enough data','warn');
                return
            end
            id0 = find(~cell2mat(volume_choice(:,6)));
            volume_choice(id0,:)=[];

            group_choice = app.Group_UITable.Data;
            id0 = find(~cell2mat(group_choice(:,9)));
            group_choice(id0,:)=[]; 

            % Create matrix
            ParmsMatrix = app.CreateMatrix;
            if isempty(ParmsMatrix)
                msgbox('No parameters or volumes selected', 'Not enough data','warn');
                return
            end            

            % Select
            pid = app.PorosityEditField.Value;
            tid = app.TortuosityEditField.Value;
            if pid~=tid
                porosityname = app.Parameter_UITable.Data(pid,2);
                tortuosityname = app.Parameter_UITable.Data(tid,2);
                names = ParmsMatrix.Parameter;
                pid = find(strcmp(names,porosityname ));
                tid = find(strcmp(names,tortuosityname ));
                if ~isempty(pid) && ~isempty(tid)
                    eps_tmp = ParmsMatrix(pid,3:end);
                    tau_tmp = ParmsMatrix(tid,3:end);
                    eps = NaN(width(eps_tmp),1);
                    tau = NaN(width(eps_tmp),1);
                    for k=1:1:length(eps)
                        v = eps_tmp(1,k).Variables;
                        if iscell(v)
                            v = cell2mat(v);
                            if ~isempty(v)
                                eps(k) = v;
                            end
                        else
                            eps(k) = v;
                        end
                        v = tau_tmp(1,k).Variables;
                        if iscell(v)
                            v = cell2mat(v);
                            if ~isempty(v)
                                tau(k) = v;
                            end
                        else
                            tau(k) = v;
                        end                        
                    end

                    % Create figure
                    pformat.fontname = app.FontDropDown.Value;
                    pformat.size.sgtitle_main = app.TitlefontsizeEditField.Value;
                    pformat.size.sgtitle_sub = app.SubtitlefontsizeEditField.Value;
                    pformat.size.axes = app.AxefontsizeEditField.Value;
                    pformat.size.legend = app.LegendfontsizeEditField.Value;
                    pformat.linewidth = app.LineWidthEditField.Value;
                    pformat.markersize = app.MarkersizeEditField.Value;
                    pformat.text = app.AddtextvolumenameCheckBox.Value;
                    pformat.textsize = app.TextfontsizeEditField.Value;
                    pformat.referenceline = app.AddreferencelinesCheckBox.Value;
                    pformat.thlimit = app.AddtheoriticallimitCheckBox.Value;
                    if app.GridCheckBox.Value
                        pformat.grid = 'on';
                    else
                        pformat.grid = 'off';
                    end
                    if app.MinorgridCheckBox.Value
                        pformat.minorgrid = 'on';
                    else
                        pformat.minorgrid = 'off';
                    end
                    pformat.legendposition = app.LegendlocationDropDown.Value; % South East
                    pformat.legendcolumn = app.NumberofcolumnEditField.Value;
                    form = app.FormDropDown.Value;
                    names = ParmsMatrix.Properties.VariableNames(3:end);
                    plotper = app.PlotcorrelateperDropDown.Value;
                    Function_PlotTortuosityPorosity_fitting(eps, tau, names, plotper, volume_choice, group_choice, form, pformat);
                end
            end  
        end

        % Value changed function: PorosityEditField
        function PorosityEditFieldValueChanged(app, event)
            por = app.PorosityEditField.Value;
            tau = app.TortuosityEditField.Value;
            if por==tau
                app.PorosityEditField.Value = event.PreviousValue;
            end            
        end

        % Value changed function: TortuosityEditField
        function TortuosityEditFieldValueChanged(app, event)
            por = app.PorosityEditField.Value;
            tau = app.TortuosityEditField.Value;
            if por==tau
                app.TortuosityEditField.Value = event.PreviousValue;
            end                  
        end

        % Value changed function: AddtextvolumenameCheckBox
        function AddtextvolumenameCheckBoxValueChanged(app, event)
            value = app.AddtextvolumenameCheckBox.Value;
            if value
                app.TextfontsizeEditField.Enable = "on";
                app.TextfontsizeEditFieldLabel.Enable = "on";
            else
                app.TextfontsizeEditField.Enable = "off";
                app.TextfontsizeEditFieldLabel.Enable = "off";
            end
        end

        % Value changed function: PlotcorrelateperDropDown
        function PlotcorrelateperDropDownValueChanged(app, event)
            value = app.PlotcorrelateperDropDown.Value;
            if strcmp(value,'volume')
                app.LineWidthEditField.Enable = "on";
                app.MarkersizeEditField.Enable = "on";
                app.LineWidthEditFieldLabel.Enable = "on";
                app.MarkersizeEditFieldLabel.Enable = "on";
            else
                app.LineWidthEditField.Enable = "off";
                app.MarkersizeEditField.Enable = "off";
                app.LineWidthEditFieldLabel.Enable = "off";
                app.MarkersizeEditFieldLabel.Enable = "off";
            end                        
        end

        % Button pushed function: PlotSpDButton
        function PlotSpDButtonPushed(app, event)
            volume_choice = app.Volume_UITable.Data;
            if isempty(volume_choice)
                msgbox('No parameters or volumes selected', 'Not enough data','warn');
                return
            end
            id0 = find(~cell2mat(volume_choice(:,6)));
            volume_choice(id0,:)=[];

            group_choice = app.Group_UITable.Data;
            id0 = find(~cell2mat(group_choice(:,9)));
            group_choice(id0,:)=[]; 

            % Create matrix
            ParmsMatrix = app.CreateMatrix;
            if isempty(ParmsMatrix)
                msgbox('No parameters or volumes selected', 'Not enough data','warn');
                return
            end            

            % Select
            pid = app.PorosityEditField.Value;
            pvfid = app.ParticlevolumefractionEditField.Value;
            pdid = app.ParticlediameterEditField.Value;
            spid = app.SpecificsurfaceareaEditField.Value;
            if pid~=pvfid && pid~=pdid && pid~=spid
                porosityname = app.Parameter_UITable.Data(pid,2);
                particlevolumefractionname = app.Parameter_UITable.Data(pvfid,2);
                particlediametername = app.Parameter_UITable.Data(pdid,2);     
                specificsurfaceareaname = app.Parameter_UITable.Data(spid,2); 
                spunit = app.Parameter_UITable.Data(spid,3); 
                names = ParmsMatrix.Parameter;
                pid = find(strcmp(names,porosityname ));
                pvfid = find(strcmp(names,particlevolumefractionname ));
                pdid = find(strcmp(names,particlediametername ));
                spid = find(strcmp(names,specificsurfaceareaname ));
                if ~isempty(pid) && ~isempty(pvfid) && ~isempty(pdid) && ~isempty(spid)
                    eps_tmp = ParmsMatrix(pid,3:end);
                    pv_tmp = ParmsMatrix(pvfid,3:end);
                    pd_tmp = ParmsMatrix(pdid,3:end);
                    sp_tmp = ParmsMatrix(spid,3:end);
                    eps = NaN(width(eps_tmp),1);
                    pv = NaN(width(eps_tmp),1);
                    pd = NaN(width(eps_tmp),1);             
                    sp = NaN(width(eps_tmp),1);
                    for k=1:1:length(eps)
                        v = eps_tmp(1,k).Variables;
                        if iscell(v)
                            v = cell2mat(v);
                            if ~isempty(v)
                                eps(k) = v;
                            end
                        else
                            eps(k) = v;
                        end
                        v = pv_tmp(1,k).Variables;
                        if iscell(v)
                            v = cell2mat(v);
                            if ~isempty(v)
                                pv(k) = v;
                            end
                        else
                            pv(k) = v;
                        end   
                        v = pd_tmp(1,k).Variables;
                        if iscell(v)
                            v = cell2mat(v);
                            if ~isempty(v)
                                pd(k) = v;
                            end
                        else
                            pd(k) = v;
                        end    
                        v = sp_tmp(1,k).Variables;
                        if iscell(v)
                            v = cell2mat(v);
                            if ~isempty(v)
                                sp(k) = v;
                            end
                        else
                            sp(k) = v;
                        end                          
                    end

                    % Create figure
                    pformat.fontname = app.FontDropDown.Value;
                    pformat.size.sgtitle_main = app.TitlefontsizeEditField.Value;
                    pformat.size.sgtitle_sub = app.SubtitlefontsizeEditField.Value;
                    pformat.size.axes = app.AxefontsizeEditField.Value;
                    pformat.size.legend = app.LegendfontsizeEditField.Value;
                    pformat.linewidth = app.LineWidthEditField.Value;
                    pformat.markersize = app.MarkersizeEditField.Value;
                    pformat.text = app.AddtextvolumenameCheckBox.Value;
                    pformat.textsize = app.TextfontsizeEditField.Value;
                    pformat.referenceline = app.AddreferencelinesCheckBox.Value;
                    pformat.thlimit = app.AddtheoriticallimitCheckBox.Value;
                    polyfit_order = app.PolynomialorderEditField.Value;
                    if app.GridCheckBox.Value
                        pformat.grid = 'on';
                    else
                        pformat.grid = 'off';
                    end
                    if app.MinorgridCheckBox.Value
                        pformat.minorgrid = 'on';
                    else
                        pformat.minorgrid = 'off';
                    end
                    pformat.legendposition = app.LegendlocationDropDown.Value; % South East
                    pformat.legendcolumn = app.NumberofcolumnEditField.Value;
                    form = app.AsfunctionofDropDown.Value;
                    names = ParmsMatrix.Properties.VariableNames(3:end);
                    plotper = app.PlotcorrelateperDropDown.Value;
                    Function_PlotSpVdDiameter_fitting(eps, pv, pd, sp, spunit, polyfit_order, names, plotper, volume_choice, group_choice, form, pformat);
                end
            end  
        end

        % Button pushed function: PlotcorrelationmapButton
        function PlotcorrelationmapButtonPushed(app, event)
            % Create matrix
            [CorrMatrix,Parnames,corrmetric,plotper] = app.Create_correlationmatrix;
                     
            pformat.fontname = app.FontDropDown.Value;
            pformat.size.sgtitle_main = app.TitlefontsizeEditField.Value;
            pformat.size.sgtitle_sub = app.SubtitlefontsizeEditField.Value;
            pformat.size.axes = app.AxefontsizeEditField.Value;
            pformat.showcellvalue = app.ShowvalueincellCheckBox.Value;
            Function_plotcorrelationheatmap(CorrMatrix,Parnames,corrmetric,plotper,pformat);


        end

        % Button pushed function: SavecorrelationmapButton
        function SavecorrelationmapButtonPushed(app, event)
            % Create matrix
            [CorrMatrix,Parnames,corrmetric,plotper] = app.Create_correlationmatrix;            
            % Save it
            [filename,foldername,idx] = uiputfile([app.savpath],'Enter filename without extension');
            if idx
                % Make table
                CorrTable = array2table(CorrMatrix,"VariableNames",Parnames);      
                ParTable = table(Parnames,'VariableNames',{'Parameters'});
                CorrTable = [ParTable CorrTable];
                [ ~ , filename , ~] = fileparts(filename); % No extension
                app.savpath = foldername; % For future save
                DATA_writetable.sheet(1).name=[corrmetric ', per ' plotper];
                DATA_writetable.sheet(1).table=CorrTable;
                Function_Writetable(foldername,filename,DATA_writetable);
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.propertiescorrelationUIFigure = uifigure('Visible', 'off');
            app.propertiescorrelationUIFigure.Name = 'properties correlation';
            app.propertiescorrelationUIFigure.Icon = 'Correlation.png';
            app.SavestateMenu = uimenu(app.propertiescorrelationUIFigure);
            app.SavestateMenu.MenuSelectedFcn = createCallbackFcn(app, @SavestateMenuSelected, true);
            app.SavestateMenu.Enable = 'off';
            app.SavestateMenu.Text = 'Save state';
            app.LoadstateMenu = uimenu(app.propertiescorrelationUIFigure);
            app.LoadstateMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadstateMenuSelected, true);
            app.LoadstateMenu.Text = 'Load state';
            app.ResetstateMenu = uimenu(app.propertiescorrelationUIFigure);
            app.ResetstateMenu.MenuSelectedFcn = createCallbackFcn(app, @ResetstateMenuSelected, true);
            app.ResetstateMenu.Text = 'Reset state';
            app.Parameter_UITable = uitable(app.propertiescorrelationUIFigure);
            app.Parameter_UITable.ColumnName = {'#'; 'Name used in figure'; 'Unit name'; 'Number of values'; 'Plot'};
            app.Parameter_UITable.RowName = {};
            app.Parameter_UITable.ColumnEditable = [false true true false true];
            app.InstructionsTextAreaLabel = uilabel(app.propertiescorrelationUIFigure);
            app.InstructionsTextAreaLabel.Text = 'Instructions';
            app.InstructionsTextArea = uitextarea(app.propertiescorrelationUIFigure);
            app.InstructionsTextArea.Editable = 'off';
            app.InstructionsTextArea.Value = {'You must first generate some data using the characterization module on several volumes.'; '- Once this preliminary step done, you can import the results: click on "1) Load parameters from one or several volumes". The folder to import for each volume is the main folder (<main folder>\Correlation). You can import all volumes in one step, or repeat the import process several times. Follow instructions as they are provided. Alternatively, if you have previously saved a state, you can import it using the menu bar (and import new volumes in addition if you choose to).'; '- Once you have imported all your volumes, in the top left table write the parameter and unit names (e.g., "particle size" and "um") and select which one you would like to plot. "Number of values" indicate how many values have been imported for each parameter among the different volumes. The number # (first column) associated with each parameter should be the same with the one you have used during the import step to keep things coherent.'; '- On the top right table, for each volume write the name you would like to use and assign a group number ("Group Id").  The group id can be used to group together several volumes that belong to the same material family. For instance, if you have 4 cathode volumes and 6 anode volumes, you can assign group id 1 for all the cathodes, and group id 2 for all the anodes. Group id is optional. You can also select which parameters you would like to plot per volume ("Parameter choice", with the numbers corresponding to the number # used in the top left table) and which volumes you would like to plot ("Plot"). If you have decided not to plot one parameter in the top left table, there is no need to modify the parameter choice in the top right table for each volume for this particular parameter.'; '- The bottom right table is used to configure the plotting legend and format for the groups, if you have chosen to use the group id feature.'; '- In the "Custom correlation" panel, match the number # with the number # you have used in the top left table for the relevant parameters. For instance if you have used #4 for the porosity parameter in the top left table, use also 4 in the custom correlation panel for the porosity. You can ignore the number # for the parameters you did not import.'; '- The "Format options" panel can be used to tailor the figure format to your like.'; ''; 'To avoid re-doing the whole process for future analysis, you can save your state in the menu bar.'; ''; '- You can save the values of each parameter for each volume (2D table) in an excel file: click on "Save matrix".'; '- Figures can be plot either per volume or per group. Some correlations are more relevant per volume (generic) or per group (material-specific).'; '- The "Heat map"  is used to plot correlation coefficient.'; '- The "plot" panel is used to plot all selected parameters as function of all selected parameters for all selected volumes (or selected groups of volumes). In addition you can plot and fit a preselected set of coefficients (e.g., tortuosity as function of porosity. For this to work, you must identify first these parameters in the "custom correlation" left panel).'};
            app.LoadparametersfromoneorseveralvolumesButton = uibutton(app.propertiescorrelationUIFigure, 'push');
            app.LoadparametersfromoneorseveralvolumesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadparametersfromoneorseveralvolumesButtonPushed, true);
            app.LoadparametersfromoneorseveralvolumesButton.Tooltip = {'You can repeat the import process several times. If you re-select a previously imported volume, it will be overwritten.'};
            app.LoadparametersfromoneorseveralvolumesButton.Text = '1) Load parameters from one or several volumes';
            app.Volume_UITable = uitable(app.propertiescorrelationUIFigure);
            app.Volume_UITable.ColumnName = {'Volume name'; 'Name used in figure'; 'Group Id'; 'Parameters loaded'; 'Parameter choice'; 'Plot'};
            app.Volume_UITable.RowName = {};
            app.Volume_UITable.ColumnEditable = [false true true false true true];
            app.Volume_UITable.CellEditCallback = createCallbackFcn(app, @Volume_UITableCellEdit, true);
            app.Label = uilabel(app.propertiescorrelationUIFigure);
            app.Label.Text = '2) For each volume, set a group Id and choose which parameter to plot ("Parameter choice")  and if you want to plot this volume.';
            app.Group_UITable = uitable(app.propertiescorrelationUIFigure);
            app.Group_UITable.ColumnName = {'Group Id'; 'Name used in figure'; 'Color'; 'LineStyle'; 'LineWidth'; 'Marker'; 'MarkerSize'; 'Filled Marker'; 'Plot'};
            app.Group_UITable.RowName = {};
            app.Group_UITable.ColumnEditable = [false true true true true true true true true];
            app.Group_UITable.CellEditCallback = createCallbackFcn(app, @Group_UITableCellEdit, true);
            app.Group_UITable.CellSelectionCallback = createCallbackFcn(app, @Group_UITableCellSelection, true);
            app.Group_UITable.Tooltip = {'Marker choice: ''o'',''square'',''diamond'',''^'',''v'',''<'',''pentagram'',''hexagram'',''+'',''*'',''.'',''x'',''_'',''|'''; ''; 'LineStyle choice : ''none'',''-'',''--'','':'',''-.'''};
            app.Label_2 = uilabel(app.propertiescorrelationUIFigure);
            app.Label_2.Text = '3) For each group id, set plot format and if you want to plot this group.';
            app.PlotcorrelateperDropDownLabel = uilabel(app.propertiescorrelationUIFigure);
            app.PlotcorrelateperDropDownLabel.Text = 'Plot/correlate per';
            app.PlotcorrelateperDropDown = uidropdown(app.propertiescorrelationUIFigure);
            app.PlotcorrelateperDropDown.Items = {'volume', 'group'};
            app.PlotcorrelateperDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotcorrelateperDropDownValueChanged, true);
            app.PlotcorrelateperDropDown.Value = 'volume';
            app.SavematrixButton = uibutton(app.propertiescorrelationUIFigure, 'push');
            app.SavematrixButton.ButtonPushedFcn = createCallbackFcn(app, @SavematrixButtonPushed, true);
            app.SavematrixButton.Enable = 'off';
            app.SavematrixButton.Text = 'Save matrix';
            app.FormatoptionsPanel = uipanel(app.propertiescorrelationUIFigure);
            app.FormatoptionsPanel.Title = 'Format options';
            app.FontDropDownLabel = uilabel(app.FormatoptionsPanel);
            app.FontDropDownLabel.Text = 'Font';
            app.FontDropDown = uidropdown(app.FormatoptionsPanel);
            app.TitlefontsizeEditFieldLabel = uilabel(app.FormatoptionsPanel);
            app.TitlefontsizeEditFieldLabel.Text = 'Title font size';
            app.TitlefontsizeEditField = uieditfield(app.FormatoptionsPanel, 'numeric');
            app.TitlefontsizeEditField.Limits = [1 Inf];
            app.TitlefontsizeEditField.RoundFractionalValues = 'on';
            app.TitlefontsizeEditField.Value = 20;
            app.SubtitlefontsizeEditFieldLabel = uilabel(app.FormatoptionsPanel);
            app.SubtitlefontsizeEditFieldLabel.Text = 'Subtitle font size';
            app.SubtitlefontsizeEditField = uieditfield(app.FormatoptionsPanel, 'numeric');
            app.SubtitlefontsizeEditField.Limits = [1 Inf];
            app.SubtitlefontsizeEditField.RoundFractionalValues = 'on';
            app.SubtitlefontsizeEditField.Value = 16;
            app.AxefontsizeEditFieldLabel = uilabel(app.FormatoptionsPanel);
            app.AxefontsizeEditFieldLabel.Text = 'Axe font size';
            app.AxefontsizeEditField = uieditfield(app.FormatoptionsPanel, 'numeric');
            app.AxefontsizeEditField.Limits = [1 Inf];
            app.AxefontsizeEditField.RoundFractionalValues = 'on';
            app.AxefontsizeEditField.Value = 10;
            app.LegendfontsizeEditFieldLabel = uilabel(app.FormatoptionsPanel);
            app.LegendfontsizeEditFieldLabel.Text = 'Legend font size';
            app.LegendfontsizeEditField = uieditfield(app.FormatoptionsPanel, 'numeric');
            app.LegendfontsizeEditField.Limits = [1 Inf];
            app.LegendfontsizeEditField.RoundFractionalValues = 'on';
            app.LegendfontsizeEditField.Value = 10;
            app.LineWidthEditFieldLabel = uilabel(app.FormatoptionsPanel);
            app.LineWidthEditFieldLabel.Text = 'LineWidth';
            app.LineWidthEditField = uieditfield(app.FormatoptionsPanel, 'numeric');
            app.LineWidthEditField.Limits = [0.1 Inf];
            app.LineWidthEditField.Value = 1;
            app.MarkersizeEditFieldLabel = uilabel(app.FormatoptionsPanel);
            app.MarkersizeEditFieldLabel.Text = 'Markersize';
            app.MarkersizeEditField = uieditfield(app.FormatoptionsPanel, 'numeric');
            app.MarkersizeEditField.Limits = [1 Inf];
            app.MarkersizeEditField.RoundFractionalValues = 'on';
            app.MarkersizeEditField.Value = 8;
            app.ForplotpervolumeonlyLabel = uilabel(app.FormatoptionsPanel);
            app.ForplotpervolumeonlyLabel.Text = 'For ''plot per volume'' only:';
            app.GridCheckBox = uicheckbox(app.FormatoptionsPanel);
            app.GridCheckBox.Text = 'Grid';
            app.GridCheckBox.Value = true;
            app.MinorgridCheckBox = uicheckbox(app.FormatoptionsPanel);
            app.MinorgridCheckBox.Text = 'Minor grid';
            app.MinorgridCheckBox.Value = true;
            app.LegendlocationDropDownLabel = uilabel(app.FormatoptionsPanel);
            app.LegendlocationDropDownLabel.Text = 'Legend location';
            app.LegendlocationDropDown = uidropdown(app.FormatoptionsPanel);
            app.LegendlocationDropDown.Items = {'South', 'East'};
            app.LegendlocationDropDown.Value = 'South';
            app.NumberofcolumnEditFieldLabel = uilabel(app.FormatoptionsPanel);
            app.NumberofcolumnEditFieldLabel.Text = 'Number of  column';
            app.NumberofcolumnEditField = uieditfield(app.FormatoptionsPanel, 'numeric');
            app.NumberofcolumnEditField.Value = 8;
            app.AddtextvolumenameCheckBox = uicheckbox(app.FormatoptionsPanel);
            app.AddtextvolumenameCheckBox.ValueChangedFcn = createCallbackFcn(app, @AddtextvolumenameCheckBoxValueChanged, true);
            app.AddtextvolumenameCheckBox.Text = 'Add text volume name';
            app.TextfontsizeEditFieldLabel = uilabel(app.FormatoptionsPanel);
            app.TextfontsizeEditFieldLabel.Enable = 'off';
            app.TextfontsizeEditFieldLabel.Text = 'Text font size';
            app.TextfontsizeEditField = uieditfield(app.FormatoptionsPanel, 'numeric');
            app.TextfontsizeEditField.Limits = [1 Inf];
            app.TextfontsizeEditField.RoundFractionalValues = 'on';
            app.TextfontsizeEditField.Enable = 'off';
            app.TextfontsizeEditField.Value = 8;
            app.CustomcorrelationPanel = uipanel(app.propertiescorrelationUIFigure);
            app.CustomcorrelationPanel.Title = 'Custom correlation';
            app.PorosityEditFieldLabel = uilabel(app.CustomcorrelationPanel);
            app.PorosityEditFieldLabel.Text = 'Porosity #';
            app.PorosityEditField = uieditfield(app.CustomcorrelationPanel, 'numeric');
            app.PorosityEditField.Limits = [1 Inf];
            app.PorosityEditField.RoundFractionalValues = 'on';
            app.PorosityEditField.ValueChangedFcn = createCallbackFcn(app, @PorosityEditFieldValueChanged, true);
            app.PorosityEditField.Tooltip = {'Enter the id number # you have associated with porosity in the above parameter table.'};
            app.PorosityEditField.Value = 1;
            app.TortuosityEditFieldLabel = uilabel(app.CustomcorrelationPanel);
            app.TortuosityEditFieldLabel.Text = 'Tortuosity #';
            app.TortuosityEditField = uieditfield(app.CustomcorrelationPanel, 'numeric');
            app.TortuosityEditField.Limits = [1 Inf];
            app.TortuosityEditField.RoundFractionalValues = 'on';
            app.TortuosityEditField.ValueChangedFcn = createCallbackFcn(app, @TortuosityEditFieldValueChanged, true);
            app.TortuosityEditField.Tooltip = {'Enter the id number # you have associated with tortuosity factor in the above parameter table.'};
            app.TortuosityEditField.Value = 3;
            app.FormDropDownLabel = uilabel(app.CustomcorrelationPanel);
            app.FormDropDownLabel.Text = 'Form';
            app.FormDropDown = uidropdown(app.CustomcorrelationPanel);
            app.FormDropDown.Items = {'Scaled Bruggeman (Thorat, 2009)'};
            app.FormDropDown.Tooltip = {'Scaled Bruggeman: tau = alpha * epsilon^(-gamma)'};
            app.FormDropDown.Value = 'Scaled Bruggeman (Thorat, 2009)';
            app.TortuosityfactorporositycorrelationLabel = uilabel(app.CustomcorrelationPanel);
            app.TortuosityfactorporositycorrelationLabel.Text = 'Tortuosity factor - porosity correlation';
            app.ParticlevolumefractionEditFieldLabel = uilabel(app.CustomcorrelationPanel);
            app.ParticlevolumefractionEditFieldLabel.Text = 'Particle volume fraction #';
            app.ParticlevolumefractionEditField = uieditfield(app.CustomcorrelationPanel, 'numeric');
            app.ParticlevolumefractionEditField.Limits = [1 Inf];
            app.ParticlevolumefractionEditField.RoundFractionalValues = 'on';
            app.ParticlevolumefractionEditField.Tooltip = {'Enter the id number # you have associated with particle volume fraction in the above parameter table.'};
            app.ParticlevolumefractionEditField.Value = 2;
            app.ParticlediameterEditFieldLabel = uilabel(app.CustomcorrelationPanel);
            app.ParticlediameterEditFieldLabel.Text = 'Particle diameter #';
            app.ParticlediameterEditField = uieditfield(app.CustomcorrelationPanel, 'numeric');
            app.ParticlediameterEditField.Limits = [1 Inf];
            app.ParticlediameterEditField.RoundFractionalValues = 'on';
            app.ParticlediameterEditField.Tooltip = {'Enter the id number # you have associated with particle diameter in the above parameter table.'};
            app.ParticlediameterEditField.Value = 4;
            app.AsfunctionofDropDownLabel = uilabel(app.CustomcorrelationPanel);
            app.AsfunctionofDropDownLabel.Text = 'As function of ';
            app.AsfunctionofDropDown = uidropdown(app.CustomcorrelationPanel);
            app.AsfunctionofDropDown.Items = {'Porosity', 'Particle volume fraction'};
            app.AsfunctionofDropDown.Tooltip = {''};
            app.AsfunctionofDropDown.Value = 'Porosity';
            app.SpecificsurfaceareaEditFieldLabel = uilabel(app.CustomcorrelationPanel);
            app.SpecificsurfaceareaEditFieldLabel.Text = 'Specific surface area';
            app.SpecificsurfaceareaEditField = uieditfield(app.CustomcorrelationPanel, 'numeric');
            app.SpecificsurfaceareaEditField.Limits = [1 Inf];
            app.SpecificsurfaceareaEditField.RoundFractionalValues = 'on';
            app.SpecificsurfaceareaEditField.Tooltip = {'Enter the id number # you have associated with specific surface area in the above parameter table.'};
            app.SpecificsurfaceareaEditField.Value = 5;
            app.PolynomialorderEditFieldLabel = uilabel(app.CustomcorrelationPanel);
            app.PolynomialorderEditFieldLabel.Text = 'Polynomial order';
            app.PolynomialorderEditField = uieditfield(app.CustomcorrelationPanel, 'numeric');
            app.PolynomialorderEditField.Limits = [1 Inf];
            app.PolynomialorderEditField.RoundFractionalValues = 'on';
            app.PolynomialorderEditField.Value = 3;
            app.AddreferencelinesCheckBox = uicheckbox(app.CustomcorrelationPanel);
            app.AddreferencelinesCheckBox.Text = 'Add reference lines';
            app.AddreferencelinesCheckBox.Value = true;
            app.AddtheoriticallimitCheckBox = uicheckbox(app.CustomcorrelationPanel);
            app.AddtheoriticallimitCheckBox.Tooltip = {'Tortuosity = 1 for porosity = 1, Specific surface area = 0 for particle volume fraction = 0 and 1.'};
            app.AddtheoriticallimitCheckBox.Text = 'Add theoritical limit';
            app.AddtheoriticallimitCheckBox.Value = true;
            app.SpecificsurfaceareaporosityradiuscorrelationLabel = uilabel(app.CustomcorrelationPanel);
            app.SpecificsurfaceareaporosityradiuscorrelationLabel.Text = 'Specific surface area, porosity, radius correlation';
            app.HeatmapPanel = uipanel(app.propertiescorrelationUIFigure);
            app.HeatmapPanel.Title = 'Heat map';
            app.CorrelationmetricDropDownLabel = uilabel(app.HeatmapPanel);
            app.CorrelationmetricDropDownLabel.Text = 'Correlation metric';
            app.CorrelationmetricDropDown = uidropdown(app.HeatmapPanel);
            app.CorrelationmetricDropDown.Items = {'Kendall'};
            app.CorrelationmetricDropDown.Value = 'Kendall';
            app.PlotcorrelationmapButton = uibutton(app.HeatmapPanel, 'push');
            app.PlotcorrelationmapButton.ButtonPushedFcn = createCallbackFcn(app, @PlotcorrelationmapButtonPushed, true);
            app.PlotcorrelationmapButton.Enable = 'off';
            app.PlotcorrelationmapButton.Text = 'Plot correlation map';
            app.SavecorrelationmapButton = uibutton(app.HeatmapPanel, 'push');
            app.SavecorrelationmapButton.ButtonPushedFcn = createCallbackFcn(app, @SavecorrelationmapButtonPushed, true);
            app.SavecorrelationmapButton.Enable = 'off';
            app.SavecorrelationmapButton.Text = 'Save correlation map';
            app.ShowvalueincellCheckBox = uicheckbox(app.HeatmapPanel);
            app.ShowvalueincellCheckBox.Text = 'Show value in cell';
            app.ShowvalueincellCheckBox.Value = true;
            app.PlotPanel = uipanel(app.propertiescorrelationUIFigure);
            app.PlotPanel.Title = 'Plot';
            app.PlotallselectedparametersButton = uibutton(app.PlotPanel, 'push');
            app.PlotallselectedparametersButton.ButtonPushedFcn = createCallbackFcn(app, @PlotallselectedparametersButtonPushed, true);
            app.PlotallselectedparametersButton.Enable = 'off';
            app.PlotallselectedparametersButton.Text = 'Plot all selected parameters';
            app.PlottortuosityfporosityButton = uibutton(app.PlotPanel, 'push');
            app.PlottortuosityfporosityButton.ButtonPushedFcn = createCallbackFcn(app, @PlottortuosityfporosityButtonPushed, true);
            app.PlottortuosityfporosityButton.Enable = 'off';
            app.PlottortuosityfporosityButton.Tooltip = {'Make sure to correctly identify porosity and tortuosity in the custom correlation panel before clicking.'};
            app.PlottortuosityfporosityButton.Text = 'Plot tortuosity=f(porosity)';
            app.PlotSpDButton = uibutton(app.PlotPanel, 'push');
            app.PlotSpDButton.ButtonPushedFcn = createCallbackFcn(app, @PlotSpDButtonPushed, true);
            app.PlotSpDButton.Enable = 'off';
            app.PlotSpDButton.Tooltip = {'Make sure to correctly identify porosity, particle volume fraction, particle diameter, and specific surface area in the custom correlation panel before clicking.'};
            app.PlotSpDButton.Text = {'Plot Specific surface area'; '= f(volume, diameter)'};
            % Show the figure after all components are created
            app.propertiescorrelationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Properties_correlation_main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.propertiescorrelationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.propertiescorrelationUIFigure)
        end
    end
end